classdef AddAnimal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        LabmouseTab                matlab.ui.container.Tab
        alive_switch               matlab.ui.control.Switch
        GenotypestatusButtonGroup  matlab.ui.container.ButtonGroup
        UnknownButton              matlab.ui.control.RadioButton
        CarrierButton              matlab.ui.control.RadioButton
        NoncarrierButton           matlab.ui.control.RadioButton
        HetButton                  matlab.ui.control.RadioButton
        HomoButton                 matlab.ui.control.RadioButton
        tagged_switch              matlab.ui.control.Switch
        SexButtonGroup             matlab.ui.container.ButtonGroup
        UnknownButton_2            matlab.ui.control.RadioButton
        MaleButton                 matlab.ui.control.RadioButton
        FemaleButton               matlab.ui.control.RadioButton
        EarpunchButtonGroup        matlab.ui.container.ButtonGroup
        NoneButton                 matlab.ui.control.RadioButton
        LButton                    matlab.ui.control.RadioButton
        RButton                    matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        DOB_known_switch           matlab.ui.control.Switch
        injected_switch            matlab.ui.control.Switch
        behavior_switch            matlab.ui.control.Switch
        UsedforeyeandorbraininjectionLabel  matlab.ui.control.Label
        UsedforbehaviorLabel       matlab.ui.control.Label
        GenotypeDropDownLabel      matlab.ui.control.Label
        GenotypeDropDown           matlab.ui.control.DropDown
        IDLabel                    matlab.ui.control.Label
        IDEditField                matlab.ui.control.NumericEditField
        CageEditFieldLabel         matlab.ui.control.Label
        CageEditField              matlab.ui.control.NumericEditField
        DOBDatePickerLabel         matlab.ui.control.Label
        DOBDatePicker              matlab.ui.control.DatePicker
        SparrowTab                 matlab.ui.container.Tab
        PerhapssomedayLabel        matlab.ui.control.Label
        AddanimaltodatabaseButton  matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function load_genotypes(app)
            genotypes = fetchn(sl_test.Genotype,'genotype_name');
            %put WT first
            ind = find(strcmp(genotypes,'WT'));
            if ~isempty(ind)
                g{1} = 'WT';
                g = [g; genotypes(setdiff(1:length(genotypes), ind))];
            else
                g = genotypes;
            end
            set(app.GenotypeDropDown,'Items',g);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            app.load_genotypes()
            
            %set initial state
            set(app.IDEditField,'Enable',false);
            set(app.GenotypestatusButtonGroup,'Visible',false);
            set(app.DOBDatePicker,'Enable',false);
        end

        % Value changed function: tagged_switch
        function tagged_switchValueChanged(app, event)
            value = app.tagged_switch.Value;
            if strcmp(value,'Untagged')
                set(app.IDEditField,'Enable',false)
            else
                set(app.IDEditField,'Enable',true);
            end
        end

        % Value changed function: GenotypeDropDown
        function GenotypeDropDownValueChanged(app, event)
            value = app.GenotypeDropDown.Value;
            if strcmp(value,'WT')
                set(app.GenotypestatusButtonGroup,'Visible',false);
            else
                set(app.GenotypestatusButtonGroup,'Visible',true);
            end
        end

        % Value changed function: DOB_known_switch
        function DOB_known_switchValueChanged(app, event)
            value = app.DOB_known_switch.Value;
            if strcmp(value,'DOB unknown')
                set(app.DOBDatePicker,'Enable',false);                
            else
                set(app.DOBDatePicker,'Enable',true);
            end
        end

        % Button pushed function: AddanimaltodatabaseButton
        function AddanimaltodatabaseButtonPushed(app, event)
            %check isAlive
            if strcmp(get(app.alive_switch,'value'),'Dead')
                isAlive = false;
            else
                isAlive = true;
            end
            
            %check isInjected
            if strcmp(get(app.injected_switch,'value'),'No')
                isInjected = false;
            else
                isInjected = true;
            end
            
            %check isForBehavior
            if strcmp(get(app.behavior_switch,'value'),'No')
                isForBehavior = false;
            else
                isForBehavior = true;
            end
            
            %make insert key
            key = struct;  
            %check isTagged
            if strcmp(get(app.tagged_switch,'value'),'Untagged')
                isTagged = false;
            else
                isTagged = true;
                key.tag_id = get(app.IDEditField,'value');                
            end                        
                      
            %get genoType and status
            key.genotype_name = get(app.GenotypeDropDown, 'value');
            if ~strcmp(key.genotype_name,'WT')
               isGenotyped = true; 
               key.genotype_status= get(get(app.GenotypestatusButtonGroup,'SelectedObject'), 'text');
            else
                isGenotyped = false; 
            end
            key.species = get(app.LabmouseTab, 'Title');
            %check DOB
            if strcmp(get(app.DOB_known_switch,'value'),'DOB known')
                key.dob = string(get(app.DOBDatePicker,'value'));
            else
                isTagged = true;
            end
            %sex
            key.sex = get(get(app.SexButtonGroup,'SelectedObject'), 'text');
            %ear punch
            key.punch = get(get(app.EarpunchButtonGroup,'SelectedObject'), 'text');
            %cage
            key.cage_number = get(app.CageEditField,'value');
            
            %add the animal
            disp('Adding animal with key:');
            key
            add_animal(key,isAlive,isGenotyped,isInjected,isForBehavior);        
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LabmouseTab = uitab(app.TabGroup);
            app.LabmouseTab.Title = 'Lab mouse';
            app.alive_switch = uiswitch(app.LabmouseTab, 'slider');
            app.alive_switch.Items = {'Dead', 'Alive'};
            app.alive_switch.Value = 'Dead';
            app.GenotypestatusButtonGroup = uibuttongroup(app.LabmouseTab);
            app.GenotypestatusButtonGroup.Title = 'Genotype status';
            app.UnknownButton = uiradiobutton(app.GenotypestatusButtonGroup);
            app.UnknownButton.Text = 'Unknown';
            app.UnknownButton.Value = true;
            app.CarrierButton = uiradiobutton(app.GenotypestatusButtonGroup);
            app.CarrierButton.Text = 'Carrier';
            app.NoncarrierButton = uiradiobutton(app.GenotypestatusButtonGroup);
            app.NoncarrierButton.Text = 'Non-carrier';
            app.HetButton = uiradiobutton(app.GenotypestatusButtonGroup);
            app.HetButton.Text = 'Het';
            app.HomoButton = uiradiobutton(app.GenotypestatusButtonGroup);
            app.HomoButton.Text = 'Homo';
            app.tagged_switch = uiswitch(app.LabmouseTab, 'slider');
            app.tagged_switch.Items = {'Untagged', 'Tag'};
            app.tagged_switch.ValueChangedFcn = createCallbackFcn(app, @tagged_switchValueChanged, true);
            app.tagged_switch.Value = 'Untagged';
            app.SexButtonGroup = uibuttongroup(app.LabmouseTab);
            app.SexButtonGroup.Title = 'Sex';
            app.UnknownButton_2 = uiradiobutton(app.SexButtonGroup);
            app.UnknownButton_2.Text = 'Unknown';
            app.UnknownButton_2.Value = true;
            app.MaleButton = uiradiobutton(app.SexButtonGroup);
            app.MaleButton.Text = 'Male';
            app.FemaleButton = uiradiobutton(app.SexButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.EarpunchButtonGroup = uibuttongroup(app.LabmouseTab);
            app.EarpunchButtonGroup.Title = 'Ear punch';
            app.NoneButton = uiradiobutton(app.EarpunchButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.LButton = uiradiobutton(app.EarpunchButtonGroup);
            app.LButton.Text = 'L';
            app.RButton = uiradiobutton(app.EarpunchButtonGroup);
            app.RButton.Text = 'R';
            app.BothButton = uiradiobutton(app.EarpunchButtonGroup);
            app.BothButton.Text = 'Both';
            app.DOB_known_switch = uiswitch(app.LabmouseTab, 'slider');
            app.DOB_known_switch.Items = {'DOB unknown', 'DOB known'};
            app.DOB_known_switch.ValueChangedFcn = createCallbackFcn(app, @DOB_known_switchValueChanged, true);
            app.DOB_known_switch.Value = 'DOB unknown';
            app.injected_switch = uiswitch(app.LabmouseTab, 'slider');
            app.injected_switch.Items = {'No', 'Yes'};
            app.injected_switch.Value = 'No';
            app.behavior_switch = uiswitch(app.LabmouseTab, 'slider');
            app.behavior_switch.Items = {'No', 'Yes'};
            app.behavior_switch.Value = 'No';
            app.UsedforeyeandorbraininjectionLabel = uilabel(app.LabmouseTab);
            app.UsedforeyeandorbraininjectionLabel.Text = 'Used for eye and/or brain injection';
            app.UsedforbehaviorLabel = uilabel(app.LabmouseTab);
            app.UsedforbehaviorLabel.Text = 'Used for behavior';
            app.GenotypeDropDownLabel = uilabel(app.LabmouseTab);
            app.GenotypeDropDownLabel.Text = 'Genotype';
            app.GenotypeDropDown = uidropdown(app.LabmouseTab);
            app.GenotypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GenotypeDropDownValueChanged, true);
            app.IDLabel = uilabel(app.LabmouseTab);
            app.IDLabel.Text = 'ID #';
            app.IDEditField = uieditfield(app.LabmouseTab, 'numeric');
            app.IDEditField.Limits = [0 Inf];
            app.IDEditField.RoundFractionalValues = 'on';
            app.IDEditField.ValueDisplayFormat = '%.0f';
            app.CageEditFieldLabel = uilabel(app.LabmouseTab);
            app.CageEditFieldLabel.Text = 'Cage #';
            app.CageEditField = uieditfield(app.LabmouseTab, 'numeric');
            app.CageEditField.Limits = [0 Inf];
            app.CageEditField.RoundFractionalValues = 'on';
            app.CageEditField.ValueDisplayFormat = '%.0f';
            app.DOBDatePickerLabel = uilabel(app.LabmouseTab);
            app.DOBDatePickerLabel.Text = 'DOB';
            app.DOBDatePicker = uidatepicker(app.LabmouseTab);
            app.DOBDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.SparrowTab = uitab(app.TabGroup);
            app.SparrowTab.Title = 'Sparrow';
            app.PerhapssomedayLabel = uilabel(app.SparrowTab);
            app.PerhapssomedayLabel.Text = 'Perhaps someday';
            app.AddanimaltodatabaseButton = uibutton(app.UIFigure, 'push');
            app.AddanimaltodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddanimaltodatabaseButtonPushed, true);
            app.AddanimaltodatabaseButton.Text = 'Add animal to database';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddAnimal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end