classdef AddDeceasedAnimal_dlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddDeceasedAnimalUIFigure  matlab.ui.Figure
        AdddeceasedanimalforcellEditFieldLabel  matlab.ui.control.Label
        AdddeceasedanimalforcellEditField  matlab.ui.control.EditField
        tagged_switch              matlab.ui.control.Switch
        SexButtonGroup             matlab.ui.container.ButtonGroup
        UnknownButton              matlab.ui.control.RadioButton
        MaleButton                 matlab.ui.control.RadioButton
        FemaleButton               matlab.ui.control.RadioButton
        DOB_known_switch           matlab.ui.control.Switch
        GenotypeDropDownLabel      matlab.ui.control.Label
        GenotypeDropDown           matlab.ui.control.DropDown
        DOBDatePickerLabel         matlab.ui.control.Label
        DOBDatePicker              matlab.ui.control.DatePicker
        TagIDEditFieldLabel        matlab.ui.control.Label
        TagIDEditField             matlab.ui.control.EditField
        CageEditFieldLabel         matlab.ui.control.Label
        CageEditField              matlab.ui.control.EditField
        Cage_known_switch          matlab.ui.control.Switch
        EyeButtonGroup             matlab.ui.container.ButtonGroup
        UnknownEyeButton           matlab.ui.control.RadioButton
        LeftButton                 matlab.ui.control.RadioButton
        RightButton                matlab.ui.control.RadioButton
        CancelButton               matlab.ui.control.Button
        AddButton                  matlab.ui.control.Button
        RoomDropDownLabel          matlab.ui.control.Label
        RoomDropDown               matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        connection % DJ connection
        exp_date % experiment date
        exp_rig %rig on which experiment was performed
        experimenter % person who performed recording
    end
    
    properties (Access = public)
        id_handle % handle for storing DJID and whichEye once app closes        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, cellName, recordingBy, id_handle)
            app.connection = dj.conn;
            if ~app.connection.isConnected
                disp('DataJoint connection failed.');
                delete(app)  
            end
            
            %set id_handle
            app.id_handle = id_handle;
            
            %set cell name
            set(app.AdddeceasedanimalforcellEditField, 'value', cellName);
            
            %get date of experiment and rig
            [app.exp_date, app.exp_rig] = cellID_to_dateAndRig(cellName);
            
            %set experimenter
            app.experimenter = recordingBy;
            
            %load genotypes menu
            genotypes = fetchn(sl.Genotype,'genotype_name');
            %put WT first
            ind = find(strcmp(genotypes,'WT'));
            if ~isempty(ind)
                g{1} = 'WT';
                g = [g; genotypes(setdiff(1:length(genotypes), ind))];
            else
                g = genotypes;
            end
            set(app.GenotypeDropDown,'Items',g);
            
            %load rooms menu
            roomList = fetchn(sl.AnimalCageRoom,'room_number');
            set(app.RoomDropDown,'Items',roomList);
            
            %initial state
            set(app.DOBDatePicker,'Visible',false);
            set(app.TagIDEditField,'Visible',false);
            set(app.CageEditField,'Visible',false);
            set(app.RoomDropDown,'Visible',false);
        end

        % Close request function: AddDeceasedAnimalUIFigure
        function AddDeceasedAnimalUIFigureCloseRequest(app, event)
            if ~isempty(app.connection) && app.connection.isConnected
                app.connection.close();
            end
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.AddDeceasedAnimalUIFigureCloseRequest(event);
        end

        % Value changed function: DOB_known_switch
        function DOB_known_switchValueChanged(app, event)
            value = app.DOB_known_switch.Value;
            if strcmp(value,'DOB unknown')
                set(app.DOBDatePicker,'Visible',false);
            else
                set(app.DOBDatePicker,'Visible',true);
            end
        end

        % Value changed function: tagged_switch
        function tagged_switchValueChanged(app, event)
            value = app.tagged_switch.Value;
            if strcmp(value,'Untagged or unknown')
                set(app.TagIDEditField,'Visible',false);
            else
                set(app.TagIDEditField,'Visible',true);
            end
        end

        % Value changed function: Cage_known_switch
        function Cage_known_switchValueChanged(app, event)
            value = app.Cage_known_switch.Value;
            if strcmp(value,'Cage # unknown')
                set(app.CageEditField,'Visible',false);
                set(app.RoomDropDown,'Visible',false);
            else
                set(app.CageEditField,'Visible',true);
                set(app.RoomDropDown,'Visible',true);
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            %make insert key
            key = struct;
            
            %get genotype
            key.genotype_name = get(app.GenotypeDropDown, 'value');
            
            %TODO - remove this part
            %check isTagged
            if strcmp(get(app.tagged_switch,'value'),'ID known')
                key.is_tagged = 'T';
                key.tag_id = str2double(get(app.TagIDEditField,'value'));
            end
            
            if strcmp(get(app.tagged_switch,'value'),'Untagged or unknown')
           %     key_tag.tag_ear = 'None';               
                addTag = false;
            else
                addTag = true;
                key_tag.tag_id = str2double(get(app.TagIDEditField,'value'));
                key_tag.tag_ear = get(get(app.TagearButtonGroup,'SelectedObject'), 'text');
                key_tag.punch = get(get(app.PunchearButtonGroup,'SelectedObject'), 'text');
            end

            %check DOB
            if strcmp(get(app.DOB_known_switch,'value'),'DOB known')
                key.dob = string(get(app.DOBDatePicker,'value'));
            end
            
            %check if eye is known
            whichEye = get(get(app.EyeButtonGroup,'SelectedObject'), 'text');
            app.id_handle.data.whichEye = whichEye;
            if strcmp(whichEye, 'Unknown')
                eyeUnknown = true;
            else
                eyeUnknown = false;
            end
            
            %check cage #
            addCage = false;
            if strcmp(get(app.Cage_known_switch,'value'),'Cage # known')
                key_cage.cause = 'assigned at database insert';
                key_cage.cage_number = get(app.CageEditField,'value');
                key_cage.room_number = get(app.RoomDropDown, 'value');
                key_cage.user_name = app.connection.user;
                key_cage.date = app.exp_date;
                addCage = true;
            end
            
            %sex
            key.sex = get(get(app.SexButtonGroup,'SelectedObject'), 'text');
            
            %source: leave as unknown for now - probably does not matter
            key.source = 'unknown';
            
            %make rig reservation entry
            if isnan(app.experimenter)
                key_reserved.user_name = 'Unknown';
            else
                key_reserved.user_name = app.experimenter;
            end
            key_reserved.rig_name = app.exp_rig;
            key_reserved.date = app.exp_date;
            
            %make AnimalDeceased entry
            if isnan(app.experimenter)
                key_deceased.user_name = 'Unknown';
            else
                key_deceased.user_name = app.experimenter;
            end
            key_deceased.date = app.exp_date;
            key_deceased.cause = 'sacrificed for experiment';
              
            %assign tag
            if addTag
                key_tag.user_name = app.login_user;
                if isempty(key.dob)
                    key_tag.date = '1900-01-01'; %arbitrary early date
                else
                    key_tag.date = key.dob; %wean date is more exact, but this will do
                end
            end
            
            %eye keys
            if eyeUnknown
                eye1.side = 'Unknown1';
                eye2.side = 'Unknown2';
            else
                eye1.side = 'Left';
                eye2.side = 'Right';
            end
            
            %add the animal
            disp('Adding animal with keys:');
            key
            if addCage
                key_cage
            end
            if addTag
               key_tag
            end
            key_reserved
            key_deceased
            
            C = app.connection;
            C.startTransaction;
            
            try
                insert(sl.Animal, key);
                id = max(fetchn(sl.Animal, 'animal_id')); %last animmal added
                
                eye1.animal_id = id;
                eye2.animal_id = id;
                insert(sl.Eye, eye1);
                insert(sl.Eye, eye2);
                
                if addCage
                    key_cage.animal_id = id;
                    add_animalEvent(key_cage, 'AssignCage',C);
                end
                
                if addTag
                    key_tag.animal_id = id;
                    add_animalEvent(key_tag, 'AssignTag',C);
                end
                
                key_reserved.animal_id = id;
                add_animalEvent(key_reserved, 'ReservedForSession',C);
                
                key_deceased.animal_id = id;
                add_animalEvent(key_deceased, 'Deceased',C);

                C.commitTransaction;
                disp('Animal insert successful');
                app.id_handle.data.animal_id = id;
            catch ME
                C.cancelTransaction;
                disp('Animal insert failed');
                app.id_handle.data.animal_id = [];
            end            
            app.AddDeceasedAnimalUIFigureCloseRequest();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddDeceasedAnimalUIFigure = uifigure('Visible', 'off');
            app.AddDeceasedAnimalUIFigure.Name = 'AddDeceasedAnimal';
            app.AddDeceasedAnimalUIFigure.CloseRequestFcn = createCallbackFcn(app, @AddDeceasedAnimalUIFigureCloseRequest, true);
            app.AdddeceasedanimalforcellEditFieldLabel = uilabel(app.AddDeceasedAnimalUIFigure);
            app.AdddeceasedanimalforcellEditFieldLabel.Text = 'Add deceased animal for cell';
            app.AdddeceasedanimalforcellEditField = uieditfield(app.AddDeceasedAnimalUIFigure, 'text');
            app.AdddeceasedanimalforcellEditField.Editable = 'off';
            app.tagged_switch = uiswitch(app.AddDeceasedAnimalUIFigure, 'slider');
            app.tagged_switch.Items = {'Untagged or unknown', 'ID known'};
            app.tagged_switch.ValueChangedFcn = createCallbackFcn(app, @tagged_switchValueChanged, true);
            app.tagged_switch.Value = 'Untagged or unknown';
            app.SexButtonGroup = uibuttongroup(app.AddDeceasedAnimalUIFigure);
            app.SexButtonGroup.Title = 'Sex';
            app.UnknownButton = uiradiobutton(app.SexButtonGroup);
            app.UnknownButton.Text = 'Unknown';
            app.UnknownButton.Value = true;
            app.MaleButton = uiradiobutton(app.SexButtonGroup);
            app.MaleButton.Text = 'Male';
            app.FemaleButton = uiradiobutton(app.SexButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.DOB_known_switch = uiswitch(app.AddDeceasedAnimalUIFigure, 'slider');
            app.DOB_known_switch.Items = {'DOB unknown', 'DOB known'};
            app.DOB_known_switch.ValueChangedFcn = createCallbackFcn(app, @DOB_known_switchValueChanged, true);
            app.DOB_known_switch.Value = 'DOB unknown';
            app.GenotypeDropDownLabel = uilabel(app.AddDeceasedAnimalUIFigure);
            app.GenotypeDropDownLabel.Text = 'Genotype';
            app.GenotypeDropDown = uidropdown(app.AddDeceasedAnimalUIFigure);
            app.DOBDatePickerLabel = uilabel(app.AddDeceasedAnimalUIFigure);
            app.DOBDatePickerLabel.Text = 'DOB';
            app.DOBDatePicker = uidatepicker(app.AddDeceasedAnimalUIFigure);
            app.DOBDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.TagIDEditFieldLabel = uilabel(app.AddDeceasedAnimalUIFigure);
            app.TagIDEditFieldLabel.Text = 'Tag ID';
            app.TagIDEditField = uieditfield(app.AddDeceasedAnimalUIFigure, 'text');
            app.CageEditFieldLabel = uilabel(app.AddDeceasedAnimalUIFigure);
            app.CageEditFieldLabel.Text = 'Cage #';
            app.CageEditField = uieditfield(app.AddDeceasedAnimalUIFigure, 'text');
            app.Cage_known_switch = uiswitch(app.AddDeceasedAnimalUIFigure, 'slider');
            app.Cage_known_switch.Items = {'Cage # unknown', 'Cage # known'};
            app.Cage_known_switch.ValueChangedFcn = createCallbackFcn(app, @Cage_known_switchValueChanged, true);
            app.Cage_known_switch.Value = 'Cage # unknown';
            app.EyeButtonGroup = uibuttongroup(app.AddDeceasedAnimalUIFigure);
            app.EyeButtonGroup.Title = 'Eye';
            app.UnknownEyeButton = uiradiobutton(app.EyeButtonGroup);
            app.UnknownEyeButton.Text = 'Unknown';
            app.UnknownEyeButton.Value = true;
            app.LeftButton = uiradiobutton(app.EyeButtonGroup);
            app.LeftButton.Text = 'Left';
            app.RightButton = uiradiobutton(app.EyeButtonGroup);
            app.RightButton.Text = 'Right';
            app.CancelButton = uibutton(app.AddDeceasedAnimalUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AddButton = uibutton(app.AddDeceasedAnimalUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RoomDropDownLabel = uilabel(app.AddDeceasedAnimalUIFigure);
            app.RoomDropDownLabel.Text = 'Room';
            app.RoomDropDown = uidropdown(app.AddDeceasedAnimalUIFigure);
            % Show the figure after all components are created
            app.AddDeceasedAnimalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddDeceasedAnimal_dlg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddDeceasedAnimalUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddDeceasedAnimalUIFigure)
        end
    end
end