classdef AnimalInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnimalInterfaceUIFigure         matlab.ui.Figure
        MainTabGroup                    matlab.ui.container.TabGroup
        LoginTab                        matlab.ui.container.Tab
        PasswordEditFieldLabel          matlab.ui.control.Label
        PasswordEditField               matlab.ui.control.EditField
        LoginButton                     matlab.ui.control.Button
        UserEditFieldLabel              matlab.ui.control.Label
        LoginUserEditField              matlab.ui.control.EditField
        AnimalLoggerTab                 matlab.ui.container.Tab
        AnimalTable                     matlab.ui.control.Table
        EventTabGroup                   matlab.ui.container.TabGroup
        GenotypedTab                    matlab.ui.container.Tab
        ResultsDropDownLabel            matlab.ui.control.Label
        GenotypeResultsDropDown         matlab.ui.control.DropDown
        DeceasedTab                     matlab.ui.container.Tab
        ReasonDropDownLabel             matlab.ui.control.Label
        DeceasedReasonDropDown          matlab.ui.control.DropDown
        SetasbreederTab                 matlab.ui.container.Tab
        AssigncageroomTab               matlab.ui.container.Tab
        ReasonDropDown_2Label           matlab.ui.control.Label
        MoveReasonDropDown              matlab.ui.control.DropDown
        TocageEditFieldLabel            matlab.ui.control.Label
        TocageEditField                 matlab.ui.control.EditField
        RoomDropDown_2Label             matlab.ui.control.Label
        RoomDropDown_logger             matlab.ui.control.DropDown
        AssigntoanimalprotocolTab       matlab.ui.container.Tab
        AnimalprotocolDropDownLabel     matlab.ui.control.Label
        AnimalprotocolDropDown          matlab.ui.control.DropDown
        TagTab                          matlab.ui.container.Tab
        TagEditFieldLabel               matlab.ui.control.Label
        TagEditField                    matlab.ui.control.EditField
        EarpunchDropDownLabel           matlab.ui.control.Label
        EarpunchDropDown                matlab.ui.control.DropDown
        TagearDropDownLabel             matlab.ui.control.Label
        TagearDropDown                  matlab.ui.control.DropDown
        IPinjectionTab                  matlab.ui.container.Tab
        SubstanceDropDownLabel          matlab.ui.control.Label
        SubstanceDropDown_IP            matlab.ui.control.DropDown
        ConcentrationmgKgEditFieldLabel_2  matlab.ui.control.Label
        IP_inj_ConcentrationEditField   matlab.ui.control.EditField
        EyeinjectionTab                 matlab.ui.container.Tab
        SubstanceDropDownLabel_2        matlab.ui.control.Label
        SubstanceDropDown_Eye           matlab.ui.control.DropDown
        EyeDropDownLabel                matlab.ui.control.Label
        WhichEyeDropDown                matlab.ui.control.DropDown
        Dilution1XEditFieldLabel_2      matlab.ui.control.Label
        Dilution_EyeEditField           matlab.ui.control.EditField
        BraininjectionTab               matlab.ui.container.Tab
        SubstanceDropDown_3Label        matlab.ui.control.Label
        SubstanceDropDown_Brain         matlab.ui.control.DropDown
        CoordinatesLabel                matlab.ui.control.Label
        TargetDropDownLabel             matlab.ui.control.Label
        BrainTargetDropDown             matlab.ui.control.DropDown
        HemisphereDropDownLabel         matlab.ui.control.Label
        HemisphereDropDown              matlab.ui.control.DropDown
        Dilution1XEditFieldLabel_3      matlab.ui.control.Label
        Dilution_BrainEditField         matlab.ui.control.EditField
        APEditField_2Label              matlab.ui.control.Label
        APEditField                     matlab.ui.control.EditField
        MLEditField_2Label              matlab.ui.control.Label
        MLEditField                     matlab.ui.control.EditField
        DVEditField_2Label              matlab.ui.control.Label
        DVEditField                     matlab.ui.control.EditField
        AngleEditField_2Label           matlab.ui.control.Label
        AngleEditField                  matlab.ui.control.EditField
        FeedTab                         matlab.ui.container.Tab
        SubstanceDropDownLabel_3        matlab.ui.control.Label
        SubstanceDropDown_Feed          matlab.ui.control.DropDown
        HandledTab                      matlab.ui.container.Tab
        DurtionminsEditFieldLabel       matlab.ui.control.Label
        HandlingDurationEditField       matlab.ui.control.EditField
        SocialbehaviorsessionTab        matlab.ui.container.Tab
        RecordBehavior_switch           matlab.ui.control.RockerSwitch
        StimulusLabel                   matlab.ui.control.Label
        Lamp_isRecorded                 matlab.ui.control.Lamp
        ExperimenttypeDropDownLabel     matlab.ui.control.Label
        ExperimenttypeDropDown          matlab.ui.control.DropDown
        AnimalAEditField                matlab.ui.control.EditField
        AnimalBEditField                matlab.ui.control.EditField
        AnimalCEditField                matlab.ui.control.EditField
        TestanimaltypeDropDownLabel     matlab.ui.control.Label
        TestanimaltypeDropDown          matlab.ui.control.DropDown
        DJIDLabel                       matlab.ui.control.Label
        WindowLabel                     matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        BLabel                          matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        WindowAStim                     matlab.ui.control.DropDown
        WindowBStim                     matlab.ui.control.DropDown
        WindowCStim                     matlab.ui.control.DropDown
        ReserveforprojectTab            matlab.ui.container.Tab
        ProjectDropDownLabel            matlab.ui.control.Label
        ProjectDropDown                 matlab.ui.control.DropDown
        ReserveforrigTab                matlab.ui.container.Tab
        RignameDropDownLabel            matlab.ui.control.Label
        RignameDropDown                 matlab.ui.control.DropDown
        CalendarrefreshtokenEditFieldLabel  matlab.ui.control.Label
        CalendarrefreshtokenEditField   matlab.ui.control.EditField
        ReauthorizeButton               matlab.ui.control.Button
        AnimalSelectionPanel            matlab.ui.container.Panel
        SearchButton                    matlab.ui.control.Button
        FilterPanel                     matlab.ui.container.Panel
        GenotypeDropDownLabel           matlab.ui.control.Label
        Filter_GenotypeDropDown         matlab.ui.control.DropDown
        StatusDropDownLabel             matlab.ui.control.Label
        Filter_GenotypeStatusDropDown   matlab.ui.control.DropDown
        EyeInjSwitchLabel               matlab.ui.control.Label
        EyeInjSwitch                    matlab.ui.control.ToggleSwitch
        BehaviorexpSwitchLabel          matlab.ui.control.Label
        BehaviorexpSwitch               matlab.ui.control.ToggleSwitch
        SexDropDownLabel                matlab.ui.control.Label
        Filter_SexDropDown              matlab.ui.control.DropDown
        BraininjSwitchLabel             matlab.ui.control.Label
        BraininjSwitch                  matlab.ui.control.ToggleSwitch
        NotrigreservedSwitchLabel       matlab.ui.control.Label
        NotrigreservedSwitch            matlab.ui.control.ToggleSwitch
        ProjectLabel                    matlab.ui.control.Label
        Filter_ProjectDropDown          matlab.ui.control.DropDown
        NotprojreservedSwitchLabel      matlab.ui.control.Label
        NotprojreservedSwitch           matlab.ui.control.ToggleSwitch
        ResetButton                     matlab.ui.control.Button
        ID_type_switch                  matlab.ui.control.Switch
        AgerangeweeksPanel              matlab.ui.container.Panel
        toLabel                         matlab.ui.control.Label
        ageLow_edit                     matlab.ui.control.EditField
        ageHigh_edit                    matlab.ui.control.EditField
        Search_DJIDEditField            matlab.ui.control.EditField
        Search_TagIDEditField           matlab.ui.control.EditField
        PersonDropDownLabel             matlab.ui.control.Label
        PersonDropDown                  matlab.ui.control.DropDown
        EventdateDatePickerLabel        matlab.ui.control.Label
        EventdateDatePicker             matlab.ui.control.DatePicker
        SubmitevententryButton          matlab.ui.control.Button
        NotesTextArea_2Label            matlab.ui.control.Label
        EventNotesTextArea              matlab.ui.control.TextArea
        EventTimeM                      matlab.ui.control.DropDown
        EventTimeAMPM                   matlab.ui.control.DropDown
        TimeLabel_2                     matlab.ui.control.Label
        AlleventsforselectedanimalButton  matlab.ui.control.Button
        EventTimeH                      matlab.ui.control.DropDown
        ViewlogsLabel                   matlab.ui.control.Label
        EventsforallanimalsButton       matlab.ui.control.Button
        TypeDropDownLabel               matlab.ui.control.Label
        EventTypeDropDown               matlab.ui.control.DropDown
        StatusLampLabel                 matlab.ui.control.Label
        StatusLamp_Logger               matlab.ui.control.Lamp
        EventInsertStatusEdit           matlab.ui.control.EditField
        ExporttoExcelButton             matlab.ui.control.Button
        BreedersTab                     matlab.ui.container.Tab
        BreederCagesTable               matlab.ui.control.Table
        MaleinselectedpairLabel         matlab.ui.control.Label
        FemaleinselectedpairLabel       matlab.ui.control.Label
        ExporttoExcelButton_breeders    matlab.ui.control.Button
        selectedMaleTable               matlab.ui.control.Table
        SubmitevententryButton_breeders  matlab.ui.control.Button
        StatusLampLabel_6               matlab.ui.control.Label
        StatusLamp_breeders             matlab.ui.control.Lamp
        EventInsertStatusEdit_breeders  matlab.ui.control.EditField
        PersonDropDown_2Label           matlab.ui.control.Label
        PersonDropDown_breeders         matlab.ui.control.DropDown
        EventdateDatePicker_2Label      matlab.ui.control.Label
        EventdateDatePicker_breeders    matlab.ui.control.DatePicker
        NotesTextArea_2Label_2          matlab.ui.control.Label
        EventNotesTextArea_breeders     matlab.ui.control.TextArea
        UnpairedBreedersTable           matlab.ui.control.Table
        UnpairedbreedersLabel           matlab.ui.control.Label
        EventTabGroup_breeders          matlab.ui.container.TabGroup
        PairbreedersTab                 matlab.ui.container.Tab
        NewbreedercageEditFieldLabel    matlab.ui.control.Label
        NewbreedercageEditField         matlab.ui.control.EditField
        RoomDropDown_2Label_2           matlab.ui.control.Label
        RoomDropDown_breeders           matlab.ui.control.DropDown
        SeparatebreedingpairTab         matlab.ui.container.Tab
        NewcageformaleEditFieldLabel    matlab.ui.control.Label
        NewcageformaleEditField         matlab.ui.control.EditField
        NewcageforfemaleEditFieldLabel  matlab.ui.control.Label
        NewcageforfemaleEditField       matlab.ui.control.EditField
        RoomDropDown_2Label_3           matlab.ui.control.Label
        RoomDropDown_breeders_sepMale   matlab.ui.control.DropDown
        RoomDropDown_2Label_4           matlab.ui.control.Label
        RoomDropDown_breeders_sepFemale  matlab.ui.control.DropDown
        RetirebreederTab                matlab.ui.container.Tab
        SacrificebreedingpairTab        matlab.ui.container.Tab
        LogbirthTab                     matlab.ui.container.Tab
        NumberofpupsEditField_2Label    matlab.ui.control.Label
        NumberofpupsEditField           matlab.ui.control.EditField
        LogweaningTab                   matlab.ui.container.Tab
        NumberofpupsEditField_2Label_2  matlab.ui.control.Label
        NumberofpupsEditField_weaned    matlab.ui.control.EditField
        selectedFemaleTable             matlab.ui.control.Table
        AddAnimalsTab                   matlab.ui.container.Tab
        SpeciesTabGroup                 matlab.ui.container.TabGroup
        LabmouseTab                     matlab.ui.container.Tab
        tagged_switch                   matlab.ui.control.Switch
        SexButtonGroup                  matlab.ui.container.ButtonGroup
        UnknownButton                   matlab.ui.control.RadioButton
        MaleButton                      matlab.ui.control.RadioButton
        FemaleButton                    matlab.ui.control.RadioButton
        PunchearButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton                      matlab.ui.control.RadioButton
        LButton                         matlab.ui.control.RadioButton
        RButton                         matlab.ui.control.RadioButton
        BothButton                      matlab.ui.control.RadioButton
        DOB_known_switch                matlab.ui.control.Switch
        GenotypeDropDownLabel_2         matlab.ui.control.Label
        GenotypeDropDown                matlab.ui.control.DropDown
        DOBDatePickerLabel              matlab.ui.control.Label
        DOBDatePicker                   matlab.ui.control.DatePicker
        TagearButtonGroup               matlab.ui.container.ButtonGroup
        NoneButton_2                    matlab.ui.control.RadioButton
        LButton_2                       matlab.ui.control.RadioButton
        RButton_2                       matlab.ui.control.RadioButton
        UnknownButton_2                 matlab.ui.control.RadioButton
        RoomDropDownLabel               matlab.ui.control.Label
        RoomDropDown                    matlab.ui.control.DropDown
        TagIDEditFieldLabel             matlab.ui.control.Label
        TagIDEditField                  matlab.ui.control.EditField
        SourceDropDownLabel             matlab.ui.control.Label
        SourceDropDown                  matlab.ui.control.DropDown
        CageEditFieldLabel              matlab.ui.control.Label
        CageEditField                   matlab.ui.control.EditField
        ProtocolDropDownLabel           matlab.ui.control.Label
        ProtocolDropDown                matlab.ui.control.DropDown
        SingleentryLabel                matlab.ui.control.Label
        MultipleentryLabel              matlab.ui.control.Label
        GenotypeDropDown_E1             matlab.ui.control.DropDown
        DOBDatePicker_E1                matlab.ui.control.DatePicker
        GenotypeLabel                   matlab.ui.control.Label
        DOBLabel                        matlab.ui.control.Label
        EntryLabel                      matlab.ui.control.Label
        Label_1                         matlab.ui.control.Label
        TagIDEditField_E1               matlab.ui.control.EditField
        TagIDLabel                      matlab.ui.control.Label
        CageEditField_E1                matlab.ui.control.EditField
        CageLabel                       matlab.ui.control.Label
        SexButtonGroup_E1               matlab.ui.container.ButtonGroup
        UnknownButton_3                 matlab.ui.control.RadioButton
        MaleButton_2                    matlab.ui.control.RadioButton
        FemaleButton_2                  matlab.ui.control.RadioButton
        RoomDropDown_E1                 matlab.ui.control.DropDown
        RoomLabel                       matlab.ui.control.Label
        SourceDropDown_E1               matlab.ui.control.DropDown
        SourceLabel                     matlab.ui.control.Label
        ProtocolDropDown_E1             matlab.ui.control.DropDown
        ProtocolLabel                   matlab.ui.control.Label
        PunchearButtonGroup_E1          matlab.ui.container.ButtonGroup
        NoneButton_3                    matlab.ui.control.RadioButton
        LButton_3                       matlab.ui.control.RadioButton
        RButton_3                       matlab.ui.control.RadioButton
        BothButton_2                    matlab.ui.control.RadioButton
        TagearButtonGroup_E1            matlab.ui.container.ButtonGroup
        NoneButton_4                    matlab.ui.control.RadioButton
        LButton_4                       matlab.ui.control.RadioButton
        RButton_4                       matlab.ui.control.RadioButton
        UnknownButton_4                 matlab.ui.control.RadioButton
        tagged_switch_E1                matlab.ui.control.Switch
        DOB_known_switch_E1             matlab.ui.control.Switch
        SetotherentriesButton           matlab.ui.control.Button
        GenotypeDropDown_E2             matlab.ui.control.DropDown
        DOBDatePicker_E2                matlab.ui.control.DatePicker
        Label_2                         matlab.ui.control.Label
        TagIDEditField_E2               matlab.ui.control.EditField
        CageEditField_E2                matlab.ui.control.EditField
        SexButtonGroup_E2               matlab.ui.container.ButtonGroup
        UnknownButton_5                 matlab.ui.control.RadioButton
        MaleButton_3                    matlab.ui.control.RadioButton
        FemaleButton_3                  matlab.ui.control.RadioButton
        RoomDropDown_E2                 matlab.ui.control.DropDown
        ProtocolDropDown_E2             matlab.ui.control.DropDown
        PunchearButtonGroup_E2          matlab.ui.container.ButtonGroup
        NoneButton_5                    matlab.ui.control.RadioButton
        LButton_5                       matlab.ui.control.RadioButton
        RButton_5                       matlab.ui.control.RadioButton
        BothButton_3                    matlab.ui.control.RadioButton
        TagearButtonGroup_E2            matlab.ui.container.ButtonGroup
        NoneButton_6                    matlab.ui.control.RadioButton
        LButton_6                       matlab.ui.control.RadioButton
        RButton_6                       matlab.ui.control.RadioButton
        UnknownButton_6                 matlab.ui.control.RadioButton
        tagged_switch_E2                matlab.ui.control.Switch
        DOB_known_switch_E2             matlab.ui.control.Switch
        GenotypeDropDown_E3             matlab.ui.control.DropDown
        DOBDatePicker_E3                matlab.ui.control.DatePicker
        Label_3                         matlab.ui.control.Label
        TagIDEditField_E3               matlab.ui.control.EditField
        CageEditField_E3                matlab.ui.control.EditField
        SexButtonGroup_E3               matlab.ui.container.ButtonGroup
        UnknownButton_7                 matlab.ui.control.RadioButton
        MaleButton_4                    matlab.ui.control.RadioButton
        FemaleButton_4                  matlab.ui.control.RadioButton
        RoomDropDown_E3                 matlab.ui.control.DropDown
        ProtocolDropDown_E3             matlab.ui.control.DropDown
        PunchearButtonGroup_E3          matlab.ui.container.ButtonGroup
        NoneButton_7                    matlab.ui.control.RadioButton
        LButton_7                       matlab.ui.control.RadioButton
        RButton_7                       matlab.ui.control.RadioButton
        BothButton_4                    matlab.ui.control.RadioButton
        TagearButtonGroup_E3            matlab.ui.container.ButtonGroup
        NoneButton_8                    matlab.ui.control.RadioButton
        LButton_8                       matlab.ui.control.RadioButton
        RButton_8                       matlab.ui.control.RadioButton
        UnknownButton_8                 matlab.ui.control.RadioButton
        tagged_switch_E3                matlab.ui.control.Switch
        DOB_known_switch_E3             matlab.ui.control.Switch
        GenotypeDropDown_E4             matlab.ui.control.DropDown
        DOBDatePicker_E4                matlab.ui.control.DatePicker
        Label_4                         matlab.ui.control.Label
        TagIDEditField_E4               matlab.ui.control.EditField
        CageEditField_E4                matlab.ui.control.EditField
        SexButtonGroup_E4               matlab.ui.container.ButtonGroup
        UnknownButton_9                 matlab.ui.control.RadioButton
        MaleButton_5                    matlab.ui.control.RadioButton
        FemaleButton_5                  matlab.ui.control.RadioButton
        RoomDropDown_E4                 matlab.ui.control.DropDown
        ProtocolDropDown_E4             matlab.ui.control.DropDown
        PunchearButtonGroup_E4          matlab.ui.container.ButtonGroup
        NoneButton_9                    matlab.ui.control.RadioButton
        LButton_9                       matlab.ui.control.RadioButton
        RButton_9                       matlab.ui.control.RadioButton
        BothButton_5                    matlab.ui.control.RadioButton
        TagearButtonGroup_E4            matlab.ui.container.ButtonGroup
        NoneButton_10                   matlab.ui.control.RadioButton
        LButton_10                      matlab.ui.control.RadioButton
        RButton_10                      matlab.ui.control.RadioButton
        UnknownButton_10                matlab.ui.control.RadioButton
        tagged_switch_E4                matlab.ui.control.Switch
        DOB_known_switch_E4             matlab.ui.control.Switch
        GenotypeDropDown_E5             matlab.ui.control.DropDown
        DOBDatePicker_E5                matlab.ui.control.DatePicker
        Label_5                         matlab.ui.control.Label
        TagIDEditField_E5               matlab.ui.control.EditField
        CageEditField_E5                matlab.ui.control.EditField
        SexButtonGroup_E5               matlab.ui.container.ButtonGroup
        UnknownButton_11                matlab.ui.control.RadioButton
        MaleButton_6                    matlab.ui.control.RadioButton
        FemaleButton_6                  matlab.ui.control.RadioButton
        RoomDropDown_E5                 matlab.ui.control.DropDown
        ProtocolDropDown_E5             matlab.ui.control.DropDown
        PunchearButtonGroup_E5          matlab.ui.container.ButtonGroup
        NoneButton_11                   matlab.ui.control.RadioButton
        LButton_11                      matlab.ui.control.RadioButton
        RButton_11                      matlab.ui.control.RadioButton
        BothButton_6                    matlab.ui.control.RadioButton
        TagearButtonGroup_E5            matlab.ui.container.ButtonGroup
        NoneButton_12                   matlab.ui.control.RadioButton
        LButton_12                      matlab.ui.control.RadioButton
        RButton_12                      matlab.ui.control.RadioButton
        UnknownButton_12                matlab.ui.control.RadioButton
        tagged_switch_E5                matlab.ui.control.Switch
        DOB_known_switch_E5             matlab.ui.control.Switch
        GenotypeDropDown_E6             matlab.ui.control.DropDown
        DOBDatePicker_E6                matlab.ui.control.DatePicker
        Label_6                         matlab.ui.control.Label
        TagIDEditField_E6               matlab.ui.control.EditField
        CageEditField_E6                matlab.ui.control.EditField
        SexButtonGroup_E6               matlab.ui.container.ButtonGroup
        UnknownButton_13                matlab.ui.control.RadioButton
        MaleButton_7                    matlab.ui.control.RadioButton
        FemaleButton_7                  matlab.ui.control.RadioButton
        RoomDropDown_E6                 matlab.ui.control.DropDown
        ProtocolDropDown_E6             matlab.ui.control.DropDown
        PunchearButtonGroup_E6          matlab.ui.container.ButtonGroup
        NoneButton_13                   matlab.ui.control.RadioButton
        LButton_13                      matlab.ui.control.RadioButton
        RButton_13                      matlab.ui.control.RadioButton
        BothButton_7                    matlab.ui.control.RadioButton
        TagearButtonGroup_E6            matlab.ui.container.ButtonGroup
        NoneButton_14                   matlab.ui.control.RadioButton
        LButton_14                      matlab.ui.control.RadioButton
        RButton_14                      matlab.ui.control.RadioButton
        UnknownButton_14                matlab.ui.control.RadioButton
        tagged_switch_E6                matlab.ui.control.Switch
        DOB_known_switch_E6             matlab.ui.control.Switch
        GenotypeDropDown_E7             matlab.ui.control.DropDown
        DOBDatePicker_E7                matlab.ui.control.DatePicker
        TagIDEditField_E7               matlab.ui.control.EditField
        CageEditField_E7                matlab.ui.control.EditField
        SexButtonGroup_E7               matlab.ui.container.ButtonGroup
        UnknownButton_15                matlab.ui.control.RadioButton
        MaleButton_8                    matlab.ui.control.RadioButton
        FemaleButton_8                  matlab.ui.control.RadioButton
        RoomDropDown_E7                 matlab.ui.control.DropDown
        ProtocolDropDown_E7             matlab.ui.control.DropDown
        PunchearButtonGroup_E7          matlab.ui.container.ButtonGroup
        NoneButton_15                   matlab.ui.control.RadioButton
        LButton_15                      matlab.ui.control.RadioButton
        RButton_15                      matlab.ui.control.RadioButton
        BothButton_8                    matlab.ui.control.RadioButton
        TagearButtonGroup_E7            matlab.ui.container.ButtonGroup
        NoneButton_16                   matlab.ui.control.RadioButton
        LButton_16                      matlab.ui.control.RadioButton
        RButton_16                      matlab.ui.control.RadioButton
        UnknownButton_16                matlab.ui.control.RadioButton
        tagged_switch_E7                matlab.ui.control.Switch
        DOB_known_switch_E7             matlab.ui.control.Switch
        GenotypeDropDown_E8             matlab.ui.control.DropDown
        DOBDatePicker_E8                matlab.ui.control.DatePicker
        TagIDEditField_E8               matlab.ui.control.EditField
        CageEditField_E8                matlab.ui.control.EditField
        SexButtonGroup_E8               matlab.ui.container.ButtonGroup
        UnknownButton_17                matlab.ui.control.RadioButton
        MaleButton_9                    matlab.ui.control.RadioButton
        FemaleButton_9                  matlab.ui.control.RadioButton
        RoomDropDown_E8                 matlab.ui.control.DropDown
        ProtocolDropDown_E8             matlab.ui.control.DropDown
        PunchearButtonGroup_E8          matlab.ui.container.ButtonGroup
        NoneButton_17                   matlab.ui.control.RadioButton
        LButton_17                      matlab.ui.control.RadioButton
        RButton_17                      matlab.ui.control.RadioButton
        BothButton_9                    matlab.ui.control.RadioButton
        TagearButtonGroup_E8            matlab.ui.container.ButtonGroup
        NoneButton_18                   matlab.ui.control.RadioButton
        LButton_18                      matlab.ui.control.RadioButton
        RButton_18                      matlab.ui.control.RadioButton
        UnknownButton_18                matlab.ui.control.RadioButton
        tagged_switch_E8                matlab.ui.control.Switch
        DOB_known_switch_E8             matlab.ui.control.Switch
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        NumberofentriesSliderLabel      matlab.ui.control.Label
        NumberofentriesSlider           matlab.ui.control.Slider
        SourceIDEditFieldLabel          matlab.ui.control.Label
        SourceIDEditField               matlab.ui.control.EditField
        BreedingcagesindatabaseDropDownLabel  matlab.ui.control.Label
        BreedingcagesindatabaseDropDown  matlab.ui.control.DropDown
        SetsourceIDButton               matlab.ui.control.Button
        SourceIDLabel                   matlab.ui.control.Label
        SourceIDEditField_E1            matlab.ui.control.EditField
        SourceDropDown_E2               matlab.ui.control.DropDown
        SourceIDEditField_E2            matlab.ui.control.EditField
        SourceDropDown_E3               matlab.ui.control.DropDown
        SourceIDEditField_E3            matlab.ui.control.EditField
        SourceDropDown_E4               matlab.ui.control.DropDown
        SourceIDEditField_E4            matlab.ui.control.EditField
        SourceDropDown_E5               matlab.ui.control.DropDown
        SourceIDEditField_E5            matlab.ui.control.EditField
        SourceDropDown_E6               matlab.ui.control.DropDown
        SourceIDEditField_E6            matlab.ui.control.EditField
        SourceDropDown_E7               matlab.ui.control.DropDown
        SourceIDEditField_E7            matlab.ui.control.EditField
        SourceDropDown_E8               matlab.ui.control.DropDown
        SourceIDEditField_E8            matlab.ui.control.EditField
        SparrowTab                      matlab.ui.container.Tab
        PerhapssomedayLabel             matlab.ui.control.Label
        AddsingleanimaltodatabaseButton  matlab.ui.control.Button
        StatusLampLabel_2               matlab.ui.control.Label
        StatusLamp_Animal               matlab.ui.control.Lamp
        AnimalInsertStatusEdit          matlab.ui.control.EditField
        AddmultipleanimalstodatabaseButton  matlab.ui.control.Button
        AddGenotypeTab                  matlab.ui.container.Tab
        GenotypenameEditFieldLabel      matlab.ui.control.Label
        GenotypenameEditField           matlab.ui.control.EditField
        DescriptionTextAreaLabel        matlab.ui.control.Label
        DescriptionTextArea             matlab.ui.control.TextArea
        SourceEditFieldLabel            matlab.ui.control.Label
        SourceEditField                 matlab.ui.control.EditField
        CatalogEditFieldLabel           matlab.ui.control.Label
        CatalogEditField                matlab.ui.control.EditField
        AddgenotypetodatabaseButton     matlab.ui.control.Button
        GenotypesindatabaseListBoxLabel  matlab.ui.control.Label
        GenotypesindatabaseListBox      matlab.ui.control.ListBox
        StatusLampLabel_3               matlab.ui.control.Label
        StatusLamp_Genotype             matlab.ui.control.Lamp
        GenotypeInsertStatusEdit        matlab.ui.control.EditField
        AddSubstanceTab                 matlab.ui.container.Tab
        SubstancenameEditFieldLabel     matlab.ui.control.Label
        SubstancenameEditField          matlab.ui.control.EditField
        DescriptionTextArea_2Label      matlab.ui.control.Label
        DescriptionTextArea_substance   matlab.ui.control.TextArea
        SourceEditField_2Label          matlab.ui.control.Label
        SourceEditField_substance       matlab.ui.control.EditField
        CatalogEditField_2Label         matlab.ui.control.Label
        CatalogEditField_substance      matlab.ui.control.EditField
        StoragelocationLabel            matlab.ui.control.Label
        StorageLocationTextArea_substance  matlab.ui.control.TextArea
        AddsubstancetodatabaseButton    matlab.ui.control.Button
        SubstancesindatabaseListBoxLabel  matlab.ui.control.Label
        SubstancesindatabaseListBox     matlab.ui.control.ListBox
        StatusLampLabel_4               matlab.ui.control.Label
        StatusLamp_Substance            matlab.ui.control.Lamp
        SubstanceInsertStatusEdit       matlab.ui.control.EditField
        AddBrainAreaTab                 matlab.ui.container.Tab
        AbbreviationEditFieldLabel      matlab.ui.control.Label
        AbbreviationEditField           matlab.ui.control.EditField
        LongnameLabel                   matlab.ui.control.Label
        BrainLongNameTextArea           matlab.ui.control.TextArea
        BrainareasindatabaseListBoxLabel  matlab.ui.control.Label
        BrainareasindatabaseListBox     matlab.ui.control.ListBox
        AddbrainareatodatabaseButton    matlab.ui.control.Button
        StatusLampLabel_5               matlab.ui.control.Label
        StatusLamp_BrainArea            matlab.ui.control.Lamp
        BrainAreaInsertStatusEdit       matlab.ui.control.EditField
        AddProjectTab                   matlab.ui.container.Tab
        AddprojecttodatabaseButton      matlab.ui.control.Button
        ProjectInsertStatusEdit         matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        ProjectNameEditField            matlab.ui.control.EditField
        DescriptionLabel                matlab.ui.control.Label
        ProjectDescriptionTextArea      matlab.ui.control.TextArea
        ProjectsindatabaseListBoxLabel  matlab.ui.control.Label
        ProjectsindatabaseListBox       matlab.ui.control.ListBox
        StatusLampLabel_7               matlab.ui.control.Label
        StatusLamp_Project              matlab.ui.control.Lamp
        AddBehaviorExperimentTypeTab    matlab.ui.container.Tab
        AddexperimenttypetodatabaseButton  matlab.ui.control.Button
        BehaviorExpInsertStatusEdit     matlab.ui.control.EditField
        NameEditFieldLabel_2            matlab.ui.control.Label
        BehaviorExpNameEditField        matlab.ui.control.EditField
        DescriptionLabel_2              matlab.ui.control.Label
        BehaviorExpDescriptionTextArea  matlab.ui.control.TextArea
        BehaviorexperimenttypesindatabaseListBoxLabel  matlab.ui.control.Label
        BehaviorexperimenttypesindatabaseListBox  matlab.ui.control.ListBox
        StatusLampLabel_8               matlab.ui.control.Label
        StatusLamp_BehaviorExp          matlab.ui.control.Lamp
        LogTab                          matlab.ui.container.Tab
        LogTextArea                     matlab.ui.control.TextArea
        ExporttotextfileButton          matlab.ui.control.Button
        DeleteAnimalsTab                matlab.ui.container.Tab
        DeleteAnimal_button             matlab.ui.control.Button
        StatusLampLabel_9               matlab.ui.control.Label
        StatusLamp_deleteAnimal         matlab.ui.control.Lamp
        EventInsertStatusEdit_deleteAnimal  matlab.ui.control.EditField
        DJIDEditFieldLabel              matlab.ui.control.Label
        DJIDEditField_deleteAnimal      matlab.ui.control.NumericEditField
        PleaseusewithcautionLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        accessible_tabs = {'Login'} % Which tabs user is allowed to select
        user_password=''; %property that will store the password entered by user
        prev_password_length=0;
        selected_animal_id = [];% animal_id for current selection
        login_user; % User who logged in
        ax_ageslider % axis for age slider
        l_ageslider  % line for age slider
        filter_ageLow = 0; % lower age limit
        filter_ageHigh = 200; % upper age limit
        filter_genotype_status = 'All'; % genotype_status to filter
        filter_struct = []; % struct as query object from filter panel
        filter_check_eye_inj = false; % booleon about whether to include only injected
        filter_check_brain_inj = false; % booleon about whether to include only injected
        filter_check_beh = false; % booleon about whether to include only behavior animals
        filter_check_reserved = false; %booleon about whether to only include unreserved
        filter_check_project_reserved = false; %check if reserved for project
        filter_project = 'All'; % reserved project to filter
        connection % DJ connection object
        cal_refresh_response; %for google calendar
        cal_access_response = []; %for google calendar
        cal_access_token_time =[]; % time of last access token request
        calDir; % directory for calendar access file
        selected_breeder_cage % selected breeder cage in Breeders tab
    end
    
    methods (Access = private)
        
        function init_AddAnimalTab(app)
            app.AddAnimal_load_genotypes();
            app.AddAnimal_loadRooms();
            app.loadBreedingCagesMenu();
            app.loadProtocolsMenu_addAnimalTab();
            
            %set initial state
            set(app.Search_DJIDEditField,'Enable',true);
            set(app.DOBDatePicker,'Enable',true);
            set(app.StatusLamp_Animal,'color','white');
            set(app.AnimalInsertStatusEdit,'value','');
            set(app.NumberofentriesSlider, 'value', 0);
            app.updateAnimalEntryVisibility();
        end
        
        function init_AddGenotypeTab(app, resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            genotypes = fetchn(sl.Genotype,'genotype_name');
            %put WT first
            ind = find(strcmp(genotypes,'WT'));
            if ~isempty(ind)
                g{1} = 'WT';
                g = [g; genotypes(setdiff(1:length(genotypes), ind))];
            else
                g = genotypes;
            end
            set(app.GenotypesindatabaseListBox,'Items',g);
            if resetStatusLamp
                set(app.StatusLamp_Genotype,'color','white');
                set(app.GenotypeInsertStatusEdit,'value','');
            end
        end
        
        function init_AddSubstanceTab(app,resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            substances = fetchn(sl.InjectionSubstance,'substance_name');
            set(app.SubstancesindatabaseListBox,'Items',substances);
            if resetStatusLamp
                set(app.StatusLamp_Substance,'color','white');
                set(app.SubstanceInsertStatusEdit,'value','');
            end
        end
        
        function init_AddBrainAreaTab(app, resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            areas = fetchn(sl.BrainArea,'target');
            set(app.BrainareasindatabaseListBox,'Items',areas);
            
            value = app.BrainareasindatabaseListBox.Value;
            if ~isempty(value)
                thisArea = fetch(sl.BrainArea & sprintf('target="%s"',value), '*');
                set(app.AbbreviationEditField, 'value', thisArea.target);
                set(app.BrainLongNameTextArea, 'value', thisArea.long_name);
            end
            
            if resetStatusLamp
                set(app.StatusLamp_BrainArea,'color','white');
                set(app.BrainAreaInsertStatusEdit,'value','');
            end
        end
        
        function init_AddProjectTab(app, resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            projects = fetchn(sl.Project,'project_name');
            set(app.ProjectsindatabaseListBox,'Items',projects);
            
            value = app.ProjectsindatabaseListBox.Value;
            if ~isempty(value)
                thisProject = fetch(sl.Project & sprintf('project_name="%s"',value), '*');
                set(app.ProjectNameEditField, 'value', thisProject.project_name);
                set(app.ProjectDescriptionTextArea, 'value', thisProject.description);
            end
            
            if resetStatusLamp
                set(app.StatusLamp_Project,'color','white');
                set(app.ProjectInsertStatusEdit,'value','');
            end
        end
        
        function init_AddBehaviorExpTab(app, resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            exps = fetchn(sl.SocialBehaviorExperimentType,'purpose');
            set(app.BehaviorexperimenttypesindatabaseListBox,'Items',exps);
            
            
            value = app.BehaviorexperimenttypesindatabaseListBox.Value;
            if ~isempty(value)
                thisExp = fetch(sl.SocialBehaviorExperimentType & sprintf('purpose="%s"',value), '*');
                set(app.BehaviorExpNameEditField, 'value', thisExp.purpose);
                set(app.BehaviorExpDescriptionTextArea, 'value', thisExp.description);
            end
            
            if resetStatusLamp
                set(app.StatusLamp_BehaviorExp,'color','white');
                set(app.BehaviorExpInsertStatusEdit,'value','');
            end
        end
        
        function AddAnimal_load_genotypes(app)
            genotypes = fetchn(sl.Genotype,'genotype_name');
            %put WT first
            ind = find(strcmp(genotypes,'WT'));
            if ~isempty(ind)
                g{1} = 'WT';
                g = [g; genotypes(setdiff(1:length(genotypes), ind))];
            else
                g = genotypes;
            end
            set(app.GenotypeDropDown,'Items',g);
            
            N_entries = 8;
            for e=1:N_entries
                 set(app.(['GenotypeDropDown_E' num2str(e)]),'Items',g);
            end            
        end
        
        function loadSubstanceMenus(app)
            substances = fetchn(sl.InjectionSubstance,'substance_name');
            set(app.SubstanceDropDown_IP,'items',substances);
            set(app.SubstanceDropDown_Eye,'items',substances);
            set(app.SubstanceDropDown_Brain,'items',substances);
            set(app.SubstanceDropDown_Feed,'items',substances);
        end
        
        function loadBrainAreas(app)
            brainAreas = fetchn(sl.BrainArea,'target');
            set(app.BrainTargetDropDown,'items',brainAreas);
        end
        
        function loadUsers(app)
            users = fetchn(sl.User,'user_name'); %eventually sl.UserActive
            %put login_user first
            ind = find(strcmp(users,app.login_user));
            if ~isempty(ind)
                u{1} = app.login_user;
                u = [u; users(setdiff(1:length(users), ind))];
            else
                u = users;
            end
            set(app.PersonDropDown,'items',u);
            if ~isempty(ind)
                set(app.PersonDropDown,'value',app.login_user);
            end
        end
        
        function loadUsers_breeders(app)
            users = fetchn(sl.User,'user_name'); %eventually sl.UserActive
            %put login_user first
            ind = find(strcmp(users,app.login_user));
            if ~isempty(ind)
                u{1} = app.login_user;
                u = [u; users(setdiff(1:length(users), ind))];
            else
                u = users;
            end
            set(app.PersonDropDown_breeders,'items',u);
            if ~isempty(ind)
                set(app.PersonDropDown_breeders,'value',app.login_user);
            end
        end
        
        function loadSocialExperimentTypes(app)
            exps = fetchn(sl.SocialBehaviorExperimentType,'purpose');
            set(app.ExperimenttypeDropDown,'items',exps);
        end
        
        function loadTestAnimalTypes(app)
            animal_types = fetchn(sl.TestAnimalType,'animal_type_name');
            set(app.TestanimaltypeDropDown,'items',animal_types);
        end
        
        function loadRigs(app)
            rigs = fetchn(sl.Rig,'rig_name');
            rigs{end+1} = 'A and B';
            set(app.RignameDropDown,'items',rigs);
        end
        
        function loadEventTypes(app)
            ch = get(app.EventTabGroup,'children');
            N = length(ch);
            eventNames = cell(N,1);
            for i=1:N
                eventNames{i} = get(ch(i),'Title');
            end
            set(app.EventTypeDropDown,'items',eventNames)
        end
        
        function loadProjects(app)
            projects = fetchn(sl.Project,'project_name');
            set(app.ProjectDropDown,'items',projects);
            set(app.Filter_ProjectDropDown,'items',['All'; projects]);
        end
        
        function init_BreedersTab(app)
            %load users
            app.loadUsers_breeders();
            
            %load rooms
            app.Breeders_loadRooms();
            
            %set date picker to current date
            set(app.EventdateDatePicker_breeders,'value',datetime(date,'format','yyyy-MM-dd'))
            
            %set status lamp to grey and empty text field
            set(app.StatusLamp_breeders,'color','white');
            set(app.EventInsertStatusEdit_breeders, 'value', '');
            
            %load unpaired breeders table
            app.loadUnpairedBreedersTable();
            
            %load breedig cages tables
            app.loadBreedingCagesTable();
            
            %set editable column in unpaired breeders table
            set(app.UnpairedBreedersTable,'ColumnEditable',logical([1 0 0 0 0 0 0]));
            
            %set selections to empty
            app.selected_breeder_cage = [];
             
        end
        
        function init_AnimalLoggerTab(app)
            %load genotypes
            app.loadGenotypeMenu();
            
            %load substances
            app.loadSubstanceMenus();
            
            %load brain areas
            app.loadBrainAreas();
            
            %load social behavior experiment types
            app.loadSocialExperimentTypes();
            
            %load test animal types for social behavior experiments
            app.loadTestAnimalTypes();
            
            %load test stimlui for social behavior experiments
            app.loadBehaviorStimuli();
            
            %load users
            app.loadUsers();
            
            %load rigs
            app.loadRigs();
            
            %load projects
            app.loadProjects();
            
            %load protocols
            app.loadProtocolsMenu_loggerTab();
            
            %load rooms
            app.Logger_loadRooms();
            
            %load possible genotype results
            app.loadGenotypeStatus();
            
            %load event types for log menu
            app.loadEventTypes();
            
            %set date picker to current date
            set(app.EventdateDatePicker,'value',datetime(date,'format','yyyy-MM-dd'))
            
            %set id switch
            set(app.ID_type_switch,'value','Tag ID');
            set(app.Search_DJIDEditField,'Enable',false);
            
            %set filter switches
            set(app.EyeInjSwitch,'value','Don''t check');
            set(app.BraininjSwitch,'value','Don''t check');
            set(app.BehaviorexpSwitch,'value','Don''t check');
            set(app.NotrigreservedSwitch,'value','Don''t check');
            app.filter_check_eye_inj = false;
            app.filter_check_brain_inj = false;
            app.filter_check_beh = false;
            app.filter_check_reserved = false;
            
            %set menus and age boxes
            set(app.Filter_SexDropDown,'value','All');
            set(app.ageLow_edit,'value','');
            set(app.ageHigh_edit,'value','');
            
            %make initial filter struct
            app.filter_genotype_status = 'All';
            app.filter_ageLow = str2double(app.ageLow_edit.Value);
            app.filter_ageHigh = str2double(app.ageHigh_edit.Value);
            
            filter_query = [];
            
            %set status lamp to grey and empty text field
            set(app.StatusLamp_Logger,'color','white');
            set(app.EventInsertStatusEdit, 'value', '');
            
            try
                %calendar init
                if isdeployed
                    app.calDir = '/home/matlabuser';
                    app.cal_refresh_response.refresh_token = '1//0f4-lbZ90smKbCgYIARAAGA8SNwF-L9IrI9vFDovfyvZzzi5caMaTLx-1_CRoLFeuLuo2RcEEtIhrkyMdG0YSNKRYoLWOT4kK2l4';
                    app.cal_refresh_response.access_token = 'ya29.a0AfH6SMClV7vcfiNv6ebcsTY4UrDavnikDD9ZpsBlYj6pXt3Ysr0qn-tPU8dWhcRTDXt3unnq6TQEpTl0uc6Qm-LShA5OHvarXggtfnkWnv2Im181Pe5l5nzdjowiwCIsuStZAdSRnqhRQmJeKQqLQfuJvbNtslpZh0A';
                else
                    app.calDir = pwd;
                    %not working for deployed yet
                    fid = fopen([app.calDir filesep 'gcal_access.txt'],'r');
                    app.cal_refresh_response.access_token = fgetl(fid);
                    app.cal_refresh_response.refresh_token = fgetl(fid);
                    
                end
                app.cal_refresh_response.expires_in = 3599;
                app.cal_refresh_response.scope = 'https://www.googleapis.com/auth/calendar';
                app.cal_refresh_response.token_type = 'Bearer';
            catch
                disp('Google calendar integration failed');
            end
            
            
            %load animal table
            app.loadAnimalTable(filter_query);
        end
        
        function loadBreedingCagesTable(app)
            %get only breeders
            allAnimals = fetch(sl.AnimalEventDeceased.living(),'*');
            animal_ids = [allAnimals.animal_id]';
            isBreeder = sl.Animal.isBreeder(animal_ids);
            animal_ids = animal_ids(isBreeder);
            
            if isempty(animal_ids)
                nCages=0;
            else
                %get breeders
                breeder_animals = fetch(sl.Animal & struct('animal_id',num2cell(animal_ids)), '*');
                
                if isempty(breeder_animals)
                    nCages=0;
                else
                    %check which are in breeding cages
                    breeding_cage_numbers = fetchn(sl.BreedingCage, 'cage_number');
                    active_ind = sl.BreedingCage.isActive(breeding_cage_numbers);
                    breeding_cage_numbers = breeding_cage_numbers(active_ind);
                    
                    nCages = length(breeding_cage_numbers);
                end
            end
            if nCages==0
                D = table;
            else
                %set up uitable
                D = table('Size',[nCages,10],'VariableTypes', ...
                    {'string',...   #1 Cage
                    'string', ...   #2 Room
                    'string',...    #3 male genotype
                    'string',...    #4 female genotype
                    'string',...    #5 date paired
                    'uint32',...    #6 number of litters
                    'string',...    #7 litter sizes born
                    'string',...    #8 litter sizes weaned
                    'string',...    #9 litter dates
                    'string',...    #10 next wean date
                    },...
                    'VariableNames', ...
                    {'Cage_number',...
                    'Room_number', ...
                    'Male_Genotype'...
                    'Female_Genotype'...
                    'Date_paired',...
                    'N_litters',...
                    'Litter_sizes',...
                    'Weaned_sizes',...
                    'Litter_dates',...
                    'Next_wean_date',...
                    });
                
            end
            if nCages>0
                allSex = {breeder_animals.sex};
                allGenotype = {breeder_animals.genotype_name};
                animals_temp = sl.Animal.roomNumber(animal_ids);
                allRooms = {animals_temp.room_number};
                animals_temp = sl.Animal.cageNumber(animal_ids);
                allCages = {animals_temp.cage_number};
            end
            for i=1:nCages
                D.Cage_number(i) = breeding_cage_numbers{i};
                theseBreeders_ind = find(strcmp(allCages,breeding_cage_numbers{i}));
                if length(theseBreeders_ind) < 2
                    disp('breeders missing');
                    %TODO: what is up here? This should not happen
                else
                    cur_sex = allSex(theseBreeders_ind);
                    if strcmp(cur_sex{1}, 'Male')
                        male_ind = theseBreeders_ind(1);
                        female_ind = theseBreeders_ind(2);
                    else
                        male_ind = theseBreeders_ind(2);
                        female_ind = theseBreeders_ind(1);
                    end
                    D.Male_Genotype(i) = allGenotype{male_ind};
                    D.Female_Genotype(i) = allGenotype{female_ind};
                    D.Room_number(i) = allRooms(male_ind);
                    activation = fetch(sl.AnimalEventActivateBreedingCage & sprintf('cage_number="%s"',breeding_cage_numbers{i}), 'date', 'LIMIT 1');
                    D.Date_paired(i) = activation.date;
                    thisBreedingCage = sl.BreedingCage & sprintf('cage_number="%s"',breeding_cage_numbers{i});
                    [littersN, littersDates] = thisBreedingCage.getLitters;
                    D.N_litters(i) = length(littersN);
                    D.Litter_sizes(i) = num2str(littersN);
                    D.Litter_dates(i) = strjoin(littersDates, ', ');
                    [weanedN, weanedDates] = thisBreedingCage.getWeaned;
                    D.Weaned_sizes(i) = num2str(weanedN);
                    if length(weanedN) < length(littersN)
                        D.Next_wean_date(i) = string(datetime(littersDates{end}) + days(21), 'yyyy-MM-dd');
                    else
                        D.Next_wean_date(i) = '';
                    end
                end
            end
            
            %set table data
            set(app.BreederCagesTable,'Data',D);
            defaultStyle = uistyle('FontColor','black','FontWeight','normal');
            addStyle(app.BreederCagesTable,defaultStyle);
            
            %make no cage selected
            app.selected_breeder_cage = [];
        end
        
        function loadUnpairedBreedersTable(app)
            %make table invisible until loaded
            set(app.UnpairedBreedersTable,'visible','off');
            drawnow;
            
            %get only breeders
            allAnimals = fetch(sl.AnimalEventDeceased.living(),'*');
            animal_ids = [allAnimals.animal_id]';
            isBreeder = sl.Animal.isBreeder(animal_ids);
            animal_ids = animal_ids(isBreeder);
            
            if isempty(animal_ids)
                unpairedBreeders = [];
            else
                %get cage numbers
                cage_numbers = sl.Animal.cageNumber(animal_ids);
                
                %check which are not breeding cages
                breeding_cage_numbers = fetchn(sl.BreedingCage, 'cage_number');
                unpaired_ind = ~ismember({cage_numbers.cage_number},breeding_cage_numbers);
                animal_ids = animal_ids(unpaired_ind);
                nAnimals = length(animal_ids);
                
                %get the pieces we need for the table
                unpairedBreeders = fetch(sl.Animal & struct('animal_id', num2cell(animal_ids)), '*');
            end
            if ~isempty(unpairedBreeders)
                T = struct2table(unpairedBreeders,'AsArray',true);
                age = sl.Animal.age(animal_ids);
                genotype_status = sl.Animal.genotypeStatus(animal_ids);
                cage_numbers = sl.Animal.cageNumber(animal_ids);
                T = outerjoin(T, struct2table(age,'AsArray',true),'MergeKeys',true,'type','Left'); %every mouse has an age
                
                tag_ids = sl.Animal.tagNumber(animal_ids);                  
                if isempty(tag_ids)
                    T.tag_id = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(tag_ids,'AsArray',true),'MergeKeys',true,'type','Left');
                end

                if isempty(genotype_status)
                    T.genotype_status = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(genotype_status,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                
                if isempty(cage_numbers)
                    T.cage_numbers = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(cage_numbers,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                
            end
            
            if isempty(unpairedBreeders)
                D = table;
            else
                %set up uitable
                D = table('Size',[nAnimals,8],'VariableTypes', ...
                    {'logical', ... #1 Selected
                    'uint32',...    #2 DJ ID
                    'uint32', ...   #3 tag ID
                    'string',...    #4 cage #
                    'string',...    #5 sex
                    'string',...    #6 genotype
                    'string',...    #7 genotype status
                    'singlenan',... #8 age
                    },...
                    'VariableNames', ...
                    {'Selected',...
                    'DJ_ID',...
                    'Tag_ID', ...
                    'Cage_number', ...
                    'Sex',...
                    'Genotype'...
                    'Genotype_status',...
                    'Age',...
                    });
                                
                D.DJ_ID = T.animal_id;
                D.Tag_ID = T.tag_id;
                D.Cage_number = T.cage_number;
                D.Sex = string(T.sex);
                D.Genotype = string(T.genotype_name);
                D.Genotype_status = T.genotype_status;
                D.Age = T.age;
            end
            %set table data
            set(app.UnpairedBreedersTable,'Data',D);
            defaultStyle = uistyle('FontColor','black','FontWeight','normal');
            addStyle(app.UnpairedBreedersTable,defaultStyle);
            
            %make no animal selected
            app.selected_animal_id = [];
            
            %and visible when done loading
            set(app.UnpairedBreedersTable,'visible','on');
            drawnow;
        end
        
        function loadAnimalTable(app, filter_query)
            % change title to table loading...
            set(app.AnimalSelectionPanel, 'Title', 'Table loading...');
            %and make table invisible
            set(app.AnimalTable,'visible','off');
            drawnow;
            
            if isempty(app.filter_ageLow) || isnan(app.filter_ageLow)
                app.filter_ageLow = 0;
            end
            if isempty(app.filter_ageHigh) || isnan(app.filter_ageHigh)
                app.filter_ageHigh = 200;
            end
            
            if ~isempty(filter_query) %run specified query instead of stored filter
                allAnimals = fetch(sl.AnimalEventDeceased.living() & filter_query,'*');
            else
                
                % make age restriction
                max_dob = char(datetime(datetime('now') - 7*app.filter_ageLow,'format','yyyy-MM-dd'));
                if app.filter_ageHigh >= 25 %25 weeks considered inf
                    min_dob = '1900-01-01'; %really old mouse!
                else
                    min_dob = char(datetime(datetime('now') - 7*app.filter_ageHigh,'format','yyyy-MM-dd'));
                end
                ageQuery = {['dob >= ' '"' min_dob '"'], ['dob <= ' '"' max_dob '"']};
                allAnimals = fetch(sl.AnimalEventDeceased.living() ...
                    & app.filter_struct & ageQuery,'animal_id', 'sex', 'dob', 'genotype_name');
            end
            if isempty(allAnimals)
                D = table;
            else
                animal_ids = [allAnimals.animal_id]';
                nAnimals = length(animal_ids);
                tag_ids = sl.Animal.tagNumber(animal_ids);
                cage_numbers = sl.Animal.cageNumber(animal_ids);
                room_numbers = sl.Animal.roomNumber(animal_ids);
                genotype_status = sl.Animal.genotypeStatus(animal_ids);
                project_name = sl.Animal.reservedProject(animal_ids);
                hasBehavior = sl.Animal.hasEvent('SocialBehaviorSession',animal_ids);
                hasBrainInjection = sl.Animal.hasEvent('BrainInjection',animal_ids);
                hasEyeInjection = sl.Animal.hasEvent('EyeInjection',animal_ids);
                hasIPinjection = sl.Animal.hasEvent('IPinjection',animal_ids);
                hasRigReservation = sl.Animal.hasEvent('ReservedForSession',animal_ids);
                hasProjectReservation = sl.Animal.hasEvent('ReservedForProject',animal_ids);
                isBreeder = sl.Animal.isBreeder(animal_ids);
                
                age = sl.Animal.age(animal_ids);
                T = struct2table(allAnimals,'AsArray',true);
                if isempty(tag_ids)
                    T.tag_id = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(tag_ids,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                if isempty(cage_numbers)
                    T.cage_number = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(cage_numbers,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                if isempty(room_numbers)
                    T.room_number = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(room_numbers,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                if isempty(genotype_status)
                    T.genotype_status = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(genotype_status,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                if isempty(project_name)
                    T.project_name = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(project_name,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                T = outerjoin(T, struct2table(age,'AsArray',true),'MergeKeys',true,'type','Left'); %every mouse has an age
                %keyboard;    
                T.hasBehavior = hasBehavior;
                T.hasBrainInjection = hasBrainInjection;
                T.hasEyeInjection = hasEyeInjection;
                T.hasIPinjection = hasIPinjection;
                T.hasRigReservation = hasRigReservation;
                T.hasProjectReservation = hasProjectReservation;
                T = T(~isBreeder, :);
                N = height(T);                
                %if N==1
                %    keyboard;
                %end
                D = table('Size',[N,14],'VariableTypes', ...
                    {'uint32',...   #1 DJ ID
                    'uint32', ...   #2 tag ID
                    'string',...    #3 cage number
                    'string',...    #4 room number
                    'string',...    #5 genotype
                    'string',...    #6 genotype status
                    'logical',...   #7 IP inj
                    'string',...    #8 sex
                    'singlenan',... #9 age
                    'logical',...   #10 eye inj
                    'logical',...   #11 brain inj
                    'logical', ...  #12 behavior
                    'logical', ...  #13 reserved for rig
                    'string', ...   #14 reserved project
                    },...
                    'VariableNames', ...
                    {'DJ_ID',...
                    'Tag_ID', ...
                    'Cage_number',...
                    'Room_number',...
                    'Genotype'...
                    'Genotype_status',...
                    'IP_inj',...
                    'Sex',...
                    'Age',...
                    'Eye_inj',...
                    'Brain_inj',...
                    'BehaviorExp',...
                    'ReservedForRig', ...
                    'ReservedProject', ...
                    });
                D.DJ_ID = T.animal_id;
                D.Tag_ID = T.tag_id;
                D.Cage_number = T.cage_number;
                D.Room_number = T.room_number;
                D.Genotype = string(T.genotype_name);
                D.Genotype_status = T.genotype_status;
                D.IP_inj = T.hasIPinjection;
                D.Sex = string(T.sex);
                D.Age = T.age;
                D.Eye_inj = T.hasEyeInjection;
                D.Brain_inj = T.hasBrainInjection;
                D.BehaviorExp = T.hasBehavior;
                D.ReservedForRig = T.hasRigReservation;
                D.ReservedProject = string(T.project_name);
                %filter parts that have to be done after table construction
                if app.filter_check_beh
                    ind = D.BehaviorExp;
                    D = D(ind,:);
                end
                if app.filter_check_eye_inj
                    ind = D.Eye_inj;
                    D = D(ind,:);
                end
                if app.filter_check_brain_inj
                    ind = D.Brain_inj;
                    D = D(ind,:);
                end
                if app.filter_check_reserved
                    ind = D.ReservedForRig;
                    D = D(~ind,:);
                end
                if app.filter_check_project_reserved
                    ind = T.hasProjectReservation;
                    D = D(~ind,:);
                end
                if ~strcmp(app.filter_genotype_status,'All')
                    ind = strcmp(D.Genotype_status, app.filter_genotype_status);
                    D = D(ind,:);
                end
                if ~strcmp(app.filter_project,'All')
                    ind = strcmp(D.ReservedProject, app.filter_project);
                    D = D(ind,:);
                end
            end
            N = height(D);
            if N>0
                set(app.AnimalSelectionPanel, 'Title', ...
                    ['Animal Selection: ' num2str(N) ' total']);
            else
                set(app.AnimalSelectionPanel, 'Title', ...
                    'Animal Selection: none found');
            end
            set(app.AnimalTable,'Data',D);
            defaultStyle = uistyle('FontColor','black','FontWeight','normal');
            addStyle(app.AnimalTable,defaultStyle);
            
            %make no animal selected
            app.selected_animal_id = [];
            
            %make visible again
            set(app.AnimalTable,'visible','on');
            drawnow;
        end
        
        function loadGenotypeMenu(app)
            genotypes = fetchn(sl.Genotype,'genotype_name');
            %put WT first
            ind = find(strcmp(genotypes,'WT'));
            if ~isempty(ind)
                g{1} = 'WT';
                g = [g; genotypes(setdiff(1:length(genotypes), ind))];
            else
                g = genotypes;
            end
            N_entries = 8;
            for e=1:N_entries
                set(app.(['GenotypeDropDown_E' num2str(e)]),'Items',g);
            end
            
            g = ['All'; g];
            set(app.Filter_GenotypeDropDown,'Items',g);
        end
        
        function loadBreedingCagesMenu(app)
            breedingCageList = fetchn(sl.BreedingCage,'cage_number');
            
            %put None (0) first
            ind = find(strcmp(breedingCageList,'0'));
            if isempty(ind)
                disp('Error: need a BreedingCage with number 0')
            else
                b{1} = 'None';
                b = [b; breedingCageList(setdiff(1:length(breedingCageList), ind))];
            end
            
            set(app.BreedingcagesindatabaseDropDown,'Items',b);            
            
        end
        
        function loadProtocolsMenu_addAnimalTab(app)
            protocolList = fetchn(sl.AnimalProtocol,'protocol_name');
            protocolList_nums = fetchn(sl.AnimalProtocol,'protocol_number');
            for i=1:length(protocolList)
                protocolList{i} = sprintf('%s - %d', protocolList{i}, protocolList_nums(i));
            end
            
            set(app.ProtocolDropDown,'Items',protocolList);
            
            N_entries = 8;
            for e=1:N_entries
                 set(app.(['ProtocolDropDown_E' num2str(e)]),'Items',protocolList);
            end
        end
        
        function loadProtocolsMenu_loggerTab(app)
            protocolList = fetchn(sl.AnimalProtocol,'protocol_name');
            protocolList_nums = fetchn(sl.AnimalProtocol,'protocol_number');
            for i=1:length(protocolList)
                protocolList{i} = sprintf('%s - %d', protocolList{i}, protocolList_nums(i));
            end
            set(app.AnimalprotocolDropDown,'Items',protocolList);
        end
        
        function [calEventAdded, result] = addCalendarEvent(app,key,type)
            if isempty(app.cal_access_token_time) || isempty(app.cal_access_response) || ...
                    datetime('now') - app.cal_access_token_time > seconds(app.cal_access_response.expires_in)
                %token expired
                try
                    app.cal_access_response = webwrite(...
                        'https://oauth2.googleapis.com/token',...
                        'grant_type','refresh_token',...
                        'client_id','667407419363-59ebnl9973ie08ui05fh1b8go1fvpbsn.apps.googleusercontent.com',...
                        'client_secret','dLydGGCoNOaMD1bA8HQFADqL',...
                        'refresh_token',app.cal_refresh_response.refresh_token,...
                        'redirect_uri','urn:ietf:wg:oauth:2.0:oob'...
                        );
                    app.cal_access_token_time = datetime('now');
                catch
                    result = 'Calendar access code failed: maybe refresh_token expired.';
                    calEventAdded = false;
                    return;
                end
            else
                disp('using saved access token');
            end
            
            try
                switch type
                    case 'reserveForRig'
                        %make reserve forRigEvent
                        genotype = fetch1(sl.Animal & sprintf('animal_id=%d',key.animal_id), 'genotype_name');
                        body = struct();
                        body.start.date = key.date;
                        body.end.date = key.date;
                        body.summary = sprintf('%s: experiment on rig %s with mouse(DJID): %d, genotype: %s', ...
                            key.user_name, key.rig_name, key.animal_id, genotype);
                        cageNum = sl.Animal.cageNumber(key.animal_id);
                        roomNum = sl.Animal.roomNumber(key.animal_id);
                        body.location = sprintf('Room %s, cage %s', roomNum.room_number, cageNum.cage_number);
                        if ~isempty(key.notes)
                            body.description = key.notes;
                        end
                        
                    case 'weaning'
                        %make weaning event
                        body = struct();
                        body.start.date = key.date;
                        body.end.date = key.date;
                        body.summary = sprintf('Wean animals in cage %s', ...
                            key.cage_number);                        
                end
               
                
                %make request
                request = matlab.net.http.RequestMessage(...
                    matlab.net.http.RequestMethod.POST,...
                    matlab.net.http.HeaderField('Authorization',sprintf('Bearer %s',app.cal_access_response.access_token),...
                    'MediaType','application/json'),...
                    body);
                
                %post it
                status = request.send('https://www.googleapis.com/calendar/v3/calendars/schwartzlabnu@gmail.com/events');
                if strcmp(status.StatusCode,'OK')
                    calEventAdded = true;
                    result = 'Calendar entry insert success';
                else
                    calEventAdded = false;
                    result = 'Calendar entry insert failed';
                end
            catch
                calEventAdded = false;
                result = 'Calendar entry insert failed';
            end
            
        end
        
        function AddAnimal_loadRooms(app)
            roomList = fetchn(sl.AnimalCageRoom,'room_number');
            set(app.RoomDropDown,'Items',roomList);
            
            N_entries = 8;
            for e=1:N_entries
                 set(app.(['RoomDropDown_E' num2str(e)]),'Items',roomList);
            end
        end
        
        function Logger_loadRooms(app)
            roomList = fetchn(sl.AnimalCageRoom,'room_number');
            set(app.RoomDropDown_logger,'Items',roomList);
        end
        
        function Breeders_loadRooms(app)
            roomList = fetchn(sl.AnimalCageRoom,'room_number');
            set(app.RoomDropDown_breeders,'Items',roomList);
            set(app.RoomDropDown_breeders_sepMale,'Items',roomList);
            set(app.RoomDropDown_breeders_sepFemale,'Items',roomList);
        end
        
        
        function loadMaleAndFemaleTables(app)
            if isempty(app.selected_breeder_cage)
                return;
            end
            %get only breeders
            allAnimals = fetch(sl.AnimalEventDeceased.living(),'*');
            animal_ids = [allAnimals.animal_id]';
            isBreeder = sl.Animal.isBreeder(animal_ids);
            animal_ids = animal_ids(isBreeder);
            
            %get breeders
            breeder_animals = fetch(sl.Animal & struct('animal_id',num2cell(animal_ids)), '*');
            
            %set up table
            nAnimals = length(animal_ids);
            age = sl.Animal.age(animal_ids);
            cage = sl.Animal.cageNumber(animal_ids);
            genotype_status = sl.Animal.genotypeStatus(animal_ids);
            allTagID = sl.Animal.tagNumber(animal_ids);
                                  
            T = struct2table(age,'AsArray',true);
            
            if isempty(allTagID)
                T.tag_id = nan(nAnimals,1);
            else
                T = outerjoin(T, struct2table(allTagID,'AsArray',true),'MergeKeys',true,'type','Left');
            end
            if isempty(cage)
                T.cage = nan(nAnimals,1);
            else
                T = outerjoin(T, struct2table(cage,'AsArray',true),'MergeKeys',true,'type','Left');
            end
            if isempty(genotype_status)
                T.genotype_status = nan(nAnimals,1);
            else
                T = outerjoin(T, struct2table(genotype_status,'AsArray',true),'MergeKeys',true,'type','Left');
            end
            
            allSex = {breeder_animals.sex};
            animals_temp = sl.Animal.cageNumber(animal_ids);
            allCages = {animals_temp.cage_number};
            theseBreeders_ind = find(strcmp(allCages,app.selected_breeder_cage));
            tagID_vec = T.tag_id; 
            
            cur_sex = allSex(theseBreeders_ind);
            if strcmp(cur_sex{1}, 'Male')
                male_ind = theseBreeders_ind(1);
                female_ind = theseBreeders_ind(2);
            else
                male_ind = theseBreeders_ind(2);
                female_ind = theseBreeders_ind(1);
            end
            
            D_male = table('Size',[1,4],'VariableTypes', ...
                {'uint32',...   #1 DJ ID
                'uint32', ...   #2 tag ID
                'string',...    #5 genotype status
                'singlenan',... #6 age
                },...
                'VariableNames', ...
                {'DJ_ID',...
                'Tag_ID', ...
                'Genotype_status',...
                'Age',...
                });
            
            D_female = D_male;
            
            D_male.DJ_ID(1) = animal_ids(male_ind);
            D_female.DJ_ID(1) = animal_ids(female_ind);
            D_male.Age(1) = calweeks(T.age(male_ind));
            D_female.Age(1) = calweeks(T.age(female_ind));
            D_male.Genotype_status(1) = T.genotype_status(male_ind);
            D_female.Genotype_status(1) = T.genotype_status(female_ind);
            if ~isempty(tagID_vec)
                D_male.Tag_ID(1) = tagID_vec(male_ind);
                D_female.Tag_ID(1) = tagID_vec(female_ind);
            else
                D_male.Tag_ID(1) = nan;
                D_female.Tag_ID(1) = nan;
            end
            
            set(app.selectedMaleTable,'Data',D_male);
            set(app.selectedFemaleTable,'Data',D_female);
        end
        
        function [selected_ids, selected_sex] = getUnpairedBreederTableSelection(app)
            D = get(app.UnpairedBreedersTable, 'Data');
            isSelected = D.Selected;
            selected_ids = D.DJ_ID(isSelected);
            selected_sex = D.Sex(isSelected);
        end
        
        function updateAnimalEntryVisibility(app)
            %value = app.NumberofentriesSpinner.Value;
            value = app.NumberofentriesSlider.Value;
            N_entries = 8;
            itemList = {'GenotypeDropDown_E' ...
                'DOBDatePicker_E', ...
                'TagIDEditField_E', ...
                'CageEditField_E', ...
                'SexButtonGroup_E', ...
                'RoomDropDown_E', ...
                'SourceDropDown_E', ...
                'SourceIDEditField_E', ...
                'ProtocolDropDown_E', ...
                'PunchearButtonGroup_E', ...
                'TagearButtonGroup_E', ...
                'tagged_switch_E', ...
                'DOB_known_switch_E', ...
                'Label_', ...
                };
            for e=1:value
                for i = 1:length(itemList)  
                    %keyboard;
                    set(app.([itemList{i} num2str(e)]), 'Visible', true);
                end
                if e==1
                    set(app.SetotherentriesButton, 'Visible', true)
                end
            end
            for e=value+1:N_entries
                for i = 1:length(itemList)  
                    set(app.([itemList{i} num2str(e)]), 'Visible', false);
                end
                if e==1
                    set(app.SetotherentriesButton, 'Visible', false)
                end
            end
        end        
        
        function loadGenotypeStatus(app)
            hdr = sl.AnimalEventGenotyped().header;
            typeDef = hdr.attributes(8).type; %attribute 8 is genotype_status. This is the whole enum line
            C = strsplit(typeDef,'(');
            C = C{2};
            C = strsplit(C,')');
            C = C{1};
            C = strrep(C,'''','');
            C = strsplit(C,',');            
            set(app.Filter_GenotypeStatusDropDown,'items',['All', C]);
            set(app.GenotypeResultsDropDown,'items',C);
        end
        
        function loadBehaviorStimuli(app)
            stims = fetchn(sl.BehaviorVisualStimulusType,'stim_type');
            set(app.AnimalAEditField, 'visible', false);
            set(app.AnimalBEditField, 'visible', false);
            set(app.AnimalCEditField, 'visible', false);
                        
            set(app.WindowAStim,'items',stims);
            set(app.WindowAStim,'value','empty');
            
            set(app.WindowBStim,'items',stims);
            set(app.WindowBStim,'value','empty');
            
            set(app.WindowCStim,'items',stims);
            set(app.WindowCStim,'value','empty');
        end                
        
    end
    
    methods (Static)
        
        function ind = sortEvents(events)
            ev_structs = fetch(events,'date','time');
            L = length(ev_structs);
            %evTimes = datetime(L,1);
            for i=1:L
                if isempty(ev_structs(i).time)
                    thisTime = '00:00:00';
                else
                    thisTime = ev_structs(i).time;
                end
                evTimes(i) = datetime([ev_structs(i).date, ' ', thisTime]);
            end
            
            [~,ind] = sort(evTimes);
        end
        
        function setRadioButtonToValue(buttonGroup, value)
            ch = get(buttonGroup, 'children');
            N_children = length(ch);
            for i=1:N_children
                if strcmp(get(ch(i),'Text'), value)
                    set(buttonGroup, 'SelectedObject', ch(i));
                end                
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.connection = struct;
            app.connection.isConnected = false;
%             if isdeployed
%                 app.logFolder = '/mnt/fsmresfiles/AnimalLogs/';
%             else
%                 app.logFolder = '/Volumes/SchwartzLab/AnimalLogs/';
%             end
            %datajoint setup
            %             setenv('DJ_USER', 'sl_user')
            %             if isdeployed
            %                 setenv('DJ_HOST', '127.0.0.1')
            %             else
            %                 setenv('DJ_HOST', 'vfsmdatajoint01.fsm.northwestern.edu')
            %             end
            %             setenv('DJ_PASS', 'r0zsNB0xk6wc5BgA!')
            %             dj.conn();
            
            %start with only login tab enabled (default)
            
            %load user names into login dropdown
            %users = fetchn(sl.User,'user_name'); %eventually sl.UserActive
            %set(app.UserDropDown,'items',users);
            
        end

        % Selection change function: MainTabGroup
        function MainTabGroupSelectionChanged(app, event)
            selectedTab = app.MainTabGroup.SelectedTab;
            
            if ~ismember(get(selectedTab,'Title'),app.accessible_tabs)
                app.MainTabGroup.SelectedTab = event.OldValue;
            else
                selectedTabName = get(selectedTab,'Title');
                
                switch(selectedTabName)
                    case 'AnimalLogger'
                        app.init_AnimalLoggerTab;
                    case 'Breeders'
                        app.init_BreedersTab;
                    case 'Add Animals'
                        app.init_AddAnimalTab;
                    case 'Add Genotype'
                        app.init_AddGenotypeTab;
                    case 'Add Substance'
                        app.init_AddSubstanceTab;
                    case 'Add Brain Area'
                        app.init_AddBrainAreaTab;
                    case 'Add Project'
                        app.init_AddProjectTab;
                    case 'Add Behavior Experiment Type'
                        app.init_AddBehaviorExpTab;
                    case 'Login'
                        if app.connection.isConnected
                            app.connection.close;
                            app.connection = struct;
                            app.connection.isConnected = false;
                        end
                        set(app.PasswordEditField,'value','');
                        app.accessible_tabs = {'Login'};
                end
            end
        end

        % Value changing function: PasswordEditField
        function PasswordEditFieldValueChanging(app, event)
            val = event.Value;
            current_pass_length=length(val); %get the number of characters present in password edit field
            app.prev_password_length=length(app.user_password);
            
            %if password edit field is empty and is in focus
            if isempty(val)
                app.user_password='';   %set the password property to empty string
                app.prev_password_length=0;  %set the previous password length to zero
                app.PasswordEditField.Value='';  %set the value in edit field to empty string
                
                %if backspace is clicked by user
            elseif(current_pass_length<app.prev_password_length)
                app.user_password = app.user_password(1:current_pass_length);
                app.PasswordEditField.Value = repelem('*', current_pass_length);
            else
                %remove the previous available '*' from the password value obtained from the app
                add_string=erase(val, repelem('*', length(app.user_password)));
                
                %append the chacter to user_passowrd property
                app.user_password=append(app.user_password,add_string);
                
                %mask the password in edit field with the total length of characters stored in user_password
                app.PasswordEditField.Value = repelem('*', length(app.user_password));
            end
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            currentUser = get(app.LoginUserEditField, 'value');
            if isdeployed
                try
                    disp('deployed');
                    app.connection = dj.conn('127.0.0.1', currentUser, app.user_password, [], true);
                catch ME
                    disp('deployed');
                    rethrow(ME);
                end
                
            else
                try
                    disp('not deployed');
                    app.connection = dj.conn('vfsmdatajoint01.fsm.northwestern.edu', currentUser, app.user_password, [], true);
                catch ME
                    disp('not deployed');
                    rethrow(ME)
                end
            end
            
            if app.connection.isConnected
                disp('login successful');
                app.login_user = currentUser;
                %enable AnimalLogger tab
                if strcmp(app.login_user, 'Susan') || strcmp(app.login_user, 'Greg')
                    app.accessible_tabs = [app.accessible_tabs, ...
                        'AnimalLogger', ...
                        'Breeders', ...
                        'Add Animals', ...
                        'Add Genotype', ...
                        'Add Substance',...
                        'Add Brain Area',...
                        'Add Project',...
                        'Add Behavior Experiment Type', ...
                        'Log',...
                        'Delete Animals', ...
                        ];
                else
                    app.accessible_tabs = [app.accessible_tabs, ...
                        'AnimalLogger', ...
                        'Breeders', ...
                        'Add Animals', ...
                        'Add Genotype', ...
                        'Add Substance',...
                        'Add Brain Area',...
                        'Add Project',...
                        'Add Behavior Experiment Type', ...
                        'Log',...
                        ];
                end
                
                app.MainTabGroup.SelectedTab = app.AnimalLoggerTab;
                
                %initialize the appropriate tabs
                app.init_AnimalLoggerTab
            else
                disp('login failed');
            end
        end

        % Value changed function: DOB_known_switch
        function DOB_known_switchValueChanged(app, event)
            value = app.DOB_known_switch.Value;
            if strcmp(value,'DOB unknown')
                set(app.DOBDatePicker,'Enable',false);
            else
                set(app.DOBDatePicker,'Enable',true);
            end
        end

        % Value changed function: tagged_switch
        function tagged_switchValueChanged(app, event)
            value = app.tagged_switch.Value;
            if strcmp(value,'Untagged')
                set(app.TagIDEditField,'Enable',false)
            else
                set(app.TagIDEditField,'Enable',true);
            end
        end

        % Button pushed function: AddsingleanimaltodatabaseButton
        function AddsingleanimaltodatabaseButtonPushed(app, event)
            %make insert key
            key = struct;
            
            if strcmp(get(app.tagged_switch,'value'),'Untagged')
                key_tag.tag_ear = 'None';                
            else
                key_tag.tag_id = str2double(get(app.TagIDEditField,'value'));
                key_tag.tag_ear = get(get(app.TagearButtonGroup,'SelectedObject'), 'text');
            end
            key_tag.punch = get(get(app.PunchearButtonGroup,'SelectedObject'), 'text');

            %get genotype
            key.genotype_name = get(app.GenotypeDropDown, 'value');
            
            %TODO
            key.species = get(get(app.SpeciesTabGroup, 'SelectedTab'), 'Title');
            
            %check DOB
            if strcmp(get(app.DOB_known_switch,'value'),'DOB known')
                key.dob = string(get(app.DOBDatePicker,'value'));
            else
                key.dob = [];
            end
            
            %sex
            key.sex = get(get(app.SexButtonGroup,'SelectedObject'), 'text');
            
            %source and source_id
            key.source = get(app.SourceDropDown,'value');
            key.source_id = get(app.SourceIDEditField, 'value');

            %assign tag
            key_tag.user_name = app.login_user;
            if isempty(key.dob)
               key_tag.date = '1900-01-01'; %arbitrary early date
            else
               key_tag.date = key.dob; %wean date is more exact, but this will do
            end
            
            %assign cage
            key_cage.cage_number = get(app.CageEditField,'value');
            key_cage.room_number = get(app.RoomDropDown, 'value');
            key_cage.user_name = app.login_user;
            if isempty(key.dob)
               key_cage.date = '1900-01-01'; %arbitrary early date
            else
               key_cage.date = key.dob; %wean date is more exact, but this will do
            end
            %key_cage.date = string(datetime(date,'format','yyyy-MM-dd')); %current date
            
            %assign to protocol
            protocol_name = get(app.ProtocolDropDown,'value');
            key_protocol.protocol_name = deblank(strtok(protocol_name, '-'));
            key_protocol.user_name = app.login_user;
            key_protocol.date = string(datetime(date,'format','yyyy-MM-dd')); %current date
          
            %add the animal
            disp('Adding animal with keys:');
            key
            key_tag
            key_cage
            key_protocol
            [inserted, out_txt] = add_animal(key, key_tag, key_cage, key_protocol);
            if inserted
                set(app.StatusLamp_Animal,'color','green');
                pause(2);
                set(app.StatusLamp_Animal,'color','white');
            else
                set(app.StatusLamp_Animal,'color','red');
            end
            set(app.AnimalInsertStatusEdit,'value',out_txt);
        end

        % Button pushed function: AddgenotypetodatabaseButton
        function AddgenotypetodatabaseButtonPushed(app, event)
            key.genotype_name = get(app.GenotypenameEditField, 'value');
            key.source = get(app.SourceEditField, 'value');
            desc = get(app.DescriptionTextArea, 'value');
            desc_flat = strcat(cell2mat(desc'));
            key.description = desc_flat;
            key.catalog_number = get(app.CatalogEditField, 'value');
            try
                insert(sl.Genotype, key);
                disp(['Genotype ' key.genotype_name ' insert successful']);
                set(app.StatusLamp_Genotype,'color','green');
                set(app.GenotypeInsertStatusEdit,'value','Genotype insert successful.')
                pause(2);
                set(app.StatusLamp_Genotype,'color','white');
                app.init_AddGenotypeTab(false);
            catch ME
                disp(['Genotype ' key.genotype_name ' insert failed']);
                set(app.StatusLamp_Genotype,'color','red');
                set(app.GenotypeInsertStatusEdit,'value','Genotype insert failed.')
                %rethrow(ME);
            end
        end

        % Value changed function: RecordBehavior_switch
        function RecordBehavior_switchValueChanged(app, event)
            value = app.RecordBehavior_switch.Value;
            if strcmp(value,'Recorded')
                app.Lamp_isRecorded.Enable = true;
            else
                app.Lamp_isRecorded.Enable = false;
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            value = get(app.ID_type_switch,'value');
            q = [];
            if strcmp(value,'DJ ID')
                v = get(app.Search_DJIDEditField,'value');
                if ischar(v)
                    v = str2double(v);
                end
                if ~isnan(v)
                    q.animal_id = v;
                end
            else
                v = get(app.Search_TagIDEditField,'value');
                if ischar(v)
                    v = str2double(v);
                end
                if ~isnan(v)
                    q.tag_id = v;
                end
            end
            if ~isempty(q)
                app.loadAnimalTable(q)
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            q = [];
            app.loadAnimalTable(q)
        end

        % Cell selection callback: AnimalTable
        function AnimalTableCellSelection(app, event)
            indices = event.Indices;
            selectedRow = indices(1,1);
            
            D = get(app.AnimalTable,'Data');
            Nrows = size(D,1);
            
            %select animal
            app.selected_animal_id = D{selectedRow,1};
            
            %make styles
            curStyle = uistyle('FontColor','red','FontWeight','bold');
            oldStyle = uistyle('FontColor','black','FontWeight','normal');
            
            %add style to this row
            addStyle(app.AnimalTable,curStyle,'row',selectedRow);
            
            %put other rows back to normal
            otherRows = setdiff(1:Nrows,selectedRow);
            for i=1:Nrows-1
                addStyle(app.AnimalTable,oldStyle,'row',otherRows(i));
            end
        end

        % Value changed function: Filter_SexDropDown
        function Filter_SexDropDownValueChanged(app, event)
            value = app.Filter_SexDropDown.Value;
            if strcmp(value,'All')
                app.filter_struct = rmfield(app.filter_struct, 'sex');
            else
                app.filter_struct.sex = value;
            end
            app.loadAnimalTable([]);
        end

        % Value changed function: Filter_GenotypeDropDown
        function Filter_GenotypeDropDownValueChanged(app, event)
            value = app.Filter_GenotypeDropDown.Value;
            if strcmp(value,'All')
                app.filter_struct = rmfield(app.filter_struct, 'genotype_name');
            else
                app.filter_struct.genotype_name = value;
            end
            app.loadAnimalTable([]);
        end

        % Value changed function: Filter_GenotypeStatusDropDown
        function Filter_GenotypeStatusDropDownValueChanged(app, event)
            value = app.Filter_GenotypeStatusDropDown.Value;
            app.filter_genotype_status = value;
            app.loadAnimalTable([]);
        end

        % Value changed function: EyeInjSwitch
        function EyeInjSwitchValueChanged(app, event)
            value = app.EyeInjSwitch.Value;
            if strcmp(value, 'Y')
                app.filter_check_eye_inj = true;
            else
                app.filter_check_eye_inj = false;
            end
            app.loadAnimalTable([]);

        end

        % Value changed function: BehaviorexpSwitch
        function BehaviorexpSwitchValueChanged(app, event)
            value = app.BehaviorexpSwitch.Value;
            if strcmp(value, 'Y')
                app.filter_check_beh = true;
            else
                app.filter_check_beh = false;
            end
            app.loadAnimalTable([]);

        end

        % Value changed function: BraininjSwitch
        function BraininjSwitchValueChanged(app, event)
            value = app.BraininjSwitch.Value;
            if strcmp(value, 'Y')
                app.filter_check_brain_inj = true;
            else
                app.filter_check_brain_inj = false;
            end
            app.loadAnimalTable([]);
        end

        % Value changed function: ID_type_switch
        function ID_type_switchValueChanged(app, event)
            value = app.ID_type_switch.Value;
            if strcmp(value,'DJ ID')
                set(app.Search_DJIDEditField, 'Enable', true);
                set(app.Search_TagIDEditField, 'Enable', false);
            else
                set(app.Search_DJIDEditField, 'Enable', false);
                set(app.Search_TagIDEditField, 'Enable', true);
            end
        end

        % Selection change function: EventTabGroup
        function EventTabGroupSelectionChanged(app, event)
            selectedTab = app.EventTabGroup.SelectedTab;
            selectedTabName = get(selectedTab,'Title');
            if strcmp(selectedTabName, 'Assign cage / room') ...
                    || strcmp(selectedTabName, 'Gave birth') ...
                    || strcmp(selectedTabName, 'Set as breeder') ...
                    || strcmp(selectedTabName, 'Assign to animal protocol') ...
                    || strcmp(selectedTabName, 'Reserve for project') ...
                    || strcmp(selectedTabName, 'Reserve for rig')
                set(app.EventTimeH,'Enable',false);
                set(app.EventTimeM,'Enable',false);
                set(app.EventTimeAMPM,'Enable',false);
            else
                set(app.EventTimeH,'Enable',true);
                set(app.EventTimeM,'Enable',true);
                set(app.EventTimeAMPM,'Enable',true);
            end
            if strcmp(selectedTabName, 'Social behavior session')
                set(app.EventTimeH,'Value','4');
                set(app.EventTimeM,'Value','00');
                set(app.EventTimeAMPM,'Value','PM');     
            else
                set(app.EventTimeH,'Value','9');
                set(app.EventTimeM,'Value','00');
                set(app.EventTimeAMPM,'Value','AM');               
            end
            set(app.EventdateDatePicker,'value',datetime(date,'format','yyyy-MM-dd'))
        end

        % Button pushed function: SubmitevententryButton
        function SubmitevententryButtonPushed(app, event)
            if isempty(app.selected_animal_id)
                disp('No animal selected');
                set(app.StatusLamp_Logger,'color','red');
                set(app.EventInsertStatusEdit,'value','No animal selected');
                return;
            end
            selectedTabName = get(get(app.EventTabGroup, 'SelectedTab'), 'Title');
            key.user_name = get(app.PersonDropDown, 'value');
            key.date = datestr(get(app.EventdateDatePicker, 'value'),'yyyy-mm-dd');
            key.animal_id = app.selected_animal_id;
            notes = get(app.EventNotesTextArea, 'value');
            key.notes = strcat(cell2mat(notes'));
            hrs = str2double(get(app.EventTimeH, 'value'));
            if strcmp(get(app.EventTimeAMPM,'value'), 'PM')
                hrs = rem(hrs, 12) + 12;
            end
            mins = get(app.EventTimeM, 'value');
            key.time = [num2str(hrs,'%02d') ':' num2str(mins,'%02d') ':00'];
            
            switch selectedTabName
                case "Genotyped"
                    key.genotype_status = get(app.GenotypeResultsDropDown, 'value');
                    key
                    [inserted, out_txt] = add_animalEvent(key,'Genotyped');
                case "Deceased"
                    key.cause = get(app.DeceasedReasonDropDown, 'value');
                    key
                    [inserted, out_txt] = add_animalEvent(key,'Deceased');
                case "Assign cage / room"
                    key.cage_number = get(app.TocageEditField, 'value');
                    key.room_number = get(app.RoomDropDown_logger, 'value');
                    key.cause = get(app.MoveReasonDropDown, 'value');
                    key = rmfield(key,'time'); %no time for this one
                    key
                    [inserted, out_txt] = add_animalEvent(key,'AssignCage');
                case "Set as breeder"
                    key = rmfield(key,'time'); %no time for this one
                    [inserted, out_txt] = add_animalEvent(key,'SetAsBreeder');
                case "Assign to animal protocol"
                    key.protocol_name = get(app.AnimalprotocolDropDown,'value');
                    key = rmfield(key,'time'); %no time for this one
                    key
                    [inserted, out_txt] = add_animalEvent(key,'AssignProtocol');
                case "Tag"
                    key = rmfield(key,'time'); %no time for this one
                    key.tag_id = uint32(str2double(get(app.TagEditField, 'value')));
                    key.tag_ear = get(app.TagearDropDown, 'value');
                    key.punch = get(app.EarpunchDropDown, 'value');
                    key
                    [inserted, out_txt] = add_animalEvent(key,'Tag');
                case "IP injection"
                    substance_name = get(app.SubstanceDropDown_IP, 'value');
                    thisSubstance = sl.InjectionSubstance & sprintf('substance_name="%s"',substance_name);
                    key.substance_id = thisSubstance.fetch1('substance_id');  
                    key.concentration = str2double(get(app.IP_inj_ConcentrationEditField, 'value'));
                    key
                    [inserted, out_txt] = add_animalEvent(key,'IPinjection');
                case "Feed"
                    substance_name = get(app.SubstanceDropDown_Feed, 'value');
                    thisSubstance = sl.InjectionSubstance & sprintf('substance_name="%s"',substance_name);
                    key.substance_id = thisSubstance.fetch1('substance_id');  
                    key
                    [inserted, out_txt] = add_animalEvent(key,'Feed');
                case "Eye injection"
                    substance_name = get(app.SubstanceDropDown_Eye, 'value');
                    thisSubstance = sl.InjectionSubstance & sprintf('substance_name="%s"',substance_name);
                    key.substance_id = thisSubstance.fetch1('substance_id');  
                    key.dilution = str2double(get(app.Dilution_EyeEditField, 'value'));
                    key.whichEye = get(app.WhichEyeDropDown, 'value');
                    
                    %add the eyes now if they are not in the DB
                    these_eyes = sl.Eye & sprintf('animal_id=%d', key.animal_id);
                    if these_eyes.count==0
                        eye1.animal_id = key.animal_id;
                        eye1.side = 'Left';
                        eye2.animal_id = key.animal_id;
                        eye2.side = 'Right';
                        insert(sl.Eye, eye1);
                        insert(sl.Eye, eye2);
                    end
                                
                    key
                    [inserted, out_txt] = add_animalEvent(key,'EyeInjection');
                case "Brain injection"
                    substance_name = get(app.SubstanceDropDown_Brain, 'value');
                    thisSubstance = sl.InjectionSubstance & sprintf('substance_name="%s"',substance_name);
                    key.substance_id = thisSubstance.fetch1('substance_id');                    
                    key.dilution = str2double(get(app.Dilution_BrainEditField, 'value'));
                    key.target = get(app.BrainTargetDropDown, 'value');
                    key.hemisphere = get(app.HemisphereDropDown, 'value');
                    key.head_rotation = str2double(get(app.AngleEditField, 'value'));
                    AP = str2double(get(app.APEditField, 'value'));
                    ML = str2double(get(app.MLEditField, 'value'));
                    DV = str2double(get(app.DVEditField, 'value'));
                    key.coordinates = [AP, ML, DV];
                    key
                    [inserted, out_txt] = add_animalEvent(key,'BrainInjection');
                case "Handled"
                    duration_mins = get(app.HandlingDurationEditField, 'value');
                    key.duration = string(duration(minutes(str2double(duration_mins)),'format','hh:mm:ss'));
                    key
                    [inserted, out_txt] = add_animalEvent(key,'Handling');
                case "Social behavior session"
                    key.purpose = get(app.ExperimenttypeDropDown,'value');
                    key.animal_type_name = get(app.TestanimaltypeDropDown,'value');
                    if strcmp(get(app.RecordBehavior_switch, 'value'), 'Recorded')
                        key.recorded = 'T';
                    else
                        key.recorded = 'F';
                    end
                    key.stimArms = {'A', 'B', 'C'};
                    key.stimTypes{1} = get(app.WindowAStim,'value');
                    key.stimTypes{2} = get(app.WindowBStim,'value');
                    key.stimTypes{3} = get(app.WindowCStim,'value');
                    if get(app.AnimalAEditField, 'visible')
                        key.stimIDs(1) = str2double(get(app.AnimalAEditField,'value'));
                    else
                        key.stimIDs(1) = 0;
                    end
                    if get(app.AnimalBEditField, 'visible')
                        key.stimIDs(2) = str2double(get(app.AnimalBEditField,'value'));
                    else
                        key.stimIDs(2) = 0;
                    end
                    if get(app.AnimalCEditField, 'visible')
                        key.stimIDs(3) = str2double(get(app.AnimalCEditField,'value'));
                    else
                        key.stimIDs(3) = 0;
                    end
                    key
                    [inserted, out_txt] = add_animalEvent(key,'SocialBehaviorSession');
                case "Reserve for project"
                    key.project_name = get(app.ProjectDropDown, 'value');
                    key = rmfield(key,'time'); %no time for this one
                    key
                    [inserted, out_txt] = add_animalEvent(key,'ReservedForProject');
                case "Reserve for rig"
                    whichRig = get(app.RignameDropDown, 'value');
                    key = rmfield(key,'time'); %no time for this one
                    if strcmp(whichRig, 'A and B')
                        keyA = key;
                        keyA.rig_name = 'A';
                        keyB = key;
                        keyB.rig_name = 'B';
                        keyA
                        keyB
                        [insertedA, out_txt] = add_animalEvent(keyA,'ReservedForSession');
                        [calEventAddedA, result] = app.addCalendarEvent(keyA, 'reserveForRig');
                        [insertedB, out_txt] = add_animalEvent(keyA,'ReservedForSession');
                        [calEventAddedB, result] = app.addCalendarEvent(keyB, 'reserveForRig');
                        out_txt = [out_txt '; Cal: ' result];
                        inserted = insertedA & calEventAddedA & insertedB & calEventAddedB ;
                    else
                        key
                        key.rig_name = whichRig;
                        [inserted, out_txt] = add_animalEvent(key,'ReservedForSession');
                        [calEventAdded, result] = app.addCalendarEvent(key, 'reserveForRig');
                        out_txt = [out_txt '; Cal: ' result];
                        inserted = inserted & calEventAdded;
                    end
            end
            if inserted
                set(app.StatusLamp_Logger,'color','green');
                %need to reload current table state
                app.loadAnimalTable([]);
                pause(2);
                set(app.StatusLamp_Logger,'color','white');
            else
                set(app.StatusLamp_Logger,'color','red');
            end
            set(app.EventInsertStatusEdit,'value',out_txt);
        end

        % Value changed function: Filter_ProjectDropDown
        function Filter_ProjectDropDownValueChanged(app, event)
            value = app.Filter_ProjectDropDown.Value;
            app.filter_project = value;
            app.loadAnimalTable([]);
        end

        % Value changed function: NotrigreservedSwitch
        function NotrigreservedSwitchValueChanged(app, event)
            value = app.NotrigreservedSwitch.Value;
            if strcmp(value, 'Y')
                app.filter_check_reserved = true;
            else
                app.filter_check_reserved = false;
            end
            app.loadAnimalTable([]);
        end

        % Close request function: AnimalInterfaceUIFigure
        function AnimalInterfaceUIFigureCloseRequest(app, event)
            if app.connection.isConnected
                app.connection.close;
            end
            delete(app)
        end

        % Value changed function: ageLow_edit
        function ageLow_editValueChanged(app, event)
            app.filter_ageLow = str2double(app.ageLow_edit.Value);
            app.loadAnimalTable([]);
        end

        % Value changed function: ageHigh_edit
        function ageHigh_editValueChanged(app, event)
            app.filter_ageHigh = str2double(app.ageHigh_edit.Value);
            app.loadAnimalTable([]);
        end

        % Value changed function: GenotypesindatabaseListBox
        function GenotypesindatabaseListBoxValueChanged(app, event)
            value = app.GenotypesindatabaseListBox.Value;
            thisGenotype = fetch(sl.Genotype & sprintf('genotype_name="%s"',value), '*');
            set(app.GenotypenameEditField, 'value', thisGenotype.genotype_name);
            set(app.DescriptionTextArea, 'value', thisGenotype.description);
            set(app.SourceEditField, 'value', thisGenotype.source);
            set(app.CatalogEditField, 'value', thisGenotype.catalog_number);
        end

        % Button pushed function: AddsubstancetodatabaseButton
        function AddsubstancetodatabaseButtonPushed(app, event)
            key.substance_name = get(app.SubstancenameEditField, 'value');
            key.source = get(app.SourceEditField_substance, 'value');
            desc = get(app.DescriptionTextArea_substance, 'value');
            desc_flat = strcat(cell2mat(desc'));
            key.description = desc_flat;
            location = get(app.StorageLocationTextArea_substance, 'value');
            location_flat = strcat(cell2mat(location'));
            key.storage_location = location_flat;
            key.catalog_number = get(app.CatalogEditField_substance, 'value');
            try
                insert(sl.InjectionSubstance, key);
                disp(['Substance ' key.substance_name ' insert successful']);
                set(app.StatusLamp_Substance,'color','green');
                set(app.SubstanceInsertStatusEdit,'value','Substance insert successful.')
                pause(2);
                set(app.StatusLamp_Substance,'color','white');
                app.init_AddSubstanceTab(false);
            catch
                disp(['Substance ' key.substance_name ' insert failed']);
                set(app.StatusLamp_Substance,'color','red');
                set(app.SubstanceInsertStatusEdit,'value','Substance insert failed.')
                app.init_AddSubstanceTab(false);
                %rethrow(ME);
            end
        end

        % Value changed function: SubstancesindatabaseListBox
        function SubstancesindatabaseListBoxValueChanged(app, event)
            value = app.SubstancesindatabaseListBox.Value;
            thisSubstance = fetch(sl.InjectionSubstance & sprintf('substance_name="%s"',value), '*');
            set(app.SubstancenameEditField, 'value', thisSubstance.substance_name);
            set(app.DescriptionTextArea_substance, 'value', thisSubstance.description);
            set(app.SourceEditField_substance, 'value', thisSubstance.source);
            set(app.CatalogEditField_substance, 'value', thisSubstance.catalog_number);
            set(app.StorageLocationTextArea_substance, 'value', thisSubstance.storage_location);
        end

        % Value changed function: BrainareasindatabaseListBox
        function BrainareasindatabaseListBoxValueChanged(app, event)
            value = app.BrainareasindatabaseListBox.Value;
            thisArea = fetch(sl.BrainArea & sprintf('target="%s"',value), '*');
            set(app.AbbreviationEditField, 'value', thisArea.target);
            set(app.BrainLongNameTextArea, 'value', thisArea.long_name);
        end

        % Button pushed function: AddbrainareatodatabaseButton
        function AddbrainareatodatabaseButtonPushed(app, event)
            key.target = get(app.AbbreviationEditField, 'value');
            long_text = get(app.BrainLongNameTextArea, 'value');
            long_text_flat = strcat(cell2mat(long_text'));
            key.long_name = long_text_flat;
            try
                insert(sl.BrainArea, key);
                disp(['Brain area ' key.target ' insert successful']);
                set(app.StatusLamp_BrainArea,'color','green');
                set(app.BrainAreaInsertStatusEdit,'value','Brain area insert successful.')
                pause(2);
                set(app.StatusLamp_BrainArea,'color','white');
                app.init_AddBrainAreaTab(false);
            catch ME
                disp(['Brain area ' key.target ' insert failed']);
                set(app.StatusLamp_BrainArea,'color','red');
                set(app.BrainAreaInsertStatusEdit,'value','Brain area insert failed.')
                %rethrow(ME);
            end

        end

        % Button pushed function: AlleventsforselectedanimalButton
        function AlleventsforselectedanimalButtonPushed(app, event)
            if isempty(app.selected_animal_id)
                disp('cannot show log: no animal selected');
                return;
            end
            events = sl.AnimalEvent.all & sprintf('animal_id=%d',app.selected_animal_id);
            eventOrder = app.sortEvents(events);
            eventStruct = fetch(events,'*');
            L = length(eventStruct);
            logText = cell(L,1);
            for i=1:L
                thisType = eventStruct(eventOrder(i)).event_type;
                thisID = eventStruct(eventOrder(i)).event_id;
                ev = feval(sprintf('sl.AnimalEvent%s',thisType)) & sprintf('event_id=%d',thisID);
                logText{i} = ev.printEvents;
            end
            set(app.LogTextArea,'value', logText);
            app.MainTabGroup.SelectedTab = app.LogTab;
        end

        % Button pushed function: EventsforallanimalsButton
        function EventsforallanimalsButtonPushed(app, event)
            selectedEventType = get(app.EventTypeDropDown,'value');
            switch selectedEventType
                case 'Genotyped'
                    eventType = 'Genotyped';
                case 'Deceased'
                    eventType = 'Deceased';
                case 'Gave birth'
                    eventType = 'GaveBirth';
                case 'Assign cage'
                    eventType = 'AssignCage';
                case 'IP injection'
                    eventType = 'IPinjection';
                case 'Eye injection'
                    eventType = 'EyeInjection';
                case 'Feed'
                    eventType = 'Feed';
                case 'Brain injection'
                    eventType = 'BrainInjection';
                case 'Handled'
                    eventType = 'Handling';
                case 'Social behavior session'
                    eventType = 'SocialBehaviorSession';
                case 'Reserve for project'
                    eventType = 'ReservedForProject';
                case 'Reserve for rig'
                    eventType = 'ReservedForSession';
            end
            D = get(app.AnimalTable,'data');
            inTableIDs = num2cell(D.DJ_ID);
            events = sl.AnimalEvent.all & struct('animal_id', inTableIDs) & sprintf('event_type="%s"',eventType);
            eventOrder = app.sortEvents(events);
            eventStruct = fetch(events,'*');
            L = length(eventStruct);
            logText = cell(L,1);
            for i=1:L
                thisID = eventStruct(eventOrder(i)).event_id;
                ev = feval(sprintf('sl.AnimalEvent%s',eventType)) & sprintf('event_id=%d',thisID);
                logText{i} = ev.printEvents;
            end
            set(app.LogTextArea,'value', logText);
            app.MainTabGroup.SelectedTab = app.LogTab;
        end

        % Button pushed function: ReauthorizeButton
        function ReauthorizeButtonPushed(app, event)
            auth_code = get(app.CalendarrefreshtokenEditField, 'value');
            try
                app.refresh_response = webwrite(...
                    'https://oauth2.googleapis.com/token',...
                    'grant_type','authorization_code',...
                    'client_id','667407419363-59ebnl9973ie08ui05fh1b8go1fvpbsn.apps.googleusercontent.com',...
                    'client_secret','dLydGGCoNOaMD1bA8HQFADqL',...
                    'code',auth_code,...
                    'redirect_uri','urn:ietf:wg:oauth:2.0:oob'...
                    );
                fid = fopen([app.calDir filesep 'gcal_access.txt'], 'w');
                fprintf(fid, '%s\n', refresh_response.access_token);
                fprintf(fid, '%s\n', refresh_response.refresh_token);
                fclose(fid);
                dips('Calendar reauthorization success');
            catch
                disp('Calendar reauthorization failed');
            end
        end

        % Button pushed function: SubmitevententryButton_breeders
        function SubmitevententryButton_breedersPushed(app, event)
            %             if isempty(app.selected_animal_id)
            %                 disp('No animal selected');
            %                 set(app.StatusLamp_Logger,'color','red');
            %                 set(app.EventInsertStatusEdit,'value','No animal selected');
            %                 return;
            %             end
            selectedTabName = get(get(app.EventTabGroup_breeders, 'SelectedTab'), 'Title');
            key.user_name = get(app.PersonDropDown_breeders, 'value');
            key.date = datestr(get(app.EventdateDatePicker_breeders, 'value'),'yyyy-mm-dd');
            notes = get(app.EventNotesTextArea_breeders, 'value');
            key.notes = strcat(cell2mat(notes'));
            %key.animal_id = app.selected_animal_id; %need to set 2 IDs
            switch selectedTabName
                case "Pair breeders"
                    [selected_ids, selected_sex] = app.getUnpairedBreederTableSelection;
                    validSelection = false;                    
                    if length(selected_ids) == 2
                        if strcmp(selected_sex(1),'Male')
                            if strcmp(selected_sex(2),'Female')
                                key.male_id = selected_ids(1);
                                key.female_id = selected_ids(2);                                
                                validSelection = true;
                            end
                        elseif strcmp(selected_sex(1),'Female')
                            if strcmp(selected_sex(2),'Male')
                                key.male_id = selected_ids(2);
                                key.female_id = selected_ids(1);
                                validSelection = true;
                            end
                        end                            
                    end
                    if ~validSelection
                        inserted = false;
                        out_txt = 'need to select a male and a female in unpaired breeders table';
                    else
                        key.cage_number = get(app.NewbreedercageEditField, 'value');
                        if isempty(key.cage_number)
                            inserted = false;
                             out_txt = 'need to enter a cage number for breeders';
                        else
                            key.room_number = get(app.RoomDropDown_breeders, 'value');
                            key
                            [inserted, out_txt] = add_animalEvent(key,'PairBreeders');
                        end
                    end
                case "Log birth"
                    key.cage_number = app.selected_breeder_cage;
                    key.number_of_pups = str2double(get(app.NumberofpupsEditField, 'value'));
                    
                    D = get(app.selectedFemaleTable,'Data');
                    if ~isempty(D)
                        key.animal_id = D.DJ_ID(1);
                        key
                        [inserted, out_txt] = add_animalEvent(key,'GaveBirth');
                        %add calendar event for weaning
                        weanDate = datetime(key.date) + days(21);
                        weanKey.date = datestr(weanDate,'yyyy-mm-dd');
                        weanKey.cage_number = key.cage_number;
                        [calEventAdded, result] = app.addCalendarEvent(weanKey, 'weaning');
                        out_txt = [out_txt '; Cal: ' result];
                        inserted = inserted & calEventAdded;
                    else
                        inserted = false;
                        out_txt = 'need to select a breeding cage';
                    end
                case "Log weaning"
                    if ~isempty(app.selected_breeder_cage)
                        key.cage_number = app.selected_breeder_cage;
                        key.number_of_pups = str2double(get(app.NumberofpupsEditField_weaned, 'value'));
                        key
                        [inserted, out_txt] = add_animalEvent(key,'Weaned');
                    else
                        inserted = false;
                        out_txt = 'need to select a breeding cage';
                    end
                case "Separate breeding pair"
                    if ~isempty(app.selected_breeder_cage)
                        key.cage_number = app.selected_breeder_cage;
                        key.new_cage_male = get(app.NewcageformaleEditField, 'value');
                        key.new_room_male = get(app.RoomDropDown_breeders_sepMale, 'value');
                        key.new_cage_female = get(app.NewcageforfemaleEditField, 'value');
                        key.new_room_female = get(app.RoomDropDown_breeders_sepFemale, 'value');
                        
                        D = get(app.selectedMaleTable,'Data');
                        key.male_id = D.DJ_ID(1);
                        D = get(app.selectedFemaleTable,'Data');
                        key.female_id = D.DJ_ID(1);
                        
                        key
                        [inserted, out_txt] = add_animalEvent(key,'SeparateBreeders');
                    else
                        inserted = false;
                        out_txt = 'need to select a breeding cage';
                    end
                case "Retire breeder"
                    selected_ids = app.getUnpairedBreederTableSelection;                    
                    if length(selected_ids) == 1                     
                        key.animal_id = selected_ids;
                        key
                        [inserted, out_txt] = add_animalEvent(key,'RetireAsBreeder');
                    else
                        inserted = false;
                        out_txt = 'need to select a single unpaired breeder';
                    end
                case "Sacrifice breeding pair"
                    if ~isempty(app.selected_breeder_cage)
                        key_sep = key;
                        
                        key_sep.cage_number = app.selected_breeder_cage;
                        D = get(app.selectedMaleTable,'Data');
                        male_id = D.DJ_ID(1);
                        key_sep.male_id = male_id;
                        D = get(app.selectedFemaleTable,'Data');
                        female_id = D.DJ_ID(1);
                        room = sl.Animal.roomNumber(male_id).room_number;
                        key_sep.female_id = female_id;

                        key_sep.new_cage_male = strcat('M', key_sep.cage_number);
                        key_sep.new_cage_female = strcat('F', key_sep.cage_number);
                        key_sep.new_room_male = room;
                        key_sep.new_room_female = room;
                        
                        key_sep
                        [inserted, out_txt] = add_animalEvent(key_sep,'SeparateBreeders');
                        
                        key_retire = key;
                        key_retire.animal_id = male_id;
                        key_retire
                        [inserted, out_txt] = add_animalEvent(key_retire,'RetireAsBreeder');
                        key_retire.animal_id = female_id;
                        key_retire
                        [inserted, out_txt] = add_animalEvent(key_retire,'RetireAsBreeder');
                        
                        key_sac = key;
                        key_sac.cause = 'sacrificed not needed';
                        key_sac.notes = 'retired breeder';
                        key_sac.animal_id = male_id;
                        key_sac
                        [inserted, out_txt] = add_animalEvent(key_sac,'Deceased');
                        key_sac.animal_id = female_id;
                        key_sac
                        [inserted, out_txt] = add_animalEvent(key_sac,'Deceased');
                        
                    else
                        inserted = false;
                        out_txt = 'need to select a breeding cage';
                    end
            end
            if inserted
                set(app.StatusLamp_breeders,'color','green');
                %need to reload current table state
                app.loadUnpairedBreedersTable();
                app.loadBreedingCagesTable();
                pause(2);
                set(app.StatusLamp_breeders,'color','white');
            else
                set(app.StatusLamp_breeders,'color','red');
            end
            set(app.EventInsertStatusEdit_breeders,'value',out_txt);
        end

        % Cell selection callback: BreederCagesTable
        function BreederCagesTableCellSelection(app, event)
            indices = event.Indices;
            selectedRow = indices(1,1);
            
            D = get(app.BreederCagesTable,'Data');
            Nrows = size(D,1);
            
            %select animal
            app.selected_breeder_cage = D{selectedRow,1};
            
            %make styles
            curStyle = uistyle('FontColor','red','FontWeight','bold');
            oldStyle = uistyle('FontColor','black','FontWeight','normal');
            
            %add style to this row
            addStyle(app.BreederCagesTable,curStyle,'row',selectedRow);
            
            %put other rows back to normal
            otherRows = setdiff(1:Nrows,selectedRow);
            for i=1:Nrows-1
                addStyle(app.BreederCagesTable,oldStyle,'row',otherRows(i));
            end
            
            app.loadMaleAndFemaleTables();
            
        end

        % Button pushed function: ExporttoExcelButton_breeders
        function ExporttoExcelButton_breedersPushed(app, event)
            %get breeder table
            D = get(app.BreederCagesTable,'Data');
            
            Ncages = height(D);
            
            %Add extra columns
            T_male = table('Size',[1,4],'VariableTypes', ...
                {'uint32',...   #1 DJ ID
                'uint32', ...   #2 tag ID
                'string',...    #5 genotype status
                'singlenan',... #6 age
                },...
                'VariableNames', ...
                {'Male_DJ_ID',...
                'Male_Tag_ID', ...
                'Male_Genotype_status',...
                'Male_Age',...
                });
            
            T_female = table('Size',[1,4],'VariableTypes', ...
                {'uint32',...   #1 DJ ID
                'uint32', ...   #2 tag ID
                'string',...    #5 genotype status
                'singlenan',... #6 age
                },...
                'VariableNames', ...
                {'Female_DJ_ID',...
                'Female_Tag_ID', ...
                'Female_Genotype_status',...
                'Female_Age',...
                });
                        
            for i=1:Ncages
                thisCage = sl.BreedingCage & sprintf('cage_number="%s"', D.Cage_number(i));
                male = thisCage.getMember('Male');
                female = thisCage.getMember('Female');                
                male_id = fetch1(male,'animal_id');
                female_id = fetch1(female,'animal_id');
          
                tagID = sl.Animal.tagNumber(male_id);
                if isempty(tagID)
                    male_tag = nan;
                else
                    male_tag = tagID.tag_id;
                end
                
                tagID = sl.Animal.tagNumber(female_id);
                if isempty(tagID)
                    female_tag = nan;
                else
                    female_tag = tagID.tag_id;
                end
                
                male_age = sl.Animal.age(male_id);
                female_age = sl.Animal.age(male_id);
                male_gstatus = sl.Animal.genotypeStatus(male_id);
                female_gstatus = sl.Animal.genotypeStatus(female_id);
                
                T_male.Male_DJ_ID(i) = male_id;
                T_female.Female_DJ_ID(i) = female_id;
                T_male.Male_Tag_ID(i) = male_tag;
                T_female.Female_Tag_ID(i) = female_tag;
                T_male.Male_Age(i) = calweeks(male_age.age);
                T_female.Female_Age(i) = calweeks(female_age.age);
                if ~isempty(male_gstatus)
                    T_male.Male_Genotype_status(i) = male_gstatus.genotype_status;
                else
                    T_male.Male_Genotype_status(i) = '';
                end
                if ~isempty(female_gstatus)
                    T_female.Female_Genotype_status(i) = female_gstatus.genotype_status;
                else
                    T_female.Female_Genotype_status(i) = '';
                end
                
            end
            
            breederTable = [D, T_male, T_female];    
            
            curDate = string(datetime("today"), 'yyyy-MM-dd');
            defname = sprintf('%s-breederTable.xls', curDate);
            [fname, fpath] = uiputfile('*','Select table file location',defname);                       
            writetable(breederTable,[fpath fname]);
        end

        % Button pushed function: ExporttoExcelButton
        function ExporttoExcelButtonPushed(app, event)
            %get current table
            D = get(app.AnimalTable,'Data');           
            curDate = string(datetime("today"), 'yyyy-MM-dd');
            defname = sprintf('%s_%s_animalTable.xls', curDate, app.login_user);
            [fname, fpath] = uiputfile('*','Select table file location',defname);
            writetable(D,[fpath, fname]);                               
        end

        % Button pushed function: ExporttotextfileButton
        function ExporttotextfileButtonPushed(app, event)
            logText = get(app.LogTextArea, 'value');
            Nlines = length(logText);
            
            curTime = string(datetime("now"), 'yyyy-MM-dd_hh-mm');
            defname = sprintf('eventLog_%s_%s.txt', curTime, app.login_user);
            [fname, fpath] = uiputfile('*','Select log file location',defname);            
            fid = fopen([fpath fname],'w');
            
            for i=1:Nlines
                fprintf(fid, [logText{i} '\n']);
            end
            
            fclose(fid);

        end

        % Callback function
        function NumberofentriesSpinnerValueChanged(app, event)
            app.updateAnimalEntryVisibility();
        end

        % Button pushed function: SetotherentriesButton
        function SetotherentriesButtonPushed(app, event)
            genotype_E1 = get(app.GenotypeDropDown_E1,'Value');
            DOB_E1 = get(app.DOBDatePicker_E1,'Value');
            tagID_E1_str = get(app.TagIDEditField_E1,'Value');
            cage_num_E1 = get(app.CageEditField_E1, 'Value');
            room_num_E1 = get(app.RoomDropDown_E1, 'Value');            
            source_E1 = get(app.SourceDropDown_E1, 'Value');
            sourceID_E1 = get(app.SourceIDEditField_E1, 'Value');
            protocol_E1 = get(app.ProtocolDropDown_E1, 'Value');
            sex_E1 = get(get(app.SexButtonGroup_E1,'SelectedObject'), 'text');
            punch_ear_E1 = get(get(app.PunchearButtonGroup_E1,'SelectedObject'), 'text');
            tag_ear_E1 = get(get(app.TagearButtonGroup_E1,'SelectedObject'), 'text');
            tagged_E1 = get(app.tagged_switch_E1,'Value'); 
            DOB_known_E1 = get(app.DOB_known_switch_E1,'Value'); 
            
            tagID_E1 = str2double(tagID_E1_str);
            
            %N_visible_entries = get(app.NumberofentriesSpinner, 'Value');
            N_visible_entries = get(app.NumberofentriesSlider, 'Value');
            for e=2:N_visible_entries
                set(app.(['GenotypeDropDown_E' num2str(e)]),'Value',genotype_E1);
                set(app.(['DOBDatePicker_E' num2str(e)]),'Value',DOB_E1);
                set(app.(['CageEditField_E' num2str(e)]),'Value',cage_num_E1);
                set(app.(['RoomDropDown_E' num2str(e)]),'Value',room_num_E1);
                set(app.(['SourceDropDown_E' num2str(e)]),'Value',source_E1);
                set(app.(['SourceIDEditField_E' num2str(e)]),'Value',sourceID_E1);
                set(app.(['ProtocolDropDown_E' num2str(e)]),'Value',protocol_E1);
                set(app.(['tagged_switch_E' num2str(e)]),'Value',tagged_E1);
                set(app.(['DOB_known_switch_E' num2str(e)]),'Value',DOB_known_E1);                
                set(app.(['TagIDEditField_E' num2str(e)]),'Value',num2str(tagID_E1+e-1));
                app.setRadioButtonToValue(app.(['SexButtonGroup_E' num2str(e)]), sex_E1);
                app.setRadioButtonToValue(app.(['PunchearButtonGroup_E' num2str(e)]), punch_ear_E1);
                app.setRadioButtonToValue(app.(['TagearButtonGroup_E' num2str(e)]), tag_ear_E1);
            end                        
        end

        % Button pushed function: AddmultipleanimalstodatabaseButton
        function AddmultipleanimalstodatabaseButtonPushed(app, event)
            %N_visible_entries = get(app.NumberofentriesSpinner, 'Value');
            N_visible_entries = get(app.NumberofentriesSlider, 'Value');

            for e=1:N_visible_entries
                %make insert key
                key = struct;
                key_tag = struct; 
                
                %check isTagged
                if strcmp(get(app.(['tagged_switch_E' num2str(e)]),'value'),'Untagged')
                    key_tag.tag_ear = 'None';
                else
                    key_tag.tag_id = uint32(str2double(get(app.(['TagIDEditField_E' num2str(e)]),'value')));
                    key_tag.tag_ear = get(get(app.TagearButtonGroup,'SelectedObject'), 'text');
                end
                %ear punch
                key_tag.punch = get(get(app.(['PunchearButtonGroup_E' num2str(e)]),'SelectedObject'), 'text');
                
                %get genotype
                key.genotype_name = get(app.(['GenotypeDropDown_E' num2str(e)]), 'value');
                
                %TODO
                key.species = get(get(app.SpeciesTabGroup, 'SelectedTab'), 'Title');
                
                %check DOB
                if strcmp(get(app.(['DOB_known_switch_E' num2str(e)]),'value'),'DOB known')
                    key.dob = string(get(app.(['DOBDatePicker_E' num2str(e)]),'value'));
                else
                    key.dob = [];
                end
                
                %sex
                key.sex = get(get(app.(['SexButtonGroup_E' num2str(e)]),'SelectedObject'), 'text');
                
                %source and source ID
                key.source = get(app.(['SourceDropDown_E' num2str(e)]),'value');
                key.source_id = get(app.(['SourceIDEditField_E' num2str(e)]),'value');
                
                %assign tag
                if isempty(key.dob)
                    key_tag.date = '1900-01-01'; %arbitrary early date
                else
                    key_tag.date = key.dob; %wean date is more exact, but this will do
                end
                key_tag.user_name = app.login_user;
                
                %assign cage
                key_cage.cage_number = get(app.(['CageEditField_E' num2str(e)]),'value');
                key_cage.room_number = get(app.(['RoomDropDown_E' num2str(e)]), 'value');
                key_cage.user_name = app.login_user;
                if isempty(key.dob)
                    key_cage.date = '1900-01-01'; %arbitrary early date
                else
                    key_cage.date = key.dob; %wean date is more exact, but this will do
                end
                
                %assign to protocol
                protocol_name = get(app.(['ProtocolDropDown_E' num2str(e)]),'value');
                key_protocol.protocol_name = deblank(strtok(protocol_name, '-'));
                key_protocol.user_name = app.login_user;
                key_protocol.date = string(datetime(date,'format','yyyy-MM-dd')); %current date
                
                key_tagVec(e) = key_tag;
                key_protocolVec(e) = key_protocol;
                key_cageVec(e) = key_cage;
                keyVec(e) = key;
                
            end
            disp(['Adding ' num2str(N_visible_entries) ' animals']);    
            %add the animals
            [inserted, out_txt] = add_animals(keyVec, key_tagVec, key_cageVec, key_protocolVec);
            
            if inserted
                set(app.StatusLamp_Animal,'color','green');
                pause(2);
                set(app.StatusLamp_Animal,'color','white');
            else
                set(app.StatusLamp_Animal,'color','red');
            end
            set(app.AnimalInsertStatusEdit,'value',out_txt);
        end

        % Button pushed function: AddprojecttodatabaseButton
        function AddprojecttodatabaseButtonPushed(app, event)
            key.project_name = get(app.ProjectNameEditField, 'value');
            desc_text = get(app.ProjectDescriptionTextArea, 'value');
            desc_text_flat = strcat(cell2mat(desc_text'));
            key.description = desc_text_flat;
            try
                insert(sl.Project, key);
                disp(['Project ' key.project_name ' insert successful']);
                set(app.StatusLamp_Project,'color','green');
                set(app.ProjectInsertStatusEdit,'value','Project insert successful.')
                pause(2);
                set(app.StatusLamp_Project,'color','white');
                app.init_AddProjectTab(false);
            catch ME
                disp(['Project ' key.project_name ' insert failed']);
                set(app.StatusLamp_Project,'color','red');
                set(app.StatusLamp_Project,'value','Project insert failed.')
                %rethrow(ME);
            end
        end

        % Value changed function: ProjectsindatabaseListBox
        function ProjectsindatabaseListBoxValueChanged(app, event)
            value = app.ProjectsindatabaseListBox.Value;
            thisProject = fetch(sl.Project & sprintf('project_name="%s"',value), '*');
            set(app.ProjectNameEditField, 'value', thisProject.project_name);
            set(app.ProjectDescriptionTextArea, 'value', thisProject.description);
        end

        % Value changed function: 
        % BehaviorexperimenttypesindatabaseListBox
        function BehaviorexperimenttypesindatabaseListBoxValueChanged(app, event)
            value = app.BehaviorexperimenttypesindatabaseListBox.Value;
            thisExp = fetch(sl.SocialBehaviorExperimentType & sprintf('purpose="%s"',value), '*');
            set(app.BehaviorExpNameEditField, 'value', thisExp.purpose);
            set(app.BehaviorExpDescriptionTextArea, 'value', thisExp.description);
        end

        % Button pushed function: AddexperimenttypetodatabaseButton
        function AddexperimenttypetodatabaseButtonPushed(app, event)
            key.purpose = get(app.BehaviorExpNameEditField, 'value');
            desc_text = get(app.BehaviorExpDescriptionTextArea, 'value');
            desc_text_flat = strcat(cell2mat(desc_text'));
            key.description = desc_text_flat;
            try
                insert(sl.SocialBehaviorExperimentType, key);
                disp(['Experiment ' key.purpose ' insert successful']);
                set(app.StatusLamp_BehaviorExp,'color','green');
                set(app.BehaviorExpInsertStatusEdit,'value','Experiment insert successful.')
                pause(2);
                set(app.StatusLamp_BehaviorExp,'color','white');
                app.init_AddProjectTab(false);
            catch ME
                disp(['Experiment ' key.purpose ' insert failed']);
                set(app.StatusLamp_BehaviorExp,'color','red');
                set(app.StatusLamp_BehaviorExp,'value','Experiment insert failed.')
                %rethrow(ME);
            end
        end

        % Value changing function: NumberofentriesSlider
        function NumberofentriesSliderValueChanging(app, event)
            changingValue = event.Value;
            set(app.NumberofentriesSlider, 'Value', round(changingValue));
        end

        % Value changed function: NumberofentriesSlider
        function NumberofentriesSliderValueChanged(app, event)
            value = app.NumberofentriesSlider.Value;
            set(app.NumberofentriesSlider, 'Value', round(value));
            app.updateAnimalEntryVisibility();
        end

        % Button pushed function: SetsourceIDButton
        function SetsourceIDButtonPushed(app, event)
            selectedCage = get(app.BreedingcagesindatabaseDropDown, 'value');
            set(app.SourceIDEditField, 'Value', selectedCage);
            set(app.SourceIDEditField_E1, 'Value', selectedCage);
        end

        % Value changed function: NotprojreservedSwitch
        function NotprojreservedSwitchValueChanged(app, event)
            value = app.NotprojreservedSwitch.Value;
            if strcmp(value, 'Y')
                app.filter_check_project_reserved = true;
            else
                app.filter_check_project_reserved = false;
            end
            app.loadAnimalTable([]);
        end

        % Button pushed function: DeleteAnimal_button
        function DeleteAnimal_buttonButtonPushed(app, event)
            DJID = get(app.DJIDEditField_deleteAnimal,'value');
            thisAnimal = sl.Animal & sprintf('animal_id=%d',DJID);
            if thisAnimal.exists
                disp(['Deleting animal ' num2str(DJID)]);
                del(thisAnimal);
                set(app.StatusLamp_deleteAnimal,'color','green');
                pause(2);
                set(app.StatusLamp_deleteAnimal,'color','white');
                out_txt = ['Animal ' num2str(DJID) ' deleted'];
            else
                disp(['DJID: ' num2str(DJID) ' not found']);
                set(app.StatusLamp_deleteAnimal,'color','red');
                out_txt = ['Animal ' num2str(DJID) ' not found'];
            end
            set(app.EventInsertStatusEdit_deleteAnimal,'value',out_txt);
                
        end

        % Value changed function: WindowAStim
        function WindowAStimValueChanged(app, event)
            value = app.WindowAStim.Value;
            needs_id = strcmp(fetch1(sl.BehaviorVisualStimulusType & sprintf('stim_type="%s"', value), 'needs_id'), 'T');
            if needs_id
               set(app.AnimalAEditField,'visible',true);
            else
               set(app.AnimalAEditField,'visible',false);
            end
        end

        % Value changed function: WindowBStim
        function WindowBStimValueChanged(app, event)
            value = app.WindowBStim.Value;
            needs_id = strcmp(fetch1(sl.BehaviorVisualStimulusType & sprintf('stim_type="%s"', value), 'needs_id'), 'T');
            if needs_id
               set(app.AnimalBEditField,'visible',true);
            else
               set(app.AnimalBEditField,'visible',false);
            end
        end

        % Value changed function: WindowCStim
        function WindowCStimValueChanged(app, event)
            value = app.WindowCStim.Value;
            needs_id = strcmp(fetch1(sl.BehaviorVisualStimulusType & sprintf('stim_type="%s"', value), 'needs_id'), 'T');
            if needs_id
               set(app.AnimalCEditField,'visible',true);
            else
               set(app.AnimalCEditField,'visible',false);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnimalInterfaceUIFigure = uifigure('Visible', 'off');
            app.AnimalInterfaceUIFigure.Name = 'AnimalInterface';
            app.AnimalInterfaceUIFigure.CloseRequestFcn = createCallbackFcn(app, @AnimalInterfaceUIFigureCloseRequest, true);
            app.MainTabGroup = uitabgroup(app.AnimalInterfaceUIFigure);
            app.MainTabGroup.SelectionChangedFcn = createCallbackFcn(app, @MainTabGroupSelectionChanged, true);
            app.LoginTab = uitab(app.MainTabGroup);
            app.LoginTab.Title = 'Login';
            app.PasswordEditFieldLabel = uilabel(app.LoginTab);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.LoginTab, 'text');
            app.PasswordEditField.ValueChangingFcn = createCallbackFcn(app, @PasswordEditFieldValueChanging, true);
            app.LoginButton = uibutton(app.LoginTab, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.UserEditFieldLabel = uilabel(app.LoginTab);
            app.UserEditFieldLabel.Text = 'User';
            app.LoginUserEditField = uieditfield(app.LoginTab, 'text');
            app.AnimalLoggerTab = uitab(app.MainTabGroup);
            app.AnimalLoggerTab.Title = 'AnimalLogger';
            app.AnimalTable = uitable(app.AnimalLoggerTab);
            app.AnimalTable.ColumnName = {'DJ ID'; 'Tag ID'; 'Cage'; 'Room'; 'Genotype'; 'Genotype status'; 'IP inj.'; 'Sex'; 'Age'; 'Eye inj.'; 'Brain inj.'; 'Behavior'; 'Res. for rig'; 'Project'};
            app.AnimalTable.RowName = {};
            app.AnimalTable.ColumnSortable = [true true true false true true true true true true true true true true];
            app.AnimalTable.CellSelectionCallback = createCallbackFcn(app, @AnimalTableCellSelection, true);
            app.EventTabGroup = uitabgroup(app.AnimalLoggerTab);
            app.EventTabGroup.SelectionChangedFcn = createCallbackFcn(app, @EventTabGroupSelectionChanged, true);
            app.GenotypedTab = uitab(app.EventTabGroup);
            app.GenotypedTab.Title = 'Genotyped';
            app.ResultsDropDownLabel = uilabel(app.GenotypedTab);
            app.ResultsDropDownLabel.Text = 'Results';
            app.GenotypeResultsDropDown = uidropdown(app.GenotypedTab);
            app.GenotypeResultsDropDown.Items = {'Carrier', 'Non-carrier', 'Unknown', 'Het', 'homo'};
            app.GenotypeResultsDropDown.Value = 'Carrier';
            app.DeceasedTab = uitab(app.EventTabGroup);
            app.DeceasedTab.Title = 'Deceased';
            app.ReasonDropDownLabel = uilabel(app.DeceasedTab);
            app.ReasonDropDownLabel.Text = 'Reason';
            app.DeceasedReasonDropDown = uidropdown(app.DeceasedTab);
            app.DeceasedReasonDropDown.Items = {'sacrificed for experiment', 'sacrificed not needed', 'other', 'unknown'};
            app.DeceasedReasonDropDown.Value = 'sacrificed for experiment';
            app.SetasbreederTab = uitab(app.EventTabGroup);
            app.SetasbreederTab.Title = 'Set as breeder';
            app.AssigncageroomTab = uitab(app.EventTabGroup);
            app.AssigncageroomTab.Title = 'Assign cage / room';
            app.ReasonDropDown_2Label = uilabel(app.AssigncageroomTab);
            app.ReasonDropDown_2Label.Text = 'Reason';
            app.MoveReasonDropDown = uidropdown(app.AssigncageroomTab);
            app.MoveReasonDropDown.Items = {'weaning', 'experiment', 'set as breeder', 'crowding', 'cage moved rooms', 'other', 'unknown'};
            app.MoveReasonDropDown.Value = 'weaning';
            app.TocageEditFieldLabel = uilabel(app.AssigncageroomTab);
            app.TocageEditFieldLabel.Text = 'To cage #';
            app.TocageEditField = uieditfield(app.AssigncageroomTab, 'text');
            app.RoomDropDown_2Label = uilabel(app.AssigncageroomTab);
            app.RoomDropDown_2Label.Text = 'Room';
            app.RoomDropDown_logger = uidropdown(app.AssigncageroomTab);
            app.AssigntoanimalprotocolTab = uitab(app.EventTabGroup);
            app.AssigntoanimalprotocolTab.Title = 'Assign to animal protocol';
            app.AnimalprotocolDropDownLabel = uilabel(app.AssigntoanimalprotocolTab);
            app.AnimalprotocolDropDownLabel.Text = 'Animal protocol';
            app.AnimalprotocolDropDown = uidropdown(app.AssigntoanimalprotocolTab);
            app.TagTab = uitab(app.EventTabGroup);
            app.TagTab.Title = 'Tag';
            app.TagEditFieldLabel = uilabel(app.TagTab);
            app.TagEditFieldLabel.Text = 'Tag #';
            app.TagEditField = uieditfield(app.TagTab, 'text');
            app.EarpunchDropDownLabel = uilabel(app.TagTab);
            app.EarpunchDropDownLabel.Text = 'Ear punch';
            app.EarpunchDropDown = uidropdown(app.TagTab);
            app.EarpunchDropDown.Items = {'None', 'L', 'R', 'Both'};
            app.EarpunchDropDown.Value = 'None';
            app.TagearDropDownLabel = uilabel(app.TagTab);
            app.TagearDropDownLabel.Text = 'Tag ear';
            app.TagearDropDown = uidropdown(app.TagTab);
            app.TagearDropDown.Items = {'None', 'L', 'R', 'Unknown'};
            app.TagearDropDown.Value = 'None';
            app.IPinjectionTab = uitab(app.EventTabGroup);
            app.IPinjectionTab.Title = 'IP injection';
            app.SubstanceDropDownLabel = uilabel(app.IPinjectionTab);
            app.SubstanceDropDownLabel.Text = 'Substance';
            app.SubstanceDropDown_IP = uidropdown(app.IPinjectionTab);
            app.ConcentrationmgKgEditFieldLabel_2 = uilabel(app.IPinjectionTab);
            app.ConcentrationmgKgEditFieldLabel_2.Text = 'Concentration (mg / Kg)';
            app.IP_inj_ConcentrationEditField = uieditfield(app.IPinjectionTab, 'text');
            app.EyeinjectionTab = uitab(app.EventTabGroup);
            app.EyeinjectionTab.Title = 'Eye injection';
            app.SubstanceDropDownLabel_2 = uilabel(app.EyeinjectionTab);
            app.SubstanceDropDownLabel_2.Text = 'Substance';
            app.SubstanceDropDown_Eye = uidropdown(app.EyeinjectionTab);
            app.EyeDropDownLabel = uilabel(app.EyeinjectionTab);
            app.EyeDropDownLabel.Text = 'Eye';
            app.WhichEyeDropDown = uidropdown(app.EyeinjectionTab);
            app.WhichEyeDropDown.Items = {'Left', 'Right'};
            app.WhichEyeDropDown.Value = 'Left';
            app.Dilution1XEditFieldLabel_2 = uilabel(app.EyeinjectionTab);
            app.Dilution1XEditFieldLabel_2.Text = 'Dilution (1:X)';
            app.Dilution_EyeEditField = uieditfield(app.EyeinjectionTab, 'text');
            app.BraininjectionTab = uitab(app.EventTabGroup);
            app.BraininjectionTab.Title = 'Brain injection';
            app.SubstanceDropDown_3Label = uilabel(app.BraininjectionTab);
            app.SubstanceDropDown_3Label.Text = 'Substance';
            app.SubstanceDropDown_Brain = uidropdown(app.BraininjectionTab);
            app.CoordinatesLabel = uilabel(app.BraininjectionTab);
            app.CoordinatesLabel.Text = 'Coordinates:';
            app.TargetDropDownLabel = uilabel(app.BraininjectionTab);
            app.TargetDropDownLabel.Text = 'Target';
            app.BrainTargetDropDown = uidropdown(app.BraininjectionTab);
            app.BrainTargetDropDown.Items = {'MeA', 'LHA', 'OPN', 'vLGN', 'dLGN', 'SC'};
            app.BrainTargetDropDown.Value = 'MeA';
            app.HemisphereDropDownLabel = uilabel(app.BraininjectionTab);
            app.HemisphereDropDownLabel.Text = 'Hemisphere';
            app.HemisphereDropDown = uidropdown(app.BraininjectionTab);
            app.HemisphereDropDown.Items = {'Left', 'Right'};
            app.HemisphereDropDown.Value = 'Left';
            app.Dilution1XEditFieldLabel_3 = uilabel(app.BraininjectionTab);
            app.Dilution1XEditFieldLabel_3.Text = 'Dilution (1:X)';
            app.Dilution_BrainEditField = uieditfield(app.BraininjectionTab, 'text');
            app.APEditField_2Label = uilabel(app.BraininjectionTab);
            app.APEditField_2Label.Text = 'AP';
            app.APEditField = uieditfield(app.BraininjectionTab, 'text');
            app.MLEditField_2Label = uilabel(app.BraininjectionTab);
            app.MLEditField_2Label.Text = 'ML';
            app.MLEditField = uieditfield(app.BraininjectionTab, 'text');
            app.DVEditField_2Label = uilabel(app.BraininjectionTab);
            app.DVEditField_2Label.Text = 'DV';
            app.DVEditField = uieditfield(app.BraininjectionTab, 'text');
            app.AngleEditField_2Label = uilabel(app.BraininjectionTab);
            app.AngleEditField_2Label.Text = 'Angle';
            app.AngleEditField = uieditfield(app.BraininjectionTab, 'text');
            app.FeedTab = uitab(app.EventTabGroup);
            app.FeedTab.Title = 'Feed';
            app.SubstanceDropDownLabel_3 = uilabel(app.FeedTab);
            app.SubstanceDropDownLabel_3.Text = 'Substance';
            app.SubstanceDropDown_Feed = uidropdown(app.FeedTab);
            app.HandledTab = uitab(app.EventTabGroup);
            app.HandledTab.Title = 'Handled';
            app.DurtionminsEditFieldLabel = uilabel(app.HandledTab);
            app.DurtionminsEditFieldLabel.Text = 'Durtion (mins)';
            app.HandlingDurationEditField = uieditfield(app.HandledTab, 'text');
            app.SocialbehaviorsessionTab = uitab(app.EventTabGroup);
            app.SocialbehaviorsessionTab.Title = 'Social behavior session';
            app.RecordBehavior_switch = uiswitch(app.SocialbehaviorsessionTab, 'rocker');
            app.RecordBehavior_switch.Items = {'Not recorded', 'Recorded'};
            app.RecordBehavior_switch.ValueChangedFcn = createCallbackFcn(app, @RecordBehavior_switchValueChanged, true);
            app.RecordBehavior_switch.Value = 'Recorded';
            app.StimulusLabel = uilabel(app.SocialbehaviorsessionTab);
            app.StimulusLabel.Text = 'Stimulus';
            app.Lamp_isRecorded = uilamp(app.SocialbehaviorsessionTab);
            app.ExperimenttypeDropDownLabel = uilabel(app.SocialbehaviorsessionTab);
            app.ExperimenttypeDropDownLabel.Text = 'Experiment type';
            app.ExperimenttypeDropDown = uidropdown(app.SocialbehaviorsessionTab);
            app.AnimalAEditField = uieditfield(app.SocialbehaviorsessionTab, 'text');
            app.AnimalBEditField = uieditfield(app.SocialbehaviorsessionTab, 'text');
            app.AnimalCEditField = uieditfield(app.SocialbehaviorsessionTab, 'text');
            app.TestanimaltypeDropDownLabel = uilabel(app.SocialbehaviorsessionTab);
            app.TestanimaltypeDropDownLabel.Text = 'Test animal type';
            app.TestanimaltypeDropDown = uidropdown(app.SocialbehaviorsessionTab);
            app.DJIDLabel = uilabel(app.SocialbehaviorsessionTab);
            app.DJIDLabel.Text = 'DJID';
            app.WindowLabel = uilabel(app.SocialbehaviorsessionTab);
            app.WindowLabel.Text = 'Window';
            app.ALabel = uilabel(app.SocialbehaviorsessionTab);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.SocialbehaviorsessionTab);
            app.BLabel.Text = 'B';
            app.CLabel = uilabel(app.SocialbehaviorsessionTab);
            app.CLabel.Text = 'C';
            app.WindowAStim = uidropdown(app.SocialbehaviorsessionTab);
            app.WindowAStim.ValueChangedFcn = createCallbackFcn(app, @WindowAStimValueChanged, true);
            app.WindowBStim = uidropdown(app.SocialbehaviorsessionTab);
            app.WindowBStim.ValueChangedFcn = createCallbackFcn(app, @WindowBStimValueChanged, true);
            app.WindowCStim = uidropdown(app.SocialbehaviorsessionTab);
            app.WindowCStim.ValueChangedFcn = createCallbackFcn(app, @WindowCStimValueChanged, true);
            app.ReserveforprojectTab = uitab(app.EventTabGroup);
            app.ReserveforprojectTab.Title = 'Reserve for project';
            app.ProjectDropDownLabel = uilabel(app.ReserveforprojectTab);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.ReserveforprojectTab);
            app.ReserveforrigTab = uitab(app.EventTabGroup);
            app.ReserveforrigTab.Title = 'Reserve for rig';
            app.RignameDropDownLabel = uilabel(app.ReserveforrigTab);
            app.RignameDropDownLabel.Text = 'Rig name';
            app.RignameDropDown = uidropdown(app.ReserveforrigTab);
            app.CalendarrefreshtokenEditFieldLabel = uilabel(app.ReserveforrigTab);
            app.CalendarrefreshtokenEditFieldLabel.Text = 'Calendar refresh token';
            app.CalendarrefreshtokenEditField = uieditfield(app.ReserveforrigTab, 'text');
            app.ReauthorizeButton = uibutton(app.ReserveforrigTab, 'push');
            app.ReauthorizeButton.ButtonPushedFcn = createCallbackFcn(app, @ReauthorizeButtonPushed, true);
            app.ReauthorizeButton.Text = 'Reauthorize';
            app.AnimalSelectionPanel = uipanel(app.AnimalLoggerTab);
            app.AnimalSelectionPanel.Title = 'Animal Selection';
            app.SearchButton = uibutton(app.AnimalSelectionPanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.FilterPanel = uipanel(app.AnimalSelectionPanel);
            app.FilterPanel.Title = 'Filter';
            app.GenotypeDropDownLabel = uilabel(app.FilterPanel);
            app.GenotypeDropDownLabel.Text = 'Genotype';
            app.Filter_GenotypeDropDown = uidropdown(app.FilterPanel);
            app.Filter_GenotypeDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_GenotypeDropDownValueChanged, true);
            app.StatusDropDownLabel = uilabel(app.FilterPanel);
            app.StatusDropDownLabel.Text = 'Status';
            app.Filter_GenotypeStatusDropDown = uidropdown(app.FilterPanel);
            app.Filter_GenotypeStatusDropDown.Items = {'All', 'carrier', 'non-carrier', 'unknown', 'het', 'Homo'};
            app.Filter_GenotypeStatusDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_GenotypeStatusDropDownValueChanged, true);
            app.Filter_GenotypeStatusDropDown.Value = 'All';
            app.EyeInjSwitchLabel = uilabel(app.FilterPanel);
            app.EyeInjSwitchLabel.Text = 'Eye Inj.';
            app.EyeInjSwitch = uiswitch(app.FilterPanel, 'toggle');
            app.EyeInjSwitch.Items = {'Don''t check', 'Y'};
            app.EyeInjSwitch.ValueChangedFcn = createCallbackFcn(app, @EyeInjSwitchValueChanged, true);
            app.EyeInjSwitch.Value = 'Don''t check';
            app.BehaviorexpSwitchLabel = uilabel(app.FilterPanel);
            app.BehaviorexpSwitchLabel.Text = 'Behavior exp.';
            app.BehaviorexpSwitch = uiswitch(app.FilterPanel, 'toggle');
            app.BehaviorexpSwitch.Items = {'Don''t check', 'Y'};
            app.BehaviorexpSwitch.ValueChangedFcn = createCallbackFcn(app, @BehaviorexpSwitchValueChanged, true);
            app.BehaviorexpSwitch.Value = 'Don''t check';
            app.SexDropDownLabel = uilabel(app.FilterPanel);
            app.SexDropDownLabel.Text = 'Sex';
            app.Filter_SexDropDown = uidropdown(app.FilterPanel);
            app.Filter_SexDropDown.Items = {'All', 'Male', 'Female'};
            app.Filter_SexDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_SexDropDownValueChanged, true);
            app.Filter_SexDropDown.Value = 'All';
            app.BraininjSwitchLabel = uilabel(app.FilterPanel);
            app.BraininjSwitchLabel.Text = 'Brain inj.';
            app.BraininjSwitch = uiswitch(app.FilterPanel, 'toggle');
            app.BraininjSwitch.Items = {'Don''t check', 'Y'};
            app.BraininjSwitch.ValueChangedFcn = createCallbackFcn(app, @BraininjSwitchValueChanged, true);
            app.BraininjSwitch.Value = 'Don''t check';
            app.NotrigreservedSwitchLabel = uilabel(app.FilterPanel);
            app.NotrigreservedSwitchLabel.Text = 'Not rig reserved';
            app.NotrigreservedSwitch = uiswitch(app.FilterPanel, 'toggle');
            app.NotrigreservedSwitch.Items = {'Don''t check', 'Y'};
            app.NotrigreservedSwitch.ValueChangedFcn = createCallbackFcn(app, @NotrigreservedSwitchValueChanged, true);
            app.NotrigreservedSwitch.Value = 'Don''t check';
            app.ProjectLabel = uilabel(app.FilterPanel);
            app.ProjectLabel.Text = 'Project';
            app.Filter_ProjectDropDown = uidropdown(app.FilterPanel);
            app.Filter_ProjectDropDown.Items = {'All', 'Male', 'Female'};
            app.Filter_ProjectDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_ProjectDropDownValueChanged, true);
            app.Filter_ProjectDropDown.Value = 'All';
            app.NotprojreservedSwitchLabel = uilabel(app.FilterPanel);
            app.NotprojreservedSwitchLabel.Text = 'Not proj. reserved';
            app.NotprojreservedSwitch = uiswitch(app.FilterPanel, 'toggle');
            app.NotprojreservedSwitch.Items = {'Don''t check', 'Y'};
            app.NotprojreservedSwitch.ValueChangedFcn = createCallbackFcn(app, @NotprojreservedSwitchValueChanged, true);
            app.NotprojreservedSwitch.Value = 'Don''t check';
            app.ResetButton = uibutton(app.AnimalSelectionPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ID_type_switch = uiswitch(app.AnimalSelectionPanel, 'slider');
            app.ID_type_switch.Items = {'Tag ID', 'DJ ID'};
            app.ID_type_switch.ValueChangedFcn = createCallbackFcn(app, @ID_type_switchValueChanged, true);
            app.ID_type_switch.Value = 'Tag ID';
            app.AgerangeweeksPanel = uipanel(app.AnimalSelectionPanel);
            app.AgerangeweeksPanel.Title = 'Age range (weeks)';
            app.toLabel = uilabel(app.AgerangeweeksPanel);
            app.toLabel.Text = 'to';
            app.ageLow_edit = uieditfield(app.AgerangeweeksPanel, 'text');
            app.ageLow_edit.ValueChangedFcn = createCallbackFcn(app, @ageLow_editValueChanged, true);
            app.ageHigh_edit = uieditfield(app.AgerangeweeksPanel, 'text');
            app.ageHigh_edit.ValueChangedFcn = createCallbackFcn(app, @ageHigh_editValueChanged, true);
            app.Search_DJIDEditField = uieditfield(app.AnimalSelectionPanel, 'text');
            app.Search_TagIDEditField = uieditfield(app.AnimalSelectionPanel, 'text');
            app.PersonDropDownLabel = uilabel(app.AnimalLoggerTab);
            app.PersonDropDownLabel.Text = 'Person';
            app.PersonDropDown = uidropdown(app.AnimalLoggerTab);
            app.PersonDropDown.Items = {'David', 'Zach', 'Devon', 'Greg', 'Kayla', 'Sam', 'Sophia', 'Susan'};
            app.PersonDropDown.Value = 'David';
            app.EventdateDatePickerLabel = uilabel(app.AnimalLoggerTab);
            app.EventdateDatePickerLabel.Text = 'Event date';
            app.EventdateDatePicker = uidatepicker(app.AnimalLoggerTab);
            app.EventdateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.SubmitevententryButton = uibutton(app.AnimalLoggerTab, 'push');
            app.SubmitevententryButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitevententryButtonPushed, true);
            app.SubmitevententryButton.Text = 'Submit event entry';
            app.NotesTextArea_2Label = uilabel(app.AnimalLoggerTab);
            app.NotesTextArea_2Label.Text = 'Notes';
            app.EventNotesTextArea = uitextarea(app.AnimalLoggerTab);
            app.EventTimeM = uidropdown(app.AnimalLoggerTab);
            app.EventTimeM.Items = {'00', '15', '30', '45'};
            app.EventTimeM.Value = '00';
            app.EventTimeAMPM = uidropdown(app.AnimalLoggerTab);
            app.EventTimeAMPM.Items = {'AM', 'PM'};
            app.EventTimeAMPM.Value = 'AM';
            app.TimeLabel_2 = uilabel(app.AnimalLoggerTab);
            app.TimeLabel_2.Text = 'Time';
            app.AlleventsforselectedanimalButton = uibutton(app.AnimalLoggerTab, 'push');
            app.AlleventsforselectedanimalButton.ButtonPushedFcn = createCallbackFcn(app, @AlleventsforselectedanimalButtonPushed, true);
            app.AlleventsforselectedanimalButton.Text = 'All events for selected animal';
            app.EventTimeH = uidropdown(app.AnimalLoggerTab);
            app.EventTimeH.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.EventTimeH.Value = '9';
            app.ViewlogsLabel = uilabel(app.AnimalLoggerTab);
            app.ViewlogsLabel.Text = 'View logs';
            app.EventsforallanimalsButton = uibutton(app.AnimalLoggerTab, 'push');
            app.EventsforallanimalsButton.ButtonPushedFcn = createCallbackFcn(app, @EventsforallanimalsButtonPushed, true);
            app.EventsforallanimalsButton.Text = 'Events for all animals';
            app.TypeDropDownLabel = uilabel(app.AnimalLoggerTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.EventTypeDropDown = uidropdown(app.AnimalLoggerTab);
            app.StatusLampLabel = uilabel(app.AnimalLoggerTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp_Logger = uilamp(app.AnimalLoggerTab);
            app.EventInsertStatusEdit = uieditfield(app.AnimalLoggerTab, 'text');
            app.EventInsertStatusEdit.Editable = 'off';
            app.ExporttoExcelButton = uibutton(app.AnimalLoggerTab, 'push');
            app.ExporttoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoExcelButtonPushed, true);
            app.ExporttoExcelButton.Text = 'Export to Excel';
            app.BreedersTab = uitab(app.MainTabGroup);
            app.BreedersTab.Title = 'Breeders';
            app.BreederCagesTable = uitable(app.BreedersTab);
            app.BreederCagesTable.ColumnName = {'Cage'; 'Room'; 'Male genotype'; 'Female genotype'; 'Date paired'; '# of litters'; 'Litter sizes (birth)'; 'Litter sizes (weaned)'; 'Litter dates'; 'Next wean date'};
            app.BreederCagesTable.RowName = {};
            app.BreederCagesTable.ColumnSortable = [true true true true true true false false false true];
            app.BreederCagesTable.CellSelectionCallback = createCallbackFcn(app, @BreederCagesTableCellSelection, true);
            app.MaleinselectedpairLabel = uilabel(app.BreedersTab);
            app.MaleinselectedpairLabel.Text = 'Male in selected pair';
            app.FemaleinselectedpairLabel = uilabel(app.BreedersTab);
            app.FemaleinselectedpairLabel.Text = 'Female in selected pair';
            app.ExporttoExcelButton_breeders = uibutton(app.BreedersTab, 'push');
            app.ExporttoExcelButton_breeders.ButtonPushedFcn = createCallbackFcn(app, @ExporttoExcelButton_breedersPushed, true);
            app.ExporttoExcelButton_breeders.Text = 'Export to Excel';
            app.selectedMaleTable = uitable(app.BreedersTab);
            app.selectedMaleTable.ColumnName = {'DJ ID'; 'Tag ID'; 'Genotype status'; 'Age (wks)'};
            app.selectedMaleTable.RowName = {};
            app.SubmitevententryButton_breeders = uibutton(app.BreedersTab, 'push');
            app.SubmitevententryButton_breeders.ButtonPushedFcn = createCallbackFcn(app, @SubmitevententryButton_breedersPushed, true);
            app.SubmitevententryButton_breeders.Text = 'Submit event entry';
            app.StatusLampLabel_6 = uilabel(app.BreedersTab);
            app.StatusLampLabel_6.Text = 'Status';
            app.StatusLamp_breeders = uilamp(app.BreedersTab);
            app.EventInsertStatusEdit_breeders = uieditfield(app.BreedersTab, 'text');
            app.EventInsertStatusEdit_breeders.Editable = 'off';
            app.PersonDropDown_2Label = uilabel(app.BreedersTab);
            app.PersonDropDown_2Label.Text = 'Person';
            app.PersonDropDown_breeders = uidropdown(app.BreedersTab);
            app.PersonDropDown_breeders.Items = {'David', 'Zach', 'Devon', 'Greg', 'Kayla', 'Sam', 'Sophia', 'Susan'};
            app.PersonDropDown_breeders.Value = 'David';
            app.EventdateDatePicker_2Label = uilabel(app.BreedersTab);
            app.EventdateDatePicker_2Label.Text = 'Event date';
            app.EventdateDatePicker_breeders = uidatepicker(app.BreedersTab);
            app.EventdateDatePicker_breeders.DisplayFormat = 'uuuu-MM-dd';
            app.NotesTextArea_2Label_2 = uilabel(app.BreedersTab);
            app.NotesTextArea_2Label_2.Text = 'Notes';
            app.EventNotesTextArea_breeders = uitextarea(app.BreedersTab);
            app.UnpairedBreedersTable = uitable(app.BreedersTab);
            app.UnpairedBreedersTable.ColumnName = {'Selected'; 'DJ ID'; 'Tag ID'; 'Cage #'; 'Sex'; 'Genotype'; 'Genotype status'; 'Age (wks)'};
            app.UnpairedBreedersTable.RowName = {};
            app.UnpairedBreedersTable.ColumnSortable = [true true true true true true true true];
            app.UnpairedbreedersLabel = uilabel(app.BreedersTab);
            app.UnpairedbreedersLabel.Text = 'Unpaired breeders';
            app.EventTabGroup_breeders = uitabgroup(app.BreedersTab);
            app.PairbreedersTab = uitab(app.EventTabGroup_breeders);
            app.PairbreedersTab.Title = 'Pair breeders';
            app.NewbreedercageEditFieldLabel = uilabel(app.PairbreedersTab);
            app.NewbreedercageEditFieldLabel.Text = 'New breeder cage #';
            app.NewbreedercageEditField = uieditfield(app.PairbreedersTab, 'text');
            app.RoomDropDown_2Label_2 = uilabel(app.PairbreedersTab);
            app.RoomDropDown_2Label_2.Text = 'Room';
            app.RoomDropDown_breeders = uidropdown(app.PairbreedersTab);
            app.SeparatebreedingpairTab = uitab(app.EventTabGroup_breeders);
            app.SeparatebreedingpairTab.Title = 'Separate breeding pair';
            app.NewcageformaleEditFieldLabel = uilabel(app.SeparatebreedingpairTab);
            app.NewcageformaleEditFieldLabel.Text = 'New cage for male';
            app.NewcageformaleEditField = uieditfield(app.SeparatebreedingpairTab, 'text');
            app.NewcageforfemaleEditFieldLabel = uilabel(app.SeparatebreedingpairTab);
            app.NewcageforfemaleEditFieldLabel.Text = 'New cage for female';
            app.NewcageforfemaleEditField = uieditfield(app.SeparatebreedingpairTab, 'text');
            app.RoomDropDown_2Label_3 = uilabel(app.SeparatebreedingpairTab);
            app.RoomDropDown_2Label_3.Text = 'Room';
            app.RoomDropDown_breeders_sepMale = uidropdown(app.SeparatebreedingpairTab);
            app.RoomDropDown_2Label_4 = uilabel(app.SeparatebreedingpairTab);
            app.RoomDropDown_2Label_4.Text = 'Room';
            app.RoomDropDown_breeders_sepFemale = uidropdown(app.SeparatebreedingpairTab);
            app.RetirebreederTab = uitab(app.EventTabGroup_breeders);
            app.RetirebreederTab.Title = 'Retire breeder';
            app.SacrificebreedingpairTab = uitab(app.EventTabGroup_breeders);
            app.SacrificebreedingpairTab.Title = 'Sacrifice breeding pair';
            app.LogbirthTab = uitab(app.EventTabGroup_breeders);
            app.LogbirthTab.Title = 'Log birth';
            app.NumberofpupsEditField_2Label = uilabel(app.LogbirthTab);
            app.NumberofpupsEditField_2Label.Text = 'Number of pups';
            app.NumberofpupsEditField = uieditfield(app.LogbirthTab, 'text');
            app.LogweaningTab = uitab(app.EventTabGroup_breeders);
            app.LogweaningTab.Title = 'Log weaning';
            app.NumberofpupsEditField_2Label_2 = uilabel(app.LogweaningTab);
            app.NumberofpupsEditField_2Label_2.Text = 'Number of pups';
            app.NumberofpupsEditField_weaned = uieditfield(app.LogweaningTab, 'text');
            app.selectedFemaleTable = uitable(app.BreedersTab);
            app.selectedFemaleTable.ColumnName = {'DJ ID'; 'Tag ID'; 'Genotype status'; 'Age (wks)'};
            app.selectedFemaleTable.RowName = {};
            app.AddAnimalsTab = uitab(app.MainTabGroup);
            app.AddAnimalsTab.Title = 'Add Animals';
            app.SpeciesTabGroup = uitabgroup(app.AddAnimalsTab);
            app.LabmouseTab = uitab(app.SpeciesTabGroup);
            app.LabmouseTab.Title = 'Lab mouse';
            app.tagged_switch = uiswitch(app.LabmouseTab, 'slider');
            app.tagged_switch.Items = {'Untagged', 'Tagged'};
            app.tagged_switch.ValueChangedFcn = createCallbackFcn(app, @tagged_switchValueChanged, true);
            app.tagged_switch.Value = 'Tagged';
            app.SexButtonGroup = uibuttongroup(app.LabmouseTab);
            app.SexButtonGroup.Title = 'Sex';
            app.UnknownButton = uiradiobutton(app.SexButtonGroup);
            app.UnknownButton.Text = 'Unknown';
            app.UnknownButton.Value = true;
            app.MaleButton = uiradiobutton(app.SexButtonGroup);
            app.MaleButton.Text = 'Male';
            app.FemaleButton = uiradiobutton(app.SexButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.PunchearButtonGroup = uibuttongroup(app.LabmouseTab);
            app.PunchearButtonGroup.Title = 'Punch ear';
            app.NoneButton = uiradiobutton(app.PunchearButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.LButton = uiradiobutton(app.PunchearButtonGroup);
            app.LButton.Text = 'L';
            app.RButton = uiradiobutton(app.PunchearButtonGroup);
            app.RButton.Text = 'R';
            app.BothButton = uiradiobutton(app.PunchearButtonGroup);
            app.BothButton.Text = 'Both';
            app.DOB_known_switch = uiswitch(app.LabmouseTab, 'slider');
            app.DOB_known_switch.Items = {'DOB unknown', 'DOB known'};
            app.DOB_known_switch.ValueChangedFcn = createCallbackFcn(app, @DOB_known_switchValueChanged, true);
            app.DOB_known_switch.Value = 'DOB known';
            app.GenotypeDropDownLabel_2 = uilabel(app.LabmouseTab);
            app.GenotypeDropDownLabel_2.Text = 'Genotype';
            app.GenotypeDropDown = uidropdown(app.LabmouseTab);
            app.DOBDatePickerLabel = uilabel(app.LabmouseTab);
            app.DOBDatePickerLabel.Text = 'DOB';
            app.DOBDatePicker = uidatepicker(app.LabmouseTab);
            app.DOBDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.TagearButtonGroup = uibuttongroup(app.LabmouseTab);
            app.TagearButtonGroup.Title = 'Tag ear';
            app.NoneButton_2 = uiradiobutton(app.TagearButtonGroup);
            app.NoneButton_2.Text = 'None';
            app.NoneButton_2.Value = true;
            app.LButton_2 = uiradiobutton(app.TagearButtonGroup);
            app.LButton_2.Text = 'L';
            app.RButton_2 = uiradiobutton(app.TagearButtonGroup);
            app.RButton_2.Text = 'R';
            app.UnknownButton_2 = uiradiobutton(app.TagearButtonGroup);
            app.UnknownButton_2.Text = 'Unknown';
            app.RoomDropDownLabel = uilabel(app.LabmouseTab);
            app.RoomDropDownLabel.Text = 'Room';
            app.RoomDropDown = uidropdown(app.LabmouseTab);
            app.TagIDEditFieldLabel = uilabel(app.LabmouseTab);
            app.TagIDEditFieldLabel.Text = 'Tag ID';
            app.TagIDEditField = uieditfield(app.LabmouseTab, 'text');
            app.SourceDropDownLabel = uilabel(app.LabmouseTab);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.LabmouseTab);
            app.SourceDropDown.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown.Value = 'breeding';
            app.CageEditFieldLabel = uilabel(app.LabmouseTab);
            app.CageEditFieldLabel.Text = 'Cage #';
            app.CageEditField = uieditfield(app.LabmouseTab, 'text');
            app.ProtocolDropDownLabel = uilabel(app.LabmouseTab);
            app.ProtocolDropDownLabel.Text = 'Protocol';
            app.ProtocolDropDown = uidropdown(app.LabmouseTab);
            app.SingleentryLabel = uilabel(app.LabmouseTab);
            app.SingleentryLabel.Text = 'Single entry';
            app.MultipleentryLabel = uilabel(app.LabmouseTab);
            app.MultipleentryLabel.Text = 'Multiple entry';
            app.GenotypeDropDown_E1 = uidropdown(app.LabmouseTab);
            app.DOBDatePicker_E1 = uidatepicker(app.LabmouseTab);
            app.DOBDatePicker_E1.DisplayFormat = 'uuuu-MM-dd';
            app.GenotypeLabel = uilabel(app.LabmouseTab);
            app.GenotypeLabel.Text = 'Genotype';
            app.DOBLabel = uilabel(app.LabmouseTab);
            app.DOBLabel.Text = 'DOB';
            app.EntryLabel = uilabel(app.LabmouseTab);
            app.EntryLabel.Text = 'Entry';
            app.Label_1 = uilabel(app.LabmouseTab);
            app.Label_1.Text = '1';
            app.TagIDEditField_E1 = uieditfield(app.LabmouseTab, 'text');
            app.TagIDLabel = uilabel(app.LabmouseTab);
            app.TagIDLabel.Text = 'Tag ID';
            app.CageEditField_E1 = uieditfield(app.LabmouseTab, 'text');
            app.CageLabel = uilabel(app.LabmouseTab);
            app.CageLabel.Text = 'Cage #';
            app.SexButtonGroup_E1 = uibuttongroup(app.LabmouseTab);
            app.SexButtonGroup_E1.Title = 'Sex';
            app.UnknownButton_3 = uiradiobutton(app.SexButtonGroup_E1);
            app.UnknownButton_3.Text = 'Unknown';
            app.UnknownButton_3.Value = true;
            app.MaleButton_2 = uiradiobutton(app.SexButtonGroup_E1);
            app.MaleButton_2.Text = 'Male';
            app.FemaleButton_2 = uiradiobutton(app.SexButtonGroup_E1);
            app.FemaleButton_2.Text = 'Female';
            app.RoomDropDown_E1 = uidropdown(app.LabmouseTab);
            app.RoomLabel = uilabel(app.LabmouseTab);
            app.RoomLabel.Text = 'Room';
            app.SourceDropDown_E1 = uidropdown(app.LabmouseTab);
            app.SourceDropDown_E1.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown_E1.Value = 'breeding';
            app.SourceLabel = uilabel(app.LabmouseTab);
            app.SourceLabel.Text = 'Source';
            app.ProtocolDropDown_E1 = uidropdown(app.LabmouseTab);
            app.ProtocolLabel = uilabel(app.LabmouseTab);
            app.ProtocolLabel.Text = 'Protocol';
            app.PunchearButtonGroup_E1 = uibuttongroup(app.LabmouseTab);
            app.PunchearButtonGroup_E1.Title = 'Punch ear';
            app.NoneButton_3 = uiradiobutton(app.PunchearButtonGroup_E1);
            app.NoneButton_3.Text = 'None';
            app.NoneButton_3.Value = true;
            app.LButton_3 = uiradiobutton(app.PunchearButtonGroup_E1);
            app.LButton_3.Text = 'L';
            app.RButton_3 = uiradiobutton(app.PunchearButtonGroup_E1);
            app.RButton_3.Text = 'R';
            app.BothButton_2 = uiradiobutton(app.PunchearButtonGroup_E1);
            app.BothButton_2.Text = 'Both';
            app.TagearButtonGroup_E1 = uibuttongroup(app.LabmouseTab);
            app.TagearButtonGroup_E1.Title = 'Tag ear';
            app.NoneButton_4 = uiradiobutton(app.TagearButtonGroup_E1);
            app.NoneButton_4.Text = 'None';
            app.NoneButton_4.Value = true;
            app.LButton_4 = uiradiobutton(app.TagearButtonGroup_E1);
            app.LButton_4.Text = 'L';
            app.RButton_4 = uiradiobutton(app.TagearButtonGroup_E1);
            app.RButton_4.Text = 'R';
            app.UnknownButton_4 = uiradiobutton(app.TagearButtonGroup_E1);
            app.UnknownButton_4.Text = 'Unknown';
            app.tagged_switch_E1 = uiswitch(app.LabmouseTab, 'slider');
            app.tagged_switch_E1.Items = {'', 'Tagged'};
            app.tagged_switch_E1.Value = 'Tagged';
            app.DOB_known_switch_E1 = uiswitch(app.LabmouseTab, 'slider');
            app.DOB_known_switch_E1.Items = {'', 'DOB known'};
            app.DOB_known_switch_E1.Value = 'DOB known';
            app.SetotherentriesButton = uibutton(app.LabmouseTab, 'push');
            app.SetotherentriesButton.ButtonPushedFcn = createCallbackFcn(app, @SetotherentriesButtonPushed, true);
            app.SetotherentriesButton.Text = 'Set other entries';
            app.GenotypeDropDown_E2 = uidropdown(app.LabmouseTab);
            app.DOBDatePicker_E2 = uidatepicker(app.LabmouseTab);
            app.DOBDatePicker_E2.DisplayFormat = 'uuuu-MM-dd';
            app.Label_2 = uilabel(app.LabmouseTab);
            app.Label_2.Text = '2';
            app.TagIDEditField_E2 = uieditfield(app.LabmouseTab, 'text');
            app.CageEditField_E2 = uieditfield(app.LabmouseTab, 'text');
            app.SexButtonGroup_E2 = uibuttongroup(app.LabmouseTab);
            app.SexButtonGroup_E2.Title = 'Sex';
            app.UnknownButton_5 = uiradiobutton(app.SexButtonGroup_E2);
            app.UnknownButton_5.Text = 'Unknown';
            app.UnknownButton_5.Value = true;
            app.MaleButton_3 = uiradiobutton(app.SexButtonGroup_E2);
            app.MaleButton_3.Text = 'Male';
            app.FemaleButton_3 = uiradiobutton(app.SexButtonGroup_E2);
            app.FemaleButton_3.Text = 'Female';
            app.RoomDropDown_E2 = uidropdown(app.LabmouseTab);
            app.ProtocolDropDown_E2 = uidropdown(app.LabmouseTab);
            app.PunchearButtonGroup_E2 = uibuttongroup(app.LabmouseTab);
            app.PunchearButtonGroup_E2.Title = 'Punch ear';
            app.NoneButton_5 = uiradiobutton(app.PunchearButtonGroup_E2);
            app.NoneButton_5.Text = 'None';
            app.NoneButton_5.Value = true;
            app.LButton_5 = uiradiobutton(app.PunchearButtonGroup_E2);
            app.LButton_5.Text = 'L';
            app.RButton_5 = uiradiobutton(app.PunchearButtonGroup_E2);
            app.RButton_5.Text = 'R';
            app.BothButton_3 = uiradiobutton(app.PunchearButtonGroup_E2);
            app.BothButton_3.Text = 'Both';
            app.TagearButtonGroup_E2 = uibuttongroup(app.LabmouseTab);
            app.TagearButtonGroup_E2.Title = 'Tag ear';
            app.NoneButton_6 = uiradiobutton(app.TagearButtonGroup_E2);
            app.NoneButton_6.Text = 'None';
            app.NoneButton_6.Value = true;
            app.LButton_6 = uiradiobutton(app.TagearButtonGroup_E2);
            app.LButton_6.Text = 'L';
            app.RButton_6 = uiradiobutton(app.TagearButtonGroup_E2);
            app.RButton_6.Text = 'R';
            app.UnknownButton_6 = uiradiobutton(app.TagearButtonGroup_E2);
            app.UnknownButton_6.Text = 'Unknown';
            app.tagged_switch_E2 = uiswitch(app.LabmouseTab, 'slider');
            app.tagged_switch_E2.Items = {'', 'Tagged'};
            app.tagged_switch_E2.Value = 'Tagged';
            app.DOB_known_switch_E2 = uiswitch(app.LabmouseTab, 'slider');
            app.DOB_known_switch_E2.Items = {'', 'DOB known'};
            app.DOB_known_switch_E2.Value = 'DOB known';
            app.GenotypeDropDown_E3 = uidropdown(app.LabmouseTab);
            app.DOBDatePicker_E3 = uidatepicker(app.LabmouseTab);
            app.DOBDatePicker_E3.DisplayFormat = 'uuuu-MM-dd';
            app.Label_3 = uilabel(app.LabmouseTab);
            app.Label_3.Text = '3';
            app.TagIDEditField_E3 = uieditfield(app.LabmouseTab, 'text');
            app.CageEditField_E3 = uieditfield(app.LabmouseTab, 'text');
            app.SexButtonGroup_E3 = uibuttongroup(app.LabmouseTab);
            app.SexButtonGroup_E3.Title = 'Sex';
            app.UnknownButton_7 = uiradiobutton(app.SexButtonGroup_E3);
            app.UnknownButton_7.Text = 'Unknown';
            app.UnknownButton_7.Value = true;
            app.MaleButton_4 = uiradiobutton(app.SexButtonGroup_E3);
            app.MaleButton_4.Text = 'Male';
            app.FemaleButton_4 = uiradiobutton(app.SexButtonGroup_E3);
            app.FemaleButton_4.Text = 'Female';
            app.RoomDropDown_E3 = uidropdown(app.LabmouseTab);
            app.ProtocolDropDown_E3 = uidropdown(app.LabmouseTab);
            app.PunchearButtonGroup_E3 = uibuttongroup(app.LabmouseTab);
            app.PunchearButtonGroup_E3.Title = 'Punch ear';
            app.NoneButton_7 = uiradiobutton(app.PunchearButtonGroup_E3);
            app.NoneButton_7.Text = 'None';
            app.NoneButton_7.Value = true;
            app.LButton_7 = uiradiobutton(app.PunchearButtonGroup_E3);
            app.LButton_7.Text = 'L';
            app.RButton_7 = uiradiobutton(app.PunchearButtonGroup_E3);
            app.RButton_7.Text = 'R';
            app.BothButton_4 = uiradiobutton(app.PunchearButtonGroup_E3);
            app.BothButton_4.Text = 'Both';
            app.TagearButtonGroup_E3 = uibuttongroup(app.LabmouseTab);
            app.TagearButtonGroup_E3.Title = 'Tag ear';
            app.NoneButton_8 = uiradiobutton(app.TagearButtonGroup_E3);
            app.NoneButton_8.Text = 'None';
            app.NoneButton_8.Value = true;
            app.LButton_8 = uiradiobutton(app.TagearButtonGroup_E3);
            app.LButton_8.Text = 'L';
            app.RButton_8 = uiradiobutton(app.TagearButtonGroup_E3);
            app.RButton_8.Text = 'R';
            app.UnknownButton_8 = uiradiobutton(app.TagearButtonGroup_E3);
            app.UnknownButton_8.Text = 'Unknown';
            app.tagged_switch_E3 = uiswitch(app.LabmouseTab, 'slider');
            app.tagged_switch_E3.Items = {'', 'Tagged'};
            app.tagged_switch_E3.Value = 'Tagged';
            app.DOB_known_switch_E3 = uiswitch(app.LabmouseTab, 'slider');
            app.DOB_known_switch_E3.Items = {'', 'DOB known'};
            app.DOB_known_switch_E3.Value = 'DOB known';
            app.GenotypeDropDown_E4 = uidropdown(app.LabmouseTab);
            app.DOBDatePicker_E4 = uidatepicker(app.LabmouseTab);
            app.DOBDatePicker_E4.DisplayFormat = 'uuuu-MM-dd';
            app.Label_4 = uilabel(app.LabmouseTab);
            app.Label_4.Text = '4';
            app.TagIDEditField_E4 = uieditfield(app.LabmouseTab, 'text');
            app.CageEditField_E4 = uieditfield(app.LabmouseTab, 'text');
            app.SexButtonGroup_E4 = uibuttongroup(app.LabmouseTab);
            app.SexButtonGroup_E4.Title = 'Sex';
            app.UnknownButton_9 = uiradiobutton(app.SexButtonGroup_E4);
            app.UnknownButton_9.Text = 'Unknown';
            app.UnknownButton_9.Value = true;
            app.MaleButton_5 = uiradiobutton(app.SexButtonGroup_E4);
            app.MaleButton_5.Text = 'Male';
            app.FemaleButton_5 = uiradiobutton(app.SexButtonGroup_E4);
            app.FemaleButton_5.Text = 'Female';
            app.RoomDropDown_E4 = uidropdown(app.LabmouseTab);
            app.ProtocolDropDown_E4 = uidropdown(app.LabmouseTab);
            app.PunchearButtonGroup_E4 = uibuttongroup(app.LabmouseTab);
            app.PunchearButtonGroup_E4.Title = 'Punch ear';
            app.NoneButton_9 = uiradiobutton(app.PunchearButtonGroup_E4);
            app.NoneButton_9.Text = 'None';
            app.NoneButton_9.Value = true;
            app.LButton_9 = uiradiobutton(app.PunchearButtonGroup_E4);
            app.LButton_9.Text = 'L';
            app.RButton_9 = uiradiobutton(app.PunchearButtonGroup_E4);
            app.RButton_9.Text = 'R';
            app.BothButton_5 = uiradiobutton(app.PunchearButtonGroup_E4);
            app.BothButton_5.Text = 'Both';
            app.TagearButtonGroup_E4 = uibuttongroup(app.LabmouseTab);
            app.TagearButtonGroup_E4.Title = 'Tag ear';
            app.NoneButton_10 = uiradiobutton(app.TagearButtonGroup_E4);
            app.NoneButton_10.Text = 'None';
            app.NoneButton_10.Value = true;
            app.LButton_10 = uiradiobutton(app.TagearButtonGroup_E4);
            app.LButton_10.Text = 'L';
            app.RButton_10 = uiradiobutton(app.TagearButtonGroup_E4);
            app.RButton_10.Text = 'R';
            app.UnknownButton_10 = uiradiobutton(app.TagearButtonGroup_E4);
            app.UnknownButton_10.Text = 'Unknown';
            app.tagged_switch_E4 = uiswitch(app.LabmouseTab, 'slider');
            app.tagged_switch_E4.Items = {'', 'Tagged'};
            app.tagged_switch_E4.Value = 'Tagged';
            app.DOB_known_switch_E4 = uiswitch(app.LabmouseTab, 'slider');
            app.DOB_known_switch_E4.Items = {'', 'DOB known'};
            app.DOB_known_switch_E4.Value = 'DOB known';
            app.GenotypeDropDown_E5 = uidropdown(app.LabmouseTab);
            app.DOBDatePicker_E5 = uidatepicker(app.LabmouseTab);
            app.DOBDatePicker_E5.DisplayFormat = 'uuuu-MM-dd';
            app.Label_5 = uilabel(app.LabmouseTab);
            app.Label_5.Text = '5';
            app.TagIDEditField_E5 = uieditfield(app.LabmouseTab, 'text');
            app.CageEditField_E5 = uieditfield(app.LabmouseTab, 'text');
            app.SexButtonGroup_E5 = uibuttongroup(app.LabmouseTab);
            app.SexButtonGroup_E5.Title = 'Sex';
            app.UnknownButton_11 = uiradiobutton(app.SexButtonGroup_E5);
            app.UnknownButton_11.Text = 'Unknown';
            app.UnknownButton_11.Value = true;
            app.MaleButton_6 = uiradiobutton(app.SexButtonGroup_E5);
            app.MaleButton_6.Text = 'Male';
            app.FemaleButton_6 = uiradiobutton(app.SexButtonGroup_E5);
            app.FemaleButton_6.Text = 'Female';
            app.RoomDropDown_E5 = uidropdown(app.LabmouseTab);
            app.ProtocolDropDown_E5 = uidropdown(app.LabmouseTab);
            app.PunchearButtonGroup_E5 = uibuttongroup(app.LabmouseTab);
            app.PunchearButtonGroup_E5.Title = 'Punch ear';
            app.NoneButton_11 = uiradiobutton(app.PunchearButtonGroup_E5);
            app.NoneButton_11.Text = 'None';
            app.NoneButton_11.Value = true;
            app.LButton_11 = uiradiobutton(app.PunchearButtonGroup_E5);
            app.LButton_11.Text = 'L';
            app.RButton_11 = uiradiobutton(app.PunchearButtonGroup_E5);
            app.RButton_11.Text = 'R';
            app.BothButton_6 = uiradiobutton(app.PunchearButtonGroup_E5);
            app.BothButton_6.Text = 'Both';
            app.TagearButtonGroup_E5 = uibuttongroup(app.LabmouseTab);
            app.TagearButtonGroup_E5.Title = 'Tag ear';
            app.NoneButton_12 = uiradiobutton(app.TagearButtonGroup_E5);
            app.NoneButton_12.Text = 'None';
            app.NoneButton_12.Value = true;
            app.LButton_12 = uiradiobutton(app.TagearButtonGroup_E5);
            app.LButton_12.Text = 'L';
            app.RButton_12 = uiradiobutton(app.TagearButtonGroup_E5);
            app.RButton_12.Text = 'R';
            app.UnknownButton_12 = uiradiobutton(app.TagearButtonGroup_E5);
            app.UnknownButton_12.Text = 'Unknown';
            app.tagged_switch_E5 = uiswitch(app.LabmouseTab, 'slider');
            app.tagged_switch_E5.Items = {'', 'Tagged'};
            app.tagged_switch_E5.Value = 'Tagged';
            app.DOB_known_switch_E5 = uiswitch(app.LabmouseTab, 'slider');
            app.DOB_known_switch_E5.Items = {'', 'DOB known'};
            app.DOB_known_switch_E5.Value = 'DOB known';
            app.GenotypeDropDown_E6 = uidropdown(app.LabmouseTab);
            app.DOBDatePicker_E6 = uidatepicker(app.LabmouseTab);
            app.DOBDatePicker_E6.DisplayFormat = 'uuuu-MM-dd';
            app.Label_6 = uilabel(app.LabmouseTab);
            app.Label_6.Text = '6';
            app.TagIDEditField_E6 = uieditfield(app.LabmouseTab, 'text');
            app.CageEditField_E6 = uieditfield(app.LabmouseTab, 'text');
            app.SexButtonGroup_E6 = uibuttongroup(app.LabmouseTab);
            app.SexButtonGroup_E6.Title = 'Sex';
            app.UnknownButton_13 = uiradiobutton(app.SexButtonGroup_E6);
            app.UnknownButton_13.Text = 'Unknown';
            app.UnknownButton_13.Value = true;
            app.MaleButton_7 = uiradiobutton(app.SexButtonGroup_E6);
            app.MaleButton_7.Text = 'Male';
            app.FemaleButton_7 = uiradiobutton(app.SexButtonGroup_E6);
            app.FemaleButton_7.Text = 'Female';
            app.RoomDropDown_E6 = uidropdown(app.LabmouseTab);
            app.ProtocolDropDown_E6 = uidropdown(app.LabmouseTab);
            app.PunchearButtonGroup_E6 = uibuttongroup(app.LabmouseTab);
            app.PunchearButtonGroup_E6.Title = 'Punch ear';
            app.NoneButton_13 = uiradiobutton(app.PunchearButtonGroup_E6);
            app.NoneButton_13.Text = 'None';
            app.NoneButton_13.Value = true;
            app.LButton_13 = uiradiobutton(app.PunchearButtonGroup_E6);
            app.LButton_13.Text = 'L';
            app.RButton_13 = uiradiobutton(app.PunchearButtonGroup_E6);
            app.RButton_13.Text = 'R';
            app.BothButton_7 = uiradiobutton(app.PunchearButtonGroup_E6);
            app.BothButton_7.Text = 'Both';
            app.TagearButtonGroup_E6 = uibuttongroup(app.LabmouseTab);
            app.TagearButtonGroup_E6.Title = 'Tag ear';
            app.NoneButton_14 = uiradiobutton(app.TagearButtonGroup_E6);
            app.NoneButton_14.Text = 'None';
            app.NoneButton_14.Value = true;
            app.LButton_14 = uiradiobutton(app.TagearButtonGroup_E6);
            app.LButton_14.Text = 'L';
            app.RButton_14 = uiradiobutton(app.TagearButtonGroup_E6);
            app.RButton_14.Text = 'R';
            app.UnknownButton_14 = uiradiobutton(app.TagearButtonGroup_E6);
            app.UnknownButton_14.Text = 'Unknown';
            app.tagged_switch_E6 = uiswitch(app.LabmouseTab, 'slider');
            app.tagged_switch_E6.Items = {'', 'Tagged'};
            app.tagged_switch_E6.Value = 'Tagged';
            app.DOB_known_switch_E6 = uiswitch(app.LabmouseTab, 'slider');
            app.DOB_known_switch_E6.Items = {'', 'DOB known'};
            app.DOB_known_switch_E6.Value = 'DOB known';
            app.GenotypeDropDown_E7 = uidropdown(app.LabmouseTab);
            app.DOBDatePicker_E7 = uidatepicker(app.LabmouseTab);
            app.DOBDatePicker_E7.DisplayFormat = 'uuuu-MM-dd';
            app.TagIDEditField_E7 = uieditfield(app.LabmouseTab, 'text');
            app.CageEditField_E7 = uieditfield(app.LabmouseTab, 'text');
            app.SexButtonGroup_E7 = uibuttongroup(app.LabmouseTab);
            app.SexButtonGroup_E7.Title = 'Sex';
            app.UnknownButton_15 = uiradiobutton(app.SexButtonGroup_E7);
            app.UnknownButton_15.Text = 'Unknown';
            app.UnknownButton_15.Value = true;
            app.MaleButton_8 = uiradiobutton(app.SexButtonGroup_E7);
            app.MaleButton_8.Text = 'Male';
            app.FemaleButton_8 = uiradiobutton(app.SexButtonGroup_E7);
            app.FemaleButton_8.Text = 'Female';
            app.RoomDropDown_E7 = uidropdown(app.LabmouseTab);
            app.ProtocolDropDown_E7 = uidropdown(app.LabmouseTab);
            app.PunchearButtonGroup_E7 = uibuttongroup(app.LabmouseTab);
            app.PunchearButtonGroup_E7.Title = 'Punch ear';
            app.NoneButton_15 = uiradiobutton(app.PunchearButtonGroup_E7);
            app.NoneButton_15.Text = 'None';
            app.NoneButton_15.Value = true;
            app.LButton_15 = uiradiobutton(app.PunchearButtonGroup_E7);
            app.LButton_15.Text = 'L';
            app.RButton_15 = uiradiobutton(app.PunchearButtonGroup_E7);
            app.RButton_15.Text = 'R';
            app.BothButton_8 = uiradiobutton(app.PunchearButtonGroup_E7);
            app.BothButton_8.Text = 'Both';
            app.TagearButtonGroup_E7 = uibuttongroup(app.LabmouseTab);
            app.TagearButtonGroup_E7.Title = 'Tag ear';
            app.NoneButton_16 = uiradiobutton(app.TagearButtonGroup_E7);
            app.NoneButton_16.Text = 'None';
            app.NoneButton_16.Value = true;
            app.LButton_16 = uiradiobutton(app.TagearButtonGroup_E7);
            app.LButton_16.Text = 'L';
            app.RButton_16 = uiradiobutton(app.TagearButtonGroup_E7);
            app.RButton_16.Text = 'R';
            app.UnknownButton_16 = uiradiobutton(app.TagearButtonGroup_E7);
            app.UnknownButton_16.Text = 'Unknown';
            app.tagged_switch_E7 = uiswitch(app.LabmouseTab, 'slider');
            app.tagged_switch_E7.Items = {'', 'Tagged'};
            app.tagged_switch_E7.Value = 'Tagged';
            app.DOB_known_switch_E7 = uiswitch(app.LabmouseTab, 'slider');
            app.DOB_known_switch_E7.Items = {'', 'DOB known'};
            app.DOB_known_switch_E7.Value = 'DOB known';

            app.GenotypeDropDown_E8 = uidropdown(app.LabmouseTab);
            app.DOBDatePicker_E8 = uidatepicker(app.LabmouseTab);
            app.DOBDatePicker_E8.DisplayFormat = 'uuuu-MM-dd';
            app.TagIDEditField_E8 = uieditfield(app.LabmouseTab, 'text');
            app.CageEditField_E8 = uieditfield(app.LabmouseTab, 'text');
            app.SexButtonGroup_E8 = uibuttongroup(app.LabmouseTab);
            app.SexButtonGroup_E8.Title = 'Sex';
            app.UnknownButton_17 = uiradiobutton(app.SexButtonGroup_E8);
            app.UnknownButton_17.Text = 'Unknown';
            app.UnknownButton_17.Value = true;
            app.MaleButton_9 = uiradiobutton(app.SexButtonGroup_E8);
            app.MaleButton_9.Text = 'Male';
            app.FemaleButton_9 = uiradiobutton(app.SexButtonGroup_E8);
            app.FemaleButton_9.Text = 'Female';
            app.RoomDropDown_E8 = uidropdown(app.LabmouseTab);
            app.ProtocolDropDown_E8 = uidropdown(app.LabmouseTab);
            app.PunchearButtonGroup_E8 = uibuttongroup(app.LabmouseTab);
            app.PunchearButtonGroup_E8.Title = 'Punch ear';
            app.NoneButton_17 = uiradiobutton(app.PunchearButtonGroup_E8);
            app.NoneButton_17.Text = 'None';
            app.NoneButton_17.Value = true;
            app.LButton_17 = uiradiobutton(app.PunchearButtonGroup_E8);
            app.LButton_17.Text = 'L';
            app.RButton_17 = uiradiobutton(app.PunchearButtonGroup_E8);
            app.RButton_17.Text = 'R';
            app.BothButton_9 = uiradiobutton(app.PunchearButtonGroup_E8);
            app.BothButton_9.Text = 'Both';
            app.TagearButtonGroup_E8 = uibuttongroup(app.LabmouseTab);
            app.TagearButtonGroup_E8.Title = 'Tag ear';
            app.NoneButton_18 = uiradiobutton(app.TagearButtonGroup_E8);
            app.NoneButton_18.Text = 'None';
            app.NoneButton_18.Value = true;
            app.LButton_18 = uiradiobutton(app.TagearButtonGroup_E8);
            app.LButton_18.Text = 'L';
            app.RButton_18 = uiradiobutton(app.TagearButtonGroup_E8);
            app.RButton_18.Text = 'R';
            app.UnknownButton_18 = uiradiobutton(app.TagearButtonGroup_E8);
            app.UnknownButton_18.Text = 'Unknown';
            app.tagged_switch_E8 = uiswitch(app.LabmouseTab, 'slider');
            app.tagged_switch_E8.Items = {'', 'Tagged'};
            app.tagged_switch_E8.Value = 'Tagged';
            app.DOB_known_switch_E8 = uiswitch(app.LabmouseTab, 'slider');
            app.DOB_known_switch_E8.Items = {'', 'DOB known'};
            app.DOB_known_switch_E8.Value = 'DOB known';
            app.Label_7 = uilabel(app.LabmouseTab);
            app.Label_7.Text = '7';
            app.Label_8 = uilabel(app.LabmouseTab);
            app.Label_8.Text = '8';
            app.NumberofentriesSliderLabel = uilabel(app.LabmouseTab);
            app.NumberofentriesSliderLabel.Text = 'Number of entries';
            app.NumberofentriesSlider = uislider(app.LabmouseTab);
            app.NumberofentriesSlider.Limits = [0 8];
            app.NumberofentriesSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofentriesSliderValueChanged, true);
            app.NumberofentriesSlider.ValueChangingFcn = createCallbackFcn(app, @NumberofentriesSliderValueChanging, true);
            app.NumberofentriesSlider.MinorTicks = [];
            app.SourceIDEditFieldLabel = uilabel(app.LabmouseTab);
            app.SourceIDEditFieldLabel.Text = 'Source ID';
            app.SourceIDEditField = uieditfield(app.LabmouseTab, 'text');
            app.BreedingcagesindatabaseDropDownLabel = uilabel(app.LabmouseTab);
            app.BreedingcagesindatabaseDropDownLabel.Text = 'Breeding cages in database';
            app.BreedingcagesindatabaseDropDown = uidropdown(app.LabmouseTab);
            app.SetsourceIDButton = uibutton(app.LabmouseTab, 'push');
            app.SetsourceIDButton.ButtonPushedFcn = createCallbackFcn(app, @SetsourceIDButtonPushed, true);
            app.SetsourceIDButton.Text = 'Set source ID';
            app.SourceIDLabel = uilabel(app.LabmouseTab);
            app.SourceIDLabel.Text = 'Source ID';
            app.SourceIDEditField_E1 = uieditfield(app.LabmouseTab, 'text');
            app.SourceDropDown_E2 = uidropdown(app.LabmouseTab);
            app.SourceDropDown_E2.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown_E2.Value = 'breeding';
            app.SourceIDEditField_E2 = uieditfield(app.LabmouseTab, 'text');
            app.SourceDropDown_E3 = uidropdown(app.LabmouseTab);
            app.SourceDropDown_E3.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown_E3.Value = 'breeding';
            app.SourceIDEditField_E3 = uieditfield(app.LabmouseTab, 'text');
            app.SourceDropDown_E4 = uidropdown(app.LabmouseTab);
            app.SourceDropDown_E4.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown_E4.Value = 'breeding';
            app.SourceIDEditField_E4 = uieditfield(app.LabmouseTab, 'text');
            app.SourceDropDown_E5 = uidropdown(app.LabmouseTab);
            app.SourceDropDown_E5.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown_E5.Value = 'breeding';
            app.SourceIDEditField_E5 = uieditfield(app.LabmouseTab, 'text');
            app.SourceDropDown_E6 = uidropdown(app.LabmouseTab);
            app.SourceDropDown_E6.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown_E6.Value = 'breeding';
            app.SourceIDEditField_E6 = uieditfield(app.LabmouseTab, 'text');
            app.SourceDropDown_E7 = uidropdown(app.LabmouseTab);
            app.SourceDropDown_E7.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown_E7.Value = 'breeding';
            app.SourceIDEditField_E7 = uieditfield(app.LabmouseTab, 'text');
            app.SourceDropDown_E8 = uidropdown(app.LabmouseTab);
            app.SourceDropDown_E8.Items = {'breeding', 'vendor', 'other lab', 'other'};
            app.SourceDropDown_E8.Value = 'breeding';
            app.SourceIDEditField_E8 = uieditfield(app.LabmouseTab, 'text');
            app.SparrowTab = uitab(app.SpeciesTabGroup);
            app.SparrowTab.Title = 'Sparrow';
            app.PerhapssomedayLabel = uilabel(app.SparrowTab);
            app.PerhapssomedayLabel.Text = 'Perhaps someday';
            app.AddsingleanimaltodatabaseButton = uibutton(app.AddAnimalsTab, 'push');
            app.AddsingleanimaltodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddsingleanimaltodatabaseButtonPushed, true);
            app.AddsingleanimaltodatabaseButton.Text = 'Add single animal to database';
            app.StatusLampLabel_2 = uilabel(app.AddAnimalsTab);
            app.StatusLampLabel_2.Text = 'Status';
            app.StatusLamp_Animal = uilamp(app.AddAnimalsTab);
            app.AnimalInsertStatusEdit = uieditfield(app.AddAnimalsTab, 'text');
            app.AnimalInsertStatusEdit.Editable = 'off';
            app.AddmultipleanimalstodatabaseButton = uibutton(app.AddAnimalsTab, 'push');
            app.AddmultipleanimalstodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddmultipleanimalstodatabaseButtonPushed, true);
            app.AddmultipleanimalstodatabaseButton.Text = 'Add multiple animals to database';
            app.AddGenotypeTab = uitab(app.MainTabGroup);
            app.AddGenotypeTab.Title = 'Add Genotype';
            app.GenotypenameEditFieldLabel = uilabel(app.AddGenotypeTab);
            app.GenotypenameEditFieldLabel.Text = 'Genotype name';
            app.GenotypenameEditField = uieditfield(app.AddGenotypeTab, 'text');
            app.DescriptionTextAreaLabel = uilabel(app.AddGenotypeTab);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.AddGenotypeTab);
            app.SourceEditFieldLabel = uilabel(app.AddGenotypeTab);
            app.SourceEditFieldLabel.Text = 'Source';
            app.SourceEditField = uieditfield(app.AddGenotypeTab, 'text');
            app.CatalogEditFieldLabel = uilabel(app.AddGenotypeTab);
            app.CatalogEditFieldLabel.Text = 'Catalog #';
            app.CatalogEditField = uieditfield(app.AddGenotypeTab, 'text');
            app.AddgenotypetodatabaseButton = uibutton(app.AddGenotypeTab, 'push');
            app.AddgenotypetodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddgenotypetodatabaseButtonPushed, true);
            app.AddgenotypetodatabaseButton.Text = 'Add genotype to database';
            app.GenotypesindatabaseListBoxLabel = uilabel(app.AddGenotypeTab);
            app.GenotypesindatabaseListBoxLabel.Text = 'Genotypes in database';
            app.GenotypesindatabaseListBox = uilistbox(app.AddGenotypeTab);
            app.GenotypesindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @GenotypesindatabaseListBoxValueChanged, true);
            app.StatusLampLabel_3 = uilabel(app.AddGenotypeTab);
            app.StatusLampLabel_3.Text = 'Status';
            app.StatusLamp_Genotype = uilamp(app.AddGenotypeTab);
            app.GenotypeInsertStatusEdit = uieditfield(app.AddGenotypeTab, 'text');
            app.GenotypeInsertStatusEdit.Editable = 'off';
            app.AddSubstanceTab = uitab(app.MainTabGroup);
            app.AddSubstanceTab.Title = 'Add Substance';
            app.SubstancenameEditFieldLabel = uilabel(app.AddSubstanceTab);
            app.SubstancenameEditFieldLabel.Text = 'Substance name';
            app.SubstancenameEditField = uieditfield(app.AddSubstanceTab, 'text');
            app.DescriptionTextArea_2Label = uilabel(app.AddSubstanceTab);
            app.DescriptionTextArea_2Label.Text = 'Description';
            app.DescriptionTextArea_substance = uitextarea(app.AddSubstanceTab);
            app.SourceEditField_2Label = uilabel(app.AddSubstanceTab);
            app.SourceEditField_2Label.Text = 'Source';
            app.SourceEditField_substance = uieditfield(app.AddSubstanceTab, 'text');
            app.CatalogEditField_2Label = uilabel(app.AddSubstanceTab);
            app.CatalogEditField_2Label.Text = 'Catalog #';
            app.CatalogEditField_substance = uieditfield(app.AddSubstanceTab, 'text');
            app.StoragelocationLabel = uilabel(app.AddSubstanceTab);
            app.StoragelocationLabel.Text = 'Storage location';
            app.StorageLocationTextArea_substance = uitextarea(app.AddSubstanceTab);
            app.AddsubstancetodatabaseButton = uibutton(app.AddSubstanceTab, 'push');
            app.AddsubstancetodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddsubstancetodatabaseButtonPushed, true);
            app.AddsubstancetodatabaseButton.Text = 'Add substance to database';
            app.SubstancesindatabaseListBoxLabel = uilabel(app.AddSubstanceTab);
            app.SubstancesindatabaseListBoxLabel.Text = 'Substances in database';
            app.SubstancesindatabaseListBox = uilistbox(app.AddSubstanceTab);
            app.SubstancesindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @SubstancesindatabaseListBoxValueChanged, true);
            app.StatusLampLabel_4 = uilabel(app.AddSubstanceTab);
            app.StatusLampLabel_4.Text = 'Status';
            app.StatusLamp_Substance = uilamp(app.AddSubstanceTab);
            app.SubstanceInsertStatusEdit = uieditfield(app.AddSubstanceTab, 'text');
            app.SubstanceInsertStatusEdit.Editable = 'off';
            app.AddBrainAreaTab = uitab(app.MainTabGroup);
            app.AddBrainAreaTab.Title = 'Add Brain Area';
            app.AbbreviationEditFieldLabel = uilabel(app.AddBrainAreaTab);
            app.AbbreviationEditFieldLabel.Text = 'Abbreviation';
            app.AbbreviationEditField = uieditfield(app.AddBrainAreaTab, 'text');
            app.LongnameLabel = uilabel(app.AddBrainAreaTab);
            app.LongnameLabel.Text = 'Long name';
            app.BrainLongNameTextArea = uitextarea(app.AddBrainAreaTab);
            app.BrainareasindatabaseListBoxLabel = uilabel(app.AddBrainAreaTab);
            app.BrainareasindatabaseListBoxLabel.Text = 'Brain areas in database';
            app.BrainareasindatabaseListBox = uilistbox(app.AddBrainAreaTab);
            app.BrainareasindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @BrainareasindatabaseListBoxValueChanged, true);
            app.AddbrainareatodatabaseButton = uibutton(app.AddBrainAreaTab, 'push');
            app.AddbrainareatodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddbrainareatodatabaseButtonPushed, true);
            app.AddbrainareatodatabaseButton.Text = 'Add brain area to database';
            app.StatusLampLabel_5 = uilabel(app.AddBrainAreaTab);
            app.StatusLampLabel_5.Text = 'Status';
            app.StatusLamp_BrainArea = uilamp(app.AddBrainAreaTab);
            app.BrainAreaInsertStatusEdit = uieditfield(app.AddBrainAreaTab, 'text');
            app.BrainAreaInsertStatusEdit.Editable = 'off';
            app.AddProjectTab = uitab(app.MainTabGroup);
            app.AddProjectTab.Title = 'Add Project';
            app.AddprojecttodatabaseButton = uibutton(app.AddProjectTab, 'push');
            app.AddprojecttodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddprojecttodatabaseButtonPushed, true);
            app.AddprojecttodatabaseButton.Text = 'Add project to database';
            app.ProjectInsertStatusEdit = uieditfield(app.AddProjectTab, 'text');
            app.ProjectInsertStatusEdit.Editable = 'off';
            app.NameEditFieldLabel = uilabel(app.AddProjectTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.ProjectNameEditField = uieditfield(app.AddProjectTab, 'text');
            app.DescriptionLabel = uilabel(app.AddProjectTab);
            app.DescriptionLabel.Text = 'Description';
            app.ProjectDescriptionTextArea = uitextarea(app.AddProjectTab);
            app.ProjectsindatabaseListBoxLabel = uilabel(app.AddProjectTab);
            app.ProjectsindatabaseListBoxLabel.Text = 'Projects in database';
            app.ProjectsindatabaseListBox = uilistbox(app.AddProjectTab);
            app.ProjectsindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @ProjectsindatabaseListBoxValueChanged, true);
            app.StatusLampLabel_7 = uilabel(app.AddProjectTab);
            app.StatusLampLabel_7.Text = 'Status';
            app.StatusLamp_Project = uilamp(app.AddProjectTab);
            app.AddBehaviorExperimentTypeTab = uitab(app.MainTabGroup);
            app.AddBehaviorExperimentTypeTab.Title = 'Add Behavior Experiment Type';
            app.AddexperimenttypetodatabaseButton = uibutton(app.AddBehaviorExperimentTypeTab, 'push');
            app.AddexperimenttypetodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddexperimenttypetodatabaseButtonPushed, true);
            app.AddexperimenttypetodatabaseButton.Text = 'Add experiment type to database';
            app.BehaviorExpInsertStatusEdit = uieditfield(app.AddBehaviorExperimentTypeTab, 'text');
            app.BehaviorExpInsertStatusEdit.Editable = 'off';
            app.NameEditFieldLabel_2 = uilabel(app.AddBehaviorExperimentTypeTab);
            app.NameEditFieldLabel_2.Text = 'Name';
            app.BehaviorExpNameEditField = uieditfield(app.AddBehaviorExperimentTypeTab, 'text');
            app.DescriptionLabel_2 = uilabel(app.AddBehaviorExperimentTypeTab);
            app.DescriptionLabel_2.Text = 'Description';
            app.BehaviorExpDescriptionTextArea = uitextarea(app.AddBehaviorExperimentTypeTab);
            app.BehaviorexperimenttypesindatabaseListBoxLabel = uilabel(app.AddBehaviorExperimentTypeTab);
            app.BehaviorexperimenttypesindatabaseListBoxLabel.Text = 'Behavior experiment types in database';
            app.BehaviorexperimenttypesindatabaseListBox = uilistbox(app.AddBehaviorExperimentTypeTab);
            app.BehaviorexperimenttypesindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @BehaviorexperimenttypesindatabaseListBoxValueChanged, true);
            app.StatusLampLabel_8 = uilabel(app.AddBehaviorExperimentTypeTab);
            app.StatusLampLabel_8.Text = 'Status';
            app.StatusLamp_BehaviorExp = uilamp(app.AddBehaviorExperimentTypeTab);
            app.LogTab = uitab(app.MainTabGroup);
            app.LogTab.Title = 'Log';
            app.LogTextArea = uitextarea(app.LogTab);
            app.LogTextArea.Editable = 'off';
            app.ExporttotextfileButton = uibutton(app.LogTab, 'push');
            app.ExporttotextfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttotextfileButtonPushed, true);
            app.ExporttotextfileButton.Text = 'Export to text file';
            app.DeleteAnimalsTab = uitab(app.MainTabGroup);
            app.DeleteAnimalsTab.Title = 'Delete Animals';
            app.DeleteAnimal_button = uibutton(app.DeleteAnimalsTab, 'push');
            app.DeleteAnimal_button.ButtonPushedFcn = createCallbackFcn(app, @DeleteAnimal_buttonButtonPushed, true);
            app.DeleteAnimal_button.Text = 'Delete';
            app.StatusLampLabel_9 = uilabel(app.DeleteAnimalsTab);
            app.StatusLampLabel_9.Text = 'Status';
            app.StatusLamp_deleteAnimal = uilamp(app.DeleteAnimalsTab);
            app.EventInsertStatusEdit_deleteAnimal = uieditfield(app.DeleteAnimalsTab, 'text');
            app.EventInsertStatusEdit_deleteAnimal.Editable = 'off';
            app.DJIDEditFieldLabel = uilabel(app.DeleteAnimalsTab);
            app.DJIDEditFieldLabel.Text = 'DJID';
            app.DJIDEditField_deleteAnimal = uieditfield(app.DeleteAnimalsTab, 'numeric');
            app.PleaseusewithcautionLabel = uilabel(app.DeleteAnimalsTab);
            app.PleaseusewithcautionLabel.Text = 'Please use with caution!';
            % Show the figure after all components are created
            app.AnimalInterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnimalInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnimalInterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnimalInterfaceUIFigure)
        end
    end
end