classdef AnimalInterfaceV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnimalInterfaceV2UIFigure       matlab.ui.Figure
        MainTabGroup                    matlab.ui.container.TabGroup
        LoginTab                        matlab.ui.container.Tab
        LoginUserEditField              matlab.ui.control.EditField
        UserEditFieldLabel              matlab.ui.control.Label
        LoginButton                     matlab.ui.control.Button
        PasswordEditField               matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        AnimalLoggerTab                 matlab.ui.container.Tab
        ExporttoExcelButton             matlab.ui.control.Button
        EventInsertStatusEdit           matlab.ui.control.EditField
        StatusLamp_Logger               matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        ViewlogsLabel                   matlab.ui.control.Label
        EventTimeH                      matlab.ui.control.DropDown
        AlleventsforselectedanimalButton  matlab.ui.control.Button
        TimeLabel_2                     matlab.ui.control.Label
        EventTimeAMPM                   matlab.ui.control.DropDown
        EventTimeM                      matlab.ui.control.DropDown
        EventNotesTextArea              matlab.ui.control.TextArea
        NotesTextArea_2Label            matlab.ui.control.Label
        SubmitevententryButton          matlab.ui.control.Button
        EventdateDatePicker             matlab.ui.control.DatePicker
        EventdateDatePickerLabel        matlab.ui.control.Label
        PersonDropDown                  matlab.ui.control.DropDown
        PersonDropDownLabel             matlab.ui.control.Label
        AnimalSelectionPanel            matlab.ui.container.Panel
        ReloadButton                    matlab.ui.control.Button
        SearchTypeButtonGroup           matlab.ui.container.ButtonGroup
        TagButton                       matlab.ui.control.RadioButton
        CageButton                      matlab.ui.control.RadioButton
        DJIDButton                      matlab.ui.control.RadioButton
        CageNumSearchEditField          matlab.ui.control.EditField
        Search_TagIDEditField           matlab.ui.control.EditField
        Search_DJIDEditField            matlab.ui.control.EditField
        ResetButton                     matlab.ui.control.Button
        FilterPanel                     matlab.ui.container.Panel
        ProjectreservationButtonGroup   matlab.ui.container.ButtonGroup
        NoButton_6                      matlab.ui.control.RadioButton
        YesButton_6                     matlab.ui.control.RadioButton
        AnyButton_6                     matlab.ui.control.RadioButton
        StrainDropDown                  matlab.ui.control.DropDown
        StrainDropDownLabel             matlab.ui.control.Label
        Filter_BgDropDown               matlab.ui.control.DropDown
        BgLabel                         matlab.ui.control.Label
        IPInjectionButtonGroup          matlab.ui.container.ButtonGroup
        NoButton_5                      matlab.ui.control.RadioButton
        YesButton_5                     matlab.ui.control.RadioButton
        AnyButton_5                     matlab.ui.control.RadioButton
        BehaviorsessionButtonGroup      matlab.ui.container.ButtonGroup
        NoButton_4                      matlab.ui.control.RadioButton
        YesButton_4                     matlab.ui.control.RadioButton
        AnyButton_4                     matlab.ui.control.RadioButton
        RigreservationButtonGroup       matlab.ui.container.ButtonGroup
        NoButton_3                      matlab.ui.control.RadioButton
        YesButton_3                     matlab.ui.control.RadioButton
        AnyButton_3                     matlab.ui.control.RadioButton
        BrainInjectionButtonGroup       matlab.ui.container.ButtonGroup
        NoButton_2                      matlab.ui.control.RadioButton
        YesButton_2                     matlab.ui.control.RadioButton
        AnyButton_2                     matlab.ui.control.RadioButton
        EyeInjectionButtonGroup         matlab.ui.container.ButtonGroup
        NoButton                        matlab.ui.control.RadioButton
        YesButton                       matlab.ui.control.RadioButton
        AnyButton                       matlab.ui.control.RadioButton
        AgerangeweeksPanel              matlab.ui.container.Panel
        ageHigh_edit                    matlab.ui.control.EditField
        ageLow_edit                     matlab.ui.control.EditField
        toLabel                         matlab.ui.control.Label
        Filter_ProjectDropDown          matlab.ui.control.DropDown
        ProjectLabel                    matlab.ui.control.Label
        Filter_SexDropDown              matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        Filter_GenotypeStatusDropDown   matlab.ui.control.DropDown
        StatusDropDownLabel             matlab.ui.control.Label
        Filter_GenotypeDropDown         matlab.ui.control.DropDown
        AlleleLabel                     matlab.ui.control.Label
        SearchButton                    matlab.ui.control.Button
        EventTabGroup                   matlab.ui.container.TabGroup
        GenotypedTab                    matlab.ui.container.Tab
        ForunclearresultAllele1ambiguousAllele2ambiguousLabel  matlab.ui.control.Label
        ForahomozygoteAllele1XAllele2XLabel  matlab.ui.control.Label
        FornoncarrierAllele1WTAllele2WTLabel  matlab.ui.control.Label
        ForacarrierAllele1XAllele2ambiguousLabel  matlab.ui.control.Label
        ForahetAllele1XAllele2WTLabel   matlab.ui.control.Label
        EnableanalleleviathestrainalleletableLabel  matlab.ui.control.Label
        EnablealocusviatheallelelocusmapLabel  matlab.ui.control.Label
        Allele2DropDown_4               matlab.ui.control.DropDown
        Allele2DropDown_3Label          matlab.ui.control.Label
        Allele2DropDown_3               matlab.ui.control.DropDown
        Allele2DropDown_2Label          matlab.ui.control.Label
        Allele1DropDown_4               matlab.ui.control.DropDown
        Allele1DropDown_3Label          matlab.ui.control.Label
        LocusDropDown_4                 matlab.ui.control.DropDown
        LocusDropDown_2Label_5          matlab.ui.control.Label
        Allele1DropDown_3               matlab.ui.control.DropDown
        Allele1DropDown_2Label          matlab.ui.control.Label
        LocusDropDown_3                 matlab.ui.control.DropDown
        LocusDropDown_2Label_4          matlab.ui.control.Label
        Allele2DropDown_2               matlab.ui.control.DropDown
        Allele2DropDown_4Label          matlab.ui.control.Label
        Allele2DropDown_1               matlab.ui.control.DropDown
        Allele2DropDownLabel            matlab.ui.control.Label
        Allele1DropDown_2               matlab.ui.control.DropDown
        Allele1DropDown_4Label          matlab.ui.control.Label
        LocusDropDown_2                 matlab.ui.control.DropDown
        LocusDropDown_2Label            matlab.ui.control.Label
        GenotypeEntriesSpinner          matlab.ui.control.Spinner
        EntriesSpinnerLabel             matlab.ui.control.Label
        Allele1DropDown_1               matlab.ui.control.DropDown
        SourceDropDown_2Label_3         matlab.ui.control.Label
        LocusDropDown_1                 matlab.ui.control.DropDown
        SourceDropDown_2Label_2         matlab.ui.control.Label
        GenotypeSourceDropDown          matlab.ui.control.DropDown
        SourceDropDown_2Label           matlab.ui.control.Label
        DeceasedTab                     matlab.ui.container.Tab
        DeceasedReasonDropDown          matlab.ui.control.DropDown
        ReasonDropDownLabel             matlab.ui.control.Label
        AssigncageTab                   matlab.ui.container.Tab
        RoomDropDown_logger             matlab.ui.control.DropDown
        RoomDropDown_2Label             matlab.ui.control.Label
        TocageEditField                 matlab.ui.control.EditField
        TocageEditFieldLabel            matlab.ui.control.Label
        MoveReasonDropDown              matlab.ui.control.DropDown
        ReasonDropDown_2Label           matlab.ui.control.Label
        MovecageroomTab                 matlab.ui.container.Tab
        ToroomDropDown                  matlab.ui.control.DropDown
        ToroomDropDownLabel             matlab.ui.control.Label
        MoveCagenumberEditField         matlab.ui.control.NumericEditField
        CagenumberEditFieldLabel        matlab.ui.control.Label
        TagTab                          matlab.ui.container.Tab
        TagearDropDown                  matlab.ui.control.DropDown
        TagearDropDownLabel             matlab.ui.control.Label
        EarpunchDropDown                matlab.ui.control.DropDown
        EarpunchDropDownLabel           matlab.ui.control.Label
        TagEditField                    matlab.ui.control.EditField
        TagEditFieldLabel               matlab.ui.control.Label
        IPinjectionTab                  matlab.ui.container.Tab
        IP_inj_ConcentrationEditField   matlab.ui.control.EditField
        ConcentrationmgKgEditFieldLabel_2  matlab.ui.control.Label
        SubstanceDropDown_IP            matlab.ui.control.DropDown
        SubstanceDropDownLabel          matlab.ui.control.Label
        EyeinjectionTab                 matlab.ui.container.Tab
        Dilution_EyeEditField           matlab.ui.control.EditField
        Dilution1XEditFieldLabel_2      matlab.ui.control.Label
        WhichEyeDropDown                matlab.ui.control.DropDown
        EyeDropDownLabel                matlab.ui.control.Label
        SubstanceDropDown_Eye           matlab.ui.control.DropDown
        SubstanceDropDownLabel_2        matlab.ui.control.Label
        BraininjectionTab               matlab.ui.container.Tab
        AngleEditField                  matlab.ui.control.EditField
        AngleEditField_2Label           matlab.ui.control.Label
        DVEditField                     matlab.ui.control.EditField
        DVEditField_2Label              matlab.ui.control.Label
        MLEditField                     matlab.ui.control.EditField
        MLEditField_2Label              matlab.ui.control.Label
        APEditField                     matlab.ui.control.EditField
        APEditField_2Label              matlab.ui.control.Label
        Dilution_BrainEditField         matlab.ui.control.EditField
        Dilution1XEditFieldLabel_3      matlab.ui.control.Label
        HemisphereDropDown              matlab.ui.control.DropDown
        HemisphereDropDownLabel         matlab.ui.control.Label
        BrainTargetDropDown             matlab.ui.control.DropDown
        TargetDropDownLabel             matlab.ui.control.Label
        CoordinatesLabel                matlab.ui.control.Label
        SubstanceDropDown_Brain         matlab.ui.control.DropDown
        SubstanceDropDown_3Label        matlab.ui.control.Label
        EyesutureTab                    matlab.ui.container.Tab
        WhicheyeDropDown                matlab.ui.control.DropDown
        WhicheyeDropDownLabel           matlab.ui.control.Label
        FeedTab                         matlab.ui.container.Tab
        SubstanceDropDown_Feed          matlab.ui.control.DropDown
        SubstanceDropDownLabel_3        matlab.ui.control.Label
        SocialbehaviorsessionTab        matlab.ui.container.Tab
        WindowCStim                     matlab.ui.control.DropDown
        WindowBStim                     matlab.ui.control.DropDown
        WindowAStim                     matlab.ui.control.DropDown
        CLabel                          matlab.ui.control.Label
        BLabel                          matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        WindowLabel                     matlab.ui.control.Label
        DJIDLabel                       matlab.ui.control.Label
        TestanimaltypeDropDown          matlab.ui.control.DropDown
        TestanimaltypeDropDownLabel     matlab.ui.control.Label
        AnimalCEditField                matlab.ui.control.EditField
        AnimalBEditField                matlab.ui.control.EditField
        AnimalAEditField                matlab.ui.control.EditField
        ExperimenttypeDropDown          matlab.ui.control.DropDown
        ExperimenttypeDropDownLabel     matlab.ui.control.Label
        Lamp_isRecorded                 matlab.ui.control.Lamp
        StimulusLabel                   matlab.ui.control.Label
        RecordBehavior_switch           matlab.ui.control.RockerSwitch
        ReserveforprojectTab            matlab.ui.container.Tab
        ProjectDropDown                 matlab.ui.control.DropDown
        ProjectDropDownLabel            matlab.ui.control.Label
        ReserveforrigTab                matlab.ui.container.Tab
        RignameDropDown                 matlab.ui.control.DropDown
        RignameDropDownLabel            matlab.ui.control.Label
        PairbreedersTab_2               matlab.ui.container.Tab
        Label2                          matlab.ui.control.Label
        StrainnameisautomaticallydeterminedbytheLabel_2  matlab.ui.control.Label
        StrainnamewillbeleftblankifnotautomaticallyfoundLabel  matlab.ui.control.Label
        StrainnameisautomaticallydeterminedbytheLabel  matlab.ui.control.Label
        editButton_2                    matlab.ui.control.StateButton
        StrainnameDropDown              matlab.ui.control.DropDown
        StrainnameDropDownLabel         matlab.ui.control.Label
        RoomDropDown_Pair               matlab.ui.control.DropDown
        RoomDropDown_2Label_5           matlab.ui.control.Label
        NewcagenumberPairEditField      matlab.ui.control.EditField
        CagenumberLabel                 matlab.ui.control.Label
        FemaleDJIDPairEditField         matlab.ui.control.EditField
        FemaleDJIDEditFieldLabel        matlab.ui.control.Label
        MaleDJIDPairEditField           matlab.ui.control.EditField
        MaleDJIDEditFieldLabel          matlab.ui.control.Label
        OtherprocedureTab               matlab.ui.container.Tab
        OtherProcNameEdit               matlab.ui.control.EditField
        NameofprocedureputadditionalinfoinNotesEditFieldLabel  matlab.ui.control.Label
        AnimalTable                     matlab.ui.control.Table
        BreedersTab                     matlab.ui.container.Tab
        StrainDropDown_BreedersTab      matlab.ui.control.DropDown
        GenotypeDropDown_2Label_2       matlab.ui.control.Label
        GenotypeDropDown_BreedersTab    matlab.ui.control.DropDown
        AlleleLabel_2                   matlab.ui.control.Label
        BreedingpairsLabel              matlab.ui.control.Label
        BreederPairs_status             matlab.ui.control.Label
        SearchTypeButtonGroup_breeders  matlab.ui.container.ButtonGroup
        CageButton_breeders             matlab.ui.control.RadioButton
        DJIDButton_breeders             matlab.ui.control.RadioButton
        CageNumSearchEditField_breeders  matlab.ui.control.EditField
        Search_DJIDEditField_breeders   matlab.ui.control.EditField
        ResetButton_breeders            matlab.ui.control.Button
        SearchButton_breeders           matlab.ui.control.Button
        EventTabGroup_breeders          matlab.ui.container.TabGroup
        SeparatebreedingpairTab         matlab.ui.container.Tab
        RoomDropDown_breeders_sepFemale  matlab.ui.control.DropDown
        RoomDropDown_2Label_4           matlab.ui.control.Label
        RoomDropDown_breeders_sepMale   matlab.ui.control.DropDown
        RoomDropDown_2Label_3           matlab.ui.control.Label
        CageforfemaleEditField          matlab.ui.control.EditField
        CageforfemaleEditFieldLabel     matlab.ui.control.Label
        CageformaleEditField            matlab.ui.control.EditField
        CageformaleEditFieldLabel       matlab.ui.control.Label
        SacrificebreedingpairTab        matlab.ui.container.Tab
        DeceasedReasonDropDown_BothBreeders  matlab.ui.control.DropDown
        ReasonDropDownLabel_3           matlab.ui.control.Label
        RemovemalesacrificeTab          matlab.ui.container.Tab
        DeceasedReasonDropDown_MaleBreeder  matlab.ui.control.DropDown
        ReasonDropDownLabel_2           matlab.ui.control.Label
        SeparateharemTab                matlab.ui.container.Tab
        DJIDEditField_harem_male        matlab.ui.control.NumericEditField
        DJIDEditFieldLabel_3            matlab.ui.control.Label
        RoomDropDown_breeders_harem_male  matlab.ui.control.DropDown
        RoomDropDown_2Label_9           matlab.ui.control.Label
        CageforfemaleEditField_harem_male  matlab.ui.control.EditField
        CageforfemaleEditFieldLabel_4   matlab.ui.control.Label
        FemalesLabel                    matlab.ui.control.Label
        MaleLabel                       matlab.ui.control.Label
        DJIDEditField_harem_female_2    matlab.ui.control.NumericEditField
        DJIDEditFieldLabel_2            matlab.ui.control.Label
        RoomDropDown_breeders_harem_female2  matlab.ui.control.DropDown
        RoomDropDown_2Label_8           matlab.ui.control.Label
        CageforfemaleEditField_harem2   matlab.ui.control.EditField
        CageforfemaleEditFieldLabel_3   matlab.ui.control.Label
        DJIDEditField_harem_female_1    matlab.ui.control.NumericEditField
        DJIDEditFieldLabel              matlab.ui.control.Label
        RoomDropDown_breeders_harem_female1  matlab.ui.control.DropDown
        RoomDropDown_2Label_7           matlab.ui.control.Label
        CageforfemaleEditField_harem1   matlab.ui.control.EditField
        CageforfemaleEditFieldLabel_2   matlab.ui.control.Label
        RemovemaleassignnewcageTab      matlab.ui.container.Tab
        RoomDropDown_MaleBreeder        matlab.ui.control.DropDown
        RoomDropDown_2Label_6           matlab.ui.control.Label
        TocageEditField_MaleBreeder     matlab.ui.control.EditField
        TocageEditFieldLabel_2          matlab.ui.control.Label
        ChangecagenumberTab             matlab.ui.container.Tab
        NewcagenumberEditField          matlab.ui.control.EditField
        NewcagenumberEditFieldLabel     matlab.ui.control.Label
        RoomDropDown_BreedingPair       matlab.ui.control.DropDown
        RoomDropDown_BreedingPairLabel  matlab.ui.control.Label
        LogbirthTab                     matlab.ui.container.Tab
        LogweaningTab                   matlab.ui.container.Tab
        NumberofpupsEditField_weaned    matlab.ui.control.EditField
        NumberofpupsLabel               matlab.ui.control.Label
        EventNotesTextArea_breeders     matlab.ui.control.TextArea
        NotesTextArea_2Label_2          matlab.ui.control.Label
        EventdateDatePicker_breeders    matlab.ui.control.DatePicker
        EventdateDatePicker_2Label      matlab.ui.control.Label
        PersonDropDown_breeders         matlab.ui.control.DropDown
        PersonDropDown_2Label           matlab.ui.control.Label
        EventInsertStatusEdit_breeders  matlab.ui.control.EditField
        StatusLamp_breeders             matlab.ui.control.Lamp
        StatusLampLabel_6               matlab.ui.control.Label
        SubmitevententryButton_breeders  matlab.ui.control.Button
        ExporttoExcelButton_breeders    matlab.ui.control.Button
        BreederCagesTable               matlab.ui.control.Table
        AddAnimalsTab                   matlab.ui.container.Tab
        AddmultipleanimalstodatabaseButton  matlab.ui.control.Button
        AnimalInsertStatusEdit          matlab.ui.control.EditField
        StatusLamp_Animal               matlab.ui.control.Lamp
        StatusLampLabel_2               matlab.ui.control.Label
        AddsingleanimaltodatabaseButton  matlab.ui.control.Button
        SpeciesTabGroup                 matlab.ui.container.TabGroup
        MouseTab                        matlab.ui.container.Tab
        CageEntryDatePicker             matlab.ui.control.DatePicker
        CageassignmentdateLabel_2       matlab.ui.control.Label
        MaleRoomDropDown_2              matlab.ui.control.DropDown
        RoomDropDown_6Label_4           matlab.ui.control.Label
        MaleCageEditField_2             matlab.ui.control.EditField
        MalecageLabel_3                 matlab.ui.control.Label
        SourceLabel_2                   matlab.ui.control.Label
        SourceLabel                     matlab.ui.control.Label
        CageEntryDatePicker_multi       matlab.ui.control.DatePicker
        CageassignmentdateLabel         matlab.ui.control.Label
        FemaleCageEditField             matlab.ui.control.EditField
        MalecageLabel_2                 matlab.ui.control.Label
        FemaleRoomDropDown              matlab.ui.control.DropDown
        RoomDropDown_6Label_3           matlab.ui.control.Label
        TagIDEditFieldLabel_5           matlab.ui.control.Label
        NumberoffemalesSpinner          matlab.ui.control.Spinner
        NumberoffemalesSpinnerLabel     matlab.ui.control.Label
        NumberofmalesSpinner            matlab.ui.control.Spinner
        NumberofmalesSpinnerLabel       matlab.ui.control.Label
        AnimalSourceDropDown_multi      matlab.ui.control.DropDown
        SourceTypeDropDown_multi        matlab.ui.control.DropDown
        SourcetypeLabel_4               matlab.ui.control.Label
        TagIDEditFieldLabel_2           matlab.ui.control.Label
        DOBDatePicker_multi             matlab.ui.control.DatePicker
        DOBDatePicker_2Label            matlab.ui.control.Label
        BackgroundDropDown_multi        matlab.ui.control.DropDown
        StrainLabel                     matlab.ui.control.Label
        SetmultitomatchButton           matlab.ui.control.Button
        AnimalSourceDropDown            matlab.ui.control.DropDown
        ExternalInfoEditField           matlab.ui.control.EditField
        SourceinfoLabel                 matlab.ui.control.Label
        MultipleentryLabel              matlab.ui.control.Label
        SingleentryLabel                matlab.ui.control.Label
        CageEditField                   matlab.ui.control.EditField
        CageEditFieldLabel              matlab.ui.control.Label
        SourceTypeDropDown              matlab.ui.control.DropDown
        SourcetypeLabel                 matlab.ui.control.Label
        TagIDEditField                  matlab.ui.control.EditField
        TagIDEditFieldLabel             matlab.ui.control.Label
        RoomDropDown                    matlab.ui.control.DropDown
        RoomDropDownLabel               matlab.ui.control.Label
        TagearButtonGroup               matlab.ui.container.ButtonGroup
        UnknownButton_2                 matlab.ui.control.RadioButton
        RButton_2                       matlab.ui.control.RadioButton
        LButton_2                       matlab.ui.control.RadioButton
        NoneButton_2                    matlab.ui.control.RadioButton
        DOBDatePicker                   matlab.ui.control.DatePicker
        DOBDatePickerLabel              matlab.ui.control.Label
        StrainDropDown_2                matlab.ui.control.DropDown
        StrainDropDown_2Label           matlab.ui.control.Label
        DOB_known_switch                matlab.ui.control.Switch
        PunchearButtonGroup             matlab.ui.container.ButtonGroup
        BothButton                      matlab.ui.control.RadioButton
        RButton                         matlab.ui.control.RadioButton
        LButton                         matlab.ui.control.RadioButton
        NoneButton                      matlab.ui.control.RadioButton
        SexButtonGroup                  matlab.ui.container.ButtonGroup
        FemaleButton                    matlab.ui.control.RadioButton
        MaleButton                      matlab.ui.control.RadioButton
        UnknownButton                   matlab.ui.control.RadioButton
        tagged_switch                   matlab.ui.control.Switch
        ChameleonTab                    matlab.ui.container.Tab
        PerhapssomedayLabel             matlab.ui.control.Label
        AddStrainTab                    matlab.ui.container.Tab
        NOTEputpromotersfirstthenreportersLabel  matlab.ui.control.Label
        CtrlclicktoselectmultipleallelesLabel  matlab.ui.control.Label
        AllelesListBox                  matlab.ui.control.ListBox
        AllelesListBoxLabel             matlab.ui.control.Label
        BackgroundDropDown_2            matlab.ui.control.DropDown
        BackgroundDropDown_2Label_2     matlab.ui.control.Label
        MakeanalleleactiveforittoappearhereLabel  matlab.ui.control.Label
        editButton                      matlab.ui.control.StateButton
        BydefaultnamewillpopulateautomaticallyfromallelesLabel  matlab.ui.control.Label
        SourceDropDown                  matlab.ui.control.DropDown
        SourceDropDownLabel             matlab.ui.control.Label
        ActivateStrainButton            matlab.ui.control.Button
        DeactivateStrainButton          matlab.ui.control.Button
        InactiveStrainListBox           matlab.ui.control.ListBox
        StrainsindatabaseLabel_2        matlab.ui.control.Label
        GenotypenameEditFieldLabel_4    matlab.ui.control.Label
        CatalogEditField_Strain         matlab.ui.control.EditField
        CatalogEditField_2Label_2       matlab.ui.control.Label
        StraintypeButtonGroup           matlab.ui.container.ButtonGroup
        BreedingButton                  matlab.ui.control.RadioButton
        CollaboratorButton              matlab.ui.control.RadioButton
        VendorButton                    matlab.ui.control.RadioButton
        ActiveStrainListBox             matlab.ui.control.ListBox
        StrainsindatabaseLabel          matlab.ui.control.Label
        StrainInsertStatusEdit          matlab.ui.control.EditField
        StatusLamp_Strain               matlab.ui.control.Lamp
        AddstraintodatabaseButton       matlab.ui.control.Button
        StrainNameEditField             matlab.ui.control.EditField
        GenotypenameEditFieldLabel_2    matlab.ui.control.Label
        AddLocusAlleleTab               matlab.ui.container.Tab
        cMLabel                         matlab.ui.control.Label
        ActivateAlleleButton            matlab.ui.control.Button
        DeactivateAlleleButton          matlab.ui.control.Button
        InactiveallelesindatabaseListBox  matlab.ui.control.ListBox
        InactiveallelesindatabaseListBoxLabel  matlab.ui.control.Label
        StatusLamp_LocusAlleleMap       matlab.ui.control.Lamp
        StatusLampLabel_14              matlab.ui.control.Label
        LocusAlleleMapInsertStatusEdit  matlab.ui.control.EditField
        AddtomapButton                  matlab.ui.control.Button
        AlleleDropDown_forMap           matlab.ui.control.DropDown
        AlleleDropDownLabel             matlab.ui.control.Label
        LocusDropDown_forMap            matlab.ui.control.DropDown
        LocusDropDown_6Label            matlab.ui.control.Label
        AlleleLocusMapLabel             matlab.ui.control.Label
        AlleleLocusTable                matlab.ui.control.Table
        PositionEditField               matlab.ui.control.EditField
        PositionEditField_2Label        matlab.ui.control.Label
        ChromosomeEditField             matlab.ui.control.EditField
        ChromosomeEditField_2Label      matlab.ui.control.Label
        StatusLamp_Locus                matlab.ui.control.Lamp
        StatusLampLabel_13              matlab.ui.control.Label
        DescriptionTextArea_Locus       matlab.ui.control.TextArea
        DescriptionTextArea_4Label      matlab.ui.control.Label
        LocusnameEditField              matlab.ui.control.EditField
        LocusnameEditField_2Label       matlab.ui.control.Label
        LocusInsertStatusEdit           matlab.ui.control.EditField
        AddgenelocusButton              matlab.ui.control.Button
        LociindatabaseListBox           matlab.ui.control.ListBox
        LociindatabaseListBoxLabel      matlab.ui.control.Label
        AlleleInsertStatusEdit          matlab.ui.control.EditField
        StatusLamp_Allele               matlab.ui.control.Lamp
        StatusLampLabel_12              matlab.ui.control.Label
        ActiveallelesindatabaseListBox  matlab.ui.control.ListBox
        ActiveallelesindatabaseListBoxLabel  matlab.ui.control.Label
        AddalleleButton_LocusAlleleTab  matlab.ui.control.Button
        DescriptionTextArea_Allele      matlab.ui.control.TextArea
        DescriptionTextArea_3Label      matlab.ui.control.Label
        AllelenameEditField             matlab.ui.control.EditField
        AllelenameEditField_2Label      matlab.ui.control.Label
        AddSubstanceTab                 matlab.ui.container.Tab
        VendorOrCollaborator_ButtonGroup  matlab.ui.container.ButtonGroup
        CollaboratorButton_substance    matlab.ui.control.RadioButton
        VendorButton_substance          matlab.ui.control.RadioButton
        SourceDropDown_substance        matlab.ui.control.DropDown
        SourceDropDown_4Label           matlab.ui.control.Label
        ActivateSubstanceButton         matlab.ui.control.Button
        DeactivateSubstanceButton       matlab.ui.control.Button
        ActivesubstancesindatabaseListBoxLabel_2  matlab.ui.control.Label
        InactiveSubstancesindatabaseListBox  matlab.ui.control.ListBox
        SubstanceInsertStatusEdit       matlab.ui.control.EditField
        StatusLamp_Substance            matlab.ui.control.Lamp
        StatusLampLabel_4               matlab.ui.control.Label
        ActivesubstancesindatabaseListBox  matlab.ui.control.ListBox
        ActivesubstancesindatabaseListBoxLabel  matlab.ui.control.Label
        AddsubstancetodatabaseButton    matlab.ui.control.Button
        StorageLocationTextArea_substance  matlab.ui.control.TextArea
        StoragelocationLabel            matlab.ui.control.Label
        CatalogEditField_substance      matlab.ui.control.EditField
        CatalogEditField_2Label         matlab.ui.control.Label
        DescriptionTextArea_substance   matlab.ui.control.TextArea
        DescriptionTextArea_2Label      matlab.ui.control.Label
        SubstancenameEditField          matlab.ui.control.EditField
        SubstancenameEditFieldLabel     matlab.ui.control.Label
        AddBrainAreaTab                 matlab.ui.container.Tab
        BrainAreaInsertStatusEdit       matlab.ui.control.EditField
        StatusLamp_BrainArea            matlab.ui.control.Lamp
        StatusLampLabel_5               matlab.ui.control.Label
        AddbrainareatodatabaseButton    matlab.ui.control.Button
        BrainareasindatabaseListBox     matlab.ui.control.ListBox
        BrainareasindatabaseListBoxLabel  matlab.ui.control.Label
        BrainLongNameTextArea           matlab.ui.control.TextArea
        LongnameLabel                   matlab.ui.control.Label
        AbbreviationEditField           matlab.ui.control.EditField
        AbbreviationEditFieldLabel      matlab.ui.control.Label
        AddProjectTab                   matlab.ui.container.Tab
        ActivateProjectButton           matlab.ui.control.Button
        DeactivateProjectButton         matlab.ui.control.Button
        InactiveprojectsindatabaseListBox  matlab.ui.control.ListBox
        InactiveprojectsindatabaseListBoxLabel  matlab.ui.control.Label
        StatusLamp_Project              matlab.ui.control.Lamp
        StatusLampLabel_7               matlab.ui.control.Label
        ActiveprojectsindatabaseListBox  matlab.ui.control.ListBox
        ActiveprojectsindatabaseListBoxLabel  matlab.ui.control.Label
        ProjectDescriptionTextArea      matlab.ui.control.TextArea
        DescriptionLabel                matlab.ui.control.Label
        ProjectNameEditField            matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        ProjectInsertStatusEdit         matlab.ui.control.EditField
        AddprojecttodatabaseButton      matlab.ui.control.Button
        AddSourceTab                    matlab.ui.container.Tab
        SourceStatusEditField           matlab.ui.control.EditField
        SourceStatusLamp                matlab.ui.control.Lamp
        AddsourceButton                 matlab.ui.control.Button
        SourceField_2_label             matlab.ui.control.Label
        SourceField_1_label             matlab.ui.control.Label
        SourceEditField_2               matlab.ui.control.EditField
        SourceEditField_1               matlab.ui.control.EditField
        ActivateButton                  matlab.ui.control.Button
        DeactivateButton                matlab.ui.control.Button
        InactiveSourceListBox           matlab.ui.control.ListBox
        InactiveListBoxLabel            matlab.ui.control.Label
        ActiveSourceListBox             matlab.ui.control.ListBox
        ActiveListBoxLabel              matlab.ui.control.Label
        SourcetypeButtonGroup           matlab.ui.container.ButtonGroup
        CollaboratorButton_2            matlab.ui.control.RadioButton
        GenotypeSourceButton            matlab.ui.control.RadioButton
        VendorButton_2                  matlab.ui.control.RadioButton
        TODOsources149arevendors5199aregenotyping101Label  matlab.ui.control.Label
        AddBehaviorExperimentTypeStimulusTab  matlab.ui.container.Tab
        NeedsanimalIDSwitch             matlab.ui.control.Switch
        NeedsanimalIDSwitchLabel        matlab.ui.control.Label
        BehaviorExpStimDescriptionTextArea  matlab.ui.control.TextArea
        DescriptionLabel_3              matlab.ui.control.Label
        BehaviorExpStimNameEditField    matlab.ui.control.EditField
        NameEditFieldLabel_4            matlab.ui.control.Label
        AddvisualstimtypetodatabaseButton  matlab.ui.control.Button
        BehaviorExpInsertStimStatusEdit  matlab.ui.control.EditField
        StatusLamp_BehaviorExpStim      matlab.ui.control.Lamp
        StatusLampLabel_15              matlab.ui.control.Label
        VisualstimuliindatabaseListBox  matlab.ui.control.ListBox
        VisualstimuliindatabaseListBoxLabel  matlab.ui.control.Label
        StatusLamp_BehaviorExp          matlab.ui.control.Lamp
        StatusLampLabel_8               matlab.ui.control.Label
        BehaviorexperimenttypesindatabaseListBox  matlab.ui.control.ListBox
        BehaviorexperimenttypesindatabaseListBoxLabel  matlab.ui.control.Label
        BehaviorExpDescriptionTextArea  matlab.ui.control.TextArea
        DescriptionLabel_2              matlab.ui.control.Label
        BehaviorExpNameEditField        matlab.ui.control.EditField
        NameEditFieldLabel_2            matlab.ui.control.Label
        BehaviorExpInsertStatusEdit     matlab.ui.control.EditField
        AddexperimenttypetodatabaseButton  matlab.ui.control.Button
        LogTab                          matlab.ui.container.Tab
        ExporttotextfileButton          matlab.ui.control.Button
        LogTextArea                     matlab.ui.control.TextArea
        LookupAnimalTab                 matlab.ui.container.Tab
        EventlistTextArea               matlab.ui.control.TextArea
        EventlistTextAreaLabel          matlab.ui.control.Label
        SearchButton_lookup             matlab.ui.control.Button
        DJIDEditField_lookup            matlab.ui.control.EditField
        DJIDEditFieldLabel_4            matlab.ui.control.Label
        TODOListTab                     matlab.ui.container.Tab
        ExporttoExcelButton_2           matlab.ui.control.Button
        EditField_TODOquery             matlab.ui.control.EditField
        StatusLamp_TODOquery            matlab.ui.control.Lamp
        StatusLampLabel_10              matlab.ui.control.Label
        QuerynameLabel                  matlab.ui.control.Label
        QueryResultTable                matlab.ui.control.Table
        SubmitnewqueryButton            matlab.ui.control.Button
        QuerynameEditField              matlab.ui.control.EditField
        NameEditFieldLabel_3            matlab.ui.control.Label
        QuerytextTextArea               matlab.ui.control.TextArea
        QuerytextTextAreaLabel          matlab.ui.control.Label
        QueriesListBox                  matlab.ui.control.ListBox
        QueriesListBoxLabel             matlab.ui.control.Label
        CagelookupTab                   matlab.ui.container.Tab
        CageLabel                       matlab.ui.control.Label
        DJIDsListBox                    matlab.ui.control.ListBox
        DJIDsListBoxLabel               matlab.ui.control.Label
        FindcageButton                  matlab.ui.control.Button
        CagenumberEditField             matlab.ui.control.NumericEditField
        CagenumberEditFieldLabel_2      matlab.ui.control.Label
    end


    properties (Access = private)
        accessible_tabs = {'Login'} % Which tabs user is allowed to select
        user_password=''; %property that will store the password entered by user
        prev_password_length=0;
        selected_animal_id = [];% animal_id for current selection
        login_user; % User who logged in
        ax_ageslider % axis for age slider
        l_ageslider  % line for age slider
        filter_ageLow = 0; % lower age limit
        filter_ageHigh = 200; % upper age limit
        filter_genotype_status = 'All'; % genotype_status to filter
        filter_struct = []; % struct as query object from filter panel
        filter_project = 'All'; % reserved project to filter
        connection % DJ connection object
        cal_access_token = ''; %access token for google calendar
        cal_access_token_time = []; % time of last access token request
        cal_expiration = 3599; % seconds until expiration of access token
        selected_breeder_cage % selected breeder cage in Breeders tab
        todo_queries % Description
        animal_event_list = {...
            'AssignCage', ...
            'Birth', ...
            'BrainInjection', ...
            'Deceased', ...
            'EyeInjection', ...
            'GenotypeResult', ...
            'IPInjection', ...
            'ReservedForProject', ...
            'ReservedForSession', ...
            'SocialBehaviorSession', ...
            'Tag', ...
            'Weaned'...
            };
        logFolder = []; % location of log files
        GenotypeSourceDropDown_map % Description
        curr_alleles
        curr_loci
        vendor_name_list % list of all vendors in database
        collaborator_name_list %list of all collaborators in database
    end

    methods (Access = private)

        function init_AddAnimalTab(app)
            app.loadAnimalSourceMenu(false);
            app.loadAnimalSourceMenu(true);

            %set initial state
            set(app.Search_DJIDEditField,'Enable',true);
            set(app.DOBDatePicker,'Enable',true);
            set(app.StatusLamp_Animal,'color','white');
            set(app.AnimalInsertStatusEdit,'value','');
        end

        function init_AddSubstanceTab(app,resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            app.loadSubstanceMenus();
            app.loadVendorsAndCollaborators();
            set(app.SourceDropDown_substance,'items',app.vendor_name_list);

            if resetStatusLamp
                set(app.StatusLamp_Substance,'color','white');
                set(app.SubstanceInsertStatusEdit,'value','');
            end
        end

        function init_AddStrainTab(app,resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            app.loadStrains();

            %set iniital state
            %             set(app.VendorEditField_Strain,'visible',true);
            set(app.CatalogEditField_Strain,'visible',true);
            %             set(app.LabnameEditField,'visible',false);
            %             set(app.OrganizationEditField,'visible',false);

            set(app.AllelesListBox, 'multiselect','on');
            set(app.AllelesListBox,'items',fetchn(sln_animal.AlleleActive,'allele_name'));

            set(app.BackgroundDropDown_2, 'items', fetchn(sln_animal.Background, 'background_name'));

            set(app.StrainNameEditField, 'enable', false);

            set(app.StraintypeButtonGroup, 'SelectedObject', app.VendorButton);
            set(app.SourceDropDown, 'items', ["";fetchn(sln_animal.Vendor, 'vendor_name')]);
            set(app.SourceDropDown, 'value', "");

            if resetStatusLamp
                set(app.StatusLamp_Strain,'color','white');
                set(app.StrainInsertStatusEdit,'value','');
            end
        end

        function init_AddBrainAreaTab(app, resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            areas = fetchn(sln_animal.BrainArea,'target');
            set(app.BrainareasindatabaseListBox,'Items',areas);

            value = app.BrainareasindatabaseListBox.Value;
            if ~isempty(value)
                thisArea = fetch(sln_animal.BrainArea & sprintf('target="%s"',value), '*');
                set(app.AbbreviationEditField, 'value', thisArea.target);
                set(app.BrainLongNameTextArea, 'value', thisArea.long_name);
            end

            if resetStatusLamp
                set(app.StatusLamp_BrainArea,'color','white');
                set(app.BrainAreaInsertStatusEdit,'value','');
            end
        end

        function init_AddProjectTab(app, resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end


            value = app.ActiveprojectsindatabaseListBox.Value;
            if ~isempty(value)
                thisProject = fetch(sln_lab.Project & sprintf('project_name="%s"',value), '*');
                set(app.ProjectNameEditField, 'value', thisProject.project_name);
                set(app.ProjectDescriptionTextArea, 'value', thisProject.description);
            end

            if resetStatusLamp
                set(app.StatusLamp_Project,'color','white');
                set(app.ProjectInsertStatusEdit,'value','');
            end
        end

        function init_AddBehaviorExpTab(app, resetStatusLamp)
            if nargin<2, resetStatusLamp = true; end
            exps = fetchn(sl_behavior.SocialBehaviorExperimentType,'purpose');
            set(app.BehaviorexperimenttypesindatabaseListBox,'Items',exps);

            value = app.BehaviorexperimenttypesindatabaseListBox.Value;
            if ~isempty(value)
                thisExp = fetch(sl_behavior.SocialBehaviorExperimentType & sprintf('purpose="%s"',value), '*');
                set(app.BehaviorExpNameEditField, 'value', thisExp.purpose);
                set(app.BehaviorExpDescriptionTextArea, 'value', thisExp.description);
            end

            if resetStatusLamp
                set(app.StatusLamp_BehaviorExp,'color','white');
                set(app.BehaviorExpInsertStatusEdit,'value','');
            end
        end

        function AddAnimal_load_backgrounds(app)
            %             bg = fetchn(sln_animal.Background,'background_name');
            %             %put C57 first
            %             ind = find(strcmp(bg, 'C57bl/6'));
            %             bg = bg([ind, setdiff(1:length(bg), ind)]);
            %             set(app.StrainDropDown_2,'Items',bg);
            %             set(app.BackgroundDropDown_multi,'Items',bg);
            %
            %             N_entries = 8;
            %             for e=1:N_entries
            %                 set(app.(['BackgroundDropDown_E' num2str(e)]),'Items',bg);
            %             end
        end

        function loadSubstanceMenus(app)

            active_substances_struct = fetch(sln_animal.InjectionSubstance.active,'*');
            if isempty(active_substances_struct)
                active_items = {};
            else
                active_items = cellfun(@(v1,v2)sprintf('%d: %s\n', v1,v2), ...
                    {active_substances_struct.substance_id}, {active_substances_struct.substance_name}, 'UniformOutput', false);
            end
            set(app.ActivesubstancesindatabaseListBox,'Items',active_items);

            active_substances = fetchn(sln_animal.InjectionSubstance.active,'substance_name');
            set(app.SubstanceDropDown_IP,'items',active_substances);
            set(app.SubstanceDropDown_Eye,'items',active_substances);
            set(app.SubstanceDropDown_Brain,'items',active_substances);
            set(app.SubstanceDropDown_Feed,'items',active_substances);

            inactive_substances_struct = fetch(sln_animal.InjectionSubstance.inactive,'*');
            if isempty(inactive_substances_struct)
                inactive_items = {};
            else
                inactive_items = cellfun(@(v1,v2)sprintf('%d: %s\n', v1,v2), ...
                    {inactive_substances_struct.substance_id}, {inactive_substances_struct.substance_name}, 'UniformOutput', false);
            end
            set(app.InactiveSubstancesindatabaseListBox,'Items',inactive_items);

        end

        function loadBrainAreas(app)
            brainAreas = fetchn(sln_animal.BrainArea,'target');
            set(app.BrainTargetDropDown,'items',brainAreas);
        end

        function loadUsers(app)
            users = fetchn(sln_lab.ActiveUser,'user_name');
            %put login_user first
            ind = find(strcmp(users,app.login_user));
            if ~isempty(ind)
                u{1} = app.login_user;
                u = [u; users(setdiff(1:length(users), ind))];
            else
                u = users;
            end
            set(app.PersonDropDown,'items',u);
            if ~isempty(ind)
                set(app.PersonDropDown,'value',app.login_user);
            end
        end

        function loadUsers_breeders(app)
            users = fetchn(sln_lab.ActiveUser,'user_name');
            %put login_user first
            ind = find(strcmp(users,app.login_user));
            if ~isempty(ind)
                u{1} = app.login_user;
                u = [u; users(setdiff(1:length(users), ind))];
            else
                u = users;
            end
            set(app.PersonDropDown_breeders,'items',u);
            if ~isempty(ind)
                set(app.PersonDropDown_breeders,'value',app.login_user);
            end
        end

        function loadSocialExperimentTypes(app)
            exps = fetchn(sl_behavior.SocialBehaviorExperimentType,'purpose');
            set(app.ExperimenttypeDropDown,'items',exps);
        end

        function loadTestAnimalTypes(app)
            animal_types = fetchn(sl_behavior.TestAnimalType,'animal_type_name');
            set(app.TestanimaltypeDropDown,'items',animal_types);
        end

        function loadRigs(app)
            rigs = fetchn(sln_lab.Rig,'rig_name');
            rigs{end+1} = 'A and B';
            set(app.RignameDropDown,'items',rigs);
        end

        function loadProjects(app)
            active_projects = fetchn(sln_lab.Project.active,'project_name');
            set(app.ProjectDropDown,'items',active_projects);
            set(app.Filter_ProjectDropDown,'items',['All'; active_projects]);
            set(app.ActiveprojectsindatabaseListBox,'Items',active_projects);

            inactive_projects = fetchn(sln_lab.Project.inactive,'project_name');
            set(app.InactiveprojectsindatabaseListBox,'Items',inactive_projects);
        end

        function init_BreedersTab(app)
            %load users
            app.loadUsers_breeders();

            %set date picker to current date
            set(app.EventdateDatePicker_breeders,'value',datetime(date,'format','yyyy-MM-dd'))

            %set status lamp to grey and empty text field
            set(app.StatusLamp_breeders,'color','white');
            set(app.EventInsertStatusEdit_breeders, 'value', '');

            %set search switch
            set(app.SearchTypeButtonGroup_breeders, 'SelectedObject', app.DJIDButton_breeders);
            set(app.Search_DJIDEditField_breeders, 'enable', true);
            set(app.CageNumSearchEditField_breeders, 'enable', false);

            %load breedig cages tables
            app.loadBreedingCagesTable([]);

            %set selections to empty
            app.selected_breeder_cage = [];

            %loadGenotypeMenu_BreedersTab
            app.loadGenotypeMenu_BreedersTab();

            set(app.SubmitevententryButton_breeders,'enable',true); %enable
        end

        function load_database_items(app)
            %load substances
            app.loadSubstanceMenus();

            %load brain areas
            app.loadBrainAreas();

            %load social behavior experiment types
            app.loadSocialExperimentTypes();

            %load test animal types for social behavior experiments
            app.loadTestAnimalTypes();

            %load rigs
            app.loadRigs();

            %load strains
            app.loadStrains();

            %genetic backgrounds
            app.load_background_dropdown();
            app.AddAnimal_load_backgrounds();

            %load test stimlui for social behavior experiments
            app.loadBehaviorStimuli();

            %load projects
            app.loadProjects();

            %load rooms
            app.Logger_loadRooms();
            app.AddAnimal_loadRooms();
            app.Breeders_loadRooms();

            %load locus and allele menus
            app.loadGenotypeTabMenus();

            %set date picker to current date
            set(app.EventdateDatePicker,'value',datetime(date,'format','yyyy-MM-dd'));

            %set add animals date picker to current date
            set(app.CageEntryDatePicker_multi,'value',datetime(date,'format','yyyy-MM-dd'));

            %set search switch
            set(app.SearchTypeButtonGroup,'SelectedObject', app.DJIDButton);
            set(app.Search_DJIDEditField, 'enable', true);
            set(app.Search_TagIDEditField, 'enable', false);
            set(app.CageNumSearchEditField, 'enable', false);

            %set menus and age boxes
            set(app.Filter_SexDropDown,'value','All');
            set(app.ageLow_edit,'value','');
            set(app.ageHigh_edit,'value','');

            %load genotype_search dropdown
            app.load_genotype_search_dropdown();

            %make initial filter struct
            app.filter_genotype_status = 'All';
            app.filter_ageLow = str2double(app.ageLow_edit.Value);
            app.filter_ageHigh = str2double(app.ageHigh_edit.Value);

            filter_query = [];
            %             try
            %                 %calendar init
            %                 if isdeployed
            %                     app.calDir = '/home/matlabuser';
            %                     app.cal_refresh_response.refresh_token = '1//0f4-lbZ90smKbCgYIARAAGA8SNwF-L9IrI9vFDovfyvZzzi5caMaTLx-1_CRoLFeuLuo2RcEEtIhrkyMdG0YSNKRYoLWOT4kK2l4';
            %                     app.cal_refresh_response.access_token = 'ya29.a0AfH6SMClV7vcfiNv6ebcsTY4UrDavnikDD9ZpsBlYj6pXt3Ysr0qn-tPU8dWhcRTDXt3unnq6TQEpTl0uc6Qm-LShA5OHvarXggtfnkWnv2Im181Pe5l5nzdjowiwCIsuStZAdSRnqhRQmJeKQqLQfuJvbNtslpZh0A';
            %                 else
            %                     app.calDir = pwd;
            %                     %not working for deployed yet
            %                     fid = fopen([app.calDir filesep 'gcal_access.txt'],'r');
            %                     app.cal_refresh_response.access_token = fgetl(fid);
            %                     app.cal_refresh_response.refresh_token = fgetl(fid);
            %
            %                 end
            %                 app.cal_refresh_response.expires_in = 3599;
            %                 app.cal_refresh_response.scope = 'https://www.googleapis.com/auth/calendar';
            %                 app.cal_refresh_response.token_type = 'Bearer';
            %             catch
            %                 disp('Google calendar integration failed');
            %             end
        end

        function init_AnimalLoggerTab(app)
            %%TODO: why load all of these every time we switch to this tab?
            %% shouldn't this just be in the app startup?? or in response
            %% to a refresh button?? doing it this way is slow

            %update genotype event entries
            app.updateGenotypeEntryVisibility();

            %load possible genotype results
            %app.loadGenotypeStatus(); %need to come back to this

            %set status lamp to grey and empty text field
            set(app.StatusLamp_Logger,'color','white');
            set(app.EventInsertStatusEdit, 'value', '');

            set(app.SubmitevententryButton,'enable',true); %enable

            %load animal table
            app.loadAnimalTable([]);
        end

        function loadBreedingCagesTable(app, filter_query)
            if nargin < 2
                filter_query = [];
            end
            % change status loading...
            set(app.BreederPairs_status, 'Text', 'loading...');
            %and make table invisible
            set(app.BreederCagesTable,'visible','off');
            drawnow;

            [breeders_table, missing_male_ids] = sln_animal.BreedingPair.breeders_table;
            breeding_pairs_struct = fetch(breeders_table,'*');
            %deal with missing males
            for i=1:length(missing_male_ids)
                ind = find([breeding_pairs_struct.source_id] == missing_male_ids(i));
                breeding_pairs_struct(ind).male_id = 0;
                breeding_pairs_struct(ind).male_strain = '';
                breeding_pairs_struct(ind).male_genotype = '';
                breeding_pairs_struct(ind).male_age = 0;
            end

            sfields = {...
                'source_id','cage_number','room_number','strain_name',...
                'female_id','female_strain','female_genotype','female_age',...
                'male_id','male_strain','male_genotype','male_age',...
                'pair_date','litter_count','litter_dates','wean_counts','next_wean'};

            breeding_pairs_struct = orderfields(breeding_pairs_struct, sfields);
            %set up uitable
            D = struct2table(breeding_pairs_struct);
            % the following is set in the design view...
            %             D = renamevars(D, sfields, {'Pair_ID',...
            %                 'Cage_number',...
            %                 'Room_number', ...
            %                 'Strain', ...
            %                 'Male_DJID',...
            %                 'Male_Strain',...
            %                 'Male_Genotype'...
            %                 'Male_age', ...
            %                 'Female_DJID', ...
            %                 'Female_Strain',...
            %                 'Female_Genotype'...
            %                 'Female_age', ...
            %                 'Date_paired',...
            %                 'N_litters',...
            %                 'Litter_dates',...
            %                 'Weaned_sizes',...
            %                 'Next_wean_date',...
            %             });

            %set table data
            set(app.BreederCagesTable,'Data',D);
            defaultStyle = uistyle('FontColor','black','FontWeight','normal');
            addStyle(app.BreederCagesTable,defaultStyle);

            %make no cage selected
            app.selected_breeder_cage = [];

            % change status
            set(app.BreederPairs_status, 'Text', [num2str(height(D)) ' cages']);
            %and make table visible
            set(app.BreederCagesTable,'visible','on');
            drawnow;
        end

        function loadAnimalTable(app, filter_query)
            % change title to table loading...
            set(app.AnimalSelectionPanel, 'Title', 'Table loading...');
            %and make table invisible
            set(app.AnimalTable,'visible','off');
            drawnow;

            if isempty(app.filter_ageLow) || isnan(app.filter_ageLow)
                app.filter_ageLow = 0;
            end
            if isempty(app.filter_ageHigh) || isnan(app.filter_ageHigh)
                app.filter_ageHigh = 200;
            end

            if ~isempty(filter_query) %run specified query instead of stored filter
                allAnimals = fetch(sln_animal.Deceased.living() & filter_query,'*');
            else
                % make age restriction
                if ~isempty(get(app.ageLow_edit,'value')) || ~isempty(get(app.ageHigh_edit,'value'))
                    max_dob = char(datetime(datetime('now') - 7*app.filter_ageLow,'format','yyyy-MM-dd'));
                    if app.filter_ageHigh >= 25 %25 weeks considered inf
                        min_dob = '1900-01-01'; %really old mouse!
                    else
                        min_dob = char(datetime(datetime('now') - 7*app.filter_ageHigh,'format','yyyy-MM-dd'));
                    end
                    ageQuery = {['dob >= ' '"' min_dob '"'], ['dob <= ' '"' max_dob '"']};
                else
                    ageQuery = {};
                end

                %use LIKE operator for genotype name
                if isfield(app.filter_struct, 'genotype_name')
                    allele_search_str = app.filter_struct.genotype_name;
                    filter = rmfield(app.filter_struct, 'genotype_name');

                    thisLocus = sln_animal.AlleleLocusMap & sprintf('allele_name="%s"', allele_search_str);
                    if thisLocus.exists
                        locus_name = fetch1(thisLocus,'locus_name');
                    else
                        locus_name = 'unknown';
                    end

                    switch app.filter_genotype_status
                        case 'All'
                            allAnimals = fetch(sln_animal.Deceased.living() * sln_animal.GenotypeString ...
                                & filter ...
                                & sprintf('genotype_string LIKE "%%%s%%"', allele_search_str) & ...
                                ageQuery,...
                                'animal_id', 'sex', 'dob', 'background_name');
                        case 'het'
                            %fprintf('genotype LIKE "%%%s:%s/WT%%"\n', locus_name, allele_search_str)
                            allAnimals = fetch(sln_animal.Deceased.living() * sln_animal.GenotypeString ...
                                & filter ...
                                & sprintf('genotype_string LIKE "%%%s:%s/WT%%"', locus_name, allele_search_str) ...
                                & ageQuery,...
                                'animal_id', 'sex', 'dob', 'background_name');

                        case 'homo'
                            %fprintf('genotype LIKE "%%%s:%s/%s%%"\n', locus_name, allele_search_str, allele_search_str)
                            allAnimals = fetch(sln_animal.Deceased.living() * sln_animal.GenotypeString ...
                                & filter ...
                                & sprintf('genotype_string LIKE "%%%s:%s/%s%%"', locus_name, allele_search_str, allele_search_str) ...
                                & ageQuery,...
                                'animal_id', 'sex', 'dob', 'background_name');

                        case 'carrier'
                            %fprintf('genotype LIKE "%%%s:%s/%%"\n', locus_name, allele_search_str)
                            allAnimals = fetch(sln_animal.Deceased.living() * sln_animal.GenotypeString ...
                                & filter ...
                                & sprintf('genotype_string LIKE "%%%s:%s/%%"', locus_name, allele_search_str) ...
                                & ageQuery,...
                                'animal_id', 'sex', 'dob', 'background_name');

                        case 'non-carrier'
                            %fprintf('genotype LIKE "%%%s:WT/WT%%"\n', locus_name)
                            allAnimals = fetch(sln_animal.Deceased.living() * sln_animal.GenotypeString ...
                                & filter ...
                                & sprintf('genotype_string LIKE "%%%s:WT/WT%%"', locus_name) ...
                                & a,...
                                'animal_id', 'sex', 'dob', 'background_name');
                        case 'unknown'
                            %fprintf('genotype LIKE "%%%s:%%"\n', locus_name)
                            allAnimals = fetch(sln_animal.Deceased.living() * sln_animal.GenotypeString ...
                                & filter ...
                                & sprintf('genotype_string LIKE "%%%s:?%%"', locus_name) ...
                                & ageQuery,...
                                'animal_id', 'sex', 'dob', 'background_name');
                    end
                else
                    filter = app.filter_struct;

                    allAnimals = fetch(sln_animal.Deceased.living() ...
                        & filter & ageQuery,'animal_id', 'sex', 'dob', 'background_name');
                end

            end
            if isempty(allAnimals)
                D = table;
            else
                animal_ids = [allAnimals.animal_id]';

                %TODO: the reason it takes so long to load the table is
                %that each of these queries incurs its own roundtrip to the
                %server. With a little thought we should be able to do all
                %of this in one query

                %remove active breeders
                active_breeders = sln_animal.BreedingPair.active_animal_ids();
                nonbreeders_ind = find(~ismember(animal_ids,active_breeders));
                allAnimals = allAnimals(nonbreeders_ind);
                animal_ids = [allAnimals.animal_id]';

                nAnimals = length(animal_ids);
                tag_ids = sln_animal.Animal.tagNumber(animal_ids);
                tag_ear = sln_animal.Animal.tagEar(animal_ids);
                punch_ear = sln_animal.Animal.earPunch(animal_ids);
                cage_numbers = sln_animal.Animal.cageNumber(animal_ids);
                room_numbers = sln_animal.Animal.roomNumber(animal_ids);
                source_info = sln_animal.Animal.source_info(animal_ids);
                strain_names = fetch(sln_animal.Animal.strainName(animal_ids), '*');
                genotype_str = fetch(sln_animal.GenotypeString & allAnimals, '*');
                project_name = sln_animal.Animal.reservedProject(animal_ids);
                hasBehavior = sln_animal.Animal.hasEvent('SocialBehaviorSession',animal_ids);
                hasBrainInjection = sln_animal.Animal.hasEvent('BrainInjection',animal_ids);
                hasEyeInjection = sln_animal.Animal.hasEvent('EyeInjection',animal_ids);
                hasEyeSuture = sln_animal.Animal.hasEvent('EyeSuture',animal_ids);
                hasIPinjection = sln_animal.Animal.hasEvent('IPInjection',animal_ids);
                hasRigReservation = sln_animal.Animal.hasEvent('ReservedForSession',animal_ids);
                hasProjectReservation = sln_animal.Animal.hasEvent('ReservedForProject',animal_ids);


                D = table('Size',[nAnimals,19],'VariableTypes', ...
                    {'uint32',...   #1 DJ ID
                    'uint32', ...   #2 tag ID
                    'string',...    #3 tag ear
                    'string',...    #4 punch ear
                    'string',...    #5 cage number
                    'string',...    #6 room number
                    'string',...    #7 background
                    'string',...    #8 strain name
                    'string',...    #9 genotype str
                    'string',...    #10 sex
                    'singlenan',... #11 age
                    'string', ...   #12 source info
                    'logical',...   #13 IP inj
                    'logical',...   #14 eye inj
                    'logical',...   #15 eye suture
                    'logical',...   #16 brain inj
                    'logical', ...  #17 behavior
                    'logical', ...  #18 reserved for rig
                    'string', ...   #19 reserved project
                    },...
                    'VariableNames', ...
                    {'DJ_ID',...
                    'Tag_ID', ...
                    'Tag_ear',...
                    'Punch_ear',...
                    'Cage_number',...
                    'Room_number',...
                    'Background',...
                    'Strain',...
                    'Genotype_str',...
                    'Sex',...
                    'Age',...
                    'SourceInfo',...
                    'IP_inj',...
                    'Eye_inj',...
                    'Eye_sut',...
                    'Brain_inj',...
                    'BehaviorExp',...
                    'ReservedForRig', ...
                    'ReservedProject', ...
                    });

                age = sln_animal.Animal.age(animal_ids);
                if nAnimals>0
                    T = struct2table(allAnimals,'AsArray',true);
                    if isempty(tag_ids)
                        T.tag_id = nan(nAnimals,1);
                    else
                        T = outerjoin(T, struct2table(tag_ids,'AsArray',true),'MergeKeys',true,'type','Left');
                    end
                    if isempty(tag_ear)
                        T.tag_ear = nan(nAnimals,1);
                    else
                        T = outerjoin(T, struct2table(tag_ear,'AsArray',true),'MergeKeys',true,'type','Left');
                    end
                    if isempty(punch_ear)
                        T.punch = nan(nAnimals,1);
                    else
                        T = outerjoin(T, struct2table(punch_ear,'AsArray',true),'MergeKeys',true,'type','Left');
                    end
                    if isempty(cage_numbers)
                        T.cage_number = nan(nAnimals,1);
                    else
                        T = outerjoin(T, struct2table(cage_numbers,'AsArray',true),'MergeKeys',true,'type','Left');
                    end
                    if isempty(room_numbers)
                        T.room_number = nan(nAnimals,1);
                    else
                        T = outerjoin(T, struct2table(room_numbers,'AsArray',true),'MergeKeys',true,'type','Left');
                    end
                    if isempty(strain_names)
                        T.strain_names = nan(nAnimals,1);
                    else
                        T = outerjoin(T, struct2table(strain_names,'AsArray',true),'MergeKeys',true,'type','Left');
                    end
                    if isempty(genotype_str)
                        T.genotype_str = nan(nAnimals,1);
                    else
                        T = outerjoin(T, struct2table(genotype_str,'AsArray',true),'MergeKeys',true,'type','Left');
                    end
                    if isempty(project_name)
                        T.project_name = nan(nAnimals,1);
                    else
                        T = outerjoin(T, struct2table(project_name,'AsArray',true),'MergeKeys',true,'type','Left');
                    end
                    if isempty(source_info)
                        T.source_info = nan(nAnimals,1);
                    else
                        T = outerjoin(T, struct2table(source_info,'AsArray',true), 'MergeKeys', true, 'type','Left');
                    end

                    T = outerjoin(T, struct2table(age,'AsArray',true),'MergeKeys',true,'type','Left'); %every mouse has an age
                    T.hasBehavior = hasBehavior;
                    T.hasBrainInjection = hasBrainInjection;
                    T.hasEyeInjection = hasEyeInjection;
                    T.hasEyeSuture = hasEyeSuture;
                    T.hasIPinjection = hasIPinjection;
                    T.hasRigReservation = hasRigReservation;
                    T.hasProjectReservation = hasProjectReservation;

                    D.DJ_ID = T.animal_id;
                    D.Tag_ID = T.tag_id;
                    D.Tag_ear = T.tag_ear;
                    D.Punch_ear = T.punch;
                    D.Cage_number = T.cage_number;
                    D.Room_number = T.room_number;
                    D.Background = string(T.background_name);
                    D.Strain = T.strain_name;
                    D.Genotype_str = T.genotype_string;
                    D.IP_inj = T.hasIPinjection;
                    D.Sex = string(T.sex);
                    D.Age = calweeks(T.age);
                    D.SourceInfo = T.source_name;
                    D.Eye_inj = T.hasEyeInjection;
                    D.Eye_sut = T.hasEyeSuture;
                    D.Brain_inj = T.hasBrainInjection;
                    D.BehaviorExp = T.hasBehavior;
                    D.ReservedForRig = T.hasRigReservation;
                    D.ReservedProject = string(T.project_name);

                    %filter parts that have to be done after table construction
                    ind = true(size(D,1),1); %don't filter by default
                    switch get(app.IPInjectionButtonGroup,'SelectedObject').Text
                        case 'Yes'
                            ind = ind & D.IP_inj;
                        case 'No'
                            ind = ind & ~D.IP_inj;
                    end
                    %                     D = D(ind,:);
                    switch get(app.EyeInjectionButtonGroup,'SelectedObject').Text
                        case 'Yes'
                            ind = ind & D.Eye_inj;
                        case 'No'
                            ind = ind & ~D.Eye_inj;
                    end

                    switch get(app.BrainInjectionButtonGroup,'SelectedObject').Text
                        case 'Yes'
                            ind = ind & D.Brain_inj;
                        case 'No'
                            ind = ind & ~D.Brain_inj;
                    end

                    switch get(app.ProjectreservationButtonGroup,'SelectedObject').Text
                        case 'Yes'
                            ind = ind & ~strcmp(D.ReservedProject,"");
                        case 'No'
                            ind = ind & strcmp(D.ReservedProject,"");
                    end

                    switch get(app.RigreservationButtonGroup,'SelectedObject').Text
                        case 'Yes'
                            ind = ind & D.ReservedForRig;
                        case 'No'
                            ind = ind & ~D.ReservedForRig;
                    end

                    switch get(app.BehaviorsessionButtonGroup,'SelectedObject').Text
                        case 'Yes'
                            ind = ind & D.BehaviorExp;
                        case 'No'
                            ind = ind & ~D.BehaviorExp;
                    end

                    %project filter
                    selected_project = get(app.Filter_ProjectDropDown,'value');
                    if ~strcmp(selected_project, 'All')
                        ind = ind & strcmp(selected_project, D.ReservedProject);
                    end

                    %strain filter
                    selected_strain = get(app.StrainDropDown,'value');
                    strain_name = strtrim(strtok(selected_strain,'('));
                    if ~strcmp(selected_strain, 'All')
                        ind = ind & strcmp(strain_name, D.Strain);
                    end

                    D = D(ind,:);
                end
            end
            N = height(D);
            if N>0
                set(app.AnimalSelectionPanel, 'Title', ...
                    ['Animal Selection: ' num2str(N) ' total']);
            else
                set(app.AnimalSelectionPanel, 'Title', ...
                    'Animal Selection: none found');
            end
            set(app.AnimalTable,'Data',D);
            defaultStyle = uistyle('FontColor','black','FontWeight','normal');
            addStyle(app.AnimalTable,defaultStyle);

            %make no animal selected
            app.selected_animal_id = [];

            %make visible again
            set(app.AnimalTable,'visible','on');
            drawnow;
        end

        function loadGenotypeMenu_BreedersTab(app)
            allele_list = fetchn(sln_animal.Allele.active,'allele_name');
            set(app.GenotypeDropDown_BreedersTab,'items',['All'; allele_list]);
        end

        function loadAnimalSourceMenu(app, multi)
            menu_str = {};
            if ~multi
                value = get(app.SourceTypeDropDown, 'value');
            else
                value = get(app.SourceTypeDropDown_multi, 'value');
            end
            switch value
                case 'breeding'
                    [male_breeders, female_breeders,breedingSourceList, breedingStrainList] = ...
                        fetchn(sln_animal.BreedingPair.active_pairs,'male_id','female_id','source_id','strain_name');
                    %                     male_breeders = fetchn(sln_animal.BreedingPair.active,'male_id');
                    %                     female_breeders = fetchn(sln_animal.BreedingPair.active,'female_id');

                    %                     breedingSourceList = fetchn(sln_animal.BreedingPair.active,'source_id');
                    %                     breedingStrainList = fetchn(sln_animal.BreedingPair.active,'strain_name');

                    if isempty(female_breeders)
                        cages = [];
                    else
                        N = length(female_breeders);
                        cages = zeros(N,1);
                        for i=1:length(female_breeders)
                            temp = sln_animal.Animal.cageNumber(female_breeders(i));
                            cages(i) = temp.cage_number;
                        end
                    end

                    %breedingCageList = breedingCageList(active_mask);

                    %put None (0) first
                    cages = [0; cages];
                    sources = [0; breedingSourceList];
                    strains = [0; breedingStrainList];
                    male_breeders = [0; male_breeders];
                    female_breeders = [0; female_breeders];

                    Ncages = length(cages);
                    menu_str = cell(Ncages,1);

                    for i=1:Ncages
                        if cages(i)==0
                            menu_str{i} = 'None';
                        else
                            menu_str{i} = sprintf('%d: Cage %d, M:%d / F:%d, Strain: %s', sources(i), cages(i), male_breeders(i), female_breeders(i), strains{i});
                        end

                    end

                case 'vendor'
                    [v,vname] = fetchn(sln_animal.Vendor.active(),'source_id','vendor_name');
                    [~,i] = unique(v);
                    menu_str = arrayfun(@(d,s) sprintf('%d: %s',d,s{1}), v(i), vname(i), 'UniformOutput', false);
                case 'collaborator'
                    [c,cname, oname] = fetchn(sln_animal.Collaborator.active(),'source_id','lab_name','organization');
                    [~,i] = unique(c);
                    menu_str = arrayfun(@(d,s,o) sprintf('%d: %s (%s)',d,s{1}, o{1}), c(i), cname(i), oname(i), 'UniformOutput', false);
            end

            if ~multi
                set(app.AnimalSourceDropDown,'Items',menu_str);
            else
                set(app.AnimalSourceDropDown_multi,'Items',menu_str);
            end

        end

        function [calEventAdded, result] = addCalendarEvent(app,key,type)
            format long
            if isempty(app.cal_access_token_time) || datetime('now') - app.cal_access_token_time > seconds(app.cal_expiration)
                %token expired
                try
                    app.cal_access_token = secure.refresh_gcal_connection('SchwartzLab Google Calendar');
                    app.cal_access_token_time = datetime('now');
                    disp('refreshed access token.');
                    app.cal_access_token
                catch
                    disp('Calendar access code failed: maybe refresh_token expired.');
                    result = 'Calendar access code failed: maybe refresh_token expired.';
                    calEventAdded = false;
                    return;
                end
            else
                app.cal_access_token = fetch1(secure.AccessToken & aggr(secure.GcalConnection, secure.AccessToken, 'max(token_id)->token_id'), 'token');
                disp('using latest saved access token');
            end

            type
            try
                switch type
                    case 'reserveForRig'
                        %make reserve forRigEvent
                        genotype = fetch1(sln_animal.GenotypeString & sprintf('animal_id=%d',key.animal_id), 'genotype_string');
                        body = struct();
                        body.start.date = key.date;
                        body.end.date = key.date;
                        body.summary = sprintf('%s: experiment on rig %s with mouse(DJID): %d, genotype: %s', ...
                            key.user_name, key.rig_name, key.animal_id, genotype);
                        cageNum = sln_animal.Animal.cageNumber(key.animal_id);
                        roomNum = sln_animal.Animal.roomNumber(key.animal_id);
                        body.location = sprintf('Room %s, cage %d', roomNum.room_number, cageNum.cage_number);
                        if ~isempty(key.notes)
                            body.description = key.notes;
                        end

                    case 'weaning'
                        %make weaning event
                        body = struct();
                        body.start.date = key.date;
                        body.end.date = key.date;
                        body.summary = sprintf('Wean animals in cage %d', ...
                            key.cage_number);
                        body.colorId = 11; %red, by request
                end


                %make request
                request = matlab.net.http.RequestMessage(...
                    matlab.net.http.RequestMethod.POST,...
                    matlab.net.http.HeaderField('Authorization',sprintf('Bearer %s',app.cal_access_token),...
                    'MediaType','application/json'),...
                    body);

                %post it
                status = request.send('https://www.googleapis.com/calendar/v3/calendars/schwartzlabnu@gmail.com/events');
                if strcmp(status.StatusCode,'OK')
                    calEventAdded = true;
                    result = 'Calendar entry insert success';
                else
                    calEventAdded = false;
                    result = 'Calendar entry insert failed';
                end
            catch ME
                calEventAdded = false;
                result = sprintf('Calendar entry insert failed: %s', ME.message);
            end

        end

        function AddAnimal_loadRooms(app)
            roomList = fetchn(sln_animal.CageRoomActive,'room_number');
            if ismember('SB-443',roomList)
                value = 'SB-443';
            else
                value = roomList{1};
            end
            set(app.RoomDropDown,'Items',roomList);
            set(app.RoomDropDown,'Value',value);
            set(app.MaleRoomDropDown_2,'Items',roomList);
            set(app.MaleRoomDropDown_2,'Value',value);
            set(app.FemaleRoomDropDown,'Items',roomList);
            set(app.FemaleRoomDropDown,'Value',value);
            %
            %             N_entries = 8;
            %             for e=1:N_entries
            %                 set(app.(['RoomDropDown_E' num2str(e)]),'Items',roomList);
            %                 set(app.(['RoomDropDown_E' num2str(e)]),'Value',value);
            %             end
        end

        function Logger_loadRooms(app)
            roomList = fetchn(sln_animal.CageRoomActive,'room_number');
            if ismember('SB-443',roomList)
                value = 'SB-443';
            else
                value = roomList{1};
            end
            set(app.RoomDropDown_logger,'Items',roomList);
            set(app.RoomDropDown_logger,'Value',value);
            set(app.RoomDropDown_Pair,'Items',roomList);
            set(app.RoomDropDown_Pair,'Value',value);
            set(app.ToroomDropDown,'Items',roomList);
            set(app.ToroomDropDown,'value',value);
        end

        function Breeders_loadRooms(app)
            roomList = fetchn(sln_animal.CageRoomActive,'room_number');
            if ismember('SB-443',roomList)
                value = 'SB-443';
            else
                value = roomList{1};
            end
            set(app.RoomDropDown_Pair,'Items',roomList);
            set(app.RoomDropDown_Pair,'Value',value);
            set(app.RoomDropDown_breeders_sepMale,'Items',roomList);
            set(app.RoomDropDown_breeders_sepMale,'Value',value);
            set(app.RoomDropDown_breeders_sepFemale,'Items',roomList);
            set(app.RoomDropDown_breeders_sepFemale,'Value',value);
            set(app.RoomDropDown_MaleBreeder,'Items',roomList);
            set(app.RoomDropDown_MaleBreeder,'Value',value);
            set(app.RoomDropDown_BreedingPair,'Items',roomList);
            set(app.RoomDropDown_BreedingPair,'Value',value);
            set(app.RoomDropDown_breeders_harem_male,'Items',roomList);
            set(app.RoomDropDown_breeders_harem_male,'Value',value);
            set(app.RoomDropDown_breeders_harem_female1,'Items',roomList);
            set(app.RoomDropDown_breeders_harem_female1,'Value',value);
            set(app.RoomDropDown_breeders_harem_female2,'Items',roomList);
            set(app.RoomDropDown_breeders_harem_female2,'Value',value);
        end

        function updateGenotypeEntryVisibility(app)
            value = app.GenotypeEntriesSpinner.Value;
            N_entries = 4;
            itemList = {'LocusDropDown_' ...
                'Allele1DropDown_'...
                'Allele2DropDown_'...
                };
            for e=1:value
                for i = 1:length(itemList)
                    set(app.([itemList{i} num2str(e)]), 'Visible', true);
                end
                %                 if e==1
                %                     set(app.SetotherentriesButton, 'Visible', true)
                %                 end
            end
            for e=value+1:N_entries
                for i = 1:length(itemList)
                    set(app.([itemList{i} num2str(e)]), 'Visible', false);
                end
                %                 if e==1
                %                     set(app.SetotherentriesButton, 'Visible', false)
                %                 end
            end
        end

        function loadBehaviorStimuli(app)
            stims = fetchn(sl_behavior.VisualStimulusType,'stim_type');
            set(app.AnimalAEditField, 'visible', false);
            set(app.AnimalBEditField, 'visible', false);
            set(app.AnimalCEditField, 'visible', false);

            set(app.WindowAStim,'items',stims);
            set(app.WindowAStim,'value','empty');

            set(app.WindowBStim,'items',stims);
            set(app.WindowBStim,'value','empty');

            set(app.WindowCStim,'items',stims);
            set(app.WindowCStim,'value','empty');

            set(app.VisualstimuliindatabaseListBox,'items',stims);
        end

        function init_TODOListTab(app)
            f = sln_animal.TodoQueries().fetch('*');
            if ~isempty(f)
                app.todo_queries = containers.Map({f(:).query_name},{f(:).query_text});
            else
                app.todo_queries = containers.Map();
            end
            set(app.QueriesListBox,'items',app.todo_queries.keys(),'value',{});
            set(app.QuerytextTextArea,'value','');
            set(app.QuerynameEditField,'value','');
            set(app.QuerynameLabel,'text','');
            set(app.QueryResultTable,'data',table(),'columnname',[]);

            set(app.StatusLamp_TODOquery,'color','white');
            set(app.EditField_TODOquery,'value','');
        end

        function loadGenotypeTabMenus(app)
            if isempty(app.selected_animal_id)
                return;
            end
            [app.curr_alleles,app.curr_loci] = fetchn((sln_animal.Animal & struct('animal_id',app.selected_animal_id)) * sln_animal.StrainAllele * sln_animal.AlleleLocusMap, 'allele_name','locus_name');

            if numel(app.curr_alleles) > 1
                set(app.GenotypeEntriesSpinner, 'Limits', [1,numel(app.curr_alleles)]);
                set(app.GenotypeEntriesSpinner, 'Value', numel(app.curr_alleles));
                set(app.GenotypeEntriesSpinner, 'Enable', 'on');
            else
                set(app.GenotypeEntriesSpinner, 'Limits', [0,1]);
                set(app.GenotypeEntriesSpinner, 'Value', numel(app.curr_alleles));
                set(app.GenotypeEntriesSpinner, 'Enable', 'off');

            end


            [gs,gi] = fetchn(sln_animal.GenotypeSource.active(),'source_name','source_id');
            set(app.GenotypeSourceDropDown, 'items', [{''}; gs]);
            set(app.GenotypeSourceDropDown, 'value', '');
            app.GenotypeSourceDropDown_map = containers.Map(gs, gi);

            for i=1:numel(app.curr_alleles)
                locusMenuName = ['LocusDropDown_' num2str(i)];
                allele1MenuName = ['Allele1DropDown_' num2str(i)];
                allele2MenuName = ['Allele2DropDown_' num2str(i)];

                %                 locus_list = fetchn(sln_animal.GeneLocus,'locus_name');
                %                 allele_list = fetchn(sln_animal.Allele.active,'allele_name');

                set(app.(locusMenuName), 'items', app.curr_loci);
                set(app.(locusMenuName), 'value', app.curr_loci{i})

                set(app.(allele1MenuName),'items', [{''; 'WT'}; app.curr_alleles{i}; {'Ambiguous'}]);
                set(app.(allele2MenuName),'items', [{''; 'WT'}; app.curr_alleles{i}; {'Ambiguous'}]);

                set(app.(allele1MenuName),'value', '');
                set(app.(allele2MenuName),'value', '');
            end
            
            app.GenotypeSourceDropDown.Value = 'Schwartz Lab';
            %LocusDropDown_1
            %AlleleDropDown_1
        end

        function load_genotype_search_dropdown(app)
            allele_list = fetchn(sln_animal.Allele.active,'allele_name');
            set(app.Filter_GenotypeDropDown,'items',['All'; allele_list]);
            set(app.GenotypeDropDown_BreedersTab,'items',['All'; allele_list]);
        end

        function load_background_dropdown(app)
            bg_list = fetchn(sln_animal.Background,'background_name');
            set(app.Filter_BgDropDown,'items',['All'; bg_list]);
        end

        function updateAlleleList(app)
            active_alleles = fetchn(sln_animal.Allele.active,'allele_name');
            set(app.ActiveallelesindatabaseListBox,'items',active_alleles);
            set(app.AlleleDropDown_forMap,'items',active_alleles);

            inactive_alleles = fetchn(sln_animal.Allele.inactive,'allele_name');
            set(app.InactiveallelesindatabaseListBox,'items',inactive_alleles);
        end

        function updateLocusList(app)
            items = fetchn(sln_animal.GeneLocus,'locus_name');
            set(app.LociindatabaseListBox,'items',items);
            set(app.LocusDropDown_forMap,'items',items);
        end

        function updateAlleleLocusTable(app)
            T = struct2table(fetch(sln_animal.AlleleLocusMap,'*'));
            set(app.AlleleLocusTable,'Data',T);
        end

        function init_AddAlleleLocusTab(app)
            app.updateAlleleList();
            app.updateLocusList();
            app.updateAlleleLocusTable();
        end

        function loadStrains(app)
            strains_active = fetch(sln_animal.StrainActive,'*');
            N_active = length(strains_active);
            if N_active > 0
                items_active = cellfun(@(v1,v2)sprintf('%s (%s)', v1,v2), ...
                    {strains_active.strain_name}, {strains_active.background_name}, 'UniformOutput', false)';
                set(app.StrainDropDown,'items',['All'; items_active]);
                set(app.StrainnameDropDown,'items',["";items_active]);
                set(app.StrainDropDown_BreedersTab,'items',['All'; items_active])
                set(app.ActiveStrainListBox,'items',items_active);
                set(app.ActiveStrainListBox,'value',items_active{1});
            end

            strains_inactive = fetch(sln_animal.Strain - sln_animal.StrainActive,'*');
            N_inactive = length(strains_inactive);
            if N_inactive > 0
                items_inactive = cellfun(@(v1,v2)sprintf('%s (%s)', v1,v2), ...
                    {strains_inactive.strain_name}, {strains_inactive.background_name}, 'UniformOutput', false)';
                set(app.InactiveStrainListBox,'items',items_inactive);
                set(app.InactiveStrainListBox,'value',items_inactive{1});
            end
        end

        function loadVendorsAndCollaborators(app)
            app.vendor_name_list = fetchn(sln_animal.Vendor.active(),'vendor_name');
            app.collaborator_name_list = fetchn(sln_animal.Collaborator.active(), 'lab_name');
        end

        function loadSourceListBoxes(app)
            selectedButton = app.SourcetypeButtonGroup.SelectedObject;
            button_text = get(selectedButton, 'Text');

            source_struct = fetch(eval(sprintf('sln_animal.%s.active()',button_text)), '*');
            source_struct_inactive = fetch(eval(sprintf('sln_animal.%s.inactive()',button_text)), '*');

            switch button_text
                case 'Vendor'
                    disp('vendor');
                    if isempty(source_struct)
                        items_active = {};
                    else
                        items_active = cellfun(@(v1,v2)sprintf('%d: %s', v1,v2), ...
                            {source_struct.source_id}, {source_struct.vendor_name}, 'UniformOutput', false);
                    end
                    if isempty(source_struct_inactive)
                        items_inactive = {};
                    else
                        items_inactive = cellfun(@(v1,v2)sprintf('%d: %s', v1,v2), ...
                            {source_struct_inactive.source_id}, {source_struct_inactive.vendor_name}, 'UniformOutput', false);
                    end

                case 'GenotypeSource'
                    disp('genotype_source');
                    if isempty(source_struct)
                        items_active = {};
                    else
                        items_active = cellfun(@(v1,v2)sprintf('%d: %s', v1,v2), ...
                            {source_struct.source_id}, {source_struct.source_name}, 'UniformOutput', false);
                    end
                    if isempty(source_struct_inactive)
                        items_inactive = {};
                    else
                        items_inactive = cellfun(@(v1,v2)sprintf('%d: %s', v1,v2), ...
                            {source_struct_inactive.source_id}, {source_struct_inactive.source_name}, 'UniformOutput', false);
                    end

                case 'Collaborator'
                    disp('collaborator');
                    if isempty(source_struct)
                        items_active = {};
                    else
                        items_active = cellfun(@(v1,v2,v3)sprintf('%d: %s, %s', v1,v2,v3), ...
                            {source_struct.source_id}, {source_struct.lab_name}, {source_struct.organization}, 'UniformOutput', false);
                    end
                    if isempty(source_struct_inactive)
                        items_inactive = {};
                    else
                        items_inactive = cellfun(@(v1,v2,v3)sprintf('%d: %s, %s', v1,v2,v3), ...
                            {source_struct_inactive.source_id}, {source_struct_inactive.lab_name}, {source_struct_inactive.organization}, 'UniformOutput', false);
                    end

            end
            set(app.ActiveSourceListBox,'items',items_active)
            set(app.InactiveSourceListBox,'items',items_inactive)

        end

        function init_addSourceTab(app)
            %app.loadSourceListBoxes();
            app.SourcetypeButtonGroupSelectionChanged([]);
        end

        function breeder_djid_changed(app, male_id, female_id)
            if isempty(male_id) || isempty(female_id)
                set(app.StrainnameDropDown, 'Value', '');
                return
            else
                %                 male_id = str2double(male_id);
                %                 female_id = str2double(female_id);

                %fetch animals with genotype results...
                %alleles_male = fetchn((sln_animal.Animal * sln_animal.StrainAllele) & 'animal_id=2000','allele_name');
                %sln_animal.AlleleLocusMap * ((sln_animal.Animal * sln_animal.StrainAllele) & 'animal_id=2000')
                animal_str = ['animal_id=' male_id ' OR animal_id=' female_id];

                strain_res = aggr(...
                    (sln_animal.GeneLocus * sln_animal.Animal)...
                    & proj(...
                    (sln_animal.GeneLocus * sln_animal.AlleleLocusMap * ((sln_animal.Animal * sln_animal.StrainAllele) & animal_str))...
                    ),...
                    sln_animal.Genotype,...
                    'if(sum(allele_name = "WT")=2,"Y","N")->is_DEFINITELY_wildtype');
                alleles = unique(fetchn((strain_res* sln_animal.Animal * sln_animal.StrainAllele * sln_animal.AlleleLocusMap)& 'is_DEFINITELY_wildtype="N"', 'allele_name'));

                cre = contains(alleles, 'cre','ignorecase',true); % put cre promoters first
                [~,i] = sort(cre, 'descend');
                strain_name = strjoin(alleles(i),' x ');

                if ismember(strain_name, app.StrainnameDropDown.Items)
                    set(app.StrainnameDropDown, 'Value', strain_name);
                else
                    set(app.StrainnameDropDown, 'Value', '');
                end

            end

        end

        function init_LookupDeceasedTab(app)

        end
        %
        % function [userName, password] = loginReceived(app, src, event)
        %
        %
        %    userName = event.HTMLEventData.username;
        %    password = event.HTMLEventData.password;
        %
        %    if isdeployed
        %         try
        %             disp('deployed');
        %             if app.connection.isConnected
        %                 app.connection.close;ß
        %                 app.connection = struct;
        %                 app.connection.isConnected = false;
        %             end
        %             app.connection = dj.conn('vfsmdatajoint01.fsm.northwestern.edu', currentUser, app.user_password, [], true);
        %         catch ME
        %             disp('deployed - wrong password');
        %             app.HTML.Data.loginTried = app.HTML.Data.loginTried + 1;
        %             rethrow(ME);
        %         end
        %
        %     else
        %         try
        %             disp('not deployed');
        %             if app.connection.isConnected
        %                 app.connection.close;
        %                 app.connection = struct;
        %                 app.connection.isConnected = false;
        %             end
        %             %                     app.connection = dj.conn();
        %             app.connection = dj.conn('vfsmdatajoint01.fsm.northwestern.edu', userName, password, [], true);
        %         catch ME
        %             disp('not deployed - wrong password');
        %             app.HTML.Data.loginTried = app.HTML.Data.loginTried + 1;
        %             rethrow(ME);
        %
        %         end
        %     end

        %         if app.connection.isConnected
        %             disp('login successful');
        %             app.login_user = userName;
        %             %enable AnimalLogger tab
        %             %if strcmp(app.login_user, 'Susan') || strcmp(app.login_user, 'Greg')
        %             app.accessible_tabs = [app.accessible_tabs, ...
        %                 'AnimalLogger', ...
        %                 'Breeders', ...
        %                 'Add Animals', ...
        %                 'Add Strain', ...
        %                 'Add Locus / Allele',...
        %                 'Add Substance',...
        %                 'Add Brain Area',...
        %                 'Add Source', ...
        %                 'Add Project',...
        %                 'Add Behavior Experiment Type / Stimulus', ...
        %                 'Lookup Deceased Animal', ...
        %                 'Log',...
        %                 'TODO List',...
        %                 ];
        %             %                 else
        %             %                     app.accessible_tabs = [app.accessible_tabs, ...
        %             %                         'AnimalLogger', ...
        %             %                         'Breeders', ...
        %             %                         'Add Animals', ...
        %             %                         'Add Genotype', ...
        %             %                         'Add Substance',...
        %             %                         'Add Brain Area',...
        %             %                         'Add Project',...
        %             %                         'Add Behavior Experiment Type', ...
        %             %                         'Log',...
        %             %                         'TODO List',...
        %             %                         ];
        %             %                 end
        %
        %             app.MainTabGroup.SelectedTab = app.AnimalLoggerTab;
        %
        %             %load users
        %             app.loadUsers(); %this is here so that it can set the login user to the default
        %
        %             %initialize the appropriate tabs
        %             app.init_AnimalLoggerTab
        %
        %
        %         end
        %     end
    end

    methods (Static)

        function ind = sortEvents(events)
            ev_structs = fetch(events,'date','time');
            L = length(ev_structs);
            %evTimes = datetime(L,1);
            for i=1:L
                if isempty(ev_structs(i).time)
                    thisTime = '00:00:00';
                else
                    thisTime = ev_structs(i).time;
                end
                evTimes(i) = datetime([ev_structs(i).date, ' ', thisTime]);
            end

            [~,ind] = sort(evTimes);
        end

        function setRadioButtonToValue(buttonGroup, value)
            ch = get(buttonGroup, 'children');
            N_children = length(ch);
            for i=1:N_children
                if strcmp(get(ch(i),'Text'), value)
                    set(buttonGroup, 'SelectedObject', ch(i));
                end
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.connection = struct;
            app.connection.isConnected = false;
            setenv('DJ_USER', 'a');
            setenv('DJ_HOST', ' ');

            if isdeployed
                app.logFolder = 'T:\Ophthalmology\Research\SchwartzLab\AnimalLogs\';
                user = compiler.UserInfo()
            else
                app.logFolder = '/Volumes/SchwartzLab/AnimalLogs/';
            end
            %datajoint setup

            drawnow;
% 
%             dj.close();
%             setenv('DJ_USER', '');
%             setenv('DJ_PASS', '');

        end

        % Selection change function: MainTabGroup
        function MainTabGroupSelectionChanged(app, event)
            selectedTab = app.MainTabGroup.SelectedTab;

            if ~ismember(get(selectedTab,'Title'),app.accessible_tabs)
                app.MainTabGroup.SelectedTab = event.OldValue;
            else
                selectedTabName = get(selectedTab,'Title');

                switch(selectedTabName)
                    case 'AnimalLogger'
                        app.init_AnimalLoggerTab;
                    case 'Breeders'
                        app.init_BreedersTab;
                    case 'Add Animals'
                        app.init_AddAnimalTab;
                    case 'Add Strain'
                        app.init_AddStrainTab;
                    case 'Add Locus / Allele'
                        app.init_AddAlleleLocusTab;
                    case 'Add Substance'
                        app.init_AddSubstanceTab;
                    case 'Add Brain Area'
                        app.init_AddBrainAreaTab;
                    case 'Add Project'
                        app.init_AddProjectTab;
                    case 'Add Source'
                        app.init_addSourceTab;
                    case 'Add Behavior Experiment Type / Stimulus'
                        app.init_AddBehaviorExpTab;
                    case 'Lookup Animal'
                        app.init_LookupDeceasedTab;
                    case 'TODO List'
                        app.init_TODOListTab;
                    case 'Login'
                        %                         if app.connection.isConnected
                        %                             app.connection.close;
                        %                             app.connection = struct;
                        %                             app.connection.isConnected = false;
                        %                         end
                        set(app.PasswordEditField,'value','');
                        %                         app.accessible_tabs = {'Login'};
                end
            end
        end

        % Value changing function: PasswordEditField
        function PasswordEditFieldValueChanging(app, event)
            val = event.Value;
            current_pass_length=length(val); %get the number of characters present in password edit field
            app.prev_password_length=length(app.user_password);

            %if password edit field is empty and is in focus
            if isempty(val)
                app.user_password='';   %set the password property to empty string
                app.prev_password_length=0;  %set the previous password length to zero
                app.PasswordEditField.Value='';  %set the value in edit field to empty string

                %if backspace is clicked by user
            elseif(current_pass_length<app.prev_password_length)
                app.user_password = app.user_password(1:current_pass_length);
                app.PasswordEditField.Value = repelem('*', current_pass_length);
            else
                %remove the previous available '*' from the password value obtained from the app
                add_string=erase(val, repelem('*', length(app.user_password)));

                %append the chacter to user_passowrd property
                app.user_password=append(app.user_password,add_string);

                %mask the password in edit field with the total length of characters stored in user_password
                app.PasswordEditField.Value = repelem('*', length(app.user_password));
            end
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            currentUser = get(app.LoginUserEditField, 'value');
            if strcmp(currentUser,'')
                currentUser = 'none';
            end
            if isdeployed
                try
                    disp('deployed');
                    if app.connection.isConnected
                        app.connection.close;
                        app.connection = struct;
                        app.connection.isConnected = false;
                    end
                    if ~strcmp(currentUser,'none')
                        app.connection = dj.conn('vfsmdatajoint01.fsm.northwestern.edu', currentUser, app.user_password, [], true);
                    end
                catch ME
                    disp('deployed');
                    rethrow(ME);
                end
            else
                try
                    disp('not deployed');
                    if app.connection.isConnected
                        app.connection.close;
                        app.connection = struct;
                        app.connection.isConnected = false;
                    end
                    %                     app.connection = dj.conn();
                    app.connection = dj.conn('vfsmdatajoint01.fsm.northwestern.edu', currentUser, app.user_password, [], true);
                catch ME
                    disp('not deployed');
                    rethrow(ME)
                end
            end

            if app.connection.isConnected
                disp('login successful');
                app.load_database_items();
                app.login_user = currentUser;
                %enable AnimalLogger tab
                %if strcmp(app.login_user, 'Susan') || strcmp(app.login_user, 'Greg')
                app.accessible_tabs = [app.accessible_tabs, ...
                    'AnimalLogger', ...
                    'Breeders', ...
                    'Add Animals', ...
                    'Add Strain', ...
                    'Add Locus / Allele',...
                    'Add Substance',...
                    'Add Brain Area',...
                    'Add Source', ...
                    'Add Project',...
                    'Add Behavior Experiment Type / Stimulus', ...
                    'Lookup Animal', ...
                    'Log',...
                    'TODO List',...
                    'Cage lookup',...
                    ];

                app.MainTabGroup.SelectedTab = app.AnimalLoggerTab;

                %load users
                app.loadUsers(); %this is here so that it can set the login user to the default

                %initialize the appropriate tabs
                app.init_AnimalLoggerTab
            else
                disp('login failed');
            end
        end

        % Value changed function: DOB_known_switch
        function DOB_known_switchValueChanged(app, event)
            value = app.DOB_known_switch.Value;
            if strcmp(value,'DOB unknown')
                set(app.DOBDatePicker,'Enable',false);
            else
                set(app.DOBDatePicker,'Enable',true);
            end
        end

        % Value changed function: tagged_switch
        function tagged_switchValueChanged(app, event)
            value = app.tagged_switch.Value;
            if ~strcmp(value,'Tagged')
                set(app.TagIDEditField,'Enable',false)
            else
                set(app.TagIDEditField,'Enable',true);
            end
        end

        % Button pushed function: AddsingleanimaltodatabaseButton
        function AddsingleanimaltodatabaseButtonPushed(app, event)
            %make insert key
            key = struct;

            key_tag.tag_id = str2double(get(app.TagIDEditField,'value'));
            key_tag.tag_ear = get(get(app.TagearButtonGroup,'SelectedObject'), 'text');
            key_tag.punch = get(get(app.PunchearButtonGroup,'SelectedObject'), 'text');

            %get background
            bname = regexp(get(app.StrainDropDown_2, 'value'), '(.*) \((.*)\)','tokens');

            key.background_name = bname{1}{2};
            key.strain_name = bname{1}{1};

            %TODO
            %             key.species_name = 'mouse';
            %get(get(app.SpeciesTabGroup, 'SelectedTab'), 'Title');

            %check DOB
            if strcmp(get(app.DOB_known_switch,'value'),'DOB known')
                key.dob = char(get(app.DOBDatePicker,'value'));
            else
                key.dob = [];
            end

            %sex
            key.sex = get(get(app.SexButtonGroup,'SelectedObject'), 'text');

            %source and source_id
            source_id_str = get(app.AnimalSourceDropDown,'value');
            key.source_id = str2double(strtok(source_id_str,':'));

            %external
            key_external.external_info = get(app.ExternalInfoEditField, 'value');

            %assign tag
            key_tag.user_name = app.login_user;
            if isempty(key.dob)
                key_tag.date = '1970-01-01'; %arbitrary early date
            else
                key_tag.date = key.dob; %wean date is more exact, but this will do
            end

            %assign cage
            key_cage.cage_number = str2double(get(app.CageEditField,'value'));
            key_cage.user_name = app.login_user;
            key_cage.cause = 'assigned at database insert';
            if isempty(key.dob)
                key_cage.date = '1970-01-01'; %arbitrary early date
            else
                %                 key_cage.date = char(datetime(get(app.DOBDatePicker,'value') + days(22), 'Format', 'yyyy-MM-dd')); %wean date approximately
                key_cage.date = char(datetime(get(app.CageEntryDatePicker, 'value'), 'Format', 'yyyy-MM-dd'));
            end

            %assign room
            key_room = rmfield(key_cage,'cause');
            key_room.room_number = get(app.RoomDropDown, 'value');

            %add the animal
            disp('Adding animal with keys:');
            key
            key_tag
            key_cage
            key_room
            key_external
            C = dj.conn;
            C.startTransaction;
            inserted = false;
            disp('before insert')
            try
                disp('starting insert')
                key
                insert(sln_animal.Animal,key);
                disp('inserted animal')
                last_id = fetch1(sln_animal.Animal,'animal_id','ORDER BY animal_id DESC LIMIT 1');
                %give it eyes
                key_eyes.animal_id = last_id;
                key_eyes.side = 'Left';
                insert(sln_animal.Eye,key_eyes);
                key_eyes.side = 'Right';
                insert(sln_animal.Eye,key_eyes);
                disp('inserted eyes')
                key_tag.animal_id = last_id;
                key_cage.animal_id = last_id;
                if strcmp(get(app.tagged_switch, 'value'), 'Tagged')
                    sln_animal.add_event(key_tag,'Tag',C);
                end
                disp('past tag')
                sln_animal.add_event(key_cage,'AssignCage',C);
                insert(sln_animal.CageAssignRoom, key_room); %does this end the transaction?
                disp('inserted room event')
                %                 sln_animal.updateGenotypeString(last_id); %TODO: can probably be done more elegantly
                if ~isempty(key_external.external_info)
                    key_external.animal_id = last_id;
                    insert(sln_animal.AnimalExternal, key_external);
                end
                disp('inserted external event')
                out_txt = 'animal insert successful';
                C.commitTransaction;
                inserted = true;
            catch ME
                disp('animal insert failed');
                C.cancelTransaction;
                out_txt = ['animal insert failed', ME.message];
                %rethrow(ME);
            end

            if inserted
                set(app.StatusLamp_Animal,'color','green');
                pause(2);
                set(app.StatusLamp_Animal,'color','white');
            else
                set(app.StatusLamp_Animal,'color','red');
            end
            set(app.AnimalInsertStatusEdit,'value',out_txt);
        end

        % Value changed function: RecordBehavior_switch
        function RecordBehavior_switchValueChanged(app, event)
            value = app.RecordBehavior_switch.Value;
            if strcmp(value,'Recorded')
                app.Lamp_isRecorded.Enable = true;
            else
                app.Lamp_isRecorded.Enable = false;
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            selectedButton = app.SearchTypeButtonGroup.SelectedObject;
            q = [];
            switch get(selectedButton,'Text')
                case 'DJ ID'
                    v = get(app.Search_DJIDEditField,'value');
                    if ischar(v)
                        v = str2double(v);
                    end
                    if ~isnan(v)
                        q.animal_id = v;
                    end
                case 'Cage #'
                    v = str2double(get(app.CageNumSearchEditField,'value'));
                    allCage = sln_animal.Animal.cageNumber([],true);
                    ind = find([allCage.cage_number]== v);
                    if isempty(ind)
                        q = struct;
                        q.animal_id = 0;
                    end
                    for i=1:length(ind)
                        q(i).animal_id = allCage(ind(i)).animal_id;
                    end
                case 'Tag #'
                    v = get(app.Search_TagIDEditField,'value');
                    if ischar(v)
                        v = str2double(v);
                    end
                    allTag = sln_animal.Animal.tagNumber([],true);
                    ind = find([allTag.tag_id]==v);
                    if isempty(ind)
                        q = struct;
                        q.animal_id = 0;
                    else
                        q.animal_id = allTag(ind).animal_id;
                    end
            end
            if ~isempty(q)
                app.loadAnimalTable(q)
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            q = [];
            app.loadAnimalTable(q)
        end

        % Cell selection callback: AnimalTable
        function AnimalTableCellSelection(app, event)
            indices = event.Indices;
            selectedRow = indices(1,1);

            D = get(app.AnimalTable,'Data');
            Nrows = size(D,1);

            %select animal
            app.selected_animal_id = D{selectedRow,1};

            %set cage in move cage tab
            selected_cage = str2double(D{selectedRow,5});
            if ~isnan(selected_cage)
                set(app.MoveCagenumberEditField,'value',selected_cage);
            end

            %make styles
            curStyle = uistyle('FontColor','red','FontWeight','bold');
            oldStyle = uistyle('FontColor','black','FontWeight','normal');

            %add style to this row
            addStyle(app.AnimalTable,curStyle,'row',selectedRow);

            %put other rows back to normal
            otherRows = setdiff(1:Nrows,selectedRow);
            addStyle(app.AnimalTable,oldStyle,'row',otherRows);
            %             for i=1:Nrows-1
            %                 addStyle(app.AnimalTable,oldStyle,'row',otherRows(i));
            %             end

            %update genotype
            %update genotype event entries
            app.loadGenotypeTabMenus();
            app.updateGenotypeEntryVisibility();
        end

        % Value changed function: Filter_SexDropDown
        function Filter_SexDropDownValueChanged(app, event)
            value = app.Filter_SexDropDown.Value;
            if strcmp(value,'All')
                app.filter_struct = rmfield(app.filter_struct, 'sex');
            else
                app.filter_struct.sex = value;
            end
            app.loadAnimalTable([]);
        end

        % Value changed function: Filter_GenotypeDropDown
        function Filter_GenotypeDropDownValueChanged(app, event)
            value = app.Filter_GenotypeDropDown.Value;
            if strcmp(value,'All')
                app.filter_struct = rmfield(app.filter_struct, 'genotype_name');
            else
                app.filter_struct.genotype_name = value;
            end
            app.loadAnimalTable([]);
        end

        % Value changed function: Filter_GenotypeStatusDropDown
        function Filter_GenotypeStatusDropDownValueChanged(app, event)
            value = app.Filter_GenotypeStatusDropDown.Value;
            app.filter_genotype_status = value;
            app.loadAnimalTable([]);
        end

        % Callback function
        function ID_type_switchValueChanged(app, event)
            value = app.ID_type_switch.Value;
            if strcmp(value,'DJ ID')
                set(app.Search_DJIDEditField, 'Enable', true);
                set(app.Search_TagIDEditField, 'Enable', false);
            else
                set(app.Search_DJIDEditField, 'Enable', false);
                set(app.Search_TagIDEditField, 'Enable', true);
            end
        end

        % Selection change function: EventTabGroup
        function EventTabGroupSelectionChanged(app, event)
            selectedTab = app.EventTabGroup.SelectedTab;
            selectedTabName = get(selectedTab,'Title');
            if strcmp(selectedTabName, 'Assign cage') ...
                    || strcmp(selectedTabName, 'Move cage room') ...
                    || strcmp(selectedTabName, 'Tag') ...
                    || strcmp(selectedTabName, 'Reserve for project') ...
                    || strcmp(selectedTabName, 'Reserve for rig')
                set(app.EventTimeH,'Enable',false);
                set(app.EventTimeM,'Enable',false);
                set(app.EventTimeAMPM,'Enable',false);
            else
                set(app.EventTimeH,'Enable',true);
                set(app.EventTimeM,'Enable',true);
                set(app.EventTimeAMPM,'Enable',true);
            end
            if strcmp(selectedTabName, 'Social behavior session')
                set(app.EventTimeH,'Value','4');
                set(app.EventTimeM,'Value','00');
                set(app.EventTimeAMPM,'Value','PM');
            else
                set(app.EventTimeH,'Value','9');
                set(app.EventTimeM,'Value','00');
                set(app.EventTimeAMPM,'Value','AM');
            end
            if strcmp(selectedTabName, 'Genotyped')
                app.loadGenotypeTabMenus();
                app.updateGenotypeEntryVisibility();
            end

            set(app.EventdateDatePicker,'value',datetime(date,'format','yyyy-MM-dd'))
        end

        % Button pushed function: SubmitevententryButton
        function SubmitevententryButtonPushed(app, event)
            selectedTabName = get(get(app.EventTabGroup, 'SelectedTab'), 'Title');
            if isempty(app.selected_animal_id) && ~strcmp(selectedTabName, 'Pair breeders')
                disp('No animal selected');
                set(app.StatusLamp_Logger,'color','red');
                set(app.EventInsertStatusEdit,'value','No animal selected');
                return;
            end

            set(app.SubmitevententryButton,'enable',false); %disable while working
            drawnow; %not updating fast - make it a toggle button?

            key.user_name = get(app.PersonDropDown, 'value');
            key.date = datestr(get(app.EventdateDatePicker, 'value'),'yyyy-mm-dd');
            key.animal_id = app.selected_animal_id;
            notes = get(app.EventNotesTextArea, 'value');
            key.notes = strcat(cell2mat(notes'));
            hrs = str2double(get(app.EventTimeH, 'value'));
            if strcmp(get(app.EventTimeAMPM,'value'), 'PM')
                hrs = rem(hrs, 12) + 12;
            end
            mins = get(app.EventTimeM, 'value');
            key.time = [num2str(hrs,'%02d') ':' num2str(mins,'%02d') ':00'];

            switch selectedTabName
                case "Genotyped"
                    source_name = get(app.GenotypeSourceDropDown,'value');
                    if strcmp(source_name, '')
                        inserted = false;
                        out_txt = 'You must enter a source for this genotyping result';
                    else
                        key.source_id = app.GenotypeSourceDropDown_map(source_name);
                        key
                        C = dj.conn;
                        C.startTransaction;

                        N_entries = app.GenotypeEntriesSpinner.Value;
                        for i=1:N_entries
                            cur_entry_key = key;
                            cur_entry_key.locus_name = get(app.(['LocusDropDown_' num2str(i)]), 'value');
                            cur_entry_key.allele1 = get(app.(['Allele1DropDown_' num2str(i)]), 'value');
                            cur_entry_key.allele2 = get(app.(['Allele2DropDown_' num2str(i)]), 'value');
                            cur_entry_key.allele2 = get(app.(['Allele2DropDown_' num2str(i)]), 'value');

                            if strcmp(cur_entry_key.allele1, '') ||  strcmp(cur_entry_key.allele2, '')
                                error('You must enter a value for each allele.');
                            end

                            [inserted, out_txt] = sln_animal.add_event(cur_entry_key,'GenotypeResult',C);
                            if ~inserted
                                break
                            end
                        end
                        if inserted
                            set(app.GenotypeSourceDropDown,'value','');
                            C.commitTransaction;
                            for i=1:N_entries
                                set(app.(['Allele1DropDown_' num2str(i)]), 'value','');
                                set(app.(['Allele2DropDown_' num2str(i)]), 'value','');
                            end
                        else
                            C.cancelTransaction;
                            out_txt = sprintf('Genotype result insert failed: %s',out_txt);
                        end
                    end

                case "Deceased"
                    key.cause = get(app.DeceasedReasonDropDown, 'value');
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'Deceased');
                case "Assign cage"
                    key.cage_number = str2double(get(app.TocageEditField, 'value'));
                    key.cause = get(app.MoveReasonDropDown, 'value');
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'AssignCage');

                    key_room = rmfield(key,{'cause','notes','animal_id','time'});
                    key_room.room_number = get(app.RoomDropDown_logger, 'value'); %TODO - finish this
                    key
                    insert(sln_animal.CageAssignRoom,key_room);
                case "Move cage room"
                    key = rmfield(key,{'notes','animal_id','time'});
                    key.cage_number = get(app.MoveCagenumberEditField,'value');
                    key.room_number = get(app.ToroomDropDown,'value');
                    key
                    try
                        insert(sln_animal.CageAssignRoom,key);
                        inserted = true;
                        out_txt = 'move cage successful';
                    catch ME
                        inserted = false;
                        out_txt = sprintf('move cage failed: %s', ME.message);
                    end
               case "Tag"
                    key = rmfield(key,'time'); %no time for this one
                    key.tag_id = uint32(str2double(get(app.TagEditField, 'value'))); % -_- this will result in 0 if empty fix below I guess?
                    if isempty(get(app.TagEditField, 'value'))
                        key.tag_id = nan(1,1);
                    end
                    key.tag_ear = get(app.TagearDropDown, 'value');
                    key.punch = get(app.EarpunchDropDown, 'value');
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'Tag');
                case "IP injection"
                    substance_name = get(app.SubstanceDropDown_IP, 'value');
                    thisSubstance = sln_animal.InjectionSubstance & sprintf('substance_name="%s"',substance_name);
                    key.substance_id = thisSubstance.fetch1('substance_id');
                    key.concentration = str2double(get(app.IP_inj_ConcentrationEditField, 'value'));
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'IPInjection');
                case "Feed"
                    substance_name = get(app.SubstanceDropDown_Feed, 'value');
                    thisSubstance = sln_animal.InjectionSubstance & sprintf('substance_name="%s"',substance_name);
                    key.substance_id = thisSubstance.fetch1('substance_id');
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'Feed');
                case "Eye injection"
                    substance_name = get(app.SubstanceDropDown_Eye, 'value');
                    thisSubstance = sln_animal.InjectionSubstance & sprintf('substance_name="%s"',substance_name);
                    key.substance_id = thisSubstance.fetch1('substance_id');
                    key.dilution = str2double(get(app.Dilution_EyeEditField, 'value'));
                    key.side = get(app.WhichEyeDropDown, 'value');

                    %add the eyes now if they are not in the DB
                    these_eyes = sln_animal.Eye & sprintf('animal_id=%d', key.animal_id);
                    if these_eyes.count==0
                        eye1.animal_id = key.animal_id;
                        eye1.side = 'Left';
                        eye2.animal_id = key.animal_id;
                        eye2.side = 'Right';
                        insert(sln_animal.Eye, eye1);
                        insert(sln_animal.Eye, eye2);
                    end

                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'EyeInjection');
                case "Eye suture"
                    key.side = get(app.WhicheyeDropDown, 'value');

                    %add the eyes now if they are not in the DB
                    these_eyes = sln_animal.Eye & sprintf('animal_id=%d', key.animal_id);
                    if these_eyes.count==0
                        eye1.animal_id = key.animal_id;
                        eye1.side = 'Left';
                        eye2.animal_id = key.animal_id;
                        eye2.side = 'Right';
                        insert(sln_animal.Eye, eye1);
                        insert(sln_animal.Eye, eye2);
                    end

                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'EyeSuture');
                case "Brain injection"
                    substance_name = get(app.SubstanceDropDown_Brain, 'value');
                    thisSubstance = sln_animal.InjectionSubstance & sprintf('substance_name="%s"',substance_name);
                    key.substance_id = thisSubstance.fetch1('substance_id');
                    key.dilution = str2double(get(app.Dilution_BrainEditField, 'value'));
                    key.target = get(app.BrainTargetDropDown, 'value');
                    key.hemisphere = get(app.HemisphereDropDown, 'value');
                    key.head_rotation = str2double(get(app.AngleEditField, 'value'));
                    AP = str2double(get(app.APEditField, 'value'));
                    ML = str2double(get(app.MLEditField, 'value'));
                    DV = str2double(get(app.DVEditField, 'value'));
                    key.coordinates = [AP, ML, DV];
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'BrainInjection');
                case "Other procedure"
                    key
                    key.procedure_name = get(app.OtherProcNameEdit,'value');
                    [inserted, out_txt] = sln_animal.add_event(key,'OtherProcedure');
                case "Pair breeders"
                    try
                        key = rmfield(key,'time'); %no time for this one
                        key.male_id = str2double(get(app.MaleDJIDPairEditField,'value'));
                        key.female_id = str2double(get(app.FemaleDJIDPairEditField,'value'));
                        %key.animal_id = key.female_id; %AnimalEvent needs an animal_id so we will set it to the female
                        male = sln_animal.Animal & sprintf('animal_id=%d', key.male_id);
                        if ~male.exists
                            error('Male with DJID = %d not found', key.male_id);
                        elseif ~strcmp(fetch1(male,'sex'),'Male')
                            error('Animal with DJID = %d is not male', key.male_id);
                        end
                        female = sln_animal.Animal & sprintf('animal_id=%d', key.female_id);
                        if ~female.exists
                            error('Female with DJID = %d not found', key.female_id);
                        elseif ~strcmp(fetch1(female,'sex'),'Female')
                            error('Animal with DJID = %d is not female', key.female_id);
                        end
                        key.cage_number = str2double(get(app.NewcagenumberPairEditField,'value'));

                        key_room = rmfield(key,{'notes','animal_id','male_id','female_id'});
                        key_room.room_number = get(app.RoomDropDown_Pair, 'value');

                        C = dj.conn;
                        C.startTransaction;
                        %first do cage assignments
                        key_cage = rmfield(key,{'male_id', 'female_id'});
                        key_cage.animal_id = key.male_id;
                        key_cage.cause = 'set as breeder';
                        key_cage
                        sln_animal.add_event(key_cage,'AssignCage',C);
                        key_cage.animal_id = key.female_id;
                        key_cage
                        sln_animal.add_event(key_cage,'AssignCage',C);
                        key_room
                        insert(sln_animal.CageAssignRoom,key_room);
                        C.commitTransaction;

                        %make breeding pair
                        %need to activate the breeding cage in a separate
                        %transaction
                        key_breeding_pair.male_id = key.male_id;
                        key_breeding_pair.female_id = key.female_id;
                        key_breeding_pair.strain_name = strtrim(strtok(get(app.StrainnameDropDown,'value'),'('));

                        if ~strcmp(fetch1(sln_animal.Animal & sprintf('animal_id=%d',key.male_id),'background_name'),'C57bl/6') || ~strcmp(fetch1(sln_animal.Animal & sprintf('animal_id=%d',key.female_id),'background_name'),'C57bl/6')
                            error('Only permitted to pair c57bl/6 mice! Please speak to an administrator');
                        end
                        key_breeding_pair.background_name = 'C57bl/6'; %TEMP HACK - need to make this secondary key
                        key_breeding_pair
                        sln_animal.add_source(key_breeding_pair, 'BreedingPair');

                        key_proj_male = rmfield(key,{'male_id', 'female_id', 'cage_number'});
                        key_proj_male.animal_id = key.male_id;
                        key_proj_male.project_name = 'Breeding';
                        key_proj_female = rmfield(key,{'male_id', 'female_id', 'cage_number'});
                        key_proj_female.animal_id = key.female_id;
                        key_proj_female.project_name = 'Breeding';

                        key_proj_male
                        key_proj_female
                        sln_animal.add_event(key_proj_male,'ReservedForProject');
                        sln_animal.add_event(key_proj_female,'ReservedForProject');

                        inserted = true;
                        out_txt = 'Pair breeders successful.';
                    catch ME
                        inserted = false;
                        out_txt = ['Pair breeders failed ' ME.message];
                    end


                    %                     thisPair = sln_animal.BreedingPair & key_breeding_pair;
                    %                     source_id = fetch1(thisPair,'source_id');
                    %                     key_activate = rmfield(key,{'animal_id','male_id','female_id','cage_number'});
                    %                     key_activate.source_id = source_id;
                    %                     key_activate
                    %                     try
                    %                         insert(sln_animal.ActivateBreedingPair, key_activate);
                    %                         out_txt = 'activation of breeding cage successful';
                    %                         inserted = true;
                    %                     catch ME
                    %                         out_txt = sprintf('activation of breeding cage failed: %s', ME.message);
                    %                         inserted = false;
                    %                     end
                case "Social behavior session"
                    key.purpose = get(app.ExperimenttypeDropDown,'value');
                    key.animal_type_name = get(app.TestanimaltypeDropDown,'value');
                    if strcmp(get(app.RecordBehavior_switch, 'value'), 'Recorded')
                        key.recorded = 'T';
                    else
                        key.recorded = 'F';
                    end
                    key.stimArms = {'A', 'B', 'C'};
                    key.stimTypes{1} = get(app.WindowAStim,'value');
                    key.stimTypes{2} = get(app.WindowBStim,'value');
                    key.stimTypes{3} = get(app.WindowCStim,'value');
                    if get(app.AnimalAEditField, 'visible')
                        key.stimIDs(1) = str2double(get(app.AnimalAEditField,'value'));
                    else
                        key.stimIDs(1) = 0;
                    end
                    if get(app.AnimalBEditField, 'visible')
                        key.stimIDs(2) = str2double(get(app.AnimalBEditField,'value'));
                    else
                        key.stimIDs(2) = 0;
                    end
                    if get(app.AnimalCEditField, 'visible')
                        key.stimIDs(3) = str2double(get(app.AnimalCEditField,'value'));
                    else
                        key.stimIDs(3) = 0;
                    end
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'SocialBehaviorSession');
                case "Reserve for project"
                    key.project_name = get(app.ProjectDropDown, 'value');
                    key
                    [inserted, out_txt] = sln_animal.add_event(key,'ReservedForProject');
                case "Reserve for rig"
                    whichRig = get(app.RignameDropDown, 'value');
                    key = rmfield(key,'time'); %no time for this one
                    if strcmp(whichRig, 'A and B')
                        keyA = key;
                        keyA.rig_name = 'A';
                        keyB = key;
                        keyB.rig_name = 'B';
                        keyA
                        keyB
                        [insertedA, out_txt] = sln_animal.add_event(keyA,'ReservedForSession');
                        [calEventAddedA, result] = app.addCalendarEvent(keyA, 'reserveForRig');
                        [insertedB, out_txt] = sln_animal.add_event(keyA,'ReservedForSession');
                        [calEventAddedB, result] = app.addCalendarEvent(keyB, 'reserveForRig');
                        out_txt = [out_txt '; Cal: ' result];
                        inserted = insertedA & calEventAddedA & insertedB & calEventAddedB ;
                    else
                        key
                        key.rig_name = whichRig;
                        [inserted, out_txt] = sln_animal.add_event(key,'ReservedForSession');
                        [calEventAdded, result] = app.addCalendarEvent(key, 'reserveForRig');
                        out_txt = [out_txt '; Cal: ' result];
                        inserted = inserted & calEventAdded;
                    end
            end
            if inserted
                set(app.StatusLamp_Logger,'color','green');
                %need to reload current table state
                app.loadAnimalTable([]);
                pause(2);
                set(app.StatusLamp_Logger,'color','white');
            else
                set(app.StatusLamp_Logger,'color','red');
            end
            set(app.EventInsertStatusEdit,'value',out_txt);

            set(app.SubmitevententryButton,'enable',true); %enable
            drawnow;
        end

        % Value changed function: Filter_ProjectDropDown
        function Filter_ProjectDropDownValueChanged(app, event)
            app.loadAnimalTable([]);
        end

        % Close request function: AnimalInterfaceV2UIFigure
        function AnimalInterfaceV2UIFigureCloseRequest(app, event)
            if app.connection.isConnected && isdeployed
                app.connection.close;
            end
            delete(app)
        end

        % Value changed function: ageLow_edit
        function ageLow_editValueChanged(app, event)
            app.filter_ageLow = str2double(app.ageLow_edit.Value);
            app.loadAnimalTable([]);
        end

        % Value changed function: ageHigh_edit
        function ageHigh_editValueChanged(app, event)
            app.filter_ageHigh = str2double(app.ageHigh_edit.Value);
            app.loadAnimalTable([]);
        end

        % Button pushed function: AddsubstancetodatabaseButton
        function AddsubstancetodatabaseButtonPushed(app, event)
            key.substance_name = get(app.SubstancenameEditField, 'value');
            desc = get(app.DescriptionTextArea_substance, 'value');
            desc_flat = strcat(cell2mat(desc'));
            key.description = desc_flat;
            location = get(app.StorageLocationTextArea_substance, 'value');
            location_flat = strcat(cell2mat(location'));
            key.storage_location = location_flat;
            key.catalog_number = get(app.CatalogEditField_substance, 'value');

            %source information
            source_type = get(get(app.VendorOrCollaborator_ButtonGroup,'SelectedObject'),'Text');
            if strcmp(source_type,'Vendor')
                vendor_name = get(app.SourceDropDown_substance,'Value');
                thisVendor = sln_animal.Vendor & sprintf('vendor_name="%s"', vendor_name);
                key.source_id = fetch1(thisVendor,'source_id');
            else %collaborator
                collaborator_name = get(app.SourceDropDown_substance,'Value');
                thisCollaborator = sln_animal.Collaborator & sprintf('lab_name="%s"', collaborator_name);
                key.source_id = fetch1(thisCollaborator,'source_id');
            end
            key

            try
                insert(sln_animal.InjectionSubstance, key);
                disp(['Substance ' key.substance_name ' insert successful']);
                thisSubstance = sln_animal.InjectionSubstance & sprintf('substance_name="%s"', key.substance_name);
                thisSubstance.activate();
                set(app.StatusLamp_Substance,'color','green');
                set(app.SubstanceInsertStatusEdit,'value','Substance insert successful.')
                pause(2);
                set(app.StatusLamp_Substance,'color','white');
                app.init_AddSubstanceTab(false);
            catch
                disp(['Substance ' key.substance_name ' insert failed']);
                set(app.StatusLamp_Substance,'color','red');
                set(app.SubstanceInsertStatusEdit,'value','Substance insert failed.')
                app.init_AddSubstanceTab(false);
                %rethrow(ME);
            end
        end

        % Value changed function: ActivesubstancesindatabaseListBox
        function ActivesubstancesindatabaseListBoxValueChanged(app, event)
            value = app.ActivesubstancesindatabaseListBox.Value;
            substance_name = strtrim(extractAfter(value,':'))
            thisSubstance = fetch(sln_animal.InjectionSubstance & sprintf('substance_name="%s"',substance_name), '*');
            set(app.SubstancenameEditField, 'value', thisSubstance.substance_name);
            set(app.DescriptionTextArea_substance, 'value', thisSubstance.description);
            set(app.CatalogEditField_substance, 'value', thisSubstance.catalog_number);
            set(app.StorageLocationTextArea_substance, 'value', thisSubstance.storage_location);

            %set source info
            vendor = sln_animal.Vendor & thisSubstance;
            if vendor.exists
                set(app.VendorOrCollaborator_ButtonGroup,'SelectedObject',app.VendorButton_substance);
                set(app.SourceDropDown_substance, 'items', app.vendor_name_list);
                set(app.SourceDropDown_substance, 'value', fetch1(vendor,'vendor_name'));
            else
                collaborator = sln_animal.Collaborator & thisSubstance;
                set(app.SourceDropDown_substance, 'items', app.collaborator_name_list);
                set(app.SourceDropDown_substance, 'value', fetch1(collaborator,'lab_name'));
            end
            %set(app.SourceEditField_substance, 'value', thisSubstance.source);

        end

        % Value changed function: BrainareasindatabaseListBox
        function BrainareasindatabaseListBoxValueChanged(app, event)
            value = app.BrainareasindatabaseListBox.Value;
            thisArea = fetch(sln_animal.BrainArea & sprintf('target="%s"',value), '*');
            set(app.AbbreviationEditField, 'value', thisArea.target);
            set(app.BrainLongNameTextArea, 'value', thisArea.long_name);
        end

        % Button pushed function: AddbrainareatodatabaseButton
        function AddbrainareatodatabaseButtonPushed(app, event)
            key.target = get(app.AbbreviationEditField, 'value');
            long_text = get(app.BrainLongNameTextArea, 'value');
            long_text_flat = strcat(cell2mat(long_text'));
            key.long_name = long_text_flat;
            try
                insert(sln_animal.BrainArea, key);
                disp(['Brain area ' key.target ' insert successful']);
                set(app.StatusLamp_BrainArea,'color','green');
                set(app.BrainAreaInsertStatusEdit,'value','Brain area insert successful.')
                pause(2);
                set(app.StatusLamp_BrainArea,'color','white');
                app.init_AddBrainAreaTab(false);
            catch ME
                disp(['Brain area ' key.target ' insert failed']);
                set(app.StatusLamp_BrainArea,'color','red');
                set(app.BrainAreaInsertStatusEdit,'value','Brain area insert failed.')
                %rethrow(ME);
            end

        end

        % Button pushed function: AlleventsforselectedanimalButton
        function AlleventsforselectedanimalButtonPushed(app, event)
            if isempty(app.selected_animal_id)
                disp('cannot show log: no animal selected');
                return;
            end
            N = length(app.animal_event_list);
            ev_cellArray = {};
            ev_type = {};
            ev_date = [];
            z = 1;
            for i=1:N
                curEvent = app.animal_event_list{i};
                q = sln_animal.AnimalEvent * feval(sprintf('sln_animal.%s', curEvent)) & sprintf('animal_id=%d',app.selected_animal_id);
                ev_struct = fetch(q,'*');
                for j=1:length(ev_struct)
                    ev_cellArray{z} = ev_struct(j);
                    ev_type{z} = curEvent;
                    if isfield(ev_struct(j),'time')
                        ev_date = [ev_date datetime([ev_struct(j).date ' ' ev_struct(j).time])];
                    else
                        ev_date = [ev_date datetime([ev_struct(j).date ' 00:00:00'])];
                    end
                    z=z+1;
                end
            end
            [~, order] = sort(ev_date);
            ev_type = ev_type(order);
            ev_cellArray = ev_cellArray(order);
            L = length(ev_type);
            logText = cell(L,1);
            for i=1:L
                thisType = ev_type{i}
                thisID = ev_cellArray{i}.event_id;
                ev = eval(sprintf('sln_animal.AnimalEvent*sln_animal.%s',thisType)) & sprintf('event_id=%d',thisID);
                e = eval(sprintf('sln_animal.%s',thisType)) & proj(ev);
                logText{i} = sln_animal.printEvents(e);
            end
            thisAnimal = fetch(sln_animal.Animal & sprintf('animal_id=%d', app.selected_animal_id),'*');
            animal_str = sprintf('Animal %d: %s born %s, source=%d',...
                thisAnimal.animal_id,thisAnimal.sex, thisAnimal.dob, thisAnimal.source_id);
            logText = cat(1,{animal_str; ''},logText);
            set(app.LogTextArea,'value', logText);
            app.MainTabGroup.SelectedTab = app.LogTab;
        end

        % Button pushed function: SubmitevententryButton_breeders
        function SubmitevententryButton_breedersPushed(app, event)
            %             if isempty(app.selected_animal_id)
            %                 disp('No animal selected');
            %                 set(app.StatusLamp_Logger,'color','red');
            %                 set(app.EventInsertStatusEdit,'value','No animal selected');
            %                 return;
            %             end
            selectedTabName = get(get(app.EventTabGroup_breeders, 'SelectedTab'), 'Title');
            key.user_name = get(app.PersonDropDown_breeders, 'value');
            key.date = datestr(get(app.EventdateDatePicker_breeders, 'value'),'yyyy-mm-dd');
            notes = get(app.EventNotesTextArea_breeders, 'value');
            key.notes = strcat(cell2mat(notes'));

            if isempty(app.selected_breeder_cage)
                disp('No breeder cage selected');
                set(app.StatusLamp_breeders,'color','red');
                set(app.EventInsertStatusEdit_breeders,'value','No breeder cage selected');
                return;
            end

            set(app.SubmitevententryButton_breeders,'enable',false); %disable while working
            drawnow; %not updating fast - make it a toggle button?

            %             key.user_name = get(app.PersonDropDown, 'value');
            %             key.date = datestr(get(app.EventdateDatePicker, 'value'),'yyyy-mm-dd');
            %             notes = get(app.EventNotesTextArea, 'value');
            %             key.notes = strcat(cell2mat(notes'));

            selected_ids = sln_animal.Cage.animalsInCage(app.selected_breeder_cage)
            if strcmp(selectedTabName, 'Separate harem')

            end

            if length(selected_ids) > 2 && ~strcmp(selectedTabName, 'Separate harem')
                error('More than 2 animals assigned to breeder cage %d. Use separate harem instead.', app.selected_breeder_cage);
            elseif length(selected_ids) == 1 %single female
                sex1 = fetch1(sln_animal.Animal & sprintf('animal_id=%d',selected_ids(1)),'sex');
                if strcmp(sex1, 'Female')
                    female_id = selected_ids(1);
                    male_id = 0;
                else
                    error('Lone breeder must be female.');
                end
            else
                sex1 = fetch1(sln_animal.Animal & sprintf('animal_id=%d',selected_ids(1)),'sex');
                if strcmp(sex1, 'Female')
                    female_id = selected_ids(1);
                    male_id = selected_ids(2);
                else
                    female_id = selected_ids(2);
                    male_id = selected_ids(1);
                end
                harem = false;
            end
            female_id
            male_id

            inserted = false;
            out_txt = '';
            %key.animal_id = app.selected_animal_id; %need to set 2 IDs
            switch selectedTabName
                case "Log birth"
                    %                     key.cage_number = app.selected_breeder_cage;
                    %                     thisPair = sln_animal.BreedingPair.active & sprintf('female_id=%d', female_id);
                    %                     key.source_id = fetch1(thisPair,'source_id');
                    key.animal_id = female_id;

                    try
                        sln_animal.add_event(key,'Birth');
                        %add calendar event for weaning
                        weanDate = datetime(key.date) + days(21);
                        weanKey.date = datestr(weanDate,'yyyy-mm-dd');
                        cage_number_querry = sprintf("fetchn(sln_animal.AnimalEvent * sln_animal.AssignCage & 'animal_id = %d', 'cage_number')", key.animal_id);
                        cage_nums = eval(cage_number_querry);
                        
                        weanKey.cage_number = cage_nums(end)
                       
                        %                         weanKey.cage_number = key.cage_number;
                        [calEventAdded, result] = app.addCalendarEvent(weanKey, 'weaning');

                        out_txt = ['Birth logged, Cal: ' result];
                        inserted = true;
                        %inserted = inserted & calEventAdded;
                    catch ME
                        out_txt = sprintf('log birth failed: %s', ME.message);
                    end
                case "Log weaning"
                    key.number_of_pups = str2double(get(app.NumberofpupsEditField_weaned, 'value'));
                    %                     key.cage_number = app.selected_breeder_cage;
                    %                     thisPair = sln_animal.BreedingPair.active & sprintf('female_id=%d', female_id);
                    %                     key.source_id = fetch1(thisPair,'source_id');
                    key.animal_id = female_id;

                    try
                        sln_animal.add_event(key,'Weaned');
                        out_txt = ['Weaning log successful'];
                        inserted = true;
                    catch ME
                        out_txt = sprintf('log wean failed: %s', ME.message);
                    end
                case "Separate breeding pair"
                    key_cage_male = key;
                    key_cage_male.cage_number = str2double(get(app.CageformaleEditField,'value'));
                    key_cage_male.room_number = get(app.RoomDropDown_breeders_sepMale, 'value');
                    key_cage_male.animal_id = male_id;
                    key_cage_male.cause = 'separated breeder';

                    key_proj_male = key;
                    key_proj_male.animal_id = male_id;
                    key_proj_male.project_name = 'Former breeder';

                    key_cage_male
                    key_proj_male

                    key_cage_female = key;
                    key_cage_female.cage_number = str2double(get(app.CageforfemaleEditField,'value'));
                    key_cage_female.room_number = get(app.RoomDropDown_breeders_sepFemale, 'value');
                    key_cage_female.animal_id = female_id;
                    key_cage_female.cause = 'separated breeder';

                    key_proj_female = key;
                    key_proj_female.animal_id = female_id;
                    key_proj_female.project_name = 'Former breeder';

                    key_cage_female
                    key_proj_female

                    try
                        sln_animal.add_event(key_cage_female,'AssignCage');
                        sln_animal.add_event(key_proj_female,'ReservedForProject');
                        if male_id > 0
                            sln_animal.add_event(key_cage_male,'AssignCage');
                            sln_animal.add_event(key_proj_male,'ReservedForProject');
                        end
                        inserted = true;
                        out_txt = 'separate breeders successful';
                    catch ME
                        out_txt = sprintf('separate breeders failed: %s', ME.message);
                    end

                case "Separate harem"

                    male_id = get(app.DJIDEditField_harem_male,'value');
                    female1_id = get(app.DJIDEditField_harem_female_1,'value');
                    female2_id = get(app.DJIDEditField_harem_female_2,'value');

                    key_cage_male = key;
                    key_cage_male.cage_number = str2double(get(app.CageforfemaleEditField_harem_male,'value'));
                    key_cage_male.room_number = get(app.RoomDropDown_breeders_harem_male, 'value');
                    key_cage_male.animal_id = male_id;
                    key_cage_male.cause = 'separated breeder';

                    key_proj_male = key;
                    key_proj_male.animal_id = male_id;
                    key_proj_male.project_name = 'Former breeder';

                    key_cage_male
                    key_proj_male

                    if female1_id>0
                        key_cage_female1 = key;
                        key_cage_female1.cage_number = str2double(get(app.CageforfemaleEditField_harem1,'value'));
                        key_cage_female1.room_number = get(app.RoomDropDown_breeders_harem_female1, 'value');
                        key_cage_female1.animal_id = female1_id;
                        key_cage_female1.cause = 'separated breeder';

                        key_proj_female1 = key;
                        key_proj_female1.animal_id = female1_id;
                        key_proj_female1.project_name = 'Former breeder';

                        key_cage_female1
                        key_proj_female1

                    end

                    if female2_id>0
                        key_cage_female2 = key;
                        key_cage_female2.cage_number = str2double(get(app.CageforfemaleEditField_harem2,'value'));
                        key_cage_female2.room_number = get(app.RoomDropDown_breeders_harem_female2, 'value');
                        key_cage_female2.animal_id = female1_id;
                        key_cage_female2.cause = 'separated breeder';

                        key_proj_female2 = key;
                        key_proj_female2.animal_id = female2_id;
                        key_proj_female2.project_name = 'Former breeder';

                        key_cage_female2
                        key_proj_female2
                    end

                    try
                        if female1_id>0
                            sln_animal.add_event(key_cage_female1,'AssignCage');
                            sln_animal.add_event(key_proj_female1,'ReservedForProject');
                        end
                        if female2_id>0
                            sln_animal.add_event(key_cage_female2,'AssignCage');
                            sln_animal.add_event(key_proj_female2,'ReservedForProject');
                        end

                        if male_id > 0
                            sln_animal.add_event(key_cage_male,'AssignCage');
                            sln_animal.add_event(key_proj_male,'ReservedForProject');
                        end
                        inserted = true;
                        out_txt = 'separate breeders successful';
                    catch ME
                        out_txt = sprintf('separate breeders failed: %s', ME.message);
                    end

                case "Remove male / assign new cage"
                    key_cage_male = key;
                    key_cage_male.cage_number = str2double(get(app.TocageEditField_MaleBreeder,'value'));
                    key_cage_male.room_number = get(app.RoomDropDown_MaleBreeder, 'value');
                    key_cage_male.animal_id = male_id;
                    key_cage_male.cause = 'separated breeder';

                    key_proj_male = key;
                    key_proj_male.animal_id = male_id;
                    key_proj_male.project_name = 'Former breeder';

                    key_cage_male
                    key_proj_male

                    try
                        %                         thisPair = sln_animal.BreedingPair.active & sprintf('female_id=%d', female_id);
                        %                         insert(sln_animal.BreedingFemale,rmfield(fetch(thisPair),'ev_id'));

                        sln_animal.add_event(key_cage_male,'AssignCage');
                        sln_animal.add_event(key_proj_male,'ReservedForProject');

                        inserted = true;
                        out_txt = 'move male breeder successful';
                    catch ME
                        out_txt = sprintf('move male breeder failed: %s', ME.message);
                    end
                case "Remove male / sacrifice"
                    key_deceased_male = key;
                    key_deceased_male.animal_id = male_id;
                    key_deceased_male.cause = get(app.DeceasedReasonDropDown_MaleBreeder,'value');
                    key_deceased_male

                    try
                        %                         thisPair = sln_animal.BreedingPair.active & sprintf('female_id=%d', female_id);
                        %                         insert(sln_animal.BreedingFemale,rmfield(fetch(thisPair),'ev_id'));

                        sln_animal.add_event(key_deceased_male,'Deceased');

                        inserted = true;
                        out_txt = 'sacrifice male breeder successful';
                    catch ME
                        out_txt = sprintf('sacrifice male breeder failed: %s', ME.message);
                    end
                case "Change cage number"
                    key_cage_male = key;
                    key_cage_male.cage_number = str2double(get(app.NewcagenumberEditField,'value'));
                    key_cage_male.room_number = get(app.RoomDropDown_BreedingPair, 'value');
                    key_cage_male.animal_id = male_id;
                    key_cage_male.cause = 'other';
                    key_cage_male

                    key_cage_female = key;
                    key_cage_female.cage_number = str2double(get(app.NewcagenumberEditField,'value'));
                    key_cage_female.room_number = get(app.RoomDropDown_BreedingPair, 'value');
                    key_cage_female.animal_id = female_id;
                    key_cage_female.cause = 'other';
                    key_cage_female

                    try
                        sln_animal.add_event(key_cage_male,'AssignCage');
                        sln_animal.add_event(key_cage_female,'AssignCage');
                        inserted = true;
                        out_txt = 'change breeder cage successful';
                    catch ME
                        out_txt = sprintf('change breeder cage failed: %s', ME.message);
                    end

                case "Sacrifice breeding pair"
                    key_deceased_male = key;
                    key_deceased_female = key;
                    key_deceased_male.animal_id = male_id;
                    key_deceased_female.animal_id = female_id;
                    key_deceased_male.cause = get(app.DeceasedReasonDropDown_BothBreeders,'value');
                    key_deceased_female.cause = get(app.DeceasedReasonDropDown_BothBreeders,'value');

                    key_deceased_male
                    key_deceased_female

                    try
                        sln_animal.add_event(key_deceased_female,'Deceased');
                        if male_id > 0
                            sln_animal.add_event(key_deceased_male,'Deceased');
                        end
                        inserted = true;
                        out_txt = 'sacrifice breeders successful';
                    catch ME
                        out_txt = sprintf('sacrifice breeders failed: %s', ME.message);
                    end
            end
            if inserted
                set(app.StatusLamp_breeders,'color','green');
                %need to reload current table state
                app.loadBreedingCagesTable([]);
                pause(2);
                set(app.StatusLamp_breeders,'color','white');
            else
                set(app.StatusLamp_breeders,'color','red');
            end
            set(app.EventInsertStatusEdit_breeders,'value',out_txt);
            set(app.SubmitevententryButton_breeders,'enable',true); %enable
            drawnow;
        end

        % Cell selection callback: BreederCagesTable
        function BreederCagesTableCellSelection(app, event)
            indices = event.Indices;
            selectedRow = indices(1,1);

            D = get(app.BreederCagesTable,'Data');
            Nrows = size(D,1);

            %select animal
            app.selected_breeder_cage = D{selectedRow,'cage_number'};

            %make styles
            curStyle = uistyle('FontColor','red','FontWeight','bold');
            oldStyle = uistyle('FontColor','black','FontWeight','normal');

            %add style to this row
            addStyle(app.BreederCagesTable,curStyle,'row',selectedRow);

            %put other rows back to normal
            otherRows = setdiff(1:Nrows,selectedRow);
            for i=1:Nrows-1
                addStyle(app.BreederCagesTable,oldStyle,'row',otherRows(i));
            end

            %set harem male DJID
            set(app.DJIDEditField_harem_male, 'value', D.male_id(selectedRow));
        end

        % Button pushed function: ExporttoExcelButton_breeders
        function ExporttoExcelButton_breedersPushed(app, event)
            %get breeder table
            D = get(app.BreederCagesTable,'Data');

            breederTable = D;

            curDate = string(datetime("today"), 'yyyy-MM-dd');
            defname = sprintf('%s-breederTable.xls', curDate);
            [fname, fpath] = uiputfile('*','Select table file location',defname);
            writetable(breederTable,[fpath fname]);
        end

        % Button pushed function: ExporttoExcelButton
        function ExporttoExcelButtonPushed(app, event)
            %get current table
            D = get(app.AnimalTable,'Data');
            curDate = string(datetime("today"), 'yyyy-MM-dd');
            defname = sprintf('%s_%s_animalTable.xls', curDate, app.login_user);
            [fname, fpath] = uiputfile('*','Select table file location',defname);
            writetable(D,[fpath, fname]);
        end

        % Button pushed function: ExporttotextfileButton
        function ExporttotextfileButtonPushed(app, event)
            logText = get(app.LogTextArea, 'value');
            Nlines = length(logText);

            curTime = string(datetime("now"), 'yyyy-MM-dd_hh-mm');
            defname = sprintf('eventLog_%s_%s.txt', curTime, app.login_user);
            [fname, fpath] = uiputfile('*','Select log file location',defname);
            fid = fopen([fpath fname],'w');

            for i=1:Nlines
                fprintf(fid, [logText{i} '\n']);
            end

            fclose(fid);

        end

        % Callback function
        function NumberofentriesSpinnerValueChanged(app, event)
            app.updateAnimalEntryVisibility();
        end

        % Callback function
        function SetotherentriesButtonPushed(app, event)
            
        end

        % Button pushed function: AddmultipleanimalstodatabaseButton
        function AddmultipleanimalstodatabaseButtonPushed(app, event)

            %TODO: this code is not good.
            % not only is it unnecessary to loop through all the animals
            % like this,
            % but the males and females need to be added in the same
            % transaction.

            N_males = get(app.NumberofmalesSpinner,'value');
            N_females = get(app.NumberoffemalesSpinner,'value');
            bname = regexp(get(app.BackgroundDropDown_multi, 'value'), '(.*) \((.*)\)','tokens');


            key = [];
            for i=1:N_males
                %TODO
                %key(i).species = get(get(app.SpeciesTabGroup, 'SelectedTab'), 'Title');
                %                 key(i).species_name = 'mouse';

                %background

                key(i).background_name = bname{1}{2};
                key(i).strain_name = bname{1}{1};

                %check DOB
                key(i).dob = char(get(app.DOBDatePicker_multi, 'value'));

                %sex
                key(i).sex = 'Male';

                %source and source ID
                source_id_str = get(app.AnimalSourceDropDown_multi,'value');
                key(i).source_id = str2double(strtok(source_id_str,':'));

                %assign cage
                key_cage(i).cage_number = str2double(get(app.MaleCageEditField_2,'value'));
                key_cage(i).user_name = app.login_user;
                key_cage(i).cause = 'assigned at database insert';
                key_cage(i).date = char(get(app.CageEntryDatePicker_multi, 'value'));

                %assign room
                tempKey = rmfield(key_cage(i),'cause');
                tempKey.room_number = get(app.MaleRoomDropDown_2, 'value');
                key_room(i) = tempKey;

            end
            fprintf('Adding %d males\n', N_males);
            if N_males > 0
                inserted = false;
                try
                    C = dj.conn;
                    C.startTransaction;
                    insert(sln_animal.Animal,key);
                    last_ids = fetchn(sln_animal.Animal,'animal_id',sprintf('ORDER BY animal_id DESC LIMIT %d', N_males));

                    for i=1:length(last_ids)
                        key_leftEye(i).animal_id = last_ids(i);
                        key_rightEye(i).animal_id = last_ids(i);
                        key_leftEye(i).side = 'Left';
                        key_rightEye(i).side = 'Right';
                        key_cage(i).animal_id = last_ids(i);

                        key_cage(i)
                        sln_animal.add_event(key_cage(i),'AssignCage',C);

                    end
                    insert(sln_animal.CageAssignRoom,key_room(1)); %does this end transaction?

                    out_txt = 'animal insert successful';
                    C.commitTransaction;
                    inserted = true;
                    %                     sln_animal.updateGenotypeString(last_ids); %TODO: can probably be done more elegantly
                    if inserted
                        insert(sln_animal.Eye,key_leftEye);
                        insert(sln_animal.Eye,key_rightEye);
                    end
                catch ME
                    disp('animal insert failed')
                    C.cancelTransaction;
                    out_txt = sprintf('animal insert failed: %s', ME.message);
                end
            end

            %clear before females
            clear('key','key_cage','key_leftEye','key_rightEye');
            key = [];
            for i=1:N_females
                %TODO
                %key(i).species = get(get(app.SpeciesTabGroup, 'SelectedTab'), 'Title');

                %background
                key(i).background_name = bname{1}{2};
                key(i).strain_name = bname{1}{1};

                %check DOB
                key(i).dob = char(get(app.DOBDatePicker_multi, 'value'));

                %sex
                key(i).sex = 'Female';

                %source and source ID
                source_id_str = get(app.AnimalSourceDropDown_multi,'value');
                key(i).source_id = str2double(strtok(source_id_str,':'));

                %assign cage
                key_cage(i).cage_number = str2double(get(app.FemaleCageEditField,'value'));
                key_cage(i).user_name = app.login_user;
                key_cage(i).cause = 'assigned at database insert';
                key_cage(i).date = char(get(app.CageEntryDatePicker_multi, 'value'));

                %assign room
                tempKey = rmfield(key_cage(i),'cause');
                tempKey.room_number = get(app.FemaleRoomDropDown, 'value');
                key_room(i) = tempKey;

            end
            fprintf('Adding %d females\n', N_females);

            if N_females > 0
                inserted = false;
                try
                    key(1)
                    insert(sln_animal.Animal,key);
                    last_ids = fetchn(sln_animal.Animal,'animal_id',sprintf('ORDER BY animal_id DESC LIMIT %d', N_females));

                    C = dj.conn;
                    C.startTransaction;
                    for i=1:length(last_ids)
                        key_leftEye(i).animal_id = last_ids(i);
                        key_rightEye(i).animal_id = last_ids(i);
                        key_leftEye(i).side = 'Left';
                        key_rightEye(i).side = 'Right';
                        key_cage(i).animal_id = last_ids(i);

                        key_cage(i)
                        sln_animal.add_event(key_cage(i),'AssignCage',C);
                    end
                    insert(sln_animal.CageAssignRoom,key_room(1)); %does this end transaction?

                    out_txt = 'animal insert successful';
                    C.commitTransaction;
                    inserted = true;
                    %                     sln_animal.updateGenotypeString(last_ids); %TODO: can probably be done more elegantly
                    if inserted
                        insert(sln_animal.Eye,key_leftEye);
                        insert(sln_animal.Eye,key_rightEye);
                    end
                catch ME
                    disp('animal insert failed')
                    C.cancelTransaction;
                    out_txt = sprintf('animal insert failed: %s', ME.message);
                end
            end


            if inserted
                set(app.StatusLamp_Animal,'color','green');
                pause(2);
                set(app.StatusLamp_Animal,'color','white');
            else
                set(app.StatusLamp_Animal,'color','red');
            end
            set(app.AnimalInsertStatusEdit,'value',out_txt);
        end

        % Button pushed function: AddprojecttodatabaseButton
        function AddprojecttodatabaseButtonPushed(app, event)
            key.project_name = get(app.ProjectNameEditField, 'value');
            desc_text = get(app.ProjectDescriptionTextArea, 'value');
            desc_text_flat = strcat(cell2mat(desc_text'));
            key.description = desc_text_flat;
            try
                insert(sln_lab.Project, key);
                disp(['Project ' key.project_name ' insert successful']);
                thisProject = sln_lab.Project & sprintf('project_name="%s"', key.project_name);
                thisProject.activate();
                app.loadProjects();
                set(app.StatusLamp_Project,'color','green');
                set(app.ProjectInsertStatusEdit,'value','Project insert successful.')
                pause(2);
                set(app.StatusLamp_Project,'color','white');
                app.init_AddProjectTab(false);
            catch ME
                disp(['Project ' key.project_name ' insert failed']);
                set(app.StatusLamp_Project,'color','red');
                set(app.StatusLamp_Project,'value','Project insert failed.')
                %rethrow(ME);
            end
        end

        % Value changed function: ActiveprojectsindatabaseListBox
        function ActiveprojectsindatabaseListBoxValueChanged(app, event)
            value = app.ActiveprojectsindatabaseListBox.Value;
            thisProject = fetch(sln_lab.Project & sprintf('project_name="%s"',value), '*');
            set(app.ProjectNameEditField, 'value', thisProject.project_name);
            set(app.ProjectDescriptionTextArea, 'value', thisProject.description);
        end

        % Value changed function: BehaviorexperimenttypesindatabaseListBox
        function BehaviorexperimenttypesindatabaseListBoxValueChanged(app, event)
            value = app.BehaviorexperimenttypesindatabaseListBox.Value;
            thisExp = fetch(sl_behavior.SocialBehaviorExperimentType & sprintf('purpose="%s"',value), '*');
            set(app.BehaviorExpNameEditField, 'value', thisExp.purpose);
            set(app.BehaviorExpDescriptionTextArea, 'value', thisExp.description);
        end

        % Button pushed function: AddexperimenttypetodatabaseButton
        function AddexperimenttypetodatabaseButtonPushed(app, event)
            key.purpose = get(app.BehaviorExpNameEditField, 'value');
            desc_text = get(app.BehaviorExpDescriptionTextArea, 'value');
            desc_text_flat = strcat(cell2mat(desc_text'));
            key.description = desc_text_flat;
            try
                insert(sl_behavior.SocialBehaviorExperimentType, key);
                disp(['Experiment ' key.purpose ' insert successful']);
                set(app.StatusLamp_BehaviorExp,'color','green');
                set(app.BehaviorExpInsertStatusEdit,'value','Experiment insert successful.')
                pause(2);
                set(app.StatusLamp_BehaviorExp,'color','white');
                app.init_AddProjectTab(false);
            catch ME
                disp(['Experiment ' key.purpose ' insert failed']);
                set(app.StatusLamp_BehaviorExp,'color','red');
                set(app.StatusLamp_BehaviorExp,'value','Experiment insert failed.')
                %rethrow(ME);
            end
            exps = fetchn(sl_behavior.SocialBehaviorExperimentType,'purpose');
            set(app.BehaviorexperimenttypesindatabaseListBox,'Items',exps);
        end

        % Callback function
        function NumberofentriesSliderValueChanging(app, event)
            changingValue = event.Value;
            set(app.NumberofentriesSlider, 'Value', round(changingValue));
        end

        % Callback function
        function NumberofentriesSliderValueChanged(app, event)
            value = app.NumberofentriesSlider.Value;
            set(app.NumberofentriesSlider, 'Value', round(value));
            app.updateAnimalEntryVisibility();
        end

        % Button pushed function: SetmultitomatchButton
        function SetmultitomatchButtonPushed(app, event)
            set(app.BackgroundDropDown_multi, 'items', get(app.StrainDropDown_2, 'items'));
            set(app.BackgroundDropDown_multi, 'value', get(app.StrainDropDown_2, 'value'));
            set(app.DOBDatePicker_multi, 'value', get(app.DOBDatePicker, 'value'));
            set(app.SourceTypeDropDown_multi, 'value', get(app.SourceTypeDropDown, 'value'));
            set(app.AnimalSourceDropDown_multi, 'items', get(app.AnimalSourceDropDown, 'items'));
            set(app.AnimalSourceDropDown_multi, 'value', get(app.AnimalSourceDropDown, 'value'));


            set(app.MaleCageEditField_2,'value','');
            set(app.FemaleCageEditField,'value','');
            set(app.MaleRoomDropDown_2, 'value', app.RoomDropDown.Value);
            set(app.FemaleRoomDropDown, 'value', app.RoomDropDown.Value);
            set(app.NumberofmalesSpinner,'value',0);
            set(app.NumberoffemalesSpinner,'value',0);


        end

        % Value changed function: WindowAStim
        function WindowAStimValueChanged(app, event)
            value = app.WindowAStim.Value;
            needs_id = strcmp(fetch1(sl_behavior.BehaviorVisualStimulusType & sprintf('stim_type="%s"', value), 'needs_id'), 'T');
            if needs_id
                set(app.AnimalAEditField,'visible',true);
            else
                set(app.AnimalAEditField,'visible',false);
            end
        end

        % Value changed function: WindowBStim
        function WindowBStimValueChanged(app, event)
            value = app.WindowBStim.Value;
            needs_id = strcmp(fetch1(sl_behavior.BehaviorVisualStimulusType & sprintf('stim_type="%s"', value), 'needs_id'), 'T');
            if needs_id
                set(app.AnimalBEditField,'visible',true);
            else
                set(app.AnimalBEditField,'visible',false);
            end
        end

        % Value changed function: WindowCStim
        function WindowCStimValueChanged(app, event)
            value = app.WindowCStim.Value;
            needs_id = strcmp(fetch1(sl_behavior.BehaviorVisualStimulusType & sprintf('stim_type="%s"', value), 'needs_id'), 'T');
            if needs_id
                set(app.AnimalCEditField,'visible',true);
            else
                set(app.AnimalCEditField,'visible',false);
            end
        end

        % Selection changed function: SearchTypeButtonGroup_breeders
        function SearchTypeButtonGroup_breedersSelectionChanged(app, event)
            selectedButton = app.SearchTypeButtonGroup_breeders.SelectedObject;
            switch get(selectedButton,'Text')
                case 'DJ ID'
                    set(app.Search_DJIDEditField_breeders, 'enable', true);
                    set(app.CageNumSearchEditField_breeders, 'enable', false);
                case 'Cage #'
                    set(app.Search_DJIDEditField_breeders, 'enable', false);
                    set(app.CageNumSearchEditField_breeders, 'enable', true);
            end
        end

        % Selection changed function: SearchTypeButtonGroup
        function SearchTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SearchTypeButtonGroup.SelectedObject;
            switch get(selectedButton,'Text')
                case 'DJ ID'
                    set(app.Search_DJIDEditField, 'enable', true);
                    set(app.Search_TagIDEditField, 'enable', false);
                    set(app.CageNumSearchEditField, 'enable', false);
                case 'Cage #'
                    set(app.Search_DJIDEditField, 'enable', false);
                    set(app.Search_TagIDEditField, 'enable', false);
                    set(app.CageNumSearchEditField, 'enable', true);
                case 'Tag #'
                    set(app.Search_DJIDEditField, 'enable', false);
                    set(app.Search_TagIDEditField, 'enable', true);
                    set(app.CageNumSearchEditField, 'enable', false);
            end
        end

        % Button pushed function: SearchButton_breeders
        function SearchButton_breedersPushed(app, event)
            selectedButton = app.SearchTypeButtonGroup_breeders.SelectedObject;
            q = [];
            switch get(selectedButton,'Text')
                case 'DJ ID'
                    q.DJID = str2double(get(app.Search_DJIDEditField_breeders,'value'));
                case 'Cage #'
                    q.cage_number = str2double(get(app.CageNumSearchEditField_breeders,'value'));
            end
            app.loadBreedingCagesTable(q);

        end

        % Button pushed function: ResetButton_breeders
        function ResetButton_breedersPushed(app, event)
            q = [];
            set(app.StrainDropDown_BreedersTab,'value','All');
            set(app.GenotypeDropDown_BreedersTab,'value','All');
            set(app.Search_DJIDEditField_breeders,'value','');
            set(app.CageNumSearchEditField_breeders,'value','');
            app.loadBreedingCagesTable(q);
        end

        % Value changed function: QueriesListBox
        function QueriesListBoxValueChanged(app, event)
            value = app.QueriesListBox.Value;
            set(app.QuerynameLabel,'text',value);
            set(app.QuerynameEditField,'value',value);

            text = split(app.todo_queries(value),'\n');
            set(app.QuerytextTextArea,'value',text);
            s = eval(cell2mat(join(text)));
            if ~isempty(s)
                table = struct2table(s);
            else
                f = fieldnames(s);
                table = cell2table(cell(0,numel(f)),'VariableNames',f);
            end
            set(app.QueryResultTable,...
                'data',table,...
                'columnname',table.Properties.VariableNames);
            % table??
        end

        % Button pushed function: SubmitnewqueryButton
        function SubmitnewqueryButtonPushed(app, event)
            key = struct(...
                'query_name', get(app.QuerynameEditField,'value'),...
                'query_text',cell2mat(join(get(app.QuerytextTextArea,'value'),'\n'))...
                );

            err = false;
            try
                eval(strrep(key.query_text,'\n','')); %if this errors, show bad status
            catch ME
                txt = 'Error evaluating query';
                err = true;
                disp(getReport(ME,'extended'));
                disp('Query was:');
                disp(strrep(key.query_text,'\n',''));
            end

            if ~err
                try
                    sln_animal.TodoQueries().insert(key)
                catch
                    err = true;
                    txt = 'Error saving TODO query to database';
                end
            end

            if ~err
                app.todo_queries(key.query_name) = key.query_text;
                set(app.QueriesListBox,'items',app.todo_queries.keys());
                set(app.QueriesListBox,'value',key.query_name);
                set(app.StatusLamp_TODOquery,'color','green')
                set(app.EditField_TODOquery,'value','success')
                pause(2)
                set(app.StatusLamp_TODOquery,'color','white')
                set(app.EditField_TODOquery,'value','')
            else
                set(app.StatusLamp_TODOquery,'color','red')
                set(app.EditField_TODOquery,'value',txt)
            end

        end

        % Value changed function: GenotypeDropDown_BreedersTab
        function GenotypeDropDown_BreedersTabValueChanged(app, event)
            value = app.GenotypeDropDown_BreedersTab.Value;
            if strcmp(value, 'All')
                q = [];
            else
                q.allele_name = value;
            end
            app.loadBreedingCagesTable(q);
        end

        % Value changed function: GenotypeEntriesSpinner
        function GenotypeEntriesSpinnerValueChanged(app, event)
            app.updateGenotypeEntryVisibility();
        end

        % Value changed function: SourceTypeDropDown
        function SourceTypeDropDownValueChanged(app, event)
            app.loadAnimalSourceMenu(false); %true if for multi animal entry
            app.AnimalSourceDropDownValueChanged(event);
        end

        % Value changed function: Filter_BgDropDown
        function Filter_BgDropDownValueChanged(app, event)
            value = app.Filter_BgDropDown.Value;
            if strcmp(value,'All')
                app.filter_struct = rmfield(app.filter_struct, 'background_name');
            else
                app.filter_struct.background_name = value;
            end
            app.loadAnimalTable([]);
        end

        % Selection changed function: IPInjectionButtonGroup
        function IPInjectionButtonGroupSelectionChanged(app, event)
            app.loadAnimalTable([]);
        end

        % Selection changed function: EyeInjectionButtonGroup
        function EyeInjectionButtonGroupSelectionChanged(app, event)
            app.loadAnimalTable([]);
        end

        % Selection changed function: BrainInjectionButtonGroup
        function BrainInjectionButtonGroupSelectionChanged(app, event)
            app.loadAnimalTable([]);
        end

        % Selection changed function: RigreservationButtonGroup
        function RigreservationButtonGroupSelectionChanged(app, event)
            app.loadAnimalTable([]);
        end

        % Selection changed function: BehaviorsessionButtonGroup
        function BehaviorsessionButtonGroupSelectionChanged(app, event)
            app.loadAnimalTable([]);
        end

        % Value changed function: LocusDropDown_1
        function LocusDropDown_1ValueChanged(app, event)
            value = app.LocusDropDown_1.Value;
            ii = strcmp(app.curr_loci,value);
            items = [{''; 'WT'}; app.curr_alleles{ii}; {'Ambiguous'}];
            set(app.Allele1DropDown_1,'items',items);
            set(app.Allele2DropDown_1,'items',items);

            other_loci = app.curr_loci(~ii);
            other_alleles = app.curr_alleles(~ii);
            other_menus = setdiff(1:numel(app.curr_alleles),1);

            for i=1:numel(other_menus)
                locusMenuName = ['LocusDropDown_' num2str(other_menus(i))];
                allele1MenuName = ['Allele1DropDown_' num2str(other_menus(i))];
                allele2MenuName = ['Allele2DropDown_' num2str(other_menus(i))];

                set(app.(locusMenuName), 'items', app.curr_loci);
                set(app.(locusMenuName), 'value', other_loci{i})

                set(app.(allele1MenuName),'items', [{''; 'WT'}; other_alleles{i}; {'Ambiguous'}]);
                set(app.(allele2MenuName),'items', [{''; 'WT'}; other_alleles{i}; {'Ambiguous'}]);

                set(app.(allele1MenuName),'value', '');
                set(app.(allele2MenuName),'value', '');
            end

        end

        % Value changed function: Allele1DropDown_1
        function Allele1DropDown_1ValueChanged(app, event)
            value = app.Allele1DropDown_1.Value;

        end

        % Value changed function: Allele2DropDown_1
        function Allele2DropDown_1ValueChanged(app, event)
            value = app.Allele2DropDown_1.Value;

        end

        % Value changed function: LocusDropDown_2
        function LocusDropDown_2ValueChanged(app, event)
            value = app.LocusDropDown_1.Value;
            ii = strcmp(app.curr_loci,value);
            items = [{''; 'WT'}; app.curr_alleles{ii}; {'Ambiguous'}];
            set(app.Allele1DropDown_1,'items',items);
            set(app.Allele2DropDown_1,'items',items);

            other_loci = app.curr_loci(~ii);
            other_alleles = app.curr_loci(~ii);
            other_menus = setdiff(1:numel(app.curr_alleles),2);

            for i=1:numel(other_menus)
                locusMenuName = ['LocusDropDown_' num2str(other_menus(i))];
                allele1MenuName = ['Allele1DropDown_' num2str(other_menus(i))];
                allele2MenuName = ['Allele2DropDown_' num2str(other_menus(i))];

                set(app.(locusMenuName), 'items', app.curr_loci);
                set(app.(locusMenuName), 'value', other_loci{i})

                set(app.(allele1MenuName),'items', [{''; 'WT'}; other_alleles{i}; {'Ambiguous'}]);
                set(app.(allele2MenuName),'items', [{''; 'WT'}; other_alleles{i}; {'Ambiguous'}]);

                set(app.(allele1MenuName),'value', '');
                set(app.(allele2MenuName),'value', '');
            end
        end

        % Value changed function: LocusDropDown_3
        function LocusDropDown_3ValueChanged(app, event)
            value = app.LocusDropDown_1.Value;
            ii = strcmp(app.curr_loci,value);
            items = [{''; 'WT'}; app.curr_alleles{ii}; {'Ambiguous'}];
            set(app.Allele1DropDown_1,'items',items);
            set(app.Allele2DropDown_1,'items',items);

            other_loci = app.curr_loci(~ii);
            other_alleles = app.curr_loci(~ii);
            other_menus = setdiff(1:numel(app.curr_alleles),3);

            for i=1:numel(other_menus)
                locusMenuName = ['LocusDropDown_' num2str(other_menus(i))];
                allele1MenuName = ['Allele1DropDown_' num2str(other_menus(i))];
                allele2MenuName = ['Allele2DropDown_' num2str(other_menus(i))];

                set(app.(locusMenuName), 'items', app.curr_loci);
                set(app.(locusMenuName), 'value', other_loci{i})

                set(app.(allele1MenuName),'items', [{''; 'WT'}; other_alleles{i}; {'Ambiguous'}]);
                set(app.(allele2MenuName),'items', [{''; 'WT'}; other_alleles{i}; {'Ambiguous'}]);

                set(app.(allele1MenuName),'value', '');
                set(app.(allele2MenuName),'value', '');
            end
        end

        % Value changed function: LocusDropDown_4
        function LocusDropDown_4ValueChanged(app, event)
            value = app.LocusDropDown_1.Value;
            ii = strcmp(app.curr_loci,value);
            items = [{''; 'WT'}; app.curr_alleles{ii}; {'Ambiguous'}];
            set(app.Allele1DropDown_1,'items',items);
            set(app.Allele2DropDown_1,'items',items);

            other_loci = app.curr_loci(~ii);
            other_alleles = app.curr_loci(~ii);
            other_menus = setdiff(1:numel(app.curr_alleles),4);

            for i=1:numel(other_menus)
                locusMenuName = ['LocusDropDown_' num2str(other_menus(i))];
                allele1MenuName = ['Allele1DropDown_' num2str(other_menus(i))];
                allele2MenuName = ['Allele2DropDown_' num2str(other_menus(i))];

                set(app.(locusMenuName), 'items', app.curr_loci);
                set(app.(locusMenuName), 'value', other_loci{i})

                set(app.(allele1MenuName),'items', [{''; 'WT'}; other_alleles{i}; {'Ambiguous'}]);
                set(app.(allele2MenuName),'items', [{''; 'WT'}; other_alleles{i}; {'Ambiguous'}]);

                set(app.(allele1MenuName),'value', '');
                set(app.(allele2MenuName),'value', '');
            end
        end

        % Value changed function: Allele1DropDown_2
        function Allele1DropDown_2ValueChanged(app, event)
            value = app.Allele1DropDown_2.Value;

        end

        % Value changed function: Allele2DropDown_2
        function Allele2DropDown_2ValueChanged(app, event)
            value = app.Allele2DropDown_2.Value;

        end

        % Value changed function: Allele1DropDown_3
        function Allele1DropDown_3ValueChanged(app, event)
            value = app.Allele1DropDown_3.Value;

        end

        % Value changed function: Allele2DropDown_3
        function Allele2DropDown_3ValueChanged(app, event)
            value = app.Allele2DropDown_3.Value;

        end

        % Value changed function: Allele1DropDown_4
        function Allele1DropDown_4ValueChanged(app, event)
            value = app.Allele1DropDown_4.Value;

        end

        % Value changed function: Allele2DropDown_4
        function Allele2DropDown_4ValueChanged(app, event)
            value = app.Allele2DropDown_4.Value;

        end

        % Callback function
        function SourceTypeDropDown_singleValueChanged(app, event)
            app.loadAnimalSourceMenu(false); %true indicates multi entry
        end

        % Value changed function: SourceTypeDropDown_multi
        function SourceTypeDropDown_multiValueChanged(app, event)
            app.loadAnimalSourceMenu(true); %true indicates multi entry
            app.AnimalSourceDropDown_multiValueChanged(event);
        end

        % Button pushed function: AddstraintodatabaseButton
        function AddstraintodatabaseButtonPushed(app, event)
            key.strain_name = get(app.StrainNameEditField,'value');
            key.background_name = get(app.BackgroundDropDown_2, 'value');
            key.species_name = 'mouse';

            key_allele = struct('allele_name',app.AllelesListBox.Value);
            [key_allele(:).strain_name] = deal(key.strain_name);
            [key_allele(:).background_name] = deal(key.background_name);

            selectedButton = app.StraintypeButtonGroup.SelectedObject;
            selection = get(selectedButton,'Text');
            %             desc = get(app.DescriptionTextArea_strain,'value');
            %             if ~isempty(desc)
            %                 key.source_info = strcat(cell2mat(desc'));
            %             end
            key_source = [];
            switch selection
                case 'Vendor'
                    key_source.source_id = fetch1(sln_animal.Vendor & struct('vendor_name',get(app.SourceDropDown,'value')),'source_id');
                    key_source.strain_id = get(app.CatalogEditField_Strain,'value');
                case 'Collaborator'
                    key_source.source_id = fetch1(sln_animal.Collaborator & struct('lab_name',get(app.SourceDropDown,'value')),'source_id');
            end
            try
                key
                insert(sln_animal.Strain, key);
                insert(sln_animal.StrainAllele, key_allele);
                if ~isempty(key_source)
                    key_source.strain_name = key.strain_name;
                    key_source.background_name = key.background_name;

                    insert(sln_animal.StrainSource, key_source);
                end

                thisStrain = sln_animal.Strain & key;
                thisStrain.activate();

                inserted_id = true;
                out_txt = 'Strain insert successful';
            catch ME
                out_txt = ME.message;
                inserted_id = 0;
            end
            if inserted_id
                set(app.StatusLamp_Strain,'color','green');
                %need to reload current table state
                app.loadStrains();
                pause(2);
                set(app.StatusLamp_Strain,'color','white');
            else
                set(app.StatusLamp_Strain,'color','red');
            end
            set(app.StrainInsertStatusEdit,'value',out_txt);
        end

        % Selection changed function: StraintypeButtonGroup
        function StraintypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.StraintypeButtonGroup.SelectedObject;
            selection = get(selectedButton,'Text');
            switch selection
                case 'Vendor'
                    set(app.CatalogEditField_Strain,'visible',true);
                    set(app.SourceDropDown, 'visible',true,'items',["";fetchn(sln_animal.Vendor.active(),'vendor_name')]);
                    set(app.SourceDropDown, 'value', '');
                case 'Collaborator'
                    set(app.CatalogEditField_Strain,'visible',false);
                    set(app.SourceDropDown, 'visible',true,'items',["";fetchn(sln_animal.Collaborator.active(),'lab_name')]);
                    set(app.SourceDropDown, 'value', '');
                case 'Breeding'
                    set(app.CatalogEditField_Strain,'visible',false);
                    set(app.SourceDropDown, 'visible',false);
            end
        end

        % Button pushed function: AddalleleButton_LocusAlleleTab
        function AddalleleButton_LocusAlleleTabPushed(app, event)
            key.allele_name = get(app.AllelenameEditField, 'value');
            desc = get(app.DescriptionTextArea_Allele,'value');
            key.description = strcat(cell2mat(desc'));
            if strcmp(key.allele_name,'WT')
                key.is_wildtype = 'T';
            else
                key.is_wildtype = 'F';
            end
            try
                key
                insert(sln_animal.Allele,key);
                thisAllele = sln_animal.Allele & sprintf('allele_name="%s"', key.allele_name);
                thisAllele.activate();
                inserted = true;
                out_txt = 'Allele insert successful';
            catch ME
                inserted = false;
                out_txt = ME.message;
            end
            if inserted
                set(app.StatusLamp_Allele,'color','green');
                %need to reload current table state
                app.updateAlleleList();
                pause(2);
                set(app.StatusLamp_Allele,'color','white');
            else
                set(app.StatusLamp_Allele,'color','red');
            end
            set(app.AlleleInsertStatusEdit,'value',out_txt);
        end

        % Button pushed function: AddgenelocusButton
        function AddgenelocusButtonPushed(app, event)
            key.locus_name = get(app.LocusnameEditField, 'value');
            desc = get(app.DescriptionTextArea_Locus,'value');
            key.description = strcat(cell2mat(desc'));
            key.chromosome = str2double(get(app.ChromosomeEditField,'value'));
            key.position = str2double(get(app.PositionEditField,'value'));
            try
                key
                insert(sln_animal.GeneLocus,key);
                inserted = true;
                out_txt = 'Gene locus insert successful';
            catch ME
                inserted = false;
                out_txt = ME.message;
            end
            if inserted
                set(app.StatusLamp_Locus,'color','green');
                %need to reload current table state
                app.updateLocusList();
                pause(2);
                set(app.StatusLamp_Locus,'color','white');
            else
                set(app.StatusLamp_Locus,'color','red');
            end
            set(app.LocusInsertStatusEdit,'value',out_txt);
        end

        % Button pushed function: AddtomapButton
        function AddtomapButtonPushed(app, event)
            key.allele_name = get(app.AlleleDropDown_forMap,'value');
            key.locus_name = get(app.LocusDropDown_forMap,'value');
            try
                key
                insert(sln_animal.AlleleLocusMap,key);
                inserted = true;
                out_txt = 'Allele locus map insert successful';
            catch ME
                inserted = false;
                out_txt = ME.message;
            end
            if inserted
                set(app.StatusLamp_LocusAlleleMap,'color','green');
                %need to reload current table state
                app.updateAlleleLocusTable();
                pause(2);
                set(app.StatusLamp_LocusAlleleMap,'color','white');
            else
                set(app.StatusLamp_LocusAlleleMap,'color','red');
            end
            set(app.LocusAlleleMapInsertStatusEdit,'value',out_txt);
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            app.load_database_items();
            app.init_AnimalLoggerTab();
        end

        % Value changed function: StrainDropDown
        function StrainDropDownValueChanged(app, event)
            app.loadAnimalTable([]);
        end

        % Callback function
        function AddnewbreederstrainButtonPushed(app, event)
            key.strain_name = get(app.NewstrainnameEditField,'value');
            desc = get(app.StrainDescriptionTextArea, 'value');
            key.strain_info = strcat(cell2mat(desc'));
            insert(sln_animal.BreedingStrain,key);
            thisStrain = sln_animal.BreedingStrain & sprintf('strain_name="%s"', key.strain_name);
            thisStrain.activate();
            app.loadStrains();
        end

        % Value changed function: StrainDropDown_BreedersTab
        function StrainDropDown_BreedersTabValueChanged(app, event)
            value = app.StrainDropDown_BreedersTab.Value;
            if strcmp(value, 'All')
                q = [];
            else
                q.strain_name = strtrim(strtok(value, '('));
            end
            app.loadBreedingCagesTable(q);
        end

        % Button pushed function: DeactivateStrainButton
        function DeactivateStrainButtonPushed(app, event)
            selected_strain = get(app.ActiveStrainListBox,'value');
            strain_name = strtrim(strtok(selected_strain,'('));

            thisStrain = sln_animal.Strain & sprintf('strain_name="%s"', strain_name);
            if thisStrain.exists
                thisStrain.deactivate();
                app.loadStrains();
                return;
            end

            %             thisStrain = sln_animal.BreedingStrain & sprintf('strain_name="%s"', selected_strain);
            %             if thisStrain.exists
            %                 thisStrain.deactivate();
            %                 app.loadStrains();
            %                 return;
            %             end
            %
            %             thisStrain = sln_animal.VendorStrain & sprintf('strain_name="%s"', selected_strain);
            %             if thisStrain.exists
            %                 thisStrain.deactivate();
            %                 app.loadStrains();
            %                 return;
            %             end
            %
            %             thisStrain = sln_animal.CollaboratorStrain & sprintf('strain_name="%s"', selected_strain);
            %             if thisStrain.exists
            %                 thisStrain.deactivate();
            %                 app.loadStrains();
            %                 return;
            %             end


        end

        % Button pushed function: ActivateStrainButton
        function ActivateStrainButtonPushed(app, event)
            selected_strain = get(app.InactiveStrainListBox,'value');
            strain_name = strtrim(strtok(selected_strain,'('));

            thisStrain = sln_animal.Strain & sprintf('strain_name="%s"', strain_name);
            if thisStrain.exists
                thisStrain.activate();
                app.loadStrains();
                return;
            end
            %
            %             thisStrain = sln_animal.BreedingStrain & sprintf('strain_name="%s"', selected_strain);
            %             if thisStrain.exists
            %                 thisStrain.activate();
            %                 app.loadStrains();
            %                 return;
            %             end
            %
            %             thisStrain = sln_animal.VendorStrain & sprintf('strain_name="%s"', selected_strain);
            %             if thisStrain.exists
            %                 thisStrain.activate();
            %                 app.loadStrains();
            %                 return;
            %             end
            %
            %             thisStrain = sln_animal.CollaboratorStrain & sprintf('strain_name="%s"', selected_strain);
            %             if thisStrain.exists
            %                 thisStrain.activate();
            %                 app.loadStrains();
            %                 return;
            %             end


        end

        % Button pushed function: DeactivateSubstanceButton
        function DeactivateSubstanceButtonPushed(app, event)
            selected_substance = get(app.ActivesubstancesindatabaseListBox,'value');

            thisSubstance = sln_animal.InjectionSubstance & sprintf('substance_id="%s"', strtrim(strtok(selected_substance, ':')));
            thisSubstance.deactivate();
            app.loadSubstanceMenus();
        end

        % Button pushed function: ActivateSubstanceButton
        function ActivateSubstanceButtonPushed(app, event)
            selected_substance = get(app.InactiveSubstancesindatabaseListBox,'value');

            thisSubstance = sln_animal.InjectionSubstance & sprintf('substance_id="%s"', strtrim(strtok(selected_substance, ':')));
            thisSubstance.activate();
            app.loadSubstanceMenus();
        end

        % Button pushed function: DeactivateProjectButton
        function DeactivateProjectButtonPushed(app, event)
            selected_project = get(app.ActiveprojectsindatabaseListBox,'value');

            thisProject = sln_lab.Project & sprintf('project_name="%s"', selected_project);
            thisProject.deactivate();
            app.loadProjects();
        end

        % Button pushed function: ActivateProjectButton
        function ActivateProjectButtonPushed(app, event)
            selected_project = get(app.InactiveprojectsindatabaseListBox,'value');

            thisProject = sln_lab.Project & sprintf('project_name="%s"', selected_project);
            thisProject.activate();
            app.loadProjects();
        end

        % Button pushed function: DeactivateAlleleButton
        function DeactivateAlleleButtonPushed(app, event)
            selected_allele = get(app.ActiveallelesindatabaseListBox,'value');

            thisAllele = sln_animal.Allele & sprintf('allele_name="%s"', selected_allele);
            thisAllele.deactivate();
            app.updateAlleleList();
        end

        % Button pushed function: ActivateAlleleButton
        function ActivateAlleleButtonPushed(app, event)
            selected_allele = get(app.InactiveallelesindatabaseListBox,'value');

            thisAllele = sln_animal.Allele & sprintf('allele_name="%s"', selected_allele);
            thisAllele.activate();
            app.updateAlleleList();
        end

        % Value changed function: VisualstimuliindatabaseListBox
        function VisualstimuliindatabaseListBoxValueChanged(app, event)
            value = app.VisualstimuliindatabaseListBox.Value;
            thisStim = fetch(sl_behavior.VisualStimulusType & sprintf('stim_type="%s"',value), '*');
            set(app.BehaviorExpStimNameEditField, 'value', thisStim.stim_type);
            set(app.BehaviorExpStimDescriptionTextArea, 'value', thisStim.description);
            if strcmp(thisStim.needs_id, 'T')
                set(app.NeedsanimalIDSwitch,'value','Yes');
            else
                set(app.NeedsanimalIDSwitch,'value','No');
            end
        end

        % Button pushed function: AddvisualstimtypetodatabaseButton
        function AddvisualstimtypetodatabaseButtonPushed(app, event)
            key.stim_type = get(app.BehaviorExpStimNameEditField, 'value');
            desc_text = get(app.BehaviorExpStimDescriptionTextArea, 'value');
            desc_text_flat = strcat(cell2mat(desc_text'));
            key.description = desc_text_flat;
            needs_animal_id = get(app.NeedsanimalIDSwitch,'value');
            if strcmp(needs_animal_id,'Yes')
                key.needs_id = 'T';
            else
                key.needs_id = 'F';
            end
            try
                insert(sl_behavior.VisualStimulusType, key);
                disp(['Stimulus ' key.stim_type ' insert successful']);
                set(app.StatusLamp_BehaviorExpStim,'color','green');
                set(app.BehaviorExpInsertStimStatusEdit,'value','Stimulus insert successful.')
                pause(2);
                set(app.StatusLamp_BehaviorExpStim,'color','white');
                app.init_AddProjectTab(false);
            catch ME
                disp(['Stimulus ' key.stim_type ' insert failed']);
                set(app.StatusLamp_BehaviorExpStim,'color','red');
                set(app.BehaviorExpInsertStimStatusEdit,'value','Experiment insert failed.')
                %rethrow(ME);
            end
            app.loadBehaviorStimuli();
        end

        % Value changed function: StrainDropDown_2
        function StrainDropDown_2ValueChanged(app, event)
            %             value = app.StrainDropDown_2.Value;
            %             set(app.StrainDropDown_2, 'items', fetchn(sln_animal.Strain & struct('background_name',value), 'strain_name'));
        end

        % Value changed function: AnimalSourceDropDown
        function AnimalSourceDropDownValueChanged(app, event)
            value = app.AnimalSourceDropDown.Value;
            source_type = app.SourceTypeDropDown.Value;
            source_id = split(value,':');
            source_id = struct('source_id',str2double(source_id{1}));
            %             set(app.StrainDropDown_2, 'value', '','enable',true);
            %             set(app.StrainDropDown_2, 'value', '','enable',true);
            if strcmpi(value,'none')
                set(app.StrainDropDown_2, 'items', {''});
                set(app.StrainDropDown_2, 'value', '');
                return
            end
            switch source_type
                case 'breeding'
                    sinfo = fetch(proj(sln_animal.BreedingPair & source_id,'concat(strain_name, " (", background_name, ")")->strain_name_full'),'*');
                    set(app.StrainDropDown_2, 'items', {sinfo.strain_name_full});
                    set(app.StrainDropDown_2, 'value', sinfo.strain_name_full);
                    %                     set(app.StrainDropDown_2, 'value', sinfo.strain_name,'enable',false);
                case 'collaborator'
                    sinfo = fetch(proj((sln_animal.StrainSource*sln_animal.Collaborator) & source_id,'concat(strain_name, " (", background_name, ")")->strain_name_full'),'*');
                    set(app.StrainDropDown_2, 'items', {'',sinfo.strain_name_full});
                    set(app.StrainDropDown_2, 'value', '');
                    %                     set(app.StrainDropDown_2, 'value', sinfo.strain_name,'enable',false);
                case 'vendor'
                    sinfo = fetch(proj((sln_animal.StrainSource*sln_animal.Vendor) & source_id,'concat(strain_name, " (", background_name, ")")->strain_name_full'),'*');
                    set(app.StrainDropDown_2, 'items', {'',sinfo.strain_name_full});
                    set(app.StrainDropDown_2, 'value', '');
                    %                     set(app.StrainDropDown_2, 'value', sinfo.strain_name,'enable',false);

            end
        end

        % Callback function
        function AnimalSourceDropDown_multiOpening(app, event)
            
        end

        % Value changed function: AnimalSourceDropDown_multi
        function AnimalSourceDropDown_multiValueChanged(app, event)
            value = app.AnimalSourceDropDown_multi.Value;
            source_type = app.SourceTypeDropDown_multi.Value;
            source_id = split(value,':');
            source_id = struct('source_id',str2double(source_id{1}));
            %             set(app.StrainDropDown_2, 'value', '','enable',true);
            %             set(app.StrainDropDown_2, 'value', '','enable',true);
            if strcmpi(value,'none')
                set(app.BackgroundDropDown_multi, 'items', {''});
                set(app.BackgroundDropDown_multi, 'value', '');
                return
            end
            switch source_type
                case 'breeding'
                    sinfo = fetch(proj(sln_animal.BreedingPair & source_id,'concat(strain_name, " (", background_name, ")")->strain_name_full'),'*');
                    set(app.BackgroundDropDown_multi, 'items', {sinfo.strain_name_full});
                    set(app.BackgroundDropDown_multi, 'value', sinfo.strain_name_full);
                    %                     set(app.StrainDropDown_2, 'value', sinfo.strain_name,'enable',false);
                case 'collaborator'
                    sinfo = fetch(proj((sln_animal.StrainSource*sln_animal.Collaborator) & source_id,'concat(strain_name, " (", background_name, ")")->strain_name_full'),'*');
                    set(app.BackgroundDropDown_multi, 'items', {'',sinfo.strain_name_full});
                    set(app.BackgroundDropDown_multi, 'value', '');
                    %                     set(app.StrainDropDown_2, 'value', sinfo.strain_name,'enable',false);
                case 'vendor'
                    sinfo = fetch(proj((sln_animal.StrainSource*sln_animal.Vendor) & source_id,'concat(strain_name, " (", background_name, ")")->strain_name_full'),'*');
                    set(app.BackgroundDropDown_multi, 'items', {'',sinfo.strain_name_full});
                    set(app.BackgroundDropDown_multi, 'value', '');
                    %                     set(app.StrainDropDown_2, 'value', sinfo.strain_name,'enable',false);

            end
        end

        % Value changed function: AllelesListBox
        function AllelesListBoxValueChanged(app, event)
            value = app.AllelesListBox.Value;
            cre = contains(value, 'cre','ignorecase',true); % put cre promoters first
            [~,i] = sort(cre, 'descend');
            set(app.StrainNameEditField, 'value',  strjoin(value(i),' x '));
        end

        % Value changed function: editButton
        function editButtonValueChanged(app, event)
            value = app.editButton.Value;
            set(app.StrainNameEditField, 'enable', value);
        end

        % Button pushed function: ExporttoExcelButton_2
        function ExporttoExcelButton_2Pushed(app, event)
            %get breeder table
            D = get(app.QueryResultTable,'Data');

            curDate = string(datetime("today"), 'yyyy-MM-dd');
            defname = sprintf('%s_%s.xls', curDate, app.QueriesListBox.Value);
            [fname, fpath] = uiputfile('*','Select table file location',defname);
            writetable(D,[fpath fname]);
        end

        % Callback function
        function BreederCagesTableSelectionChanged(app, event)
            selection = app.BreederCagesTable.Selection;

        end

        % Selection changed function: VendorOrCollaborator_ButtonGroup
        function VendorOrCollaborator_ButtonGroupSelectionChanged(app, event)
            selectedButton = app.VendorOrCollaborator_ButtonGroup.SelectedObject;
            source_type = get(selectedButton,'Text');
            if strcmp(source_type,'Vendor')
                set(app.SourceDropDown_substance,'items',app.vendor_name_list);
            else %collaborator
                set(app.SourceDropDown_substance,'items',app.collaborator_name_list);
            end
        end

        % Selection changed function: SourcetypeButtonGroup
        function SourcetypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SourcetypeButtonGroup.SelectedObject;
            button_text = get(selectedButton, 'Text');
            switch button_text
                case 'Vendor'
                    set(app.SourceField_1_label,'Text','Vendor name');
                    set(app.SourceEditField_1,'visible',true);
                    set(app.SourceField_1_label,'visible',true);
                    set(app.SourceEditField_2,'visible',false);
                    set(app.SourceField_2_label,'visible',false);

                case 'GenotypeSource'
                    set(app.SourceField_1_label,'Text','Source name');
                    set(app.SourceEditField_1,'visible',true);
                    set(app.SourceField_1_label,'visible',true);
                    set(app.SourceField_2_label,'Text','Description');
                    set(app.SourceEditField_2,'visible',true);
                    set(app.SourceField_2_label,'visible',true);

                case 'Collaborator'
                    set(app.SourceField_1_label,'Text','Lab name');
                    set(app.SourceEditField_1,'visible',true);
                    set(app.SourceField_1_label,'visible',true);
                    set(app.SourceField_2_label,'Text','Organization');
                    set(app.SourceEditField_2,'visible',true);
                    set(app.SourceField_2_label,'visible',true);
            end

            app.loadSourceListBoxes();
        end

        % Button pushed function: AddsourceButton
        function AddsourceButtonPushed(app, event)
            selectedButton = app.SourcetypeButtonGroup.SelectedObject;
            button_text = get(selectedButton, 'Text');
            key = struct;
            try
                switch button_text
                    case 'Vendor'
                        key.vendor_name = get(app.SourceEditField_1,'value');
                        sln_animal.add_source(key,'Vendor');

                    case 'GenotypeSource'
                        key.source_name = get(app.SourceEditField_1,'value');
                        key.description = get(app.SourceEditField_2,'value');
                        sln_animal.add_source(key,'GenotypeSource');

                    case 'Collaborator'
                        key.lab_name = get(app.SourceEditField_1,'value');
                        key.organization = get(app.SourceEditField_2,'value');
                        sln_animal.add_source(key,'Collaborator');
                end
                status_txt = 'source insert successful';
                set(app.SourceStatusLamp,'color','green');
                set(app.SourceStatusEditField,'value', status_txt);
                app.loadSourceListBoxes();
                pause(2);
                set(app.SourceStatusLamp,'color','white');
            catch ME
                %TODO set status lamp and text
                status_txt = sprintf('Source insert failed: %s', ME.message);
                set(app.SourceStatusLamp,'color','red');
                set(app.SourceStatusEditField,'value', status_txt);
            end
        end

        % Button pushed function: ActivateButton
        function ActivateButtonPushed(app, event)
            selection = get(app.InactiveSourceListBox,'value');
            selectedButton = app.SourcetypeButtonGroup.SelectedObject;

            if ~isempty(selection)
                button_text = get(selectedButton, 'Text');
                source_id = str2double(strtok(selection, ':'));

                switch button_text
                    case 'Vendor'
                        thisSource = sln_animal.Vendor & sprintf('source_id=%d', source_id);

                    case 'GenotypeSource'
                        thisSource = sln_animal.GenotypeSource & sprintf('source_id=%d', source_id);

                    case 'Collaborator'
                        thisSource = sln_animal.Collaborator & sprintf('source_id=%d', source_id);

                end

                if thisSource.exists
                    thisSource.activate();
                    app.loadSourceListBoxes();
                end
            end
        end

        % Button pushed function: DeactivateButton
        function DeactivateButtonPushed(app, event)
            selection = get(app.ActiveSourceListBox,'value');
            selectedButton = app.SourcetypeButtonGroup.SelectedObject;

            if ~isempty(selection)
                button_text = get(selectedButton, 'Text');
                source_id = str2double(strtok(selection, ':'));

                switch button_text
                    case 'Vendor'
                        thisSource = sln_animal.Vendor & sprintf('source_id=%d', source_id);

                    case 'GenotypeSource'
                        thisSource = sln_animal.GenotypeSource & sprintf('source_id=%d', source_id);

                    case 'Collaborator'
                        thisSource = sln_animal.Collaborator & sprintf('source_id=%d', source_id);

                end

                if thisSource.exists
                    thisSource.deactivate();
                    app.loadSourceListBoxes();
                end
            end
        end

        % Value changed function: editButton_2
        function editButton_2ValueChanged(app, event)
            value = app.editButton_2.Value;
            app.StrainnameDropDown.Enable = value;
        end

        % Value changed function: MaleDJIDPairEditField
        function MaleDJIDPairEditFieldValueChanged(app, event)
            app.breeder_djid_changed(app.MaleDJIDPairEditField.Value, app.FemaleDJIDPairEditField.Value);
        end

        % Value changed function: FemaleDJIDPairEditField
        function FemaleDJIDPairEditFieldValueChanged(app, event)
            app.breeder_djid_changed(app.MaleDJIDPairEditField.Value, app.FemaleDJIDPairEditField.Value);
        end

        % Selection changed function: ProjectreservationButtonGroup
        function ProjectreservationButtonGroupSelectionChanged(app, event)
            app.loadAnimalTable([]);
        end

        % Button pushed function: SearchButton_lookup
        function SearchButton_lookupPushed(app, event)
            searched_animal_id = str2double(app.DJIDEditField_lookup.Value);
            q = sln_animal.Animal & sprintf('animal_id=%d',searched_animal_id);
            if ~q.exists
                logText = 'Animal not found';
                set(app.EventlistTextArea,'value', logText);
                disp('Error: animal not found');
                return;
            end
            N = length(app.animal_event_list);
            ev_cellArray = {};
            ev_type = {};
            ev_date = [];
            z = 1;
            for i=1:N
                curEvent = app.animal_event_list{i};
                q = sln_animal.AnimalEvent * feval(sprintf('sln_animal.%s', curEvent)) & sprintf('animal_id=%d',searched_animal_id);
                ev_struct = fetch(q,'*');
                for j=1:length(ev_struct)
                    ev_cellArray{z} = ev_struct(j);
                    ev_type{z} = curEvent;
                    if isfield(ev_struct(j),'time')
                        ev_date = [ev_date datetime([ev_struct(j).date ' ' ev_struct(j).time])];
                    else
                        ev_date = [ev_date datetime([ev_struct(j).date ' 00:00:00'])];
                    end
                    z=z+1;
                end
            end
            [~, order] = sort(ev_date);
            ev_type = ev_type(order);
            ev_cellArray = ev_cellArray(order);
            L = length(ev_type);
            logText = cell(L,1);
            for i=1:L
                thisType = ev_type{i};
                thisID = ev_cellArray{i}.event_id;
                ev = eval(sprintf('sln_animal.AnimalEvent*sln_animal.%s',thisType)) & sprintf('event_id=%d',thisID);
                e = eval(sprintf('sln_animal.%s',thisType)) & proj(ev);
                logText{i} = sln_animal.printEvents(e);
            end
            thisAnimal_base = sln_animal.Animal * sln_animal.GenotypeString * sln_animal.Source & sprintf('animal_id=%d', searched_animal_id);
            thisAnimal_breederSource = thisAnimal_base * proj(sln_animal.BreedingPair, 'background_name->breeder_background','strain_name->breeder_strain_name','*');
            if thisAnimal_breederSource.exists
                thisAnimal = fetch(thisAnimal_breederSource, '*');
                animal_str = sprintf('Animal %d: %s, genotype %s born %s, strain=%s, source is breeder: id %d, strain %s, male: %d, female: %d',...
                    thisAnimal.animal_id,thisAnimal.sex, thisAnimal.genotype_string, thisAnimal.dob, thisAnimal.strain_name, thisAnimal.source_id, thisAnimal.breeder_strain_name, thisAnimal.male_id, thisAnimal.female_id);
            else
                thisAnimal_vendorSource = thisAnimal_base * sln_animal.Vendor;
                if thisAnimal_vendorSource.exists
                    thisAnimal = fetch(thisAnimal_vendorSource, '*');
                    animal_str = sprintf('Animal %d: %s, genotype %s born %s, strain=%s, source is vendor: %s',...
                        thisAnimal.animal_id,thisAnimal.sex, thisAnimal.genotype_string, thisAnimal.dob, thisAnimal.strain_name, thisAnimal.vendor_name);
                else
                    thisAnimal_collabSource = thisAnimal_base * sln_animal.Collaborator;
                    thisAnimal = fetch(thisAnimal_collabSource, '*');
                    animal_str = sprintf('Animal %d: %s, genotype %s born %s, strain=%s, source is collaborator: %s at %s',...
                        thisAnimal.animal_id,thisAnimal.sex, thisAnimal.genotype_string, thisAnimal.dob, thisAnimal.strain_name, thisAnimal.lab_name, thisAnimal.organization);
                end
            end
            logText = cat(1,{animal_str; ''},logText);
            set(app.EventlistTextArea,'value', logText);
        end

        % Button pushed function: FindcageButton
        function FindcageButtonPushed(app, event)
            cage_number = app.CagenumberEditField.Value;
            statement_1 = sprintf('sln_animal.AnimalEvent * sln_animal.AssignCage & "cage_number = %d"', cage_number);
            q1 = eval(statement_1);

            statement_2 = sprintf('sln_animal.CageAssignRoom & "cage_number = %d"', cage_number);
            q2 = eval(statement_2);
            if q2.count > 0
                djids = unique(fetchn(q1, 'animal_id'));
                app.DJIDsListBox.Items = string(djids);
                cage_info = fetch(q2, '*');
                cage_statement = sprintf('Cage %d in room %s', cage_info(1).cage_number, cage_info(1).room_number);
                app.CageLabel.Text = cage_statement;
            else
                cage_statement = sprintf('Cannot find cage %d uwu', cage_number);
                app.CageLabel.Text = cage_statement;
                app.DJIDsListBox.Items = {};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnimalInterfaceV2UIFigure = uifigure('Visible', 'off');
            app.AnimalInterfaceV2UIFigure.Name = 'AnimalInterfaceV2';
            app.AnimalInterfaceV2UIFigure.CloseRequestFcn = createCallbackFcn(app, @AnimalInterfaceV2UIFigureCloseRequest, true);
            app.MainTabGroup = uitabgroup(app.AnimalInterfaceV2UIFigure);
            app.MainTabGroup.SelectionChangedFcn = createCallbackFcn(app, @MainTabGroupSelectionChanged, true);
            app.LoginTab = uitab(app.MainTabGroup);
            app.LoginTab.Title = 'Login';
            app.PasswordEditFieldLabel = uilabel(app.LoginTab);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.LoginTab, 'text');
            app.PasswordEditField.ValueChangingFcn = createCallbackFcn(app, @PasswordEditFieldValueChanging, true);
            app.LoginButton = uibutton(app.LoginTab, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.UserEditFieldLabel = uilabel(app.LoginTab);
            app.UserEditFieldLabel.Text = 'User';
            app.LoginUserEditField = uieditfield(app.LoginTab, 'text');
            app.AnimalLoggerTab = uitab(app.MainTabGroup);
            app.AnimalLoggerTab.Title = 'AnimalLogger';
            app.AnimalTable = uitable(app.AnimalLoggerTab);
            app.AnimalTable.ColumnName = {'DJ ID'; 'Tag ID'; 'Tag'; 'Punch'; 'Cage'; 'Room'; 'Bg'; 'Strain'; 'Genotype'; 'Sex'; 'Age'; 'Source'; 'IP inj.'; 'Eye inj.'; 'Eye sut.'; 'Brain inj.'; 'Behavior'; 'Res. for rig'; 'Project'};
            app.AnimalTable.RowName = {};
            app.AnimalTable.ColumnSortable = true;
            app.AnimalTable.CellSelectionCallback = createCallbackFcn(app, @AnimalTableCellSelection, true);
            app.EventTabGroup = uitabgroup(app.AnimalLoggerTab);
            app.EventTabGroup.SelectionChangedFcn = createCallbackFcn(app, @EventTabGroupSelectionChanged, true);
            app.GenotypedTab = uitab(app.EventTabGroup);
            app.GenotypedTab.Title = 'Genotyped';
            app.SourceDropDown_2Label = uilabel(app.GenotypedTab);
            app.SourceDropDown_2Label.Text = 'Source';
            app.GenotypeSourceDropDown = uidropdown(app.GenotypedTab);
            app.SourceDropDown_2Label_2 = uilabel(app.GenotypedTab);
            app.SourceDropDown_2Label_2.Text = 'Locus';
            app.LocusDropDown_1 = uidropdown(app.GenotypedTab);
            app.LocusDropDown_1.ValueChangedFcn = createCallbackFcn(app, @LocusDropDown_1ValueChanged, true);
            app.SourceDropDown_2Label_3 = uilabel(app.GenotypedTab);
            app.SourceDropDown_2Label_3.Text = 'Allele1';
            app.Allele1DropDown_1 = uidropdown(app.GenotypedTab);
            app.Allele1DropDown_1.ValueChangedFcn = createCallbackFcn(app, @Allele1DropDown_1ValueChanged, true);
            app.EntriesSpinnerLabel = uilabel(app.GenotypedTab);
            app.EntriesSpinnerLabel.Text = 'Entries';
            app.GenotypeEntriesSpinner = uispinner(app.GenotypedTab);
            app.GenotypeEntriesSpinner.Limits = [0 4];
            app.GenotypeEntriesSpinner.ValueChangedFcn = createCallbackFcn(app, @GenotypeEntriesSpinnerValueChanged, true);
            app.GenotypeEntriesSpinner.Editable = 'off';
            app.GenotypeEntriesSpinner.Value = 2;
            app.LocusDropDown_2Label = uilabel(app.GenotypedTab);
            app.LocusDropDown_2Label.Text = 'Locus';
            app.LocusDropDown_2 = uidropdown(app.GenotypedTab);
            app.LocusDropDown_2.ValueChangedFcn = createCallbackFcn(app, @LocusDropDown_2ValueChanged, true);
            app.Allele1DropDown_4Label = uilabel(app.GenotypedTab);
            app.Allele1DropDown_4Label.Text = 'Allele1';
            app.Allele1DropDown_2 = uidropdown(app.GenotypedTab);
            app.Allele1DropDown_2.ValueChangedFcn = createCallbackFcn(app, @Allele1DropDown_2ValueChanged, true);
            app.Allele2DropDownLabel = uilabel(app.GenotypedTab);
            app.Allele2DropDownLabel.Text = 'Allele2';
            app.Allele2DropDown_1 = uidropdown(app.GenotypedTab);
            app.Allele2DropDown_1.ValueChangedFcn = createCallbackFcn(app, @Allele2DropDown_1ValueChanged, true);
            app.Allele2DropDown_4Label = uilabel(app.GenotypedTab);
            app.Allele2DropDown_4Label.Text = 'Allele2';
            app.Allele2DropDown_2 = uidropdown(app.GenotypedTab);
            app.Allele2DropDown_2.ValueChangedFcn = createCallbackFcn(app, @Allele2DropDown_2ValueChanged, true);
            app.LocusDropDown_2Label_4 = uilabel(app.GenotypedTab);
            app.LocusDropDown_2Label_4.Text = 'Locus';
            app.LocusDropDown_3 = uidropdown(app.GenotypedTab);
            app.LocusDropDown_3.ValueChangedFcn = createCallbackFcn(app, @LocusDropDown_3ValueChanged, true);
            app.Allele1DropDown_2Label = uilabel(app.GenotypedTab);
            app.Allele1DropDown_2Label.Text = 'Allele1';
            app.Allele1DropDown_3 = uidropdown(app.GenotypedTab);
            app.Allele1DropDown_3.ValueChangedFcn = createCallbackFcn(app, @Allele1DropDown_3ValueChanged, true);
            app.LocusDropDown_2Label_5 = uilabel(app.GenotypedTab);
            app.LocusDropDown_2Label_5.Text = 'Locus';
            app.LocusDropDown_4 = uidropdown(app.GenotypedTab);
            app.LocusDropDown_4.ValueChangedFcn = createCallbackFcn(app, @LocusDropDown_4ValueChanged, true);
            app.Allele1DropDown_3Label = uilabel(app.GenotypedTab);
            app.Allele1DropDown_3Label.Text = 'Allele1';
            app.Allele1DropDown_4 = uidropdown(app.GenotypedTab);
            app.Allele1DropDown_4.ValueChangedFcn = createCallbackFcn(app, @Allele1DropDown_4ValueChanged, true);
            app.Allele2DropDown_2Label = uilabel(app.GenotypedTab);
            app.Allele2DropDown_2Label.Text = 'Allele2';
            app.Allele2DropDown_3 = uidropdown(app.GenotypedTab);
            app.Allele2DropDown_3.ValueChangedFcn = createCallbackFcn(app, @Allele2DropDown_3ValueChanged, true);
            app.Allele2DropDown_3Label = uilabel(app.GenotypedTab);
            app.Allele2DropDown_3Label.Text = 'Allele2';
            app.Allele2DropDown_4 = uidropdown(app.GenotypedTab);
            app.Allele2DropDown_4.ValueChangedFcn = createCallbackFcn(app, @Allele2DropDown_4ValueChanged, true);
            app.EnablealocusviatheallelelocusmapLabel = uilabel(app.GenotypedTab);
            app.EnablealocusviatheallelelocusmapLabel.Text = 'Enable a locus via the allele/locus map';
            app.EnableanalleleviathestrainalleletableLabel = uilabel(app.GenotypedTab);
            app.EnableanalleleviathestrainalleletableLabel.Text = 'Enable an allele via the strain/allele table';
            app.ForahetAllele1XAllele2WTLabel = uilabel(app.GenotypedTab);
            app.ForahetAllele1XAllele2WTLabel.Text = 'For a het:                Allele1 = X                Allele2 = WT';
            app.ForacarrierAllele1XAllele2ambiguousLabel = uilabel(app.GenotypedTab);
            app.ForacarrierAllele1XAllele2ambiguousLabel.Text = 'For a carrier:           Allele1 = X                Allele2 =ambiguous';
            app.FornoncarrierAllele1WTAllele2WTLabel = uilabel(app.GenotypedTab);
            app.FornoncarrierAllele1WTAllele2WTLabel.Text = 'For non-carrier:      Allele1 = WT             Allele2 = WT';
            app.ForahomozygoteAllele1XAllele2XLabel = uilabel(app.GenotypedTab);
            app.ForahomozygoteAllele1XAllele2XLabel.Text = 'For a homozygote:  Allele1 = X               Allele2 = X';
            app.ForunclearresultAllele1ambiguousAllele2ambiguousLabel = uilabel(app.GenotypedTab);
            app.ForunclearresultAllele1ambiguousAllele2ambiguousLabel.Text = 'For unclear result:  Allele1 = ambiguous Allele2 = ambiguous';
            app.DeceasedTab = uitab(app.EventTabGroup);
            app.DeceasedTab.Title = 'Deceased';
            app.ReasonDropDownLabel = uilabel(app.DeceasedTab);
            app.ReasonDropDownLabel.Text = 'Reason';
            app.DeceasedReasonDropDown = uidropdown(app.DeceasedTab);
            app.DeceasedReasonDropDown.Items = {'sacrificed for experiment', 'sacrificed not needed', 'other', 'unknown'};
            app.DeceasedReasonDropDown.Value = 'sacrificed for experiment';
            app.AssigncageTab = uitab(app.EventTabGroup);
            app.AssigncageTab.Title = 'Assign cage';
            app.ReasonDropDown_2Label = uilabel(app.AssigncageTab);
            app.ReasonDropDown_2Label.Text = 'Reason';
            app.MoveReasonDropDown = uidropdown(app.AssigncageTab);
            app.MoveReasonDropDown.Items = {'weaning', 'experiment', 'crowding', 'cage moved rooms', 'other', 'unknown'};
            app.MoveReasonDropDown.Value = 'weaning';
            app.TocageEditFieldLabel = uilabel(app.AssigncageTab);
            app.TocageEditFieldLabel.Text = 'To cage #';
            app.TocageEditField = uieditfield(app.AssigncageTab, 'text');
            app.RoomDropDown_2Label = uilabel(app.AssigncageTab);
            app.RoomDropDown_2Label.Text = 'Room';
            app.RoomDropDown_logger = uidropdown(app.AssigncageTab);
            app.MovecageroomTab = uitab(app.EventTabGroup);
            app.MovecageroomTab.Title = 'Move cage room';
            app.CagenumberEditFieldLabel = uilabel(app.MovecageroomTab);
            app.CagenumberEditFieldLabel.Text = 'Cage number';
            app.MoveCagenumberEditField = uieditfield(app.MovecageroomTab, 'numeric');
            app.MoveCagenumberEditField.ValueDisplayFormat = '%.0f';
            app.ToroomDropDownLabel = uilabel(app.MovecageroomTab);
            app.ToroomDropDownLabel.Text = 'To room';
            app.ToroomDropDown = uidropdown(app.MovecageroomTab);
            app.TagTab = uitab(app.EventTabGroup);
            app.TagTab.Title = 'Tag';
            app.TagEditFieldLabel = uilabel(app.TagTab);
            app.TagEditFieldLabel.Text = 'Tag #';
            app.TagEditField = uieditfield(app.TagTab, 'text');
            app.EarpunchDropDownLabel = uilabel(app.TagTab);
            app.EarpunchDropDownLabel.Text = 'Ear punch';
            app.EarpunchDropDown = uidropdown(app.TagTab);
            app.EarpunchDropDown.Items = {'None', 'R', 'L', 'Both', 'RR', 'LL'};
            app.EarpunchDropDown.Value = 'None';
            app.TagearDropDownLabel = uilabel(app.TagTab);
            app.TagearDropDownLabel.Text = 'Tag ear';
            app.TagearDropDown = uidropdown(app.TagTab);
            app.TagearDropDown.Items = {'None', 'L', 'R', 'Unknown'};
            app.TagearDropDown.Value = 'None';
            app.IPinjectionTab = uitab(app.EventTabGroup);
            app.IPinjectionTab.Title = 'IP injection';
            app.SubstanceDropDownLabel = uilabel(app.IPinjectionTab);
            app.SubstanceDropDownLabel.Text = 'Substance';
            app.SubstanceDropDown_IP = uidropdown(app.IPinjectionTab);
            app.ConcentrationmgKgEditFieldLabel_2 = uilabel(app.IPinjectionTab);
            app.ConcentrationmgKgEditFieldLabel_2.Text = 'Concentration (mg / Kg)';
            app.IP_inj_ConcentrationEditField = uieditfield(app.IPinjectionTab, 'text');
            app.EyeinjectionTab = uitab(app.EventTabGroup);
            app.EyeinjectionTab.Title = 'Eye injection';
            app.SubstanceDropDownLabel_2 = uilabel(app.EyeinjectionTab);
            app.SubstanceDropDownLabel_2.Text = 'Substance';
            app.SubstanceDropDown_Eye = uidropdown(app.EyeinjectionTab);
            app.EyeDropDownLabel = uilabel(app.EyeinjectionTab);
            app.EyeDropDownLabel.Text = 'Eye';
            app.WhichEyeDropDown = uidropdown(app.EyeinjectionTab);
            app.WhichEyeDropDown.Items = {'Left', 'Right'};
            app.WhichEyeDropDown.Value = 'Left';
            app.Dilution1XEditFieldLabel_2 = uilabel(app.EyeinjectionTab);
            app.Dilution1XEditFieldLabel_2.Text = 'Dilution (1:X)';
            app.Dilution_EyeEditField = uieditfield(app.EyeinjectionTab, 'text');
            app.BraininjectionTab = uitab(app.EventTabGroup);
            app.BraininjectionTab.Title = 'Brain injection';
            app.SubstanceDropDown_3Label = uilabel(app.BraininjectionTab);
            app.SubstanceDropDown_3Label.Text = 'Substance';
            app.SubstanceDropDown_Brain = uidropdown(app.BraininjectionTab);
            app.CoordinatesLabel = uilabel(app.BraininjectionTab);
            app.CoordinatesLabel.Text = 'Coordinates:';
            app.TargetDropDownLabel = uilabel(app.BraininjectionTab);
            app.TargetDropDownLabel.Text = 'Target';
            app.BrainTargetDropDown = uidropdown(app.BraininjectionTab);
            app.BrainTargetDropDown.Items = {'MeA', 'LHA', 'OPN', 'vLGN', 'dLGN', 'SC'};
            app.BrainTargetDropDown.Value = 'MeA';
            app.HemisphereDropDownLabel = uilabel(app.BraininjectionTab);
            app.HemisphereDropDownLabel.Text = 'Hemisphere';
            app.HemisphereDropDown = uidropdown(app.BraininjectionTab);
            app.HemisphereDropDown.Items = {'Left', 'Right'};
            app.HemisphereDropDown.Value = 'Left';
            app.Dilution1XEditFieldLabel_3 = uilabel(app.BraininjectionTab);
            app.Dilution1XEditFieldLabel_3.Text = 'Dilution (1:X)';
            app.Dilution_BrainEditField = uieditfield(app.BraininjectionTab, 'text');
            app.APEditField_2Label = uilabel(app.BraininjectionTab);
            app.APEditField_2Label.Text = 'AP';
            app.APEditField = uieditfield(app.BraininjectionTab, 'text');
            app.MLEditField_2Label = uilabel(app.BraininjectionTab);
            app.MLEditField_2Label.Text = 'ML';
            app.MLEditField = uieditfield(app.BraininjectionTab, 'text');
            app.DVEditField_2Label = uilabel(app.BraininjectionTab);
            app.DVEditField_2Label.Text = 'DV';
            app.DVEditField = uieditfield(app.BraininjectionTab, 'text');
            app.AngleEditField_2Label = uilabel(app.BraininjectionTab);
            app.AngleEditField_2Label.Text = 'Angle';
            app.AngleEditField = uieditfield(app.BraininjectionTab, 'text');
            app.EyesutureTab = uitab(app.EventTabGroup);
            app.EyesutureTab.Title = 'Eye suture';
            app.WhicheyeDropDownLabel = uilabel(app.EyesutureTab);
            app.WhicheyeDropDownLabel.Text = 'Which eye';
            app.WhicheyeDropDown = uidropdown(app.EyesutureTab);
            app.WhicheyeDropDown.Items = {'Left', 'Right', 'Both'};
            app.WhicheyeDropDown.Value = 'Left';
            app.FeedTab = uitab(app.EventTabGroup);
            app.FeedTab.Title = 'Feed';
            app.SubstanceDropDownLabel_3 = uilabel(app.FeedTab);
            app.SubstanceDropDownLabel_3.Text = 'Substance';
            app.SubstanceDropDown_Feed = uidropdown(app.FeedTab);
            app.SocialbehaviorsessionTab = uitab(app.EventTabGroup);
            app.SocialbehaviorsessionTab.Title = 'Social behavior session';
            app.RecordBehavior_switch = uiswitch(app.SocialbehaviorsessionTab, 'rocker');
            app.RecordBehavior_switch.Items = {'Not recorded', 'Recorded'};
            app.RecordBehavior_switch.ValueChangedFcn = createCallbackFcn(app, @RecordBehavior_switchValueChanged, true);
            app.RecordBehavior_switch.Value = 'Recorded';
            app.StimulusLabel = uilabel(app.SocialbehaviorsessionTab);
            app.StimulusLabel.Text = 'Stimulus';
            app.Lamp_isRecorded = uilamp(app.SocialbehaviorsessionTab);
            app.ExperimenttypeDropDownLabel = uilabel(app.SocialbehaviorsessionTab);
            app.ExperimenttypeDropDownLabel.Text = 'Experiment type';
            app.ExperimenttypeDropDown = uidropdown(app.SocialbehaviorsessionTab);
            app.AnimalAEditField = uieditfield(app.SocialbehaviorsessionTab, 'text');
            app.AnimalBEditField = uieditfield(app.SocialbehaviorsessionTab, 'text');
            app.AnimalCEditField = uieditfield(app.SocialbehaviorsessionTab, 'text');
            app.TestanimaltypeDropDownLabel = uilabel(app.SocialbehaviorsessionTab);
            app.TestanimaltypeDropDownLabel.Text = 'Test animal type';
            app.TestanimaltypeDropDown = uidropdown(app.SocialbehaviorsessionTab);
            app.DJIDLabel = uilabel(app.SocialbehaviorsessionTab);
            app.DJIDLabel.Text = 'DJID';
            app.WindowLabel = uilabel(app.SocialbehaviorsessionTab);
            app.WindowLabel.Text = 'Window';
            app.ALabel = uilabel(app.SocialbehaviorsessionTab);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.SocialbehaviorsessionTab);
            app.BLabel.Text = 'B';
            app.CLabel = uilabel(app.SocialbehaviorsessionTab);
            app.CLabel.Text = 'C';
            app.WindowAStim = uidropdown(app.SocialbehaviorsessionTab);
            app.WindowAStim.ValueChangedFcn = createCallbackFcn(app, @WindowAStimValueChanged, true);
            app.WindowBStim = uidropdown(app.SocialbehaviorsessionTab);
            app.WindowBStim.ValueChangedFcn = createCallbackFcn(app, @WindowBStimValueChanged, true);
            app.WindowCStim = uidropdown(app.SocialbehaviorsessionTab);
            app.WindowCStim.ValueChangedFcn = createCallbackFcn(app, @WindowCStimValueChanged, true);
            app.ReserveforprojectTab = uitab(app.EventTabGroup);
            app.ReserveforprojectTab.Title = 'Reserve for project';
            app.ProjectDropDownLabel = uilabel(app.ReserveforprojectTab);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.ReserveforprojectTab);
            app.ReserveforrigTab = uitab(app.EventTabGroup);
            app.ReserveforrigTab.Title = 'Reserve for rig';
            app.RignameDropDownLabel = uilabel(app.ReserveforrigTab);
            app.RignameDropDownLabel.Text = 'Rig name';
            app.RignameDropDown = uidropdown(app.ReserveforrigTab);
            app.PairbreedersTab_2 = uitab(app.EventTabGroup);
            app.PairbreedersTab_2.Title = 'Pair breeders';
            app.MaleDJIDEditFieldLabel = uilabel(app.PairbreedersTab_2);
            app.MaleDJIDEditFieldLabel.Text = 'Male DJ ID';
            app.MaleDJIDPairEditField = uieditfield(app.PairbreedersTab_2, 'text');
            app.MaleDJIDPairEditField.ValueChangedFcn = createCallbackFcn(app, @MaleDJIDPairEditFieldValueChanged, true);
            app.FemaleDJIDEditFieldLabel = uilabel(app.PairbreedersTab_2);
            app.FemaleDJIDEditFieldLabel.Text = 'Female DJ ID';
            app.FemaleDJIDPairEditField = uieditfield(app.PairbreedersTab_2, 'text');
            app.FemaleDJIDPairEditField.ValueChangedFcn = createCallbackFcn(app, @FemaleDJIDPairEditFieldValueChanged, true);
            app.CagenumberLabel = uilabel(app.PairbreedersTab_2);
            app.CagenumberLabel.Text = 'Cage number';
            app.NewcagenumberPairEditField = uieditfield(app.PairbreedersTab_2, 'text');
            app.RoomDropDown_2Label_5 = uilabel(app.PairbreedersTab_2);
            app.RoomDropDown_2Label_5.Text = 'Room';
            app.RoomDropDown_Pair = uidropdown(app.PairbreedersTab_2);
            app.StrainnameDropDownLabel = uilabel(app.PairbreedersTab_2);
            app.StrainnameDropDownLabel.Enable = 'off';
            app.StrainnameDropDownLabel.Text = 'Strain name';
            app.StrainnameDropDown = uidropdown(app.PairbreedersTab_2);
            app.StrainnameDropDown.Enable = 'off';
            app.editButton_2 = uibutton(app.PairbreedersTab_2, 'state');
            app.editButton_2.ValueChangedFcn = createCallbackFcn(app, @editButton_2ValueChanged, true);
            app.editButton_2.Text = 'edit';
            app.StrainnameisautomaticallydeterminedbytheLabel = uilabel(app.PairbreedersTab_2);
            app.StrainnameisautomaticallydeterminedbytheLabel.Text = 'Strain name is automatically determined by the genotypes of the parent animals';
            app.StrainnamewillbeleftblankifnotautomaticallyfoundLabel = uilabel(app.PairbreedersTab_2);
            app.StrainnamewillbeleftblankifnotautomaticallyfoundLabel.Text = 'Strain name will be left blank if not automatically found';
            app.StrainnameisautomaticallydeterminedbytheLabel_2 = uilabel(app.PairbreedersTab_2);
            app.StrainnameisautomaticallydeterminedbytheLabel_2.Text = 'Alleles are excluded if the parent is homozygous WT for the corresponding gene';
            app.Label2 = uilabel(app.PairbreedersTab_2);
            app.Label2.Text = 'Unfound strain names either don''t exist or are inactive. In either case, visit the "Add Strain" tab';
            app.OtherprocedureTab = uitab(app.EventTabGroup);
            app.OtherprocedureTab.Title = 'Other procedure';
            app.NameofprocedureputadditionalinfoinNotesEditFieldLabel = uilabel(app.OtherprocedureTab);
            app.NameofprocedureputadditionalinfoinNotesEditFieldLabel.Text = 'Name of procedure (put additional info. in Notes)';
            app.OtherProcNameEdit = uieditfield(app.OtherprocedureTab, 'text');
            app.AnimalSelectionPanel = uipanel(app.AnimalLoggerTab);
            app.AnimalSelectionPanel.Title = 'Animal Selection';
            app.SearchButton = uibutton(app.AnimalSelectionPanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.FilterPanel = uipanel(app.AnimalSelectionPanel);
            app.FilterPanel.Title = 'Filter';
            app.AlleleLabel = uilabel(app.FilterPanel);
            app.AlleleLabel.Text = 'Allele';
            app.Filter_GenotypeDropDown = uidropdown(app.FilterPanel);
            app.Filter_GenotypeDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_GenotypeDropDownValueChanged, true);
            app.StatusDropDownLabel = uilabel(app.FilterPanel);
            app.StatusDropDownLabel.Text = 'Status';
            app.Filter_GenotypeStatusDropDown = uidropdown(app.FilterPanel);
            app.Filter_GenotypeStatusDropDown.Items = {'All', 'carrier', 'non-carrier', 'unknown', 'het', 'homo'};
            app.Filter_GenotypeStatusDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_GenotypeStatusDropDownValueChanged, true);
            app.Filter_GenotypeStatusDropDown.Value = 'All';
            app.SexDropDownLabel = uilabel(app.FilterPanel);
            app.SexDropDownLabel.Text = 'Sex';
            app.Filter_SexDropDown = uidropdown(app.FilterPanel);
            app.Filter_SexDropDown.Items = {'All', 'Male', 'Female'};
            app.Filter_SexDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_SexDropDownValueChanged, true);
            app.Filter_SexDropDown.Value = 'All';
            app.ProjectLabel = uilabel(app.FilterPanel);
            app.ProjectLabel.Text = 'Project';
            app.Filter_ProjectDropDown = uidropdown(app.FilterPanel);
            app.Filter_ProjectDropDown.Items = {'All', 'Male', 'Female'};
            app.Filter_ProjectDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_ProjectDropDownValueChanged, true);
            app.Filter_ProjectDropDown.Value = 'All';
            app.AgerangeweeksPanel = uipanel(app.FilterPanel);
            app.AgerangeweeksPanel.Title = 'Age range (weeks)';
            app.toLabel = uilabel(app.AgerangeweeksPanel);
            app.toLabel.Text = 'to';
            app.ageLow_edit = uieditfield(app.AgerangeweeksPanel, 'text');
            app.ageLow_edit.ValueChangedFcn = createCallbackFcn(app, @ageLow_editValueChanged, true);
            app.ageHigh_edit = uieditfield(app.AgerangeweeksPanel, 'text');
            app.ageHigh_edit.ValueChangedFcn = createCallbackFcn(app, @ageHigh_editValueChanged, true);
            app.EyeInjectionButtonGroup = uibuttongroup(app.FilterPanel);
            app.EyeInjectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EyeInjectionButtonGroupSelectionChanged, true);
            app.EyeInjectionButtonGroup.Title = 'Eye Injection';
            app.AnyButton = uiradiobutton(app.EyeInjectionButtonGroup);
            app.AnyButton.Text = 'Any';
            app.AnyButton.Value = true;
            app.YesButton = uiradiobutton(app.EyeInjectionButtonGroup);
            app.YesButton.Text = 'Yes';
            app.NoButton = uiradiobutton(app.EyeInjectionButtonGroup);
            app.NoButton.Text = 'No';
            app.BrainInjectionButtonGroup = uibuttongroup(app.FilterPanel);
            app.BrainInjectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BrainInjectionButtonGroupSelectionChanged, true);
            app.BrainInjectionButtonGroup.Title = 'Brain Injection';
            app.AnyButton_2 = uiradiobutton(app.BrainInjectionButtonGroup);
            app.AnyButton_2.Text = 'Any';
            app.AnyButton_2.Value = true;
            app.YesButton_2 = uiradiobutton(app.BrainInjectionButtonGroup);
            app.YesButton_2.Text = 'Yes';
            app.NoButton_2 = uiradiobutton(app.BrainInjectionButtonGroup);
            app.NoButton_2.Text = 'No';
            app.RigreservationButtonGroup = uibuttongroup(app.FilterPanel);
            app.RigreservationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RigreservationButtonGroupSelectionChanged, true);
            app.RigreservationButtonGroup.Title = 'Rig reservation';
            app.AnyButton_3 = uiradiobutton(app.RigreservationButtonGroup);
            app.AnyButton_3.Text = 'Any';
            app.AnyButton_3.Value = true;
            app.YesButton_3 = uiradiobutton(app.RigreservationButtonGroup);
            app.YesButton_3.Text = 'Yes';
            app.NoButton_3 = uiradiobutton(app.RigreservationButtonGroup);
            app.NoButton_3.Text = 'No';
            app.BehaviorsessionButtonGroup = uibuttongroup(app.FilterPanel);
            app.BehaviorsessionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BehaviorsessionButtonGroupSelectionChanged, true);
            app.BehaviorsessionButtonGroup.Title = 'Behavior session';
            app.AnyButton_4 = uiradiobutton(app.BehaviorsessionButtonGroup);
            app.AnyButton_4.Text = 'Any';
            app.AnyButton_4.Value = true;
            app.YesButton_4 = uiradiobutton(app.BehaviorsessionButtonGroup);
            app.YesButton_4.Text = 'Yes';
            app.NoButton_4 = uiradiobutton(app.BehaviorsessionButtonGroup);
            app.NoButton_4.Text = 'No';
            app.IPInjectionButtonGroup = uibuttongroup(app.FilterPanel);
            app.IPInjectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IPInjectionButtonGroupSelectionChanged, true);
            app.IPInjectionButtonGroup.Title = 'IP Injection';
            app.AnyButton_5 = uiradiobutton(app.IPInjectionButtonGroup);
            app.AnyButton_5.Text = 'Any';
            app.AnyButton_5.Value = true;
            app.YesButton_5 = uiradiobutton(app.IPInjectionButtonGroup);
            app.YesButton_5.Text = 'Yes';
            app.NoButton_5 = uiradiobutton(app.IPInjectionButtonGroup);
            app.NoButton_5.Text = 'No';
            app.BgLabel = uilabel(app.FilterPanel);
            app.BgLabel.Text = 'Bg';
            app.Filter_BgDropDown = uidropdown(app.FilterPanel);
            app.Filter_BgDropDown.Items = {'All', 'Male', 'Female'};
            app.Filter_BgDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_BgDropDownValueChanged, true);
            app.Filter_BgDropDown.Value = 'All';
            app.StrainDropDownLabel = uilabel(app.FilterPanel);
            app.StrainDropDownLabel.Text = 'Strain';
            app.StrainDropDown = uidropdown(app.FilterPanel);
            app.StrainDropDown.ValueChangedFcn = createCallbackFcn(app, @StrainDropDownValueChanged, true);
            app.ProjectreservationButtonGroup = uibuttongroup(app.FilterPanel);
            app.ProjectreservationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProjectreservationButtonGroupSelectionChanged, true);
            app.ProjectreservationButtonGroup.Title = 'Project reservation';
            app.AnyButton_6 = uiradiobutton(app.ProjectreservationButtonGroup);
            app.AnyButton_6.Text = 'Any';
            app.AnyButton_6.Value = true;
            app.YesButton_6 = uiradiobutton(app.ProjectreservationButtonGroup);
            app.YesButton_6.Text = 'Yes';
            app.NoButton_6 = uiradiobutton(app.ProjectreservationButtonGroup);
            app.NoButton_6.Text = 'No';
            app.ResetButton = uibutton(app.AnimalSelectionPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Search_DJIDEditField = uieditfield(app.AnimalSelectionPanel, 'text');
            app.Search_TagIDEditField = uieditfield(app.AnimalSelectionPanel, 'text');
            app.CageNumSearchEditField = uieditfield(app.AnimalSelectionPanel, 'text');
            app.SearchTypeButtonGroup = uibuttongroup(app.AnimalSelectionPanel);
            app.SearchTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SearchTypeButtonGroupSelectionChanged, true);
            app.DJIDButton = uiradiobutton(app.SearchTypeButtonGroup);
            app.DJIDButton.Text = 'DJ ID';
            app.DJIDButton.Value = true;
            app.CageButton = uiradiobutton(app.SearchTypeButtonGroup);
            app.CageButton.Text = 'Cage #';
            app.TagButton = uiradiobutton(app.SearchTypeButtonGroup);
            app.TagButton.Text = 'Tag #';
            app.ReloadButton = uibutton(app.AnimalSelectionPanel, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.PersonDropDownLabel = uilabel(app.AnimalLoggerTab);
            app.PersonDropDownLabel.Text = 'Person';
            app.PersonDropDown = uidropdown(app.AnimalLoggerTab);
            app.PersonDropDown.Items = {'David', 'Zach', 'Devon', 'Greg', 'Kayla', 'Sam', 'Sophia', 'Susan'};
            app.PersonDropDown.Value = 'David';
            app.EventdateDatePickerLabel = uilabel(app.AnimalLoggerTab);
            app.EventdateDatePickerLabel.Text = 'Event date';
            app.EventdateDatePicker = uidatepicker(app.AnimalLoggerTab);
            app.EventdateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.SubmitevententryButton = uibutton(app.AnimalLoggerTab, 'push');
            app.SubmitevententryButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitevententryButtonPushed, true);
            app.SubmitevententryButton.Text = 'Submit event entry';
            app.NotesTextArea_2Label = uilabel(app.AnimalLoggerTab);
            app.NotesTextArea_2Label.Text = 'Notes';
            app.EventNotesTextArea = uitextarea(app.AnimalLoggerTab);
            app.EventTimeM = uidropdown(app.AnimalLoggerTab);
            app.EventTimeM.Items = {'00', '15', '30', '45'};
            app.EventTimeM.Value = '00';
            app.EventTimeAMPM = uidropdown(app.AnimalLoggerTab);
            app.EventTimeAMPM.Items = {'AM', 'PM'};
            app.EventTimeAMPM.Value = 'AM';
            app.TimeLabel_2 = uilabel(app.AnimalLoggerTab);
            app.TimeLabel_2.Text = 'Time';
            app.AlleventsforselectedanimalButton = uibutton(app.AnimalLoggerTab, 'push');
            app.AlleventsforselectedanimalButton.ButtonPushedFcn = createCallbackFcn(app, @AlleventsforselectedanimalButtonPushed, true);
            app.AlleventsforselectedanimalButton.Text = 'All events for selected animal';
            app.EventTimeH = uidropdown(app.AnimalLoggerTab);
            app.EventTimeH.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.EventTimeH.Value = '9';
            app.ViewlogsLabel = uilabel(app.AnimalLoggerTab);
            app.ViewlogsLabel.Text = 'View logs';
            app.StatusLampLabel = uilabel(app.AnimalLoggerTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp_Logger = uilamp(app.AnimalLoggerTab);
            app.EventInsertStatusEdit = uieditfield(app.AnimalLoggerTab, 'text');
            app.EventInsertStatusEdit.Editable = 'off';
            app.ExporttoExcelButton = uibutton(app.AnimalLoggerTab, 'push');
            app.ExporttoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoExcelButtonPushed, true);
            app.ExporttoExcelButton.Text = 'Export to Excel';
            app.BreedersTab = uitab(app.MainTabGroup);
            app.BreedersTab.Title = 'Breeders';
            app.BreederCagesTable = uitable(app.BreedersTab);
            app.BreederCagesTable.ColumnName = {'Pair DJID'; 'Cage'; 'Room'; 'Strain'; 'F DJID'; 'F Strain'; 'F Genotype'; 'F Age (wks)'; 'M DJID'; 'M Strain'; 'M Genotype'; 'M Age (wks)'; 'Date paired'; '# of litters'; 'Litter dates'; 'Weaned sizes'; 'Next wean date'};
            app.BreederCagesTable.RowName = {};
            app.BreederCagesTable.ColumnSortable = [true true true true true true true true true true true true true true false false false];
            app.BreederCagesTable.CellSelectionCallback = createCallbackFcn(app, @BreederCagesTableCellSelection, true);
            app.ExporttoExcelButton_breeders = uibutton(app.BreedersTab, 'push');
            app.ExporttoExcelButton_breeders.ButtonPushedFcn = createCallbackFcn(app, @ExporttoExcelButton_breedersPushed, true);
            app.ExporttoExcelButton_breeders.Text = 'Export to Excel';
            app.SubmitevententryButton_breeders = uibutton(app.BreedersTab, 'push');
            app.SubmitevententryButton_breeders.ButtonPushedFcn = createCallbackFcn(app, @SubmitevententryButton_breedersPushed, true);
            app.SubmitevententryButton_breeders.Text = 'Submit event entry';
            app.StatusLampLabel_6 = uilabel(app.BreedersTab);
            app.StatusLampLabel_6.Text = 'Status';
            app.StatusLamp_breeders = uilamp(app.BreedersTab);
            app.EventInsertStatusEdit_breeders = uieditfield(app.BreedersTab, 'text');
            app.EventInsertStatusEdit_breeders.Editable = 'off';
            app.PersonDropDown_2Label = uilabel(app.BreedersTab);
            app.PersonDropDown_2Label.Text = 'Person';
            app.PersonDropDown_breeders = uidropdown(app.BreedersTab);
            app.PersonDropDown_breeders.Items = {'David', 'Zach', 'Devon', 'Greg', 'Kayla', 'Sam', 'Sophia', 'Susan'};
            app.PersonDropDown_breeders.Value = 'David';
            app.EventdateDatePicker_2Label = uilabel(app.BreedersTab);
            app.EventdateDatePicker_2Label.Text = 'Event date';
            app.EventdateDatePicker_breeders = uidatepicker(app.BreedersTab);
            app.EventdateDatePicker_breeders.DisplayFormat = 'uuuu-MM-dd';
            app.NotesTextArea_2Label_2 = uilabel(app.BreedersTab);
            app.NotesTextArea_2Label_2.Text = 'Notes';
            app.EventNotesTextArea_breeders = uitextarea(app.BreedersTab);
            app.EventTabGroup_breeders = uitabgroup(app.BreedersTab);
            app.SeparatebreedingpairTab = uitab(app.EventTabGroup_breeders);
            app.SeparatebreedingpairTab.Title = 'Separate breeding pair';
            app.CageformaleEditFieldLabel = uilabel(app.SeparatebreedingpairTab);
            app.CageformaleEditFieldLabel.Text = 'Cage for male';
            app.CageformaleEditField = uieditfield(app.SeparatebreedingpairTab, 'text');
            app.CageforfemaleEditFieldLabel = uilabel(app.SeparatebreedingpairTab);
            app.CageforfemaleEditFieldLabel.Text = 'Cage for female';
            app.CageforfemaleEditField = uieditfield(app.SeparatebreedingpairTab, 'text');
            app.RoomDropDown_2Label_3 = uilabel(app.SeparatebreedingpairTab);
            app.RoomDropDown_2Label_3.Text = 'Room';
            app.RoomDropDown_breeders_sepMale = uidropdown(app.SeparatebreedingpairTab);
            app.RoomDropDown_2Label_4 = uilabel(app.SeparatebreedingpairTab);
            app.RoomDropDown_2Label_4.Text = 'Room';
            app.RoomDropDown_breeders_sepFemale = uidropdown(app.SeparatebreedingpairTab);
            app.SacrificebreedingpairTab = uitab(app.EventTabGroup_breeders);
            app.SacrificebreedingpairTab.Title = 'Sacrifice breeding pair';
            app.ReasonDropDownLabel_3 = uilabel(app.SacrificebreedingpairTab);
            app.ReasonDropDownLabel_3.Text = 'Reason';
            app.DeceasedReasonDropDown_BothBreeders = uidropdown(app.SacrificebreedingpairTab);
            app.DeceasedReasonDropDown_BothBreeders.Items = {'sacrificed for experiment', 'sacrificed not needed', 'other', 'unknown'};
            app.DeceasedReasonDropDown_BothBreeders.Value = 'sacrificed for experiment';
            app.RemovemalesacrificeTab = uitab(app.EventTabGroup_breeders);
            app.RemovemalesacrificeTab.Title = 'Remove male / sacrifice';
            app.ReasonDropDownLabel_2 = uilabel(app.RemovemalesacrificeTab);
            app.ReasonDropDownLabel_2.Text = 'Reason';
            app.DeceasedReasonDropDown_MaleBreeder = uidropdown(app.RemovemalesacrificeTab);
            app.DeceasedReasonDropDown_MaleBreeder.Items = {'sacrificed for experiment', 'sacrificed not needed', 'other', 'unknown'};
            app.DeceasedReasonDropDown_MaleBreeder.Value = 'sacrificed for experiment';
            app.SeparateharemTab = uitab(app.EventTabGroup_breeders);
            app.SeparateharemTab.Title = 'Separate harem';
            app.CageforfemaleEditFieldLabel_2 = uilabel(app.SeparateharemTab);
            app.CageforfemaleEditFieldLabel_2.Text = 'Cage ';
            app.CageforfemaleEditField_harem1 = uieditfield(app.SeparateharemTab, 'text');
            app.RoomDropDown_2Label_7 = uilabel(app.SeparateharemTab);
            app.RoomDropDown_2Label_7.Text = 'Room';
            app.RoomDropDown_breeders_harem_female1 = uidropdown(app.SeparateharemTab);
            app.DJIDEditFieldLabel = uilabel(app.SeparateharemTab);
            app.DJIDEditFieldLabel.Text = 'DJID';
            app.DJIDEditField_harem_female_1 = uieditfield(app.SeparateharemTab, 'numeric');
            app.CageforfemaleEditFieldLabel_3 = uilabel(app.SeparateharemTab);
            app.CageforfemaleEditFieldLabel_3.Text = 'Cage ';
            app.CageforfemaleEditField_harem2 = uieditfield(app.SeparateharemTab, 'text');
            app.RoomDropDown_2Label_8 = uilabel(app.SeparateharemTab);
            app.RoomDropDown_2Label_8.Text = 'Room';
            app.RoomDropDown_breeders_harem_female2 = uidropdown(app.SeparateharemTab);
            app.DJIDEditFieldLabel_2 = uilabel(app.SeparateharemTab);
            app.DJIDEditFieldLabel_2.Text = 'DJID';
            app.DJIDEditField_harem_female_2 = uieditfield(app.SeparateharemTab, 'numeric');
            app.MaleLabel = uilabel(app.SeparateharemTab);
            app.MaleLabel.Text = 'Male';
            app.FemalesLabel = uilabel(app.SeparateharemTab);
            app.FemalesLabel.Text = 'Females';
            app.CageforfemaleEditFieldLabel_4 = uilabel(app.SeparateharemTab);
            app.CageforfemaleEditFieldLabel_4.Text = 'Cage ';
            app.CageforfemaleEditField_harem_male = uieditfield(app.SeparateharemTab, 'text');
            app.RoomDropDown_2Label_9 = uilabel(app.SeparateharemTab);
            app.RoomDropDown_2Label_9.Text = 'Room';
            app.RoomDropDown_breeders_harem_male = uidropdown(app.SeparateharemTab);
            app.DJIDEditFieldLabel_3 = uilabel(app.SeparateharemTab);
            app.DJIDEditFieldLabel_3.Text = 'DJID';
            app.DJIDEditField_harem_male = uieditfield(app.SeparateharemTab, 'numeric');
            app.DJIDEditField_harem_male.Editable = 'off';
            app.RemovemaleassignnewcageTab = uitab(app.EventTabGroup_breeders);
            app.RemovemaleassignnewcageTab.Title = 'Remove male / assign new cage';
            app.TocageEditFieldLabel_2 = uilabel(app.RemovemaleassignnewcageTab);
            app.TocageEditFieldLabel_2.Text = 'To cage #';
            app.TocageEditField_MaleBreeder = uieditfield(app.RemovemaleassignnewcageTab, 'text');
            app.RoomDropDown_2Label_6 = uilabel(app.RemovemaleassignnewcageTab);
            app.RoomDropDown_2Label_6.Text = 'Room';
            app.RoomDropDown_MaleBreeder = uidropdown(app.RemovemaleassignnewcageTab);
            app.ChangecagenumberTab = uitab(app.EventTabGroup_breeders);
            app.ChangecagenumberTab.Title = 'Change cage number';
            app.RoomDropDown_BreedingPairLabel = uilabel(app.ChangecagenumberTab);
            app.RoomDropDown_BreedingPairLabel.Text = 'Room';
            app.RoomDropDown_BreedingPair = uidropdown(app.ChangecagenumberTab);
            app.NewcagenumberEditFieldLabel = uilabel(app.ChangecagenumberTab);
            app.NewcagenumberEditFieldLabel.Text = 'New cage number';
            app.NewcagenumberEditField = uieditfield(app.ChangecagenumberTab, 'text');
            app.LogbirthTab = uitab(app.EventTabGroup_breeders);
            app.LogbirthTab.Title = 'Log birth';
            app.LogweaningTab = uitab(app.EventTabGroup_breeders);
            app.LogweaningTab.Title = 'Log weaning';
            app.NumberofpupsLabel = uilabel(app.LogweaningTab);
            app.NumberofpupsLabel.Text = '🍼Number of pups';
            app.NumberofpupsEditField_weaned = uieditfield(app.LogweaningTab, 'text');
            app.SearchButton_breeders = uibutton(app.BreedersTab, 'push');
            app.SearchButton_breeders.ButtonPushedFcn = createCallbackFcn(app, @SearchButton_breedersPushed, true);
            app.SearchButton_breeders.Text = 'Search';
            app.ResetButton_breeders = uibutton(app.BreedersTab, 'push');
            app.ResetButton_breeders.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_breedersPushed, true);
            app.ResetButton_breeders.Text = 'Reset';
            app.Search_DJIDEditField_breeders = uieditfield(app.BreedersTab, 'text');
            app.CageNumSearchEditField_breeders = uieditfield(app.BreedersTab, 'text');
            app.SearchTypeButtonGroup_breeders = uibuttongroup(app.BreedersTab);
            app.SearchTypeButtonGroup_breeders.SelectionChangedFcn = createCallbackFcn(app, @SearchTypeButtonGroup_breedersSelectionChanged, true);
            app.DJIDButton_breeders = uiradiobutton(app.SearchTypeButtonGroup_breeders);
            app.DJIDButton_breeders.Text = 'DJ ID';
            app.DJIDButton_breeders.Value = true;
            app.CageButton_breeders = uiradiobutton(app.SearchTypeButtonGroup_breeders);
            app.CageButton_breeders.Text = 'Cage #';
            app.BreederPairs_status = uilabel(app.BreedersTab);
            app.BreederPairs_status.Text = 'loading...';
            app.BreedingpairsLabel = uilabel(app.BreedersTab);
            app.BreedingpairsLabel.Text = 'Breeding pairs:';
            app.AlleleLabel_2 = uilabel(app.BreedersTab);
            app.AlleleLabel_2.Text = 'Allele';
            app.GenotypeDropDown_BreedersTab = uidropdown(app.BreedersTab);
            app.GenotypeDropDown_BreedersTab.ValueChangedFcn = createCallbackFcn(app, @GenotypeDropDown_BreedersTabValueChanged, true);
            app.GenotypeDropDown_2Label_2 = uilabel(app.BreedersTab);
            app.GenotypeDropDown_2Label_2.Text = 'Strain';
            app.StrainDropDown_BreedersTab = uidropdown(app.BreedersTab);
            app.StrainDropDown_BreedersTab.ValueChangedFcn = createCallbackFcn(app, @StrainDropDown_BreedersTabValueChanged, true);
            app.AddAnimalsTab = uitab(app.MainTabGroup);
            app.AddAnimalsTab.Title = 'Add Animals';
            app.SpeciesTabGroup = uitabgroup(app.AddAnimalsTab);
            app.MouseTab = uitab(app.SpeciesTabGroup);
            app.MouseTab.Title = 'Mouse';
            app.tagged_switch = uiswitch(app.MouseTab, 'slider');
            app.tagged_switch.Items = {'Untagged', 'Tagged'};
            app.tagged_switch.ValueChangedFcn = createCallbackFcn(app, @tagged_switchValueChanged, true);
            app.tagged_switch.Value = 'Tagged';
            app.SexButtonGroup = uibuttongroup(app.MouseTab);
            app.SexButtonGroup.Title = 'Sex';
            app.UnknownButton = uiradiobutton(app.SexButtonGroup);
            app.UnknownButton.Text = 'Unknown';
            app.UnknownButton.Value = true;
            app.MaleButton = uiradiobutton(app.SexButtonGroup);
            app.MaleButton.Text = 'Male';
            app.FemaleButton = uiradiobutton(app.SexButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.PunchearButtonGroup = uibuttongroup(app.MouseTab);
            app.PunchearButtonGroup.Title = 'Punch ear';
            app.NoneButton = uiradiobutton(app.PunchearButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.LButton = uiradiobutton(app.PunchearButtonGroup);
            app.LButton.Text = 'L';
            app.RButton = uiradiobutton(app.PunchearButtonGroup);
            app.RButton.Text = 'R';
            app.BothButton = uiradiobutton(app.PunchearButtonGroup);
            app.BothButton.Text = 'Both';
            app.DOB_known_switch = uiswitch(app.MouseTab, 'slider');
            app.DOB_known_switch.Items = {'DOB unknown', 'DOB known'};
            app.DOB_known_switch.ValueChangedFcn = createCallbackFcn(app, @DOB_known_switchValueChanged, true);
            app.DOB_known_switch.Value = 'DOB known';
            app.StrainDropDown_2Label = uilabel(app.MouseTab);
            app.StrainDropDown_2Label.Text = 'Strain';
            app.StrainDropDown_2 = uidropdown(app.MouseTab);
            app.StrainDropDown_2.ValueChangedFcn = createCallbackFcn(app, @StrainDropDown_2ValueChanged, true);
            app.DOBDatePickerLabel = uilabel(app.MouseTab);
            app.DOBDatePickerLabel.Text = 'DOB';
            app.DOBDatePicker = uidatepicker(app.MouseTab);
            app.DOBDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.TagearButtonGroup = uibuttongroup(app.MouseTab);
            app.TagearButtonGroup.Title = 'Tag ear';
            app.NoneButton_2 = uiradiobutton(app.TagearButtonGroup);
            app.NoneButton_2.Text = 'None';
            app.NoneButton_2.Value = true;
            app.LButton_2 = uiradiobutton(app.TagearButtonGroup);
            app.LButton_2.Text = 'L';
            app.RButton_2 = uiradiobutton(app.TagearButtonGroup);
            app.RButton_2.Text = 'R';
            app.UnknownButton_2 = uiradiobutton(app.TagearButtonGroup);
            app.UnknownButton_2.Text = 'Unknown';
            app.RoomDropDownLabel = uilabel(app.MouseTab);
            app.RoomDropDownLabel.Text = 'Room';
            app.RoomDropDown = uidropdown(app.MouseTab);
            app.TagIDEditFieldLabel = uilabel(app.MouseTab);
            app.TagIDEditFieldLabel.Text = 'Tag ID';
            app.TagIDEditField = uieditfield(app.MouseTab, 'text');
            app.SourcetypeLabel = uilabel(app.MouseTab);
            app.SourcetypeLabel.Text = 'Source type';
            app.SourceTypeDropDown = uidropdown(app.MouseTab);
            app.SourceTypeDropDown.Items = {'breeding', 'vendor', 'collaborator'};
            app.SourceTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceTypeDropDownValueChanged, true);
            app.SourceTypeDropDown.Value = 'breeding';
            app.CageEditFieldLabel = uilabel(app.MouseTab);
            app.CageEditFieldLabel.Text = 'Cage #';
            app.CageEditField = uieditfield(app.MouseTab, 'text');
            app.SingleentryLabel = uilabel(app.MouseTab);
            app.SingleentryLabel.Text = 'Single entry';
            app.MultipleentryLabel = uilabel(app.MouseTab);
            app.MultipleentryLabel.Text = 'Multiple entry';
            app.SourceinfoLabel = uilabel(app.MouseTab);
            app.SourceinfoLabel.Text = 'External info.';
            app.ExternalInfoEditField = uieditfield(app.MouseTab, 'text');
            app.AnimalSourceDropDown = uidropdown(app.MouseTab);
            app.AnimalSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @AnimalSourceDropDownValueChanged, true);
            app.SetmultitomatchButton = uibutton(app.MouseTab, 'push');
            app.SetmultitomatchButton.ButtonPushedFcn = createCallbackFcn(app, @SetmultitomatchButtonPushed, true);
            app.SetmultitomatchButton.Text = 'Set multi to match';
            app.StrainLabel = uilabel(app.MouseTab);
            app.StrainLabel.Text = 'Strain';
            app.BackgroundDropDown_multi = uidropdown(app.MouseTab);
            app.DOBDatePicker_2Label = uilabel(app.MouseTab);
            app.DOBDatePicker_2Label.Text = 'DOB';
            app.DOBDatePicker_multi = uidatepicker(app.MouseTab);
            app.DOBDatePicker_multi.DisplayFormat = 'uuuu-MM-dd';
            app.TagIDEditFieldLabel_2 = uilabel(app.MouseTab);
            app.TagIDEditFieldLabel_2.Text = 'Tag individually after entry';
            app.SourcetypeLabel_4 = uilabel(app.MouseTab);
            app.SourcetypeLabel_4.Text = 'Source type';
            app.SourceTypeDropDown_multi = uidropdown(app.MouseTab);
            app.SourceTypeDropDown_multi.Items = {'breeding', 'vendor', 'collaborator'};
            app.SourceTypeDropDown_multi.ValueChangedFcn = createCallbackFcn(app, @SourceTypeDropDown_multiValueChanged, true);
            app.SourceTypeDropDown_multi.Value = 'breeding';
            app.AnimalSourceDropDown_multi = uidropdown(app.MouseTab);
            app.AnimalSourceDropDown_multi.ValueChangedFcn = createCallbackFcn(app, @AnimalSourceDropDown_multiValueChanged, true);
            app.NumberofmalesSpinnerLabel = uilabel(app.MouseTab);
            app.NumberofmalesSpinnerLabel.Text = 'Number of males';
            app.NumberofmalesSpinner = uispinner(app.MouseTab);
            app.NumberofmalesSpinner.Limits = [0 Inf];
            app.NumberoffemalesSpinnerLabel = uilabel(app.MouseTab);
            app.NumberoffemalesSpinnerLabel.Text = 'Number of females';
            app.NumberoffemalesSpinner = uispinner(app.MouseTab);
            app.NumberoffemalesSpinner.Limits = [0 Inf];
            app.TagIDEditFieldLabel_5 = uilabel(app.MouseTab);
            app.TagIDEditFieldLabel_5.Text = 'No external info. on multiple entry';
            app.RoomDropDown_6Label_3 = uilabel(app.MouseTab);
            app.RoomDropDown_6Label_3.Text = 'Room';
            app.FemaleRoomDropDown = uidropdown(app.MouseTab);
            app.MalecageLabel_2 = uilabel(app.MouseTab);
            app.MalecageLabel_2.Text = 'Female cage ';
            app.FemaleCageEditField = uieditfield(app.MouseTab, 'text');
            app.CageassignmentdateLabel = uilabel(app.MouseTab);
            app.CageassignmentdateLabel.Text = 'Cage assignment date';
            app.CageEntryDatePicker_multi = uidatepicker(app.MouseTab);
            app.CageEntryDatePicker_multi.DisplayFormat = 'uuuu-MM-dd';
            app.SourceLabel = uilabel(app.MouseTab);
            app.SourceLabel.Text = 'Source';
            app.SourceLabel_2 = uilabel(app.MouseTab);
            app.SourceLabel_2.Text = 'Source';
            app.MalecageLabel_3 = uilabel(app.MouseTab);
            app.MalecageLabel_3.Text = 'Male cage ';
            app.MaleCageEditField_2 = uieditfield(app.MouseTab, 'text');
            app.RoomDropDown_6Label_4 = uilabel(app.MouseTab);
            app.RoomDropDown_6Label_4.Text = 'Room';
            app.MaleRoomDropDown_2 = uidropdown(app.MouseTab);
            app.CageassignmentdateLabel_2 = uilabel(app.MouseTab);
            app.CageassignmentdateLabel_2.Text = 'Cage assignment date';
            app.CageEntryDatePicker = uidatepicker(app.MouseTab);
            app.CageEntryDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.ChameleonTab = uitab(app.SpeciesTabGroup);
            app.ChameleonTab.Title = 'Chameleon';
            app.PerhapssomedayLabel = uilabel(app.ChameleonTab);
            app.PerhapssomedayLabel.Text = 'Perhaps someday';
            app.AddsingleanimaltodatabaseButton = uibutton(app.AddAnimalsTab, 'push');
            app.AddsingleanimaltodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddsingleanimaltodatabaseButtonPushed, true);
            app.AddsingleanimaltodatabaseButton.Text = 'Add single animal to database';
            app.StatusLampLabel_2 = uilabel(app.AddAnimalsTab);
            app.StatusLampLabel_2.Text = 'Status';
            app.StatusLamp_Animal = uilamp(app.AddAnimalsTab);
            app.AnimalInsertStatusEdit = uieditfield(app.AddAnimalsTab, 'text');
            app.AnimalInsertStatusEdit.Editable = 'off';
            app.AddmultipleanimalstodatabaseButton = uibutton(app.AddAnimalsTab, 'push');
            app.AddmultipleanimalstodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddmultipleanimalstodatabaseButtonPushed, true);
            app.AddmultipleanimalstodatabaseButton.Text = 'Add multiple animals to database';
            app.AddStrainTab = uitab(app.MainTabGroup);
            app.AddStrainTab.Title = 'Add Strain';
            app.GenotypenameEditFieldLabel_2 = uilabel(app.AddStrainTab);
            app.GenotypenameEditFieldLabel_2.Text = 'Strain name';
            app.StrainNameEditField = uieditfield(app.AddStrainTab, 'text');
            app.AddstraintodatabaseButton = uibutton(app.AddStrainTab, 'push');
            app.AddstraintodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddstraintodatabaseButtonPushed, true);
            app.AddstraintodatabaseButton.Text = 'Add strain to database';
            app.StatusLamp_Strain = uilamp(app.AddStrainTab);
            app.StrainInsertStatusEdit = uieditfield(app.AddStrainTab, 'text');
            app.StrainInsertStatusEdit.Editable = 'off';
            app.StrainsindatabaseLabel = uilabel(app.AddStrainTab);
            app.StrainsindatabaseLabel.Text = 'Active strains in database';
            app.ActiveStrainListBox = uilistbox(app.AddStrainTab);
            app.ActiveStrainListBox.Items = {};
            app.ActiveStrainListBox.Value = {};
            app.StraintypeButtonGroup = uibuttongroup(app.AddStrainTab);
            app.StraintypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StraintypeButtonGroupSelectionChanged, true);
            app.StraintypeButtonGroup.Title = 'Strain type';
            app.VendorButton = uiradiobutton(app.StraintypeButtonGroup);
            app.VendorButton.Text = 'Vendor';
            app.VendorButton.Value = true;
            app.CollaboratorButton = uiradiobutton(app.StraintypeButtonGroup);
            app.CollaboratorButton.Text = 'Collaborator';
            app.BreedingButton = uiradiobutton(app.StraintypeButtonGroup);
            app.BreedingButton.Text = 'Breeding';
            app.CatalogEditField_2Label_2 = uilabel(app.AddStrainTab);
            app.CatalogEditField_2Label_2.Text = 'Catalog #';
            app.CatalogEditField_Strain = uieditfield(app.AddStrainTab, 'text');
            app.GenotypenameEditFieldLabel_4 = uilabel(app.AddStrainTab);
            app.GenotypenameEditFieldLabel_4.Text = 'Status';
            app.StrainsindatabaseLabel_2 = uilabel(app.AddStrainTab);
            app.StrainsindatabaseLabel_2.Text = 'Inactive strains in database';
            app.InactiveStrainListBox = uilistbox(app.AddStrainTab);
            app.InactiveStrainListBox.Items = {};
            app.InactiveStrainListBox.Value = {};
            app.DeactivateStrainButton = uibutton(app.AddStrainTab, 'push');
            app.DeactivateStrainButton.ButtonPushedFcn = createCallbackFcn(app, @DeactivateStrainButtonPushed, true);
            app.DeactivateStrainButton.Text = 'Deactivate';
            app.ActivateStrainButton = uibutton(app.AddStrainTab, 'push');
            app.ActivateStrainButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateStrainButtonPushed, true);
            app.ActivateStrainButton.Text = 'Activate';
            app.SourceDropDownLabel = uilabel(app.AddStrainTab);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.AddStrainTab);
            app.BydefaultnamewillpopulateautomaticallyfromallelesLabel = uilabel(app.AddStrainTab);
            app.BydefaultnamewillpopulateautomaticallyfromallelesLabel.Text = 'By default, name will populate automatically from alleles';
            app.editButton = uibutton(app.AddStrainTab, 'state');
            app.editButton.ValueChangedFcn = createCallbackFcn(app, @editButtonValueChanged, true);
            app.editButton.Text = 'edit';
            app.MakeanalleleactiveforittoappearhereLabel = uilabel(app.AddStrainTab);
            app.MakeanalleleactiveforittoappearhereLabel.Text = 'Make an allele active for it to appear here';
            app.BackgroundDropDown_2Label_2 = uilabel(app.AddStrainTab);
            app.BackgroundDropDown_2Label_2.Text = 'Background';
            app.BackgroundDropDown_2 = uidropdown(app.AddStrainTab);
            app.AllelesListBoxLabel = uilabel(app.AddStrainTab);
            app.AllelesListBoxLabel.Text = 'Alleles';
            app.AllelesListBox = uilistbox(app.AddStrainTab);
            app.AllelesListBox.ValueChangedFcn = createCallbackFcn(app, @AllelesListBoxValueChanged, true);
            app.CtrlclicktoselectmultipleallelesLabel = uilabel(app.AddStrainTab);
            app.CtrlclicktoselectmultipleallelesLabel.Text = 'Ctrl + click to select multiple alleles';
            app.NOTEputpromotersfirstthenreportersLabel = uilabel(app.AddStrainTab);
            app.NOTEputpromotersfirstthenreportersLabel.Text = 'NOTE: put promoters first, then reporters';
            app.AddLocusAlleleTab = uitab(app.MainTabGroup);
            app.AddLocusAlleleTab.Title = 'Add Locus / Allele';
            app.AllelenameEditField_2Label = uilabel(app.AddLocusAlleleTab);
            app.AllelenameEditField_2Label.Text = 'Allele name';
            app.AllelenameEditField = uieditfield(app.AddLocusAlleleTab, 'text');
            app.DescriptionTextArea_3Label = uilabel(app.AddLocusAlleleTab);
            app.DescriptionTextArea_3Label.Text = 'Description';
            app.DescriptionTextArea_Allele = uitextarea(app.AddLocusAlleleTab);
            app.AddalleleButton_LocusAlleleTab = uibutton(app.AddLocusAlleleTab, 'push');
            app.AddalleleButton_LocusAlleleTab.ButtonPushedFcn = createCallbackFcn(app, @AddalleleButton_LocusAlleleTabPushed, true);
            app.AddalleleButton_LocusAlleleTab.Text = 'Add allele';
            app.ActiveallelesindatabaseListBoxLabel = uilabel(app.AddLocusAlleleTab);
            app.ActiveallelesindatabaseListBoxLabel.Text = 'Active alleles in database';
            app.ActiveallelesindatabaseListBox = uilistbox(app.AddLocusAlleleTab);
            app.ActiveallelesindatabaseListBox.Items = {};
            app.ActiveallelesindatabaseListBox.Value = {};
            app.StatusLampLabel_12 = uilabel(app.AddLocusAlleleTab);
            app.StatusLampLabel_12.Text = 'Status';
            app.StatusLamp_Allele = uilamp(app.AddLocusAlleleTab);
            app.AlleleInsertStatusEdit = uieditfield(app.AddLocusAlleleTab, 'text');
            app.AlleleInsertStatusEdit.Editable = 'off';
            app.LociindatabaseListBoxLabel = uilabel(app.AddLocusAlleleTab);
            app.LociindatabaseListBoxLabel.Text = 'Loci in database';
            app.LociindatabaseListBox = uilistbox(app.AddLocusAlleleTab);
            app.LociindatabaseListBox.Items = {};
            app.LociindatabaseListBox.Value = {};
            app.AddgenelocusButton = uibutton(app.AddLocusAlleleTab, 'push');
            app.AddgenelocusButton.ButtonPushedFcn = createCallbackFcn(app, @AddgenelocusButtonPushed, true);
            app.AddgenelocusButton.Text = 'Add gene locus';
            app.LocusInsertStatusEdit = uieditfield(app.AddLocusAlleleTab, 'text');
            app.LocusInsertStatusEdit.Editable = 'off';
            app.LocusnameEditField_2Label = uilabel(app.AddLocusAlleleTab);
            app.LocusnameEditField_2Label.Text = 'Locus name';
            app.LocusnameEditField = uieditfield(app.AddLocusAlleleTab, 'text');
            app.DescriptionTextArea_4Label = uilabel(app.AddLocusAlleleTab);
            app.DescriptionTextArea_4Label.Text = 'Description';
            app.DescriptionTextArea_Locus = uitextarea(app.AddLocusAlleleTab);
            app.StatusLampLabel_13 = uilabel(app.AddLocusAlleleTab);
            app.StatusLampLabel_13.Text = 'Status';
            app.StatusLamp_Locus = uilamp(app.AddLocusAlleleTab);
            app.ChromosomeEditField_2Label = uilabel(app.AddLocusAlleleTab);
            app.ChromosomeEditField_2Label.Text = 'Chromosome';
            app.ChromosomeEditField = uieditfield(app.AddLocusAlleleTab, 'text');
            app.PositionEditField_2Label = uilabel(app.AddLocusAlleleTab);
            app.PositionEditField_2Label.Text = 'Position';
            app.PositionEditField = uieditfield(app.AddLocusAlleleTab, 'text');
            app.AlleleLocusTable = uitable(app.AddLocusAlleleTab);
            app.AlleleLocusTable.ColumnName = {'Allele'; 'Locus'};
            app.AlleleLocusTable.RowName = {};
            app.AlleleLocusTable.ColumnSortable = true;
            app.AlleleLocusMapLabel = uilabel(app.AddLocusAlleleTab);
            app.AlleleLocusMapLabel.Text = 'Allele: Locus Map';
            app.LocusDropDown_6Label = uilabel(app.AddLocusAlleleTab);
            app.LocusDropDown_6Label.Text = 'Locus';
            app.LocusDropDown_forMap = uidropdown(app.AddLocusAlleleTab);
            app.AlleleDropDownLabel = uilabel(app.AddLocusAlleleTab);
            app.AlleleDropDownLabel.Text = 'Allele';
            app.AlleleDropDown_forMap = uidropdown(app.AddLocusAlleleTab);
            app.AddtomapButton = uibutton(app.AddLocusAlleleTab, 'push');
            app.AddtomapButton.ButtonPushedFcn = createCallbackFcn(app, @AddtomapButtonPushed, true);
            app.AddtomapButton.Text = 'Add to map';
            app.LocusAlleleMapInsertStatusEdit = uieditfield(app.AddLocusAlleleTab, 'text');
            app.LocusAlleleMapInsertStatusEdit.Editable = 'off';
            app.StatusLampLabel_14 = uilabel(app.AddLocusAlleleTab);
            app.StatusLampLabel_14.Text = 'Status';
            app.StatusLamp_LocusAlleleMap = uilamp(app.AddLocusAlleleTab);
            app.InactiveallelesindatabaseListBoxLabel = uilabel(app.AddLocusAlleleTab);
            app.InactiveallelesindatabaseListBoxLabel.Text = 'Inactive alleles in database';
            app.InactiveallelesindatabaseListBox = uilistbox(app.AddLocusAlleleTab);
            app.InactiveallelesindatabaseListBox.Items = {};
            app.InactiveallelesindatabaseListBox.Value = {};
            app.DeactivateAlleleButton = uibutton(app.AddLocusAlleleTab, 'push');
            app.DeactivateAlleleButton.ButtonPushedFcn = createCallbackFcn(app, @DeactivateAlleleButtonPushed, true);
            app.DeactivateAlleleButton.Text = 'Deactivate';
            app.ActivateAlleleButton = uibutton(app.AddLocusAlleleTab, 'push');
            app.ActivateAlleleButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateAlleleButtonPushed, true);
            app.ActivateAlleleButton.Text = 'Activate';
            app.cMLabel = uilabel(app.AddLocusAlleleTab);
            app.cMLabel.Text = 'cM';
            app.AddSubstanceTab = uitab(app.MainTabGroup);
            app.AddSubstanceTab.Title = 'Add Substance';
            app.SubstancenameEditFieldLabel = uilabel(app.AddSubstanceTab);
            app.SubstancenameEditFieldLabel.Text = 'Substance name';
            app.SubstancenameEditField = uieditfield(app.AddSubstanceTab, 'text');
            app.DescriptionTextArea_2Label = uilabel(app.AddSubstanceTab);
            app.DescriptionTextArea_2Label.Text = 'Description';
            app.DescriptionTextArea_substance = uitextarea(app.AddSubstanceTab);
            app.CatalogEditField_2Label = uilabel(app.AddSubstanceTab);
            app.CatalogEditField_2Label.Text = 'Catalog #';
            app.CatalogEditField_substance = uieditfield(app.AddSubstanceTab, 'text');
            app.StoragelocationLabel = uilabel(app.AddSubstanceTab);
            app.StoragelocationLabel.Text = 'Storage location';
            app.StorageLocationTextArea_substance = uitextarea(app.AddSubstanceTab);
            app.AddsubstancetodatabaseButton = uibutton(app.AddSubstanceTab, 'push');
            app.AddsubstancetodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddsubstancetodatabaseButtonPushed, true);
            app.AddsubstancetodatabaseButton.Text = 'Add substance to database';
            app.ActivesubstancesindatabaseListBoxLabel = uilabel(app.AddSubstanceTab);
            app.ActivesubstancesindatabaseListBoxLabel.Text = 'Active substances in database';
            app.ActivesubstancesindatabaseListBox = uilistbox(app.AddSubstanceTab);
            app.ActivesubstancesindatabaseListBox.Items = {};
            app.ActivesubstancesindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @ActivesubstancesindatabaseListBoxValueChanged, true);
            app.ActivesubstancesindatabaseListBox.Value = {};
            app.StatusLampLabel_4 = uilabel(app.AddSubstanceTab);
            app.StatusLampLabel_4.Text = 'Status';
            app.StatusLamp_Substance = uilamp(app.AddSubstanceTab);
            app.SubstanceInsertStatusEdit = uieditfield(app.AddSubstanceTab, 'text');
            app.SubstanceInsertStatusEdit.Editable = 'off';
            app.InactiveSubstancesindatabaseListBox = uilistbox(app.AddSubstanceTab);
            app.InactiveSubstancesindatabaseListBox.Items = {};
            app.InactiveSubstancesindatabaseListBox.Value = {};
            app.ActivesubstancesindatabaseListBoxLabel_2 = uilabel(app.AddSubstanceTab);
            app.ActivesubstancesindatabaseListBoxLabel_2.Text = 'Inactive substances in database';
            app.DeactivateSubstanceButton = uibutton(app.AddSubstanceTab, 'push');
            app.DeactivateSubstanceButton.ButtonPushedFcn = createCallbackFcn(app, @DeactivateSubstanceButtonPushed, true);
            app.DeactivateSubstanceButton.Text = 'Deactivate';
            app.ActivateSubstanceButton = uibutton(app.AddSubstanceTab, 'push');
            app.ActivateSubstanceButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateSubstanceButtonPushed, true);
            app.ActivateSubstanceButton.Text = 'Activate';
            app.SourceDropDown_4Label = uilabel(app.AddSubstanceTab);
            app.SourceDropDown_4Label.Text = 'Source';
            app.SourceDropDown_substance = uidropdown(app.AddSubstanceTab);
            app.VendorOrCollaborator_ButtonGroup = uibuttongroup(app.AddSubstanceTab);
            app.VendorOrCollaborator_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VendorOrCollaborator_ButtonGroupSelectionChanged, true);
            app.VendorButton_substance = uiradiobutton(app.VendorOrCollaborator_ButtonGroup);
            app.VendorButton_substance.Text = 'Vendor';
            app.VendorButton_substance.Value = true;
            app.CollaboratorButton_substance = uiradiobutton(app.VendorOrCollaborator_ButtonGroup);
            app.CollaboratorButton_substance.Text = 'Collaborator';
            app.AddBrainAreaTab = uitab(app.MainTabGroup);
            app.AddBrainAreaTab.Title = 'Add Brain Area';
            app.AbbreviationEditFieldLabel = uilabel(app.AddBrainAreaTab);
            app.AbbreviationEditFieldLabel.Text = 'Abbreviation';
            app.AbbreviationEditField = uieditfield(app.AddBrainAreaTab, 'text');
            app.LongnameLabel = uilabel(app.AddBrainAreaTab);
            app.LongnameLabel.Text = 'Long name';
            app.BrainLongNameTextArea = uitextarea(app.AddBrainAreaTab);
            app.BrainareasindatabaseListBoxLabel = uilabel(app.AddBrainAreaTab);
            app.BrainareasindatabaseListBoxLabel.Text = 'Brain areas in database';
            app.BrainareasindatabaseListBox = uilistbox(app.AddBrainAreaTab);
            app.BrainareasindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @BrainareasindatabaseListBoxValueChanged, true);
            app.AddbrainareatodatabaseButton = uibutton(app.AddBrainAreaTab, 'push');
            app.AddbrainareatodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddbrainareatodatabaseButtonPushed, true);
            app.AddbrainareatodatabaseButton.Text = 'Add brain area to database';
            app.StatusLampLabel_5 = uilabel(app.AddBrainAreaTab);
            app.StatusLampLabel_5.Text = 'Status';
            app.StatusLamp_BrainArea = uilamp(app.AddBrainAreaTab);
            app.BrainAreaInsertStatusEdit = uieditfield(app.AddBrainAreaTab, 'text');
            app.BrainAreaInsertStatusEdit.Editable = 'off';
            app.AddProjectTab = uitab(app.MainTabGroup);
            app.AddProjectTab.Title = 'Add Project';
            app.AddprojecttodatabaseButton = uibutton(app.AddProjectTab, 'push');
            app.AddprojecttodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddprojecttodatabaseButtonPushed, true);
            app.AddprojecttodatabaseButton.Text = 'Add project to database';
            app.ProjectInsertStatusEdit = uieditfield(app.AddProjectTab, 'text');
            app.ProjectInsertStatusEdit.Editable = 'off';
            app.NameEditFieldLabel = uilabel(app.AddProjectTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.ProjectNameEditField = uieditfield(app.AddProjectTab, 'text');
            app.DescriptionLabel = uilabel(app.AddProjectTab);
            app.DescriptionLabel.Text = 'Description';
            app.ProjectDescriptionTextArea = uitextarea(app.AddProjectTab);
            app.ActiveprojectsindatabaseListBoxLabel = uilabel(app.AddProjectTab);
            app.ActiveprojectsindatabaseListBoxLabel.Text = 'Active projects in database';
            app.ActiveprojectsindatabaseListBox = uilistbox(app.AddProjectTab);
            app.ActiveprojectsindatabaseListBox.Items = {};
            app.ActiveprojectsindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @ActiveprojectsindatabaseListBoxValueChanged, true);
            app.ActiveprojectsindatabaseListBox.Value = {};
            app.StatusLampLabel_7 = uilabel(app.AddProjectTab);
            app.StatusLampLabel_7.Text = 'Status';
            app.StatusLamp_Project = uilamp(app.AddProjectTab);
            app.InactiveprojectsindatabaseListBoxLabel = uilabel(app.AddProjectTab);
            app.InactiveprojectsindatabaseListBoxLabel.Text = 'Inactive projects in database';
            app.InactiveprojectsindatabaseListBox = uilistbox(app.AddProjectTab);
            app.InactiveprojectsindatabaseListBox.Items = {};
            app.InactiveprojectsindatabaseListBox.Value = {};
            app.DeactivateProjectButton = uibutton(app.AddProjectTab, 'push');
            app.DeactivateProjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeactivateProjectButtonPushed, true);
            app.DeactivateProjectButton.Text = 'Deactivate';
            app.ActivateProjectButton = uibutton(app.AddProjectTab, 'push');
            app.ActivateProjectButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateProjectButtonPushed, true);
            app.ActivateProjectButton.Text = 'Activate';
            app.AddSourceTab = uitab(app.MainTabGroup);
            app.AddSourceTab.Title = 'Add Source';
            app.TODOsources149arevendors5199aregenotyping101Label = uilabel(app.AddSourceTab);
            app.TODOsources149arevendors5199aregenotyping101Label.Text = 'Sources 1-89 are vendors; 90-99 are genotyping; 100- 999 are collaborators; 1000+ are breeders';
            app.SourcetypeButtonGroup = uibuttongroup(app.AddSourceTab);
            app.SourcetypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SourcetypeButtonGroupSelectionChanged, true);
            app.SourcetypeButtonGroup.Title = 'Source type';
            app.VendorButton_2 = uiradiobutton(app.SourcetypeButtonGroup);
            app.VendorButton_2.Text = 'Vendor';
            app.VendorButton_2.Value = true;
            app.GenotypeSourceButton = uiradiobutton(app.SourcetypeButtonGroup);
            app.GenotypeSourceButton.Text = 'GenotypeSource';
            app.CollaboratorButton_2 = uiradiobutton(app.SourcetypeButtonGroup);
            app.CollaboratorButton_2.Text = 'Collaborator';
            app.ActiveListBoxLabel = uilabel(app.AddSourceTab);
            app.ActiveListBoxLabel.Text = 'Active';
            app.ActiveSourceListBox = uilistbox(app.AddSourceTab);
            app.InactiveListBoxLabel = uilabel(app.AddSourceTab);
            app.InactiveListBoxLabel.Text = 'Inactive';
            app.InactiveSourceListBox = uilistbox(app.AddSourceTab);
            app.DeactivateButton = uibutton(app.AddSourceTab, 'push');
            app.DeactivateButton.ButtonPushedFcn = createCallbackFcn(app, @DeactivateButtonPushed, true);
            app.DeactivateButton.Text = 'Deactivate';
            app.ActivateButton = uibutton(app.AddSourceTab, 'push');
            app.ActivateButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateButtonPushed, true);
            app.ActivateButton.Text = 'Activate';
            app.SourceEditField_1 = uieditfield(app.AddSourceTab, 'text');
            app.SourceEditField_2 = uieditfield(app.AddSourceTab, 'text');
            app.SourceField_1_label = uilabel(app.AddSourceTab);
            app.SourceField_2_label = uilabel(app.AddSourceTab);
            app.AddsourceButton = uibutton(app.AddSourceTab, 'push');
            app.AddsourceButton.ButtonPushedFcn = createCallbackFcn(app, @AddsourceButtonPushed, true);
            app.AddsourceButton.Text = 'Add source';
            app.SourceStatusLamp = uilamp(app.AddSourceTab);
            app.SourceStatusEditField = uieditfield(app.AddSourceTab, 'text');
            app.AddBehaviorExperimentTypeStimulusTab = uitab(app.MainTabGroup);
            app.AddBehaviorExperimentTypeStimulusTab.Title = 'Add Behavior Experiment Type / Stimulus';
            app.AddexperimenttypetodatabaseButton = uibutton(app.AddBehaviorExperimentTypeStimulusTab, 'push');

            app.AddexperimenttypetodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddexperimenttypetodatabaseButtonPushed, true);
            app.AddexperimenttypetodatabaseButton.Text = 'Add experiment type to database';
            app.BehaviorExpInsertStatusEdit = uieditfield(app.AddBehaviorExperimentTypeStimulusTab, 'text');
            app.BehaviorExpInsertStatusEdit.Editable = 'off';
            app.NameEditFieldLabel_2 = uilabel(app.AddBehaviorExperimentTypeStimulusTab);
            app.NameEditFieldLabel_2.Text = 'Name';
            app.BehaviorExpNameEditField = uieditfield(app.AddBehaviorExperimentTypeStimulusTab, 'text');
            app.DescriptionLabel_2 = uilabel(app.AddBehaviorExperimentTypeStimulusTab);
            app.DescriptionLabel_2.Text = 'Description';
            app.BehaviorExpDescriptionTextArea = uitextarea(app.AddBehaviorExperimentTypeStimulusTab);
            app.BehaviorexperimenttypesindatabaseListBoxLabel = uilabel(app.AddBehaviorExperimentTypeStimulusTab);
            app.BehaviorexperimenttypesindatabaseListBoxLabel.Text = 'Behavior experiment types in database';
            app.BehaviorexperimenttypesindatabaseListBox = uilistbox(app.AddBehaviorExperimentTypeStimulusTab);
            app.BehaviorexperimenttypesindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @BehaviorexperimenttypesindatabaseListBoxValueChanged, true);
            app.StatusLampLabel_8 = uilabel(app.AddBehaviorExperimentTypeStimulusTab);
            app.StatusLampLabel_8.Text = 'Status';
            app.StatusLamp_BehaviorExp = uilamp(app.AddBehaviorExperimentTypeStimulusTab);
            app.VisualstimuliindatabaseListBoxLabel = uilabel(app.AddBehaviorExperimentTypeStimulusTab);
            app.VisualstimuliindatabaseListBoxLabel.Text = 'Visual stimuli in database';
            app.VisualstimuliindatabaseListBox = uilistbox(app.AddBehaviorExperimentTypeStimulusTab);
            app.VisualstimuliindatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @VisualstimuliindatabaseListBoxValueChanged, true);
            app.StatusLampLabel_15 = uilabel(app.AddBehaviorExperimentTypeStimulusTab);
            app.StatusLampLabel_15.Text = 'Status';
            app.StatusLamp_BehaviorExpStim = uilamp(app.AddBehaviorExperimentTypeStimulusTab);
            app.BehaviorExpInsertStimStatusEdit = uieditfield(app.AddBehaviorExperimentTypeStimulusTab, 'text');
            app.BehaviorExpInsertStimStatusEdit.Editable = 'off';
            app.AddvisualstimtypetodatabaseButton = uibutton(app.AddBehaviorExperimentTypeStimulusTab, 'push');
            app.AddvisualstimtypetodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddvisualstimtypetodatabaseButtonPushed, true);
            app.AddvisualstimtypetodatabaseButton.Text = 'Add visual stim type to database';
            app.NameEditFieldLabel_4 = uilabel(app.AddBehaviorExperimentTypeStimulusTab);
            app.NameEditFieldLabel_4.Text = 'Name';
            app.BehaviorExpStimNameEditField = uieditfield(app.AddBehaviorExperimentTypeStimulusTab, 'text');
            app.DescriptionLabel_3 = uilabel(app.AddBehaviorExperimentTypeStimulusTab);
            app.DescriptionLabel_3.Text = 'Description';
            app.BehaviorExpStimDescriptionTextArea = uitextarea(app.AddBehaviorExperimentTypeStimulusTab);
            app.NeedsanimalIDSwitchLabel = uilabel(app.AddBehaviorExperimentTypeStimulusTab);
            app.NeedsanimalIDSwitchLabel.Text = 'Needs animal ID';
            app.NeedsanimalIDSwitch = uiswitch(app.AddBehaviorExperimentTypeStimulusTab, 'slider');
            app.NeedsanimalIDSwitch.Items = {'No', 'Yes'};
            app.NeedsanimalIDSwitch.Value = 'No';
            app.LogTab = uitab(app.MainTabGroup);
            app.LogTab.Title = 'Log';
            app.LogTextArea = uitextarea(app.LogTab);
            app.LogTextArea.Editable = 'off';
            app.ExporttotextfileButton = uibutton(app.LogTab, 'push');
            app.ExporttotextfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttotextfileButtonPushed, true);
            app.ExporttotextfileButton.Text = 'Export to text file';
            app.LookupAnimalTab = uitab(app.MainTabGroup);
            app.LookupAnimalTab.Title = 'Lookup Animal';
            app.DJIDEditFieldLabel_4 = uilabel(app.LookupAnimalTab);
            app.DJIDEditFieldLabel_4.Text = 'DJID';
            app.DJIDEditField_lookup = uieditfield(app.LookupAnimalTab, 'text');
            app.SearchButton_lookup = uibutton(app.LookupAnimalTab, 'push');
            app.SearchButton_lookup.ButtonPushedFcn = createCallbackFcn(app, @SearchButton_lookupPushed, true);
            app.SearchButton_lookup.Text = 'Search';
            app.EventlistTextAreaLabel = uilabel(app.LookupAnimalTab);
            app.EventlistTextAreaLabel.Text = 'Event list';
            app.EventlistTextArea = uitextarea(app.LookupAnimalTab);
            app.TODOListTab = uitab(app.MainTabGroup);
            app.TODOListTab.Title = 'TODO List';
            app.QueriesListBoxLabel = uilabel(app.TODOListTab);
            app.QueriesListBoxLabel.Text = 'Queries';
            app.QueriesListBox = uilistbox(app.TODOListTab);
            app.QueriesListBox.Items = {};
            app.QueriesListBox.ValueChangedFcn = createCallbackFcn(app, @QueriesListBoxValueChanged, true);
            app.QueriesListBox.Value = {};
            app.QuerytextTextAreaLabel = uilabel(app.TODOListTab);
            app.QuerytextTextAreaLabel.Text = 'Query text';
            app.QuerytextTextArea = uitextarea(app.TODOListTab);
            app.NameEditFieldLabel_3 = uilabel(app.TODOListTab);
            app.NameEditFieldLabel_3.Text = 'Name';
            app.QuerynameEditField = uieditfield(app.TODOListTab, 'text');
            app.SubmitnewqueryButton = uibutton(app.TODOListTab, 'push');
            app.SubmitnewqueryButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitnewqueryButtonPushed, true);
            app.SubmitnewqueryButton.Text = 'Submit new query';
            app.QueryResultTable = uitable(app.TODOListTab);
            app.QueryResultTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.QueryResultTable.RowName = {};
            app.QuerynameLabel = uilabel(app.TODOListTab);
            app.QuerynameLabel.Text = 'Query name';
            app.StatusLampLabel_10 = uilabel(app.TODOListTab);
            app.StatusLampLabel_10.Text = 'Status';
            app.StatusLamp_TODOquery = uilamp(app.TODOListTab);
            app.EditField_TODOquery = uieditfield(app.TODOListTab, 'text');
            app.EditField_TODOquery.Editable = 'off';
            app.ExporttoExcelButton_2 = uibutton(app.TODOListTab, 'push');
            app.ExporttoExcelButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExporttoExcelButton_2Pushed, true);
            app.ExporttoExcelButton_2.Text = 'Export to Excel';
            app.CagelookupTab = uitab(app.MainTabGroup);
            app.CagelookupTab.Title = 'Cage lookup';
            app.CagenumberEditFieldLabel_2 = uilabel(app.CagelookupTab);
            app.CagenumberEditFieldLabel_2.Text = 'Cage number';
            app.CagenumberEditField = uieditfield(app.CagelookupTab, 'numeric');
            app.CagenumberEditField.ValueDisplayFormat = '%.0f';
            app.FindcageButton = uibutton(app.CagelookupTab, 'push');
            app.FindcageButton.ButtonPushedFcn = createCallbackFcn(app, @FindcageButtonPushed, true);
            app.FindcageButton.Text = 'Find cage';
            app.DJIDsListBoxLabel = uilabel(app.CagelookupTab);
            app.DJIDsListBoxLabel.Text = 'DJIDs';
            app.DJIDsListBox = uilistbox(app.CagelookupTab);
            app.CageLabel = uilabel(app.CagelookupTab);
            app.CageLabel.Text = 'Cage';
            % Show the figure after all components are created
            app.AnimalInterfaceV2UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnimalInterfaceV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnimalInterfaceV2UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnimalInterfaceV2UIFigure)
        end
    end
end