classdef AnimalLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnimalLoggerUIFigure           matlab.ui.Figure
        AnimalTable                    matlab.ui.control.Table
        AnimalSelectionPanel           matlab.ui.container.Panel
        SearchButton                   matlab.ui.control.Button
        DJIDEditField                  matlab.ui.control.NumericEditField
        FilterPanel                    matlab.ui.container.Panel
        GenotypeDropDownLabel          matlab.ui.control.Label
        Filter_GenotypeDropDown        matlab.ui.control.DropDown
        StatusDropDownLabel            matlab.ui.control.Label
        Filter_GenotypeStatusDropDown  matlab.ui.control.DropDown
        EyeInjSwitchLabel              matlab.ui.control.Label
        EyeInjSwitch                   matlab.ui.control.ToggleSwitch
        BehaviorexpSwitchLabel         matlab.ui.control.Label
        BehaviorexpSwitch              matlab.ui.control.ToggleSwitch
        SexDropDownLabel               matlab.ui.control.Label
        Filter_SexDropDown             matlab.ui.control.DropDown
        BraininjSwitchLabel            matlab.ui.control.Label
        BraininjSwitch                 matlab.ui.control.ToggleSwitch
        ResetButton                    matlab.ui.control.Button
        ID_type_switch                 matlab.ui.control.Switch
        TagIDEditField                 matlab.ui.control.NumericEditField
        AgerangeweeksPanel             matlab.ui.container.Panel
        ageLow_edit                    matlab.ui.control.NumericEditField
        ageHigh_edit                   matlab.ui.control.NumericEditField
        toLabel                        matlab.ui.control.Label
        AddentriesPanel                matlab.ui.container.Panel
        AddanimalButton                matlab.ui.control.Button
        AddgenotypeButton              matlab.ui.control.Button
        AddsubstanceButton             matlab.ui.control.Button
        AddbrainareaButton             matlab.ui.control.Button
        EventTabGroup                  matlab.ui.container.TabGroup
        GenotypedTab                   matlab.ui.container.Tab
        ResultsDropDownLabel           matlab.ui.control.Label
        GenotypeResultsDropDown        matlab.ui.control.DropDown
        NotesTextAreaLabel             matlab.ui.control.Label
        GenotypedNotesTextArea         matlab.ui.control.TextArea
        DeceasedTab                    matlab.ui.container.Tab
        ReasonDropDownLabel            matlab.ui.control.Label
        DeceasedReasonDropDown         matlab.ui.control.DropDown
        NotesTextArea_2Label           matlab.ui.control.Label
        DeceasedNotesTextArea          matlab.ui.control.TextArea
        GavebirthTab                   matlab.ui.container.Tab
        NotesTextArea_3Label           matlab.ui.control.Label
        BirthTextArea                  matlab.ui.control.TextArea
        NumberofpupsSpinnerLabel       matlab.ui.control.Label
        NumberofpupsSpinner            matlab.ui.control.Spinner
        AssigncageTab                  matlab.ui.container.Tab
        ReasonDropDown_2Label          matlab.ui.control.Label
        MoveReasonDropDown             matlab.ui.control.DropDown
        NotesTextArea_4Label           matlab.ui.control.Label
        MoveCageTextArea               matlab.ui.control.TextArea
        TocageEditFieldLabel           matlab.ui.control.Label
        TocageEditField                matlab.ui.control.NumericEditField
        IPinjectionTab                 matlab.ui.container.Tab
        NotesTextArea_5Label           matlab.ui.control.Label
        IP_inj_NotesTextArea           matlab.ui.control.TextArea
        SubstanceDropDownLabel         matlab.ui.control.Label
        SubstanceDropDown_IP           matlab.ui.control.DropDown
        ConcentrationmgKgEditFieldLabel  matlab.ui.control.Label
        IP_inj_ConcentrationEditField  matlab.ui.control.NumericEditField
        IP_timeH                       matlab.ui.control.Spinner
        IP_time_ampm                   matlab.ui.control.DropDown
        IP_timeM                       matlab.ui.control.DropDown
        TimeLabel_5                    matlab.ui.control.Label
        EyeinjectionTab                matlab.ui.container.Tab
        SubstanceDropDownLabel_2       matlab.ui.control.Label
        SubstanceDropDown_Eye          matlab.ui.control.DropDown
        NotesTextArea_7Label           matlab.ui.control.Label
        Eye_inj_NotesTextArea          matlab.ui.control.TextArea
        EyeDropDownLabel               matlab.ui.control.Label
        WhichEyeDropDown               matlab.ui.control.DropDown
        Dilution1XEditFieldLabel       matlab.ui.control.Label
        Dilution_EyeEditField          matlab.ui.control.NumericEditField
        Eye_timeH                      matlab.ui.control.Spinner
        Eye_time_ampm                  matlab.ui.control.DropDown
        Eye_timeM                      matlab.ui.control.DropDown
        TimeLabel_4                    matlab.ui.control.Label
        BraininjectionTab              matlab.ui.container.Tab
        SubstanceDropDown_3Label       matlab.ui.control.Label
        SubstanceDropDown_Brain        matlab.ui.control.DropDown
        Dilution1XEditField_2Label     matlab.ui.control.Label
        Dilution_BrainEditField        matlab.ui.control.NumericEditField
        NotesTextArea_8Label           matlab.ui.control.Label
        Brain_inj_NotesTextArea        matlab.ui.control.TextArea
        CoordinatesLabel               matlab.ui.control.Label
        TargetDropDownLabel            matlab.ui.control.Label
        BrainTargetDropDown            matlab.ui.control.DropDown
        HemisphereDropDownLabel        matlab.ui.control.Label
        HemisphereDropDown             matlab.ui.control.DropDown
        APEditFieldLabel               matlab.ui.control.Label
        APEditField                    matlab.ui.control.NumericEditField
        MLEditFieldLabel               matlab.ui.control.Label
        MLEditField                    matlab.ui.control.NumericEditField
        DVEditFieldLabel               matlab.ui.control.Label
        DVEditField                    matlab.ui.control.NumericEditField
        AngleEditFieldLabel            matlab.ui.control.Label
        AngleEditField                 matlab.ui.control.NumericEditField
        Brain_timeH                    matlab.ui.control.Spinner
        Brain_time_ampm                matlab.ui.control.DropDown
        Brain_timeM                    matlab.ui.control.DropDown
        TimeLabel_3                    matlab.ui.control.Label
        HandledTab                     matlab.ui.container.Tab
        NotesTextArea_6Label           matlab.ui.control.Label
        Handled_NotesTextArea          matlab.ui.control.TextArea
        DurationminutesEditFieldLabel  matlab.ui.control.Label
        HandlingDurationEditField      matlab.ui.control.NumericEditField
        handled_timeH                  matlab.ui.control.Spinner
        handled_time_ampm              matlab.ui.control.DropDown
        handled_timeM                  matlab.ui.control.DropDown
        StarttimeLabel_4               matlab.ui.control.Label
        SocialbehaviorsessionTab       matlab.ui.container.Tab
        NotesTextArea_9Label           matlab.ui.control.Label
        BehaviorSession_NotesTextArea  matlab.ui.control.TextArea
        DurationminsEditFieldLabel     matlab.ui.control.Label
        SocialB_DurationminsEditField  matlab.ui.control.NumericEditField
        RootfilenameEditFieldLabel     matlab.ui.control.Label
        SocialB_filenameEditField      matlab.ui.control.EditField
        RecordBehavior_switch          matlab.ui.control.RockerSwitch
        AEditFieldLabel                matlab.ui.control.Label
        AnimalAEditField               matlab.ui.control.NumericEditField
        StimulusanimalIDs0foremptyLabel  matlab.ui.control.Label
        BEditFieldLabel                matlab.ui.control.Label
        AnimalBEditField               matlab.ui.control.NumericEditField
        CEditFieldLabel                matlab.ui.control.Label
        AnimalCEditField               matlab.ui.control.NumericEditField
        Lamp_isRecorded                matlab.ui.control.Lamp
        SocialB_timeH                  matlab.ui.control.Spinner
        SocialB_time_ampm              matlab.ui.control.DropDown
        SocialB_timeM                  matlab.ui.control.DropDown
        StarttimeLabel_3               matlab.ui.control.Label
        ExperimenttypeDropDownLabel    matlab.ui.control.Label
        ExperimenttypeDropDown         matlab.ui.control.DropDown
        EventdateDatePickerLabel       matlab.ui.control.Label
        EventdateDatePicker            matlab.ui.control.DatePicker
        SubmitevententryButton         matlab.ui.control.Button
        PersonDropDownLabel            matlab.ui.control.Label
        PersonDropDown                 matlab.ui.control.DropDown
        VieweventlogButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        ax_ageslider % axis for age slider
        l_ageslider  % line for age slider
        selected_animal_id % animal_id for current selection
        filter_struct = []; % struct as query object from filter panel
        filter_genotype_status = 'All'; % genotype_status to filtere
        filter_check_eye_inj = false; % booleon about whether to include only injected 
        filter_check_brain_inj = false; % booleon about whether to include only injected    
        filter_check_beh = false; % booleon about whether to include only behavior animals
        filter_ageLow = 0; % lower age limit
        filter_ageHigh = Inf; % upper age limit
        login_user; % User who logged in
    end
    
    methods (Access = private)
        % Function that responds to the movement of the sliders
        function lMoving(app, source)
            % Always keep the position rounded to 1 decimal place
            source.Position(1,1) = round(source.Position(1,1),1);
            source.Position(2,1) = round(source.Position(2,1),1);
            % make sure y stays at 0
            source.Position(1,2) = 0;
            source.Position(2,2) = 0;
            %insert numbers into edit boxes
            app.ageLow_edit.Value = source.Position(1,1);
            app.filter_ageLow = app.ageLow_edit.Value;
            app.ageHigh_edit.Value = source.Position(2,1);
            app.filter_ageHigh = app.ageHigh_edit.Value;
            % Add any other action here triggered by the movement of the sliders
            app.loadAnimalTable([]);
        end
        
    end
    
    
    methods (Access = public)
        
        function loadAnimalTable(app, filter_query)
            % change title to table loading... 
            set(app.AnimalLoggerUIFigure, 'Name', 'Table loading...');
            set(app.AnimalSelectionPanel, 'Title', 'Table loading...');
            %and make table invisible
            set(app.AnimalTable,'visible','off');
                        
            if ~isempty(filter_query) %run specified query instead of stored filter
                allAnimals = fetch(sl.AnimalEventDeceased.living() & filter_query,'*');
            else
                % make age restriction
                max_dob = char(datetime(datetime('now') - 7*app.filter_ageLow,'format','yyyy-MM-dd'));
                if app.filter_ageHigh >= 25 %25 weeks considered inf
                    min_dob = '1900-01-01'; %really old mouse!
                else
                    min_dob = char(datetime(datetime('now') - 7*app.filter_ageHigh,'format','yyyy-MM-dd'));
                end
                ageQuery = {['dob >= ' '"' min_dob '"'], ['dob <= ' '"' max_dob '"']};
                allAnimals = fetch(sl.AnimalEventDeceased.living() ...
                    & app.filter_struct & ageQuery,'*');
            end
            if isempty(allAnimals)
                D = table;
            else
                animal_ids = [allAnimals.animal_id]';
                nAnimals = length(animal_ids);
                cage_numbers = sl.Animal.cageNumber(animal_ids);
                genotype_status = sl.Animal.genotypeStatus(animal_ids);
                hasBehavior = sl.Animal.hasEvent('SocialBehaviorSession',animal_ids);
                hasBrainInjection = sl.Animal.hasEvent('BrainInjection',animal_ids);
                hasEyeInjection = sl.Animal.hasEvent('EyeInjection',animal_ids);
                hasIPinjection = sl.Animal.hasEvent('IPinjection',animal_ids);
                age = sl.Animal.age(animal_ids);
                T = struct2table(allAnimals);
                if isempty(cage_numbers)
                    T.cage_number = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(cage_numbers),'MergeKeys',true,'type','Left');
                end
                if isempty(genotype_status)
                    T.genotype_status = nan(nAnimals,1);
                else
                    T = outerjoin(T, struct2table(genotype_status,'AsArray',true),'MergeKeys',true,'type','Left');
                end
                T = outerjoin(T, struct2table(age),'MergeKeys',true,'type','Left'); %every mouse has an age
                T.hasBehavior = hasBehavior;
                T.hasBrainInjection = hasBrainInjection;
                T.hasEyeInjection = hasEyeInjection;
                T.hasIPinjection = hasIPinjection;
                N = height(T);                
                D = table('Size',[N,11],'VariableTypes', ...
                    {'uint32',...   #1 DJ ID
                    'uint32', ...   #2 tag ID
                    'uint32',...    #3 cage number
                    'string',...    #4 genotype
                    'string',...    #5 genotype status
                    'logical',...   #6 IP inj
                    'string',...    #7 sex
                    'singlenan',... #8 age
                    'logical',...   #9 eye inj
                    'logical',...   #10 brain inj
                    'logical', ...  #11 behavior
                    },...
                    'VariableNames', ...
                    {'DJ_ID',...
                    'Tag_ID', ...
                    'Cage_number',...
                    'Genotype'...
                    'Genotype_status',...
                    'IP_inj',...
                    'Sex',...
                    'Age',...
                    'Eye_inj',...
                    'Brain_inj',...
                    'BehaviorExp',...
                    });
                D.DJ_ID = T.animal_id;
                D.Tag_ID = T.tag_id;
                D.Cage_number = T.cage_number;
                D.Genotype = T.genotype_name;
                D.Genotype_status = T.genotype_status;
                D.IP_inj = T.hasIPinjection;
                D.Sex = T.sex;
                D.Age = T.age;
                D.Eye_inj = T.hasEyeInjection;
                D.Brain_inj = T.hasBrainInjection;
                D.BehaviorExp = T.hasBehavior;
                %filter parts that have to be done after table construction
                if app.filter_check_beh
                    ind = D.BehaviorExp;
                    D = D(ind,:);
                end
                if app.filter_check_eye_inj
                    ind = D.Eye_inj;
                    D = D(ind,:);
                end
                if app.filter_check_brain_inj
                    ind = D.Brain_inj;
                    D = D(ind,:);
                end
                if ~strcmp(app.filter_genotype_status,'All')
                    ind = strcmp(D.Genotype_status, app.filter_genotype_status);
                    D = D(ind,:);
                end
            end
            N = height(D);
            if N>0
                set(app.AnimalSelectionPanel, 'Title', ...
                    ['Animal Selection: ' num2str(N) ' total']);
            else
                set(app.AnimalSelectionPanel, 'Title', ...
                    'Animal Selection: none found');
            end
            set(app.AnimalTable,'Data',D);
            defaultStyle = uistyle('FontColor','black','FontWeight','normal');
            addStyle(app.AnimalTable,defaultStyle);
            
            %make visible again
            set(app.AnimalTable,'visible','on');

            % change title back
            set(app.AnimalLoggerUIFigure, 'Name', 'Animal Logger');
        end
        
        function loadGenotypeMenu(app)
            genotypes = fetchn(sl.Genotype,'genotype_name');
            %put WT first
            ind = find(strcmp(genotypes,'WT'));
            if ~isempty(ind)
                g{1} = 'WT';
                g = [g; genotypes(setdiff(1:length(genotypes), ind))];
            else
                g = genotypes;
            end
            g = ['All'; g];
            set(app.Filter_GenotypeDropDown,'Items',g);
        end
        
        function loadSubstanceMenus(app)
            substances = fetchn(sl.InjectionSubstance,'substance_name');
            set(app.SubstanceDropDown_IP,'items',substances);
            set(app.SubstanceDropDown_Eye,'items',substances);
            set(app.SubstanceDropDown_Brain,'items',substances);
        end
        
        function loadBrainAreas(app)
            brainAreas = fetchn(sl.BrainArea,'target');
            set(app.BrainTargetDropDown,'items',brainAreas);
        end
        
        function loadUsers(app)            
            users = fetchn(sl.User,'user_name'); %eventually sl.UserActive
            %put login_user first
            ind = find(strcmp(users,app.login_user));
            if ~isempty(ind)
                u{1} = app.login_user;
                u = [u; users(setdiff(1:length(users), ind))];           
            else
                u = users;
            end
            set(app.PersonDropDown,'items',u);
        end
        
        function loadSocialExperimentTypes(app)
            exps = fetchn(sl.SocialBehaviorExperimentType,'purpose'); 
            set(app.ExperimenttypeDropDown,'items',exps);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, login_user)
            if nargin<2
                app.login_user = '';
            else
                app.login_user = login_user;
            end
            disp(['Login User: ' app.login_user]);
            
            %datajoint setup
            setenv('DJ_USER', 'sl_user')
            setenv('DJ_HOST', 'vfsmdatajoint01.fsm.northwestern.edu')
            setenv('DJ_PASS', 'r0zsNB0xk6wc5BgA!')
            dj.conn();
            
            % Create the 1D axes
            app.ax_ageslider = axes( ...
                app.AgerangeweeksPanel,'Color','none','YColor','none','XLim',[0,25],'YTick',[], ...
                'XTick',[0 5 10 15 20 25], ...
                'XTickLabel',{'0','5','10','15','20','25+'}, ...
                'TickDir','both','TickLength',[0.03,0.035],'XMinorTick', ...
                'on','Units','normalized','Position',[.1,.7,0.7,0.8] ...
                );
            % Disable the interactivity & toolbar visibility
            disableDefaultInteractivity(app.ax_ageslider);
            app.ax_ageslider.Toolbar.Visible = 'off';
            % Add the line ROI
            app.l_ageslider = images.roi.Line(app.ax_ageslider ...
                ,'Position',[4,0;25,0]);
            set(app.ageLow_edit, 'Value', 4);
            set(app.ageHigh_edit, 'Value', 25);
            % Add a listener that will trigger a callback function titled "lMoving" when user
            % moves the ROI endpoints or the line ROI as a whole
            addlistener(app.l_ageslider,'MovingROI',@(varargin)lMoving(app,app.l_ageslider));
            
            %load genotypes
            app.loadGenotypeMenu();
            
            %load substances
            app.loadSubstanceMenus();
            
            %load brain areas
            app.loadBrainAreas();
            
            %load social behavior experiment types
            app.loadSocialExperimentTypes();
            
            %load users
            app.loadUsers();
            
            %set id switch
            set(app.ID_type_switch,'value','Tag ID');
            set(app.DJIDEditField,'Enable',false);
            
            %make initial filter struct
            app.filter_genotype_status = 'All';
            app.filter_ageLow = app.ageLow_edit.Value;
            app.filter_ageHigh = app.ageHigh_edit.Value;
            
            filter_query = [];
            
            %load animal table
            app.loadAnimalTable(filter_query);
            
        end

        % Value changed function: RecordBehavior_switch
        function RecordBehavior_switchValueChanged(app, event)
            value = app.RecordBehavior_switch.Value;
            if strcmp(value,'Recorded')
                app.Lamp_isRecorded.Enable = true;
                app.SocialB_filenameEditField.Enable = true;
            else
                app.Lamp_isRecorded.Enable = false;
                app.SocialB_filenameEditField.Value = ' ';
                app.SocialB_filenameEditField.Enable = false;
            end
        end

        % Value changed function: ageLow_edit
        function ageLow_editValueChanged(app, event)
            value = app.ageLow_edit.Value;
            P = get(app.l_ageslider, 'Position');
            P(1,1) = value;
            if value > P(2,1) %higher limit is below this
                P(2,1) = value;
                set(app.ageHigh_edit, 'Value', value);
                app.filter_ageHigh = value;
            end
            app.filter_ageLow = value;
            set(app.l_ageslider, 'Position', P);
            app.loadAnimalTable([]);
        end

        % Value changed function: ageHigh_edit
        function ageHigh_editValueChanged(app, event)
            value = app.ageHigh_edit.Value;
            P = get(app.l_ageslider, 'Position');
            P(2,1) = value;
            if value < P(1,1) %lower limit is above this
                P(1,1) = value;
                set(app.ageLow_edit, 'Value', value);
                app.filter_ageLow = value;
            end
            app.filter_ageHigh = value;
            set(app.l_ageslider, 'Position', P);
            app.loadAnimalTable([]);
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            value = app.ID_type_switch.Value;
            if strcmp(value,'DJ ID')
                q.animal_id = get(app.DJIDEditField,'value');
            else
                q.tag_id = get(app.TagIDEditField,'value');
            end
            q
            app.loadAnimalTable(q)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            q = [];
            app.loadAnimalTable(q)
        end

        % Value changed function: ID_type_switch
        function ID_type_switchValueChanged(app, event)
            value = app.ID_type_switch.Value;
            if strcmp(value,'DJ ID')
                set(app.DJIDEditField, 'Enable', true);
                set(app.TagIDEditField, 'Enable', false);
            else
                set(app.DJIDEditField, 'Enable', false);
                set(app.TagIDEditField, 'Enable', true);
            end
        end

        % Cell selection callback: AnimalTable
        function AnimalTableCellSelection(app, event)
            indices = event.Indices;
            selectedRow = indices(1,1);
            
            D = get(app.AnimalTable,'Data');
            Nrows = size(D,1);
            
            %select animal
            app.selected_animal_id = D{selectedRow,1};
            
            %make styles
            curStyle = uistyle('FontColor','red','FontWeight','bold');
            oldStyle = uistyle('FontColor','black','FontWeight','normal');
            
            %add style to this row
            addStyle(app.AnimalTable,curStyle,'row',selectedRow);
            
            %put other rows back to normal
            otherRows = setdiff(1:Nrows,selectedRow);
            for i=1:Nrows-1
                addStyle(app.AnimalTable,oldStyle,'row',otherRows(i));
            end
        end

        % Button pushed function: SubmitevententryButton
        function SubmitevententryButtonPushed(app, event)
            selectedTabName = get(get(app.EventTabGroup, 'SelectedTab'), 'Title');
            user = get(app.PersonDropDown, 'value');
            key.date = datestr(get(app.EventdateDatePicker, 'value'),'yyyy-mm-dd');
            key.animal_id = app.selected_animal_id;
            key.user_name = user;
            switch selectedTabName
                case "Genotyped"
                    key.genotype_status = get(app.GenotypeResultsDropDown, 'value');
                    notes = get(app.GenotypedNotesTextArea, 'value');
                    key.notes = strcat(cell2mat(notes'));
                    key
                    add_animalEvent(key,'Genotyped');
                case "Deceased"
                    key.cause = get(app.DeceasedReasonDropDown, 'value');
                    notes = get(app.DeceasedNotesTextArea, 'value');
                    key.notes = strcat(cell2mat(notes'));
                    key
                    add_animalEvent(key,'Deceased');
                    %need to remove from live list
                case "Gave birth"
                    key.number_of_pups = get(app.NumberofpupsSpinner, 'value');
                    notes = get(app.BirthTextArea, 'value');
                    key.notes = strcat(cell2mat(notes'));
                    key = rmfield(key,'user_name'); %no user for this one
                    key
                    add_animalEvent(key,'GaveBirth');
                case "Assign cage"
                    key.cage_number = get(app.TocageEditField, 'value');
                    key.cause = get(app.MoveReasonDropDown, 'value');
                    notes = get(app.MoveCageTextArea, 'value');
                    key.notes = strcat(cell2mat(notes'));
                    key
                    add_animalEvent(key,'AssignCage');
                case "IP injection"
                    key.substance_name = get(app.SubstanceDropDown_IP, 'value');
                    key.concentration = get(app.IP_inj_ConcentrationEditField, 'value');
                    notes = get(app.IP_inj_NotesTextArea, 'value');
                    key.notes = strcat(cell2mat(notes'));
                    hrs = get(app.IP_timeH, 'value');
                    if strcmp(get(app.IP_time_ampm,'value'), 'PM')
                        hrs = rem(hrs, 12) + 12;
                    end
                    mins = get(app.IP_timeM, 'value');
                    key.time = [num2str(hrs,'%02d') ':' num2str(mins,'%02d') ':00'];
                    key
                    add_animalEvent(key,'IPinjection');
                case "Eye injection"
                    key.substance_name = get(app.SubstanceDropDown_Eye, 'value');
                    key.dilution = get(app.Dilution_EyeEditField, 'value');
                    notes = get(app.Eye_inj_NotesTextArea, 'value');
                    key.notes = strcat(cell2mat(notes'));
                    hrs = get(app.Eye_timeH, 'value');
                    if strcmp(get(app.Eye_time_ampm,'value'), 'PM')
                        hrs = rem(hrs, 12) + 12;
                    end
                    key.whichEye = get(app.WhichEyeDropDown, 'value');
                    mins = get(app.Eye_timeM, 'value');
                    key.time = [num2str(hrs,'%02d') ':' num2str(mins,'%02d') ':00'];
                    key
                    add_animalEvent(key,'EyeInjection');
                case "Brain injection"
                    key.substance_name = get(app.SubstanceDropDown_Brain, 'value');
                    key.dilution = get(app.Dilution_BrainEditField, 'value');
                    notes = get(app.Brain_inj_NotesTextArea, 'value');
                    key.notes = strcat(cell2mat(notes'));
                    hrs = get(app.Brain_timeH, 'value');
                    if strcmp(get(app.Brain_time_ampm,'value'), 'PM')
                        hrs = rem(hrs, 12) + 12;
                    end
                    key.target = get(app.BrainTargetDropDown, 'value');
                    key.hemisphere = get(app.HemisphereDropDown, 'value');
                    key.head_rotation = get(app.AngleEditField, 'value');
                    AP = get(app.APEditField, 'value');
                    ML = get(app.MLEditField, 'value');
                    DV = get(app.DVEditField, 'value');
                    key.coordinates = [AP, ML, DV];
                    mins = get(app.Brain_timeM, 'value');
                    key.time = [num2str(hrs,'%02d') ':' num2str(mins,'%02d') ':00'];
                    key
                    add_animalEvent(key,'BrainInjection');
                case "Handled"
                    notes = get(app.Handled_NotesTextArea, 'value');
                    key.notes = strcat(cell2mat(notes'));
                    hrs = get(app.handled_timeH, 'value');
                    if strcmp(get(app.handled_time_ampm,'value'), 'PM')
                        hrs = rem(hrs, 12) + 12;
                    end
                    mins = get(app.handled_timeM, 'value');
                    key.time = [num2str(hrs,'%02d') ':' num2str(mins,'%02d') ':00'];
                    key.duration = get(app.HandlingDurationEditField, 'value');
                    key
                    add_animalEvent(key,'Handling');
                case "Social behavior session"
                    notes = get(app.BehaviorSession_NotesTextArea, 'value');
                    key.notes = strcat(cell2mat(notes'));
                    hrs = get(app.SocialB_timeH, 'value');
                    if strcmp(get(app.SocialB_time_ampm,'value'), 'PM')
                        hrs = rem(hrs, 12) + 12;
                    end
                    mins = get(app.SocialB_timeM, 'value');
                    key.time = [num2str(hrs,'%02d') ':' num2str(mins,'%02d') ':00'];
                    key.purpose = get(app.ExperimenttypeDropDown,'value');
                    if strcmp(get(app.RecordBehavior_switch, 'value'), 'Recorded')
                        key.recorded = 'T';
                        key.fname = get(app.SocialB_filenameEditField, 'value');
                    else
                        key.recorded = 'F';
                    end
                    key.duration = get(app.SocialB_DurationminsEditField, 'value');
                    animalA_id = get(app.AnimalAEditField,'value');
                    animalB_id = get(app.AnimalBEditField,'value');
                    animalC_id = get(app.AnimalCEditField,'value');
                    stimAnimals = [];
                    stimArms = {};
                    if animalA_id
                        stimAnimals = [stimAnimals, animalA_id];
                        stimArms = [stimArms, 'A'];
                    end
                    if animalB_id
                        stimAnimals = [stimAnimals, animalB_id];
                        stimArms = [stimArms, 'B'];
                    end
                    if animalC_id
                        stimAnimals = [stimAnimals, animalC_id];
                        stimArms = [stimArms, 'C'];
                    end
                    key.stimAnimals = stimAnimals;
                    key.stimArms = stimArms;
                    key
                    add_animalEvent(key,'SocialBehaviorSession');
            end
            %need to reload current table state
            app.loadAnimalTable([]);
        end

        % Value changed function: Filter_SexDropDown
        function Filter_SexDropDownValueChanged(app, event)
            value = app.Filter_SexDropDown.Value;
            if strcmp(value,'All')
                app.filter_struct = rmfield(app.filter_struct, 'sex');
            else
                app.filter_struct.sex = value;
            end
            app.loadAnimalTable([]);
        end

        % Value changed function: Filter_GenotypeStatusDropDown
        function Filter_GenotypeStatusDropDownValueChanged(app, event)
            value = app.Filter_GenotypeStatusDropDown.Value;
            app.filter_genotype_status = value;
            app.loadAnimalTable([]);
        end

        % Value changed function: Filter_GenotypeDropDown
        function Filter_GenotypeDropDownValueChanged(app, event)
            value = app.Filter_GenotypeDropDown.Value;
            if strcmp(value,'All')
                app.filter_struct = rmfield(app.filter_struct, 'genotype_name');
            else
                app.filter_struct.genotype_name = value;
            end
            app.loadAnimalTable([]);
        end

        % Value changed function: EyeInjSwitch
        function EyeInjSwitchValueChanged(app, event)
            value = app.EyeInjSwitch.Value;
            if strcmp(value, 'Y')
                app.filter_check_eye_inj = true;
            else
                app.filter_check_eye_inj = false;
            end
            app.loadAnimalTable([]);
        end

        % Value changed function: BehaviorexpSwitch
        function BehaviorexpSwitchValueChanged(app, event)
            value = app.BehaviorexpSwitch.Value;
            if strcmp(value, 'Y')
                app.filter_check_beh = true;
            else
                app.filter_check_beh = false;
            end
            app.loadAnimalTable([]);
        end

        % Value changed function: BraininjSwitch
        function BraininjSwitchValueChanged(app, event)
            value = app.BraininjSwitch.Value;
            if strcmp(value, 'Y')
                app.filter_check_brain_inj = true;
            else
                app.filter_check_brain_inj = false;
            end
            app.loadAnimalTable([]);
        end

        % Button pushed function: AddanimalButton
        function AddanimalButtonPushed(app, event)
            %test for whether user has access to this function
            AddAnimal(app, app.login_user);
        end

        % Button pushed function: AddgenotypeButton
        function AddgenotypeButtonPushed(app, event)
            %test for whether user has access to this function
            AddGenotype(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnimalLoggerUIFigure = uifigure('Visible', 'off');
            app.AnimalLoggerUIFigure.Name = 'Animal Logger';
            app.AnimalTable = uitable(app.AnimalLoggerUIFigure);
            app.AnimalTable.ColumnName = {'DJ ID'; 'Tag ID'; 'Cage #'; 'Genotype'; 'Genotype status'; 'IP inj.'; 'Sex'; 'Age (wks)'; 'Eye inj.'; 'Brain inj.'; 'Behavior'};
            app.AnimalTable.RowName = {};
            app.AnimalTable.ColumnSortable = true;
            app.AnimalTable.CellSelectionCallback = createCallbackFcn(app, @AnimalTableCellSelection, true);
            app.AnimalSelectionPanel = uipanel(app.AnimalLoggerUIFigure);
            app.AnimalSelectionPanel.Title = 'Animal Selection';
            app.SearchButton = uibutton(app.AnimalSelectionPanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.DJIDEditField = uieditfield(app.AnimalSelectionPanel, 'numeric');
            app.FilterPanel = uipanel(app.AnimalSelectionPanel);
            app.FilterPanel.Title = 'Filter';
            app.GenotypeDropDownLabel = uilabel(app.FilterPanel);
            app.GenotypeDropDownLabel.Text = 'Genotype';
            app.Filter_GenotypeDropDown = uidropdown(app.FilterPanel);
            app.Filter_GenotypeDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_GenotypeDropDownValueChanged, true);
            app.StatusDropDownLabel = uilabel(app.FilterPanel);
            app.StatusDropDownLabel.Text = 'Status';
            app.Filter_GenotypeStatusDropDown = uidropdown(app.FilterPanel);
            app.Filter_GenotypeStatusDropDown.Items = {'All', 'carrier', 'non-carrier', 'unknown', 'het', 'Homo'};
            app.Filter_GenotypeStatusDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_GenotypeStatusDropDownValueChanged, true);
            app.Filter_GenotypeStatusDropDown.Value = 'All';
            app.EyeInjSwitchLabel = uilabel(app.FilterPanel);
            app.EyeInjSwitchLabel.Text = 'Eye Inj.';
            app.EyeInjSwitch = uiswitch(app.FilterPanel, 'toggle');
            app.EyeInjSwitch.Items = {'Don''t check', 'Y'};
            app.EyeInjSwitch.ValueChangedFcn = createCallbackFcn(app, @EyeInjSwitchValueChanged, true);
            app.EyeInjSwitch.Value = 'Don''t check';
            app.BehaviorexpSwitchLabel = uilabel(app.FilterPanel);
            app.BehaviorexpSwitchLabel.Text = 'Behavior exp.';
            app.BehaviorexpSwitch = uiswitch(app.FilterPanel, 'toggle');
            app.BehaviorexpSwitch.Items = {'Don''t check', 'Y'};
            app.BehaviorexpSwitch.ValueChangedFcn = createCallbackFcn(app, @BehaviorexpSwitchValueChanged, true);
            app.BehaviorexpSwitch.Value = 'Don''t check';
            app.SexDropDownLabel = uilabel(app.FilterPanel);
            app.SexDropDownLabel.Text = 'Sex';
            app.Filter_SexDropDown = uidropdown(app.FilterPanel);
            app.Filter_SexDropDown.Items = {'All', 'Male', 'Female'};
            app.Filter_SexDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_SexDropDownValueChanged, true);
            app.Filter_SexDropDown.Value = 'All';
            app.BraininjSwitchLabel = uilabel(app.FilterPanel);
            app.BraininjSwitchLabel.Text = 'Brain inj.';
            app.BraininjSwitch = uiswitch(app.FilterPanel, 'toggle');
            app.BraininjSwitch.Items = {'Don''t check', 'Y'};
            app.BraininjSwitch.ValueChangedFcn = createCallbackFcn(app, @BraininjSwitchValueChanged, true);
            app.BraininjSwitch.Value = 'Don''t check';
            app.ResetButton = uibutton(app.AnimalSelectionPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ID_type_switch = uiswitch(app.AnimalSelectionPanel, 'slider');
            app.ID_type_switch.Items = {'Tag ID', 'DJ ID'};
            app.ID_type_switch.ValueChangedFcn = createCallbackFcn(app, @ID_type_switchValueChanged, true);
            app.ID_type_switch.Value = 'Tag ID';
            app.TagIDEditField = uieditfield(app.AnimalSelectionPanel, 'numeric');
            app.AgerangeweeksPanel = uipanel(app.AnimalSelectionPanel);
            app.AgerangeweeksPanel.Title = 'Age range (weeks)';
            app.ageLow_edit = uieditfield(app.AgerangeweeksPanel, 'numeric');
            app.ageLow_edit.ValueChangedFcn = createCallbackFcn(app, @ageLow_editValueChanged, true);
            app.ageHigh_edit = uieditfield(app.AgerangeweeksPanel, 'numeric');
            app.ageHigh_edit.ValueChangedFcn = createCallbackFcn(app, @ageHigh_editValueChanged, true);
            app.toLabel = uilabel(app.AgerangeweeksPanel);
            app.toLabel.Text = 'to';
            app.AddentriesPanel = uipanel(app.AnimalLoggerUIFigure);
            app.AddentriesPanel.Title = 'Add entries';
            app.AddanimalButton = uibutton(app.AddentriesPanel, 'push');
            app.AddanimalButton.ButtonPushedFcn = createCallbackFcn(app, @AddanimalButtonPushed, true);
            app.AddanimalButton.Text = 'Add animal';
            app.AddgenotypeButton = uibutton(app.AddentriesPanel, 'push');
            app.AddgenotypeButton.ButtonPushedFcn = createCallbackFcn(app, @AddgenotypeButtonPushed, true);
            app.AddgenotypeButton.Text = 'Add genotype';
            app.AddsubstanceButton = uibutton(app.AddentriesPanel, 'push');
            app.AddsubstanceButton.Text = 'Add substance';
            app.AddbrainareaButton = uibutton(app.AddentriesPanel, 'push');
            app.AddbrainareaButton.Text = 'Add brain area';
            app.EventTabGroup = uitabgroup(app.AnimalLoggerUIFigure);
            app.GenotypedTab = uitab(app.EventTabGroup);
            app.GenotypedTab.Title = 'Genotyped';
            app.ResultsDropDownLabel = uilabel(app.GenotypedTab);
            app.ResultsDropDownLabel.Text = 'Results';
            app.GenotypeResultsDropDown = uidropdown(app.GenotypedTab);
            app.GenotypeResultsDropDown.Items = {'Carrier', 'Non-carrier', 'Unknown', 'Het', 'homo'};
            app.GenotypeResultsDropDown.Value = 'Carrier';
            app.NotesTextAreaLabel = uilabel(app.GenotypedTab);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.GenotypedNotesTextArea = uitextarea(app.GenotypedTab);
            app.DeceasedTab = uitab(app.EventTabGroup);
            app.DeceasedTab.Title = 'Deceased';
            app.ReasonDropDownLabel = uilabel(app.DeceasedTab);
            app.ReasonDropDownLabel.Text = 'Reason';
            app.DeceasedReasonDropDown = uidropdown(app.DeceasedTab);
            app.DeceasedReasonDropDown.Items = {'sacrificed for experiment', 'sacrificed because not needed', 'other', 'unknown'};
            app.DeceasedReasonDropDown.Value = 'sacrificed for experiment';
            app.NotesTextArea_2Label = uilabel(app.DeceasedTab);
            app.NotesTextArea_2Label.Text = 'Notes';
            app.DeceasedNotesTextArea = uitextarea(app.DeceasedTab);
            app.GavebirthTab = uitab(app.EventTabGroup);
            app.GavebirthTab.Title = 'Gave birth';
            app.NotesTextArea_3Label = uilabel(app.GavebirthTab);
            app.NotesTextArea_3Label.Text = 'Notes';
            app.BirthTextArea = uitextarea(app.GavebirthTab);
            app.NumberofpupsSpinnerLabel = uilabel(app.GavebirthTab);
            app.NumberofpupsSpinnerLabel.Text = 'Number of pups';
            app.NumberofpupsSpinner = uispinner(app.GavebirthTab);
            app.AssigncageTab = uitab(app.EventTabGroup);
            app.AssigncageTab.Title = 'Assign cage';
            app.ReasonDropDown_2Label = uilabel(app.AssigncageTab);
            app.ReasonDropDown_2Label.Text = 'Reason';
            app.MoveReasonDropDown = uidropdown(app.AssigncageTab);
            app.MoveReasonDropDown.Items = {'weaning', 'experiment', 'set as breeder', 'crowding', 'other', 'unknown'};
            app.MoveReasonDropDown.Value = 'weaning';
            app.NotesTextArea_4Label = uilabel(app.AssigncageTab);
            app.NotesTextArea_4Label.Text = 'Notes';
            app.MoveCageTextArea = uitextarea(app.AssigncageTab);
            app.TocageEditFieldLabel = uilabel(app.AssigncageTab);
            app.TocageEditFieldLabel.Text = 'To cage #';
            app.TocageEditField = uieditfield(app.AssigncageTab, 'numeric');
            app.IPinjectionTab = uitab(app.EventTabGroup);
            app.IPinjectionTab.Title = 'IP injection';
            app.NotesTextArea_5Label = uilabel(app.IPinjectionTab);
            app.NotesTextArea_5Label.Text = 'Notes';
            app.IP_inj_NotesTextArea = uitextarea(app.IPinjectionTab);
            app.SubstanceDropDownLabel = uilabel(app.IPinjectionTab);
            app.SubstanceDropDownLabel.Text = 'Substance';
            app.SubstanceDropDown_IP = uidropdown(app.IPinjectionTab);
            app.ConcentrationmgKgEditFieldLabel = uilabel(app.IPinjectionTab);
            app.ConcentrationmgKgEditFieldLabel.Text = 'Concentration (mg / Kg)';
            app.IP_inj_ConcentrationEditField = uieditfield(app.IPinjectionTab, 'numeric');
            app.IP_timeH = uispinner(app.IPinjectionTab);
            app.IP_timeH.Limits = [1 12];
            app.IP_timeH.RoundFractionalValues = 'on';
            app.IP_timeH.Value = 10;
            app.IP_time_ampm = uidropdown(app.IPinjectionTab);
            app.IP_time_ampm.Items = {'AM', 'PM'};
            app.IP_time_ampm.Value = 'AM';
            app.IP_timeM = uidropdown(app.IPinjectionTab);
            app.IP_timeM.Items = {'00', '15', '30', '45'};
            app.IP_timeM.Value = '00';
            app.TimeLabel_5 = uilabel(app.IPinjectionTab);
            app.TimeLabel_5.Text = 'Time';
            app.EyeinjectionTab = uitab(app.EventTabGroup);
            app.EyeinjectionTab.Title = 'Eye injection';
            app.SubstanceDropDownLabel_2 = uilabel(app.EyeinjectionTab);
            app.SubstanceDropDownLabel_2.Text = 'Substance';
            app.SubstanceDropDown_Eye = uidropdown(app.EyeinjectionTab);
            app.NotesTextArea_7Label = uilabel(app.EyeinjectionTab);
            app.NotesTextArea_7Label.Text = 'Notes';
            app.Eye_inj_NotesTextArea = uitextarea(app.EyeinjectionTab);
            app.EyeDropDownLabel = uilabel(app.EyeinjectionTab);
            app.EyeDropDownLabel.Text = 'Eye';
            app.WhichEyeDropDown = uidropdown(app.EyeinjectionTab);
            app.WhichEyeDropDown.Items = {'Left', 'Right'};
            app.WhichEyeDropDown.Value = 'Left';
            app.Dilution1XEditFieldLabel = uilabel(app.EyeinjectionTab);
            app.Dilution1XEditFieldLabel.Text = 'Dilution (1:X)';
            app.Dilution_EyeEditField = uieditfield(app.EyeinjectionTab, 'numeric');
            app.Dilution_EyeEditField.Limits = [1 Inf];
            app.Dilution_EyeEditField.Value = 1;
            app.Eye_timeH = uispinner(app.EyeinjectionTab);
            app.Eye_timeH.Limits = [1 12];
            app.Eye_timeH.RoundFractionalValues = 'on';
            app.Eye_timeH.Value = 10;
            app.Eye_time_ampm = uidropdown(app.EyeinjectionTab);
            app.Eye_time_ampm.Items = {'AM', 'PM'};
            app.Eye_time_ampm.Value = 'AM';
            app.Eye_timeM = uidropdown(app.EyeinjectionTab);
            app.Eye_timeM.Items = {'00', '15', '30', '45'};
            app.Eye_timeM.Value = '00';
            app.TimeLabel_4 = uilabel(app.EyeinjectionTab);
            app.TimeLabel_4.Text = 'Time';
            app.BraininjectionTab = uitab(app.EventTabGroup);
            app.BraininjectionTab.Title = 'Brain injection';
            app.SubstanceDropDown_3Label = uilabel(app.BraininjectionTab);
            app.SubstanceDropDown_3Label.Text = 'Substance';
            app.SubstanceDropDown_Brain = uidropdown(app.BraininjectionTab);
            app.Dilution1XEditField_2Label = uilabel(app.BraininjectionTab);
            app.Dilution1XEditField_2Label.Text = 'Dilution (1:X)';
            app.Dilution_BrainEditField = uieditfield(app.BraininjectionTab, 'numeric');
            app.Dilution_BrainEditField.Limits = [1 Inf];
            app.Dilution_BrainEditField.Value = 1;
            app.NotesTextArea_8Label = uilabel(app.BraininjectionTab);
            app.NotesTextArea_8Label.Text = 'Notes';
            app.Brain_inj_NotesTextArea = uitextarea(app.BraininjectionTab);
            app.CoordinatesLabel = uilabel(app.BraininjectionTab);
            app.CoordinatesLabel.Text = 'Coordinates:';
            app.TargetDropDownLabel = uilabel(app.BraininjectionTab);
            app.TargetDropDownLabel.Text = 'Target';
            app.BrainTargetDropDown = uidropdown(app.BraininjectionTab);
            app.BrainTargetDropDown.Items = {'MeA', 'LHA', 'OPN', 'vLGN', 'dLGN', 'SC'};
            app.BrainTargetDropDown.Value = 'MeA';
            app.HemisphereDropDownLabel = uilabel(app.BraininjectionTab);
            app.HemisphereDropDownLabel.Text = 'Hemisphere';
            app.HemisphereDropDown = uidropdown(app.BraininjectionTab);
            app.HemisphereDropDown.Items = {'Left', 'Right'};
            app.HemisphereDropDown.Value = 'Left';
            app.APEditFieldLabel = uilabel(app.BraininjectionTab);
            app.APEditFieldLabel.Text = 'AP';
            app.APEditField = uieditfield(app.BraininjectionTab, 'numeric');
            app.MLEditFieldLabel = uilabel(app.BraininjectionTab);
            app.MLEditFieldLabel.Text = 'ML';
            app.MLEditField = uieditfield(app.BraininjectionTab, 'numeric');
            app.DVEditFieldLabel = uilabel(app.BraininjectionTab);
            app.DVEditFieldLabel.Text = 'DV';
            app.DVEditField = uieditfield(app.BraininjectionTab, 'numeric');
            app.AngleEditFieldLabel = uilabel(app.BraininjectionTab);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.BraininjectionTab, 'numeric');
            app.Brain_timeH = uispinner(app.BraininjectionTab);
            app.Brain_timeH.Limits = [1 12];
            app.Brain_timeH.RoundFractionalValues = 'on';
            app.Brain_timeH.Value = 10;
            app.Brain_time_ampm = uidropdown(app.BraininjectionTab);
            app.Brain_time_ampm.Items = {'AM', 'PM'};
            app.Brain_time_ampm.Value = 'AM';
            app.Brain_timeM = uidropdown(app.BraininjectionTab);
            app.Brain_timeM.Items = {'00', '15', '30', '45'};
            app.Brain_timeM.Value = '00';
            app.TimeLabel_3 = uilabel(app.BraininjectionTab);
            app.TimeLabel_3.Text = 'Time';
            app.HandledTab = uitab(app.EventTabGroup);
            app.HandledTab.Title = 'Handled';
            app.NotesTextArea_6Label = uilabel(app.HandledTab);
            app.NotesTextArea_6Label.Text = 'Notes';
            app.Handled_NotesTextArea = uitextarea(app.HandledTab);
            app.DurationminutesEditFieldLabel = uilabel(app.HandledTab);
            app.DurationminutesEditFieldLabel.Text = 'Duration (minutes)';
            app.HandlingDurationEditField = uieditfield(app.HandledTab, 'numeric');
            app.HandlingDurationEditField.Limits = [0 360];
            app.HandlingDurationEditField.RoundFractionalValues = 'on';
            app.handled_timeH = uispinner(app.HandledTab);
            app.handled_timeH.Limits = [1 12];
            app.handled_timeH.RoundFractionalValues = 'on';
            app.handled_timeH.Value = 10;
            app.handled_time_ampm = uidropdown(app.HandledTab);
            app.handled_time_ampm.Items = {'AM', 'PM'};
            app.handled_time_ampm.Value = 'AM';
            app.handled_timeM = uidropdown(app.HandledTab);
            app.handled_timeM.Items = {'00', '15', '30', '45'};
            app.handled_timeM.Value = '00';
            app.StarttimeLabel_4 = uilabel(app.HandledTab);
            app.StarttimeLabel_4.Text = 'Start time';
            app.SocialbehaviorsessionTab = uitab(app.EventTabGroup);
            app.SocialbehaviorsessionTab.Title = 'Social behavior session';
            app.NotesTextArea_9Label = uilabel(app.SocialbehaviorsessionTab);
            app.NotesTextArea_9Label.Text = 'Notes';
            app.BehaviorSession_NotesTextArea = uitextarea(app.SocialbehaviorsessionTab);
            app.DurationminsEditFieldLabel = uilabel(app.SocialbehaviorsessionTab);
            app.DurationminsEditFieldLabel.Text = 'Duration (mins)';
            app.SocialB_DurationminsEditField = uieditfield(app.SocialbehaviorsessionTab, 'numeric');
            app.RootfilenameEditFieldLabel = uilabel(app.SocialbehaviorsessionTab);
            app.RootfilenameEditFieldLabel.Text = 'Root filename';
            app.SocialB_filenameEditField = uieditfield(app.SocialbehaviorsessionTab, 'text');
            app.RecordBehavior_switch = uiswitch(app.SocialbehaviorsessionTab, 'rocker');
            app.RecordBehavior_switch.Items = {'Not recorded', 'Recorded'};
            app.RecordBehavior_switch.ValueChangedFcn = createCallbackFcn(app, @RecordBehavior_switchValueChanged, true);
            app.RecordBehavior_switch.Value = 'Recorded';
            app.AEditFieldLabel = uilabel(app.SocialbehaviorsessionTab);
            app.AEditFieldLabel.Text = 'A';
            app.AnimalAEditField = uieditfield(app.SocialbehaviorsessionTab, 'numeric');
            app.StimulusanimalIDs0foremptyLabel = uilabel(app.SocialbehaviorsessionTab);
            app.StimulusanimalIDs0foremptyLabel.Text = 'Stimulus animal IDs (0 for empty)';
            app.BEditFieldLabel = uilabel(app.SocialbehaviorsessionTab);
            app.BEditFieldLabel.Text = 'B';
            app.AnimalBEditField = uieditfield(app.SocialbehaviorsessionTab, 'numeric');
            app.CEditFieldLabel = uilabel(app.SocialbehaviorsessionTab);
            app.CEditFieldLabel.Text = 'C';
            app.AnimalCEditField = uieditfield(app.SocialbehaviorsessionTab, 'numeric');
            app.Lamp_isRecorded = uilamp(app.SocialbehaviorsessionTab);
            app.SocialB_timeH = uispinner(app.SocialbehaviorsessionTab);
            app.SocialB_timeH.Limits = [1 12];
            app.SocialB_timeH.RoundFractionalValues = 'on';
            app.SocialB_timeH.Value = 10;
            app.SocialB_time_ampm = uidropdown(app.SocialbehaviorsessionTab);
            app.SocialB_time_ampm.Items = {'AM', 'PM'};
            app.SocialB_time_ampm.Value = 'AM';
            app.SocialB_timeM = uidropdown(app.SocialbehaviorsessionTab);
            app.SocialB_timeM.Items = {'00', '15', '30', '45'};
            app.SocialB_timeM.Value = '00';
            app.StarttimeLabel_3 = uilabel(app.SocialbehaviorsessionTab);
            app.StarttimeLabel_3.Text = 'Start time';
            app.ExperimenttypeDropDownLabel = uilabel(app.SocialbehaviorsessionTab);
            app.ExperimenttypeDropDownLabel.Text = 'Experiment type';
            app.ExperimenttypeDropDown = uidropdown(app.SocialbehaviorsessionTab);
            app.EventdateDatePickerLabel = uilabel(app.AnimalLoggerUIFigure);
            app.EventdateDatePickerLabel.Text = 'Event date';
            app.EventdateDatePicker = uidatepicker(app.AnimalLoggerUIFigure);
            app.EventdateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.SubmitevententryButton = uibutton(app.AnimalLoggerUIFigure, 'push');
            app.SubmitevententryButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitevententryButtonPushed, true);
            app.SubmitevententryButton.Text = 'Submit event entry';
            app.PersonDropDownLabel = uilabel(app.AnimalLoggerUIFigure);
            app.PersonDropDownLabel.Text = 'Person';
            app.PersonDropDown = uidropdown(app.AnimalLoggerUIFigure);
            app.PersonDropDown.Items = {'David', 'Zach', 'Devon', 'Greg', 'Kayla', 'Sam', 'Sophia', 'Susan'};
            app.PersonDropDown.Value = 'David';
            app.VieweventlogButton = uibutton(app.AnimalLoggerUIFigure, 'push');
            app.VieweventlogButton.Text = 'View event log';
            % Show the figure after all components are created
            app.AnimalLoggerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnimalLogger(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnimalLoggerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnimalLoggerUIFigure)
        end
    end
end