classdef AssignCellType_dlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AssignCellTypeUIFigure  matlab.ui.Figure
        CelltypeDropDownLabel   matlab.ui.control.Label
        CelltypeDropDown        matlab.ui.control.DropDown
        ClassDropDownLabel      matlab.ui.control.Label
        ClassDropDown           matlab.ui.control.DropDown
        CancelButton            matlab.ui.control.Button
        AssignButton            matlab.ui.control.Button
        CellIDEditFieldLabel    matlab.ui.control.Label
        CellIDEditField         matlab.ui.control.EditField
    end

    properties (Access = private)
        connection % DJ connection
        cell_unid % unique id for MeasuredCell table
        cell_id
        animal_id
    end
    
    methods (Access = private)
        
        function loadTypeMenu(app)
            q.cell_class = app.ClassDropDown.Value;
            types = fetchn(sl.CellType & q, 'name_full');
            set(app.CelltypeDropDown, 'items',types);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, cell_unid, animal_id, cell_id, tryType)
            app.connection = dj.conn;
            if ~app.connection.isConnected
                disp('DataJoint connection failed.');
                delete(app)  
            end
            
            if nargin>=3
                %set cell_unid and animal_id
                app.cell_unid = cell_unid;
                app.animal_id = animal_id;
                app.cell_id = cell_id;
                
                %set cell name
                set(app.CellIDEditField, 'value', cell_id);
                
                %init type menu
                app.loadTypeMenu();
                
                %set initial values
                thisTypeEntry = sl_mutable.CurrentCellType & sprintf('cell_unid=%d', cell_unid);
                if thisTypeEntry.exists
                    startingClass = fetch1(thisTypeEntry, 'cell_class');
                    startingType = fetch1(thisTypeEntry, 'cell_type');
                    set(app.ClassDropDown, 'value', startingClass);
                    set(app.CelltypeDropDown, 'value', startingType);
                elseif nargin == 5
                    types = get(app.CelltypeDropDown, 'items');
                    if ismember(tryType, types)
                        set(app.CelltypeDropDown, 'value', tryType);
                    end
                end
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.AssignCellTypeUIFigureCloseRequest();
        end

        % Button pushed function: AssignButton
        function AssignButtonPushed(app, event)
            C = app.connection;
            C.startTransaction;
            
            try
                key.cell_unid = app.cell_unid;
                key.animal_id = app.animal_id;
                key.cell_id = app.cell_id;
                key.user_name = C.user;
                key.cell_class = get(app.ClassDropDown,'Value');
                key.name_full = get(app.CelltypeDropDown, 'Value');
                
                disp('Assigning cell type with key:');
                key
                insert(sl.CellEventAssignType,key);   
                
                key = rmfield(key,'user_name');
                key.cell_type = key.name_full;
                key = rmfield(key,'name_full');                
                insert(sl_mutable.CurrentCellType, key, 'REPLACE'); 
                
                C.commitTransaction;
                disp('Cell assignment successful');
                app.AssignCellTypeUIFigureCloseRequest();  
            catch ME
                C.cancelTransaction;
                disp('Cell assignment failed');
                rethrow(ME);
            end
        end

        % Close request function: AssignCellTypeUIFigure
        function AssignCellTypeUIFigureCloseRequest(app, event)
            if ~isempty(app.connection) && app.connection.isConnected
                app.connection.close();
            end
            delete(app)            
        end

        % Value changed function: ClassDropDown
        function ClassDropDownValueChanged(app, event)
            app.loadTypeMenu();
        end

        % Value changed function: CellIDEditField
        function CellIDEditFieldValueChanged(app, event)
            value = app.CellIDEditField.Value;
            thisCell = sl.MeasuredCell & sprintf('cell_id="%s"', value);
            if thisCell.exists
                key = thisCell.fetch;
                app.cell_id = key.cell_id;
                app.cell_unid = key.cell_unid;
                app.animal_id = key.animal_id;                
            else
                app.cell_id = [];
                app.cell_unid = [];
                app.animal_id = [];  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AssignCellTypeUIFigure = uifigure('Visible', 'off');
            app.AssignCellTypeUIFigure.Name = 'AssignCellType';
            app.AssignCellTypeUIFigure.CloseRequestFcn = createCallbackFcn(app, @AssignCellTypeUIFigureCloseRequest, true);
            app.CelltypeDropDownLabel = uilabel(app.AssignCellTypeUIFigure);
            app.CelltypeDropDownLabel.Text = 'Cell type';
            app.CelltypeDropDown = uidropdown(app.AssignCellTypeUIFigure);
            app.ClassDropDownLabel = uilabel(app.AssignCellTypeUIFigure);
            app.ClassDropDownLabel.Text = 'Class';
            app.ClassDropDown = uidropdown(app.AssignCellTypeUIFigure);
            app.ClassDropDown.Items = {'RGC', 'amacrine', 'bipolar', 'horizontal', 'photoreceptor', 'other neuron', 'glia', 'pericyte', 'RPE cell', 'unknown', 'other'};
            app.ClassDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassDropDownValueChanged, true);
            app.ClassDropDown.Value = 'RGC';
            app.CancelButton = uibutton(app.AssignCellTypeUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AssignButton = uibutton(app.AssignCellTypeUIFigure, 'push');
            app.AssignButton.ButtonPushedFcn = createCallbackFcn(app, @AssignButtonPushed, true);
            app.AssignButton.Text = 'Assign';
            app.CellIDEditFieldLabel = uilabel(app.AssignCellTypeUIFigure);
            app.CellIDEditFieldLabel.Text = 'Cell ID';
            app.CellIDEditField = uieditfield(app.AssignCellTypeUIFigure, 'text');
            app.CellIDEditField.ValueChangedFcn = createCallbackFcn(app, @CellIDEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.AssignCellTypeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AssignCellType_dlg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AssignCellTypeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AssignCellTypeUIFigure)
        end
    end
end