classdef Calcium_Imaging_Browser_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalciumImagingBrowserUIFigure  matlab.ui.Figure
        ScatterPlotButton              matlab.ui.control.Button
        SpikePlotSwitch                matlab.ui.control.Switch
        SpikePlotSwitchLabel           matlab.ui.control.Label
        CalciumImagingUnitSwitch       matlab.ui.control.Switch
        CalciumImagingUnitSwitchLabel  matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        Spinner                        matlab.ui.control.Spinner
        SpinnerLabel                   matlab.ui.control.Label
        EpochSlider                    matlab.ui.control.Slider
        EpochSliderLabel               matlab.ui.control.Label
        PlotbyepochSwitch              matlab.ui.control.Switch
        PlotbyepochSwitchLabel         matlab.ui.control.Label
        PlotsPanel                     matlab.ui.container.Panel
        GoButton                       matlab.ui.control.Button
        preceedingepochsSpinner        matlab.ui.control.Spinner
        preceedingepochsSpinnerLabel   matlab.ui.control.Label
        PlotCheckBox                   matlab.ui.control.CheckBox
        MultipleparamsCheckBox         matlab.ui.control.CheckBox
        VariableparamsListBox          matlab.ui.control.ListBox
        VariableparamsListBoxLabel     matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        AlignmentListBox               matlab.ui.control.ListBox
        AlignmentListBoxLabel          matlab.ui.control.Label
        CellsListBox                   matlab.ui.control.ListBox
        CellsListBoxLabel              matlab.ui.control.Label
        ExperimentdayDatePicker        matlab.ui.control.DatePicker
        ExperimentdayLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        public_file_name = struct();
        public_source_id_list = [];
        public_alignment_list = [];
        public_variable_params = [];
        public_epoch_params = [];
        public_spike_data = struct();
        public_cal_data = struct();
        public_spike_timing = {};
        public_spike_sample_rate = 50000;
    end

    methods (Access = private)

        function loadAlignment(app, file_name, source_id)
            if nargin < 3
                file_name = app.public_file_name.file_name;
                source_id = app.public_source_id_list(1);
            end
            alignment_querry_string = sprintf('sln_funcimage.Alignment & ''file_name = "%s" and source_id = %d''', app.public_file_name.file_name, source_id);
            alignment_querry = fetch(eval(alignment_querry_string), '*');
            alignments = [{alignment_querry.file_name}; {alignment_querry.dataset_name}; {alignment_querry.image_fname}; {alignment_querry.source_id}];
            app.public_alignment_list = cell2struct(alignments, {'file_name', 'dataset_name', 'image_fname', 'source_id'});

            app.AlignmentListBox.Items = join([{alignment_querry.dataset_name}; {alignment_querry.image_fname}]', " | ");

        end


        function loadVariableParams(app, alignment)
            if nargin < 2
                alignment = app.public_alignment_list(1);
            end
            epoch_block_querry = sln_symphony.ExperimentEpochBlock & (sln_symphony.DatasetEpoch & (sln_funcimage.Alignment & alignment & app.public_file_name));
            protocol_name = sqlProtName2ProtName(fetch1(epoch_block_querry, 'protocol_name'));
            variable_param = '*';
            switch protocol_name
                case 'FlashedBar'
                    variable_param = 'bar_angle';
                case 'SpotsMultiSize'
                    variable_param = 'cur_spot_size';
                case 'MultiPulse'
                    variable_param = 'pulse_1_curr';
                case 'DriftingGratings'
                    variable_param = 'grating_angle';
                case 'StepPulseScale'
                    variable_param = 'scaled_amplitude';
                
            end
            app.VariableparamsListBoxLabel.Text = ['Variable params: ', variable_param];
            if strcmp(protocol_name,'Chirp')
                epoch_params = 0;
            else
                epoch_params = fetchn(aka.EpochParams(protocol_name) & epoch_block_querry, variable_param);
            end
            if strcmp(protocol_name,'MultiPulse')
                [pre_time, stim_time] = fetchn(aka.BlockParams(protocol_name) * aka.EpochParams(protocol_name) & epoch_block_querry, 'pre_time', 'stim_1_time', 'LIMIT 1');
           
            else
                [pre_time, stim_time] = fetchn(aka.BlockParams(protocol_name) * aka.EpochParams(protocol_name) & epoch_block_querry, 'pre_time', 'stim_time', 'LIMIT 1');
            end
            app.public_spike_data.pre_time = pre_time;
            app.public_spike_data.stim_time = stim_time;
            app.VariableparamsListBox.Items = string(unique(epoch_params));
            
            app.public_variable_params = unique(epoch_params);
            app.public_epoch_params = epoch_params;

        end

        function loadData(app, alignment)
            app.Lamp.Color = "#FF0000"; drawnow();


            if nargin < 2
                alignment = app.public_alignment_list(1);

            end

            spike_querry = sln_symphony.ExperimentEpochChannel & (sln_symphony.DatasetEpoch & (sln_funcimage.Alignment & alignment) & 'channel_name LIKE "Amp_"' & app.public_file_name);
            cal_querry = sln_funcimage.ROITraces & alignment;
            sample_rate = fetch1(sln_symphony.ExperimentChannel & (sln_symphony.DatasetEpoch & (sln_funcimage.Alignment & alignment) & 'channel_name LIKE "Amp_"' & app.public_file_name), 'sample_rate');
            app.public_spike_sample_rate = sample_rate;
            spike_time_querry = sln_symphony.SpikeTrain & (sln_symphony.DatasetEpoch & alignment);
            spike_time = fetchn(spike_time_querry, 'spike_indices');
            spike_time = cellfun(@uint32, spike_time, 'UniformOutput', false);
            app.public_spike_timing = spike_time;
            raw_data = cell2mat(fetchn(spike_querry, 'raw_data'));
            n_spike_time_points = size(raw_data, 2);
            spike_time_axis = [0:n_spike_time_points - 1] ./ sample_rate * 1e3;
            app.public_spike_data.time_axis = spike_time_axis;
            app.public_spike_data.raw_data = raw_data;
            if app.CalciumImagingUnitSwitch.Value == "dF/F0"
                [time_axis, ~, ~, trace_matrix] = sln_funcimage.meanForROI(cal_querry, 1, true);
            else
                [time_axis, ~, ~, trace_matrix] = sln_funcimage.meanForROI(cal_querry, 1, false);
                trace_matrix = zscore(trace_matrix, 1, "all");
            end
            app.public_cal_data.time_axis = time_axis + abs(min(time_axis));
            app.public_cal_data.trace_matrix = trace_matrix;

            app.plotAllDataNow();
            app.Lamp.Color = "#00FF00";

        end

        function results = plotAllDataNow(app)
            t = tiledlayout(app.PlotsPanel,2,1);
            ax1 = nexttile(t);
            if app.SpikePlotSwitch.Value == "Raster"
                app.plotRasterPlot(ax1, app.public_spike_timing);
            else
                plot(ax1,app.public_spike_data.time_axis, app.public_spike_data.raw_data', 'LineWidth', 1, 'HandleVisibility', 'off');
                xlabel(ax1, 'Time (ms)');
                ylabel(ax1, 'mV');
            end
            ax2 = nexttile(t);
            plot(ax2, app.public_cal_data.time_axis, app.public_cal_data.trace_matrix', 'LineWidth', 1, 'HandleVisibility', 'off');
            fontsize(ax1, 15, 'points');

            fontsize(ax2, 15, 'points');
            xlabel(ax2, 'Time (ms)');
            app.CalciumYLabel(ax2);
            xline(ax1, app.public_spike_data.pre_time, 'r:', 'LineWidth', 1, 'HandleVisibility', 'off');
            xline(ax2, app.public_spike_data.pre_time, 'r:', 'LineWidth', 1, 'HandleVisibility', 'off');
            xline(ax1, app.public_spike_data.pre_time + app.public_spike_data.stim_time, 'r:', 'LineWidth', 1, 'HandleVisibility', 'off');
            xline(ax2, app.public_spike_data.pre_time + app.public_spike_data.stim_time, 'r:', 'LineWidth', 1, 'HandleVisibility', 'off');

        end

        function CalciumYLabel(app, ax)
            if app.CalciumImagingUnitSwitch.Value == "dF/F0"
                ylabel(ax, '\DeltaF/F_0');
            else
                ylabel(ax, 'SD');
            end
        end

        function plotRasterPlot(app, ax, spike_idx_cell, idx)

            if nargin < 4
                n_trial = length(spike_idx_cell);
                idx = [1:n_trial];
            else
                n_trial = length(idx);
            end
            hold(ax, 'on');
            for j = 1:n_trial
                id = idx(j);
                y_vals = ones(length(spike_idx_cell{id}),1) * j;
                x_vals = double(spike_idx_cell{id}) ./ app.public_spike_sample_rate * 1E3;
                plot(ax,x_vals,y_vals,'k|','LineWidth',1);
            end
            xlabel(ax, 'Time (ms)')
            ylabel(ax, 'Trial');
            if n_trial < 11
                yticks(ax, [1:n_trial])
            else
                yticks(ax, 'auto');
            end
            xlim(ax, [0, max(app.public_spike_data.time_axis)]);
            ylim(ax, [0, n_trial + 1]);
            hold(ax, 'off');
        end

        function plotScatterPlot(app)

        end
        
        function spike_rate_matrix = calculateSpikeRate(app, spike_matrix, recording_rate)
            spike_rate_matrix = sum(spike_matrix, 2) / (size(spike_matrix,2) / recording_rate);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            t = tiledlayout(app.PlotsPanel,2,1);
            ax1 = nexttile(t);
            plot(ax1,[1:10]);
            ax2 = nexttile(t);
            plot(ax2,[1:30], ':r')
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            experiment_day = datestr(app.ExperimentdayDatePicker.Value, 'mmddyy');
            alignment_querry_1 = sprintf('sln_funcimage.Alignment & ''file_name like "%s_"''', experiment_day);
            querry_1 = eval(alignment_querry_1);
            if querry_1.count == 0
                errordlg(sprintf("Selected day %s doesn't have alignment", experiment_day), '404', 'non-modal');
            else
                alignment_fetch_1 = fetch(querry_1);
                file_name_fetch_1 = alignment_fetch_1(1).file_name;
                source_fetch_1 = unique([alignment_fetch_1.source_id]);
                n = length(source_fetch_1);
                A = '%d';
                source_id_string = join(repmat({A},1,n),', ');
                source_id_string = source_id_string{1};
                cell_name_querry_string = ['sln_cell.CellName & ''file_name = "%s" and source_id in(', source_id_string,')'''];
                cell_name_querry_string = sprintf(cell_name_querry_string, file_name_fetch_1, source_fetch_1);
                cell_name_querry = eval(cell_name_querry_string);
                cell_names_fetch = fetch(cell_name_querry, '*');
                app.CellsListBox.Items = {cell_names_fetch.cell_name};
                app.public_file_name.file_name = file_name_fetch_1;
                app.public_source_id_list = source_fetch_1;
                app.public_alignment_list = alignment_fetch_1;
                app.loadAlignment()
                app.loadVariableParams()
                app.loadData()
            end


        end

        % Double-clicked callback: CellsListBox
        function CellsListBoxDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            loadAlignment(app, app.public_file_name, app.public_source_id_list(item));
            loadVariableParams(app);
            loadData(app);
            plotAllDataNow(app);

        end

        % Double-clicked callback: AlignmentListBox
        function AlignmentListBoxDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            loadVariableParams(app, app.public_alignment_list(item));
            loadData(app, app.public_alignment_list(item));

        end

        % Double-clicked callback: VariableparamsListBox
        function VariableparamsListBoxDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            curr = app.public_variable_params(item);
            idx = find(app.public_epoch_params == curr);
            spike_data = vertcat(app.public_spike_data.raw_data(idx, :));
            cal_data = vertcat(app.public_cal_data.trace_matrix(idx, :));
            t = tiledlayout(app.PlotsPanel,2,1);
            ax1 = nexttile(t, 1);

            if app.SpikePlotSwitch.Value == "Raster"
                app.plotRasterPlot(ax1, app.public_spike_timing, idx)
            else
                for i = 1:length(idx)
                    hold(ax1, "on");
                    plot(ax1,app.public_spike_data.time_axis, spike_data(i, :), 'LineWidth', 2, 'DisplayName', string(i));
                end
                legend(ax1);
                xlabel(ax1, 'Time (ms)');
                ylabel(ax1, 'mV');
            end
            fontsize(ax1, 15, 'points');
            ax2 = nexttile(t, 2);
            for i = 1:length(idx)
                hold(ax2, "on");
                plot(ax2, app.public_cal_data.time_axis, cal_data(i,:), 'LineWidth', 2, 'DisplayName', string(i));
            end
            legend(ax2); fontsize(ax2, 15, 'points');
            xlabel(ax2, 'Time (ms)');
            app.CalciumYLabel(ax2);
            xline(ax1, app.public_spike_data.pre_time, 'r:', 'LineWidth', 1, 'HandleVisibility', 'off');
            xline(ax2, app.public_spike_data.pre_time, 'r:', 'LineWidth', 1, 'HandleVisibility', 'off');
            xline(ax1, app.public_spike_data.pre_time + app.public_spike_data.stim_time, 'r:', 'LineWidth', 1, 'HandleVisibility', 'off');
            xline(ax2, app.public_spike_data.pre_time + app.public_spike_data.stim_time, 'r:', 'LineWidth', 1, 'HandleVisibility', 'off');

        end

        % Button pushed function: ScatterPlotButton
        function ScatterPlotButtonPushed(app, event)
            spike_matrix = zeros(size(app.public_spike_data.raw_data));
            for i = 1:size(spike_matrix,1)
                spike_idx = app.public_spike_timing{i};
                if ~isempty(spike_idx)
                    spike_matrix(i, spike_idx) = 1;
                end
            end

            pre_idx = app.public_spike_data.pre_time * app.public_spike_sample_rate /1e3;
            stim_idx = (app.public_spike_data.pre_time + app.public_spike_data.stim_time) * app.public_spike_sample_rate /1e3;
            pre_spike_matrix = spike_matrix(:, 1: pre_idx);
            stim_spike_matrix = spike_matrix(:, pre_idx + 1 : stim_idx);
            post_idx = min(stim_idx * 2, length(spike_matrix));
            post_spike_matrix = spike_matrix(:, stim_idx + 1 : post_idx);
            sampling_rate = app.public_spike_sample_rate;
            
            pre_stim_spike_rate = app.calculateSpikeRate(pre_spike_matrix, sampling_rate);
            mean_pre_stim_spike = mean(pre_stim_spike_rate);
            stim_spike_rate = app.calculateSpikeRate(stim_spike_matrix, sampling_rate);
            post_spike_rate = app.calculateSpikeRate(post_spike_matrix, sampling_rate);


            delta_stim_spike = stim_spike_rate - mean_pre_stim_spike;
            delta_post_spike = post_spike_rate - mean_pre_stim_spike;

            delta_hehe = max(sum(stim_spike_matrix,2) - mean_pre_stim_spike, sum(post_spike_matrix,2) - mean_pre_stim_spike);
            
            cal_stim_idx = find([app.public_cal_data.time_axis] > app.public_spike_data.pre_time & [app.public_cal_data.time_axis < (app.public_spike_data.pre_time + app.public_spike_data.stim_time + 200)]);
            cal_post_idx = find(app.public_cal_data.time_axis >=   (app.public_spike_data.pre_time + app.public_spike_data.stim_time + 200));
            trace_matrix_stim = app.public_cal_data.trace_matrix(:, cal_stim_idx);
            trace_matrix_post = app.public_cal_data.trace_matrix(:, cal_post_idx);
            max_z = max(trace_matrix_stim, [], 2);
            max_z_post = max(trace_matrix_post, [], 2);

            t = table(delta_stim_spike, delta_post_spike, max_z, max_z_post, app.public_epoch_params, 'VariableNames',{'delta_stim', 'delta_post', 'z', 'zpost' ,'param'});
            temp_1 = repelem({app.CellsListBox.Value}, height(t));
            t.file_name = temp_1';
            file_path = '/Users/letrung/Documents/RGC_Calcium/third_data_populate_table.xlsx';
            writetable(t, file_path, "FileType", "spreadsheet", "WriteMode", "append", "Sheet", 1);
            t2 = tiledlayout(1,2);
            ax3 = nexttile(t2);
            s1 = scatter(ax3,t, 'delta_stim', 'z','filled', 'ColorVariable', 'param');
            s1.SizeData = 100;
            xlabel(ax3,'\Delta Spikes');
            ylabel(ax3, 'Max Calcium');
            title(ax3, 'Stim')
            colorbar(ax3);
            ax4 = nexttile(t2);
            s2 = scatter(ax4,t, 'delta_post', 'zpost','filled', 'ColorVariable', 'param');
            s2.SizeData = 100;
            xlabel(ax4,'\Delta Spikes');
            ylabel(ax4, 'Max Calcium');
            colorbar(ax4);
            title(ax4, 'Tail')
            fontsize(15, "points");

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CalciumImagingBrowserUIFigure = uifigure('Visible', 'off');
            app.CalciumImagingBrowserUIFigure.Name = 'Calcium Imaging Browser';
            app.CalciumImagingBrowserUIFigure.Icon = fullfile(pathToMLAPP, 'pngimg.com - cow_PNG50631.png');
            app.ExperimentdayLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.ExperimentdayLabel.Text = 'Experiment day';
            app.ExperimentdayDatePicker = uidatepicker(app.CalciumImagingBrowserUIFigure);
            app.ExperimentdayDatePicker.DisplayFormat = 'dd.MM.uuuu';
            app.ExperimentdayDatePicker.Value = datetime([2024 7 5]);
            app.CellsListBoxLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.CellsListBoxLabel.Text = 'Cells';
            app.CellsListBox = uilistbox(app.CalciumImagingBrowserUIFigure);
            app.CellsListBox.DoubleClickedFcn = createCallbackFcn(app, @CellsListBoxDoubleClicked, true);
            app.AlignmentListBoxLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.AlignmentListBoxLabel.Text = 'Alignment';
            app.AlignmentListBox = uilistbox(app.CalciumImagingBrowserUIFigure);
            app.AlignmentListBox.DoubleClickedFcn = createCallbackFcn(app, @AlignmentListBoxDoubleClicked, true);
            app.PlotButton = uibutton(app.CalciumImagingBrowserUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.VariableparamsListBoxLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.VariableparamsListBoxLabel.Text = 'Variable params';
            app.VariableparamsListBox = uilistbox(app.CalciumImagingBrowserUIFigure);
            app.VariableparamsListBox.Multiselect = 'on';
            app.VariableparamsListBox.DoubleClickedFcn = createCallbackFcn(app, @VariableparamsListBoxDoubleClicked, true);
            app.VariableparamsListBox.Value = {'Item 1'};
            app.MultipleparamsCheckBox = uicheckbox(app.CalciumImagingBrowserUIFigure);
            app.MultipleparamsCheckBox.Text = 'Multiple params';
            app.PlotCheckBox = uicheckbox(app.CalciumImagingBrowserUIFigure);
            app.PlotCheckBox.Text = 'Plot';
            app.preceedingepochsSpinnerLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.preceedingepochsSpinnerLabel.Text = 'preceeding epochs';
            app.preceedingepochsSpinner = uispinner(app.CalciumImagingBrowserUIFigure);
            app.GoButton = uibutton(app.CalciumImagingBrowserUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.PlotsPanel = uipanel(app.CalciumImagingBrowserUIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.PlotbyepochSwitchLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.PlotbyepochSwitchLabel.Text = 'Plot by epoch';
            app.PlotbyepochSwitch = uiswitch(app.CalciumImagingBrowserUIFigure, 'slider');
            app.EpochSliderLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.EpochSliderLabel.Text = 'Epoch';
            app.EpochSlider = uislider(app.CalciumImagingBrowserUIFigure);
            app.SpinnerLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.CalciumImagingBrowserUIFigure);
            app.Lamp = uilamp(app.CalciumImagingBrowserUIFigure);
            app.CalciumImagingUnitSwitchLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.CalciumImagingUnitSwitchLabel.Text = 'Calcium Imaging Unit';
            app.CalciumImagingUnitSwitch = uiswitch(app.CalciumImagingBrowserUIFigure, 'slider');
            app.CalciumImagingUnitSwitch.Items = {'z-score', 'dF/F0'};
            app.CalciumImagingUnitSwitch.Value = 'z-score';
            app.SpikePlotSwitchLabel = uilabel(app.CalciumImagingBrowserUIFigure);
            app.SpikePlotSwitchLabel.Text = 'Spike Plot';
            app.SpikePlotSwitch = uiswitch(app.CalciumImagingBrowserUIFigure, 'slider');
            app.SpikePlotSwitch.Items = {'Raw trace', 'Raster'};
            app.SpikePlotSwitch.Value = 'Raw trace';
            app.ScatterPlotButton = uibutton(app.CalciumImagingBrowserUIFigure, 'push');
            app.ScatterPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ScatterPlotButtonPushed, true);
            app.ScatterPlotButton.Text = 'Scatter Plot';
            % Show the figure after all components are created
            app.CalciumImagingBrowserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calcium_Imaging_Browser_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalciumImagingBrowserUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalciumImagingBrowserUIFigure)
        end
    end
end