classdef CellTyper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CellTyperUIFigure              matlab.ui.Figure
        LightSteprawdatatracesPanel    matlab.ui.container.Panel
        ShowHideLSButton               matlab.ui.control.StateButton
        SubmitButton                   matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        LoadcellEditField              matlab.ui.control.EditField
        LoadcellButton                 matlab.ui.control.Button
        LoadcellnamesButton            matlab.ui.control.Button
        ForwardButton                  matlab.ui.control.Button
        BackButton                     matlab.ui.control.Button
        CurrentcellEditFieldLabel      matlab.ui.control.Label
        CurrentcellEditField           matlab.ui.control.EditField
        ofLabel                        matlab.ui.control.Label
        ListIndexEdit                  matlab.ui.control.NumericEditField
        ListLengthEdit                 matlab.ui.control.NumericEditField
        RGCtypeDropDownLabel           matlab.ui.control.Label
        RGCtypeDropDown                matlab.ui.control.DropDown
        SpotsMultiSizePanel            matlab.ui.container.Panel
        MovingBarPanel                 matlab.ui.container.Panel
        DLabel                         matlab.ui.control.Label
        VLabel                         matlab.ui.control.Label
        TLabel                         matlab.ui.control.Label
        NLabel                         matlab.ui.control.Label
        ForwardButton_MB               matlab.ui.control.Button
        BackButton_MB                  matlab.ui.control.Button
        DriftingGratingsPanel          matlab.ui.container.Panel
        ForwardButton_DG               matlab.ui.control.Button
        BackButton_DG                  matlab.ui.control.Button
        FlashedBarPanel                matlab.ui.container.Panel
        ContrastResponseFunctionPanel  matlab.ui.container.Panel
    end

    
    properties (Access = private)
        current_cell_id % cell ID of currently loaded cell
        cell_id_list % cell array of cell_ids to work on
        listIndex % index into cell_ind_list
        loadedCellQuery = []; % query of sl.SymphonyRecorded cell for current_cell_id
        connection = []; % DJ connection
        RsetMB = []; % current MB results cell array in case there is more than 1 matching dataset
        RindMB = 1; % index into current Rset for MB
        RsetDG = []; % current DG results cell array in case there is more than 1 matching dataset
        RindDG = 1; % index into current Rset for DG
        DG_multi_per_dataset = false; % does current DG dataset have multiple conditions
        DG_N_in_dataset = 0; % number of conditions in the current DG dataset
    end
    
    methods (Access = private)
        
        function updateDataPlots(app)
            set(app.CellTyperUIFigure,'name','Loading...');
            drawnow;
            
            %set current cell field
            set(app.CurrentcellEditField, 'value', app.current_cell_id);
            app.loadedCellQuery = sl.SymphonyRecordedCell & sprintf('cell_id="%s"', app.current_cell_id);
            
            %set type if known
            curType = sl_mutable.CurrentCellType & sprintf('cell_id="%s"', app.current_cell_id);
            if curType.exists
                set(app.RGCtypeDropDown,'value', curType.fetch1('cell_type'));
            end
            
            %clear everything
            app.DG_multi_per_dataset = false;
            app.DG_N_in_dataset = 0;
            delete(get(app.SpotsMultiSizePanel,'children'));
            delete(get(app.ContrastResponseFunctionPanel,'children'));
            delete(get(app.FlashedBarPanel,'children'));
            ch = get(app.MovingBarPanel,'children');
            for i=1:length(ch)
                if contains(class(ch(i)),'UIAxes')
                    delete(ch(i));
                end
            end
            ch = get(app.DriftingGratingsPanel,'children');
            for i=1:length(ch)
                if contains(class(ch(i)),'UIAxes')
                    delete(ch(i));
                end
            end
            ch = get(app.LightSteprawdatatracesPanel,'children');
            for i=1:length(ch)
                if contains(class(ch(i)),'UIAxes')
                    delete(ch(i));
                end
            end
            set(app.DLabel,'visible', false);
            set(app.VLabel,'visible', false);
            set(app.NLabel,'visible', false);
            set(app.TLabel,'visible', false);
            set(app.BackButton_MB, 'visible', false);
            set(app.ForwardButton_MB, 'visible', false);
            set(app.BackButton_DG, 'visible', false);
            set(app.ForwardButton_DG, 'visible', false);
            set(app.MovingBarPanel, 'title', 'Moving Bar');
            set(app.DriftingGratingsPanel, 'title', 'Drifitng Gratings');
            
            %SMS
            SMS_result = sl_greg.DatasetResult ...
                & sprintf('cell_id="%s"', app.current_cell_id) ...
                & sprintf('dataset_func_name="%s"', 'SMS_spike_analysis') ...
                & sprintf('pipeline_name="%s"', 'typology_SMS_CA');
            if SMS_result.count==0
                SMS_result = sl_shared.DatasetResult ...
                & sprintf('cell_id="%s"', app.current_cell_id) ...
                & sprintf('dataset_func_name="%s"', 'SMS_spike_analysis') ...
                & sprintf('pipeline_name="%s"', 'typology_SMS_CA');
            end
            if SMS_result.count > 1
                disp('Error: multiple matching SMS datasets found');
            elseif SMS_result.count == 1
                R = SMS_result.fetch1('result');
                app.plotSMS(R);
            end
            
            %LightStep
            if ~get(app.ShowHideLSButton,'value')
                SMS_result = sl_greg.DatasetResult ...
                    & sprintf('cell_id="%s"', app.current_cell_id) ...
                    & sprintf('dataset_func_name="%s"', 'SMS_spike_analysis') ...
                    & sprintf('pipeline_name="%s"', 'typology_SMS_CA');
                if SMS_result.count==0
                    SMS_result = sl_shared.DatasetResult ...
                    & sprintf('cell_id="%s"', app.current_cell_id) ...
                    & sprintf('dataset_func_name="%s"', 'SMS_spike_analysis') ...
                    & sprintf('pipeline_name="%s"', 'typology_SMS_CA');
                end
                if SMS_result.count > 1
                    disp('Error: multiple matching SMS datasets found');
                elseif SMS_result.count == 1
                    SMS_dataset = sl.Dataset & SMS_result;
                    epoch_ids = SMS_dataset.fetch1('epoch_ids');
                    R = SMS_result.fetch1('result');
                    app.plotLS(R,epoch_ids);
                end
            end
            
            %MovingBar
            MB_result = sl_greg.DatasetResult ...
                & sprintf('cell_id="%s"', app.current_cell_id) ...
                & sprintf('dataset_func_name="%s"', 'MovingBar_spike_analysis') ...
                & sprintf('pipeline_name="%s"', 'typology_MB_CA');
            if MB_result.count==0
                MB_result = sl_shared.DatasetResult ...
                & sprintf('cell_id="%s"', app.current_cell_id) ...
                & sprintf('dataset_func_name="%s"', 'MovingBar_spike_analysis') ...
                & sprintf('pipeline_name="%s"', 'typology_MB_CA');
            end
            if MB_result.count > 1
                %disp('Error: multiple matching MB datasets found');
                app.RsetMB = MB_result.fetchn('result');
                app.RindMB = 1;
                app.plotMB(app.RsetMB{app.RindMB});
                set(app.BackButton_MB, 'visible', true);
                set(app.ForwardButton_MB, 'visible', true);
            elseif MB_result.count == 1
                R = MB_result.fetch1('result');
                app.plotMB(R);
            end
            
            %Contrast Response
            CR_result = sl_greg.DatasetResult ...
                & sprintf('cell_id="%s"', app.current_cell_id) ...
                & sprintf('dataset_func_name="%s"', 'contrastResponse_spike_analysis') ...
                & sprintf('pipeline_name="%s"', 'typology_CR_CA');
            if CR_result.count==0
                CR_result = sl_shared.DatasetResult ...
                & sprintf('cell_id="%s"', app.current_cell_id) ...
                & sprintf('dataset_func_name="%s"', 'contrastResponse_spike_analysis') ...
                & sprintf('pipeline_name="%s"', 'typology_CR_CA');
            end
            if CR_result.count > 1
                disp('Error: multiple matching CR datasets found');
            elseif CR_result.count == 1
                R = CR_result.fetch1('result');
                ax = uiaxes(app.ContrastResponseFunctionPanel);
                pos = get(app.MovingBarPanel, 'position');
                w = pos(3);
                h = pos(4);
                set(ax,'Position',[5 5 w-5 h-25]);
                errorbar(ax, R.contrast, R.spikeRateStim_baselineSubtraced_mean, R.spikeRateStim_baselineSubtraced_sem,...
                    'Color',[0 0 0],...
                    'LineWidth',2);
                hold(ax,'on');
                xlabel(ax, 'Contrast')
                ylabel(ax, 'Spike count from baseline');
                hold(ax,'off');
            end
            
            %Drifting Gratings
            DG_result = sl_greg.DatasetResult ...
                & sprintf('cell_id="%s"', app.current_cell_id) ...
                & sprintf('dataset_func_name="%s"', 'DriftingGratings_spike_analysis') ...
                & sprintf('pipeline_name="%s"', 'typology_DG_CA');
            if DG_result.count==0
                DG_result = sl_shared.DatasetResult ...
                & sprintf('cell_id="%s"', app.current_cell_id) ...
                & sprintf('dataset_func_name="%s"', 'DriftingGratings_spike_analysis') ...
                & sprintf('pipeline_name="%s"', 'typology_DG_CA');
            end
            if DG_result.count > 1
                disp('Multiple matching DG datasets found');
                app.RsetDG = DG_result.fetchn('result');
                app.RindDG = 1;
                app.plotDG(app.RsetDG{app.RindDG});
                set(app.BackButton_DG, 'visible', true);
                set(app.ForwardButton_DG, 'visible', true);
            elseif DG_result.count == 1
                R = DG_result.fetch1('result');
                app.plotDG(R);
            end
            
            %Flashed Bar
            FB_result = sl_greg.DatasetResult ...
                & sprintf('cell_id="%s"', app.current_cell_id) ...
                & sprintf('dataset_func_name="%s"', 'FlashedBar_spike_analysis') ...
                & sprintf('pipeline_name="%s"', 'typology_FB_CA');
            if FB_result.count==0
                FB_result = sl_shared.DatasetResult ...
                & sprintf('cell_id="%s"', app.current_cell_id) ...
                & sprintf('dataset_func_name="%s"', 'FlashedBar_spike_analysis') ...
                & sprintf('pipeline_name="%s"', 'typology_FB_CA');
            end
            if FB_result.count > 1
                disp('Error: multiple matching MB datasets found');
            elseif FB_result.count == 1
                R = FB_result.fetch1('result');
                app.plotFB(R);
            end
            
            set(app.CellTyperUIFigure,'name','CellTyper');
            drawnow;
        end
        
        function plotMB(app,R)
            ax = uiaxes(app.MovingBarPanel);
            pos = get(app.MovingBarPanel, 'position');
            if isfield(R, 'barSpeed') && isfield(R, 'barLength') && isfield(R, 'barWidth')
                set(app.MovingBarPanel, 'title', sprintf('Moving Bar: speed %d, length %d, width %d', R.barSpeed, R.barLength, R.barWidth));
            end
            w = pos(3);
            h = pos(4);
            set(ax,'Position',[12 20 w-25 h-50]);
            set(ax,'XAxisLocation','origin');
            set(ax,'YAxisLocation','origin');
            p = polarerror(deg2rad(R.barAngle),R.spikeCountFull_mean,R.spikeCountFull_sem);
            set(p(1),'Parent',ax,'color','b','LineWidth',2);
            set(p(2),'Parent',ax,'color','b','LineWidth',2);
            p = polarerror(deg2rad(R.barAngle),R.spikeCountON_mean,R.spikeCountON_sem);
            set(p(1),'Parent',ax,'color',[0 1 1],'LineWidth',1);
            set(p(2),'Parent',ax,'color',[0 1 1],'LineWidth',1);
            p = polarerror(deg2rad(R.barAngle),R.spikeCountOFF_mean,R.spikeCountOFF_sem);
            set(p(1),'Parent',ax,'color',[0 0 0],'LineWidth',1);
            set(p(2),'Parent',ax,'color',[0 0 0],'LineWidth',1);
            set(ax,'Ytick',get(ax,'xtick'));
            curX = get(ax,'Xlim');
            curY = get(ax,'Ylim');
            maxVal = max(abs([curX, curY]));
            set(ax,'Xlim',[-maxVal maxVal],'Ylim',[-maxVal maxVal]);
            
            thisCell = sl.MeasuredRetinalCell & sprintf('cell_id="%s"', app.current_cell_id);
            thisSymCell = sl.SymphonyRecordedCell & sprintf('cell_id="%s"', app.current_cell_id);
            posx = thisSymCell.fetch1('position_x');
            posy = thisSymCell.fetch1('position_y');
            whichEye = thisCell.fetch1('side');
            if strcmp(whichEye, 'Right')
                set(ax,'xdir','reverse');
            end
            if (posx ~= 0 || posy ~=0) && ...
                    strcmp(whichEye, 'Right') || strcmp(whichEye, 'Left')
                set(app.DLabel,'visible', true);
                set(app.VLabel,'visible', true);
                set(app.NLabel,'visible', true);
                set(app.TLabel,'visible', true);
            end
        end
        
        function plotSMS(app,R)
            ax = uiaxes(app.SpotsMultiSizePanel, 'XLim',[0 inf]);
            errorbar(ax, R.spotSize, R.spikeRateStim_baselineSubtraced_mean, R.spikeRateStim_baselineSubtraced_sem,...
                'Color',[0 1 1],...
                'LineWidth',2);
            hold(ax,'on');
            xlabel(ax, 'Spot size (microns)')
            ylabel(ax, 'Spike count from baseline');
            pos = get(app.SpotsMultiSizePanel, 'position');
            w = pos(3);
            h = pos(4);
            set(ax,'Position',[5 5 w-5 h-25]);
            errorbar(ax, R.spotSize, R.spikeRatePost_baselineSubtraced_mean, R.spikeRatePost_baselineSubtraced_sem,...
                'Color',[0 0 0],...
                'LineWidth',2);
            hold(ax,'off');
        end
        
        function plotFB(app,R)
            ax = uiaxes(app.FlashedBarPanel, 'XLim',[0 inf]);
            errorbar(ax, R.barAngle, R.spikeRate_mean, R.spikeRate_sem,...
                'Color',[0 0 0],...
                'LineWidth',2);
            hold(ax,'on');
            xlabel(ax, 'Bar angle (degrees)')
            ylabel(ax, 'Spike rate from baseline');
            pos = get(app.SpotsMultiSizePanel, 'position');
            w = pos(3);
            h = pos(4);
            set(ax,'Position',[5 5 w-5 h-25]);
            hold(ax,'off');
        end
        
        function plotDG(app,R)
            if isfield(R,'paramSet')
                app.DG_N_in_dataset = length(R.paramSet);
                %this single result struct contains multiple conditions
                app.RsetDG = R;
                ax = uiaxes(app.DriftingGratingsPanel);
                pos = get(app.DriftingGratingsPanel, 'position');
                %TODO title with params
                curParams = R.paramSet(app.RindDG);
                fields = fieldnames(curParams);
                title_suffix = [];
                for p=1:length(fields)
                    if contains(fields{p}, 'Width')
                        title_suffix = [title_suffix, sprintf('width:%d ', curParams.(fields{p}))];
                    elseif contains(fields{p}, 'Speed')
                        title_suffix = [title_suffix, sprintf('speed:%d ', curParams.(fields{p}))];
                    elseif contains(fields{p}, 'contrast')
                        title_suffix = [title_suffix, sprintf('contrast:%f ', curParams.(fields{p}))];
                    end
                end
                set(app.DriftingGratingsPanel,'title',['Drifting Gratings: ' title_suffix]);
                w = pos(3);
                h = pos(4);
                set(ax,'Position',[12 20 w-25 h-50]);
                set(ax,'XAxisLocation','origin');
                set(ax,'YAxisLocation','origin');
                p = polar(deg2rad(R.gratingAngle{app.RindDG}),R.cycleAvgPeakFR{app.RindDG}');
                set(p,'Parent',ax,'color','k','LineWidth',2);
                set(ax,'Ytick',get(ax,'xtick'));
                curX = get(ax,'Xlim');
                curY = get(ax,'Ylim');
                maxVal = max(abs([curX, curY]));
                set(ax,'Xlim',[-maxVal maxVal],'Ylim',[-maxVal maxVal]);
                set(app.BackButton_DG, 'visible', true);
                set(app.ForwardButton_DG, 'visible', true);
                app.DG_multi_per_dataset = true;
            else
                ax = uiaxes(app.DriftingGratingsPanel);
                pos = get(app.DriftingGratingsPanel, 'position');
                set(app.DriftingGratingsPanel, 'title', sprintf('Drifting gratings: %d μm, %d cycles/s', R.cycleHalfWidth, R.temporalFreq));
                w = pos(3);
                h = pos(4);
                set(ax,'Position',[12 20 w-25 h-50]);
                set(ax,'XAxisLocation','origin');
                set(ax,'YAxisLocation','origin');
                p = polar(deg2rad(R.gratingAngle),R.cycleAvgPeakFR');
                set(p,'Parent',ax,'color','k','LineWidth',2);
                set(ax,'Ytick',get(ax,'xtick'));
                curX = get(ax,'Xlim');
                curY = get(ax,'Ylim');
                maxVal = max(abs([curX, curY]));
                set(ax,'Xlim',[-maxVal maxVal],'Ylim',[-maxVal maxVal]);
            end
        end
        
        function plotLS(app, R, epoch_ids)
            ind80 = find(R.spotSize>80,1);
            ind200 = find(R.spotSize>200,1);
            ind1200 = find(R.spotSize>=1200,1);
            ax1 = [];
            ax2 = [];
            ax3 = [];
            if ~isempty(ind80)
                epInd_80 = find(R.key_ind(ind80,:));
                [timeAxis, data80] = epochRawData(app.current_cell_id, epoch_ids(epInd_80));
                ax1 = uiaxes(app.LightSteprawdatatracesPanel);
                cla(ax1, 'reset');
                set(ax1,'YTick',[],'XTick',[]);
                plot(ax1,timeAxis,data80(1,:),"Color",[0 0 0]);
                title(ax1,sprintf('%d μm', R.spotSize(ind80)));
            end
            if ~isempty(ind200)
                epInd_200 = find(R.key_ind(ind200,:));
                [~, data200] = epochRawData(app.current_cell_id, epoch_ids(epInd_200));
                ax2 = uiaxes(app.LightSteprawdatatracesPanel,'YTick',[],'Xtick',[]);
                cla(ax2, 'reset');
                set(ax2,'YTick',[],'XTick',[]);
                plot(ax2,timeAxis,data200(1,:),"Color",[0 0 0]);
                title(ax2,sprintf('%d μm', R.spotSize(ind200)));
            end
            if ~isempty(ind1200)
                epInd_1200 = find(R.key_ind(ind1200,:));
                [~, data1200] = epochRawData(app.current_cell_id, epoch_ids(epInd_1200));
                ax3 = uiaxes(app.LightSteprawdatatracesPanel);
                cla(ax3, 'reset');
                set(ax3,'YTick',[],'Xtick',[0 0.5 1 1.5]);
                plot(ax3,timeAxis,data1200(1,:),"Color",[0 0 0]);
                title(ax3,sprintf('%d μm', R.spotSize(ind1200)));
                xlabel(ax3,'Time (s)');
            end
            
            pos = get(app.LightSteprawdatatracesPanel, 'position');
            w = pos(3);
            h = pos(4);
            
            if ~isempty(ax1)
                set(ax1,'Position',[5 5+3*h/4-25 w-5 h/4]);
            end
            if ~isempty(ax2)
                set(ax2,'Position',[5 5+h/2-20 w-5 h/4]);
            end
            if ~isempty(ax3)
                set(ax3,'Position',[5 5 w-5 h/2-15]);
            end
            
            %put axes underneath show/hide button
            %ch = get(app.LightSteprawdatatracesPanel, 'children');
            %set(app.LightSteprawdatatracesPanel, 'children', flip(ch));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, cell_list)
            try
                app.connection = dj.conn;
            catch
                disp('Unable to establish datajoint connection.')
                app.CellTyperUIFigureCloseRequest();
            end
            
            RGCs = sl.CellType & 'cell_class="RGC"';
            RGC_type_names = RGCs.fetchn('name_full');
            set(app.RGCtypeDropDown, 'items', RGC_type_names);
            set(app.RGCtypeDropDown, 'value', 'unknown');
            set(app.BackButton_MB, 'visible', false);
            set(app.ForwardButton_MB, 'visible', false);
            
            if nargin==2
                app.cell_id_list = cell_list;
                app.listIndex = 1;
                set(app.ListIndexEdit,'value',1);
                set(app.ListLengthEdit,'value',length(app.cell_id_list));
                app.current_cell_id = app.cell_id_list{1};
                app.updateDataPlots();
            end
            
        end

        % Button pushed function: LoadcellButton
        function LoadcellButtonPushed(app, event)
            cell_id = get(app.LoadcellEditField, 'value');
            try
                app.loadedCellQuery = sl.SymphonyRecordedCell & sprintf('cell_id="%s"', cell_id);
                app.current_cell_id = cell_id;
                app.cell_id_list = {};
                app.listIndex = 1;
                set(app.ListIndexEdit, 'value', 1);
                set(app.ListLengthEdit, 'value', 1);
            catch
                app.loadedCellQuery = [];
                fprintf('Unable to load cell %s from database', cell_id);
            end
            app.updateDataPlots();
        end

        % Close request function: CellTyperUIFigure
        function CellTyperUIFigureCloseRequest(app, event)
            if ~isempty(app.connection) && app.connection.isConnected
                app.connection.close();
            end
            delete(app);
        end

        % Button pushed function: LoadcellnamesButton
        function LoadcellnamesButtonPushed(app, event)
            [fname, fpath] = uigetfile('*.txt','Select plain text file with cell names.');
            if fname
                app.cell_id_list = importdata([fpath, fname]);
                app.listIndex = 1;
                set(app.ListIndexEdit,'value',1);
                set(app.ListLengthEdit,'value',length(app.cell_id_list));
                app.current_cell_id = app.cell_id_list{1};
                app.updateDataPlots();
            end
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            if app.listIndex < length(app.cell_id_list)
                app.listIndex = app.listIndex+1;
                set(app.ListIndexEdit,'value',app.listIndex);
                app.current_cell_id = app.cell_id_list{app.listIndex};
                app.updateDataPlots();
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            if app.listIndex > 1
                app.listIndex = app.listIndex-1;
                set(app.ListIndexEdit,'value',app.listIndex);
                app.current_cell_id = app.cell_id_list{app.listIndex};
                app.updateDataPlots();
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CellTyperUIFigureCloseRequest();
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            C = app.connection;
            C.startTransaction;
            
            try
                key = app.loadedCellQuery.fetch();
                key.user_name = C.user;
                key.cell_class = 'RGC';
                key.name_full = get(app.RGCtypeDropDown, 'Value');
                
                disp('Assigning cell type with key:');
                key
                insert(sl.CellEventAssignType,key);
                
                key = rmfield(key,'user_name');
                key.cell_type = key.name_full;
                key = rmfield(key,'name_full');
                insert(sl_mutable.CurrentCellType, key, 'REPLACE');
                
                C.commitTransaction;
                disp('Cell assignment successful');
            catch ME
                C.cancelTransaction;
                disp('Cell assignment failed');
                rethrow(ME);
            end
        end

        % Button pushed function: ForwardButton_MB
        function ForwardButton_MBPushed(app, event)
            L = length(app.RsetMB);
            if app.RindMB < L
                app.RindMB = app.RindMB+1;
                app.plotMB(app.RsetMB{app.RindMB});
            end
        end

        % Button pushed function: BackButton_MB
        function BackButton_MBPushed(app, event)
            if app.RindMB > 1
                app.RindMB = app.RindMB-1;
                app.plotMB(app.RsetMB{app.RindMB});
            end
        end

        % Button pushed function: BackButton_DG
        function BackButton_DGPushed(app, event)
            if app.DG_multi_per_dataset
                if app.RindDG > 1
                    app.RindDG = app.RindDG-1;
                    app.plotDG(app.RsetDG);
                end
            else
                if app.RindDG > 1
                    app.RindDG = app.RindDG-1;
                    app.plotDG(app.RsetDG{app.RindDG});
                end
            end
        end

        % Button pushed function: ForwardButton_DG
        function ForwardButton_DGPushed(app, event)
            if app.DG_multi_per_dataset
                if app.RindDG < app.DG_N_in_dataset
                    app.RindDG = app.RindDG+1;
                    app.plotDG(app.RsetDG);
                end
            else
                L = length(app.RsetDG);
                if app.RindDG < L
                    app.RindDG = app.RindDG+1;
                    app.plotDG(app.RsetDG{app.RindDG});
                end
            end
        end

        % Value changed function: ShowHideLSButton
        function ShowHideLSButtonValueChanged(app, event)
            value = app.ShowHideLSButton.Value;
            if value
                set(app.ShowHideLSButton, 'text', 'Show');
            else
                set(app.ShowHideLSButton, 'text', 'Hide');
            end
            app.updateDataPlots();
        end

        % Value changed function: ListIndexEdit
        function ListIndexEditValueChanged(app, event)
            value = app.ListIndexEdit.Value;
            if value > 0 && value <= length(app.cell_id_list)
                app.listIndex = value;
                app.current_cell_id = app.cell_id_list{app.listIndex};
                app.updateDataPlots();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CellTyperUIFigure = uifigure('Visible', 'off');
            app.CellTyperUIFigure.Name = 'CellTyper';
            app.CellTyperUIFigure.CloseRequestFcn = createCallbackFcn(app, @CellTyperUIFigureCloseRequest, true);
            app.LightSteprawdatatracesPanel = uipanel(app.CellTyperUIFigure);
            app.LightSteprawdatatracesPanel.Title = 'Light Step raw data traces';
            app.ShowHideLSButton = uibutton(app.LightSteprawdatatracesPanel, 'state');
            app.ShowHideLSButton.ValueChangedFcn = createCallbackFcn(app, @ShowHideLSButtonValueChanged, true);
            app.ShowHideLSButton.Text = 'Hide';
            app.SubmitButton = uibutton(app.CellTyperUIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.CancelButton = uibutton(app.CellTyperUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.LoadcellEditField = uieditfield(app.CellTyperUIFigure, 'text');
            app.LoadcellButton = uibutton(app.CellTyperUIFigure, 'push');
            app.LoadcellButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcellButtonPushed, true);
            app.LoadcellButton.Text = 'Load cell';
            app.LoadcellnamesButton = uibutton(app.CellTyperUIFigure, 'push');
            app.LoadcellnamesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcellnamesButtonPushed, true);
            app.LoadcellnamesButton.Text = 'Load cell names';
            app.ForwardButton = uibutton(app.CellTyperUIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = '→';
            app.BackButton = uibutton(app.CellTyperUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = '←';
            app.CurrentcellEditFieldLabel = uilabel(app.CellTyperUIFigure);
            app.CurrentcellEditFieldLabel.Text = 'Current cell';
            app.CurrentcellEditField = uieditfield(app.CellTyperUIFigure, 'text');
            app.CurrentcellEditField.Editable = 'off';
            app.ofLabel = uilabel(app.CellTyperUIFigure);
            app.ofLabel.Text = 'of';
            app.ListIndexEdit = uieditfield(app.CellTyperUIFigure, 'numeric');
            app.ListIndexEdit.Limits = [1 Inf];
            app.ListIndexEdit.RoundFractionalValues = 'on';
            app.ListIndexEdit.ValueChangedFcn = createCallbackFcn(app, @ListIndexEditValueChanged, true);
            app.ListIndexEdit.Value = 1;
            app.ListLengthEdit = uieditfield(app.CellTyperUIFigure, 'numeric');
            app.ListLengthEdit.Limits = [1 Inf];
            app.ListLengthEdit.RoundFractionalValues = 'on';
            app.ListLengthEdit.Editable = 'off';
            app.ListLengthEdit.Value = 1;
            app.RGCtypeDropDownLabel = uilabel(app.CellTyperUIFigure);
            app.RGCtypeDropDownLabel.Text = 'RGC type';
            app.RGCtypeDropDown = uidropdown(app.CellTyperUIFigure);
            app.SpotsMultiSizePanel = uipanel(app.CellTyperUIFigure);
            app.SpotsMultiSizePanel.Title = 'Spots Multi Size';
            app.MovingBarPanel = uipanel(app.CellTyperUIFigure);
            app.MovingBarPanel.Title = 'Moving Bar';
            app.DLabel = uilabel(app.MovingBarPanel);
            app.DLabel.Visible = 'off';
            app.DLabel.Text = 'D';
            app.VLabel = uilabel(app.MovingBarPanel);
            app.VLabel.Visible = 'off';
            app.VLabel.Text = 'V';
            app.TLabel = uilabel(app.MovingBarPanel);
            app.TLabel.Visible = 'off';
            app.TLabel.Text = 'T';
            app.NLabel = uilabel(app.MovingBarPanel);
            app.NLabel.Visible = 'off';
            app.NLabel.Text = 'N';
            app.ForwardButton_MB = uibutton(app.MovingBarPanel, 'push');
            app.ForwardButton_MB.ButtonPushedFcn = createCallbackFcn(app, @ForwardButton_MBPushed, true);
            app.ForwardButton_MB.Text = '→';
            app.BackButton_MB = uibutton(app.MovingBarPanel, 'push');
            app.BackButton_MB.ButtonPushedFcn = createCallbackFcn(app, @BackButton_MBPushed, true);
            app.BackButton_MB.Text = '←';
            app.DriftingGratingsPanel = uipanel(app.CellTyperUIFigure);
            app.DriftingGratingsPanel.Title = 'Drifting Gratings';
            app.ForwardButton_DG = uibutton(app.DriftingGratingsPanel, 'push');
            app.ForwardButton_DG.ButtonPushedFcn = createCallbackFcn(app, @ForwardButton_DGPushed, true);
            app.ForwardButton_DG.Text = '→';
            app.BackButton_DG = uibutton(app.DriftingGratingsPanel, 'push');
            app.BackButton_DG.ButtonPushedFcn = createCallbackFcn(app, @BackButton_DGPushed, true);
            app.BackButton_DG.Text = '←';
            app.FlashedBarPanel = uipanel(app.CellTyperUIFigure);
            app.FlashedBarPanel.Title = 'Flashed Bar';
            app.ContrastResponseFunctionPanel = uipanel(app.CellTyperUIFigure);
            app.ContrastResponseFunctionPanel.Title = 'Contrast Response Function';
            % Show the figure after all components are created
            app.CellTyperUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellTyper(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CellTyperUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CellTyperUIFigure)
        end
    end
end