classdef Curator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CuratorUIFigure                matlab.ui.Figure
        QuickCheatLabel                matlab.ui.control.Label
        QuickCheatTable                matlab.ui.control.Table
        CellTypeSwitch                 matlab.ui.control.RockerSwitch
        CurrentepochEditField          matlab.ui.control.NumericEditField
        CurrentepochEditFieldLabel     matlab.ui.control.Label
        RunfilterButton                matlab.ui.control.Button
        QuickselectepochgroupDropDown  matlab.ui.control.DropDown
        QuickselectepochgroupDropDownLabel  matlab.ui.control.Label
        EpochnumberSlider              matlab.ui.control.Slider
        EpochnumberSliderLabel         matlab.ui.control.Label
        LoadcellButton                 matlab.ui.control.Button
        WorkingcellEditField           matlab.ui.control.EditField
        WorkingcellEditFieldLabel      matlab.ui.control.Label
        FilterconstructionLabel        matlab.ui.control.Label
        ParametersforselectedepochLabel  matlab.ui.control.Label
        SelectedValTable               matlab.ui.control.Table
        ParmeterswithmultiplevaluesLabel  matlab.ui.control.Label
        MultiValTable                  matlab.ui.control.Table
        FilterTable                    matlab.ui.control.Table
        FilterpatternstringEditField   matlab.ui.control.EditField
        FilterpatternstringEditFieldLabel  matlab.ui.control.Label
        DatasetsDropDown               matlab.ui.control.DropDown
        DatasetsDropDownLabel          matlab.ui.control.Label
        DeletedatasetButton            matlab.ui.control.Button
        RenamedatasetButton            matlab.ui.control.Button
        SavedatasetButton              matlab.ui.control.Button
        DatachannelDropDown            matlab.ui.control.DropDown
        DatachannelDropDownLabel       matlab.ui.control.Label
        XaxisDropDown                  matlab.ui.control.DropDown
        XaxisDropDownLabel             matlab.ui.control.Label
        RawAxes                        matlab.ui.control.UIAxes
        DiaryAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        loaded_cell % query for currently loaded cell
        all_epoch_ids % all epoch_ids for loaded cell
        epoch_ids % epoch_ids matching current query
        loaded_epoch %query for currently loaded epoch
        epoch_selection_index
        diary_x % x points in diary plot
        diary_y % y points in diary plot
        operators = {' ', '=','>','<','>=','<=','!=', 'between'};
        params_to_remove = {...
            'file_name', ...
            'source_id', ...
            'cell_id', ...
            'spot_sizes', ...
            'epoch_group_id', ...
            'epoch_block_id', ...
            'number_of_cycles', ...
            'epoch_block_start_time', ...
            'epoch_block_end_time', ...
            'epoch_group_start_time', ...
            'epoch_group_end_time', ...
            'epoch_start_time' ...
            };
        valsMap % map of all unique parameter values for each parameter
        non_prot_params_map % map of parameters for each epoch, not including the ones corresponding to protocols
        prot_params_map %map of maps of protocols params
        epoch_1_start % start time of epochs_ids(1)
        epoch_1_all_start % start time of first epoch in file for this cell
        epoch_ids_in_datasets % epoch ids in any dataset 
        DataGrouperApp % DataGroupper app
        NoCuratedForSpikeApp; % NoCurated
        raw_plot % Description
        raw_lines
    end

    methods (Access = private)

        function init(app)
            app.prot_params_map = containers.Map; %map of maps of protocols params
        end

        function loadCell(app, cellname)
            s.file_name = cellname(1:7);
            s.rig_name = cellname(7);
            s.cell_number = str2double(cellname(9:end));
            if strcmp(app.CellTypeSwitch.Value,'brain')
                q = sln_symphony.Experiment * sln_symphony.ExperimentBrainCell & s;
            else
                q = sln_symphony.Experiment * sln_symphony.ExperimentCell & s;
            end
            if ~q.exists
                errordlg(sprintf('%s not found', cellname));
            elseif q.count > 1
                errordlg(sprintf('%s: more than 1 matching cell', cellname));
            else
                cla(app.RawAxes);

                app.raw_plot = plot(app.RawAxes,nan,'k');
                app.raw_lines{1} = line(app.RawAxes, [0, 0], [nan,nan], 'color', 'r');
                app.raw_lines{2} = line(app.RawAxes, [0, 0], [nan,nan], 'color', 'r', 'linestyle','--');
                
                cla(app.DiaryAxes);
                drawnow;
                app.CuratorUIFigure.Name = 'loading...';
                app.loaded_cell = q;

                %check for sln_cell.Cell. If not there, make it
                if strcmp(app.CellTypeSwitch.Value,'brain')
                    cell_query = sln_cell.BrainCell & proj(app.loaded_cell);    
                else
                    cell_query = sln_cell.Cell & proj(app.loaded_cell);
                end
                if ~cell_query.exists
                    if strcmp(app.CellTypeSwitch.Value,'brain')
                        cell_insert_query = sln_symphony.ExperimentBrainCell * ...
                            proj(sln_symphony.ExperimentBrain,'*','source_id->brain_id') * ...
                            proj(sln_symphony.ExperimentBrainSlice,'*','source_id->brain_slice_id') * ...
                            proj(sln_animal.Animal,'*','source_id->animal_source_id') & ...
                            proj(app.loaded_cell);
                        key_cell = rmfield(fetch(cell_insert_query),{'brain_id','brain_slice_id'});
                        insert(sln_cell.BrainCell,key_cell);
                        fprintf('Inserted sln_cell.BrainCell for %s\n',cellname);
                    else
                        cell_insert_query = sln_symphony.ExperimentCell * ...
                            proj(sln_symphony.ExperimentRetina,'*','source_id->retina_id') * ...
                            proj(sln_animal.Animal,'*','source_id->animal_source_id') & ...
                            proj(app.loaded_cell);
                        key_cell = rmfield(fetch(cell_insert_query),'retina_id');
                        insert(sln_cell.Cell,key_cell);
                        fprintf('Inserted sln_cell.Cell for %s\n',cellname);
                    end

                    %add RetinalCell: TODO - update this to do something
                    %different for brain or other types of cells
                    if strcmp(app.CellTypeSwitch.Value,'retina')
                        key_retinal_cell = fetch(cell_insert_query,'side','x','y');
                        cell_unid = fetch1(sln_cell.Cell & sprintf('animal_id=%d',key_retinal_cell.animal_id) & 'LIMIT 1 PER animal_id ORDER BY cell_unid DESC','cell_unid');
                        key_retinal_cell = rmfield(key_retinal_cell,{'file_name','source_id','retina_id'});
                        key_retinal_cell.cell_unid = cell_unid;
                        if key_retinal_cell.x == 0 && key_retinal_cell.y == 0
                            key_retinal_cell = rmfield(key_retinal_cell,{'x','y'});
                        end
                        insert(sln_cell.RetinalCell,key_retinal_cell);
                        fprintf('Inserted sln_cell.RetinalCell for %s\n',cellname);
                    end

                    [data_levels, user_params_table_names] = sln_symphony.get_all_user_param_in_db;
                    s = fetch(cell_insert_query);
                    for i = 1:length(data_levels)
                        table_name = sprintf('sln_symphony.UserParam%s%s', data_levels{i}, user_params_table_names{i});

                        if strcmp(app.CellTypeSwitch.Value,'brain')
                            switch data_levels{i}
                                case 'Experiment'
                                    s_for_insert = rmfield(s, {'animal_id', 'brain_id', 'brain_slice_id', 'source_id'});

                                case 'Animal'
                                    s_for_insert = rmfield(s, {'file_name', 'source_id', 'brain_id', 'brain_slice_id'});

                                case 'Cell'
                                    s_for_insert = rmfield(s, {'animal_id', 'brain_id', 'brain_slice_id'});

                                case 'Dataset'
                                    unlabeled_items = fetch(sln_symphony.Dataset * cell_insert_query);
                                    s_for_insert = rmfield(unlabeled_items, {'brain_id', 'brain_slice_id', 'animal_id'});

                                case 'Epoch'
                                    unlabeled_items = fetch(aka.Epoch * cell_insert_query);
                                    s_for_insert = rmfield(unlabeled_items, {'brain_id', 'brain_slice_id', 'animal_id'});
                            end

                        else
                            switch data_levels{i}
                                case 'Experiment'
                                    s_for_insert = rmfield(s, {'animal_id', 'source_id', 'retina_id'});

                                case 'Animal'
                                    s_for_insert = rmfield(s, {'file_name', 'source_id', 'retina_id'});

                                case 'Cell'
                                    s_for_insert = rmfield(s, {'animal_id', 'retina_id'});

                                case 'Dataset'
                                    unlabeled_items = fetch(sln_symphony.Dataset * cell_insert_query);
                                    s_for_insert = rmfield(unlabeled_items, {'retina_id', 'animal_id'});

                                case 'Epoch'
                                    unlabeled_items = fetch(aka.Epoch * cell_insert_query);
                                    s_for_insert = rmfield(unlabeled_items, {'retina_id', 'animal_id'});
                            end
                            
                        end
                        insert(eval(table_name),s_for_insert,'IGNORE');
                    end

                    sln_cell.add_unclassified_events
                end
                
                if strcmp(app.CellTypeSwitch.Value,'brain')
                    cell_unid = fetch1(sln_cell.BrainCell & proj(app.loaded_cell), 'cell_unid');
                else
                    cell_unid = fetch1(sln_cell.Cell & proj(app.loaded_cell), 'cell_unid');
                end

                if strcmp(app.CellTypeSwitch.Value,'brain')
                    %check for sln_cell.AssignType. If not there, make it
                    assign_query = sln_cell.BrainCellEvent & sln_cell.AssignBrainCellType & sprintf('cell_unid=%d',cell_unid);
                    if ~assign_query.exists
                        key_cell = struct;
                        key_cell.cell_class = 'other';
                        key_cell.cell_type = 'unclassified';
                        key_cell.user_name = 'Unknown';
                        key_cell.cell_unid = cell_unid;
                        sln_cell.add_event(key_cell,'AssignBrainCellType');
                        fprintf('Inserted blank cell assignment for %s\n',cellname);
                    end
                else
                    %check for sln_cell.AssignType. If not there, make it
                    assign_query = sln_cell.CellEvent & sln_cell.AssignType & sprintf('cell_unid=%d',cell_unid);
                    if ~assign_query.exists
                        key_cell = struct;
                        key_cell.cell_class = 'other';
                        key_cell.cell_type = 'unclassified';
                        key_cell.user_name = 'Unknown';
                        key_cell.cell_unid = cell_unid;
                        sln_cell.add_event(key_cell,'AssignType');
                        fprintf('Inserted blank cell assignment for %s\n',cellname);
                    end
                end

                app.WorkingcellEditField.Value = cellname;
                app.all_epoch_ids = sort(fetchn(aka.Epoch & app.loaded_cell, 'epoch_id'), 'ascend');
                app.epoch_ids = app.all_epoch_ids;
                app.loaded_epoch = aka.Epoch & app.loaded_cell & sprintf('epoch_id = %d', app.epoch_ids(1));
                try
                    app.EpochnumberSlider.Limits = [app.epoch_ids(1), app.epoch_ids(end)];
                    app.EpochnumberSlider.Value = app.epoch_ids(1);
                    app.EpochnumberSlider.MajorTicks = round(linspace(app.epoch_ids(1), app.epoch_ids(end), 10));
                catch
                
                end
                
                if strcmp(app.CellTypeSwitch.Value,'retina')
                    querry_cell = struct();
                    querry_cell.file_name = cellname(1:7);
                    querry_cell.rig_name = cellname(7);
                    querry_cell.cell_number = str2double(cellname(9:end));
                    epoch_blocks_querry = sln_symphony.ExperimentEpoch * sln_symphony.ExperimentEpochBlock * sln_symphony.ExperimentCell & querry_cell;
                    epoch_blocks = fetch(epoch_blocks_querry, '*');
                    epoch_blocks_ids = unique([epoch_blocks.epoch_block_id]);
                    epoch_blocks_start = zeros(size(epoch_blocks_ids));
                    epoch_blocks_end = zeros(size(epoch_blocks_ids));
                    epoch_block_prots = {};
                    for i = 1:length(epoch_blocks_ids)
                        idx = find([epoch_blocks.epoch_block_id] == epoch_blocks_ids(i));
                        current_epochs = epoch_blocks(idx);
                        epoch_blocks_start(i) = min([current_epochs.epoch_id]);
                        epoch_blocks_end(i) = max([current_epochs.epoch_id]);
                        epoch_block_prots{i} = current_epochs(1).protocol_name;
                    end
                    t = table(epoch_blocks_ids', epoch_blocks_start', epoch_blocks_end', epoch_block_prots', 'VariableNames', {'Epoch Block ID','Start', 'End', 'Protocol Name'});
                    app.QuickCheatTable.Data = t;

                end

                app.epoch_1_all_start = fetch1(app.loaded_epoch,'epoch_start_time');

                app.epoch_selection_index = 1;
                app.fetchEpochParams();
                app.init_filter_table();
                app.store_dataset_epochs();
                app.updateDiaryPlot(true);
                app.plotCurrentEpoch();
                app.fillMultiParamsTable();
                app.fillSingleParamsTable();
                app.updateDatasetMenu();
                app.CuratorUIFigure.Name = sprintf('Curator: %d of %d epochs selected', length(app.epoch_ids), length(app.all_epoch_ids));
            end
        end

        function plotCurrentEpoch(app)
            % cla(app.RawAxes);
            % title(app.RawAxes, 'plotting...');
            % drawnow;
            % app.CuratorUIFigure.Name = 'plotting...';
            if strcmp(fetch1(sln_symphony.ExperimentEpochBlock & proj(app.loaded_epoch),'protocol_name'),'set_mean_level') %HACK
                return
            end
            [timeAxis, data] = sln_symphony.getEpochRawData(app.loaded_epoch,app.DatachannelDropDown.Value);
            if isempty(data)
                return;
            end
            epoch_number = fetch1(app.loaded_epoch, 'epoch_id');

            if strcmp(app.CellTypeSwitch.Value,'brain')
                recording_mode = fetch1(sln_symphony.ExperimentBrainElectrode & app.loaded_epoch & sprintf('channel_name="%s"', app.DatachannelDropDown.Value), 'recording_mode');
            else
                recording_mode = fetch1(sln_symphony.ExperimentElectrode & app.loaded_epoch & sprintf('channel_name="%s"', app.DatachannelDropDown.Value), 'recording_mode');
            end
    
            if strcmp(recording_mode,'Current clamp') %% THIS IS STILL BACKWARDS!
                units = 'pA';
            else
                units = 'mV';
            end

            protocol_name = sqlProtName2ProtName(fetch1(sln_symphony.ExperimentEpochBlock & app.loaded_epoch, 'protocol_name'));
            block_params = fetch(aka.BlockParams(protocol_name) & app.loaded_epoch, '*');
            if isempty(block_params) %HACK
                block_params = fetch(aka.BlockParams('SpotsMultiSizeClassify') & app.loaded_epoch, '*');
            end

            % plot(app.RawAxes,timeAxis,data,'k');
            set(app.raw_plot,'xdata',timeAxis,'ydata',data);
            % hold(app.RawAxes,'on');
            if isfield(block_params, 'pre_time')
                if ~isnan(block_params.pre_time)
                    % line(app.RawAxes, [0 0], ylim(app.RawAxes),'Color','r');
                    set(app.raw_lines{1}, 'ydata', ylim(app.RawAxes));
                else
                    set(app.raw_lines{1}, 'ydata', [nan, nan]);
                end
            end
            if isfield(block_params, 'stim_time')
                if ~isnan(block_params.stim_time)
                    % line(app.RawAxes, [block_params.stim_time block_params.stim_time]./1E3, ylim(app.RawAxes),...
                    %     'Color','r','LineStyle','--');
                    set(app.raw_lines{2}, 'xdata', [block_params.stim_time block_params.stim_time]./1E3, 'ydata', ylim(app.RawAxes));
                else
                    set(app.raw_lines{2}, 'xdata', [nan, nan]);
                end
            elseif isfield(block_params, 'stim_1_time')
                if ~isnan(block_params.stim_1_time)
                    % line(app.RawAxes, [block_params.stim_1_time block_params.stim_1_time]./1E3, ylim(app.RawAxes),...
                    %     'Color','r','LineStyle','--');
                    set(app.raw_lines{2}, 'xdata', [block_params.stim_1_time block_params.stim_1_time]./1E3, 'ydata', ylim(app.RawAxes));
                else
                    set(app.raw_lines{2}, 'xdata', [nan, nan]);
                end
            end

            % ylabel(app.RawAxes, units);
            % title(app.RawAxes, sprintf('Epoch %d', epoch_number));
            % hold(app.RawAxes,'off');

            app.updateDiaryPlot();
            app.updateSelectedParams();

            hold(app.DiaryAxes,'on');
            scatter(app.DiaryAxes, app.diary_x(app.epoch_selection_index), app.diary_y(app.epoch_selection_index), ...
                'filled','o','Color','r','HitTest','off');
            hold(app.DiaryAxes,'off');
            app.CurrentepochEditField.Value = epoch_number;
            app.CuratorUIFigure.Name = sprintf('Curator: %d of %d epochs selected', length(app.epoch_ids), length(app.all_epoch_ids));

        end

        function incrementEpoch(app)
            epoch_number = fetch1(app.loaded_epoch, 'epoch_id');
            ind = find(app.epoch_ids == epoch_number);
            if ind < length(app.epoch_ids)
                app.loaded_epoch = aka.Epoch & app.loaded_cell & sprintf('epoch_id = %d', app.epoch_ids(ind+1));
                app.EpochnumberSlider.Value = app.epoch_ids(ind+1);
                app.epoch_selection_index = ind+1;
                app.plotCurrentEpoch();
            end
        end

        function decrementEpoch(app)
            epoch_number = fetch1(app.loaded_epoch, 'epoch_id');
            ind = find(app.epoch_ids == epoch_number);
            if ind > 1
                app.loaded_epoch = aka.Epoch & app.loaded_cell & sprintf('epoch_id = %d', app.epoch_ids(ind-1));
                app.EpochnumberSlider.Value = app.epoch_ids(ind-1);
                app.epoch_selection_index = ind-1;
                app.plotCurrentEpoch();
            end
        end


        function updateDiaryPlot(app, fill_cond_menu)
            if nargin < 2
                fill_cond_menu = false;
            end
            s = cell2struct(num2cell(app.epoch_ids),'epoch_id', 2);
            if strcmp(app.CellTypeSwitch.Value,'brain')
                ep_struct = fetch(sln_symphony.ExperimentEpochBlock * ...
                    sln_symphony.ExperimentEpochGroup * ...
                    aka.Epoch * ...
                    sln_symphony.ExperimentBrainElectrode ...
                    & sprintf('channel_name="%s"', app.DatachannelDropDown.Value) & app.loaded_cell & s, ...
                    'epoch_id', 'protocol_name', 'epoch_start_time', 'epoch_group_label','recording_mode','amp_mode');
            else
                ep_struct = fetch(sln_symphony.ExperimentEpochBlock * ...
                    sln_symphony.ExperimentEpochGroup * ...
                    aka.Epoch * ...
                    sln_symphony.ExperimentElectrode ...
                    & sprintf('channel_name="%s"', app.DatachannelDropDown.Value) & app.loaded_cell & s, ...
                    'epoch_id', 'protocol_name', 'epoch_start_time', 'epoch_group_label','recording_mode','amp_mode');
            end

            N_epochs = length(ep_struct);
            prot_name_ind = zeros(N_epochs, 1);
            epoch_group_label = {ep_struct.epoch_group_label};
            epoch_recording_mode = {ep_struct.recording_mode};
            epoch_amp_mode = {ep_struct.amp_mode};
            epoch_time = ([ep_struct.epoch_start_time] - ep_struct(1).epoch_start_time) ./ 1E3; %to seconds
            app.epoch_1_start = ep_struct(1).epoch_start_time;

            prot_name_list = unique({ep_struct.protocol_name});
            N_protocols = length(prot_name_list);
            for i=1:N_protocols
                prot_name_list{i} = sqlProtName2ProtName(prot_name_list{i});
            end

            condition_names = {};
            condition_ind = zeros(N_epochs, 1);
            z=1;
            for i=1:N_epochs
                prot_name = sqlProtName2ProtName(ep_struct(i).protocol_name);
                prot_name_ind(i) = find(strcmp(prot_name, prot_name_list));

                cur_name = sprintf('%s_%s_%s_%s',...
                    prot_name,epoch_amp_mode{i},epoch_recording_mode{i},epoch_group_label{i});
                ind = find(strcmp(cur_name,condition_names));
                if isempty(ind)
                    condition_names{z} = cur_name;
                    tickA{z} = prot_name;
                    tickB{z} = epoch_amp_mode{i};
                    tickC{z} = epoch_recording_mode{i};
                    tickD{z} = epoch_group_label{i};
                    condition_ind(i) = z;
                    z=z+1;
                else
                    condition_ind(i) = ind;
                end
            end

            if fill_cond_menu
                app.QuickselectepochgroupDropDown.Items = [' ', condition_names];
            end

            YTickA = 1:length(condition_names);
            YTickB = YTickA + .2;
            YTickC = YTickA + .4;
            YTick = [YTickA, YTickB, YTickC];
            tick = [tickA tickB tickC];

            if length(unique(tickD)) > 1
                YTickD = YTickA + .6;
                YTick = [YTickA, YTickB, YTickC, YTickD];
                tick = [tickA tickB tickC tickD];
            end

            [YTick, ind] = sort(YTick, 'ascend');
            tick = tick(ind);

            app.DiaryAxes.YTick = YTick;
            app.DiaryAxes.YTickLabel = tick;
            app.diary_y = condition_ind;
            in_dataset_ind = ismember(app.epoch_ids, app.epoch_ids_in_datasets);
            if strcmp(app.XaxisDropDown.Value,'Time')
                app.diary_x = epoch_time;
                scatter(app.DiaryAxes,epoch_time(in_dataset_ind),condition_ind(in_dataset_ind), 'go','filled','HitTest','off');
                hold(app.DiaryAxes,'on');
                scatter(app.DiaryAxes,epoch_time(~in_dataset_ind),condition_ind(~in_dataset_ind), 'ko','HitTest','off');
                hold(app.DiaryAxes,'off');
                xlabel(app.DiaryAxes,'Time (s)')
            else
                app.diary_x = app.epoch_ids;
                scatter(app.DiaryAxes,app.epoch_ids(in_dataset_ind),condition_ind(in_dataset_ind), 'go','filled','HitTest','off');
                hold(app.DiaryAxes,'on');
                scatter(app.DiaryAxes,app.epoch_ids(~in_dataset_ind),condition_ind(~in_dataset_ind), 'ko','HitTest','off');
                hold(app.DiaryAxes,'off');
                xlabel(app.DiaryAxes,'Epoch number')
            end
            ylim(app.DiaryAxes,[0 length(condition_names)+1]);
            app.CuratorUIFigure.Name = sprintf('Curator: %d of %d epochs selected', length(app.epoch_ids), length(app.all_epoch_ids));
        end

        function fetchEpochParams(app)
            if isempty(app.epoch_ids)
                return;
            end

            %tic;
            s = cell2struct(num2cell(app.epoch_ids),'epoch_id', 2);
            if strcmp(app.CellTypeSwitch.Value,'brain')
                q = sln_symphony.ExperimentEpochBlock * ...
                    sln_symphony.ExperimentBrainElectrode   * ...
                    aka.Epoch ...
                    & app.loaded_cell & s;
            else
                q = sln_symphony.ExperimentEpochBlock * ...
                    sln_symphony.ExperimentElectrode   * ...
                    aka.Epoch ...
                    & app.loaded_cell & s;
            end
            non_prot_params = fetch(q,'*');
            non_prot_params = rmfield(non_prot_params, ...
                {'epoch_block_id',...
                'epoch_block_start_time', ...
                'epoch_block_end_time', ...
                'epoch_start_time'});
            
            prot_name = {non_prot_params.protocol_name};
            all_prot_names = unique(prot_name);        
            app.non_prot_params_map = structArray2Map(non_prot_params);

            N_prot = length(all_prot_names);
            app.prot_params_map = containers.Map;
            for i=1:N_prot
                cur_name = sqlProtName2ProtName(all_prot_names{i});
                if strcmp(cur_name,'ObjectMotionSensitivity') %HACK
                    q_prot = aka.BlockParams(cur_name) * ...
                        proj(aka.EpochParams(cur_name),'motion_mode->epoch_motion_mode','*') & proj(q);
                elseif strcmp(cur_name,'RandomMotionObject') %HACK
                    q_prot = aka.BlockParams(cur_name) * ...
                        proj(aka.EpochParams(cur_name),'motion_seed->epoch_motion_seed','seed->epoch_seed','*') & proj(q);
                elseif strcmp(cur_name,'SetMeanLevel') %HACK
                    q_prot = proj(sln_symphony.ExperimentEpoch & proj(q & 'protocol_name="set_mean_level"'),'""->mean_level');
                else
                    q_prot = aka.BlockParams(cur_name) * proj(aka.EpochParams(cur_name), '*') & proj(q);
                end
                if ~q_prot.exists && strcmp(cur_name,'SpotsMultiSize')
                    q_prot = aka.BlockParams('SpotsMultiSizeClassify') * aka.EpochParams('SpotsMultiSizeClassify') & proj(q);
                end
                if count(q_prot * sln_symphony.ExperimentProjectorSettings)
                    q_prot = q_prot * sln_symphony.ExperimentProjectorSettings;
                end
                prot_params = fetch(q_prot,'*');
                prot_params = rmfield(prot_params,...
                    {'epoch_block_id', ...
                    'epoch_group_id', ...
                    'file_name', ...
                    'source_id'});
                app.prot_params_map(cur_name) = structArray2Map(prot_params);
            end
            
            %elapsed = toc
            %disp('done loading params');
            app.uniqueValsFromMergedMaps()
        end

        function getParamsForEpochs(app)
            if isempty(app.epoch_ids)
                return;
            end
            s = cell2struct(num2cell(app.epoch_ids),'epoch_id', 2);
            q = sln_symphony.ExperimentEpochBlock * ...
                aka.Epoch ...
                & app.loaded_cell & s;

            for i=1:length(s)
                prot_name = sqlProtName2ProtName(fetch1(q & s(i),'protocol_name'));
                if strcmp(app.CellTypeSwitch.Value,'brain')
                    q_with_params = aka.BlockParams(prot_name) * proj(aka.EpochParams(prot_name), '*') ...
                        * sln_symphony.ExperimentBrainElectrode * sln_symphony.ExperimentEpochGroup ...
                        * q & s(i) & sprintf('channel_name="%s"', app.DatachannelDropDown.Value);
                else
                    q_with_params = aka.BlockParams(prot_name) * proj(aka.EpochParams(prot_name), '*') ...
                        * sln_symphony.ExperimentElectrode * sln_symphony.ExperimentEpochGroup ...
                        * q & s(i) & sprintf('channel_name="%s"', app.DatachannelDropDown.Value);
                end
                if count(q_with_params * sln_symphony.ExperimentProjectorSettings)
                    q_with_params = q_with_params * sln_symphony.ExperimentProjectorSettings;
                end
                if i==1 
                    param_list = q_with_params.header.notBlobs;
                else
                    param_list = union(param_list, q_with_params.header.notBlobs);
                end
                param_list = setdiff(param_list,app.params_to_remove);
                app.param_list = [' ', 'Time', 'Epoch number', param_list];
            end
            valsMap = containers.Map;
            all_prot_names = unique(fetchn(q & s,'protocol_name'));
            for i=1:length(all_prot_names)
                all_prot_names{i} = sqlProtName2ProtName(all_prot_names{i});
                if strcmp(app.CellTypeSwitch.Value,'brain')
                    q_with_params = aka.BlockParams(all_prot_names{i}) * proj(aka.EpochParams(all_prot_names{i}), '*') ...
                        * sln_symphony.ExperimentBrainElectrode * sln_symphony.ExperimentEpochGroup ...
                        * q & s;
                else
                    q_with_params = aka.BlockParams(all_prot_names{i}) * proj(aka.EpochParams(all_prot_names{i}), '*') ...
                        * sln_symphony.ExperimentElectrode * sln_symphony.ExperimentEpochGroup ...
                        * q & s;
                end
                if count(q_with_params * sln_symphony.ExperimentProjectorSettings)
                    q_with_params = q_with_params * sln_symphony.ExperimentProjectorSettings;
                end
                for j=1:length(param_list)
                    try
                        vals = unique(fetchn(q_with_params, param_list{j}),'stable');
                        if valsMap.isKey(param_list{j})
                            valsMap(param_list{j}) = union(valsMap(param_list{j}), vals);
                        else
                            valsMap(param_list{j}) = vals;
                        end
                        v = valsMap(param_list{j});
                        valsMap(param_list{j}) = v(isfinite(v));
                    catch
                        %skipping params not in this protocol
                    end
                end
            end
            app.valsMap = valsMap;

            app.fillMultiParamsTable();
            app.fillSingleParamsTable();
        end

        function init_filter_table(app)
            param_list = app.valsMap.keys;
            param_list = [' ', 'Time', 'Epoch number', param_list];

            Nrows = 15; %7 is too little!! 

            D = {1,param_list{1},app.operators{1},' '};
            for i=2:Nrows
                D = [D; {i,param_list{1},app.operators{1},' '};];
            end
            app.FilterTable.Data = D;
            app.FilterTable.ColumnEditable = [false, true, true, true];
            app.FilterTable.ColumnFormat = {'numeric',param_list, app.operators,'char'};
        end

        function fillMultiParamsTable(app)
            allKeys = app.valsMap.keys;
            app.MultiValTable.Data = {};
            z=1;
            for i=1:app.valsMap.Count
                if ~strcmp(allKeys{i}, 'epoch_id')
                    val = app.valsMap(allKeys{i});
                    if isnumeric(val) && length(val)>1
                        app.MultiValTable.Data{z,1} = allKeys{i};
                        [r, c] = size(val);
                        if r>c
                            app.MultiValTable.Data{z,2}= num2str(val');
                        else
                            app.MultiValTable.Data{z,2}= num2str(val);
                        end
                        z=z+1;
                    elseif iscell(val) && length(val)>1
                        app.MultiValTable.Data{z,1} = allKeys{i};
                        app.MultiValTable.Data{z,2}= char(join(val, ', '));
                        z=z+1;
                    end
                end
            end
        end

        function fillSingleParamsTable(app)
            allKeys = app.valsMap.keys;
            app.SelectedValTable.ColumnFormat = {[' ',allKeys], 'char'};
            app.SelectedValTable.ColumnEditable = [true, false];
            for i=1:3
                app.SelectedValTable.Data{i,1} = ' ';
                app.SelectedValTable.Data{i,2} = ' ';
            end
        end

        function updateSelectedParams(app)
            q = sln_symphony.ExperimentEpochBlock * app.loaded_epoch;
            if count(q * sln_symphony.ExperimentProjectorSettings)
                q = q * sln_symphony.ExperimentProjectorSettings;
            end
            prot_name = sqlProtName2ProtName(fetch1(q,'protocol_name'));
            if strcmp(app.CellTypeSwitch.Value,'brain')
                q_with_params = aka.BlockParams(prot_name) * proj(aka.EpochParams(prot_name), '*') ...
                    * sln_symphony.ExperimentBrainElectrode * sln_symphony.ExperimentEpochGroup * q & sprintf('channel_name="%s"', app.DatachannelDropDown.Value);
            else
                q_with_params = aka.BlockParams(prot_name) * proj(aka.EpochParams(prot_name), '*') ...
                    * sln_symphony.ExperimentElectrode * sln_symphony.ExperimentEpochGroup * q & sprintf('channel_name="%s"', app.DatachannelDropDown.Value);
            end
            for i=1:size(app.SelectedValTable.Data,1)
                param = app.SelectedValTable.Data{i,1};
                if ~strcmp(param, ' ')
                    try
                        val = fetch1(q_with_params,param);
                        if isnumeric(val)
                            app.SelectedValTable.Data{i,2} = num2str(val);
                        else
                            app.SelectedValTable.Data{i,2} = val;
                        end
                    catch
                        app.SelectedValTable.Data{i,2} = '';
                        fprintf('param %s not found\n', param);
                    end
                end
            end

        end
        
        function setFilterFromQuickSelect(app)
            selected_group_name = app.QuickselectepochgroupDropDown.Value;
            if strcmp(selected_group_name, ' ')
                for i=1:size(app.FilterTable.Data,1)
                    app.FilterTable.Data{i,2} = ' ';
                    app.FilterTable.Data{i,3} = ' ';                    
                    app.FilterTable.Data{i,4} = '';                    
                end
                app.FilterpatternstringEditField.Value = '';
                app.runFilter();
            else
                vals = strsplit(selected_group_name, '_');

                app.FilterTable.Data{1,2} = 'protocol_name';
                app.FilterTable.Data{1,3} = '=';
                app.FilterTable.Data{1,4} = protName2SqlProtName(vals{1});

                app.FilterTable.Data{2,2} = 'amp_mode';
                app.FilterTable.Data{2,3} = '=';
                app.FilterTable.Data{2,4} = vals{2};

                app.FilterTable.Data{3,2} = 'recording_mode';
                app.FilterTable.Data{3,3} = '=';
                app.FilterTable.Data{3,4} = vals{3};

                app.FilterTable.Data{4,2} = 'epoch_group_label';
                app.FilterTable.Data{4,3} = '=';
                app.FilterTable.Data{4,4} = vals{4};

                app.FilterpatternstringEditField.Value = '@1 && @2 && @3 && @4';

                app.runFilter();
            end            
        end

        function [query_string, operators] = queryStringFromFilter(app)
            query_string = {};
            operators = {};
            filt_pat_string = app.FilterpatternstringEditField.Value;
            filt_pat_string = strrep(filt_pat_string, '&&', '+');
            filt_pat_string = strrep(filt_pat_string, '&!', '-');
            filt_pat_string = strrep(filt_pat_string, '&~', '-');

            plus = findstr(filt_pat_string, '+');
            minus = findstr(filt_pat_string, '-');

            operators = [repmat('+', 1, length(plus)), repmat('-', 1, length(minus))];
            [~, ind_ordered] = sort([plus, minus], 'ascend');
            operators = operators(ind_ordered);
            
            z=1;
            for i=1:size(app.FilterTable.Data,1)
                if strcmp(app.FilterTable.Data{i,2},'Time')
                    app.FilterTable.Data{i,2} = 'epoch_start_time';
                elseif strcmp(app.FilterTable.Data{i,2},'Epoch number')
                    app.FilterTable.Data{i,2} = 'epoch_id';
                end
                
                if contains(filt_pat_string,['@' num2str(i)])
                   % if strcmp(app.FilterTable.Data{i,2},'Time')
                   %      t = app.epoch_1_all_start + (app.epoch_1_all_start - app.epoch_1_start) + str2double(app.FilterTable.Data{i,4})*1E3 ;
                   %      query_string{z} = sprintf('epoch_start_time%s%d',...
                   %          app.FilterTable.Data{i,3}, t);
                   % elseif strcmp(app.FilterTable.Data{i,2},'Epoch number')
                   %      query_string{z} = sprintf('epoch_id%s%s',...
                   %          app.FilterTable.Data{i,3}, app.FilterTable.Data{i,4});
                   if strcmp(app.FilterTable.Data{i,3},'between')
                       vec = eval(app.FilterTable.Data{i,4});
                       if length(vec) ~= 2
                           error('between operators must be followed by [a b] to search val >= a AND val <= b');
                       end
                       query_string{z} = sprintf('%s>=%d AND %s<=%d',...
                            app.FilterTable.Data{i,2}, vec(1), app.FilterTable.Data{i,2}, vec(2));
                   elseif isnan(str2double(app.FilterTable.Data{i,4})) %strings
                        query_string{z} = sprintf('%s%s"%s"',...
                            app.FilterTable.Data{i,2}, app.FilterTable.Data{i,3}, app.FilterTable.Data{i,4});
                   else
                        query_string{z} = sprintf('%s%s%s',...
                            app.FilterTable.Data{i,2}, app.FilterTable.Data{i,3}, app.FilterTable.Data{i,4});
                   end
                   z=z+1;
                end
            end
        end
        
        function runFilter(app)
            [query_string, query_operators] = app.queryStringFromFilter();
            query_operators = ['=', query_operators];
            cla(app.RawAxes);
            app.raw_plot = plot(app.RawAxes,nan,'k');
            app.raw_lines{1} = line(app.RawAxes, [0, 0], [nan,nan], 'color', 'r');
            app.raw_lines{2} = line(app.RawAxes, [0, 0], [nan,nan], 'color', 'r', 'linestyle','--');            

            % line(app.RawAxes, [block_params.stim_time block_params.stim_time]./1E3, ylim(app.RawAxes),...
            %     'Color','r','LineStyle','--');
            % line(app.RawAxes, [0 0], ylim(app.RawAxes),'Color','r');


            cla(app.DiaryAxes);
            drawnow;
            app.CuratorUIFigure.Name = 'loading...';
            if strcmp(app.CellTypeSwitch.Value,'brain')
                q = app.loaded_cell * aka.Epoch * sln_symphony.ExperimentEpochBlock * ...
                    sln_symphony.ExperimentBrainElectrode * sln_symphony.ExperimentEpochGroup & sprintf('channel_name="%s"', app.DatachannelDropDown.Value);
            else
                q = app.loaded_cell * aka.Epoch * sln_symphony.ExperimentEpochBlock * ...
                    sln_symphony.ExperimentElectrode * sln_symphony.ExperimentEpochGroup & sprintf('channel_name="%s"', app.DatachannelDropDown.Value);
            end
            if ~isempty(query_string)
                if contains(query_string{1}, 'protocol_name=')
                    temp = extractAfter(query_string{1},'protocol_name=');
                    prot_name = sqlProtName2ProtName(strrep(temp(1:end-1),'"',''));
                    if strcmp(prot_name,'ObjectMotionSensitivity') %HACK
                        prot_params = aka.BlockParams(prot_name) * ...
                            proj(proj(aka.EpochParams(prot_name), '*'),'motion_mode->epoch_motion_mode','*') * q;
                    elseif strcmp(prot_name,'RandomMotionObject') %HACK
                        prot_params = aka.BlockParams(prot_name) * ...
                            proj(proj(aka.EpochParams(prot_name), '*'),'motion_seed->epoch_motion_seed','seed->epoch_seed','*') * q;
                    else
                        prot_params = q * aka.BlockParams(prot_name) * proj(aka.EpochParams(prot_name), '*');
                    end

                    if ~prot_params.exists %HACK
                        prot_params = q * aka.BlockParams('SpotsMultiSizeClassify') * aka.EpochParams('SpotsMultiSizeClassify');
                    end
                    q = prot_params;
                    if count(q * sln_symphony.ExperimentProjectorSettings)
                        q = q * sln_symphony.ExperimentProjectorSettings;
                    end

                end

                for i=1:length(query_string)
                    if strcmp(query_operators(i), '+') || strcmp(query_operators(i), '=') 
                        q = q & query_string{i};
                    elseif strcmp(query_operators(i), '-')
                        q = q - query_string{i};
                    end
                end
            end
            
            if ~q.exists
                disp('No epochs found matching query');
                app.CuratorUIFigure.Name = sprintf('Curator: %d of %d epochs selected', length(app.epoch_ids), length(app.all_epoch_ids));
                return;
            end

            app.epoch_ids = sort(fetchn(q, 'epoch_id'), 'ascend');
            app.loaded_epoch = app.loaded_cell * aka.Epoch & sprintf('epoch_id = %d', app.epoch_ids(1));
            try
                app.EpochnumberSlider.Limits = [app.epoch_ids(1), app.epoch_ids(end)];
                app.EpochnumberSlider.Value = app.epoch_ids(1);
                app.EpochnumberSlider.MajorTicks = round(linspace(app.epoch_ids(1), app.epoch_ids(end), 10));
            catch
            
            end

            app.epoch_selection_index = 1;
            app.fetchEpochParams();
            app.uniqueValsFromMergedMaps();
            app.updateDiaryPlot();
            app.fillMultiParamsTable();
            app.fillSingleParamsTable();
            app.plotCurrentEpoch();
            app.CuratorUIFigure.Name = sprintf('Curator: %d of %d epochs selected', length(app.epoch_ids), length(app.all_epoch_ids));
        end
        
        function uniqueValsFromMergedMaps(app)
            app.valsMap = app.non_prot_params_map.remove('epoch_id');
            prot_keys = app.prot_params_map.keys;
            for i=1:app.prot_params_map.Count
                curMap = app.prot_params_map(prot_keys{i});
                keys = curMap.keys;
                for k=1:length(keys)
                    key = keys{k};
                    if app.valsMap.isKey(key)

                        c = curMap(key);
                        v= app.valsMap(key);
                        if iscell(app.valsMap(key)) && ~iscell(curMap(key))
                            v = cellfun(@(x) strcmp(x, 'T'), app.valsMap(key));
                        elseif ~iscell(app.valsMap(key)) && iscell(curMap(key))
                            c = cellfun(@(x) strcmp(x, 'T'), curMap(key));
                        end
                        % if iscell(app.valsMap(key)) || iscell(curMap(key))
                        %     if iscell(curMap(key))
                        %         curMap_boolean = cellfun(@(x) strcmp(x, 'T'), curMap(key));
                        %         app.valsMap(key) = union(app.valsMap(key), curMap_boolean);
                        %     elseif iscell(app.valsMap(key))
                        %         valsMap_boolean = cellfun(@(x) strcmp(x, 'T'), app.valsMap(key));
                        %         app.valsMap(key) = union(valsMap_boolean, curMap(key));
                        %     end
                        % 
                        % else
                        %     app.valsMap(key) = union(app.valsMap(key), curMap(key));
                        % end

                        % if iscell(curMap(key))
                        %     c = cellfun(@(x) strcmp(x, 'T'), curMap(key));
                        % else
                        %     c = curMap(key);
                        % end
                        % if iscell(app.valsMap(key))
                        %     v = cellfun(@(x) strcmp(x, 'T'), app.valsMap(key));
                        % else
                        %     v= app.valsMap(key);
                        % end
                        app.valsMap(key) = union(c,v);
                        % app.valsMap(key) = union(app.valsMap(key), curMap(key));
                    else
                        app.valsMap(key) = curMap(key);
                    end
                end

            end

            keys = app.valsMap.keys;
            for i=1:length(keys)
                key = keys{i};
                if isnumeric(app.valsMap(key))
                    temp = app.valsMap(key);
                    temp = temp(isfinite(temp));
                    app.valsMap(key) = temp;
                end
                app.valsMap(key) = unique(app.valsMap(key),'stable');
            end
        end
        
        
        function updateDatasetMenu(app)
            q = sln_symphony.Dataset & app.loaded_cell;
            if q.exists
                dataset_names = fetchn(q,'dataset_name');
            else
                dataset_names = {};
            end
            app.DatasetsDropDown.Items = [' '; dataset_names];

        end
        
        function store_dataset_epochs(app)
            if strcmp(app.CellTypeSwitch.Value,'brain')
                q = sln_symphony.DatasetEpoch & (sln_symphony.ExperimentBrainCell * aka.Dataset & proj(app.loaded_cell));
            else
                q = sln_symphony.DatasetEpoch & (sln_symphony.ExperimentCell * aka.Dataset & proj(app.loaded_cell));
            end
            if q.exists
                app.epoch_ids_in_datasets = fetchn(q,'epoch_id');
            else
                app.epoch_ids_in_datasets = [];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, init_cellname, caller)
            movegui(app.CuratorUIFigure, 'center');
            app.init()
            
            if nargin <3
                caller = 0;
            end 

            if ~isempty(init_cellname)
                app.loadCell(init_cellname);

            end
            if nargin > 2
                switch class(caller)
                    case 'NoCuratedForSpike'
                        app.NoCuratedForSpikeApp = caller;
                    case 'DataGrouper'
                        app.DataGrouperApp = caller;
                end
            end
            
            
        end

        % Button pushed function: LoadcellButton
        function LoadcellButtonPushed(app, event)
            app.loadCell(app.WorkingcellEditField.Value);
        end

        % Value changed function: EpochnumberSlider
        function EpochnumberSliderValueChanged(app, event)
            value = app.EpochnumberSlider.Value;
            d = app.epoch_ids - value;
            [~, ind] = min(abs(d));
            app.epoch_selection_index = ind(1);
            app.loaded_epoch = aka.Epoch & app.loaded_cell & sprintf('epoch_id = %d', app.epoch_ids(ind(1)));
            app.plotCurrentEpoch;
            %app.CurrentepochEditField.Value = ind(1);
        end

        % Key press function: CuratorUIFigure
        function CuratorUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'rightarrow')
                app.incrementEpoch();
            elseif strcmp(key,'leftarrow')
                app.decrementEpoch();
            end
        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownValueChanged(app, event)
            app.updateDiaryPlot();
        end

        % Button down function: DiaryAxes
        function DiaryAxesButtonDown(app, event)
            if strcmp(event.EventName,'Hit')
                xval = event.IntersectionPoint(1);
                d = abs(app.diary_x - xval);
                [~, ind] = min(d);
                app.EpochnumberSlider.Value = app.epoch_ids(ind(1));
                app.epoch_selection_index = ind(1);
                app.loaded_epoch = aka.Epoch & app.loaded_cell & sprintf('epoch_id = %d', app.epoch_ids(ind(1)));
                app.updateDiaryPlot();
                app.plotCurrentEpoch();
            end
        end

        % Value changed function: DatachannelDropDown
        function DatachannelDropDownValueChanged(app, event)
            app.plotCurrentEpoch();
        end

        % Cell edit callback: FilterTable
        function FilterTableCellEdit(app, event)
            row = event.Indices(1);
            col = event.Indices(2);
            if col==2 %param column
                param = event.NewData;
                if app.valsMap.isKey(param)
                    if isnumeric(app.valsMap(param))
                        [r,c] = size(app.valsMap(param));
                        if r>c
                            app.FilterTable.Data{row,4} = num2str(app.valsMap(param)');
                        else
                            app.FilterTable.Data{row,4} = num2str(app.valsMap(param));
                        end
                    elseif iscell(app.valsMap(param))
                        app.FilterTable.Data{row,4} = char(join(app.valsMap(param), ', '));
                    else
                        app.FilterTable.Data{row,4} = char(app.valsMap(param));
                    end
                else
                    app.FilterTable.Data{row,4} = '';
                end

            end

        end

        % Cell edit callback: SelectedValTable
        function SelectedValTableCellEdit(app, event)
            row = event.Indices(1);
            col = event.Indices(2);
            if col==1 %param column
                param = event.NewData;
                q = sln_symphony.ExperimentEpochBlock * app.loaded_epoch;
                prot_name = sqlProtName2ProtName(fetch1(q,'protocol_name'));
                if strcmp(app.CellTypeSwitch.Value,'brain')
                    q_with_params = aka.BlockParams(prot_name) * proj(aka.EpochParams(prot_name), '*') ...
                        * sln_symphony.ExperimentBrainElectrode * sln_symphony.ExperimentEpochGroup * q & sprintf('channel_name="%s"', app.DatachannelDropDown.Value);
                else
                    q_with_params = aka.BlockParams(prot_name) * proj(aka.EpochParams(prot_name), '*') ...
                        * sln_symphony.ExperimentElectrode * sln_symphony.ExperimentEpochGroup * q & sprintf('channel_name="%s"', app.DatachannelDropDown.Value);
                end
                try
                    val = fetch1(q_with_params,param);
                    if isnumeric(val)
                        app.SelectedValTable.Data{row,2} = num2str(val);
                    else
                        app.SelectedValTable.Data{row,2} = val;
                    end
                catch
                    app.SelectedValTable.Data{row,2} = '';
                    fprintf('param %s not found\n', param);
                end
            end
        end

        % Value changed function: QuickselectepochgroupDropDown
        function QuickselectepochgroupDropDownValueChanged(app, event)
            app.setFilterFromQuickSelect();
        end

        % Button pushed function: RunfilterButton
        function RunfilterButtonPushed(app, event)
            app.runFilter();
        end

        % Button pushed function: SavedatasetButton
        function SavedatasetButtonPushed(app, event)
            default_name = {strrep(strjoin(app.DiaryAxes.YTickLabel,'_'),' ','_')};
            dataset_name = inputdlg({'Save dataset as...'},'Save dataset',[1 80],default_name);
            dataset_name = dataset_name{1};
            q = sln_symphony.Dataset & app.loaded_cell & sprintf('dataset_name="%s"', dataset_name);
            if q.exists
                overwrite = uiconfirm(app.CuratorUIFigure,sprintf('Overwrite current dataset %s?',dataset_name),'Overwrite?');
                if ~strcmp(overwrite, 'OK')
                    disp('aborting save');
                    return;
                end
            end
            key = fetch(app.loaded_cell);
            key.epoch_id = app.epoch_ids;
            key.dataset_name = dataset_name;
            insert(sln_symphony.Dataset, key);

            %add blank dataset userparams
            [data_levels, user_params_table_names] = sln_symphony.get_all_user_param_in_db;
            key = rmfield(key,'epoch_id');
            for i = 1:length(data_levels)
                table_name = sprintf('sln_symphony.UserParam%s%s', data_levels{i}, user_params_table_names{i});
                if strcmp(data_levels{i}, 'Dataset')
                    insert(eval(table_name),key,'IGNORE');
                end
            end

            app.store_dataset_epochs();
            app.updateDatasetMenu();
            app.updateDiaryPlot();
   
        end

        % Value changed function: DatasetsDropDown
        function DatasetsDropDownValueChanged(app, event)
            value = app.DatasetsDropDown.Value;
            if strcmp(value,' ')
                app.epoch_ids = app.all_epoch_ids;
            else
                q_dataset = app.loaded_cell * sln_symphony.Dataset & sprintf('dataset_name="%s"',value);
                app.epoch_ids = fetchn(sln_symphony.DatasetEpoch & q_dataset, 'epoch_id');
            end

            app.loaded_epoch = app.loaded_cell * aka.Epoch & sprintf('epoch_id = %d', app.epoch_ids(1));
            try
                app.EpochnumberSlider.Limits = [app.epoch_ids(1), app.epoch_ids(end)];
                app.EpochnumberSlider.Value = app.epoch_ids(1);
                app.EpochnumberSlider.MajorTicks = round(linspace(app.epoch_ids(1), app.epoch_ids(end), 10));
            catch
            
            end
            app.epoch_selection_index = 1;
            app.fetchEpochParams();
            app.uniqueValsFromMergedMaps();
            app.updateDiaryPlot();
            app.fillMultiParamsTable();
            app.fillSingleParamsTable();
            app.plotCurrentEpoch();
        end

        % Button pushed function: DeletedatasetButton
        function DeletedatasetButtonPushed(app, event)
            selected_dataset = app.DatasetsDropDown.Value;
            q = sln_symphony.Dataset & app.loaded_cell & sprintf('dataset_name="%s"', selected_dataset);
            if q.exists
                confirm_delete = uiconfirm(app.CuratorUIFigure,sprintf('Delete dataset %s?',selected_dataset),'Delete?');
                if ~strcmp(confirm_delete, 'OK')
                    disp('aborting delete');
                    return;
                else
                    dataset_epochs = sln_symphony.DatasetEpoch & q;
                    delQuick(dataset_epochs);
                    %delQuick(q);
                    del(q);
                    app.store_dataset_epochs();
                    app.updateDiaryPlot();
                    app.updateDatasetMenu();
                end
            end
        end

        % Button pushed function: RenamedatasetButton
        function RenamedatasetButtonPushed(app, event)
            selected_dataset = app.DatasetsDropDown.Value;
            q = sln_symphony.Dataset & app.loaded_cell & sprintf('dataset_name="%s"', selected_dataset);
            if q.exists
                new_name = inputdlg({'Rename dataset to...'},'Rename dataset',[1 80],{selected_dataset});
                if isempty(new_name) || strcmp(selected_dataset, new_name{1})
                    disp('aborting rename');
                    return;
                else
                    dataset_epochs = sln_symphony.DatasetEpoch & q;
                    delQuick(dataset_epochs);
                    delQuick(q);
                    key = fetch(app.loaded_cell);
                    key.epoch_id = app.epoch_ids;
                    key.dataset_name = new_name{1};
                    insert(sln_symphony.Dataset, key);

                    %add blank dataset userparams
                    key = rmfield(key,'epoch_id');
                    [data_levels, user_params_table_names] = sln_symphony.get_all_user_param_in_db;
                    for i = 1:length(data_levels)
                        table_name = sprintf('sln_symphony.UserParam%s%s', data_levels{i}, user_params_table_names{i});
                        if strcmp(data_levels{i}, 'Dataset')
                            insert(eval(table_name),key,'IGNORE');
                        end
                    end

                    app.store_dataset_epochs();
                    app.updateDatasetMenu();
                    app.updateDiaryPlot();
                end
            end
        end

        % Close request function: CuratorUIFigure
        function CuratorUIFigureCloseRequest(app, event)
            try
                app.DataGrouperApp.ToCuratorButton.Enable = 'on';
                app.NoCuratedForSpikeApp.delete;
                app.DataGrouperApp.DataElementsListBoxClicked;
            catch
            end

            delete(app)
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CuratorUIFigure = uifigure('Visible', 'off');
            app.CuratorUIFigure.Name = 'Curator';
            app.CuratorUIFigure.CloseRequestFcn = createCallbackFcn(app, @CuratorUIFigureCloseRequest, true);
            app.CuratorUIFigure.KeyPressFcn = createCallbackFcn(app, @CuratorUIFigureKeyPress, true);
            app.DiaryAxes = uiaxes(app.CuratorUIFigure);
            app.DiaryAxes.YDir = 'reverse';
            app.DiaryAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.DiaryAxes.ButtonDownFcn = createCallbackFcn(app, @DiaryAxesButtonDown, true);
            app.RawAxes = uiaxes(app.CuratorUIFigure);
            xlabel(app.RawAxes, 'Epoch time (s)')
            app.XaxisDropDownLabel = uilabel(app.CuratorUIFigure);
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.XaxisDropDown = uidropdown(app.CuratorUIFigure);
            app.XaxisDropDown.Items = {'Time', 'Epoch number'};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownValueChanged, true);
            app.XaxisDropDown.Value = 'Time';
            app.DatachannelDropDownLabel = uilabel(app.CuratorUIFigure);
            app.DatachannelDropDownLabel.Text = 'Data channel';
            app.DatachannelDropDown = uidropdown(app.CuratorUIFigure);
            app.DatachannelDropDown.Items = {'Amp1', 'Amp2'};
            app.DatachannelDropDown.ValueChangedFcn = createCallbackFcn(app, @DatachannelDropDownValueChanged, true);
            app.DatachannelDropDown.Value = 'Amp1';
            app.SavedatasetButton = uibutton(app.CuratorUIFigure, 'push');
            app.SavedatasetButton.ButtonPushedFcn = createCallbackFcn(app, @SavedatasetButtonPushed, true);
            app.SavedatasetButton.Text = 'Save dataset';
            app.RenamedatasetButton = uibutton(app.CuratorUIFigure, 'push');
            app.RenamedatasetButton.ButtonPushedFcn = createCallbackFcn(app, @RenamedatasetButtonPushed, true);
            app.RenamedatasetButton.Text = 'Rename dataset';
            app.DeletedatasetButton = uibutton(app.CuratorUIFigure, 'push');
            app.DeletedatasetButton.ButtonPushedFcn = createCallbackFcn(app, @DeletedatasetButtonPushed, true);
            app.DeletedatasetButton.Text = 'Delete dataset';
            app.DatasetsDropDownLabel = uilabel(app.CuratorUIFigure);
            app.DatasetsDropDownLabel.Text = 'Data sets';
            app.DatasetsDropDown = uidropdown(app.CuratorUIFigure);
            app.DatasetsDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetsDropDownValueChanged, true);
            app.FilterpatternstringEditFieldLabel = uilabel(app.CuratorUIFigure);
            app.FilterpatternstringEditFieldLabel.Text = 'Filter pattern string';
            app.FilterpatternstringEditField = uieditfield(app.CuratorUIFigure, 'text');
            app.FilterTable = uitable(app.CuratorUIFigure);
            app.FilterTable.ColumnName = {'#'; 'Param'; 'Operator'; 'Value'};
            app.FilterTable.RowName = {};
            app.FilterTable.ColumnEditable = true;
            app.FilterTable.CellEditCallback = createCallbackFcn(app, @FilterTableCellEdit, true);
            app.MultiValTable = uitable(app.CuratorUIFigure);
            app.MultiValTable.ColumnName = {'Param'; 'Values'};
            app.MultiValTable.RowName = {};
            app.ParmeterswithmultiplevaluesLabel = uilabel(app.CuratorUIFigure);
            app.ParmeterswithmultiplevaluesLabel.Text = 'Parmeters with multiple values';
            app.SelectedValTable = uitable(app.CuratorUIFigure);
            app.SelectedValTable.ColumnName = {'Param'; 'Value'};
            app.SelectedValTable.RowName = {};
            app.SelectedValTable.CellEditCallback = createCallbackFcn(app, @SelectedValTableCellEdit, true);
            app.ParametersforselectedepochLabel = uilabel(app.CuratorUIFigure);
            app.ParametersforselectedepochLabel.Text = 'Parameters for selected epoch';
            app.FilterconstructionLabel = uilabel(app.CuratorUIFigure);
            app.FilterconstructionLabel.Text = 'Filter construction';
            app.WorkingcellEditFieldLabel = uilabel(app.CuratorUIFigure);
            app.WorkingcellEditFieldLabel.Text = 'Working cell';
            app.WorkingcellEditField = uieditfield(app.CuratorUIFigure, 'text');
            app.LoadcellButton = uibutton(app.CuratorUIFigure, 'push');
            app.LoadcellButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcellButtonPushed, true);
            app.LoadcellButton.Text = 'Load cell';
            app.EpochnumberSliderLabel = uilabel(app.CuratorUIFigure);
            app.EpochnumberSliderLabel.Text = 'Epoch number';
            app.EpochnumberSlider = uislider(app.CuratorUIFigure);
            app.EpochnumberSlider.ValueChangedFcn = createCallbackFcn(app, @EpochnumberSliderValueChanged, true);
            app.EpochnumberSlider.MinorTicks = [];
            app.QuickselectepochgroupDropDownLabel = uilabel(app.CuratorUIFigure);
            app.QuickselectepochgroupDropDownLabel.Text = 'Quick select epoch group';
            app.QuickselectepochgroupDropDown = uidropdown(app.CuratorUIFigure);
            app.QuickselectepochgroupDropDown.ValueChangedFcn = createCallbackFcn(app, @QuickselectepochgroupDropDownValueChanged, true);
            app.RunfilterButton = uibutton(app.CuratorUIFigure, 'push');
            app.RunfilterButton.ButtonPushedFcn = createCallbackFcn(app, @RunfilterButtonPushed, true);
            app.RunfilterButton.Text = 'Run filter';
            app.CurrentepochEditFieldLabel = uilabel(app.CuratorUIFigure);
            app.CurrentepochEditFieldLabel.Text = 'Current epoch';
            app.CurrentepochEditField = uieditfield(app.CuratorUIFigure, 'numeric');
            app.CurrentepochEditField.Editable = 'off';
            app.CellTypeSwitch = uiswitch(app.CuratorUIFigure, 'rocker');
            app.CellTypeSwitch.Items = {'retina', 'brain'};
            app.CellTypeSwitch.Value = 'retina';
            app.QuickCheatTable = uitable(app.CuratorUIFigure);
            app.QuickCheatTable.ColumnName = {'Epoch Block ID'; 'Start'; 'End'; 'Protocol'};
            app.QuickCheatTable.RowName = {};
            app.QuickCheatLabel = uilabel(app.CuratorUIFigure);
            app.QuickCheatLabel.Text = 'Quick Cheat';
            % Show the figure after all components are created
            app.CuratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Curator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CuratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CuratorUIFigure)
        end
    end
end