classdef DataExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataExplorerUIFigure          matlab.ui.Figure
        LoadcellEditField             matlab.ui.control.EditField
        LoadcellButton                matlab.ui.control.Button
        LoadcellnamestxtButton        matlab.ui.control.Button
        ForwardButton                 matlab.ui.control.Button
        BackButton                    matlab.ui.control.Button
        ofLabel                       matlab.ui.control.Label
        ListIndexEdit                 matlab.ui.control.NumericEditField
        ListLengthEdit                matlab.ui.control.NumericEditField
        CurrentcellEditFieldLabel     matlab.ui.control.Label
        CurrentcellEditField          matlab.ui.control.EditField
        LoadqueryButton               matlab.ui.control.Button
        QueryeditorButton             matlab.ui.control.Button
        CellclassEditFieldLabel       matlab.ui.control.Label
        CellclassEditField            matlab.ui.control.EditField
        CelltypeEditFieldLabel        matlab.ui.control.Label
        CelltypeEditField             matlab.ui.control.EditField
        PlotlevelDropDownLabel        matlab.ui.control.Label
        PlotlevelDropDown             matlab.ui.control.DropDown
        PlotfuncDropDownLabel         matlab.ui.control.Label
        PlotfuncDropDown              matlab.ui.control.DropDown
        Currentdatatoh5Button         matlab.ui.control.Button
        LoadstateButton               matlab.ui.control.Button
        SavestateButton               matlab.ui.control.Button
        CurrentdatasetnameEditFieldLabel  matlab.ui.control.Label
        CurrentdatasetnameEditField   matlab.ui.control.EditField
        PlotsPanel                    matlab.ui.container.Panel
        ValsTable                     matlab.ui.control.Table
        MatchescurrentqueryLampLabel  matlab.ui.control.Label
        MatchescurrentqueryLamp       matlab.ui.control.Lamp
        EntryTypeLabel                matlab.ui.control.Label
        ResultdatabaseDropDownLabel   matlab.ui.control.Label
        ResultdatabaseDropDown        matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        cell_id_list % cell array of cell_ids to work on
        listIndex = 1; % index into cell_ind_list
        loadedCellQuery = []; % query of sl.SymphonyRecorded cell for current_cell_id
        loadedQuery = []; % query loaded
        loadedQueryState = []; % Full state of query editor
        connection = []; % DJ connection
        plotLevel = []; % Selected plot level (Epoch, Dataset, or Cell)
        plotName = []; % Name of selected plotter function
        dataStructs = []; % vector of structures: one of each item to loop through
        searchTable = []; % For current query... must match the function type selected
        curEntry = []; % current single query item for plotter
        results_db; % name of results database to use
    end
    
    methods (Access = private)
        
        function updateLoadedData(app)
            %app.plotLevel = get(app.PlotfuncDropDown, 'value');
            if isempty(app.loadedQueryState)
                eval(sprintf('app.loadedQuery=%s.%sResult;', app.results_db, app.plotLevel));
            end
            set(app.EntryTypeLabel,'text',app.plotLevel);
            if isempty(app.cell_id_list)
                q = app.loadedQuery;
            else
                q = app.loadedQuery & struct('cell_id', app.cell_id_list);
            end
            if q.exists
                set(app.ListLengthEdit, 'value', q.count);
                set(app.ListIndexEdit, 'value', 1);
            else
                set(app.ListLengthEdit, 'value', 0);
                set(app.ListIndexEdit, 'value', 0);
            end
            app.listIndex = 1;
            app.dataStructs = q.fetch();
            app.updateCurrentEntry();
        end
        
        function updatePlots(app)
            set(app.DataExplorerUIFigure,'Name','Plotting...');
            drawnow();
            R = app.curEntry.fetch1('result');
            entryID = app.curEntry.fetch();
            ch = get(app.PlotsPanel,'children');
            for i=1:length(ch)
                if contains(class(ch(i)),'UIAxes')
                    delete(ch(i));
                end
            end
            panelPos = get(app.PlotsPanel, 'Position');
            w = panelPos(3);
            h = panelPos(4);
            Naxes = nargin(app.plotName) - 2;
            for i=1:Naxes
                ax(i) = uiaxes(app.PlotsPanel);
                set(ax(i),'Visible',false);
            end
            tableData = [];
            
            try
                ax_str = [];
                for i=1:Naxes
                    ax_str = [ax_str, sprintf(',ax(%d)',i)];
                end
                eval(sprintf('tableData=%s(R,entryID%s);', app.plotName, ax_str));
                
                for i=1:Naxes
                    partWidth = .9*w / Naxes;
                    set(ax(i),'Position',[.05*w+(i-1)*partWidth .05*h partWidth .9*h])
                    set(ax(i),'Parent',app.PlotsPanel);
                    set(ax(i),'Visible',true);
                end
            catch ME
                fprintf('Plot %s failed\n', app.plotName);
                %rethrow(ME)
            end
            
            if ~isempty(tableData)
                D = struct2table(tableData);
            else
                D = table;
            end
            set(app.ValsTable, 'ColumnName', D.Properties.VariableNames);
            set(app.ValsTable, 'Data', D);
            set(app.DataExplorerUIFigure,'Name','DataExplorer');
        end
        
        function checkLevelMatch(app)
            if ~isempty(app.loadedQueryState)
                curTables = app.loadedQueryState.currentTables;
                queryMatch = true;
                if ~isempty(app.loadedQueryState)
                    switch get(app.PlotlevelDropDown, 'value')
                        case 'Epoch'
                            if ismember('EpochResult', curTables) && ...
                                    ~ismember('DatasetResult', curTables) && ...
                                    ~ismember('CellResult', curTables)
                                queryMatch = true;
                            else
                                queryMatch = false;
                            end
                        case 'Dataset'
                            if ismember('DatasetResult', curTables) && ...
                                    ~ismember('EpochResult', curTables) && ...
                                    ~ismember('CellResult', curTables)
                                queryMatch = true;
                            else
                                queryMatch = false;
                            end
                        case 'Cell'
                            if ismember('CellResult', curTables) && ...
                                    ~ismember('EpochResult', curTables) && ...
                                    ~ismember('DatasetResult', curTables)
                                queryMatch = true;
                            else
                                queryMatch = false;
                            end
                    end
                    if queryMatch
                        set(app.MatchescurrentqueryLamp, 'color', 'g');
                    else
                        set(app.MatchescurrentqueryLamp, 'color', 'r');
                    end
                end
            end
        end
        
        function loadPlotFuncMenu(app)
            switch(get(app.PlotlevelDropDown,'value'))
                case 'Epoch'
                    d_shared = dir([getenv('DJ_root') 'plotting_functions' filesep 'epoch_plotters' filesep '*.m']);
                    d_user = dir([getenv('user_epoch_plotters_folder') '*.m']);
                case 'Dataset'
                    d_shared = dir([getenv('DJ_root') 'plotting_functions' filesep 'dataset_plotters' filesep '*.m']);
                    d_user = dir([getenv('user_dataset_plotters_folder') '*.m']);
                case 'Cell'
                    d_shared = dir([getenv('DJ_root') 'plotting_functions' filesep 'cell_plotters' filesep '*.m']);
                    d_user = dir([getenv('user_cell_plotters_folder') '*.m']);
            end
            N = length(d_user);
            fnames_user = cell(1,N);
            for i=1:N
                fnames_user{i} = strtok(d_user(i).name,'.');
            end
            
            N = length(d_shared);
            fnames_shared = cell(1,N);
            for i=1:N
                fnames_shared{i} = strtok(d_shared(i).name,'.');
            end
            
            fnames_all = [fnames_user, '-------------------', fnames_shared];
                        
            set(app.PlotfuncDropDown, 'items', fnames_all);
            app.plotName = get(app.PlotfuncDropDown, 'value');
        end
        
        function updateCurrentEntry(app)
            app.curEntry = app.loadedQuery & app.dataStructs(app.listIndex);
            curEntry_struct = app.curEntry.fetch();
            set(app.CurrentcellEditField, 'value', curEntry_struct.cell_id);
            if strcmp(app.plotLevel, 'Dataset')
                set(app.CurrentdatasetnameEditField,'value',curEntry_struct.dataset_name);
            end
            qtype = sl_mutable.CurrentCellType & sprintf('cell_id="%s"',curEntry_struct.cell_id);
            set(app.CellclassEditField, 'value', qtype.fetch1('cell_class'));
            set(app.CelltypeEditField, 'value', qtype.fetch1('cell_type'));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.connection = dj.conn;
            catch
                disp('Unable to establish datajoint connection.')
                app.CellTyperUIFigureCloseRequest();
            end
            
            %load database menu
            user_dbs = fetchn(sl.UserDB,'db_name');
            N = length(user_dbs);
            set(app.ResultdatabaseDropDown, 'items', user_dbs);
            %select this user's db
            if strrcmp(app.connection.user,'OfficeDesktop')
                set(app.ResultdatabaseDropDown, 'value', 'sl_shared');
            else
                set(app.ResultdatabaseDropDown, 'value', sprintf('sl_%s', lower(app.connection.user)));
            end
            app.results_db = get(app.ResultdatabaseDropDown, 'value');
            
            app.PlotlevelDropDownValueChanged([]);
            app.updateLoadedData();
            app.updatePlots();
        end

        % Close request function: DataExplorerUIFigure
        function DataExplorerUIFigureCloseRequest(app, event)
            if ~isempty(app.connection) && app.connection.isConnected
                app.connection.close();
            end
            delete(app);
        end

        % Button pushed function: LoadcellButton
        function LoadcellButtonPushed(app, event)
            cell_id = get(app.LoadcellEditField, 'value');
            try
                app.cell_id_list = {cell_id};
                app.listIndex = 1;
            catch
                app.cell_id_list = {};
                fprintf('Unable to load cell %s from database', cell_id);
            end
            app.updateLoadedData();
            app.updatePlots();
        end

        % Button pushed function: LoadcellnamestxtButton
        function LoadcellnamestxtButtonPushed(app, event)
            [fname, fpath] = uigetfile('*.txt','Select plain text file with cell names.');
            if fname
                app.cell_id_list = importdata([fpath, fname]);
                app.listIndex = 1;
                set(app.ListIndexEdit,'value',1);
                set(app.ListLengthEdit,'value',length(app.cell_id_list));
                app.updateLoadedData()
                app.updatePlots();
            end
        end

        % Button pushed function: LoadqueryButton
        function LoadqueryButtonPushed(app, event)
            [fname, fpath] = uigetfile('.mat','Load query file',getenv('queryFolder'));
            if fname
                load([fpath fname], 'queryState');
                app.loadedQueryState = queryState;
                app.loadedQuery = parseQueryStruct(queryState);
                [~,username] = strtok(app.results_db,'_');
                username = username(2:end); %remove leading '_'
                app.searchTable = makeSearchTable(queryState.currentTables,username);
                app.updateLoadedData();
                app.checkLevelMatch();
                app.updatePlots();
            end
        end

        % Button pushed function: QueryeditorButton
        function QueryeditorButtonPushed(app, event)
            h = AppDataHandle;
            tableStartState.availableTables = {'DatasetResult',...
                'EpochResult', ...
                'CellResult',...
                'CurrentCellType'...
                };
            tableStartState.initTables = {sprintf('%sResult', app.plotLevel)};
            tableStartState.results_db = app.results_db;
            qb_app = QueryBuilder(h, app.loadedQueryState, tableStartState);
            waitfor(qb_app);
            if isfield(h.data, 'queryResult')
                app.loadedQueryState = h.data.queryState;
                app.loadedQuery = h.data.queryResult;
                app.searchTable = h.data.searchTable;
                app.updateLoadedData();
                app.checkLevelMatch();
                app.updatePlots();
            end
        end

        % Value changed function: PlotlevelDropDown
        function PlotlevelDropDownValueChanged(app, event)
            value = app.PlotlevelDropDown.Value;
            app.plotLevel = value;
            app.loadPlotFuncMenu();
            app.updateLoadedData();
            app.checkLevelMatch();
        end

        % Value changed function: PlotfuncDropDown
        function PlotfuncDropDownValueChanged(app, event)
            value = app.PlotfuncDropDown.Value;
            app.plotName = value;
            app.updatePlots();
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            if app.listIndex < length(app.dataStructs)
                app.listIndex = app.listIndex+1;
                set(app.ListIndexEdit,'value',app.listIndex);
                app.updateCurrentEntry();
                app.updatePlots();
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            if app.listIndex > 1
                app.listIndex = app.listIndex-1;
                set(app.ListIndexEdit,'value',app.listIndex);
                app.updateCurrentEntry();
                app.updatePlots();
            end
        end

        % Button pushed function: Currentdatatoh5Button
        function Currentdatatoh5ButtonPushed(app, event)
            switch app.plotLevel
                case 'Epoch'
                    entryID = app.curEntry.fetch();
                    [timeAxis, data] = epochRawData(entryID.cell_id, entryID.epoch_number);
                    dataToH5ForIgor(timeAxis, data, [entryID.cell_id '.h5'], sprintf('e%d', entryID.epoch_number));
                case 'Dataset'
                    datasetResultToH5(app.curEntry.fetch1('cell_id'), app.curEntry.fetch1('dataset_name'), app.results_db);
                case 'Cell'
                    disp('Not implemented yet');
            end
        end

        % Button pushed function: SavestateButton
        function SavestateButtonPushed(app, event)
            meta = ?DataExplorer;
            props = meta.PropertyList;
            allAccess = {props.GetAccess};
            allNames = {props.Name};
            
            private_ind = strcmp(allAccess, 'private');
            fields = allNames(private_ind);
            appState = struct;
            for i=1:length(fields)
                appState.(fields{i}) = app.(fields{i});
            end
            [fname, fpath] = uiputfile('*.mat','Save file with app state...',getenv('DataExplorer_states_folder'));
            if fname
                save([fpath, fname], 'appState');
            end
        end

        % Button pushed function: LoadstateButton
        function LoadstateButtonPushed(app, event)
            [fname, fpath] = uigetfile('*.mat','Selet app state file', getenv('DataExplorer_states_folder'));
            if fname
                load([fpath fname], 'appState');
                fields = fieldnames(appState);
                for i=1:length(fields)
                    app.(fields{i}) = appState.(fields{i});
                end
                set(app.PlotlevelDropDown, 'value', app.plotLevel);
                set(app.PlotfuncDropDown, 'value', app.plotName);
                app.updateLoadedData();
                app.updateCurrentEntry();
                app.updatePlots();
            end
        end

        % Value changed function: ResultdatabaseDropDown
        function ResultdatabaseDropDownValueChanged(app, event)
            value = app.ResultdatabaseDropDown.Value;
            app.results_db = value;
            app.updateLoadedData();
        end

        % Value changed function: ListIndexEdit
        function ListIndexEditValueChanged(app, event)
            value = app.ListIndexEdit.Value;
            if value > 0 && value <= length(app.dataStructs)
                app.listIndex = value;
                app.updateCurrentEntry();
                app.updatePlots();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataExplorerUIFigure = uifigure('Visible', 'off');
            app.DataExplorerUIFigure.Name = 'DataExplorer';
            app.DataExplorerUIFigure.CloseRequestFcn = createCallbackFcn(app, @DataExplorerUIFigureCloseRequest, true);
            app.LoadcellEditField = uieditfield(app.DataExplorerUIFigure, 'text');
            app.LoadcellButton = uibutton(app.DataExplorerUIFigure, 'push');
            app.LoadcellButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcellButtonPushed, true);
            app.LoadcellButton.Text = 'Load cell';
            app.LoadcellnamestxtButton = uibutton(app.DataExplorerUIFigure, 'push');
            app.LoadcellnamestxtButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcellnamestxtButtonPushed, true);
            app.LoadcellnamestxtButton.Text = 'Load cell names .txt';
            app.ForwardButton = uibutton(app.DataExplorerUIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = '→';
            app.BackButton = uibutton(app.DataExplorerUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = '←';
            app.ofLabel = uilabel(app.DataExplorerUIFigure);
            app.ofLabel.Text = 'of';
            app.ListIndexEdit = uieditfield(app.DataExplorerUIFigure, 'numeric');
            app.ListIndexEdit.Limits = [0 Inf];
            app.ListIndexEdit.RoundFractionalValues = 'on';
            app.ListIndexEdit.ValueChangedFcn = createCallbackFcn(app, @ListIndexEditValueChanged, true);
            app.ListLengthEdit = uieditfield(app.DataExplorerUIFigure, 'numeric');
            app.ListLengthEdit.Limits = [0 Inf];
            app.ListLengthEdit.RoundFractionalValues = 'on';
            app.ListLengthEdit.Editable = 'off';
            app.CurrentcellEditFieldLabel = uilabel(app.DataExplorerUIFigure);
            app.CurrentcellEditFieldLabel.Text = 'Current cell';
            app.CurrentcellEditField = uieditfield(app.DataExplorerUIFigure, 'text');
            app.CurrentcellEditField.Editable = 'off';
            app.LoadqueryButton = uibutton(app.DataExplorerUIFigure, 'push');
            app.LoadqueryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadqueryButtonPushed, true);
            app.LoadqueryButton.Text = 'Load query';
            app.QueryeditorButton = uibutton(app.DataExplorerUIFigure, 'push');
            app.QueryeditorButton.ButtonPushedFcn = createCallbackFcn(app, @QueryeditorButtonPushed, true);
            app.QueryeditorButton.Text = 'Query editor';
            app.CellclassEditFieldLabel = uilabel(app.DataExplorerUIFigure);
            app.CellclassEditFieldLabel.Text = 'Cell class';
            app.CellclassEditField = uieditfield(app.DataExplorerUIFigure, 'text');
            app.CellclassEditField.Editable = 'off';
            app.CelltypeEditFieldLabel = uilabel(app.DataExplorerUIFigure);
            app.CelltypeEditFieldLabel.Text = 'Cell type';
            app.CelltypeEditField = uieditfield(app.DataExplorerUIFigure, 'text');
            app.CelltypeEditField.Editable = 'off';
            app.PlotlevelDropDownLabel = uilabel(app.DataExplorerUIFigure);
            app.PlotlevelDropDownLabel.Text = 'Plot level';
            app.PlotlevelDropDown = uidropdown(app.DataExplorerUIFigure);
            app.PlotlevelDropDown.Items = {'Epoch', 'Dataset', 'Cell'};
            app.PlotlevelDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotlevelDropDownValueChanged, true);
            app.PlotlevelDropDown.Value = 'Dataset';
            app.PlotfuncDropDownLabel = uilabel(app.DataExplorerUIFigure);
            app.PlotfuncDropDownLabel.Text = 'Plot func.';
            app.PlotfuncDropDown = uidropdown(app.DataExplorerUIFigure);
            app.PlotfuncDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotfuncDropDownValueChanged, true);
            app.Currentdatatoh5Button = uibutton(app.DataExplorerUIFigure, 'push');
            app.Currentdatatoh5Button.ButtonPushedFcn = createCallbackFcn(app, @Currentdatatoh5ButtonPushed, true);
            app.Currentdatatoh5Button.Text = 'Current data to .h5';
            app.LoadstateButton = uibutton(app.DataExplorerUIFigure, 'push');
            app.LoadstateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadstateButtonPushed, true);
            app.LoadstateButton.Text = 'Load state';
            app.SavestateButton = uibutton(app.DataExplorerUIFigure, 'push');
            app.SavestateButton.ButtonPushedFcn = createCallbackFcn(app, @SavestateButtonPushed, true);
            app.SavestateButton.Text = 'Save state';
            app.CurrentdatasetnameEditFieldLabel = uilabel(app.DataExplorerUIFigure);
            app.CurrentdatasetnameEditFieldLabel.Text = 'Current dataset name';
            app.CurrentdatasetnameEditField = uieditfield(app.DataExplorerUIFigure, 'text');
            app.CurrentdatasetnameEditField.Editable = 'off';
            app.PlotsPanel = uipanel(app.DataExplorerUIFigure);
            app.ValsTable = uitable(app.DataExplorerUIFigure);
            app.ValsTable.ColumnName = {''; ''; ''; ''};
            app.ValsTable.RowName = {};
            app.MatchescurrentqueryLampLabel = uilabel(app.DataExplorerUIFigure);
            app.MatchescurrentqueryLampLabel.Text = 'Matches current query';
            app.MatchescurrentqueryLamp = uilamp(app.DataExplorerUIFigure);
            app.EntryTypeLabel = uilabel(app.DataExplorerUIFigure);
            app.EntryTypeLabel.Text = 'Dataset';
            app.ResultdatabaseDropDownLabel = uilabel(app.DataExplorerUIFigure);
            app.ResultdatabaseDropDownLabel.Text = 'Result database';
            app.ResultdatabaseDropDown = uidropdown(app.DataExplorerUIFigure);
            app.ResultdatabaseDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultdatabaseDropDownValueChanged, true);
            % Show the figure after all components are created
            app.DataExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataExplorerUIFigure)
        end
    end
end