classdef Diary_plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiaryPlotterUIFigure        matlab.ui.Figure
        TableP                      matlab.ui.control.Table
        TableB                      matlab.ui.control.Table
        TableA                      matlab.ui.control.Table
        ColorButton                 matlab.ui.control.Button
        DeleteconditionButton       matlab.ui.control.Button
        ConditionNameEditField      matlab.ui.control.EditField
        AddconditionButton          matlab.ui.control.Button
        ConditionsPanel             matlab.ui.container.Panel
        SelectionBEditField         matlab.ui.control.EditField
        SelectionBEditField_2Label  matlab.ui.control.Label
        SelectionAEditField         matlab.ui.control.EditField
        SelectionAEditField_2Label  matlab.ui.control.Label
        DrawBButton                 matlab.ui.control.Button
        DrawAButton                 matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        RightUpperEdit              matlab.ui.control.NumericEditField
        RightLowerEdit              matlab.ui.control.NumericEditField
        LeftUpperEdit               matlab.ui.control.NumericEditField
        LeftLowerEdit               matlab.ui.control.NumericEditField
        RightaxisDropDown           matlab.ui.control.DropDown
        RightaxisDropDownLabel      matlab.ui.control.Label
        LeftaxisDropDown            matlab.ui.control.DropDown
        LeftaxisDropDownLabel       matlab.ui.control.Label
        LoadButton                  matlab.ui.control.Button
        EpochsEditField             matlab.ui.control.EditField
        EpochsEditFieldLabel        matlab.ui.control.Label
        CellnameEditField           matlab.ui.control.EditField
        CellnameEditFieldLabel      matlab.ui.control.Label
        TempAxis                    matlab.ui.control.UIAxes
        ConditionsAxis              matlab.ui.control.UIAxes
        WaveAxisB                   matlab.ui.control.UIAxes
        WaveAxisA                   matlab.ui.control.UIAxes
        DiaryAxis_T                 matlab.ui.control.UIAxes
        DiaryAxis_B                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        results_struct % all epoch results
        epoch_times % s, since epoch 1
        good_ind % logical vector for indexing epochs
        selectionA = []% which epochs selected for axisA
        selectionB = []% which epochs selected for axisB        
        roiA = [] % rectangle ROI for selectionA
        roiB = [] % rectangle ROI for selectionB
        condition_rois = dictionary % vector of roi (handles) for each condition
    end
    
    methods (Access = private)
        
        function init_dropdowns(app)
            fnames = fieldnames(app.results_struct);
            fnames = setdiff(fnames,{'file_name', ...
                'source_id', ...
                'epoch_group_id', ...
                'epoch_block_id', ...
                'epoch_id', ...
                'channel_name', ...
                'analysis_entry_time', ...
                'isolated_spike_shape', ...
                'epoch_start_time', ...
                'epoch_duration' ...
                });
            app.LeftaxisDropDown.Items = fnames;
            app.RightaxisDropDown.Items = ['None'; fnames];
            app.LeftaxisDropDown.Value = fnames{1};
            app.RightaxisDropDown.Value = "None";
        end
        
        function update_plot(app)
            app.DiaryPlotterUIFigure.Name = 'plotting...';
            if ishandle(app.roiA)
                app.roiA.Parent = app.TempAxis;
            end
            if ishandle(app.roiB)
                app.roiB.Parent = app.TempAxis;
            end
            yyaxis(app.DiaryAxis_B,'left'); 
            cla(app.DiaryAxis_B);
            yyaxis(app.DiaryAxis_B,'right'); 
            cla(app.DiaryAxis_B);
            yyaxis(app.DiaryAxis_T,'left'); 
            cla(app.DiaryAxis_T);
            yyaxis(app.DiaryAxis_T,'right'); 
            cla(app.DiaryAxis_T);
                      
            yvals_left = [app.results_struct.(app.LeftaxisDropDown.Value)];
            yvals_left = yvals_left(app.good_ind);
            if strcmp(app.RightaxisDropDown.Value,'None')
                yvals_right = [];
            else
                yvals_right= [app.results_struct.(app.RightaxisDropDown.Value)];
                yvals_right = yvals_right(app.good_ind);
            end
            yyaxis(app.DiaryAxis_B,'left');    
            scatter(app.DiaryAxis_B,app.epoch_times(app.good_ind),yvals_left,'bo','filled');
            ylabel(app.DiaryAxis_B,app.LeftaxisDropDown.Value,'Interpreter','none');            
            app.DiaryAxis_T.XTickMode = 'manual';
            epoch_ticks = [app.results_struct(app.good_ind).epoch_id];
            epoch_times = [app.epoch_times(app.good_ind)];
            app.DiaryAxis_T.XTick = epoch_times(1:10:end);  
            app.DiaryAxis_T.XTickLabel = epoch_ticks(1:10:end);
            app.DiaryAxis_T.YTick = [];

            if ~isempty(yvals_right)
                yyaxis(app.DiaryAxis_B,'right');
                scatter(app.DiaryAxis_B,app.epoch_times(app.good_ind),yvals_right,'ro','filled');
                ylabel(app.DiaryAxis_B,app.RightaxisDropDown.Value,'Interpreter','none');
                app.DiaryAxis_B.YTickMode = 'auto';
                right_lims = app.DiaryAxis_B.YLim;
                app.RightLowerEdit.Value = right_lims(1);
                app.RightUpperEdit.Value = right_lims(2);
            else
                yyaxis(app.DiaryAxis_B,'right');
                ylabel(app.DiaryAxis_B,'');
                app.DiaryAxis_B.YTick = [];
            end

            yyaxis(app.DiaryAxis_B,'left');
            left_lims = app.DiaryAxis_B.YLim;
            app.LeftLowerEdit.Value = left_lims(1);
            app.LeftUpperEdit.Value = left_lims(2);

            if ishandle(app.roiA)
                app.roiA.Position(2) = left_lims(1);
                app.roiA.Position(4) = left_lims(2) - left_lims(1);
                app.roiA.Parent = app.DiaryAxis_B;
            end
            if ishandle(app.roiB)
                app.roiB.Position(2) = left_lims(1);
                app.roiB.Position(4) = left_lims(2) - left_lims(1);
                app.roiB.Parent = app.DiaryAxis_B;
            end

            app.DiaryAxis_B.XLim = [min(app.epoch_times(app.good_ind))-1, max(app.epoch_times(app.good_ind))+1];
            app.DiaryAxis_T.XLim = [min(app.epoch_times(app.good_ind))-1, max(app.epoch_times(app.good_ind))+1];
            app.ConditionsAxis.XLim = app.DiaryAxis_B.XLim;
            app.update_tables;
            app.DiaryPlotterUIFigure.Name = 'Diary Plotter';
        end
        
        function update_waveform_plot(app,whichOne)
            app.DiaryPlotterUIFigure.Name = 'plotting waveforms...';
            axis_name = sprintf('WaveAxis%s',whichOne);
            selection_name = sprintf('selection%s',whichOne);
            selected_epochs = app.(selection_name);
            edit_field_name = sprintf('Selection%sEditField',whichOne);
            roi_name = sprintf('roi%s',whichOne);

            if isempty(selected_epochs)
                delete(app.(roi_name));
                app.(roi_name) = [];
                cla(app.(axis_name));
                app.(edit_field_name).Value = '';
            else
                cla(app.(axis_name));
                hold(app.(axis_name), "on");
                epoch_ids = [app.results_struct.epoch_id];
                for i=1:length(selected_epochs)
                    ind = find(epoch_ids==selected_epochs(i));
                    if ~isempty(ind)
                        channel = sln_symphony.ExperimentChannel & app.results_struct(ind);
                        sample_rate = fetch1(channel,'sample_rate');
                        waveform = app.results_struct(ind).isolated_spike_shape;
                        L = length(waveform)-1;
                        L_ms = 1E3 * L / sample_rate;
                        xvals = -L_ms/2:1E3/sample_rate:L_ms/2;
                        plot(app.(axis_name),xvals,waveform,'Color',[0.7 0.7 0.7],'LineWidth',0.5);
                    end

                end
                hold(app.(axis_name), "off");
            end
            app.DiaryPlotterUIFigure.Name = 'Diary Plotter';
        end
        
        function update_tables(app)
            l_axis = app.LeftaxisDropDown.Value;
            valsL_A = [];
            valsL_B = [];
            valsR_A = [];
            valsR_B = [];

            epoch_ids = [app.results_struct.epoch_id];
            
            if ~isempty(app.selectionA)
                app.TableA.ColumnName{2} = l_axis;
                indA = ismember(epoch_ids,app.selectionA);
                vals = [app.results_struct(indA).(l_axis)];
                vals = vals(~isnan(vals));
                app.TableA.Data{1,2} = mean(vals);
                app.TableA.Data{2,2} = length(vals);
                app.TableA.Data{3,2} = std(vals);
                app.TableA.Data{4,2} = std(vals) / sqrt(length(vals)-1);
                valsL_A = vals;
            end
            if ~isempty(app.selectionB)
                app.TableB.ColumnName{2} = l_axis;
                indB = ismember(epoch_ids,app.selectionB);
                vals = [app.results_struct(indB).(l_axis)];
                vals = vals(~isnan(vals));
                app.TableB.Data{1,2} = mean(vals);
                app.TableB.Data{2,2} = length(vals);
                app.TableB.Data{3,2} = std(vals);
                app.TableB.Data{4,2} = std(vals) / sqrt(length(vals)-1);
                valsL_B = vals;
            end
            
            if ~isempty(valsL_A) && ~isempty(valsL_B)
                app.TableP.Data{1,1} = l_axis;
                [~,p] = ttest2(valsL_A,valsL_B);
                app.TableP.Data{1,2} = p;
            end
            
            r_axis = app.RightaxisDropDown.Value;
            if ~strcmp(r_axis,'None')
                if ~isempty(app.selectionA)
                    app.TableA.ColumnName{3} = r_axis;
                    indA = ismember(epoch_ids,app.selectionA);
                    vals = [app.results_struct(indA).(r_axis)];
                    vals = vals(~isnan(vals));
                    app.TableA.Data{1,3} = mean(vals);
                    app.TableA.Data{2,3} = length(vals);
                    app.TableA.Data{3,3} = std(vals);
                    app.TableA.Data{4,3} = std(vals) / sqrt(length(vals)-1);
                    valsR_A = vals;
                end
                if ~isempty(app.selectionB)
                    app.TableB.ColumnName{3} = r_axis;
                    indB = ismember(epoch_ids,app.selectionB);
                    vals = [app.results_struct(indB).(r_axis)];
                    vals = vals(~isnan(vals));
                    app.TableB.Data{1,3} = mean(vals);
                    app.TableB.Data{2,3} = length(vals);
                    app.TableB.Data{3,3} = std(vals);
                    app.TableB.Data{4,3} = std(vals) / sqrt(length(vals)-1);
                    valsR_B = vals;
                end

                if ~isempty(valsR_A) && ~isempty(valsR_B)
                    app.TableP.Data{2,1} = r_axis;
                    [~,p] = ttest2(valsR_A,valsR_B);
                    app.TableP.Data{2,2} = p;
                end
            end

        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            t = tiledlayout(app.Panel,1,1);
            app.DiaryAxis_B.Parent = t;
            app.DiaryAxis_T.Parent = t;  
            app.DiaryAxis_B.Box = "off";
            app.DiaryAxis_T.Box = "off";
            app.DiaryAxis_T.Color = 'none';
            app.DiaryAxis_B.XTickMode = 'auto';
            app.DiaryAxis_T.XTickMode = 'manual';
            app.DiaryAxis_B.YTickMode = 'auto';
            app.DiaryAxis_T.YTickMode = 'manual';
            app.DiaryAxis_T.YTick = [];            
            app.DiaryAxis_B.XLimitMethod = "tight";
            app.DiaryAxis_T.XLimitMethod = "tight";         

            tc = tiledlayout(app.ConditionsPanel,1,1);
            app.ConditionsAxis.Parent = tc;
            app.ConditionsAxis.XLimitMethod = 'tight';
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            thisCell = sln_cell.CellName & ...
                sprintf('cell_name="%s"',app.CellnameEditField.Value);
            if ~thisCell.exists
                fig = uifigure;
                uialert(fig,'Cell not found','Cell not found');
                return;
            end
            epochResults = sln_results.CCEpochStats & thisCell;
            if ~epochResults.exists
                fig = uifigure;
                uialert(fig,'No epoch results found for this cell','No epoch results found');
                return;
            end
            app.DiaryPlotterUIFigure.Name = 'loading...';
            first_epoch_id = min(fetchn(aka.Epoch & thisCell,'epoch_id'));
            first_epoch_start_time = fetch1(aka.Epoch & thisCell & ...
                sprintf('epoch_id=%d',first_epoch_id), 'epoch_start_time');
            app.results_struct = fetch(epochResults,'*');
            selected_epochs = [];
            try
                selected_epochs = eval(app.EpochsEditField.Value);
            catch
            end
            if ~isempty(selected_epochs)
                app.good_ind = ismember([app.results_struct.epoch_id],selected_epochs);
            else
                app.good_ind = true(1,length(app.results_struct));
            end
            epoch_times = fetchn(aka.Epoch & epochResults, 'epoch_start_time');
            app.epoch_times = (epoch_times - first_epoch_start_time) / 1E3; %s
            app.init_dropdowns;
            app.roiA = [];
            app.roiB = [];
            cla(app.WaveAxisA);
            cla(app.WaveAxisB);
            app.SelectionAEditField.Value = '';
            app.SelectionBEditField.Value = '';
            cla(app.ConditionsAxis);
            app.condition_rois = dictionary;

            app.TableA.Data = cell(5,3);
            app.TableB.Data = cell(5,3);

            app.TableA.Data{1,1} = 'Mean';
            app.TableA.Data{2,1} = 'N';
            app.TableA.Data{3,1} = 'STD';
            app.TableA.Data{4,1} = 'SEM';

            app.TableB.Data{1,1} = 'Mean';
            app.TableB.Data{2,1} = 'N';
            app.TableB.Data{3,1} = 'STD';
            app.TableB.Data{4,1} = 'SEM';

            app.TableA.ColumnName = {' '};
            app.TableB.ColumnName = {' '};          

            app.TableP.Data = [];           
            app.DiaryPlotterUIFigure.Name = 'Diary Plotter';
            app.update_plot;
        end

        % Value changed function: LeftaxisDropDown
        function LeftaxisDropDownValueChanged(app, event)
            app.update_plot;
        end

        % Value changed function: RightaxisDropDown
        function RightaxisDropDownValueChanged(app, event)
            app.update_plot;            
        end

        % Value changed function: LeftLowerEdit
        function LeftLowerEditValueChanged(app, event)
            value = app.LeftLowerEdit.Value;
            yyaxis(app.DiaryAxis_B,'left');
            lims = app.DiaryAxis_B.YLim;
            if value<lims(2)
                app.DiaryAxis_B.YLim = [value, lims(2)];
            end
        end

        % Value changed function: LeftUpperEdit
        function LeftUpperEditValueChanged(app, event)
            value = app.LeftUpperEdit.Value;
            yyaxis(app.DiaryAxis_B,'left');
            lims = app.DiaryAxis_B.YLim;
            if value>lims(1)
                app.DiaryAxis_B.YLim = [lims(1), value];
            end
        end

        % Value changed function: RightLowerEdit
        function RightLowerEditValueChanged(app, event)
            value = app.RightLowerEdit.Value;
            yyaxis(app.DiaryAxis_B,'right');
            lims = app.DiaryAxis_B.YLim;
            if value<lims(2)
                app.DiaryAxis_B.YLim = [value, lims(2)];
            end
        end

        % Value changed function: RightUpperEdit
        function RightUpperEditValueChanged(app, event)
            value = app.RightUpperEdit.Value;
            yyaxis(app.DiaryAxis_B,'right');
            lims = app.DiaryAxis_B.YLim;
            if value>lims(1)
                app.DiaryAxis_B.YLim = [lims(1), value];
            end
        end

        % Value changed function: EpochsEditField
        function EpochsEditFieldValueChanged(app, event)
            value = app.EpochsEditField.Value;
            selected_epochs = [];
            try
                selected_epochs = eval(value);
            catch
            end
            if ~isempty(selected_epochs)
                app.good_ind = ismember([app.results_struct.epoch_id],selected_epochs);
            else
                app.good_ind = true(1,length(app.results_struct));
            end
            app.update_plot;
        end

        % Value changed function: SelectionBEditField
        function SelectionBEditFieldValueChanged(app, event)
            value = app.SelectionBEditField.Value;
            try
                app.selectionB = eval(value);
            catch
            end
            if ~isempty(app.selectionB)
                first_epoch = min(app.selectionB);
                last_epoch = max(app.selectionB);
                epoch_ids = [app.results_struct.epoch_id];
                ind_first = find(epoch_ids>=first_epoch, 1);
                ind_last = find(epoch_ids<=last_epoch, 1, 'last');
                first_time = app.epoch_times(ind_first);
                last_time = app.epoch_times(ind_last);
                if ~isempty(app.roiB)
                    app.roiB.Position(1) = first_time;
                    app.roiB.Position(3) = last_time - first_time;                    
                end
                app.update_tables;
                app.update_waveform_plot('B');
            end
        end

        % Value changed function: SelectionAEditField
        function SelectionAEditFieldValueChanged(app, event)
            value = app.SelectionAEditField.Value;
            try
                app.selectionA = eval(value);
            catch
            end
            if ~isempty(app.selectionA)
                first_epoch = min(app.selectionA);
                last_epoch = max(app.selectionA);
                epoch_ids = [app.results_struct.epoch_id];
                ind_first = find(epoch_ids>=first_epoch, 1);
                ind_last = find(epoch_ids<=last_epoch, 1, 'last');
                first_time = app.epoch_times(ind_first);
                last_time = app.epoch_times(ind_last);
                if ~isempty(app.roiA)
                    app.roiA.Position(1) = first_time;
                    app.roiA.Position(3) = last_time - first_time;                    
                end
                app.update_tables;
                app.update_waveform_plot('A');
            end
        end

        % Button pushed function: DrawAButton
        function DrawAButtonPushed(app, event)
            if ~isempty(app.roiA)
                delete(app.roiA);
            end
            app.roiA = drawrectangle(app.DiaryAxis_B,'Label','A','Color',[0 1 1]);
            tmin = app.roiA.Position(1);
            tmax = tmin + app.roiA.Position(3);
            ind = find(app.epoch_times >= tmin & app.epoch_times <= tmax);
            epoch_ids = [app.results_struct.epoch_id];            
            app.SelectionAEditField.Value = sprintf('%d:%d',epoch_ids(ind(1)),epoch_ids(ind(end)));
            app.selectionA = epoch_ids(ind(1)):epoch_ids(ind(end));            
            yyaxis(app.DiaryAxis_B,'left');
            ylims = app.DiaryAxis_B.YLim;
            app.roiA.Position(2) = ylims(1);
            app.roiA.Position(4) = ylims(2) - ylims(1);
            app.update_tables;
            app.update_waveform_plot('A');
        end

        % Button pushed function: DrawBButton
        function DrawBButtonPushed(app, event)
            if ~isempty(app.roiB)
                delete(app.roiB);
            end
            app.roiB = drawrectangle(app.DiaryAxis_B,'Label','B','Color',[1 0 1]);
            tmin = app.roiB.Position(1);
            tmax = tmin + app.roiB.Position(3);
            ind = find(app.epoch_times >= tmin & app.epoch_times <= tmax);
            epoch_ids = [app.results_struct.epoch_id];            
            app.SelectionBEditField.Value = sprintf('%d:%d',epoch_ids(ind(1)),epoch_ids(ind(end)));
            app.selectionB = epoch_ids(ind(1)):epoch_ids(ind(end));            
            yyaxis(app.DiaryAxis_B,'left');
            ylims = app.DiaryAxis_B.YLim;
            app.roiB.Position(2) = ylims(1);
            app.roiB.Position(4) = ylims(2) - ylims(1);
            app.update_tables;
            app.update_waveform_plot('B');
        end

        % Button pushed function: AddconditionButton
        function AddconditionButtonPushed(app, event)
            cond_name = app.ConditionNameEditField.Value;
            if ~isempty(cond_name)
                r = drawrectangle(app.ConditionsAxis,'Label',cond_name);
                app.condition_rois(cond_name) = r;                
            end
        end

        % Button pushed function: DeleteconditionButton
        function DeleteconditionButtonPushed(app, event)
            cond_name = app.ConditionNameEditField.Value;
            if ~isempty(cond_name)
                if app.condition_rois.isKey(cond_name)
                    delete(app.condition_rois(cond_name));
                    app.condition_rois(cond_name) = [];
                end
            end
        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            c = uisetcolor([1 1 0],'Select color for condition');
            cond_name = app.ConditionNameEditField.Value;
            if ~isempty(cond_name)
                if app.condition_rois.isKey(cond_name)
                    app.condition_rois(cond_name).Color = c;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiaryPlotterUIFigure = uifigure('Visible', 'off');
            app.DiaryPlotterUIFigure.Name = 'Diary Plotter';
            app.DiaryAxis_B = uiaxes(app.DiaryPlotterUIFigure);
            xlabel(app.DiaryAxis_B, 'Time from first epoch (s)')
            zlabel(app.DiaryAxis_B, 'Z')
            app.DiaryAxis_B.XTick = [];
            app.DiaryAxis_B.YTick = [];
            app.DiaryAxis_T = uiaxes(app.DiaryPlotterUIFigure);
            xlabel(app.DiaryAxis_T, 'Epoch number')
            zlabel(app.DiaryAxis_T, 'Z')
            app.DiaryAxis_T.XAxisLocation = 'top';
            app.DiaryAxis_T.XTick = [];
            app.DiaryAxis_T.YTick = [];
            app.WaveAxisA = uiaxes(app.DiaryPlotterUIFigure);
            title(app.WaveAxisA, 'Spike waveforms')
            xlabel(app.WaveAxisA, 'ms')
            ylabel(app.WaveAxisA, 'mV')
            zlabel(app.WaveAxisA, 'Z')
            app.WaveAxisB = uiaxes(app.DiaryPlotterUIFigure);
            title(app.WaveAxisB, 'Spike waveforms')
            xlabel(app.WaveAxisB, 'ms')
            ylabel(app.WaveAxisB, 'mV')
            zlabel(app.WaveAxisB, 'Z')
            app.ConditionsAxis = uiaxes(app.DiaryPlotterUIFigure);
            title(app.ConditionsAxis, 'Conditions')
            zlabel(app.ConditionsAxis, 'Z')
            app.ConditionsAxis.XTick = [];
            app.ConditionsAxis.YTick = [];
            app.TempAxis = uiaxes(app.DiaryPlotterUIFigure);
            title(app.TempAxis, 'Title')
            xlabel(app.TempAxis, 'X')
            ylabel(app.TempAxis, 'Y')
            zlabel(app.TempAxis, 'Z')
            app.CellnameEditFieldLabel = uilabel(app.DiaryPlotterUIFigure);
            app.CellnameEditFieldLabel.Text = 'Cellname';
            app.CellnameEditField = uieditfield(app.DiaryPlotterUIFigure, 'text');
            app.EpochsEditFieldLabel = uilabel(app.DiaryPlotterUIFigure);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.DiaryPlotterUIFigure, 'text');
            app.EpochsEditField.ValueChangedFcn = createCallbackFcn(app, @EpochsEditFieldValueChanged, true);
            app.LoadButton = uibutton(app.DiaryPlotterUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LeftaxisDropDownLabel = uilabel(app.DiaryPlotterUIFigure);
            app.LeftaxisDropDownLabel.Text = 'Left axis';
            app.LeftaxisDropDown = uidropdown(app.DiaryPlotterUIFigure);
            app.LeftaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @LeftaxisDropDownValueChanged, true);
            app.RightaxisDropDownLabel = uilabel(app.DiaryPlotterUIFigure);
            app.RightaxisDropDownLabel.Text = 'Right axis';
            app.RightaxisDropDown = uidropdown(app.DiaryPlotterUIFigure);
            app.RightaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @RightaxisDropDownValueChanged, true);
            app.LeftLowerEdit = uieditfield(app.DiaryPlotterUIFigure, 'numeric');
            app.LeftLowerEdit.ValueChangedFcn = createCallbackFcn(app, @LeftLowerEditValueChanged, true);
            app.LeftUpperEdit = uieditfield(app.DiaryPlotterUIFigure, 'numeric');
            app.LeftUpperEdit.ValueChangedFcn = createCallbackFcn(app, @LeftUpperEditValueChanged, true);
            app.RightLowerEdit = uieditfield(app.DiaryPlotterUIFigure, 'numeric');
            app.RightLowerEdit.ValueChangedFcn = createCallbackFcn(app, @RightLowerEditValueChanged, true);
            app.RightUpperEdit = uieditfield(app.DiaryPlotterUIFigure, 'numeric');
            app.RightUpperEdit.ValueChangedFcn = createCallbackFcn(app, @RightUpperEditValueChanged, true);
            app.Panel = uipanel(app.DiaryPlotterUIFigure);
            app.DrawAButton = uibutton(app.DiaryPlotterUIFigure, 'push');
            app.DrawAButton.ButtonPushedFcn = createCallbackFcn(app, @DrawAButtonPushed, true);
            app.DrawAButton.Text = 'Draw';
            app.DrawBButton = uibutton(app.DiaryPlotterUIFigure, 'push');
            app.DrawBButton.ButtonPushedFcn = createCallbackFcn(app, @DrawBButtonPushed, true);
            app.DrawBButton.Text = 'Draw';
            app.SelectionAEditField_2Label = uilabel(app.DiaryPlotterUIFigure);
            app.SelectionAEditField_2Label.Text = 'Selection A';
            app.SelectionAEditField = uieditfield(app.DiaryPlotterUIFigure, 'text');
            app.SelectionAEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionAEditFieldValueChanged, true);
            app.SelectionBEditField_2Label = uilabel(app.DiaryPlotterUIFigure);
            app.SelectionBEditField_2Label.Text = 'Selection B';
            app.SelectionBEditField = uieditfield(app.DiaryPlotterUIFigure, 'text');
            app.SelectionBEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionBEditFieldValueChanged, true);
            app.ConditionsPanel = uipanel(app.DiaryPlotterUIFigure);
            app.AddconditionButton = uibutton(app.DiaryPlotterUIFigure, 'push');
            app.AddconditionButton.ButtonPushedFcn = createCallbackFcn(app, @AddconditionButtonPushed, true);
            app.AddconditionButton.Text = 'Add condition';
            app.ConditionNameEditField = uieditfield(app.DiaryPlotterUIFigure, 'text');
            app.DeleteconditionButton = uibutton(app.DiaryPlotterUIFigure, 'push');
            app.DeleteconditionButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteconditionButtonPushed, true);
            app.DeleteconditionButton.Text = 'Delete condition';
            app.ColorButton = uibutton(app.DiaryPlotterUIFigure, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Text = 'Color';
            app.TableA = uitable(app.DiaryPlotterUIFigure);
            app.TableA.ColumnName = '';
            app.TableA.RowName = {};
            app.TableB = uitable(app.DiaryPlotterUIFigure);
            app.TableB.ColumnName = '';
            app.TableB.RowName = {};
            app.TableP = uitable(app.DiaryPlotterUIFigure);
            app.TableP.ColumnName = {'Variable'; 'p (t-test)'};
            app.TableP.RowName = {};
            % Show the figure after all components are created
            app.DiaryPlotterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Diary_plotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiaryPlotterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiaryPlotterUIFigure)
        end
    end
end