classdef H5ExportTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        h5ExporterUIFigure           matlab.ui.Figure
        BasefilenameEditFieldLabel   matlab.ui.control.Label
        BasefilenameEditField        matlab.ui.control.EditField
        EpochsLabel                  matlab.ui.control.Label
        DatasetsLabel                matlab.ui.control.Label
        CellsLabel                   matlab.ui.control.Label
        EyesLabel                    matlab.ui.control.Label
        AnimalsLabel                 matlab.ui.control.Label
        NameasDropDown_ResultFields  matlab.ui.control.DropDown
        ResultfieldsLabel            matlab.ui.control.Label
        EditField_Epochs             matlab.ui.control.EditField
        EditField_Datasets           matlab.ui.control.EditField
        EditField_Cells              matlab.ui.control.EditField
        EditField_Eyes               matlab.ui.control.EditField
        EditField_ResultFields       matlab.ui.control.NumericEditField
        EditField_Animals            matlab.ui.control.NumericEditField
        NameasLabel                  matlab.ui.control.Label
        NoneCheckBox_ResultFields    matlab.ui.control.CheckBox
        NoneCheckBox_Epochs          matlab.ui.control.CheckBox
        NoneCheckBox_Datasets        matlab.ui.control.CheckBox
        NoneCheckBox_Cells           matlab.ui.control.CheckBox
        NoneCheckBox_Eyes            matlab.ui.control.CheckBox
        NoneCheckBox_Animals         matlab.ui.control.CheckBox
        NameasDropDown_Epochs        matlab.ui.control.DropDown
        NameasDropDown_Datasets      matlab.ui.control.DropDown
        NameasDropDown_Cells         matlab.ui.control.DropDown
        NameasDropDown_Eyes          matlab.ui.control.DropDown
        NameasDropDown_Animals       matlab.ui.control.DropDown
        FilenameEditFieldLabel       matlab.ui.control.Label
        FilenameEditField            matlab.ui.control.EditField
        DatasetEditFieldLabel        matlab.ui.control.Label
        DatasetEditField             matlab.ui.control.EditField
        SampleLabel                  matlab.ui.control.Label
        VariableEditFieldLabel       matlab.ui.control.Label
        VariableEditField            matlab.ui.control.EditField
        RunButton                    matlab.ui.control.Button
        SaveandcloseButton           matlab.ui.control.Button
        ClearandcloseButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        connection % DJ connection
        resultsStruct % full analysisOutput structure
        pipeline % pipeline_name for export
        loadedState % state at load
        overwrite % whether to overwrite h5 files
    end
    
    properties (Access = public)
        exportState = struct; % struct to store settings of this app.
        dataHandle; % for storing exportState
    end
    
    methods (Access = private)
        
        function updateMenuVisibility(app, event)
            caller = event.Source.Tag;
            [~, caller_type] = strtok(caller,'_');
            set(app.(['NameasDropDown' caller_type]), 'visible', ~event.Value);        
            app.saveSettings();
        end
        
        function loadSettings(app, exportState)
            app.exportState = exportState;
            resultLevel = {'ResultFields', ...
                'Epochs', ...
                'Datasets', ...
                'Cells', ...
                'Eyes', ...
                'Animals'};
            
            for i=1:length(resultLevel)
                if ~isfield(exportState, resultLevel{i})
                    set(app.(['NoneCheckBox_' resultLevel{i}]),'visible',false);
                    set(app.(['NameasDropDown_' resultLevel{i}]),'visible',false);
                    set(app.(['EditField_' resultLevel{i}]),'visible',false);
                    set(app.([resultLevel{i} 'Label']),'visible',false);
                end
                if strcmp(exportState.(resultLevel{i}), 'multi')
                   set(app.(['NoneCheckBox_' resultLevel{i}]),'visible',false);
                   set(app.(['NameasDropDown_' resultLevel{i}]),'visible',true);
                   set(app.(['NameasDropDown_' resultLevel{i}]),'value',exportState.(resultLevel{i}).saveas);
                else
                   set(app.(['NoneCheckBox_' resultLevel{i}]),'visible',true);
                   if isempty(exportState.(resultLevel{i}).saveas)
                       set(app.(['NoneCheckBox_' resultLevel{i}]),'value',true);
                       set(app.(['NameasDropDown_' resultLevel{i}]),'visible',false);
                   else
                       set(app.(['NameasDropDown_' resultLevel{i}]),'visible',true);
                       set(app.(['NameasDropDown_' resultLevel{i}]),'value',exportState.(resultLevel{i}).saveas);
                   end                   
                end
            end
            
            set(app.BasefilenameEditField, 'value', exportState.base_filename);
            disp('loaded export state');     
            
        end
        
        function saveSettings(app)
            resultLevel = {'ResultFields', ...
                'Epochs', ...
                'Datasets', ...
                'Cells', ...
                'Eyes', ...
                'Animals'};
            
            for i=1:length(resultLevel)
                if get(app.(['NameasDropDown_' resultLevel{i}]),'visible')
                    app.exportState.(resultLevel{i}).saveas = get(app.(['NameasDropDown_' resultLevel{i}]),'value');
                else
                    app.exportState.(resultLevel{i}).saveas = '';
                end
            end
            
            app.exportState.base_filename = get(app.BasefilenameEditField, 'value');            
            [filename_example, datasetname_example, varName_example] = parseExportSettings(app.exportState, app.resultsStruct(1));
            set(app.FilenameEditField, 'value', filename_example);
            set(app.DatasetEditField, 'value', datasetname_example);
            set(app.VariableEditField, 'value', varName_example{1});
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, pipeline, funcType, analysisOutput, dataHandle, overwrite, loadState)
            app.connection = dj.conn;
            if ~app.connection.isConnected
                disp('DataJoint connection failed.');
                delete(app)
            end
            
            if nargin<6
                loadState = [];
            end
            
            app.dataHandle = dataHandle;
            app.overwrite = overwrite;
            app.loadedState = loadState;
            
            app.pipeline = pipeline;
            app.resultsStruct = analysisOutput;
            queryStruct = [analysisOutput.input];
            
            N_resultFields = length(fieldnames(analysisOutput(1).result));
            ep = sl.Epoch & queryStruct;
            
            if strcmp(funcType, 'Dataset') 
                %first get all possible datasets
                datasets = sl.Dataset & queryStruct;
                dataset_structs = datasets.fetch('epoch_ids');
                N_datasets = datasets.count;
                
                multi_epoch_per_dataset = false;
                dataset_structs_new = [];
                for i=1:N_datasets
                    epid = dataset_structs(i).epoch_ids;
                    epid_struct = struct('epoch_number', num2cell(epid));
                    epochs_in_dataset = sl.Epoch & dataset_structs(i) & ep.proj & epid_struct;
                    if epochs_in_dataset.count > 0
                        dataset_structs_new = [dataset_structs_new; dataset_structs(i)];
                        if epochs_in_dataset.count > 1
                            multi_epoch_per_dataset = true;
                        end
                    end
                end
                
                datasets = sl.Dataset & rmfield(dataset_structs_new, 'epoch_ids');
                
                measuredCells = sl.MeasuredCell & datasets;
                measuredCells_structs = measuredCells.fetch();
                
                multi_dataset_per_cell = false;
                for i=1:measuredCells.count
                    datasets_in_cell = datasets & measuredCells_structs(i);
                    if datasets_in_cell.count > 1
                        multi_dataset_per_cell = true;
                        break;
                    end
                end
                measuredRetinalCells = sl.MeasuredRetinalCell & datasets; %includes which eye
                animals = sl.Animal & datasets;          
            else
                measuredCells = sl.MeasuredCell & queryStruct;
                measuredRetinalCells = sl.MeasuredRetinalCell & queryStruct;
                animals = sl.Animal & measuredRetinalCells;

            end
            N_animals = animals.count;
            animals_structs = animals.fetch();
            multi_cell_per_animal = false;
            multi_eye_per_animal = false;
            
            for i=1:N_animals
                cells_in_animal = measuredCells & animals_structs(i);
                retinal_cells_in_animal = measuredRetinalCells & animals_structs(i);
                if cells_in_animal.count > 1
                    multi_cell_per_animal = true;
                    if retinal_cells_in_animal.count>1
                        allSides = unique(fetchn(retinal_cells_in_animal, 'side'));
                        if length(allSides) > 1
                            multi_eye_per_animal = true;
                        end
                    end
                end
            end
            
            set(app.EditField_ResultFields, 'value', N_resultFields);
            if N_resultFields == 1
                app.exportState.ResultFields.multi = false;
                set(app.NoneCheckBox_ResultFields, 'visible', true);
            else
                app.exportState.ResultFields.multi = true;
                set(app.NoneCheckBox_ResultFields, 'visible', false);
            end
            
            if strcmp(funcType, 'Dataset')
                if multi_epoch_per_dataset
                    app.exportState.Epochs.multi = true;
                    set(app.EditField_Epochs, 'value', 'multiple per dataset');
                else
                    app.exportState.Epochs.multi = false;
                    set(app.EditField_Epochs, 'value', 'one per dataset');
                end
                if multi_dataset_per_cell
                    app.exportState.Datasets.multi = true;
                    set(app.EditField_Datasets, 'value', 'multiple per cell');
                else
                    app.exportState.Datasets.multi = false;
                    set(app.EditField_Datasets, 'value', 'one per cell');
                end
            end
            
            if multi_cell_per_animal
                app.exportState.Cells.multi = true;
                set(app.EditField_Cells, 'value', 'multiple per animal');
            else
                app.exportState.Cells.multi = false;
                set(app.EditField_Cells, 'value', 'one per animal');
            end
            if multi_eye_per_animal
                app.exportState.Eyes.multi = true;
                set(app.EditField_Eyes, 'value', 'multiple per animal');
            else
                app.exportState.Eyes.multi = false;
                set(app.EditField_Eyes, 'value', 'one per animal');
            end
            set(app.EditField_Animals, 'value', N_animals);
            if N_animals == 1
                app.exportState.Animals.multi = false;
                set(app.NoneCheckBox_Animals, 'visible', true);
            else
                app.exportState.Animals.multi = true;
                %set(app.NoneCheckBox_Animals, 'visible', false);
            end
                        
            if strcmp(funcType, 'Epoch') 
                set(app.EpochsLabel, 'visible', true);
                set(app.EditField_Epochs, 'visible', true);
                set(app.DatasetsLabel, 'visible', false);
                set(app.EditField_Datasets, 'visible', false);
                set(app.NoneCheckBox_Datasets, 'visible', false);
                set(app.NameasDropDown_Datasets, 'visible', false);
                
                if strcmp(get(app.EditField_Epochs, 'value'), 'one per dataset')
                    set(app.NoneCheckBox_Epochs, 'visible', true);
                else
                    set(app.NoneCheckBox_Epochs, 'visible', false);
                end
                set(app.NameasDropDown_Epochs, 'visible', true);                
            else
                set(app.EpochsLabel, 'visible', false);
                set(app.EditField_Epochs, 'visible', false);
                set(app.NoneCheckBox_Epochs, 'visible', false);
                set(app.NameasDropDown_Epochs, 'visible', false);
            end
            
            if strcmp(funcType, 'Dataset')
                set(app.DatasetsLabel, 'visible', true);
                set(app.EditField_Datasets, 'visible', true);
                if strcmp(get(app.EditField_Datasets, 'value'), 'one per cell')
                    set(app.NoneCheckBox_Datasets, 'visible', true);
                else
                    set(app.NoneCheckBox_Datasets, 'visible', false);
                end
                set(app.NameasDropDown_Datasets, 'visible', true);
            else
                set(app.DatasetsLabel, 'visible', false);
                set(app.EditField_Datasets, 'visible', false);
                set(app.NoneCheckBox_Datasets, 'visible', false);
                set(app.NameasDropDown_Datasets, 'visible', false);
            end
            
            if strcmp(funcType, 'Epoch') || strcmp(funcType, 'Dataset') || strcmp(funcType, 'Cell')
                set(app.CellsLabel, 'visible', true);
                set(app.EditField_Cells, 'visible', true);
                if strcmp(get(app.EditField_Cells, 'value'), 'one per animal')
                    set(app.NoneCheckBox_Cells, 'visible', true);
                else
                    set(app.NoneCheckBox_Cells, 'visible', false);
                end
            else
                set(app.CellsLabel, 'visible', false);
                set(app.EditField_Cells, 'visible', false);
                set(app.NoneCheckBox_Cells, 'visible', false);
                set(app.NameasDropDown_Cells, 'visible', false);
            end            
            
            if ~isempty(loadState)
                app.loadSettings(loadState);
            end
            app.saveSettings();
        end

        % Value changed function: NoneCheckBox_ResultFields
        function NoneCheckBox_ResultFieldsValueChanged(app, event)
            app.updateMenuVisibility(event);
        end

        % Value changed function: NoneCheckBox_Epochs
        function NoneCheckBox_EpochsValueChanged(app, event)
            app.updateMenuVisibility(event);
        end

        % Value changed function: NoneCheckBox_Datasets
        function NoneCheckBox_DatasetsValueChanged(app, event)
            app.updateMenuVisibility(event);
        end

        % Value changed function: NoneCheckBox_Cells
        function NoneCheckBox_CellsValueChanged(app, event)
            app.updateMenuVisibility(event);
        end

        % Value changed function: NoneCheckBox_Eyes
        function NoneCheckBox_EyesValueChanged(app, event)
            app.updateMenuVisibility(event);
        end

        % Value changed function: NoneCheckBox_Animals
        function NoneCheckBox_AnimalsValueChanged(app, event)
            app.updateMenuVisibility(event);
        end

        % Callback function
        function SavesettingsButtonPushed(app, event)
            app.saveSettings();
        end

        % Value changed function: NameasDropDown_ResultFields
        function NameasDropDown_ResultFieldsValueChanged(app, event)
            app.saveSettings();
        end

        % Value changed function: NameasDropDown_Epochs
        function NameasDropDown_EpochsValueChanged(app, event)
            app.saveSettings();
        end

        % Value changed function: NameasDropDown_Datasets
        function NameasDropDown_DatasetsValueChanged(app, event)
           app.saveSettings();
        end

        % Value changed function: NameasDropDown_Cells
        function NameasDropDown_CellsValueChanged(app, event)
           app.saveSettings();
        end

        % Value changed function: NameasDropDown_Eyes
        function NameasDropDown_EyesValueChanged(app, event)
            app.saveSettings();
        end

        % Value changed function: NameasDropDown_Animals
        function NameasDropDown_AnimalsValueChanged(app, event)
            app.saveSettings();
        end

        % Value changed function: BasefilenameEditField
        function BasefilenameEditFieldValueChanged(app, event)
            app.saveSettings();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            h5Export(app.pipeline, app.exportState, app.resultsStruct, app.overwrite);
            app.dataHandle.data.exportState = app.exportState; 
        end

        % Close request function: h5ExporterUIFigure
        function h5ExporterUIFigureCloseRequest(app, event)
            if ~isempty(app.connection) && app.connection.isConnected
                app.connection.close();
            end
            if ~isfield(app.dataHandle.data, 'exportState')
                app.dataHandle.data.exportState = app.loadedState; 
            end
            delete(app)
        end

        % Button pushed function: SaveandcloseButton
        function SaveandcloseButtonPushed(app, event)
            app.dataHandle.data.exportState = app.exportState;  
            app.h5ExporterUIFigureCloseRequest(event);
        end

        % Button pushed function: ClearandcloseButton
        function ClearandcloseButtonPushed(app, event)
            app.dataHandle.data.exportState = [];
            app.h5ExporterUIFigureCloseRequest(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.h5ExporterUIFigure = uifigure('Visible', 'off');
            app.h5ExporterUIFigure.Name = 'h5Exporter';
            app.h5ExporterUIFigure.CloseRequestFcn = createCallbackFcn(app, @h5ExporterUIFigureCloseRequest, true);
            app.BasefilenameEditFieldLabel = uilabel(app.h5ExporterUIFigure);
            app.BasefilenameEditFieldLabel.Text = 'Base filename';
            app.BasefilenameEditField = uieditfield(app.h5ExporterUIFigure, 'text');
            app.BasefilenameEditField.ValueChangedFcn = createCallbackFcn(app, @BasefilenameEditFieldValueChanged, true);
            app.EpochsLabel = uilabel(app.h5ExporterUIFigure);
            app.EpochsLabel.Text = 'Epochs';
            app.DatasetsLabel = uilabel(app.h5ExporterUIFigure);
            app.DatasetsLabel.Text = 'Datasets';
            app.CellsLabel = uilabel(app.h5ExporterUIFigure);
            app.CellsLabel.Text = 'Cells';
            app.EyesLabel = uilabel(app.h5ExporterUIFigure);
            app.EyesLabel.Text = 'Eyes';
            app.AnimalsLabel = uilabel(app.h5ExporterUIFigure);
            app.AnimalsLabel.Text = 'Animals';
            app.NameasDropDown_ResultFields = uidropdown(app.h5ExporterUIFigure);
            app.NameasDropDown_ResultFields.Items = {'h5_filename', 'dataset (Igor folder)', 'variable names'};
            app.NameasDropDown_ResultFields.ValueChangedFcn = createCallbackFcn(app, @NameasDropDown_ResultFieldsValueChanged, true);
            app.NameasDropDown_ResultFields.Value = 'variable names';
            app.ResultfieldsLabel = uilabel(app.h5ExporterUIFigure);
            app.ResultfieldsLabel.Text = 'Result fields';
            app.EditField_Epochs = uieditfield(app.h5ExporterUIFigure, 'text');
            app.EditField_Epochs.Editable = 'off';
            app.EditField_Datasets = uieditfield(app.h5ExporterUIFigure, 'text');
            app.EditField_Datasets.Editable = 'off';
            app.EditField_Cells = uieditfield(app.h5ExporterUIFigure, 'text');
            app.EditField_Cells.Editable = 'off';
            app.EditField_Eyes = uieditfield(app.h5ExporterUIFigure, 'text');
            app.EditField_Eyes.Editable = 'off';
            app.EditField_ResultFields = uieditfield(app.h5ExporterUIFigure, 'numeric');
            app.EditField_ResultFields.Editable = 'off';
            app.EditField_Animals = uieditfield(app.h5ExporterUIFigure, 'numeric');
            app.EditField_Animals.Editable = 'off';
            app.NameasLabel = uilabel(app.h5ExporterUIFigure);
            app.NameasLabel.Text = 'Name as';
            app.NoneCheckBox_ResultFields = uicheckbox(app.h5ExporterUIFigure);
            app.NoneCheckBox_ResultFields.ValueChangedFcn = createCallbackFcn(app, @NoneCheckBox_ResultFieldsValueChanged, true);
            app.NoneCheckBox_ResultFields.Tag = 'NoneCheckBox_ResultFields';
            app.NoneCheckBox_ResultFields.Text = 'None';
            app.NoneCheckBox_Epochs = uicheckbox(app.h5ExporterUIFigure);
            app.NoneCheckBox_Epochs.ValueChangedFcn = createCallbackFcn(app, @NoneCheckBox_EpochsValueChanged, true);
            app.NoneCheckBox_Epochs.Tag = 'NoneCheckBox_Epochs';
            app.NoneCheckBox_Epochs.Text = 'None';
            app.NoneCheckBox_Datasets = uicheckbox(app.h5ExporterUIFigure);
            app.NoneCheckBox_Datasets.ValueChangedFcn = createCallbackFcn(app, @NoneCheckBox_DatasetsValueChanged, true);
            app.NoneCheckBox_Datasets.Tag = 'NoneCheckBox_Datasets';
            app.NoneCheckBox_Datasets.Text = 'None';
            app.NoneCheckBox_Cells = uicheckbox(app.h5ExporterUIFigure);
            app.NoneCheckBox_Cells.ValueChangedFcn = createCallbackFcn(app, @NoneCheckBox_CellsValueChanged, true);
            app.NoneCheckBox_Cells.Tag = 'NoneCheckBox_Cells';
            app.NoneCheckBox_Cells.Text = 'None';
            app.NoneCheckBox_Eyes = uicheckbox(app.h5ExporterUIFigure);
            app.NoneCheckBox_Eyes.ValueChangedFcn = createCallbackFcn(app, @NoneCheckBox_EyesValueChanged, true);
            app.NoneCheckBox_Eyes.Tag = 'NoneCheckBox_Eyes';
            app.NoneCheckBox_Eyes.Text = 'None';
            app.NoneCheckBox_Animals = uicheckbox(app.h5ExporterUIFigure);
            app.NoneCheckBox_Animals.ValueChangedFcn = createCallbackFcn(app, @NoneCheckBox_AnimalsValueChanged, true);
            app.NoneCheckBox_Animals.Tag = 'NoneCheckBox_Animals';
            app.NoneCheckBox_Animals.Text = 'None';
            app.NameasDropDown_Epochs = uidropdown(app.h5ExporterUIFigure);
            app.NameasDropDown_Epochs.Items = {'h5_filename', 'dataset (Igor folder)', 'variable names'};
            app.NameasDropDown_Epochs.ValueChangedFcn = createCallbackFcn(app, @NameasDropDown_EpochsValueChanged, true);
            app.NameasDropDown_Epochs.Value = 'dataset (Igor folder)';
            app.NameasDropDown_Datasets = uidropdown(app.h5ExporterUIFigure);
            app.NameasDropDown_Datasets.Items = {'h5_filename', 'dataset (Igor folder)', 'variable names'};
            app.NameasDropDown_Datasets.ValueChangedFcn = createCallbackFcn(app, @NameasDropDown_DatasetsValueChanged, true);
            app.NameasDropDown_Datasets.Value = 'dataset (Igor folder)';
            app.NameasDropDown_Cells = uidropdown(app.h5ExporterUIFigure);
            app.NameasDropDown_Cells.Items = {'h5_filename', 'dataset (Igor folder)', 'variable names'};
            app.NameasDropDown_Cells.ValueChangedFcn = createCallbackFcn(app, @NameasDropDown_CellsValueChanged, true);
            app.NameasDropDown_Cells.Value = 'dataset (Igor folder)';
            app.NameasDropDown_Eyes = uidropdown(app.h5ExporterUIFigure);
            app.NameasDropDown_Eyes.Items = {'h5_filename', 'dataset (Igor folder)', 'variable names'};
            app.NameasDropDown_Eyes.ValueChangedFcn = createCallbackFcn(app, @NameasDropDown_EyesValueChanged, true);
            app.NameasDropDown_Eyes.Value = 'h5_filename';
            app.NameasDropDown_Animals = uidropdown(app.h5ExporterUIFigure);
            app.NameasDropDown_Animals.Items = {'h5_filename', 'dataset (Igor folder)', 'variable names'};
            app.NameasDropDown_Animals.ValueChangedFcn = createCallbackFcn(app, @NameasDropDown_AnimalsValueChanged, true);
            app.NameasDropDown_Animals.Value = 'h5_filename';
            app.FilenameEditFieldLabel = uilabel(app.h5ExporterUIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.h5ExporterUIFigure, 'text');
            app.FilenameEditField.Editable = 'off';
            app.DatasetEditFieldLabel = uilabel(app.h5ExporterUIFigure);
            app.DatasetEditFieldLabel.Text = 'Dataset';
            app.DatasetEditField = uieditfield(app.h5ExporterUIFigure, 'text');
            app.DatasetEditField.Editable = 'off';
            app.SampleLabel = uilabel(app.h5ExporterUIFigure);
            app.SampleLabel.Text = 'Sample';
            app.VariableEditFieldLabel = uilabel(app.h5ExporterUIFigure);
            app.VariableEditFieldLabel.Text = 'Variable';
            app.VariableEditField = uieditfield(app.h5ExporterUIFigure, 'text');
            app.VariableEditField.Editable = 'off';
            app.RunButton = uibutton(app.h5ExporterUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SaveandcloseButton = uibutton(app.h5ExporterUIFigure, 'push');
            app.SaveandcloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandcloseButtonPushed, true);
            app.SaveandcloseButton.Text = 'Save and close';
            app.ClearandcloseButton = uibutton(app.h5ExporterUIFigure, 'push');
            app.ClearandcloseButton.ButtonPushedFcn = createCallbackFcn(app, @ClearandcloseButtonPushed, true);
            app.ClearandcloseButton.Text = 'Clear and close';
            % Show the figure after all components are created
            app.h5ExporterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = H5ExportTool(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.h5ExporterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.h5ExporterUIFigure)
        end
    end
end