classdef H5_Exporter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        H5ExporterUIFigure       matlab.ui.Figure
        ResulttypeDropDown       matlab.ui.control.DropDown
        ResulttypeDropDownLabel  matlab.ui.control.Label
        NentriesEditField        matlab.ui.control.NumericEditField
        entriesEditFieldLabel    matlab.ui.control.Label
        ExportButton             matlab.ui.control.Button
        LayersSpinner            matlab.ui.control.Spinner
        LayersSpinnerLabel       matlab.ui.control.Label
        DropDown_6               matlab.ui.control.DropDown
        DropDown_5               matlab.ui.control.DropDown
        DropDown_4               matlab.ui.control.DropDown
        DropDown_3               matlab.ui.control.DropDown
        DropDown_2               matlab.ui.control.DropDown
        DropDown_1               matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        data_group = []; %loaded data group
        fields_to_remove = {'source_id', ...
            'retina_source_id', ...
            'animal_source_id', ...
            'event_id', ...
            'cell_unid', ...
            'entry_time', ...
            'git_tag', ...
            'retina_id', ...
            'cell_number', ...
            'online_type', ...
            'x', ...
            'y' ...
            }; % fields not to use for h5 hierarchy        
        all_results = []; % query result for all the results joined
    end
    
    methods (Access = private)
        
        function update_menu_visibility(app)
            N_max = 6;
            N = get(app.LayersSpinner,'value');
            
            for i=1:N_max
                menu_name = sprintf('DropDown_%d',i);
                if i<= N
                    set(app.(menu_name),'Visible',true);
                else
                    set(app.(menu_name),'Visible',false);
                end
            end
        end

        function load_result_types_menu(app)
            set(app.ResulttypeDropDown,'items',sln_results.AllResultTableNames);            
        end
        
        function load_properties_menus(app)
            result_table = feval(sprintf('sln_results.%s', get(app.ResulttypeDropDown,'value')));
            q = (result_table & proj(app.data_group)) * ...
                sln_symphony.ExperimentCell * ...
                proj(sln_symphony.ExperimentRetina, 'source_id->retina_id', 'side') * ...
                sln_cell.Cell * ...
                sln_cell.CellName * ...
                proj(sln_cell.AssignType.current, 'user_name->cell_event_user_name', 'entry_time->cell_event_entry_time', '*') * ...
                proj(sln_animal.Animal, 'source_id->animal_source_id') * ...
                sln_animal.GenotypeString * ...
                proj(sln_cell.RetinaQuadrant, 'quadrant');
            
            [data_levels, user_params_table_names] = sln_symphony.get_all_user_param_in_db;
            
            %add in all user tags %It's giving issues when creating new
            %tags
           % for i=1:length(user_params_table_names)
           %        table_name = sprintf('sln_symphony.UserParam%s%s', data_levels{i}, user_params_table_names{i});
           %        table = feval(table_name);
           %        % column names
           %        % qq = ...
           %        column_names = table.header.dependentFields;
           %        %dj.U(column_names{:})
           %        q = q * feval(table_name);
           %        disp([i, count(q)])
           % end

            app.all_results = q;
            % fast count: restrict the result table by the data group keys
            % instead of executing the full metadata join (GenotypeString etc.)
            set(app.NentriesEditField, 'value', count(result_table & proj(app.data_group)));
            property_list = setdiff(app.all_results.header.notBlobs, app.fields_to_remove);

            N_max = 6;            
            for i=1:N_max
                menu_name = sprintf('DropDown_%d',i);
                set(app.(menu_name),'items',property_list);
            end
        end
        
        function prop_list = get_current_prop_list(app)
            N = get(app.LayersSpinner,'value');
            prop_list = {};
            for i=1:N
                menu_name = sprintf('DropDown_%d',i);
                prop_list = [prop_list; get(app.(menu_name), 'value')];
            end
        end
        
        function S = struct_from_results_by_prop(app, results_table, prop_list)
            if isempty(prop_list) %should never happen
                S = [];
            else
                cur_prop = prop_list{1};
                f = fetch(results_table, cur_prop);
                % if iscell(f(1).(cur_prop))
                vals = unique({f.(cur_prop)});
                % else
                %     vals = unique([f.(cur_prop)]);
                % end
                %vals = unique(fetchn(results_table, cur_prop))
                for i=1:length(vals)
                    if iscell(vals)
                        field_name = genvarname(sprintf('%s__%s', cur_prop, num2str(vals{i})))
                        if isempty(num2str(vals{i}))
                            results_table_part = results_table & ...
                                sprintf('%s IS NULL', cur_prop);
                        else
                            results_table_part = results_table & ...
                                sprintf('%s="%s"', cur_prop, num2str(vals{i}));
                        end
                    end
                    if length(prop_list) == 1 %end condition
                        ml_table = sln_results.toMatlabTable(results_table_part);                        
                        S.(field_name) = table2struct(ml_table);
                    else
                        S.(field_name) = app.struct_from_results_by_prop(results_table_part, prop_list(2:end));
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data_group)
            app.data_group = data_group;
            set(app.H5ExporterUIFigure,'Name', 'loading...');

            app.load_result_types_menu();
            app.update_menu_visibility();
            app.load_properties_menus();

            set(app.H5ExporterUIFigure,'Name', 'H5 Exporter');
        end

        % Value changed function: ResulttypeDropDown
        function ResulttypeDropDownValueChanged(app, event)
            set(app.H5ExporterUIFigure,'Name', 'loading...');
            app.load_properties_menus();
            set(app.H5ExporterUIFigure,'Name', 'H5 Exporter');
        end

        % Value changed function: LayersSpinner
        function LayersSpinnerValueChanged(app, event)
            app.update_menu_visibility();
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            export_hierarchy = app.get_current_prop_list();
            [fname, fpath] = uiputfile([getenv('h5_folder') '*.h5'], 'Save hdf5');
            if ~isequal(fname, 0)
                set(app.H5ExporterUIFigure,'Name', 'exporting...');
                fastExportResultsToH5(app.data_group, ...
                    get(app.ResulttypeDropDown,'value'), export_hierarchy, ...
                    fullfile(fpath, fname));
                delete(app);    % close the exporter once the export completes
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.H5ExporterUIFigure = uifigure('Visible', 'off');
            app.H5ExporterUIFigure.Name = 'H5 Exporter';
            app.DropDown_1 = uidropdown(app.H5ExporterUIFigure);
            app.DropDown_2 = uidropdown(app.H5ExporterUIFigure);
            app.DropDown_3 = uidropdown(app.H5ExporterUIFigure);
            app.DropDown_4 = uidropdown(app.H5ExporterUIFigure);
            app.DropDown_5 = uidropdown(app.H5ExporterUIFigure);
            app.DropDown_6 = uidropdown(app.H5ExporterUIFigure);
            app.LayersSpinnerLabel = uilabel(app.H5ExporterUIFigure);
            app.LayersSpinnerLabel.Text = 'Layers';
            app.LayersSpinner = uispinner(app.H5ExporterUIFigure);
            app.LayersSpinner.Limits = [1 6];
            app.LayersSpinner.ValueChangedFcn = createCallbackFcn(app, @LayersSpinnerValueChanged, true);
            app.LayersSpinner.Value = 1;
            app.ExportButton = uibutton(app.H5ExporterUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.entriesEditFieldLabel = uilabel(app.H5ExporterUIFigure);
            app.entriesEditFieldLabel.Text = 'entries';
            app.NentriesEditField = uieditfield(app.H5ExporterUIFigure, 'numeric');
            app.NentriesEditField.Editable = 'off';
            app.ResulttypeDropDownLabel = uilabel(app.H5ExporterUIFigure);
            app.ResulttypeDropDownLabel.Text = 'Result type';
            app.ResulttypeDropDown = uidropdown(app.H5ExporterUIFigure);
            app.ResulttypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ResulttypeDropDownValueChanged, true);
            % Show the figure after all components are created
            app.H5ExporterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = H5_Exporter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.H5ExporterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.H5ExporterUIFigure)
        end
    end
end