classdef NewPipeline < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NewPipelineUIFigure         matlab.ui.Figure
        NewpipelinePanel            matlab.ui.container.Panel
        AddnewpipelineButton        matlab.ui.control.Button
        NameEditFieldLabel          matlab.ui.control.Label
        NameEditField_New           matlab.ui.control.EditField
        DescriptionTextArea_2Label  matlab.ui.control.Label
        DescriptionTextArea_New     matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        dataHandle % to store pipeline name
    end
    
    properties (Access = private)
        connection % for DJ connection
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataHandle)
            app.dataHandle = dataHandle;
            app.dataHandle.data.pipeline_name = '';
            
            app.connection = dj.conn;
            if ~app.connection.isConnected
                disp('DataJoint connection failed.');
                delete(app)  
            end
        end

        % Button pushed function: AddnewpipelineButton
        function AddnewpipelineButtonPushed(app, event)
            name = get(app.NameEditField_New, 'value');
            if isempty(name)
                disp('Error: must enter a pipeline name')
                return;
            end
            key.pipeline_name = name;
            desc = get(app.DescriptionTextArea_New, 'value');
            desc_flat = strcat(cell2mat(desc'));
            key.pipeline_description = desc_flat;
            key.owner = app.connection.user;
            try
                insert(sl.Pipeline, key);
                disp('New pipeline inserted');
                app.dataHandle.data.pipeline_name = name;
                app.NewPipelineUIFigureCloseRequest(event);
            catch ME
                disp('Error adding new pipeline');
                rethrow(ME);
            end
        end

        % Close request function: NewPipelineUIFigure
        function NewPipelineUIFigureCloseRequest(app, event)
            if ~isempty(app.connection) && app.connection.isConnected
                app.connection.close();
            end
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NewPipelineUIFigure = uifigure('Visible', 'off');
            app.NewPipelineUIFigure.Name = 'NewPipeline';
            app.NewPipelineUIFigure.CloseRequestFcn = createCallbackFcn(app, @NewPipelineUIFigureCloseRequest, true);
            app.NewpipelinePanel = uipanel(app.NewPipelineUIFigure);
            app.NewpipelinePanel.Title = 'New pipeline';
            app.AddnewpipelineButton = uibutton(app.NewpipelinePanel, 'push');
            app.AddnewpipelineButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewpipelineButtonPushed, true);
            app.AddnewpipelineButton.Text = 'Add new pipeline';
            app.NameEditFieldLabel = uilabel(app.NewpipelinePanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField_New = uieditfield(app.NewpipelinePanel, 'text');
            app.DescriptionTextArea_2Label = uilabel(app.NewpipelinePanel);
            app.DescriptionTextArea_2Label.Text = 'Description';
            app.DescriptionTextArea_New = uitextarea(app.NewpipelinePanel);
            % Show the figure after all components are created
            app.NewPipelineUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewPipeline(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NewPipelineUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NewPipelineUIFigure)
        end
    end
end