classdef NoCuratedForSpike < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        YesButton           matlab.ui.control.Button
        NoButton            matlab.ui.control.Button
        OpeninCuratorLabel  matlab.ui.control.Label
        BoldLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        DataGrouperApp % Description
        CurrentCellName
        CuratorApp;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, cell_name, caller)
            app.DataGrouperApp = caller;
            movegui(app.UIFigure, 'center');
            app.BoldLabel.Text = sprintf('Cell %s does not have any curated datasets', cell_name);
            app.OpeninCuratorLabel.Text = sprintf('Open cell %s in Curator?', cell_name)
            app.CurrentCellName = cell_name
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            app.CuratorApp = Curator(app.CurrentCellName, app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BoldLabel = uilabel(app.UIFigure);
            app.BoldLabel.Text = 'Cell does not have any curated cell-attach dataset.';
            app.OpeninCuratorLabel = uilabel(app.UIFigure);
            app.OpeninCuratorLabel.Text = 'Open in Curator?';
            app.NoButton = uibutton(app.UIFigure, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No 🙅‍♂️';
            app.YesButton = uibutton(app.UIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes 💃';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NoCuratedForSpike(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end