classdef ParamTable_dlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditParameterTableUIFigure  matlab.ui.Figure
        PTable                      matlab.ui.control.Table
        CancelButton                matlab.ui.control.Button
        DoneButton                  matlab.ui.control.Button
        EditingparametersforLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        dataHandle % for storing result table
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, initTable, paramSetName, dataHandle)
            set(app.PTable, 'Data', initTable);      
            set(app.EditingparametersforLabel, 'text', ['Editing parameters for ' paramSetName]);
            app.dataHandle = dataHandle;
            app.dataHandle.data.table = initTable;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.dataHandle.data.table = get(app.PTable, 'data');
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditParameterTableUIFigure = uifigure('Visible', 'off');
            app.EditParameterTableUIFigure.Name = 'EditParameterTable';
            app.PTable = uitable(app.EditParameterTableUIFigure);
            app.PTable.ColumnName = {'Parameter'; 'Value'};
            app.PTable.RowName = {};
            app.PTable.ColumnEditable = true;
            app.CancelButton = uibutton(app.EditParameterTableUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.DoneButton = uibutton(app.EditParameterTableUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.EditingparametersforLabel = uilabel(app.EditParameterTableUIFigure);
            app.EditingparametersforLabel.Text = 'Editing parameters for';
            % Show the figure after all components are created
            app.EditParameterTableUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParamTable_dlg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EditParameterTableUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditParameterTableUIFigure)
        end
    end
end