classdef PipelineLoader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PipelineLoaderUIFigure      matlab.ui.Figure
        DescriptionTextAreaLabel    matlab.ui.control.Label
        DescriptionTextArea         matlab.ui.control.TextArea
        PipelinesListBoxLabel       matlab.ui.control.Label
        PipelinesListBox            matlab.ui.control.ListBox
        SelectButton                matlab.ui.control.Button
        SelectpipelinetouseLabel    matlab.ui.control.Label
        NewpipelinePanel            matlab.ui.container.Panel
        AddnewpipelineButton        matlab.ui.control.Button
        NameEditFieldLabel          matlab.ui.control.Label
        NameEditField_New           matlab.ui.control.EditField
        DescriptionTextArea_2Label  matlab.ui.control.Label
        DescriptionTextArea_New     matlab.ui.control.TextArea
        NewDataSwitch               matlab.ui.control.Switch
    end

    
    properties (Access = public)
        dataHandle % for storing selected pipeline
    end
    
    properties (Access = private)
        connection = []; % DJ connection
        
    end
    
    methods (Access = private)
        
        function updateListBox(app)
            %load existing pipelines
            %put ones belonging to this user first
            user_pipelines = sl.Pipeline & sprintf('owner="%s"',app.connection.user); 
            other_pipelines = sl.Pipeline - sprintf('owner="%s"',app.connection.user); 
            
            if user_pipelines.exists
                user_pipeline_names = user_pipelines.fetchn('pipeline_name');                
            else
                user_pipeline_names = {};
            end
            if other_pipelines.exists
                other_pipeline_names = other_pipelines.fetchn('pipeline_name');                
            else
                other_pipeline_names = {};
            end
            set(app.PipelinesListBox, 'items', [user_pipeline_names; other_pipeline_names]);
            app.PipelinesListBoxValueChanged([]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataHandle)
            app.dataHandle = dataHandle;
            app.dataHandle.data.pipeline_name = '';
            
            app.connection = dj.conn;
            if ~app.connection.isConnected
                disp('DataJoint connection failed.');
                delete(app)  
            end
            
            app.updateListBox();           
        end

        % Close request function: PipelineLoaderUIFigure
        function PipelineLoaderUIFigureCloseRequest(app, event)
            if ~isempty(app.connection) && app.connection.isConnected
                app.connection.close();
            end
            delete(app)     
        end

        % Value changed function: PipelinesListBox
        function PipelinesListBoxValueChanged(app, event)
            value = app.PipelinesListBox.Value;
            curItem = sl.Pipeline & sprintf('pipeline_name="%s"', value);            
            desc = fetch1(curItem, 'pipeline_description');
            set(app.DescriptionTextArea, 'value', desc);
        end

        % Button pushed function: AddnewpipelineButton
        function AddnewpipelineButtonPushed(app, event)
            name = get(app.NameEditField_New, 'value');
            if isempty(name)
                disp('Error: must enter a pipeline name')
                return;
            end
            key.pipeline_name = name;
            desc = get(app.DescriptionTextArea_New, 'value');
            desc_flat = strcat(cell2mat(desc'));
            key.pipeline_description = desc_flat;
            key.owner = app.connection.user;
            try
                insert(sl.Pipeline, key);
                disp('New pipeline inserted');
                app.updateListBox();
            catch ME
                disp('Error adding new pipeline');
                retrhow(ME);
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.dataHandle.data.pipeline_name = get(app.PipelinesListBox, 'value');
            if strcmp(get(app.NewDataSwitch, 'value'), 'Not analyzed only')
                app.dataHandle.data.new_data_only = true;
            else
                app.dataHandle.data.new_data_only = false;
            end
            app.PipelineLoaderUIFigureCloseRequest();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PipelineLoaderUIFigure = uifigure('Visible', 'off');
            app.PipelineLoaderUIFigure.Name = 'PipelineLoader';
            app.PipelineLoaderUIFigure.CloseRequestFcn = createCallbackFcn(app, @PipelineLoaderUIFigureCloseRequest, true);
            app.DescriptionTextAreaLabel = uilabel(app.PipelineLoaderUIFigure);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.PipelineLoaderUIFigure);
            app.PipelinesListBoxLabel = uilabel(app.PipelineLoaderUIFigure);
            app.PipelinesListBoxLabel.Text = 'Pipelines';
            app.PipelinesListBox = uilistbox(app.PipelineLoaderUIFigure);
            app.PipelinesListBox.ValueChangedFcn = createCallbackFcn(app, @PipelinesListBoxValueChanged, true);
            app.SelectButton = uibutton(app.PipelineLoaderUIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SelectpipelinetouseLabel = uilabel(app.PipelineLoaderUIFigure);
            app.SelectpipelinetouseLabel.Text = 'Select pipeline to use';
            app.NewpipelinePanel = uipanel(app.PipelineLoaderUIFigure);
            app.NewpipelinePanel.Title = 'New pipeline';
            app.AddnewpipelineButton = uibutton(app.NewpipelinePanel, 'push');
            app.AddnewpipelineButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewpipelineButtonPushed, true);
            app.AddnewpipelineButton.Text = 'Add new pipeline';
            app.NameEditFieldLabel = uilabel(app.NewpipelinePanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField_New = uieditfield(app.NewpipelinePanel, 'text');
            app.DescriptionTextArea_2Label = uilabel(app.NewpipelinePanel);
            app.DescriptionTextArea_2Label.Text = 'Description';
            app.DescriptionTextArea_New = uitextarea(app.NewpipelinePanel);
            app.NewDataSwitch = uiswitch(app.PipelineLoaderUIFigure, 'slider');
            app.NewDataSwitch.Items = {'All data', 'Not analyzed only'};
            app.NewDataSwitch.Value = 'Not analyzed only';
            % Show the figure after all components are created
            app.PipelineLoaderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PipelineLoader(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PipelineLoaderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PipelineLoaderUIFigure)
        end
    end
end