classdef PlotDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotDesignerUIFigure   matlab.ui.Figure
        SaveButton             matlab.ui.control.Button
        ColumnsEditField       matlab.ui.control.NumericEditField
        ColumnsEditFieldLabel  matlab.ui.control.Label
        RowsEditField          matlab.ui.control.NumericEditField
        RowsEditFieldLabel     matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        GridLayout             matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        plot_selector = []; % matrix of uidropdown menus for plot selection
        plot_names = {};
        plotter_list % list of plotter functions in folder
    end
    
    methods (Access = private)
                
        function reset_layout(app)
            if ~isempty(app.plot_selector)
                [R,C] = size(app.plot_selector);
                for r=1:R
                    for c=1:C
                        delete(app.plot_selector(r,c));
                    end
                end
                app.plot_selector = [];
                app.plot_names = {};
            end
            rows = app.RowsEditField.Value;
            cols = app.ColumnsEditField.Value;
            col_width_vec = cell(1,cols);
            row_height_vec = cell(1,rows);
            col_width_vec(:) = {'1x'};
            row_height_vec(:) = {'1x'};
            app.GridLayout.ColumnWidth = col_width_vec;
            app.GridLayout.RowHeight = row_height_vec;

            app.plot_selector = zeros(rows,cols);
            for r=1:rows
                for c=1:cols
                    app.plot_selector(r, c) = uidropdown(app.GridLayout);
                    set(app.plot_selector(r, c),'Items',['Select a plot', app.plotter_list]);
                    set(app.plot_selector(r, c),'Value','Select a plot');
                    L = get(app.plot_selector(r,c),'Layout');
                    L.Row = r;
                    L.Column = c;
                    set(app.plot_selector(r, c),'UserData',[r c]);                    
                    set(app.plot_selector(r,c),'Layout',L);
                    set(app.plot_selector(r,c),'ValueChangedFcn',createCallbackFcn(app, @select_plot, true));
                end
            end
        end
        
        function load_plotter_list(app)
            plotters_base_folder = getenv('User_plotters_folder');
            app.plotter_list = app.m_files_in_folder(plotters_base_folder);
        end
        
        function items = m_files_in_folder(app, folder_name)
            D = dir(folder_name);
            items = {};
            z=1;
            for i=1:length(D)
                curName = D(i).name;
                if endsWith(curName,'.m')
                    items{z} = extractBefore(curName,'.m');
                    z=z+1;
                end
            end
        end
        
        function select_plot(app, event)
            coords = get(event.Source,'UserData');
            app.plot_names{coords(1),coords(2)} = event.Value;            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.load_plotter_list();
            app.reset_layout();
        end

        % Value changed function: RowsEditField
        function RowsEditFieldValueChanged(app, event)
                app.reset_layout();
        end

        % Value changed function: ColumnsEditField
        function ColumnsEditFieldValueChanged(app, event)
            app.reset_layout();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [fname, path] = uiputfile('*.mat','Save as...',getenv('fig_layouts_folder'));
            if fname
                layout = app.plot_names;
                save([path fname], 'layout');
            end         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlotDesignerUIFigure = uifigure('Visible', 'off');
            app.PlotDesignerUIFigure.Name = 'Plot Designer';
            app.Panel = uipanel(app.PlotDesignerUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.RowsEditFieldLabel = uilabel(app.PlotDesignerUIFigure);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.PlotDesignerUIFigure, 'numeric');
            app.RowsEditField.Limits = [1 10];
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @RowsEditFieldValueChanged, true);
            app.RowsEditField.Value = 2;
            app.ColumnsEditFieldLabel = uilabel(app.PlotDesignerUIFigure);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.PlotDesignerUIFigure, 'numeric');
            app.ColumnsEditField.Limits = [1 10];
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnsEditFieldValueChanged, true);
            app.ColumnsEditField.Value = 2;
            app.SaveButton = uibutton(app.PlotDesignerUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.PlotDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotDesignerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotDesignerUIFigure)
        end
    end
end