classdef ROI_Tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROIToolUIFigure                matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadimageMenu                  matlab.ui.container.Menu
        LoadROIsetMenu                 matlab.ui.container.Menu
        SaveROIsMenu                   matlab.ui.container.Menu
        SelectMenu                     matlab.ui.container.Menu
        FreedrawMenu                   matlab.ui.container.Menu
        PointMenu                      matlab.ui.container.Menu
        GratingangleDropDown           matlab.ui.control.DropDown
        GratingangleLabel              matlab.ui.control.Label
        PlotROIamplitudemapButton      matlab.ui.control.Button
        LoadfromdatabaseDropDown       matlab.ui.control.DropDown
        LoadfromdatabaseDropDownLabel  matlab.ui.control.Label
        SelectROIDropDown              matlab.ui.control.DropDown
        SelectROIDropDownLabel         matlab.ui.control.Label
        SelectROIfieldDropDown         matlab.ui.control.DropDown
        SelectROIfieldDropDownLabel    matlab.ui.control.Label
        WriteROIstodatabaseButton      matlab.ui.control.Button
        HighpassHzEditField            matlab.ui.control.NumericEditField
        HighpassHzEditFieldLabel       matlab.ui.control.Label
        UpdateButton                   matlab.ui.control.Button
        CurTimeEditField               matlab.ui.control.NumericEditField
        EndFrameEditField              matlab.ui.control.NumericEditField
        FrameEditFieldLabel_3          matlab.ui.control.Label
        EndTimeEditField               matlab.ui.control.NumericEditField
        CurFrameEditField              matlab.ui.control.NumericEditField
        StartFrameEditField            matlab.ui.control.NumericEditField
        FrameEditFieldLabel_2          matlab.ui.control.Label
        StartTimeEditField             matlab.ui.control.NumericEditField
        FrameEditFieldLabel            matlab.ui.control.Label
        StarttimesSlider               matlab.ui.control.Slider
        StarttimesSliderLabel          matlab.ui.control.Label
        ProcessingstepsPanel           matlab.ui.container.Panel
        ClearlastButton                matlab.ui.control.Button
        StepsTextArea                  matlab.ui.control.TextArea
        ClearallButton                 matlab.ui.control.Button
        CorrelationsPanel              matlab.ui.container.Panel
        AreaThresEditField             matlab.ui.control.NumericEditField
        PathdistthresholdmLabel_2      matlab.ui.control.Label
        MakeROIsButton                 matlab.ui.control.Button
        CorrelationthresholdSlider     matlab.ui.control.Slider
        CorrelationthresholdSliderLabel  matlab.ui.control.Label
        PathDistEditField              matlab.ui.control.NumericEditField
        PathdistthresholdmLabel        matlab.ui.control.Label
        ComputeallcorrelationsButton   matlab.ui.control.Button
        SearchradiusEditField          matlab.ui.control.NumericEditField
        SearchradiusmEditFieldLabel    matlab.ui.control.Label
        ProjectionsPanel               matlab.ui.container.Panel
        StdProjButton_2                matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        ProjThresholdSlider            matlab.ui.control.Slider
        ThresholdSliderLabel           matlab.ui.control.Label
        LimittotimeslidersSwitch       matlab.ui.control.Switch
        LimittotimeslidersSwitchLabel  matlab.ui.control.Label
        StdProjButton                  matlab.ui.control.Button
        MeanProjButton                 matlab.ui.control.Button
        MaxProjButton                  matlab.ui.control.Button
        EndtimesSlider                 matlab.ui.control.Slider
        EndtimesSliderLabel            matlab.ui.control.Label
        TimesSlider                    matlab.ui.control.Slider
        ImagingruninformationPanel     matlab.ui.container.Panel
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        HeightmEditField               matlab.ui.control.NumericEditField
        HeightmEditFieldLabel          matlab.ui.control.Label
        WidthmEditField                matlab.ui.control.NumericEditField
        WidthmEditFieldLabel           matlab.ui.control.Label
        NumberofROIsEditField          matlab.ui.control.NumericEditField
        NumberofROIsEditFieldLabel     matlab.ui.control.Label
        YresolutionmpixelEditField     matlab.ui.control.NumericEditField
        YresolutionmpixelEditFieldLabel  matlab.ui.control.Label
        XresolutionmpixelEditField     matlab.ui.control.NumericEditField
        XresolutionmpixelEditFieldLabel  matlab.ui.control.Label
        DurationsEditField             matlab.ui.control.NumericEditField
        DurationsEditFieldLabel        matlab.ui.control.Label
        FramerateHzEditField           matlab.ui.control.NumericEditField
        FramerateHzEditFieldLabel      matlab.ui.control.Label
        TimesSliderLabel               matlab.ui.control.Label
        SomaVoltageAxis                matlab.ui.control.UIAxes
        rois_heatmap_axis              matlab.ui.control.UIAxes
        ROITemporalProfileAxis         matlab.ui.control.UIAxes
        TemporalProfileAxis            matlab.ui.control.UIAxes
        rois_axis                      matlab.ui.control.UIAxes
        working_axis                   matlab.ui.control.UIAxes
        original_axis                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imageVolume % The full original image volume
        n_frames
        duration %seconds
        width %pixels
        height %pixels
        res_x %µm/pixel
        res_y %µm/pixel
        width_um %µm
        height_um %µm 
        frame_rate %Hz
        image_fname
        cur_frame
        selected_mask % mask of current roi selection
        selected_roi = [];% roi object
        trace % currently shown profile trace (full time)
        cur_frame_start
        cur_frame_end
        proj % current projection_image
        proj_mask = []; % current projection mask
        proj_type = ''; 
        proj_thres
        corr_point % roi of point from which we will examine correlations
        working_cbar = [];
        path_dist_tensor = []; %tensor of path distances between all pixels in mask
        corr_tensor = []; % tensor of correlations between all pixels in mask
        ROIs % cell array of indices
        ROI_traces = []; %trace for each ROI
        ROI_method_id % uniuqe index in sln_funcimage.ROIMethod
        epochs_query % loaded epochs
    end
    
    methods (Access = private)
        
        function loadImageSequence(app, fname, meta_fname)
            app.imageVolume = tiffreadVolume(fname);
            info = imfinfo(fname);
            load(meta_fname,'SI');
            app.frame_rate = SI.hRoiManager.scanFrameRate;
            app.n_frames = length(info);
            app.width = info(1).Width;
            app.height = info(1).Height;
            app.res_x = 1./info(1).XResolution;
            app.res_y = 1./info(1).YResolution;
            app.width_um = app.width * app.res_y;
            app.height_um = app.height * app.res_x;
            [fpath, image_fname, ~] = fileparts(fname);
            app.image_fname = image_fname;
            app.duration = app.n_frames / app.frame_rate;

            app.FramerateHzEditField.Value = app.frame_rate;
            app.DurationsEditField.Value = app.duration;
            app.XresolutionmpixelEditField.Value = app.res_x;
            app.YresolutionmpixelEditField.Value = app.res_y;
            app.WidthmEditField.Value = app.width_um;
            app.HeightmEditField.Value = app.height_um;
            app.FilenameEditField.Value = app.image_fname;

            app.setSliders;
            app.cur_frame = 1;
            app.cur_frame_start = 1;
            app.cur_frame_end = app.n_frames;
            app.StartFrameEditField.Value = 1;
            app.EndFrameEditField.Value = app.n_frames;
            app.StartTimeEditField.Value = 1./app.frame_rate;
            app.EndTimeEditField.Value = app.n_frames./app.frame_rate;
            app.StarttimesSlider.Value = 1./app.frame_rate;
            app.EndtimesSlider.Value = app.n_frames./app.frame_rate;        
            if ~isempty(app.selected_roi)
                delete(app.selected_roi);
            end
            app.updateImageFrame;
            app.ROIs = [];
            app.NumberofROIsEditField.Value = 0;
            app.plot_ROIs;
            app.updateROIFieldsMenu;
        end
        
        function loadImageSequence_fromDatabase(app, fname, meta_struct)
            movie_set = sln_funcimage.EpochMovie & sprintf('image_fname="%s"', fname) & 'LIMIT 1000 PER source_id ORDER BY epoch_id ASC';
            movies = fetchn(movie_set,'raw_movie');
            movies_shifted = cellfun(@(m)shiftdim(m,2),movies,'UniformOutput',false);
            V = cell2mat(movies_shifted);
            app.imageVolume = shiftdim(V,1);
            clear('V');

            app.frame_rate = meta_struct.frame_rate;            
            app.n_frames = size(app.imageVolume,3);

            app.width = meta_struct.width;
            app.height = meta_struct.height;
            app.res_x = meta_struct.res_x;
            app.res_y = meta_struct.res_y;
            app.width_um = app.width * app.res_y;
            app.height_um = app.height * app.res_x;
            
            app.image_fname = fname;
            app.duration = app.n_frames / app.frame_rate;

            app.FramerateHzEditField.Value = app.frame_rate;
            app.DurationsEditField.Value = app.duration;
            app.XresolutionmpixelEditField.Value = app.res_x;
            app.YresolutionmpixelEditField.Value = app.res_y;
            app.WidthmEditField.Value = app.width_um;
            app.HeightmEditField.Value = app.height_um;
            app.FilenameEditField.Value = app.image_fname;

            app.setSliders;
            app.cur_frame = 1;
            app.cur_frame_start = 1;
            app.cur_frame_end = app.n_frames;
            app.StartFrameEditField.Value = 1;
            app.EndFrameEditField.Value = app.n_frames;
            app.StartTimeEditField.Value = 1./app.frame_rate;
            app.EndTimeEditField.Value = app.n_frames./app.frame_rate;
            app.StarttimesSlider.Value = 1./app.frame_rate;
            app.EndtimesSlider.Value = app.n_frames./app.frame_rate;        
            if ~isempty(app.selected_roi)
                delete(app.selected_roi);
            end

            epochs = sln_symphony.ExperimentEpochBlock * aka.Epoch * sln_symphony.ExperimentEpochChannel * sln_symphony.ExperimentChannel & movie_set;
            prot_name = unique(fetchn(epochs,'protocol_name'));
            if length(prot_name) > 1
                error('Should not have more than one protocol');
            else
                prot_name = prot_name{1};
            end

            epochs = epochs * aka.BlockParams(sqlProtName2ProtName(prot_name)) * aka.EpochParams(sqlProtName2ProtName(prot_name)) & 'channel_name="Amp1"';
            app.epochs_query = epochs;
            epochs_struct = fetch(epochs,'*');

            if strmatch(prot_name,'drifting_gratings')
                app.GratingangleDropDown.Visible = true;
                angles = sort(unique([epochs_struct.grating_angle]));
                app.load_grating_angles_menu(angles);
            else
                app.GratingangleDropDown.Visible = false;
            end
            
            if ~(app.GratingangleDropDown.Visible)
                N_samples = length(epochs_struct(1).raw_data);
                D = reshape([epochs_struct.raw_data],N_samples, []);
                time_axis = 1E3*(1:N_samples) ./ epochs_struct(1).sample_rate - epochs_struct(1).pre_time; % ms
                plot(app.SomaVoltageAxis,time_axis,D,'color',[0.7 0.7 0.7]);
                hold(app.SomaVoltageAxis,'on');
                plot(app.SomaVoltageAxis, time_axis, mean(D,2),'color','k','linewidth',2);
                hold(app.SomaVoltageAxis,'off');
            else
                app.plot_soma_voltage_gratings()
            end
           
            app.updateImageFrame;
            app.ROIs = [];
            app.NumberofROIsEditField.Value = 0;
            app.plot_ROIs;
            app.updateROIFieldsMenu;  
        end

        function setSliders(app)
            app.TimesSlider.Limits = [1/app.frame_rate app.duration];
            app.TimesSlider.MajorTicks = floor(linspace(1, app.duration, 5));
            app.CurTimeEditField.Limits = [1/app.frame_rate app.duration];
            app.CurFrameEditField.Limits = [1 app.n_frames];

            app.StarttimesSlider.Limits = [1/app.frame_rate app.duration];
            app.EndtimesSlider.Limits = [1/app.frame_rate app.duration];  

            app.TimesSlider.Value = 1/app.frame_rate;
            app.CurTimeEditField.Value = 1/app.frame_rate;
            app.CurFrameEditField.Value = 1;

            app.StarttimesSlider.Value = 1/app.frame_rate;
            app.EndtimesSlider.Value = app.duration;
        end
        
        function updateImageFrame(app)
            cur_im = squeeze(app.imageVolume(:,:,app.cur_frame));
            imshow(cur_im,[min(cur_im, [], 'all'), max(cur_im, [], 'all')],'Parent',app.original_axis);
        end
        
        function updateProfileTrace(app)
            app.trace = squeeze(mean(double(app.imageVolume).*repmat(app.selected_mask,[1, 1, app.n_frames]),[1 2]));
            plot(app.TemporalProfileAxis,[1:app.n_frames]./app.frame_rate,...
                app.trace,...
                'Color','k','LineWidth',2);            
        end
        
        function updateProfileTraceTime(app)
            plot(app.TemporalProfileAxis,[app.cur_frame_start:app.cur_frame_end]./app.frame_rate, ...
                app.trace(app.cur_frame_start:app.cur_frame_end),...
                'Color','k','LineWidth',2); 
        end
        
        function plot_ROIs(app)
            R = zeros(app.height, app.width);
            N_ROIs = length(app.ROIs);
            app.NumberofROIsEditField.Value = N_ROIs;

            xvals = zeros(N_ROIs,1);
            yvals = zeros(N_ROIs,1);
            for i=1:N_ROIs
                R(app.ROIs{i}) = i;
                [row, col] = ind2sub(size(R),app.ROIs{i});
                xvals(i) = round(mean(col));
                yvals(i) = round(mean(row));
            end
            imagesc(app.rois_axis,R);
            cmap = colormap(app.rois_axis,'turbo');
            cmap = [[0, 0, 0]; cmap];
            colormap(app.rois_axis,cmap);

            for i=1:N_ROIs
                text(app.rois_axis,xvals(i),yvals(i),num2str(i),...
                    'FontWeight','bold','Color',[1 1 1],'FontSize',10);                                
            end

        end
        
        function makeROIs(app)
            if isempty(app.corr_tensor)
                errordlg('Correlation and path distance tensors must be computed first.');
                return
            end
            C = app.corr_tensor >= app.CorrelationthresholdSlider.Value;
            D = app.path_dist_tensor <= app.PathDistEditField.Value;
            CD = C.*D;

            ind = find(app.proj_mask);
            N_pix = length(ind);
            z = 1;
            for i=1:N_pix
                %[x, y] = ind2sub([app.height, app.width], ind(i));
                regions = bwconncomp(squeeze(CD(:,:,i)),4);
                for r=1:regions.NumObjects
                    curROI = regions.PixelIdxList{r};
                    area = length(curROI).*app.res_x.*app.res_y;
                    if area >= app.AreaThresEditField.Value                        
                        ROIs{z} = sort(curROI);
                        z=z+1;
                    end
                end
            end          
            app.ROIs = UniqueCell(ROIs);
            app.ROIs = removeSubsets(app.ROIs);
            app.ROIs = getLargerSet(app.ROIs);
            app.plot_ROIs;
            step_line = sprintf('ROIs computed with within %f µm path distance, area threshold = %f µm^2, corr. threshold = %f.\n', ...
                app.PathDistEditField.Value,app.AreaThresEditField,app.CorrelationthresholdSlider.Value);
            app.StepsTextArea.Value = [app.StepsTextArea.Value; step_line];   
        end
        
        function updateROITraces(app)
            populate(sln_funcimage.ROITraces);
            app.SelectROIDropDown.Items = strtrim(cellstr(num2str((1:app.NumberofROIsEditField.Value)')));
            app.SelectROIDropDown.Value = '1';
        end
        
        function updateROIFieldsMenu(app)

            q = sln_funcimage.ROIField & sprintf('image_fname="%s"',app.image_fname);
            if q.exists
                method_ids = unique(fetchn(q,'method_id'));
                app.SelectROIfieldDropDown.Items = strtrim(cellstr(num2str(method_ids)));
                app.SelectROIfieldDropDown.Value = num2str(method_ids(1));
            else
                app.SelectROIfieldDropDown.Items = {''};      
                %app.SelectROIfieldDropDown.Value = '';
                cla(app.ROITemporalProfileAxis);
                app.SelectROIDropDown.Items = {};
                %app.SelectROIDropDown.Value = [];                
            end
        end
        
        function load_ROIs(app,roi_field_struct)
            app.ROIs = roi_field_struct.pixels_in_roi;
            app.NumberofROIsEditField.Value = roi_field_struct.n_rois;
            app.plot_ROIs;    
            app.updateROIFieldsMenu;
        end
        
        function plot_roi_map(app)
            n_rois = length(app.ROIs);
            mapVal = zeros(n_rois,1);
            q = sln_funcimage.ROITraces & sprintf('image_fname="%s"',app.image_fname) & sprintf('method_id=%d',app.ROI_method_id);
            for i=1:n_rois                
                [~, trace_mean] = sln_funcimage.meanForROI(q,i);
                mapVal(i) = max(trace_mean);                
            end

            R = zeros(app.height, app.width);

            for i=1:n_rois
                R(app.ROIs{i}) = mapVal(i);
            end
            imagesc(app.rois_heatmap_axis,R);
            cmap = colormap(app.rois_axis,'parula');
            cmap = [[0, 0, 0]; cmap];
            colormap(app.rois_heatmap_axis,cmap);
            cbar = colorbar(app.rois_heatmap_axis);
            cbar.Label.String = 'Response amplitude dF/F';
        end
        
        function load_grating_angles_menu(app, angles)
            angles_cell = strtrim(cellstr(num2str(angles')));
            app.GratingangleDropDown.Items = angles_cell;
            app.GratingangleDropDown.Value = angles_cell{1};            
        end
        
        function plot_roi_trace(app)
            roi = str2double(app.SelectROIDropDown.Value);

            q = sln_funcimage.ROITraces & sprintf('image_fname="%s"',app.image_fname) & sprintf('method_id=%d',app.ROI_method_id);
            if app.GratingangleDropDown.Visible %DG
                angle = str2double(app.GratingangleDropDown.Value);
                q = q * app.epochs_query & sprintf('grating_angle=%d',angle);

            end
            
            q = sln_funcimage.ROITraces & proj(q);
            [time_axis, trace_mean, trace_sem, trace_matrix] = sln_funcimage.meanForROI(q,roi);
            plot(app.ROITemporalProfileAxis,time_axis,trace_matrix,'Color',[0.7 0.7 0.7],'LineWidth',0.5);
            hold(app.ROITemporalProfileAxis,'on');
            if size(trace_matrix,1) > 3
                errorbar(app.ROITemporalProfileAxis,time_axis,trace_mean,trace_sem,'k','LineWidth',2);
            else
                plot(app.ROITemporalProfileAxis,time_axis,trace_mean,'k','LineWidth',2);
            end
            hold(app.ROITemporalProfileAxis,'off');
        end
        
        function load_roi_field(app)
            value = app.SelectROIfieldDropDown.Value;
            app.ROI_method_id = str2double(value);
            q = sln_funcimage.ROIField & sprintf('image_fname="%s"',app.image_fname) & sprintf('method_id=%d',app.ROI_method_id);
            roi_field_struct = fetch(q,'*');
            app.ROIs = roi_field_struct.pixels_in_roi;
            app.NumberofROIsEditField.Value = roi_field_struct.n_rois;
            app.plot_ROIs;   
            app.updateROITraces;     
        end
        
        function plot_soma_voltage_gratings(app)
            angle = str2double(app.GratingangleDropDown.Value);
            q = app.epochs_query & sprintf('grating_angle=%d',angle);

            epochs_struct = fetch(q,'*');

            N_samples = length(epochs_struct(1).raw_data);
            D = reshape([epochs_struct.raw_data],N_samples, []);
            time_axis = 1E3*(1:N_samples) ./ epochs_struct(1).sample_rate - epochs_struct(1).pre_time; % ms
            plot(app.SomaVoltageAxis,time_axis,D,'color',[0.7 0.7 0.7]);
            hold(app.SomaVoltageAxis,'on');
            plot(app.SomaVoltageAxis, time_axis, mean(D,2),'color','k','linewidth',2);
            hold(app.SomaVoltageAxis,'off');            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LoadfromdatabaseDropDown.Items = ['None'; fetchn(sln_funcimage.ImagingRun, 'image_fname')];
            app.LoadfromdatabaseDropDown.Value = 'None'; 
        end

        % Menu selected function: LoadimageMenu
        function LoadimageMenuSelected(app, event)
            [fname, fpath] = uigetfile('*.tif','Select functional imaging file',...
                [getenv('Func_imaging_folder') filesep 'SingleOrPairedCell']);
            meta_fname = uigetfile('*.mat','Select metadata file', fpath);
            app.loadImageSequence([fpath, fname], [fpath meta_fname]);
        end

        % Value changed function: TimesSlider
        function TimesSliderValueChanged(app, event)
            value = app.TimesSlider.Value;
            app.cur_frame = round(value * app.frame_rate);
            app.CurTimeEditField.Value = value;
            app.CurFrameEditField.Value = app.cur_frame;
            app.updateImageFrame;
        end

        % Value changed function: CurFrameEditField
        function CurFrameEditFieldValueChanged(app, event)
            value = app.CurFrameEditField.Value;
            app.cur_frame = value;
            time_val = value / app.frame_rate;
            app.TimesSlider.Value = time_val;
            app.CurTimeEditField.Value = time_val;
            app.updateImageFrame;
        end

        % Value changed function: CurTimeEditField
        function CurTimeEditFieldValueChanged(app, event)
            value = app.CurTimeEditField.Value;
            app.cur_frame = round(value * app.frame_rate);
            app.CurFrameEditField.Value = app.cur_frame;
            app.TimesSlider.Value = value;
            app.updateImageFrame; 
        end

        % Menu selected function: FreedrawMenu
        function FreedrawMenuSelected(app, event)
            if ~isempty(app.selected_roi)
                delete(app.selected_roi);
            end
            app.selected_roi = drawpolygon(app.original_axis);            
            app.selected_mask = createMask(app.selected_roi);
            app.updateProfileTrace();
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.selected_mask = createMask(app.selected_roi);
            app.updateProfileTrace();
        end

        % Value changed function: StartTimeEditField
        function StartTimeEditFieldValueChanged(app, event)
            value = app.StartTimeEditField.Value;
            app.cur_frame_start = round(value * app.frame_rate);
            app.StartFrameEditField.Value = app.cur_frame_start;
            app.StarttimesSlider.Value = value;
            app.EndFrameEditField.Limits = [app.cur_frame_start+1, app.n_frames];
            app.EndtimesSlider.Limits = [app.cur_frame_start+1, app.n_frames]./app.frame_rate;
            app.EndTimeEditField.Limits = [app.cur_frame_start+1, app.n_frames]./app.frame_rate;
            app.updateProfileTraceTime();
        end

        % Value changed function: StartFrameEditField
        function StartFrameEditFieldValueChanged(app, event)
            value = app.StartFrameEditField.Value;
            app.cur_frame_start = value;
            time_val = value / app.frame_rate;
            app.StarttimesSlider.Value = time_val;
            app.StartTimeEditField.Value = time_val;
            app.EndFrameEditField.Limits = [app.cur_frame_start+1, app.n_frames];
            app.EndtimesSlider.Limits = [app.cur_frame_start+1, app.n_frames]./app.frame_rate;
            app.EndTimeEditField.Limits = [app.cur_frame_start+1, app.n_frames]./app.frame_rate;
            app.updateProfileTraceTime();
        end

        % Value changed function: EndTimeEditField
        function EndTimeEditFieldValueChanged(app, event)
            value = app.EndTimeEditField.Value;
            app.cur_frame_end = round(value * app.frame_rate);
            app.EndFrameEditField.Value = app.cur_frame_end;
            app.EndtimesSlider.Value = value;
            app.StartFrameEditField.Limits = [1, app.cur_frame_end-1];
            app.StarttimesSlider.Limits = [1, app.cur_frame_end-1]./app.frame_rate;
            app.StartTimeEditField.Limits = [1, app.cur_frame_end-1]./app.frame_rate;
            app.updateProfileTraceTime();
        end

        % Value changed function: EndFrameEditField
        function EndFrameEditFieldValueChanged(app, event)
            value = app.EndFrameEditField.Value;
            app.cur_frame_end = value;
            time_val = value / app.frame_rate;
            app.EndtimesSlider.Value = time_val;
            app.EndTimeEditField.Value = time_val;
            app.StartFrameEditField.Limits = [1, app.cur_frame_end-1];
            app.StarttimesSlider.Limits = [1, app.cur_frame_end-1]./app.frame_rate;
            app.StartTimeEditField.Limits = [1, app.cur_frame_end-1]./app.frame_rate;
            app.updateProfileTraceTime();
        end

        % Value changed function: StarttimesSlider
        function StarttimesSliderValueChanged(app, event)
            value = app.StarttimesSlider.Value;
            app.cur_frame_start = round(value * app.frame_rate);
            app.StartTimeEditField.Value = value;
            app.StartFrameEditField.Value = app.cur_frame_start;
            app.EndFrameEditField.Limits = [app.cur_frame_start+1, app.n_frames];
            app.EndtimesSlider.Limits = [app.cur_frame_start+1, app.n_frames]./app.frame_rate;
            app.EndTimeEditField.Limits = [app.cur_frame_start+1, app.n_frames]./app.frame_rate;
            app.updateProfileTraceTime();
        end

        % Value changed function: EndtimesSlider
        function EndtimesSliderValueChanged(app, event)
            value = app.EndtimesSlider.Value;
            app.cur_frame_end = round(value * app.frame_rate);
            app.EndTimeEditField.Value = value;
            app.EndFrameEditField.Value = app.cur_frame_end;
            app.StartFrameEditField.Limits = [1, app.cur_frame_end-1];
            app.StarttimesSlider.Limits = [1, app.cur_frame_end-1]./app.frame_rate;
            app.StartTimeEditField.Limits = [1, app.cur_frame_end-1]./app.frame_rate;
            app.updateProfileTraceTime();
        end

        % Button pushed function: MaxProjButton
        function MaxProjButtonPushed(app, event)
            if strcmp(app.LimittotimeslidersSwitch.Value, 'N')
                frame_range = [1:app.n_frames];
            else
                frame_range = [app.cur_frame_start:app.cur_frame_end];                
            end
            if ~isempty(app.working_cbar)
                delete(app.working_cbar)
            end
            app.proj = squeeze(max(double(app.imageVolume(:,:,frame_range)),[],3));
            app.proj_type = 'max';
            imshow(app.proj,[min(app.proj, [], 'all'), max(app.proj, [], 'all')],'Parent',app.working_axis);
        end

        % Button pushed function: MeanProjButton
        function MeanProjButtonPushed(app, event)
            if strcmp(app.LimittotimeslidersSwitch.Value, 'N')
                frame_range = [1:app.n_frames];
            else
                frame_range = [app.cur_frame_start:app.cur_frame_end];                
            end
            app.proj = squeeze(mean(double(app.imageVolume(:,:,frame_range)),3));
            app.proj_type = 'mean';
            imshow(app.proj,[min(app.proj, [], 'all'), max(app.proj, [], 'all')],'Parent',app.working_axis);
        end

        % Button pushed function: StdProjButton
        function StdProjButtonPushed(app, event)
            if strcmp(app.LimittotimeslidersSwitch.Value, 'N')
                frame_range = [1:app.n_frames];
            else
                frame_range = [app.cur_frame_start:app.cur_frame_end];                
            end
            app.proj = squeeze(std(double(app.imageVolume(:,:,frame_range)),[],3));
            app.proj_type = 'std';
            imshow(app.proj,[min(app.proj, [], 'all'), max(app.proj, [], 'all')],'Parent',app.working_axis);
        end

        % Value changed function: ProjThresholdSlider
        function ProjThresholdSliderValueChanged(app, event)
            value = app.ProjThresholdSlider.Value;
            app.proj_thres = value;
            P = (app.proj - min(app.proj, [], 'all')) / max(app.proj,[],'all'); %normalize;
            app.proj_mask = P>value;
            mask_red = zeros([size(app.proj_mask), 3]);
            mask_red(:,:,1) = app.proj_mask;
            proj_im = repmat(P,[1 1 3]);
%            keyboard;
            %mask_red(:,:,4) = app.proj_mask; % set alpha
            %colormap(app.working_axis,'hot');
            imshow(imfuse(2*proj_im,mask_red,'blend','scaling','joint'),'Parent',app.working_axis);
            %image(app.working_axis,'CData',app.proj_mask,'AlphaData',app.proj_mask)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            imshow(app.proj_mask,[0 1],'Parent',app.working_axis);
            step_line = sprintf('Threshold %s projection at %f.\n', app.proj_type, app.proj_thres);
            if isempty(app.StepsTextArea.Value{1})
                app.StepsTextArea.Value = step_line;
            else
                app.StepsTextArea.Value = [app.StepsTextArea.Value; step_line];
            end
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            app.StepsTextArea.Value = {''};
        end

        % Button pushed function: ClearlastButton
        function ClearlastButtonPushed(app, event)
            app.StepsTextArea.Value = app.StepsTextArea.Value(1:end-1);
        end

        % Button pushed function: StdProjButton_2
        function StdProjButton_2Pushed(app, event)
            P = zeros(size(app.proj_mask));
            app.proj_mask = [];
            app.proj = P;
            imshow(P,'Parent',app.working_axis);
        end

        % Menu selected function: PointMenu
        function PointMenuSelected(app, event)
            if isempty(app.proj_mask)
                if ~isempty(app.selected_roi)
                    delete(app.selected_roi);
                end
                app.selected_roi = drawpoint(app.original_axis,'Color','b');
                app.selected_roi.Position
                pos = round(app.selected_roi.Position);
                app.selected_mask = zeros(app.height, app.width);                
                app.selected_mask(pos(2), pos(1)) = 1;
                app.updateProfileTrace();
            else
                if ~isempty(app.corr_point)
                    delete(app.corr_point);
                end
                app.corr_point = drawpoint(app.working_axis,'Color','b');
                pos = round(app.corr_point.Position);
                [X, Y] = meshgrid(app.res_y*(1:app.height), app.res_x*(1:app.width));
                X_flat = reshape(X,[app.width * app.height, 1]);
                Y_flat = reshape(Y,[app.width * app.height, 1]);
                D = pdist2([X_flat, Y_flat], pos.*[app.res_y, app.res_x]);
                D_map = reshape(D,[app.height, app.width]);
                D_map = D_map .* app.proj_mask;
                corr_mask = D_map > 0 & D_map < app.SearchradiusEditField.Value;                
                ind = find(corr_mask);
                pix_trace = squeeze(app.imageVolume(pos(2),pos(1),:));
                [x_test,y_test] = ind2sub([app.height,app.width], ind);
                test_traces = zeros(length(pix_trace),length(x_test));
                for i=1:length(x_test)
                    test_traces(:,i) = squeeze(app.imageVolume(x_test(i),y_test(i),:));
                end
                corr_vals = corr(double(test_traces),double(pix_trace));
                corr_map = zeros(size(corr_mask));
                corr_map(ind) = corr_vals;
                C = [[0 0 0]; colormap(app.working_axis,'parula')];
                colormap(app.working_axis,C);
                %clim(app.working_axis,[0, max(corr_map,[],'all')]);
                clim(app.working_axis,[0, 1]);
                app.working_cbar = colorbar(app.working_axis);
                app.working_cbar.Label.String = 'Corr. coeff.';

                %D_map(D_map==0) = nan;
                imagesc(app.working_axis,corr_map);    

            end
        end

        % Button pushed function: ComputeallcorrelationsButton
        function ComputeallcorrelationsButtonPushed(app, event)
            if isempty(app.proj_mask)
                cla(app.rois_axis);
                app.NumberofROIsEditField.Value = 0;                
                errordlg('Projection mask must be computed first.');
                return
            end
            ind = find(app.proj_mask);
            N_pix = length(ind);
            app.path_dist_tensor = zeros(app.height,app.width,N_pix);
            app.corr_tensor = zeros(app.height,app.width,N_pix);
            [x_test,y_test] = ind2sub([app.height,app.width], ind);
            test_traces = zeros(app.n_frames,length(x_test));
            for i=1:length(x_test)
                test_traces(:,i) = squeeze(app.imageVolume(x_test(i),y_test(i),:));
            end

            for i=1:N_pix
                [x, y] = ind2sub([app.height, app.width], ind(i));
                pix_trace = squeeze(app.imageVolume(x,y,:));            
                corr_vals = corr(double(test_traces),double(pix_trace));
                corr_map = zeros(app.height,app.width);
                corr_map(ind) = corr_vals;
                app.corr_tensor(:,:,i) = corr_map;
                app.path_dist_tensor(:,:,i) = bwdistgeodesic(app.proj_mask,ind(i),'quasi-euclidean') .* mean([app.res_x, app.res_y]);                
            end
            step_line = sprintf('Correlations within %f µm radius computed.\n', app.SearchradiusEditField.Value);
            app.StepsTextArea.Value = [app.StepsTextArea.Value; step_line];
            app.makeROIs;
        end

        % Button pushed function: MakeROIsButton
        function MakeROIsButtonPushed(app, event)
           app.makeROIs;
        end

        % Value changed function: CorrelationthresholdSlider
        function CorrelationthresholdSliderValueChanged(app, event)
            app.makeROIs;
        end

        % Value changed function: PathDistEditField
        function PathDistEditFieldValueChanged(app, event)
            app.makeROIs;
        end

        % Value changed function: AreaThresEditField
        function AreaThresEditFieldValueChanged(app, event)
            app.makeROIs;
        end

        % Menu selected function: SaveROIsMenu
        function SaveROIsMenuSelected(app, event)
            s = struct;
            s.proj_mask = app.proj_mask;
            s.ROIs = app.ROIs;
            s.steps_text = app.StepsTextArea.Value;
            [fname, fpath] = uiputfile('ROIs_.mat','Save ROIs...');
            save([fpath fname], 's');
            ROI_mask = zeros(app.height,app.width);
            for i=1:length(app.ROIs)
                ROI_mask(app.ROIs{i}) = 255;
            end
            ROI_mask = uint8(ROI_mask);
            %write image
            fname_image = strrep(fname,'ROIs_','ROImask_');
            fname_image = strrep(fname_image,'.mat','.tif');
            imwrite(ROI_mask,[fpath fname_image]);
        end

        % Button pushed function: WriteROIstodatabaseButton
        function WriteROIstodatabaseButtonPushed(app, event)
            key = struct;
            ROI_mask = false(app.height,app.width);
            for i=1:length(app.ROIs)
                ROI_mask(app.ROIs{i}) = true;
            end
            key.pixels_in_roi = app.ROIs;
            key.mask = ROI_mask;
            key.n_rois = length(app.ROIs);
            key.image_fname = app.image_fname;
            
            key_method.method_name = 'ROI_Tool';
            key_method.notes = [app.StepsTextArea.Value{:}];
            insert(sln_funcimage.ROIMethod,key_method);

            last_id = max(fetchn(sln_funcimage.ROIMethod,'method_id'));
            key.method_id = last_id;
            app.ROI_method_id = key.method_id;
            
            q = sln_funcimage.ImagingRun & sprintf('image_fname="%s"', app.image_fname);
            ds_struct = fetch(sln_symphony.Dataset & q,'*');
            for i=1:q.count
                key = mergeStruct(key,ds_struct(i));
                insert(sln_funcimage.ROIField,key)
            end

            app.updateROIFieldsMenu;
            app.updateROITraces;
        end

        % Value changed function: LoadfromdatabaseDropDown
        function LoadfromdatabaseDropDownValueChanged(app, event)
            value = app.LoadfromdatabaseDropDown.Value;
            q = sln_funcimage.ImagingRun & sprintf('image_fname="%s"', value) & 'LIMIT 1 PER image_fname';
            app.loadImageSequence_fromDatabase(value,fetch(q,'*'));
        end

        % Value changed function: SelectROIfieldDropDown
        function SelectROIfieldDropDownValueChanged(app, event)
            app.load_roi_field;
                   
        end

        % Value changed function: SelectROIDropDown
        function SelectROIDropDownValueChanged(app, event)
            app.plot_roi_trace;            
        end

        % Button pushed function: PlotROIamplitudemapButton
        function PlotROIamplitudemapButtonPushed(app, event)
            app.plot_roi_map;
        end

        % Value changed function: GratingangleDropDown
        function GratingangleDropDownValueChanged(app, event)
            app.plot_soma_voltage_gratings;
            app.plot_roi_trace;           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROIToolUIFigure = uifigure('Visible', 'off');
            app.ROIToolUIFigure.Name = 'ROI Tool';
            app.FileMenu = uimenu(app.ROIToolUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadimageMenu = uimenu(app.FileMenu);
            app.LoadimageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadimageMenuSelected, true);
            app.LoadimageMenu.Accelerator = 'L';
            app.LoadimageMenu.Text = 'Load image';
            app.LoadROIsetMenu = uimenu(app.FileMenu);
            app.LoadROIsetMenu.Accelerator = 'R';
            app.LoadROIsetMenu.Text = 'Load ROI set';
            app.SaveROIsMenu = uimenu(app.FileMenu);
            app.SaveROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveROIsMenuSelected, true);
            app.SaveROIsMenu.Accelerator = 'S';
            app.SaveROIsMenu.Text = 'Save ROIs';
            app.SelectMenu = uimenu(app.ROIToolUIFigure);
            app.SelectMenu.Text = 'Select';
            app.FreedrawMenu = uimenu(app.SelectMenu);
            app.FreedrawMenu.MenuSelectedFcn = createCallbackFcn(app, @FreedrawMenuSelected, true);
            app.FreedrawMenu.Accelerator = 'D';
            app.FreedrawMenu.Text = 'Free draw';
            app.PointMenu = uimenu(app.SelectMenu);
            app.PointMenu.MenuSelectedFcn = createCallbackFcn(app, @PointMenuSelected, true);
            app.PointMenu.Accelerator = 'P';
            app.PointMenu.Text = 'Point';
            app.original_axis = uiaxes(app.ROIToolUIFigure);
            title(app.original_axis, 'Original')
            app.original_axis.XTick = [];
            app.original_axis.YTick = [];
            app.working_axis = uiaxes(app.ROIToolUIFigure);
            title(app.working_axis, 'Working')
            app.working_axis.XTick = [];
            app.working_axis.YTick = [];
            app.rois_axis = uiaxes(app.ROIToolUIFigure);
            title(app.rois_axis, 'ROIs')
            app.rois_axis.XTick = [];
            app.rois_axis.YTick = [];
            app.TemporalProfileAxis = uiaxes(app.ROIToolUIFigure);
            title(app.TemporalProfileAxis, 'Selection temporal profile')
            xlabel(app.TemporalProfileAxis, 'Time (s)')
            app.ROITemporalProfileAxis = uiaxes(app.ROIToolUIFigure);
            title(app.ROITemporalProfileAxis, 'ROI temporal profile')
            xlabel(app.ROITemporalProfileAxis, 'Time (ms)')
            app.rois_heatmap_axis = uiaxes(app.ROIToolUIFigure);
            title(app.rois_heatmap_axis, 'Response amplitude by ROI')
            app.rois_heatmap_axis.XTick = [];
            app.rois_heatmap_axis.YTick = [];
            app.SomaVoltageAxis = uiaxes(app.ROIToolUIFigure);
            title(app.SomaVoltageAxis, 'Soma voltage')
            xlabel(app.SomaVoltageAxis, 'Time (ms)')
            app.TimesSliderLabel = uilabel(app.ROIToolUIFigure);
            app.TimesSliderLabel.Text = 'Time (s)';
            app.ImagingruninformationPanel = uipanel(app.ROIToolUIFigure);
            app.ImagingruninformationPanel.Title = 'Imaging run information';
            app.FramerateHzEditFieldLabel = uilabel(app.ImagingruninformationPanel);
            app.FramerateHzEditFieldLabel.Text = 'Frame rate (Hz)';
            app.FramerateHzEditField = uieditfield(app.ImagingruninformationPanel, 'numeric');
            app.FramerateHzEditField.Editable = 'off';
            app.DurationsEditFieldLabel = uilabel(app.ImagingruninformationPanel);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.ImagingruninformationPanel, 'numeric');
            app.DurationsEditField.Editable = 'off';
            app.XresolutionmpixelEditFieldLabel = uilabel(app.ImagingruninformationPanel);
            app.XresolutionmpixelEditFieldLabel.Text = 'X resolution (µm/pixel)';
            app.XresolutionmpixelEditField = uieditfield(app.ImagingruninformationPanel, 'numeric');
            app.XresolutionmpixelEditField.Editable = 'off';
            app.YresolutionmpixelEditFieldLabel = uilabel(app.ImagingruninformationPanel);
            app.YresolutionmpixelEditFieldLabel.Text = 'Y resolution (µm/pixel)';
            app.YresolutionmpixelEditField = uieditfield(app.ImagingruninformationPanel, 'numeric');
            app.YresolutionmpixelEditField.Editable = 'off';
            app.NumberofROIsEditFieldLabel = uilabel(app.ImagingruninformationPanel);
            app.NumberofROIsEditFieldLabel.Text = 'Number of ROIs';
            app.NumberofROIsEditField = uieditfield(app.ImagingruninformationPanel, 'numeric');
            app.NumberofROIsEditField.Editable = 'off';
            app.WidthmEditFieldLabel = uilabel(app.ImagingruninformationPanel);
            app.WidthmEditFieldLabel.Text = 'Width (µm)';
            app.WidthmEditField = uieditfield(app.ImagingruninformationPanel, 'numeric');
            app.WidthmEditField.Editable = 'off';
            app.HeightmEditFieldLabel = uilabel(app.ImagingruninformationPanel);
            app.HeightmEditFieldLabel.Text = 'Height (µm)';
            app.HeightmEditField = uieditfield(app.ImagingruninformationPanel, 'numeric');
            app.HeightmEditField.Editable = 'off';
            app.FilenameEditFieldLabel = uilabel(app.ImagingruninformationPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.ImagingruninformationPanel, 'text');
            app.TimesSlider = uislider(app.ROIToolUIFigure);
            app.TimesSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.TimesSlider.ValueChangedFcn = createCallbackFcn(app, @TimesSliderValueChanged, true);
            app.TimesSlider.MinorTicks = [];
            app.EndtimesSliderLabel = uilabel(app.ROIToolUIFigure);
            app.EndtimesSliderLabel.Text = 'End time (s)';
            app.EndtimesSlider = uislider(app.ROIToolUIFigure);
            app.EndtimesSlider.ValueChangedFcn = createCallbackFcn(app, @EndtimesSliderValueChanged, true);
            app.EndtimesSlider.MinorTicks = [];
            app.ProjectionsPanel = uipanel(app.ROIToolUIFigure);
            app.ProjectionsPanel.Title = 'Projections';
            app.MaxProjButton = uibutton(app.ProjectionsPanel, 'push');
            app.MaxProjButton.ButtonPushedFcn = createCallbackFcn(app, @MaxProjButtonPushed, true);
            app.MaxProjButton.Text = 'Max';
            app.MeanProjButton = uibutton(app.ProjectionsPanel, 'push');
            app.MeanProjButton.ButtonPushedFcn = createCallbackFcn(app, @MeanProjButtonPushed, true);
            app.MeanProjButton.Text = 'Mean';
            app.StdProjButton = uibutton(app.ProjectionsPanel, 'push');
            app.StdProjButton.ButtonPushedFcn = createCallbackFcn(app, @StdProjButtonPushed, true);
            app.StdProjButton.Text = 'Std';
            app.LimittotimeslidersSwitchLabel = uilabel(app.ProjectionsPanel);
            app.LimittotimeslidersSwitchLabel.Text = 'Limit to time sliders';
            app.LimittotimeslidersSwitch = uiswitch(app.ProjectionsPanel, 'slider');
            app.LimittotimeslidersSwitch.Items = {'N', 'Y'};
            app.LimittotimeslidersSwitch.Value = 'N';
            app.ThresholdSliderLabel = uilabel(app.ProjectionsPanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ProjThresholdSlider = uislider(app.ProjectionsPanel);
            app.ProjThresholdSlider.Limits = [0 1];
            app.ProjThresholdSlider.MajorTicks = [0 0.5 1];
            app.ProjThresholdSlider.MajorTickLabels = {'min', '0.5', 'max'};
            app.ProjThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ProjThresholdSliderValueChanged, true);
            app.ProjThresholdSlider.MinorTicks = [];
            app.ApplyButton = uibutton(app.ProjectionsPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.StdProjButton_2 = uibutton(app.ProjectionsPanel, 'push');
            app.StdProjButton_2.ButtonPushedFcn = createCallbackFcn(app, @StdProjButton_2Pushed, true);
            app.StdProjButton_2.Text = 'Clear';
            app.CorrelationsPanel = uipanel(app.ROIToolUIFigure);
            app.CorrelationsPanel.Title = 'Correlations';
            app.SearchradiusmEditFieldLabel = uilabel(app.CorrelationsPanel);
            app.SearchradiusmEditFieldLabel.Text = 'Search radius (µm)';
            app.SearchradiusEditField = uieditfield(app.CorrelationsPanel, 'numeric');
            app.SearchradiusEditField.Limits = [1 Inf];
            app.SearchradiusEditField.Value = 20;
            app.ComputeallcorrelationsButton = uibutton(app.CorrelationsPanel, 'push');
            app.ComputeallcorrelationsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeallcorrelationsButtonPushed, true);
            app.ComputeallcorrelationsButton.Text = 'Compute all correlations';
            app.PathdistthresholdmLabel = uilabel(app.CorrelationsPanel);
            app.PathdistthresholdmLabel.Text = 'Path dist. threshold (µm)';
            app.PathDistEditField = uieditfield(app.CorrelationsPanel, 'numeric');
            app.PathDistEditField.Limits = [1 Inf];
            app.PathDistEditField.ValueChangedFcn = createCallbackFcn(app, @PathDistEditFieldValueChanged, true);
            app.PathDistEditField.Value = 10;
            app.CorrelationthresholdSliderLabel = uilabel(app.CorrelationsPanel);
            app.CorrelationthresholdSliderLabel.Text = 'Correlation threshold';
            app.CorrelationthresholdSlider = uislider(app.CorrelationsPanel);
            app.CorrelationthresholdSlider.Limits = [0 1];
            app.CorrelationthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @CorrelationthresholdSliderValueChanged, true);
            app.MakeROIsButton = uibutton(app.CorrelationsPanel, 'push');
            app.MakeROIsButton.ButtonPushedFcn = createCallbackFcn(app, @MakeROIsButtonPushed, true);
            app.MakeROIsButton.Text = 'Make ROIs';
            app.PathdistthresholdmLabel_2 = uilabel(app.CorrelationsPanel);
            app.PathdistthresholdmLabel_2.Text = 'Area threshold (µm^2)';
            app.AreaThresEditField = uieditfield(app.CorrelationsPanel, 'numeric');
            app.AreaThresEditField.Limits = [0 Inf];
            app.AreaThresEditField.ValueChangedFcn = createCallbackFcn(app, @AreaThresEditFieldValueChanged, true);
            app.AreaThresEditField.Value = 2;
            app.ProcessingstepsPanel = uipanel(app.ROIToolUIFigure);
            app.ProcessingstepsPanel.Title = 'Processing steps';
            app.ClearallButton = uibutton(app.ProcessingstepsPanel, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.StepsTextArea = uitextarea(app.ProcessingstepsPanel);
            app.ClearlastButton = uibutton(app.ProcessingstepsPanel, 'push');
            app.ClearlastButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlastButtonPushed, true);
            app.ClearlastButton.Text = 'Clear last';
            app.StarttimesSliderLabel = uilabel(app.ROIToolUIFigure);
            app.StarttimesSliderLabel.Text = 'Start time (s)';
            app.StarttimesSlider = uislider(app.ROIToolUIFigure);
            app.StarttimesSlider.ValueChangedFcn = createCallbackFcn(app, @StarttimesSliderValueChanged, true);
            app.StarttimesSlider.MinorTicks = [];
            app.FrameEditFieldLabel = uilabel(app.ROIToolUIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.StartTimeEditField = uieditfield(app.ROIToolUIFigure, 'numeric');
            app.StartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditFieldValueChanged, true);
            app.FrameEditFieldLabel_2 = uilabel(app.ROIToolUIFigure);
            app.FrameEditFieldLabel_2.Text = 'Frame';
            app.StartFrameEditField = uieditfield(app.ROIToolUIFigure, 'numeric');
            app.StartFrameEditField.ValueChangedFcn = createCallbackFcn(app, @StartFrameEditFieldValueChanged, true);
            app.CurFrameEditField = uieditfield(app.ROIToolUIFigure, 'numeric');
            app.CurFrameEditField.ValueChangedFcn = createCallbackFcn(app, @CurFrameEditFieldValueChanged, true);
            app.EndTimeEditField = uieditfield(app.ROIToolUIFigure, 'numeric');
            app.EndTimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimeEditFieldValueChanged, true);
            app.FrameEditFieldLabel_3 = uilabel(app.ROIToolUIFigure);
            app.FrameEditFieldLabel_3.Text = 'Frame';
            app.EndFrameEditField = uieditfield(app.ROIToolUIFigure, 'numeric');
            app.EndFrameEditField.ValueChangedFcn = createCallbackFcn(app, @EndFrameEditFieldValueChanged, true);
            app.CurTimeEditField = uieditfield(app.ROIToolUIFigure, 'numeric');
            app.CurTimeEditField.ValueChangedFcn = createCallbackFcn(app, @CurTimeEditFieldValueChanged, true);
            app.UpdateButton = uibutton(app.ROIToolUIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.HighpassHzEditFieldLabel = uilabel(app.ROIToolUIFigure);
            app.HighpassHzEditFieldLabel.Text = 'High pass (Hz)';
            app.HighpassHzEditField = uieditfield(app.ROIToolUIFigure, 'numeric');
            app.WriteROIstodatabaseButton = uibutton(app.ROIToolUIFigure, 'push');
            app.WriteROIstodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @WriteROIstodatabaseButtonPushed, true);
            app.WriteROIstodatabaseButton.Text = 'Write ROIs to database';
            app.SelectROIfieldDropDownLabel = uilabel(app.ROIToolUIFigure);
            app.SelectROIfieldDropDownLabel.Text = 'Select ROI field';
            app.SelectROIfieldDropDown = uidropdown(app.ROIToolUIFigure);
            app.SelectROIfieldDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectROIfieldDropDownValueChanged, true);
            app.SelectROIDropDownLabel = uilabel(app.ROIToolUIFigure);
            app.SelectROIDropDownLabel.Text = 'Select ROI';
            app.SelectROIDropDown = uidropdown(app.ROIToolUIFigure);
            app.SelectROIDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectROIDropDownValueChanged, true);
            app.LoadfromdatabaseDropDownLabel = uilabel(app.ROIToolUIFigure);
            app.LoadfromdatabaseDropDownLabel.Text = 'Load from database';
            app.LoadfromdatabaseDropDown = uidropdown(app.ROIToolUIFigure);
            app.LoadfromdatabaseDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadfromdatabaseDropDownValueChanged, true);
            app.PlotROIamplitudemapButton = uibutton(app.ROIToolUIFigure, 'push');
            app.PlotROIamplitudemapButton.ButtonPushedFcn = createCallbackFcn(app, @PlotROIamplitudemapButtonPushed, true);
            app.PlotROIamplitudemapButton.Text = 'Plot ROI amplitude map';
            app.GratingangleLabel = uilabel(app.ROIToolUIFigure);
            app.GratingangleLabel.Text = 'Grating angle (°)';
            app.GratingangleDropDown = uidropdown(app.ROIToolUIFigure);
            app.GratingangleDropDown.ValueChangedFcn = createCallbackFcn(app, @GratingangleDropDownValueChanged, true);
            % Show the figure after all components are created
            app.ROIToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROI_Tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROIToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROIToolUIFigure)
        end
    end
end