classdef ResetPassword < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ResetpasswordButton        matlab.ui.control.Button
        StatusLabel                matlab.ui.control.Label
        StatusEditField            matlab.ui.control.EditField
        Lamp_status                matlab.ui.control.Lamp
        NewpasswordEditField       matlab.ui.control.EditField
        NewpasswordEditFieldLabel  matlab.ui.control.Label
        OldpasswordEditField       matlab.ui.control.EditField
        OldpasswordEditFieldLabel  matlab.ui.control.Label
        UserEditField              matlab.ui.control.EditField
        UserEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        connection % DJ connection object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetpasswordButton
        function ResetpasswordButtonPushed(app, event)
            currentUser = get(app.UserEditField, 'value');
            old_pwd = get(app.OldpasswordEditField, 'value');
            new_pwd = get(app.NewpasswordEditField, 'value');
            app.connection = struct;
            if isdeployed
                try
                    app.connection = dj.conn('vfsmdatajoint01.fsm.northwestern.edu', currentUser, old_pwd, [], true);
                catch 
                    app.connection.isConnected = false;
                end
                
            else
                try
                    app.connection = dj.conn('vfsmdatajoint01.fsm.northwestern.edu', currentUser, old_pwd, [], true);
                catch                    
                    app.connection.isConnected = false;
                end
            end
            
            if app.connection.isConnected
                try
                    if isdeployed
                        query_str = sprintf('ALTER USER "%s"@"165.124.123.179" IDENTIFIED BY ''%s''',...
                        currentUser, new_pwd); %TEMP windows server IP
                    else
                        query_str = sprintf('ALTER USER "%s"@"10.120.14.%%" IDENTIFIED BY ''%s''',...
                        currentUser, new_pwd);
                    end
                    app.connection.query(query_str);
                    set(app.Lamp_status,'color','green');
                    set(app.StatusEditField,'value','Password reset successful.');
                catch 
                    set(app.Lamp_status,'color','red');
                    set(app.StatusEditField,'value','Password reset failed. Check permissions.');
                end
            else
                set(app.Lamp_status,'color','red');
                set(app.StatusEditField,'value','Connection with old password failed.');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.connection.close;
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UserEditFieldLabel = uilabel(app.UIFigure);
            app.UserEditFieldLabel.Text = 'User';
            app.UserEditField = uieditfield(app.UIFigure, 'text');
            app.OldpasswordEditFieldLabel = uilabel(app.UIFigure);
            app.OldpasswordEditFieldLabel.Text = 'Old password';
            app.OldpasswordEditField = uieditfield(app.UIFigure, 'text');
            app.NewpasswordEditFieldLabel = uilabel(app.UIFigure);
            app.NewpasswordEditFieldLabel.Text = 'New password';
            app.NewpasswordEditField = uieditfield(app.UIFigure, 'text');
            app.Lamp_status = uilamp(app.UIFigure);
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.StatusEditField.Editable = 'off';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.ResetpasswordButton = uibutton(app.UIFigure, 'push');
            app.ResetpasswordButton.ButtonPushedFcn = createCallbackFcn(app, @ResetpasswordButtonPushed, true);
            app.ResetpasswordButton.Text = 'Reset password';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResetPassword

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end