classdef SaveQuery < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SavequeryindatabaseLabel    matlab.ui.control.Label
        CancelButton                matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        NameEditField               matlab.ui.control.EditField
        NameEditFieldLabel          matlab.ui.control.Label
        ProjectqueriesListBox       matlab.ui.control.ListBox
        ProjectqueriesListBoxLabel  matlab.ui.control.Label
        YourqueriesListBox          matlab.ui.control.ListBox
        YourqueriesListBoxLabel     matlab.ui.control.Label
        ProjectDropDown             matlab.ui.control.DropDown
        ProjectDropDownLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        user % connected user
        query % the query passed in
    end
    
    methods (Access = private)
        
        function [] = updateUserQueries(app)
            query_names = fetchn(sln_lab.Query & ...
                sprintf('user_name = "%s"', app.user), 'query_name');

            app.YourqueriesListBox.Items = query_names;
        end
        
        function updateProjectQueries(app)
            selected_project = app.ProjectDropDown.Value;

            query_names = fetchn(sln_lab.Query & ...
                sprintf('project_name = "%s"', selected_project), 'query_name');

            app.ProjectqueriesListBox.Items = query_names;            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, q)
            C = dj.conn;
            app.user = C.user;
            app.query = q;

            projects = fetchn(sln_lab.ProjectActive,'project_name');
            app.ProjectDropDown.Items = [' '; projects];
            app.ProjectDropDown.Value = ' ';

            app.updateUserQueries();

        end

        % Value changed function: ProjectqueriesListBox
        function ProjectqueriesListBoxValueChanged(app, event)
            value = app.ProjectqueriesListBox.Value;
            app.NameEditField.Value = value;
        end

        % Value changed function: YourqueriesListBox
        function YourqueriesListBoxValueChanged(app, event)
            value = app.YourqueriesListBox.Value;
            app.NameEditField.Value = value;
        end

        % Value changed function: ProjectDropDown
        function ProjectDropDownValueChanged(app, event)
            app.updateProjectQueries();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            key.query_name = app.NameEditField.Value;
            key.user_name = dj.conn().user;
            project = app.ProjectDropDown.Value;
            if exists(sln_lab.Query & key)
                choice = questdlg(sprintf('Replace query %s?', key.query_name), ...
                  'Confirmation', ...
                  'OK', 'Cancel', 'OK');
                if strcmp(choice,'OK')
                    delQuick(sln_lab.Query & key)
                    q_str = app.query.sql;
                    key.sql_query = q_str;
                    if ~strcmp(project, ' ')
                        key.project_name = project;
                    end
                    insert(sln_lab.Query, key);
                end
            else
                q_str = app.query.sql;
                key.sql_query = q_str;
                if ~strcmp(project, ' ')
                    key.project_name = project;
                end
                insert(sln_lab.Query, key);
            end

            app.updateUserQueries();
            app.updateProjectQueries();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProjectDropDownLabel = uilabel(app.UIFigure);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.UIFigure);
            app.ProjectDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectDropDownValueChanged, true);
            app.YourqueriesListBoxLabel = uilabel(app.UIFigure);
            app.YourqueriesListBoxLabel.Text = 'Your queries';
            app.YourqueriesListBox = uilistbox(app.UIFigure);
            app.YourqueriesListBox.Items = {};
            app.YourqueriesListBox.ValueChangedFcn = createCallbackFcn(app, @YourqueriesListBoxValueChanged, true);
            app.YourqueriesListBox.Value = {};
            app.ProjectqueriesListBoxLabel = uilabel(app.UIFigure);
            app.ProjectqueriesListBoxLabel.Text = 'Project queries';
            app.ProjectqueriesListBox = uilistbox(app.UIFigure);
            app.ProjectqueriesListBox.Items = {};
            app.ProjectqueriesListBox.ValueChangedFcn = createCallbackFcn(app, @ProjectqueriesListBoxValueChanged, true);
            app.ProjectqueriesListBox.Value = {};
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SavequeryindatabaseLabel = uilabel(app.UIFigure);
            app.SavequeryindatabaseLabel.Text = 'Save query in database';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaveQuery(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end