classdef SpikeDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ReclusterspikesButton           matlab.ui.control.Button
        SplitafterselectedepochButton   matlab.ui.control.Button
        ClustereventsButton             matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        RigSwitch                       matlab.ui.control.Switch
        RigSwitchLabel                  matlab.ui.control.Label
        PushtodatabaseButton            matlab.ui.control.Button
        LoadsettingsfromfileButton      matlab.ui.control.Button
        SavesettingstofileButton        matlab.ui.control.Button
        PrinCompY                       matlab.ui.control.NumericEditField
        PrinCompX                       matlab.ui.control.NumericEditField
        AddfeatureLabel                 matlab.ui.control.Label
        fromanonymousfunctionButton     matlab.ui.control.Button
        frommfileButton                 matlab.ui.control.Button
        RunonSwitch                     matlab.ui.control.Switch
        RunonSwitchLabel                matlab.ui.control.Label
        ResetinclusionlistButton        matlab.ui.control.Button
        ResetexclusionlistButton        matlab.ui.control.Button
        YaxisDropDown                   matlab.ui.control.DropDown
        YaxisDropDownLabel              matlab.ui.control.Label
        XaxisDropDown                   matlab.ui.control.DropDown
        XaxisDropDownLabel              matlab.ui.control.Label
        DrawinclusionzoneButton         matlab.ui.control.Button
        KeepincludedpointsSwitch_2      matlab.ui.control.Switch
        KeepincludedpointsSwitch_2Label  matlab.ui.control.Label
        KeepincludedpointsSwitch        matlab.ui.control.Switch
        KeepincludedpointsSwitchLabel   matlab.ui.control.Label
        KeepexcludedpointsSwitch_2      matlab.ui.control.Switch
        KeepexcludedpointsSwitch_2Label  matlab.ui.control.Label
        KeepexcludedpointsSwitch        matlab.ui.control.Switch
        KeepexcludedpointsSwitchLabel   matlab.ui.control.Label
        DrawexclusionzoneButton         matlab.ui.control.Button
        ClusteringsettingsLabel         matlab.ui.control.Label
        PCAsettingsLabel                matlab.ui.control.Label
        ThresholdsettingsLabel          matlab.ui.control.Label
        FiltersettingsLabel             matlab.ui.control.Label
        ThresholdonSwitch               matlab.ui.control.Switch
        ThresholdonSwitchLabel          matlab.ui.control.Label
        MinimumpointswithinepsilonEditField  matlab.ui.control.NumericEditField
        MinimumpointswithinepsilonEditFieldLabel  matlab.ui.control.Label
        EpsilonEditField                matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel           matlab.ui.control.Label
        NumberofPCstouseEditField       matlab.ui.control.NumericEditField
        NumberofPCstouseEditFieldLabel  matlab.ui.control.Label
        MaximumalignmentoffsetmsEditField  matlab.ui.control.NumericEditField
        MaximumalignmentoffsetmsEditFieldLabel  matlab.ui.control.Label
        EventdurationmsEditField        matlab.ui.control.NumericEditField
        EventdurationmsEditFieldLabel   matlab.ui.control.Label
        RefractoryperiodmsEditField     matlab.ui.control.NumericEditField
        RefractoryperiodmsEditFieldLabel  matlab.ui.control.Label
        CurrentfilterspikeFiltermatLabel  matlab.ui.control.Label
        SelectnewfilterButton           matlab.ui.control.Button
        MergeepochsButton               matlab.ui.control.Button
        SplitbeforeselectedepochButton  matlab.ui.control.Button
        EpochsetListBox                 matlab.ui.control.ListBox
        EpochsetListBoxLabel            matlab.ui.control.Label
        DisplaySwitch                   matlab.ui.control.Switch
        DisplaySwitchLabel              matlab.ui.control.Label
        EventthresholdpAEditField       matlab.ui.control.NumericEditField
        EventthresholdpAEditFieldLabel  matlab.ui.control.Label
        CellListBox                     matlab.ui.control.ListBox
        CellListBoxLabel                matlab.ui.control.Label
        ExperimentDateDatePicker        matlab.ui.control.DatePicker
        ExperimentDateDatePickerLabel   matlab.ui.control.Label
        waveform_axes                   matlab.ui.control.UIAxes
        feature_axes                    matlab.ui.control.UIAxes
        epoch_axes                      matlab.ui.control.UIAxes
        dataset_axes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        experiment
        cell_data % Description
        selectedEpoch % Description
        selectedCell
        selectedEpochSet
        epoch_sets % Description
        filters % Description
        filter_path % Description
        epoch_trace % Description
        epoch_indicator % Description
        dataset_markers % Description
        threshold_trace % Description
        event_indices = {};
        event_offsets % Description
        event_counts % Description
        event_waveforms % Description
        waveform_traces % Description
        feature_names
        prin_comps
        features % Description
        cluster_index % Description
        event_markers
        spike_indices % Description
        is_spike
        F % futures
        state

    end

    methods (Access = private)

        function updateExperiment(app)
            
            %% fetch info on the cells in this experiment
            app.experiment = sprintf('%s%s',datestr(app.ExperimentDateDatePicker.Value, 'mmddyy'), app.RigSwitch.Value);
            g = aggr(dj.U('file_name','source_id'), sln_symphony.DatasetEpoch & sprintf('file_name="%s"', app.experiment), 'count(*)->n_epochs', 'count(distinct dataset_name)->n_datasets');
            h = (dj.U('source_type') * sln_symphony.ExperimentCell().proj('"Cell" -> source_type')) | (dj.U('source_type') * sln_symphony.ExperimentCellPair().proj('"Pair" -> source_type'));
            
            app.cell_data = fetch(g * h, '*');
            if isempty(app.cell_data)
                errordlg('No datasets for selected experiment date.')
                % TODO: clear things?
                return
            end

            %% update the cell and epoch set listviews
            app.CellListBox.Items = arrayfun(@(x) sprintf('Source %d (%s)',x.source_id,x.source_type), app.cell_data,'uni',0);
            app.CellListBox.Value = app.CellListBox.Items{1};
            app.EpochsetListBox.Items = {'1 (0 events, 0 spikes)'};
            app.EpochsetListBox.Value = '1 (0 events, 0 spikes)';

            app.selectedCell = 1;
            app.updateCell();

        end

        function updateCell(app)

            app.selectedEpochSet = 1;
            app.selectedEpoch = 1;
            app.clearClusters();

            %% update selected cell
            rd = (sln_symphony.ExperimentEpoch & app.cell_data(app.selectedCell)) * sln_symphony.DatasetEpoch * ...
                sln_symphony.ExperimentEpochChannel * sln_symphony.ExperimentChannel * ...
                (sln_symphony.ExperimentElectrode & 'recording_mode="Current clamp"' & 'amp_mode="Cell attached"');
            dlg = uiprogressdlg(app.UIFigure,'message',sprintf('Loading %d epochs from %d datasets...', app.cell_data(app.selectedCell).n_epochs, app.cell_data(app.selectedCell).n_datasets));
            tic;
            app.epoch_sets = struct();
            app.epoch_sets(app.selectedEpochSet).epoch_data = fetch(rd,'*');
            app.epoch_sets(app.selectedEpochSet).features = [];
            app.epoch_sets(app.selectedEpochSet).prin_comps = [];
            app.epoch_sets(app.selectedEpochSet).cluster_index = [];
            app.epoch_sets(app.selectedEpochSet).state.showRaw = strcmp(app.DisplaySwitch.Value,'Raw');
            app.epoch_sets(app.selectedEpochSet).state.thresholdRaw = strcmp(app.ThresholdonSwitch.Value,'Raw');
            app.epoch_sets(app.selectedEpochSet).state.threshold = app.EventthresholdpAEditField.Value;
            app.epoch_sets(app.selectedEpochSet).state.duration = app.EventdurationmsEditField.Value/1000;
            app.epoch_sets(app.selectedEpochSet).state.refractory = app.RefractoryperiodmsEditField.Value/1000;            
            
            t1=toc;
            dlg.Message = sprintf('Loaded %d epochs from %d datasets in %0.2f seconds.', app.cell_data(app.selectedCell).n_epochs, app.cell_data(app.selectedCell).n_datasets,t1);
            
            
            % app.epoch_sets(app.selectedEpochSet).epoch_data = app.all_data(([app.all_data.source_id] == app.cell_data(1).source_id));
            %% update the dataset view
            app.drawDatasets();

            %% filter the data
            dlg.Message = sprintf('Loaded %d epochs from %d datasets in %0.2f seconds. \nFiltering...', app.cell_data(app.selectedCell).n_epochs, app.cell_data(app.selectedCell).n_datasets,t1);
            tic;
            app.filterData();
            t2=toc;
            dlg.Message = sprintf('Loaded %d epochs from %d datasets in %0.2f seconds. \nFiltered data in %0.2f seconds.', app.cell_data(app.selectedCell).n_epochs, app.cell_data(app.selectedCell).n_datasets,t1,t2);
            
            %%
            
            %% set up remaining work to run in background pool 
            app.applyThreshold();
            close(dlg);
        end

        function drawDatasets(app)
            %% fetch info on datasets for this cell
            % cd = app.cell_data(strcmp(app.CellListBox.Items, app.CellListBox.Value));
            % ed = (sln_symphony.DatasetEpoch & cd) * sln_symphony.ExperimentElectrode & 'recording_mode="Current clamp"' & 'amp_mode="Cell attached"';
            % ed = aggr(dj.U('dataset_name'), ed * sln_symphony.ExperimentEpoch, 'min(epoch_start_time)->start_time', 'max(epoch_start_time + epoch_duration)->end_time');
            % f = fetch(ed, '*');

            %% update plot
            %             cla(app.dataset_axes);
            [ds_name,~,dsi] = unique({app.epoch_sets(app.selectedEpochSet).epoch_data(:).dataset_name});

            delete(app.dataset_markers);
            app.dataset_markers = scatter(app.dataset_axes,...
                [app.epoch_sets(app.selectedEpochSet).epoch_data(:).epoch_start_time]/3600000, dsi,...
                30,'k','filled');

            %better would be to draw all the epochs when cell data changes
            % then here we only highlight the epochs in the selected
            % dataset
            
            yticks(app.dataset_axes, 1:length(ds_name));
            yticklabels(app.dataset_axes, ds_name);
            ylim(app.dataset_axes,[0.5, length(ds_name)+0.5]);
            set(app.dataset_axes,'TickLabelInterpreter','none')
        end

        function drawEpoch(app)
            %% update epoch axes
            if app.epoch_sets(app.selectedEpochSet).state.showRaw
                d = app.epoch_sets(app.selectedEpochSet).epoch_data(app.selectedEpoch).raw_data;
            else
                d = app.epoch_sets(app.selectedEpochSet).epoch_data(app.selectedEpoch).filt_data;
            end

            set(app.epoch_trace, 'xdata', 1:length(d), 'ydata', d); %if view raw...
            set(app.threshold_trace, 'visible', (app.epoch_sets(app.selectedEpochSet).state.showRaw == app.epoch_sets(app.selectedEpochSet).state.thresholdRaw));
            set(app.threshold_trace, 'value', app.epoch_sets(app.selectedEpochSet).state.threshold);     
            
            xlim(app.epoch_axes, [1, length(d)]); %TODO: time in seconds...

            cd = zeros(numel(app.epoch_sets(app.selectedEpochSet).is_spike{app.selectedEpoch}),3);
            cd(app.epoch_sets(app.selectedEpochSet).is_spike{app.selectedEpoch},2) = 1;
            cd(~app.epoch_sets(app.selectedEpochSet).is_spike{app.selectedEpoch},:) = .8;
            set(app.event_markers, 'xdata', app.epoch_sets(app.selectedEpochSet).event_indices{app.selectedEpoch},...
                'ydata', 6+3*app.epoch_sets(app.selectedEpochSet).is_spike{app.selectedEpoch},...
                'cdata', cd);


            %% update dataset axes
            set(app.epoch_indicator, 'value', (app.epoch_sets(app.selectedEpochSet).epoch_data(app.selectedEpoch).epoch_start_time) / 3600000, 'visible', true);

            %% update waveform axes

            app.drawWaveforms();

            % ei = cumsum(app.event_counts);
            % ei = ei(app.selectedEpoch) - app.event_counts(app.selectedEpoch)+1 : ei(app.selectedEpoch);
            % 
            % updating just the color doesn't work, since the plotting
            % order will obscur the highlighted traces
            %  set(app.waveform_traces,'color','#cccccc');
            %  set(app.waveform_traces(ei),'color','k');
            % 
            % updating the visibility is fast, but what if we want to see
            % all the traces?
            % set(app.waveform_traces,'visible',false);
            % set(app.waveform_traces(ei),'visible',true);
            % 
            % updating the plot order works in combination with the color
            % update, but is very slow
            %  set(app.waveform_axes,'children',app.waveform_traces([ei, 1:ei(1)-1, ei(end)+1:end]));
            %  uistack(app.waveform_traces(ei), 'top');
            % uistack might be supported as of 2023a, but is it faster?
            % 
            % set(app.spike_markers, 'xdata', app.epoch_sets(app.selectedEpochSet).spike_indices{app.selectedEpoch},'ydata', 1.1*max(d)*ones(size(app.epoch_sets(app.selectedEpochSet).spike_indices{app.selectedEpoch})))
            % c = app.epoch_sets(app.selectedEpochSet).is_spike(app.event_counts(app.selectedEpoch)+1:app.event_counts(app.SelectedEpoch));
            % 
            % 
            % ad = zeros(1, numel(app.epoch_sets(app.selectedEpochSet).is_spike));
            % ad(ei) = 1;
            % set(app.event_markers,'alphadata',ad);

        end

        function filterData(app)
            warning('off','signal:filtfilt:ParseSOS');
            for i = 1:length(app.epoch_sets(app.selectedEpochSet).epoch_data)
                app.filterEpoch(i);
            end            
            warning('on','signal:filtfilt:ParseSOS');
        end

        function filterEpoch(app,i)
            %% filter
            % tmp = filtfilt(app.filters(app.epoch_sets(app.selectedEpochSet).epoch_data(i).sample_rate),app.epoch_sets(app.selectedEpochSet).epoch_data(i).raw_data);
            tmp = filtfilt(app.filters(app.epoch_sets(app.selectedEpochSet).epoch_data(i).sample_rate).sosMatrix,app.filters(app.epoch_sets(app.selectedEpochSet).epoch_data(i).sample_rate).ScaleValues,app.epoch_sets(app.selectedEpochSet).epoch_data(i).raw_data);
            app.epoch_sets(app.selectedEpochSet).epoch_data(i).filt_data = tmp / mad(tmp,1);

            %also convert raw data to mad's
            app.epoch_sets(app.selectedEpochSet).epoch_data(i).raw_data = app.epoch_sets(app.selectedEpochSet).epoch_data(i).raw_data - median(app.epoch_sets(app.selectedEpochSet).epoch_data(i).raw_data);
            app.epoch_sets(app.selectedEpochSet).epoch_data(i).raw_data = app.epoch_sets(app.selectedEpochSet).epoch_data(i).raw_data / mad(app.epoch_sets(app.selectedEpochSet).epoch_data(i).raw_data, 1);
        
        end

        % function detectAndAlignEvents(app)
        %     c = cell(size(app.epoch_sets(app.selectedEpochSet).epoch_data));
        %     app.epoch_sets(app.selectedEpochSet).event_indices = cell(size(app.epoch_sets(app.selectedEpochSet).epoch_data));
        %     app.epoch_sets(app.selectedEpochSet).spike_indices = cell(size(app.epoch_sets(app.selectedEpochSet).epoch_data));
        %     if strcmp(app.ThresholdonSwitch,'Raw')
        %         t = 'raw_data';
        %     else
        %         t = 'filt_data';
        %     end
        %     rf = app.RefractoryperiodmsEditField.Value/1000;
        %     w100k = app.EventdurationmsEditField.Value*100;
        % 
        %     for i =1:length(app.epoch_sets(app.selectedEpochSet).epoch_data)
        %         [~,app.epoch_sets(app.selectedEpochSet).event_indices{i}] = findpeaks(sign(app.epoch_sets(app.selectedEpochSet).state.threshold) * app.epoch_sets(app.selectedEpochSet).epoch_data(i).(t),  'minpeakheight', abs(app.epoch_sets(app.selectedEpochSet).state.threshold), 'minpeakdistance', rf * app.epoch_sets(app.selectedEpochSet).epoch_data(i).sample_rate);
        %         if isempty(app.epoch_sets(app.selectedEpochSet).event_indices{i})
        %             continue
        %         end
        % 
        %         w = ceil((app.EventdurationmsEditField.Value/1000*app.epoch_sets(app.selectedEpochSet).epoch_data(i).sample_rate)/2); %window
        % 
        %         ii = app.epoch_sets(app.selectedEpochSet).event_indices{i} + ((-w+1):w)'; %indices of data in windows around events
        %         ii(ii<1) = 1;
        %         ii(ii>length(app.epoch_sets(app.selectedEpochSet).epoch_data(i).(t))) = length(app.epoch_sets(app.selectedEpochSet).epoch_data(i).(t));
        % 
        %         c{i} = reshape(resample(app.epoch_sets(app.selectedEpochSet).epoch_data(i).(t)(ii), 100000, app.epoch_sets(app.selectedEpochSet).epoch_data(i).sample_rate), w100k,[])'; %resample locally to 100kHz
        %     end
        %     app.epoch_sets(app.selectedEpochSet).event_waveforms =cell2mat(c)';
        % 
        %     n = size(app.epoch_sets(app.selectedEpochSet).event_waveforms,2);
        %     k = randperm(n,min(n,1000));
        %     cc = corr(app.epoch_sets(app.selectedEpochSet).event_waveforms(:,k));
        %     [~,i] = max(sum(cc,1)); % the index of the most 'central' spike
        % 
        %     max_o = app.MaximumalignmentoffsetmsEditField.Value * 100;
        %     r = cell2mat(arrayfun(@(x) xcorr(app.epoch_sets(app.selectedEpochSet).event_waveforms(:,k(i)), app.epoch_sets(app.selectedEpochSet).event_waveforms(:,x), max_o), 1:size(app.epoch_sets(app.selectedEpochSet).event_waveforms,2), 'uni', 0));
        % 
        %     [~,i] = max(r,[],1); %for each event, the best offset to align to the central one
        % 
        %     app.event_offsets = i - max_o + 1;
        %     app.event_counts = cellfun(@length,app.epoch_sets(app.selectedEpochSet).event_indices);
        %     eo = mat2cell(app.event_offsets, 1, app.event_counts)';
        %     app.epoch_sets(app.selectedEpochSet).event_indices = cellfun(@(x,y,z) x + round(y/100000*z), app.epoch_sets(app.selectedEpochSet).event_indices, eo, {app.epoch_sets(app.selectedEpochSet).epoch_data(:).sample_rate}', 'uni', 0);
        %     app.epoch_sets(app.selectedEpochSet).is_spike = false(sum(app.event_counts),1);
        %     app.epoch_sets(app.selectedEpochSet).event_waveforms = cell2mat(arrayfun(@(x) circshift(app.epoch_sets(app.selectedEpochSet).event_waveforms(:,x),app.event_offsets(x)), 1:size(app.epoch_sets(app.selectedEpochSet).event_waveforms,2), 'uni', 0));
        % 
        % 
        %     set(app.event_markers, 'xdata', horzcat(app.epoch_sets(app.selectedEpochSet).event_indices{:}), 'ydata', -6*app.epoch_sets(app.selectedEpochSet).state.threshold*ones(size(app.epoch_sets(app.selectedEpochSet).is_spike)));
        % end

        function drawWaveforms(app)
            %plot all the waveforms in gray...
            delete(app.waveform_traces);
            app.waveform_traces = plot(app.waveform_axes, app.epoch_sets(app.selectedEpochSet).event_waveforms{app.selectedEpoch}, 'color', '#cccccc');


            % set(app.waveform_traces(~app.epoch_sets(app.selectedEpochSet).is_spike{app.selectedEpoch}),'color', '#cccccc');
            set(app.waveform_traces(app.epoch_sets(app.selectedEpochSet).is_spike{app.selectedEpoch}),'color', 'g');
        end
        
        function applyThreshold(app)
            if isempty(app.epoch_sets(app.selectedEpochSet).epoch_data)
                return
            end
            app.clearClusters();
   
            app.epoch_sets(app.selectedEpochSet).spike_indices = cell(size(app.epoch_sets(app.selectedEpochSet).epoch_data));
            app.epoch_sets(app.selectedEpochSet).event_indices = cell(size(app.epoch_sets(app.selectedEpochSet).epoch_data));
            app.epoch_sets(app.selectedEpochSet).event_waveforms = cell(size(app.epoch_sets(app.selectedEpochSet).epoch_data));
            app.epoch_sets(app.selectedEpochSet).is_spike = cell(size(app.epoch_sets(app.selectedEpochSet).epoch_data));

            
            window = ceil((app.epoch_sets(app.selectedEpochSet).state.duration*app.epoch_sets(app.selectedEpochSet).epoch_data(app.selectedEpoch).sample_rate)/2); %window

            if app.epoch_sets(app.selectedEpochSet).state.thresholdRaw
                [indices,waveforms] = detectEvents(...
                    app.epoch_sets(app.selectedEpochSet).epoch_data(app.selectedEpoch).raw_data, app.epoch_sets(app.selectedEpochSet).epoch_data(app.selectedEpoch).sample_rate, ...
                    app.epoch_sets(app.selectedEpochSet).state.threshold, app.epoch_sets(app.selectedEpochSet).state.refractory, window, 100000);
            else
                [indices,waveforms] = detectEvents(...
                    app.epoch_sets(app.selectedEpochSet).epoch_data(app.selectedEpoch).filt_data, app.epoch_sets(app.selectedEpochSet).epoch_data(app.selectedEpoch).sample_rate, ...
                    app.epoch_sets(app.selectedEpochSet).state.threshold, app.epoch_sets(app.selectedEpochSet).state.refractory, window, 100000);
            end
            app.updateEpoch(indices, waveforms, app.selectedEpoch)

            app.drawEpoch();

            app.F = parallel.FevalFuture.empty;
            app.startBackground();
        end
        
        function doClustering(app)
            % TODO: check for remaining futures and hang until processing
            % is done...
            app.clearClusters();

            % waveforms = cell2mat(app.epoch_sets(app.selectedEpochSet).event_waveforms');
            N = sum(cellfun(@(x) size(x,2), app.epoch_sets(app.selectedEpochSet).event_waveforms));
            dlg = uiprogressdlg(app.UIFigure,'message',sprintf('Aligning %d events...', N),'indeterminate','on');
            tic;
            emp = cellfun(@isempty, app.epoch_sets(app.selectedEpochSet).event_waveforms);
            waveforms = alignEvents(cell2mat(cellfun(@(x,r) resample(x,100000, r), app.epoch_sets(app.selectedEpochSet).event_waveforms(~emp), {app.epoch_sets(app.selectedEpochSet).epoch_data(~emp).sample_rate}','uni',0)'));
            t1 = toc;
            dlg.Message = sprintf('Aligned %d events in %0.2f seconds. \nExtracting features...', N, t1);
            tic;
            app.epoch_sets(app.selectedEpochSet).prin_comps = pca(waveforms);
            
            %% peak-to-peak current and time
            [a,i] = min(waveforms,[],1);
            [b,j] = max(waveforms,[],1);
            ppi = b-a;
            ppt = j-i;           
            
            %% other features
            med = median(waveforms,1); %median of waveform
            tmp = waveforms > (med - .5*(med-a));
            fwhm = arrayfun(@(x) i(x) + find(tmp(i(x):end,x),1,'first') - find(tmp(1:i(x),x),1,'last'), 1:numel(med),'uni',0);
            fwhm(cellfun(@isempty,fwhm)) = deal({0});
            fwhm = cell2mat(fwhm); %full width at half max
            
            %wall time of epoch
            wt = cell2mat(arrayfun(@(x,y,z) x+y*cell2mat(z), [app.epoch_sets(app.selectedEpochSet).epoch_data(:).epoch_start_time]/1000, 1./[app.epoch_sets(app.selectedEpochSet).epoch_data(:).sample_rate], app.epoch_sets(app.selectedEpochSet).event_indices', 'UniformOutput', false));
            
            d = abs(diff(waveforms,1,1));
            [maxd,i] = max(d,[],1); %max slope
            tmp = d<.2*maxd; 
            th = arrayfun(@(x) find(tmp(1:i(x),x),1,'last'), 1:numel(maxd),'uni',0); %time when slope passes 20% of max
            th(cellfun(@isempty,th)) = deal({0});
            th = cell2mat(th);
            
            %500-1500Hz content
            f = abs(fft(waveforms));
            e500_1500 = sum(f(2:4,:),1) ./ sum(f,1);
            
            %% TODO: features from feature list
            
            app.epoch_sets(app.selectedEpochSet).features = vertcat(ppi,ppt,med,fwhm,wt,maxd,th,e500_1500, a, b)';

            t2 = toc;
            dlg.Message = sprintf('Aligned %d events in %0.2f seconds. \nExtracted features in %0.2f seconds. \nClustering...', N, t1,t2);
            
            %% clustering
            ft = zscore(app.epoch_sets(app.selectedEpochSet).features,[],1);
            % app.epoch_sets(app.selectedEpochSet).cluster_index = dbscan(horzcat(ft,app.epoch_sets(app.selectedEpochSet).prin_comps(:,1:app.NumberofPCstouseEditField.Value)),app.EpsilonEditField.Value,app.MinimumpointswithinepsilonEditField.Value);
            app.epoch_sets(app.selectedEpochSet).cluster_index = kmeans(horzcat(app.epoch_sets(app.selectedEpochSet).prin_comps(:,1:3), ft),20);

            app.drawClusters();
            close(dlg);
        end
        
        function drawClusters(app)
            [x,y] = app.getFeats();
            spike = cell2mat(app.epoch_sets(app.selectedEpochSet).is_spike')';
            not_spike = ~spike;
            hold(app.feature_axes,'off');
            scatter(app.feature_axes,x(not_spike),y(not_spike),20,app.epoch_sets(app.selectedEpochSet).cluster_index(not_spike),'+');
            hold(app.feature_axes,'on');
            scatter(app.feature_axes,x(spike),y(spike),20,app.epoch_sets(app.selectedEpochSet).cluster_index(spike),'o','filled');
            colormap(app.feature_axes, 'colorcube');
            xmin = min(x);
            xmax = max(x);
            ymin = min(y);
            ymax = max(y);
            xr = xmax - xmin;
            xlim(app.feature_axes,[xmin-xr*.1, xmax+xr*.1]);
            yr = ymax - ymin;
            ylim(app.feature_axes,[ymin-yr*.1, ymax+yr*.1]);            
        end

        function clearClusters(app)
            cla(app.feature_axes);
            if isempty(app.epoch_sets(app.selectedEpochSet).features)
                return
            end
            app.epoch_sets(app.selectedEpochSet).features = [];
            app.epoch_sets(app.selectedEpochSet).spike_indices = cell(size(app.epoch_sets(app.selectedEpochSet).epoch_data));
            app.epoch_sets(app.selectedEpochSet).prin_comps = [];
            app.epoch_sets(app.selectedEpochSet).cluster_index = [];            
            % app.epoch_sets(app.selectedEpochSet).is_spike = cellfun(@(x) zeros(size(x),'logical'), app.epoch_sets(app.selectedEpochSet).event_indices,'uni',0);
            app.epoch_sets(app.selectedEpochSet).is_spike = cellfun(@(x) false(size(x)), app.epoch_sets(app.selectedEpochSet).event_indices,'uni',0);
            
            app.updateSpikeIndices();
        end
        
        function [x,y] = getFeats(app)
            xind = find(strcmp(app.feature_names, app.XaxisDropDown.Value)) - 2;
            yind = find(strcmp(app.feature_names, app.YaxisDropDown.Value)) - 2;
            
            if xind == -1
                x = app.epoch_sets(app.selectedEpochSet).prin_comps(:,app.PrinCompX.Value);
            elseif xind == 0
                x = app.epoch_sets(app.selectedEpochSet).cluster_index;
            else
                x = app.epoch_sets(app.selectedEpochSet).features(:,xind);
            end
            
            if yind == -1
                y = app.epoch_sets(app.selectedEpochSet).prin_comps(:,app.PrinCompY.Value);
            elseif yind == 0
                y = app.epoch_sets(app.selectedEpochSet).cluster_index;
            else
                y = app.epoch_sets(app.selectedEpochSet).features(:,yind);
            end
        end
    
        function updateSpikeIndices(app)
            % (1) plot on the epoch axes
            app.drawEpoch();

            n_events = sum(cellfun(@length, app.epoch_sets(app.selectedEpochSet).event_indices));
            n_spikes = sum(cellfun(@length, app.epoch_sets(app.selectedEpochSet).spike_indices));
            

            app.EpochsetListBox.Items{app.selectedEpochSet} = sprintf('%d (%d events, %d spikes)', app.selectedEpochSet, n_events, n_spikes);

        end

        function updateEpoch(app, indices, waveforms, i)
            app.epoch_sets(app.selectedEpochSet).event_indices{i} = indices;
            app.epoch_sets(app.selectedEpochSet).event_waveforms{i} = waveforms;
            app.epoch_sets(app.selectedEpochSet).is_spike{i} = zeros(size(indices),'logical');
        end
        
        function startBackground(app)
            epochs = setdiff(1:length(app.epoch_sets(app.selectedEpochSet).epoch_data),app.selectedEpoch);
            
            ii=0;
            for i = epochs
                ii = ii+1;
                window = ceil((app.epoch_sets(app.selectedEpochSet).state.duration*app.epoch_sets(app.selectedEpochSet).epoch_data(i).sample_rate)/2); %window

                if app.epoch_sets(app.selectedEpochSet).state.thresholdRaw
                    app.F(ii) = parfeval(backgroundPool, @detectEvents, 3,...
                        app.epoch_sets(app.selectedEpochSet).epoch_data(i).raw_data, app.epoch_sets(app.selectedEpochSet).epoch_data(i).sample_rate, ...
                        app.epoch_sets(app.selectedEpochSet).state.threshold, app.epoch_sets(app.selectedEpochSet).state.refractory, window, 100000, i);
                else
                    app.F(ii) = parfeval(backgroundPool, @detectEvents, 3,...
                        app.epoch_sets(app.selectedEpochSet).epoch_data(i).filt_data, app.epoch_sets(app.selectedEpochSet).epoch_data(i).sample_rate, ...
                        app.epoch_sets(app.selectedEpochSet).state.threshold, app.epoch_sets(app.selectedEpochSet).state.refractory, window, 100000, i);
                end
            end
              %
            afterEach(app.F, @app.updateEpoch, 0);
            afterAll(app.F,@app.endBackground, 0);
        end
        
        function endBackground(app)
            n_events = sum(cellfun(@length, app.epoch_sets(app.selectedEpochSet).event_indices));
            
            app.EpochsetListBox.Items{app.selectedEpochSet} = sprintf('%d (%d events, 0 spikes)', app.selectedEpochSet, n_events);
        end

        function setSelectedEpoch(app, i)
            app.selectedEpoch = i;
            % TODO: cancel any futures for this epoch? and run in foreground
            % or just hang on this future...
            app.drawEpoch();
        end
        
        function updateEpochSetListView(app)
            app.EpochsetListBox.Items = arrayfun(@(i,es) sprintf('%d (%d events, %d spikes)',i,...
                sum(cellfun(@length, es.event_indices)), sum(cellfun(@length, es.spike_indices))...
                ), (1:length(app.epoch_sets))', app.epoch_sets, 'uni', 0);
            app.EpochsetListBox.Value = app.EpochsetListBox.Items{app.selectedEpochSet};
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app)
            %% get state
            app.selectedEpochSet = 1;
            
            app.epoch_sets(app.selectedEpochSet).features = [];
            app.epoch_sets(app.selectedEpochSet).state = struct();
            app.epoch_sets(app.selectedEpochSet).state.showRaw = strcmp(app.DisplaySwitch.Value,'Raw');
            app.epoch_sets(app.selectedEpochSet).state.thresholdRaw = strcmp(app.ThresholdonSwitch.Value, 'Raw');
            app.epoch_sets(app.selectedEpochSet).state.threshold = app.EventthresholdpAEditField.Value;
            app.epoch_sets(app.selectedEpochSet).state.duration = app.EventdurationmsEditField.Value/1000;
            app.epoch_sets(app.selectedEpochSet).state.refractory = app.RefractoryperiodmsEditField.Value/1000;
            
            
            %% load spike detector(s)
            % app.filter_path = sprintf('%s%sutilities%sspikeFilter.mat',getenv('ANALYSIS_CODE_FOLDER'),filesep,filesep);
            % app.filters = containers.Map('KeyType','double','ValueType','any');
            % 
            % % allows 1 filter per sample rate
            % tmp = load(app.filter_path);
            % f = fieldnames(tmp);
            % for fi = 1:numel(f)
            %     app.filters(tmp.(f{fi}).SampleRate) = tmp.(f{fi});
            % end

            app.filter_path = sprintf('%s%sApps%sspikeFilter.mat',getenv('DJ_ROOT'),filesep,filesep);
            app.filters = containers.Map('KeyType','double','ValueType','any');

            % allows 1 filter per sample rate
            tmp = load(app.filter_path);
            f = fieldnames(tmp);
            for fi = 1:numel(f)
                app.filters(10000) = tmp.(f{fi});
            end
            
            %% initialize plots
            hold(app.epoch_axes, 'on');
            app.epoch_trace = plot(app.epoch_axes, 0, nan, 'k');
            app.epoch_indicator = xline(app.dataset_axes, 0,'visible',false,'color','m','linewidth',10);
            hold(app.dataset_axes,'on');
            app.threshold_trace = yline(app.epoch_axes,app.epoch_sets(app.selectedEpochSet).state.threshold, 'm--', 'visible', true);

            app.event_markers = scatter(app.epoch_axes, nan, nan, 30,'marker','v','markerfacecolor','none', 'markeredgecolor','flat');            
            set(app.event_markers,'cdata',[.8 .8 .8]);
            %% features...
%             app.epoch_sets(app.selectedEpochSet).features = vertcat(ppi,ppt,med,fwhm,wt,maxd,th,coeff(:,1:app.NumberofPCstouseEditField.Value)')';
            app.feature_names = {'Principal component', 'Cluster index', 'Peak-to-peak current', 'Peak-to-peak time', 'Median current', 'Full width at half maximum', 'Event time', 'Maximum slope', 'Threshold time','500Hz-1500Hz content',' Min value', 'Max value'};
            app.XaxisDropDown.Items = app.feature_names;
            app.YaxisDropDown.Items = app.feature_names;
            app.XaxisDropDown.Value = 'Full width at half maximum';
            app.YaxisDropDown.Value = 'Maximum slope';
            
        end

        % Key release function: UIFigure
        function keybinds(app, event)
            key = event.Key;
            switch key
                case 'rightarrow'
                    if app.selectedEpoch < length(app.epoch_sets(app.selectedEpochSet).epoch_data)
                        app.setSelectedEpoch(app.selectedEpoch + 1);
                    end
                case 'leftarrow'
                    if app.selectedEpoch > 1
                        app.setSelectedEpoch(app.selectedEpoch - 1);
                    end
            end
        end

        % Value changed function: DisplaySwitch
        function DisplaySwitchValueChanged(app, event)
            app.epoch_sets(app.selectedEpochSet).state.showRaw = strcmp(app.DisplaySwitch.Value,'Raw');
            app.drawEpoch();
        end

        % Value changed function: EventthresholdpAEditField
        function EventthresholdpAEditFieldValueChanged(app, event)
            app.epoch_sets(app.selectedEpochSet).state.threshold = app.EventthresholdpAEditField.Value;
            app.applyThreshold();
        end

        % Value changed function: ThresholdonSwitch
        function ThresholdonSwitchValueChanged(app, event)
            app.epoch_sets(app.selectedEpochSet).state.thresholdRaw = strcmp(app.ThresholdonSwitch.Value,'Raw');
            app.drawEpoch();
            app.applyThreshold();
        end

        % Button down function: dataset_axes
        function dataset_axesButtonDown(app, event)
            if event.Button == 1
                t = event.IntersectionPoint(1);
                [~,se] = min(abs(([app.epoch_sets(app.selectedEpochSet).epoch_data(:).epoch_start_time] + [app.epoch_sets(app.selectedEpochSet).epoch_data(:).epoch_duration])/3600000 - t));
                app.setSelectedEpoch(se);
            end
        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownValueChanged(app, event)
            value = app.XaxisDropDown.Value;
            if strcmp(value,'Principal component')
                app.PrinCompX.Visible = true;  
            else
                app.PrinCompX.Visible = false;   
            end
            app.drawClusters();
        end

        % Value changed function: YaxisDropDown
        function YaxisDropDownValueChanged(app, event)
            value = app.YaxisDropDown.Value;
            if strcmp(value,'Principal component')
                app.PrinCompY.Visible = true;    
            else
                app.PrinCompY.Visible = false;   
            end
            app.drawClusters();
        end

        % Value changed function: PrinCompX
        function PrinCompXValueChanged(app, event)
            app.drawClusters();
        end

        % Value changed function: PrinCompY
        function PrinCompYValueChanged(app, event)
            app.drawClusters();
        end

        % Value changed function: NumberofPCstouseEditField
        function NumberofPCstouseEditFieldValueChanged(app, event)
            app.doClustering();
        end

        % Value changed function: EpsilonEditField
        function EpsilonEditFieldValueChanged(app, event)
            app.doClustering();
        end

        % Value changed function: MinimumpointswithinepsilonEditField
        function MinimumpointswithinepsilonEditFieldValueChanged(app, event)
            app.doClustering();
        end

        % Button pushed function: DrawinclusionzoneButton
        function DrawinclusionzoneButtonPushed(app, event)
            %TODO: make the app non-interactable

            roi = drawpolygon(app.feature_axes,'color','g','interactionsallowed','none');
            %TODO: perhaps it would be nice to allow people to drag the roi
            % can do this with the addlistener method of the roi object...
            
            if isempty(roi.Position)
                return
            end

            [x,y] = app.getFeats();
            pts = inROI(roi,x,y);
            
            N = cellfun(@length,app.epoch_sets(app.selectedEpochSet).event_indices);
            
            ipts = mat2cell(pts', 1, N)';
            % ipts = mat2cell(pts,N);

            app.epoch_sets(app.selectedEpochSet).is_spike = cellfun(@(x,y) x|y, app.epoch_sets(app.selectedEpochSet).is_spike , ipts, 'uni', 0);
            app.epoch_sets(app.selectedEpochSet).spike_indices = cellfun(@(x,y,z) union(y(x), z), ipts, app.epoch_sets(app.selectedEpochSet).event_indices, app.epoch_sets(app.selectedEpochSet).spike_indices, 'uni', 0); %TODO: need multiple inclusions...
            

            app.updateSpikeIndices();
            %we need some visual indication when the spikes are included...
            % 1) count the number of spikes in the epoch set view?
            % 2) change feature_axes / epoch_axes markers to filled?

            app.drawClusters();
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if isnat(app.ExperimentDateDatePicker.Value)
                errordlg('No experiment date selected.')
                return
            end

            app.updateExperiment();
        end

        % Button pushed function: ClustereventsButton
        function ClustereventsButtonPushed(app, event)
            app.doClustering();
        end

        % Value changed function: CellListBox
        function CellListBoxValueChanged(app, event)
            app.selectedCell = find(strcmp(app.CellListBox.Value, app.CellListBox.Items));
            app.updateCell();
        end

        % Button pushed function: SplitafterselectedepochButton
        function SplitafterselectedepochButtonPushed(app, event)
            app.clearClusters();
            
            i = 1:app.selectedEpoch;
            splitA = struct(...
                'epoch_data',{app.epoch_sets(app.selectedEpochSet).epoch_data(i)},...
                'event_indices',{app.epoch_sets(app.selectedEpochSet).event_indices(i)},...
                'event_waveforms',{app.epoch_sets(app.selectedEpochSet).event_waveforms(i)},...
                'spike_indices',{app.epoch_sets(app.selectedEpochSet).spike_indices(i)},...                
                'is_spike',{app.epoch_sets(app.selectedEpochSet).is_spike(i)},...
                'state', {app.epoch_sets(app.selectedEpochSet).state},...
                'features',{[]},...
                'prin_comps',{[]},...
                'cluster_index',{[]}...                
                );

            i = app.selectedEpoch+1:length(app.epoch_sets(app.selectedEpochSet).epoch_data);
            splitB = struct(...
                'epoch_data',{app.epoch_sets(app.selectedEpochSet).epoch_data(i)},...
                'event_indices',{app.epoch_sets(app.selectedEpochSet).event_indices(i)},...
                'event_waveforms',{app.epoch_sets(app.selectedEpochSet).event_waveforms(i)},...
                'spike_indices',{app.epoch_sets(app.selectedEpochSet).spike_indices(i)},...                
                'is_spike',{app.epoch_sets(app.selectedEpochSet).is_spike(i)},...
                'state', {app.epoch_sets(app.selectedEpochSet).state},...
                'features',{[]},...
                'prin_comps',{[]},...
                'cluster_index',{[]}...                
                );

            app.epoch_sets = cat(1,reshape(app.epoch_sets(1:app.selectedEpochSet-1),[],1),...
                splitA,splitB,...
                reshape(app.epoch_sets(app.selectedEpochSet+1:end),[],1));
            
            % we either need to save state, or throw out results 
            % if we're saving state:
            %   thresholdOn, pcaOn, duration, [maxo], thresh, refract,
            %   nPCs, [epsilon], [min epsilon], *nclu*
            %  
            %    +pcs, features, cluster_index
            %   onSelectEpochSet: set state
            
            % honestly this seems easier since i think we need this anyway
            % for the epochset selection

            %but we should probably throw out cluster results when
            %splitting

            %redraw plots
            % 1) datasets -- need to update this plot. current epochs
            % should be highlighted
            % 2) clusters -- 
            app.drawDatasets();
            app.updateEpochSetListView();
        end

        % Button pushed function: SplitbeforeselectedepochButton
        function SplitbeforeselectedepochButtonPushed(app, event)
            app.clearClusters();

            i = 1:app.selectedEpoch-1;
            splitA = struct(...
                'epoch_data',{app.epoch_sets(app.selectedEpochSet).epoch_data(i)},...
                'event_indices',{app.epoch_sets(app.selectedEpochSet).event_indices(i)},...
                'event_waveforms',{app.epoch_sets(app.selectedEpochSet).event_waveforms(i)},...
                'spike_indices',{app.epoch_sets(app.selectedEpochSet).spike_indices(i)},...                
                'is_spike',{app.epoch_sets(app.selectedEpochSet).is_spike(i)},...
                'state', {app.epoch_sets(app.selectedEpochSet).state},...
                'features',{[]},...
                'prin_comps',{[]},...
                'cluster_index',{[]}...                
                );

            i = app.selectedEpoch:length(app.epoch_sets(app.selectedEpochSet).epoch_data);
            splitB = struct(...
                'epoch_data',{app.epoch_sets(app.selectedEpochSet).epoch_data(i)},...
                'event_indices',{app.epoch_sets(app.selectedEpochSet).event_indices(i)},...
                'event_waveforms',{app.epoch_sets(app.selectedEpochSet).event_waveforms(i)},...
                'spike_indices',{app.epoch_sets(app.selectedEpochSet).spike_indices(i)},...                
                'is_spike',{app.epoch_sets(app.selectedEpochSet).is_spike(i)},...
                'state', {app.epoch_sets(app.selectedEpochSet).state},...
                'features',{[]},...
                'prin_comps',{[]},...
                'cluster_index',{[]}...                
                );

            app.epoch_sets = cat(1,reshape(app.epoch_sets(1:app.selectedEpochSet-1),[],1),...
                splitA,splitB,...
                reshape(app.epoch_sets(app.selectedEpochSet+1:end),[],1));
            app.selectedEpoch = 1;
            app.selectedEpochSet = app.selectedEpochSet+1;

            %redraw plots?
            app.drawDatasets();
            app.updateEpochSetListView();
        end

        % Button pushed function: MergeepochsButton
        function MergeepochsButtonPushed(app, event)
            % get selected epoch sets --- allow multiselect? seems like a
            % headache
            %perhaps instead of this button, just allow multiselect?

            % newepochset = [epochset1, epochset2,...]
            %selectedepochset = newepochset
            %selectedepoch = find(selectedepochset == selectedepoch) ???

            %updates...
            %   foreach epochsetlistview %d (...,...)
            %   newepochset.listview = %d (%d events, %d spikes) \\ sum

            %  anything else?
        end

        % Button pushed function: PushtodatabaseButton
        function PushtodatabaseButtonPushed(app, event)
            %for epoch_Data
            key = cell2mat(arrayfun(@(es) struct(...
                'spike_indices',cellfun(@(s) s', es.spike_indices, 'uni',0),...
                'spike_count',cellfun(@length, es.spike_indices, 'uni', 0),...
                'file_name',{es.epoch_data.file_name}',...
                'source_id',{es.epoch_data.source_id}',...                
                'epoch_group_id',{es.epoch_data.epoch_group_id}',...
                'epoch_block_id',{es.epoch_data.epoch_block_id}',...
                'epoch_id',{es.epoch_data.epoch_id}',...
                'channel_name',{es.epoch_data.channel_name}'...
                ), app.epoch_sets, 'uni', 0));
            try
                insert(sln_symphony.SpikeTrain, key);
                uialert(app.UIFigure, 'Spikes inserted successfully','Insert','icon','success');
            catch ME
                report = getReport(ME);
                uialert(app.UIFigure,report,"Insert","Interpreter","html");
            end

        end

        % Value changed function: RefractoryperiodmsEditField
        function RefractoryperiodmsEditFieldValueChanged(app, event)
            app.epoch_sets(app.selectedEpochSet).state.refractory = app.RefractoryperiodmsEditField.Value/1000;
            
        end

        % Value changed function: EventdurationmsEditField
        function EventdurationmsEditFieldValueChanged(app, event)
            app.epoch_sets(app.selectedEpochSet).state.duration = app.EventdurationmsEditField.Value/1000;
            
        end

        % Value changed function: EpochsetListBox
        function EpochsetListBoxValueChanged(app, event)
            app.selectedEpochSet = find(strcmp(app.EpochsetListBox.Items, app.EpochsetListBox.Value));
            app.selectedEpoch = 1;
            app.drawDatasets();
            app.drawEpoch();

            if isempty(app.epoch_sets(app.selectedEpochSet).features)
                app.clearClusters();
            else
                app.drawClusters()
            end
        end

        % Button pushed function: DrawexclusionzoneButton
        function DrawexclusionzoneButtonPushed(app, event)
             %TODO: make the app non-interactable

            roi = drawpolygon(app.feature_axes,'color','r','interactionsallowed','none');
            %TODO: perhaps it would be nice to allow people to drag the roi
            % can do this with the addlistener method of the roi object...
            
            if isempty(roi.Position)
                return
            end

            [x,y] = app.getFeats();
            pts = inROI(roi,x,y);
            
            N = cellfun(@length,app.epoch_sets(app.selectedEpochSet).event_indices);
            
            ipts = mat2cell(pts', 1, N)';
            
            app.epoch_sets(app.selectedEpochSet).is_spike = cellfun(@(x,y) x&(~y), app.epoch_sets(app.selectedEpochSet).is_spike , ipts, 'uni', 0);
            app.epoch_sets(app.selectedEpochSet).spike_indices = cellfun(@(x,y,z) setdiff(z, y(x)), ipts, app.epoch_sets(app.selectedEpochSet).event_indices, app.epoch_sets(app.selectedEpochSet).spike_indices, 'uni', 0); %TODO: need multiple inclusions...
            

            app.updateSpikeIndices();
            %we need some visual indication when the spikes are included...
            % 1) count the number of spikes in the epoch set view?
            % 2) change feature_axes / epoch_axes markers to filled?

            app.drawClusters();
        end

        % Button pushed function: ReclusterspikesButton
        function ReclusterspikesButtonPushed(app, event)
            app.epoch_sets(app.selectedEpochSet).event_indices = cellfun(@(x) reshape(x,1,[]), app.epoch_sets(app.selectedEpochSet).spike_indices, 'uni', 0);
            % app.epoch_sets(app.selectedEpochSet).event_indices = app.epoch_sets(app.selectedEpochSet).spike_indices;
            app.epoch_sets(app.selectedEpochSet).event_waveforms = cellfun(@(x,y) x(:,y), app.epoch_sets(app.selectedEpochSet).event_waveforms, app.epoch_sets(app.selectedEpochSet).is_spike,'uni',0);
            app.epoch_sets(app.selectedEpochSet).is_spike = cellfun(@(x) x(x), app.epoch_sets(app.selectedEpochSet).is_spike,'uni',0);

            app.drawEpoch();

            app.doClustering();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @keybinds, true);
            app.dataset_axes = uiaxes(app.UIFigure);
            title(app.dataset_axes, 'Epochs Summary')
            xlabel(app.dataset_axes, 'Time (hr)')
            ylabel(app.dataset_axes, 'Dataset')
            zlabel(app.dataset_axes, 'Z')
            app.dataset_axes.ButtonDownFcn = createCallbackFcn(app, @dataset_axesButtonDown, true);
            app.epoch_axes = uiaxes(app.UIFigure);
            title(app.epoch_axes, 'Data for Selected Epoch')
            xlabel(app.epoch_axes, 'Time (s)')
            ylabel(app.epoch_axes, 'Membrane current (m.a.d.)')
            zlabel(app.epoch_axes, 'Z')
            app.feature_axes = uiaxes(app.UIFigure);
            title(app.feature_axes, 'Spike Clusters')
            zlabel(app.feature_axes, 'Z')
            app.waveform_axes = uiaxes(app.UIFigure);
            title(app.waveform_axes, 'Spike Waveforms')
            xlabel(app.waveform_axes, 'Time (ms)')
            ylabel(app.waveform_axes, 'Current (pA)')
            zlabel(app.waveform_axes, 'Z')
            app.ExperimentDateDatePickerLabel = uilabel(app.UIFigure);
            app.ExperimentDateDatePickerLabel.Text = 'Experiment Date';
            app.ExperimentDateDatePicker = uidatepicker(app.UIFigure);
            app.ExperimentDateDatePicker.DisplayFormat = 'MMddyy';
            app.CellListBoxLabel = uilabel(app.UIFigure);
            app.CellListBoxLabel.Text = 'Cell';
            app.CellListBox = uilistbox(app.UIFigure);
            app.CellListBox.Items = {'Cell 1 (source_id=2)', 'Cell 2 (source_id=3)', 'Pair 1, Cell 2 (source_id=3)', 'Pair 1, Cell 3 (source_id=4)'};
            app.CellListBox.ValueChangedFcn = createCallbackFcn(app, @CellListBoxValueChanged, true);
            app.CellListBox.Value = 'Cell 1 (source_id=2)';
            app.EventthresholdpAEditFieldLabel = uilabel(app.UIFigure);
            app.EventthresholdpAEditFieldLabel.Text = 'Event threshold (pA)';
            app.EventthresholdpAEditField = uieditfield(app.UIFigure, 'numeric');
            app.EventthresholdpAEditField.ValueChangedFcn = createCallbackFcn(app, @EventthresholdpAEditFieldValueChanged, true);
            app.EventthresholdpAEditField.Value = -6;
            app.DisplaySwitchLabel = uilabel(app.UIFigure);
            app.DisplaySwitchLabel.Text = 'Display';
            app.DisplaySwitch = uiswitch(app.UIFigure, 'slider');
            app.DisplaySwitch.Items = {'Raw', 'Filtered'};
            app.DisplaySwitch.ValueChangedFcn = createCallbackFcn(app, @DisplaySwitchValueChanged, true);
            app.DisplaySwitch.Value = 'Filtered';
            app.EpochsetListBoxLabel = uilabel(app.UIFigure);
            app.EpochsetListBoxLabel.Text = 'Epoch set';
            app.EpochsetListBox = uilistbox(app.UIFigure);
            app.EpochsetListBox.Items = {'1 (0 events, 0 spikes)', '', '', ''};
            app.EpochsetListBox.ValueChangedFcn = createCallbackFcn(app, @EpochsetListBoxValueChanged, true);
            app.EpochsetListBox.Value = '1 (0 events, 0 spikes)';
            app.SplitbeforeselectedepochButton = uibutton(app.UIFigure, 'push');
            app.SplitbeforeselectedepochButton.ButtonPushedFcn = createCallbackFcn(app, @SplitbeforeselectedepochButtonPushed, true);
            app.SplitbeforeselectedepochButton.Text = 'Split before selected epoch';
            app.MergeepochsButton = uibutton(app.UIFigure, 'push');
            app.MergeepochsButton.ButtonPushedFcn = createCallbackFcn(app, @MergeepochsButtonPushed, true);
            app.MergeepochsButton.Enable = 'off';
            app.MergeepochsButton.Text = 'Merge epochs';
            app.SelectnewfilterButton = uibutton(app.UIFigure, 'push');
            app.SelectnewfilterButton.Text = 'Select new filter';
            app.CurrentfilterspikeFiltermatLabel = uilabel(app.UIFigure);
            app.CurrentfilterspikeFiltermatLabel.Text = {'Current filter:'; '.../spikeFilter.mat'};
            app.RefractoryperiodmsEditFieldLabel = uilabel(app.UIFigure);
            app.RefractoryperiodmsEditFieldLabel.Text = 'Refractory period (ms)';
            app.RefractoryperiodmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RefractoryperiodmsEditField.ValueChangedFcn = createCallbackFcn(app, @RefractoryperiodmsEditFieldValueChanged, true);
            app.RefractoryperiodmsEditField.Value = 1.5;
            app.EventdurationmsEditFieldLabel = uilabel(app.UIFigure);
            app.EventdurationmsEditFieldLabel.Text = 'Event duration (ms)';
            app.EventdurationmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EventdurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @EventdurationmsEditFieldValueChanged, true);
            app.EventdurationmsEditField.Value = 2;
            app.MaximumalignmentoffsetmsEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumalignmentoffsetmsEditFieldLabel.Text = 'Maximum alignment offset (ms)';
            app.MaximumalignmentoffsetmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumalignmentoffsetmsEditField.Value = 1;
            app.NumberofPCstouseEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofPCstouseEditFieldLabel.Text = 'Number of PCs to use';
            app.NumberofPCstouseEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofPCstouseEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPCstouseEditFieldValueChanged, true);
            app.NumberofPCstouseEditField.Value = 5;
            app.EpsilonEditFieldLabel = uilabel(app.UIFigure);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpsilonEditField.ValueChangedFcn = createCallbackFcn(app, @EpsilonEditFieldValueChanged, true);
            app.EpsilonEditField.Value = 1;
            app.MinimumpointswithinepsilonEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumpointswithinepsilonEditFieldLabel.Text = 'Minimum points within epsilon';
            app.MinimumpointswithinepsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumpointswithinepsilonEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumpointswithinepsilonEditFieldValueChanged, true);
            app.MinimumpointswithinepsilonEditField.Value = 5;
            app.ThresholdonSwitchLabel = uilabel(app.UIFigure);
            app.ThresholdonSwitchLabel.Text = 'Threshold on';
            app.ThresholdonSwitch = uiswitch(app.UIFigure, 'slider');
            app.ThresholdonSwitch.Items = {'Raw', 'Filtered'};
            app.ThresholdonSwitch.ValueChangedFcn = createCallbackFcn(app, @ThresholdonSwitchValueChanged, true);
            app.ThresholdonSwitch.Value = 'Filtered';
            app.FiltersettingsLabel = uilabel(app.UIFigure);
            app.FiltersettingsLabel.Text = 'Filter settings';
            app.ThresholdsettingsLabel = uilabel(app.UIFigure);
            app.ThresholdsettingsLabel.Text = 'Threshold settings';
            app.PCAsettingsLabel = uilabel(app.UIFigure);
            app.PCAsettingsLabel.Text = 'PCA settings';
            app.ClusteringsettingsLabel = uilabel(app.UIFigure);
            app.ClusteringsettingsLabel.Text = 'Clustering settings';
            app.DrawexclusionzoneButton = uibutton(app.UIFigure, 'push');
            app.DrawexclusionzoneButton.ButtonPushedFcn = createCallbackFcn(app, @DrawexclusionzoneButtonPushed, true);
            app.DrawexclusionzoneButton.Text = 'Draw exclusion zone';
            app.KeepexcludedpointsSwitchLabel = uilabel(app.UIFigure);
            app.KeepexcludedpointsSwitchLabel.Text = 'Keep excluded points';
            app.KeepexcludedpointsSwitch = uiswitch(app.UIFigure, 'slider');
            app.KeepexcludedpointsSwitch.Value = 'On';
            app.KeepexcludedpointsSwitch_2Label = uilabel(app.UIFigure);
            app.KeepexcludedpointsSwitch_2Label.Text = 'Keep excluded points';
            app.KeepexcludedpointsSwitch_2 = uiswitch(app.UIFigure, 'slider');
            app.KeepexcludedpointsSwitch_2.Value = 'On';
            app.KeepincludedpointsSwitchLabel = uilabel(app.UIFigure);
            app.KeepincludedpointsSwitchLabel.Text = 'Keep included points';
            app.KeepincludedpointsSwitch = uiswitch(app.UIFigure, 'slider');
            app.KeepincludedpointsSwitch.Value = 'On';
            app.KeepincludedpointsSwitch_2Label = uilabel(app.UIFigure);
            app.KeepincludedpointsSwitch_2Label.Text = 'Keep included points';
            app.KeepincludedpointsSwitch_2 = uiswitch(app.UIFigure, 'slider');
            app.KeepincludedpointsSwitch_2.Value = 'On';
            app.DrawinclusionzoneButton = uibutton(app.UIFigure, 'push');
            app.DrawinclusionzoneButton.ButtonPushedFcn = createCallbackFcn(app, @DrawinclusionzoneButtonPushed, true);
            app.DrawinclusionzoneButton.Text = 'Draw inclusion zone';
            app.XaxisDropDownLabel = uilabel(app.UIFigure);
            app.XaxisDropDownLabel.Text = 'X axis';
            app.XaxisDropDown = uidropdown(app.UIFigure);
            app.XaxisDropDown.Items = {'Peak-to-peak current', 'Maximum slope', 'Principal component', ''};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownValueChanged, true);
            app.XaxisDropDown.Value = 'Principal component';
            app.YaxisDropDownLabel = uilabel(app.UIFigure);
            app.YaxisDropDownLabel.Text = 'Y axis';
            app.YaxisDropDown = uidropdown(app.UIFigure);
            app.YaxisDropDown.Items = {'Peak-to-peak current', 'Maximum slope', 'Principal component', ''};
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YaxisDropDownValueChanged, true);
            app.YaxisDropDown.Value = 'Peak-to-peak current';
            app.ResetexclusionlistButton = uibutton(app.UIFigure, 'push');
            app.ResetexclusionlistButton.Text = 'Reset exclusion list';
            app.ResetinclusionlistButton = uibutton(app.UIFigure, 'push');
            app.ResetinclusionlistButton.Text = 'Reset inclusion list';
            app.RunonSwitchLabel = uilabel(app.UIFigure);
            app.RunonSwitchLabel.Text = 'Run on';
            app.RunonSwitch = uiswitch(app.UIFigure, 'slider');
            app.RunonSwitch.Items = {'Raw', 'Filtered'};
            app.RunonSwitch.Value = 'Filtered';
            app.frommfileButton = uibutton(app.UIFigure, 'push');
            app.frommfileButton.Text = 'from *.m file';
            app.fromanonymousfunctionButton = uibutton(app.UIFigure, 'push');
            app.fromanonymousfunctionButton.Text = 'from anonymous function';
            app.AddfeatureLabel = uilabel(app.UIFigure);
            app.AddfeatureLabel.Text = 'Add feature';
            app.PrinCompX = uieditfield(app.UIFigure, 'numeric');
            app.PrinCompX.ValueChangedFcn = createCallbackFcn(app, @PrinCompXValueChanged, true);
            app.PrinCompX.Visible = 'off';
            app.PrinCompX.Value = 1;
            app.PrinCompY = uieditfield(app.UIFigure, 'numeric');
            app.PrinCompY.ValueChangedFcn = createCallbackFcn(app, @PrinCompYValueChanged, true);
            app.PrinCompY.Visible = 'off';
            app.PrinCompY.Value = 2;
            app.SavesettingstofileButton = uibutton(app.UIFigure, 'push');
            app.SavesettingstofileButton.Text = 'Save settings to file';
            app.LoadsettingsfromfileButton = uibutton(app.UIFigure, 'push');
            app.LoadsettingsfromfileButton.Text = 'Load settings from file';
            app.PushtodatabaseButton = uibutton(app.UIFigure, 'push');
            app.PushtodatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @PushtodatabaseButtonPushed, true);
            app.PushtodatabaseButton.Text = 'Push to database';
            app.RigSwitchLabel = uilabel(app.UIFigure);
            app.RigSwitchLabel.Text = 'Rig';
            app.RigSwitch = uiswitch(app.UIFigure, 'slider');
            app.RigSwitch.Items = {'A', 'B'};
            app.RigSwitch.Value = 'A';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ClustereventsButton = uibutton(app.UIFigure, 'push');
            app.ClustereventsButton.ButtonPushedFcn = createCallbackFcn(app, @ClustereventsButtonPushed, true);
            app.ClustereventsButton.Text = 'Cluster events';
            app.SplitafterselectedepochButton = uibutton(app.UIFigure, 'push');
            app.SplitafterselectedepochButton.ButtonPushedFcn = createCallbackFcn(app, @SplitafterselectedepochButtonPushed, true);
            app.SplitafterselectedepochButton.Text = 'Split after selected epoch';
            app.ReclusterspikesButton = uibutton(app.UIFigure, 'push');
            app.ReclusterspikesButton.ButtonPushedFcn = createCallbackFcn(app, @ReclusterspikesButtonPushed, true);
            app.ReclusterspikesButton.Text = 'Recluster spikes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeDetector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end