classdef TissueDB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ReloadButton                    matlab.ui.control.Button
        OtherAnimalEditField            matlab.ui.control.NumericEditField
        AnimalDropDown                  matlab.ui.control.DropDown
        AnimalDropDownLabel             matlab.ui.control.Label
        TissueTypeDropDown              matlab.ui.control.DropDown
        AddtissueButton                 matlab.ui.control.Button
        NotesTextArea_completeTask      matlab.ui.control.TextArea
        NotesTextArea_2Label            matlab.ui.control.Label
        LogoutButton                    matlab.ui.control.Button
        BrainOrRetnaTasksDropDown       matlab.ui.control.DropDown
        LoginButton                     matlab.ui.control.Button
        PasswordEditField               matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        UserEditField                   matlab.ui.control.EditField
        UserEditFieldLabel              matlab.ui.control.Label
        ChangeusertoDropDown            matlab.ui.control.DropDown
        ChangeusertoDropDownLabel       matlab.ui.control.Label
        NotesTextArea_addTask           matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        CompletetaskButton              matlab.ui.control.Button
        AddtaskButton                   matlab.ui.control.Button
        CreatenewtaskPanel              matlab.ui.container.Panel
        AnalysisoutputsTextArea         matlab.ui.control.TextArea
        AnalysisoutputsLabel            matlab.ui.control.Label
        CreateButton                    matlab.ui.control.Button
        ScopeDropDown                   matlab.ui.control.DropDown
        ScopeDropDownLabel              matlab.ui.control.Label
        Reagent2secondaryDropDown       matlab.ui.control.DropDown
        Reagent2secondaryDropDownLabel  matlab.ui.control.Label
        Reagent1primaryDropDown         matlab.ui.control.DropDown
        Reagent1primaryDropDownLabel    matlab.ui.control.Label
        FortissuetypeDropDown           matlab.ui.control.DropDown
        FortissuetypeDropDownLabel      matlab.ui.control.Label
        TasktypeDropDown                matlab.ui.control.DropDown
        TasktypeDropDownLabel           matlab.ui.control.Label
        TasknameEditField               matlab.ui.control.EditField
        TasknameEditFieldLabel          matlab.ui.control.Label
        TaskTable                       matlab.ui.control.Table
    end

    
    properties (Access = private)
        current_user %user currently logged in
        inactivity_timeout = 300; % seconds until you are logged out
        logout_timer = timer; % timer object for logout
    end
    
    methods (Access = private)
        
        function init(app)
              app.current_user = 'viewer';
              set(app.UserEditField,'value',app.current_user);
              app.load_users();
              set(app.ChangeusertoDropDown,'value','viewer');      
              app.load_recently_deceased_animals();
              app.set_permissions();
              
              %initial state
              set(app.OtherAnimalEditField,'visible',false);

              app.load_tissue_table();

              app.reset_logout_timer();              
        end

        function load_users(app)
            users = fetchn(sln_lab.ActiveUser,'user_name');
            users = setdiff(users, 'CCM staff');
            items = ['viewer'; users];
            set(app.ChangeusertoDropDown,'items',items);
        end

        function load_recently_deceased_animals(app)
            recent_dead = sln_animal.Deceased * sln_animal.AnimalEvent & 'cause = "sacrificed for experiment"' & 'LIMIT 6 PER cause ORDER BY date DESC';
            recent_dead_struct = fetch(recent_dead,'animal_id','user_name','date','notes');
            items = cell(7,1);
            for i=1:length(recent_dead_struct)
                items{i} = sprintf('%d: %s. Entered by %s. %s', ...
                    recent_dead_struct(i).animal_id, recent_dead_struct(i).date, recent_dead_struct(i).user_name, recent_dead_struct(i).notes);                
            end
            items{end} = 'other';            

            set(app.AnimalDropDown,'items',items);            
        end

        function set_permissions(app)
            if strcmp(app.current_user,'viewer')
                set(app.AddtissueButton,'enable',false);
                set(app.AddtaskButton,'enable',false);
                set(app.CompletetaskButton,'enable',false);
                set(app.CreateButton,'enable',false);
            else
                 set(app.AddtissueButton,'enable',true);
                set(app.AddtaskButton,'enable',true);
                set(app.CompletetaskButton,'enable',true);
                set(app.CreateButton,'enable',true);
            end
            
        end
        
        function reset_logout_timer(app)
            if isvalid(app.logout_timer)
                app.logout_timer.stop();
                delete(app.logout_timer);
            end
            app.logout_timer = timer('TimerFcn',@(~,~)app.LogoutButtonPushed());
            app.logout_timer.StartDelay = app.inactivity_timeout;
            app.logout_timer.start();
            %disp('timer reset');
        end
        
        function load_tissue_table(app)
            %TODO: only unfinished tasks
            tasks = sln_tissue.Task;
            N = tasks.count;

            tasks_struct = fetch(tasks,'*');
            tissue_ids = unique([tasks_struct.tissue_id]);
            N_tissues = length(tissue_ids);

            T = table('size',[N_tissues, 5],...
            'VariableNames',...
                {'Date', ...
                'Type', ...
                'Animal_ID', ...
                'Owner', ...
                'Tasks'}, ...
                'VariableTypes',...
            {'string',...
                'string',...
                'int32',...
                'string',...
                'string'});

            for i=1:N_tissues
                tasks_for_this_tissue = tasks_struct([tasks_struct.tissue_id] == tissue_ids(i));
                T.Tasks(i) = strjoin(cellstr(strvcat(tasks_for_this_tissue.task_name)), ', ');
                T.Date(i) = datestr(min(datetime(strvcat(tasks_for_this_tissue.task_entry_time))),'yyyy-mm-dd');
                thisBrain = sln_tissue.Brain * sln_tissue.Tissue & sprintf('tissue_id=%d',tissue_ids(i));
                thisRetina = sln_tissue.Retina * sln_tissue.Tissue & sprintf('tissue_id=%d',tissue_ids(i));
                if thisBrain.exists
                    T.Type(i) = 'Brain';
                    T.Animal_ID(i) = fetch1(thisBrain,'animal_id');
                elseif thisRetina.exists
                    T.Type(i) = 'Retina';
                    T.Animal_ID(i) = fetch1(thisRetina,'animal_id');
                else
                    error('Tissue ID %d not found in Brain or Retina tables', tissue_ids(i));
                end
                T.Owner(i) = 'Greg'; %TODO: dropdown for this
            end

            set(app.TaskTable,'Data',T);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
        end

        % Value changed function: AnimalDropDown
        function AnimalDropDownValueChanged(app, event)
            value = app.AnimalDropDown.Value;
            if strcmp(value,'other')
                set(app.OtherAnimalEditField,'visible',true);
            else
                set(app.OtherAnimalEditField,'visible',false);
            end
            app.reset_logout_timer();
        end

        % Value changed function: BrainOrRetnaTasksDropDown
        function BrainOrRetnaTasksDropDownValueChanged(app, event)
            value = app.BrainOrRetnaTasksDropDown.Value;   
            app.reset_logout_timer();
        end

        % Button pushed function: AddtissueButton
        function AddtissueButtonPushed(app, event)
            selected_animal = app.AnimalDropDown.Value;
            if strcmp(selected_animal,'other')
                key.animal_id = get(app.OtherAnimalEditField,'value');
            else
                key.animal_id = str2double(extractBefore(selected_animal, ':'));
            end
            
            selected_tissue_type = get(app.TissueTypeDropDown,'value');
            switch selected_tissue_type
                case 'Brain'
                    tissue_id = sln_tissue.add_tissue(key, 'Brain');
                case 'Left retina'
                    key.side = 'Left';
                    tissue_id = sln_tissue.add_tissue(key, 'Retina');
                case 'Right retina'
                    key.side = 'Right';
                    tissue_id = sln_tissue.add_tissue(key, 'Retina');
                case 'Unknown retina'
                    disp('Not implemented');
                    %TODO: this is a pain. Will need to add new eyes and
                    %such
                    %key.side = 'UnknownA';
                    %tissue_id = add_tissue(key, 'Retina');
            end
            app.reset_logout_timer();
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            %TODO: password part
            login_success = true;
            if login_success
                app.current_user = get(app.ChangeusertoDropDown,'value');
                set(app.UserEditField, 'value', app.current_user);
                app.set_permissions();
            end
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            app.current_user = 'viewer';
            set(app.UserEditField, 'value', app.current_user);
            set(app.ChangeusertoDropDown,'value','viewer');
            app.set_permissions();
        end

        % Button pushed function: AddtaskButton
        function AddtaskButtonPushed(app, event)

        end

        % Callback function: TaskTable
        function TaskTableSelectionChanged(app, event)
            selection = app.TaskTable.Selection;

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            app.reset_logout_timer();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            app.reset_logout_timer();
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            app.init();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.TaskTable = uitable(app.UIFigure);
            app.TaskTable.ColumnName = {'Entry date'; 'Type'; 'Animal DJ ID'; 'Owner'; 'Unfinished tasks'};
            app.TaskTable.RowName = {};
            app.TaskTable.ColumnSortable = true;
            app.TaskTable.SelectionChangedFcn = createCallbackFcn(app, @TaskTableSelectionChanged, true);
            app.CreatenewtaskPanel = uipanel(app.UIFigure);
            app.CreatenewtaskPanel.Title = 'Create new task';
            app.TasknameEditFieldLabel = uilabel(app.CreatenewtaskPanel);
            app.TasknameEditFieldLabel.Text = 'Task name';
            app.TasknameEditField = uieditfield(app.CreatenewtaskPanel, 'text');
            app.TasktypeDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.TasktypeDropDownLabel.Text = 'Task type';
            app.TasktypeDropDown = uidropdown(app.CreatenewtaskPanel);
            app.TasktypeDropDown.Items = {'Stain', 'Image cell morphology', 'Count somas', 'Image axon terminals'};
            app.TasktypeDropDown.Value = 'Stain';
            app.FortissuetypeDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.FortissuetypeDropDownLabel.Text = 'For tissue type';
            app.FortissuetypeDropDown = uidropdown(app.CreatenewtaskPanel);
            app.FortissuetypeDropDown.Items = {'Retina', 'Brain'};
            app.FortissuetypeDropDown.Value = 'Retina';
            app.Reagent1primaryDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.Reagent1primaryDropDownLabel.Text = 'Reagent 1 / primary';
            app.Reagent1primaryDropDown = uidropdown(app.CreatenewtaskPanel);
            app.Reagent2secondaryDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.Reagent2secondaryDropDownLabel.Text = 'Reagent 2 / secondary';
            app.Reagent2secondaryDropDown = uidropdown(app.CreatenewtaskPanel);
            app.ScopeDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.ScopeDropDownLabel.Text = 'Scope';
            app.ScopeDropDown = uidropdown(app.CreatenewtaskPanel);
            app.CreateButton = uibutton(app.CreatenewtaskPanel, 'push');
            app.CreateButton.Text = 'Create';
            app.AnalysisoutputsLabel = uilabel(app.CreatenewtaskPanel);
            app.AnalysisoutputsLabel.Text = 'Analysis outputs:';
            app.AnalysisoutputsTextArea = uitextarea(app.CreatenewtaskPanel);
            app.AddtaskButton = uibutton(app.UIFigure, 'push');
            app.AddtaskButton.ButtonPushedFcn = createCallbackFcn(app, @AddtaskButtonPushed, true);
            app.AddtaskButton.Text = 'Add task';
            app.CompletetaskButton = uibutton(app.UIFigure, 'push');
            app.CompletetaskButton.Text = 'Complete task';
            app.NotesTextAreaLabel = uilabel(app.UIFigure);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea_addTask = uitextarea(app.UIFigure);
            app.ChangeusertoDropDownLabel = uilabel(app.UIFigure);
            app.ChangeusertoDropDownLabel.Text = 'Change user to';
            app.ChangeusertoDropDown = uidropdown(app.UIFigure);
            app.UserEditFieldLabel = uilabel(app.UIFigure);
            app.UserEditFieldLabel.Text = 'User';
            app.UserEditField = uieditfield(app.UIFigure, 'text');
            app.UserEditField.Editable = 'off';
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password:';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.BrainOrRetnaTasksDropDown = uidropdown(app.UIFigure);
            app.BrainOrRetnaTasksDropDown.ValueChangedFcn = createCallbackFcn(app, @BrainOrRetnaTasksDropDownValueChanged, true);
            app.LogoutButton = uibutton(app.UIFigure, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            app.NotesTextArea_2Label = uilabel(app.UIFigure);
            app.NotesTextArea_2Label.Text = 'Notes';
            app.NotesTextArea_completeTask = uitextarea(app.UIFigure);
            app.AddtissueButton = uibutton(app.UIFigure, 'push');
            app.AddtissueButton.ButtonPushedFcn = createCallbackFcn(app, @AddtissueButtonPushed, true);
            app.AddtissueButton.Text = 'Add tissue';
            app.TissueTypeDropDown = uidropdown(app.UIFigure);
            app.TissueTypeDropDown.Items = {'Left retina', 'Right retina', 'Unknown retina', 'Brain'};
            app.TissueTypeDropDown.Value = 'Left retina';
            app.AnimalDropDownLabel = uilabel(app.UIFigure);
            app.AnimalDropDownLabel.Text = 'Animal';
            app.AnimalDropDown = uidropdown(app.UIFigure);
            app.AnimalDropDown.ValueChangedFcn = createCallbackFcn(app, @AnimalDropDownValueChanged, true);
            app.OtherAnimalEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReloadButton = uibutton(app.UIFigure, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TissueDB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end