classdef TissueDB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TissueDBUIFigure                matlab.ui.Figure
        TissueTypeToAddButtonGroup      matlab.ui.container.ButtonGroup
        BrainButton_2                   matlab.ui.control.RadioButton
        RretinaButton                   matlab.ui.control.RadioButton
        LretinaButton                   matlab.ui.control.RadioButton
        ByprojectDropDown               matlab.ui.control.DropDown
        ByprojectDropDownLabel          matlab.ui.control.Label
        ByownerDropDown                 matlab.ui.control.DropDown
        ByownerDropDownLabel            matlab.ui.control.Label
        TissueTypeButtonGroup           matlab.ui.container.ButtonGroup
        RetinaButton                    matlab.ui.control.RadioButton
        BrainButton                     matlab.ui.control.RadioButton
        AllButton_2                     matlab.ui.control.RadioButton
        AddnoteforthistissueButton      matlab.ui.control.Button
        NoteTextArea                    matlab.ui.control.TextArea
        DeactivatetissueswithnotasksButton  matlab.ui.control.Button
        ShowLabel                       matlab.ui.control.Label
        DisplayFilterButtonGroup        matlab.ui.container.ButtonGroup
        MonthsEditField                 matlab.ui.control.NumericEditField
        monthsLabel                     matlab.ui.control.Label
        LastButton                      matlab.ui.control.RadioButton
        AllButton                       matlab.ui.control.RadioButton
        ActiveButton                    matlab.ui.control.RadioButton
        AssigntoprojectDropDown         matlab.ui.control.DropDown
        AssigntoprojectDropDownLabel    matlab.ui.control.Label
        ShowtasktreeButton              matlab.ui.control.Button
        AddMacroToTissueButton          matlab.ui.control.Button
        MacrolistListBox                matlab.ui.control.ListBox
        MacrolistListBoxLabel           matlab.ui.control.Label
        TasktreeLabel                   matlab.ui.control.Label
        MacrosforthistissueLabel        matlab.ui.control.Label
        TaskTree_noEdit                 matlab.ui.container.Tree
        CurrentTissueMacrosListBox      matlab.ui.control.ListBox
        CreatenewmacroPanel             matlab.ui.container.Panel
        TasktreeforthismacroLabel       matlab.ui.control.Label
        RetinaBrainSwitch               matlab.ui.control.Switch
        DropTaskButton                  matlab.ui.control.Button
        TaskToMacroButton               matlab.ui.control.Button
        MovedownButton                  matlab.ui.control.Button
        MoveupButton                    matlab.ui.control.Button
        TaskTree                        matlab.ui.container.Tree
        TasklistLabel                   matlab.ui.control.Label
        TaskListBox                     matlab.ui.control.ListBox
        StatusEdit_macro                matlab.ui.control.EditField
        StatusLamp_macro                matlab.ui.control.Lamp
        CreateButton_macro              matlab.ui.control.Button
        MacronameEditField              matlab.ui.control.EditField
        MacronameEditFieldLabel         matlab.ui.control.Label
        OwnerDropDown                   matlab.ui.control.DropDown
        OwnerDropDownLabel              matlab.ui.control.Label
        AborttaskButton                 matlab.ui.control.Button
        ReloadButton                    matlab.ui.control.Button
        OtherAnimalEditField            matlab.ui.control.NumericEditField
        AnimalDropDown                  matlab.ui.control.DropDown
        AnimalDropDownLabel             matlab.ui.control.Label
        AddtissueButton                 matlab.ui.control.Button
        LogoutButton                    matlab.ui.control.Button
        LoginButton                     matlab.ui.control.Button
        PasswordEditField               matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        UserEditField                   matlab.ui.control.EditField
        UserEditFieldLabel              matlab.ui.control.Label
        ChangeusertoDropDown            matlab.ui.control.DropDown
        ChangeusertoDropDownLabel       matlab.ui.control.Label
        NotesTextArea_CompleteOrAbort   matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        CompletetaskButton              matlab.ui.control.Button
        CreatenewtaskPanel              matlab.ui.container.Panel
        AntibodyTargetEditField         matlab.ui.control.EditField
        TargetLabel                     matlab.ui.control.Label
        Color2_EditField                matlab.ui.control.EditField
        Color1_EditField                matlab.ui.control.EditField
        TargetEditField                 matlab.ui.control.EditField
        HostEditField                   matlab.ui.control.EditField
        ColorLabel_2                    matlab.ui.control.Label
        ColorLabel                      matlab.ui.control.Label
        TargetspeciesLabel              matlab.ui.control.Label
        HostspeciesLabel                matlab.ui.control.Label
        ClearingprotocolLabel           matlab.ui.control.Label
        ClearingprotocolEditField       matlab.ui.control.EditField
        StatusEdit_task                 matlab.ui.control.EditField
        StatusLamp_task                 matlab.ui.control.Lamp
        CellnumberEditField             matlab.ui.control.NumericEditField
        CellnumberEditFieldLabel        matlab.ui.control.Label
        BrainareaDropDown               matlab.ui.control.DropDown
        BrainareaDropDownLabel          matlab.ui.control.Label
        Reagent1primaryDropDown         matlab.ui.control.DropDown
        Reagent1primaryDropDownLabel    matlab.ui.control.Label
        ThicknessmEditField             matlab.ui.control.NumericEditField
        ThicknessmEditFieldLabel        matlab.ui.control.Label
        ColorchannelDropDown            matlab.ui.control.DropDown
        ColorchannelDropDownLabel       matlab.ui.control.Label
        AnalysisoutputsTextArea         matlab.ui.control.TextArea
        AnalysisoutputsLabel            matlab.ui.control.Label
        CreateButton_task               matlab.ui.control.Button
        ScopeDropDown                   matlab.ui.control.DropDown
        ScopeDropDownLabel              matlab.ui.control.Label
        Reagent2secondaryDropDown       matlab.ui.control.DropDown
        Reagent2secondaryDropDownLabel  matlab.ui.control.Label
        TasktypeDropDown                matlab.ui.control.DropDown
        TasktypeDropDownLabel           matlab.ui.control.Label
        TasknameEditField               matlab.ui.control.EditField
        TasknameEditFieldLabel          matlab.ui.control.Label
        TaskTable                       matlab.ui.control.Table
    end

    
    properties (Access = private)
        current_user %user currently logged in
        inactivity_timeout = 1800; % seconds until you are logged out
        logout_timer = timer; % timer object for logout
        prev_password_length = 0;
        user_password = '';
        selectedStyle % uistyle for selected row
        unselectedStyle %uistyle for unselected row
    end
    
    methods (Access = private)
        
        function init(app, reload)
            if nargin < 2
                reload = false;
            end
            
            if ~reload
                app.current_user = 'viewer';
                set(app.UserEditField,'value',app.current_user);
                app.load_users();
                set(app.ChangeusertoDropDown,'value','viewer');
            end
            
            app.load_recently_deceased_animals();
            app.set_permissions();

            %make styles for table
            app.selectedStyle = uistyle('FontColor','red','FontWeight','bold');
            app.unselectedStyle = uistyle('FontColor','black','FontWeight','normal');

            %initial state
            set(app.OtherAnimalEditField,'visible',false);
            set(app.StatusLamp_task,'color','white');
            set(app.StatusEdit_task,'value','');

            app.load_scopes();
            app.load_brain_areas();
            app.load_color_channels();
            app.load_tissue_table();
            app.load_tasks();
            app.load_macros('All');
            app.load_projects();

            app.TasktypeDropDownValueChanged([]);
            app.reset_logout_timer();
        end

        function load_users(app)
            users = fetchn(sln_lab.ActiveUser,'user_name');
            all_users = fetchn(sln_lab.User,'user_name');
            users = setdiff(users, 'CCM staff');
            items = ['viewer'; users];
            set(app.ChangeusertoDropDown,'items',items);
            set(app.OwnerDropDown,'items',users);
            set(app.ByownerDropDown,'items',['Any'; all_users])
        end

        function load_recently_deceased_animals(app)
            recent_dead = sln_animal.Deceased * sln_animal.AnimalEvent & 'cause = "sacrificed for experiment"' & 'LIMIT 6 PER cause ORDER BY date DESC';
            recent_dead_struct = fetch(recent_dead,'animal_id','user_name','date','notes');
            items = cell(7,1);
            for i=1:length(recent_dead_struct)
                items{i} = sprintf('%d: %s. Entered by %s. %s', ...
                    recent_dead_struct(i).animal_id, recent_dead_struct(i).date, recent_dead_struct(i).user_name, recent_dead_struct(i).notes);                
            end
            items{end} = 'other';            

            set(app.AnimalDropDown,'items',items);            
        end

        function set_permissions(app)
            if strcmp(app.current_user,'viewer')
                set(app.AddtissueButton,'enable',false);
                set(app.AddMacroToTissueButton,'enable',false);
                set(app.CompletetaskButton,'enable',false);
                set(app.AborttaskButton,'enable',false);
                set(app.CreateButton_task,'enable',false);
                set(app.AddnoteforthistissueButton,'enable',false);
            else
                set(app.AddtissueButton,'enable',true);
                set(app.AddMacroToTissueButton,'enable',true);
                set(app.CompletetaskButton,'enable',true);
                set(app.AborttaskButton,'enable',true);
                set(app.CreateButton_task,'enable',true);
                set(app.AddnoteforthistissueButton,'enable',true);
            end
            
        end
        
        function reset_logout_timer(app)
            if isvalid(app.logout_timer)
                app.logout_timer.stop();
                delete(app.logout_timer);
            end
            app.logout_timer = timer('TimerFcn',@(~,~)app.LogoutButtonPushed());
            app.logout_timer.StartDelay = app.inactivity_timeout;
            app.logout_timer.start();
            %disp('timer reset');
        end
        
        function load_tissue_table(app)
            set(app.TissueDBUIFigure,'name','Loading...');
            tissue_ids = app.get_tissues_passing_filter();
            N_tissues = length(tissue_ids);

            T = table('size',[N_tissues, 8],...
            'VariableNames',...
                {'Date', ...
                'Type', ...
                'Side', ...
                'Animal_ID', ...
                'Owner', ...
                'Projects', ...
                'Macros', ...
                'Notes'}, ...
                'VariableTypes',...
            {'string',...
                'string',...
                'string',...
                'int32',...
                'string', ...
                'string', ...
                'string', ...
                'string'});

            for i=1:N_tissues
                assignments = sln_tissue.Assignment & sprintf('tissue_id=%d', tissue_ids(i));
                if assignments.exists
                    dates = datetime(fetchn(assignments,'entry_time'));
                    T.Date(i) = datestr(min(dates), 'yyyy-mm-dd');
                    macros = fetchn(assignments,'macro_name');
                    projects = fetchn(assignments,'project_name');
                    macro_list = macros{1};
                    project_list = projects{1};
                    for j=2:length(macros)
                        macro_list = [macro_list, ',', macros{j}];
                        if ~isempty(projects{j})
                            project_list = [project_list, ',', projects{j}];
                        end
                    end
                    T.Macros(i) = macro_list;
                    T.Projects(i) = project_list;
                end
                notes_for_this_tissue = sln_tissue.Note & sprintf('tissue_id=%d',tissue_ids(i));
                if notes_for_this_tissue.exists
                    all_notes = strjoin(fetchn(notes_for_this_tissue,'note_text'),',');
                    T.Notes(i) = all_notes;
                else
                    T.Notes(i) = '';
                end
                thisBrain = sln_tissue.Brain * sln_tissue.Tissue & sprintf('tissue_id=%d',tissue_ids(i));
                thisRetina = sln_tissue.Retina * sln_tissue.Tissue & sprintf('tissue_id=%d',tissue_ids(i));
                if thisBrain.exists
                    T.Type(i) = 'Brain';
                    T.Animal_ID(i) = fetch1(thisBrain,'animal_id');
                    T.Side(i) = '';
                    T.Owner(i) = fetch1(thisBrain, 'owner');
                elseif thisRetina.exists
                    T.Type(i) = 'Retina';
                    T.Animal_ID(i) = fetch1(thisRetina,'animal_id');
                    T.Side(i) = fetch1(thisRetina,'side');
                    T.Owner(i) = fetch1(thisRetina, 'owner');
                else
                    error('Tissue ID %d not found in Brain or Retina tables', tissue_ids(i));
                end
            end

            set(app.TaskTable,'Data',T);
            set(app.TissueDBUIFigure,'name','TissueDB');
        end
        
        function load_tasks(app)
            set(app.TaskListBox,'items',fetchn(sln_tissue.Task,'task_name'));        
        end
        
        function load_scopes(app)
            set(app.ScopeDropDown,'items',fetchn(sln_lab.Scope,'scope_name'));            
        end
        
        function load_color_channels(app)
            set(app.ColorchannelDropDown,'items',fetchn(sln_tissue.ColorChannel,'color_channel_name'));
        end
        
        function load_brain_areas(app)
            set(app.BrainareaDropDown,'items',fetchn(sln_animal.BrainArea,'target'));
        end

%         function update_task_tree(app)
%               ch = get(app.TaskTree,'children');
%               for i=1:length(ch)
%                   delete(ch(i));
%               end
% 
%               tissue_id = app.get_selected_tissue_id();
%               if ~isempty(tissue_id)
%                   completed_tasks = sln_tissue.Task & sprintf('tissue_id=%d',tissue_id) & sln_tissue.TaskCompleted;
%                   aborted_tasks = sln_tissue.Task & sprintf('tissue_id=%d',tissue_id) & sln_tissue.TaskAborted;
%                   tasks = (sln_tissue.Task & sprintf('tissue_id=%d',tissue_id)) - sln_tissue.TaskCompleted - sln_tissue.TaskAborted;
%                   if tasks.exists
%                       N = tasks.count;
%                       tasks_struct = fetch(tasks,'*');
%                       for i=1:N
%                           uitreenode(app.TaskTree,'Text',tasks_struct(i).task_name);
%                       end
%                       app.load_tissue_table();
%                   elseif completed_tasks.exists || aborted_tasks.exists %deactivate tissue because everything is complete
%                       thisTissue = sln_tissue.Tissue & sprintf('tissue_id=%d',tissue_id);
%                       thisTissue.deactivate();
%                       app.load_tissue_table();
%                   end
%               end
%         end
        
        function tissue_id = get_selected_tissue_id(app)
            tissue_id = [];
            tissue_selection =  get(app.TaskTable,'selection');
              if ~isempty(tissue_selection)
                  whichRow = tissue_selection(1);
              else
                  whichRow = nan;
              end
              tissue_table_data = get(app.TaskTable,'Data');

              if whichRow <= height(tissue_table_data)
                  tissue_type = tissue_table_data.Type(tissue_selection(1));
                  key.animal_id = tissue_table_data.Animal_ID(tissue_selection(1));
                  if strcmp(tissue_type,'Brain')
                    tissue_id = fetch1(sln_tissue.Tissue * sln_tissue.Brain & key, 'tissue_id');
                  elseif strcmp(tissue_type,'Retina')
                      key.side = char(tissue_table_data.Side(tissue_selection(1)));
                      tissue_id = fetch1(sln_tissue.Tissue * sln_tissue.Retina & key, 'tissue_id');
                  end
              else
                  disp('No tissue selected');
                  return
              end
        end
        
        function siblings = get_siblings(app, node)
            siblings = [];
            siblings_plus_me = get(get(node,'parent'),'children');
            z = 1;
            for i=1:length(siblings_plus_me)
                if ~isequal(siblings_plus_me(i),node)
                    siblings(z) = siblings_plus_me(i);
                    z=z+1;
                end
            end            
        end
        
        function clear_task_tree(app)
            ch = get(app.TaskTree,'children');
            for i=1:length(ch)
                ch(i).delete();
            end               
        end

        function T = export_full_task_tree(app)
            macro_name = get(app.MacronameEditField,'value');
            ch = get(app.TaskTree,'children');
            T = tree(macro_name);
            for i=1:length(ch)
                task = get(ch(i),'Text');
                parent_id = 1;
                [T, new_id] = T.addnode(parent_id, task);
                T = app.export_task_tree(T, ch(i), new_id);
            end
        end
        
        function T = export_task_tree(app, T, ui_tree, parent_id)
            ch = get(ui_tree,'children');            
            if isempty(ch)
                return;
            end
            for i=1:length(ch)
                task = get(ch(i),'Text');
                [T, new_id] = T.addnode(parent_id, task);
                T = app.export_task_tree(T, ch(i), new_id);
            end
        end
        
        function load_macros(app, tissue_type)
            if strcmp(tissue_type, 'All')
                macro_names = fetchn(sln_tissue.Macro,'macro_name');
            else
                macro_names = fetchn(sln_tissue.Macro & sprintf('tissue_type="%s"',tissue_type),'macro_name');
            end

            if ~isempty(macro_names)
                set(app.MacrolistListBox,'items',macro_names);
            end            
        end        
        
        function set_uitasktree_from_tree(app,T,check_completion)            
            %clear first
            ch = get(app.TaskTree_noEdit,'children');
            for i=1:length(ch)
                ch(i).delete();
            end 
            set(app.TaskTree_noEdit,'children',[]);
            
            if check_completion
                tissue_id = app.get_selected_tissue_id();
%                 completed_style = uistyle('BackgroundColor',[0.9 0.9 0.9]);
%                 todo_style = uistyle('BackgroundColor',[0 .7 .7]);
%                 aborted_style = uistyle('BackgroundColor', [.8 0 0]);
            end

            root_ind = 1;
            task = T.Node{root_ind};
            nodes = cell(T.nnodes,1);
            nodes{root_ind} = uitreenode(app.TaskTree_noEdit,'Text',task);
            for i=1:max(T.Parent)
                %i
                ind = find(T.Parent==i);           
                for j=1:length(ind)
                    %j
                    task = T.Node{ind(j)};
                    nodes{ind(j)} = uitreenode(nodes{i},'Text',task);
                    if check_completion
                        thisTaskCompleted = sln_tissue.TaskCompleted & sprintf('task_name="%s"',task) & sprintf('tissue_id=%d',tissue_id);
                        if thisTaskCompleted.exists
                            set(nodes{ind(j)}, 'Icon', 'checkmark-64.png');
                            disp('found completed task');
                            %addStyle(app.TaskTree_noEdit, completed_style, 'node', nodes{ind(j)});
                        else
                            thisTaskAborted= sln_tissue.TaskAborted & sprintf('task_name="%s"',task) & sprintf('tissue_id=%d',tissue_id);
                            if thisTaskAborted.exists
                                set(nodes{ind(j)}, 'Icon', 'x-mark-64.png');
                                %addStyle(app.TaskTree_noEdit, aborted_style, 'node', nodes{ind(j)});
                            else
                                %addStyle(app.TaskTree_noEdit, todo_style, 'node', nodes{ind(j)});
                            end
                        end
                    end
                end
            end
        end
        
        function load_projects(app)
            projects = fetchn(sln_lab.ProjectActive, 'project_name');
            all_projects = fetchn(sln_lab.Project, 'project_name');
            set(app.AssigntoprojectDropDown,'items',['None'; projects]); 
            set(app.ByprojectDropDown,'items',['Any'; all_projects]);
        end

        function abort_task_and_children(app, cur_node, reason)
            key.tissue_id = app.get_selected_tissue_id();
            key.user_name = app.current_user;
            if nargin < 3
                key.reason = 'parent task aborted';
            else
                key.reason = reason;
            end
            key.task_name = get(cur_node,'Text');
            insert(sln_tissue.TaskAborted,key);

            ch = get(cur_node,'children');
            for i=1:length(ch)
                app.abort_task_and_children(ch(i));
            end
        end
        
        function is_finished = check_for_unfinished_tasks(app, tissue_id)
            assigned_macros = sln_tissue.Macro * sln_tissue.Assignment & sprintf('tissue_id=%d',tissue_id);
            is_finished = true;
            if ~assigned_macros.exists
                return
            end
            macro_names = fetchn(assigned_macros,'macro_name');
            for i=1:length(macro_names)
                m = sln_tissue.Macro & sprintf('macro_name="%s"', macro_names{i});
                T = m.getTaskTree();
                task_list = cellstr(strvcat(T.Node{2:end})); %remove root node that lists macro name
                for j=1:length(task_list)
                    completed = sln_tissue.TaskCompleted & sprintf('tissue_id=%d',tissue_id) & sprintf('task_name="%s"',task_list{j});
                    aborted = sln_tissue.TaskAborted & sprintf('tissue_id=%d',tissue_id) & sprintf('task_name="%s"',task_list{j});
                    if ~completed.exists && ~aborted.exists
                        is_finished = false;
                        return;
                    end
                end
            end

        end
        
        function load_reagents(app)
            reagent_list = fetchn(sln_tissue.StainReagent,'reagent_name');
            fluorescent_reagent_list = fetchn(sln_tissue.FluorescentReagent * sln_tissue.StainReagent,'reagent_name');
            set(app.Reagent1primaryDropDown,'items',reagent_list); 
            set(app.Reagent2secondaryDropDown,'items',['None'; fluorescent_reagent_list]); 
        end
        
        function tissue_ids = get_tissues_passing_filter(app)
            %First filter by the radio button group
            selection_filter = get(get(app.DisplayFilterButtonGroup,'SelectedObject'),'Text');
            switch selection_filter
                case 'Active'
                    q = sln_tissue.Tissue.active();
                case 'All'
                    q = sln_tissue.Tissue;
                otherwise %last X months
                    m = get(app.MonthsEditField,'value');
                    start_date = datestr(datetime('now') - calmonths(m),'yyyy-mm-dd');
                    q = sln_tissue.Tissue * sln_tissue.Assignment & sprintf('entry_time>"%s"',start_date);
            end
            type_filter = get(get(app.TissueTypeButtonGroup,'SelectedObject'),'Text');
            switch type_filter
                case 'Brain'
                    q = q & sln_tissue.Brain;
                case 'Retina'
                    q = q & sln_tissue.Retina;
                otherwise
                    %do nothing
            end

            %owner
            filter_owner = get(app.ByownerDropDown,'value');
            if ~strcmp(filter_owner,'Any')
                q = q & sprintf('owner="%s"',filter_owner);
            end

            %project
            filter_project = get(app.ByprojectDropDown,'value');
            if ~strcmp(filter_project,'Any')
                q = q * sln_tissue.Assignment & sprintf('project_name="%s"',filter_project);
            end

            tissue_ids = fetchn(q, 'tissue_id');
            
        end
        
        function load_macros_for_selected_tissue(app, selectedRow)
            D = get(app.TaskTable,'Data');
            Nrows = size(D,1);
            
            if selectedRow>Nrows
                return;
            end
            
            %first clear tree
            ch = get(app.TaskTree_noEdit,'children');
            for i=1:length(ch)
                delete(ch(i));
            end

            tissue_id = app.get_selected_tissue_id();

            %add style to this row
            addStyle(app.TaskTable,app.selectedStyle,'row',selectedRow);

            %put other rows back to normal
            otherRows = setdiff(1:Nrows,selectedRow);
            addStyle(app.TaskTable,app.unselectedStyle,'row',otherRows);

            if isempty(tissue_id)
                set(app.CurrentTissueMacrosListBox,'items',{});
            else
                %load macro list for brain or retina
                thisBrain = sln_tissue.Brain & sprintf('tissue_id=%d',tissue_id);
                %thisRetina = sln_tissue.Retina & sprintf('tissue_id=%d',tissue_id);
                if thisBrain.exists
                    app.load_macros('Brain')
                else
                    app.load_macros('Retina');
                end

                assigned_macros = sln_tissue.Assignment & sprintf('tissue_id=%d',tissue_id);
                if assigned_macros.exists
                    macros = fetchn(assigned_macros,'macro_name');
                    set(app.CurrentTissueMacrosListBox,'items',macros);
                    set(app.CurrentTissueMacrosListBox,'value',macros{1});
                    app.CurrentTissueMacrosListBoxValueChanged([]);
                else
                    set(app.CurrentTissueMacrosListBox,'items',{});
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %datajoint setup
            setenv('DJ_HOST', 'vfsmdatajoint01.fsm.northwestern.edu')
            if isdeployed
                setenv('DJ_USER', 'WebAppServer')
                setenv('DJ_PASS', 'uu8ok7ucz3Pi')
            else
                %TODO
            end
            %setenv('DJ_PASS', 'uu8ok7ucz3Pi')
            C=dj.conn();
            
            app.init(false);
        end

        % Value changed function: AnimalDropDown
        function AnimalDropDownValueChanged(app, event)
            value = app.AnimalDropDown.Value;
            if strcmp(value,'other')
                set(app.OtherAnimalEditField,'visible',true);
            else
                set(app.OtherAnimalEditField,'visible',false);
            end
        end

        % Button pushed function: AddtissueButton
        function AddtissueButtonPushed(app, event)
            selected_animal = app.AnimalDropDown.Value;
            if strcmp(selected_animal,'other')
                key.animal_id = get(app.OtherAnimalEditField,'value');
            else
                key.animal_id = str2double(extractBefore(selected_animal, ':'));
            end
            key.owner = get(app.OwnerDropDown,'value');
            
            selected_tissue_type = get(get(app.TissueTypeToAddButtonGroup,'SelectedObject'),'Text');
            switch selected_tissue_type
                case 'Brain'
                    tissue_id = sln_tissue.add_tissue(key, 'Brain');
                case 'L retina'
                    key.side = 'Left';
                    tissue_id = sln_tissue.add_tissue(key, 'Retina');
                case 'R retina'
                    key.side = 'Right';
                    tissue_id = sln_tissue.add_tissue(key, 'Retina');
                case 'Unknown retina'
                    disp('Not implemented');
                    %TODO: this is a pain. Will need to add new eyes and
                    %such
                    %key.side = 'UnknownA';
                    %tissue_id = add_tissue(key, 'Retina');
                    tissue_id = 0;
                otherwise
                    tissue_id = 0;
            end
            if tissue_id
                thisTissue = sln_tissue.Tissue & sprintf('tissue_id=%d',tissue_id);
                thisTissue.activate();
            end
            app.load_tissue_table();
            app.load_tasks();
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            %TODO: password part
            username = get(app.ChangeusertoDropDown,'value');            
            if strcmp(username, 'viewer') %special user required for this
                password = 'ACDBom8WZ7Wz';
            else
                password = app.user_password;
            end

            login_success = false;
            try
                C = dj.conn('vfsmdatajoint01.fsm.northwestern.edu',username,password, [], true);
                login_success = C.isConnected;
            catch ME
                fprintf('Connection to database failed for user %s: %s\n', username, ME.message);
            end
            if login_success
                app.current_user = get(app.ChangeusertoDropDown,'value');
                set(app.UserEditField, 'value', app.current_user);
                set(app.OwnerDropDown,'value',app.current_user);
                app.set_permissions();
            end
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            app.current_user = 'viewer';
            app.user_password = '';
            set(app.PasswordEditField,'value','');
            set(app.UserEditField, 'value', app.current_user);
            set(app.ChangeusertoDropDown,'value','viewer');
            app.set_permissions();
        end

        % Window button down function: TissueDBUIFigure
        function TissueDBUIFigureWindowButtonDown(app, event)
            app.reset_logout_timer();
        end

        % Key press function: TissueDBUIFigure
        function TissueDBUIFigureKeyPress(app, event)
            key = event.Key;
            app.reset_logout_timer();
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            app.init(true);
        end

        % Value changed function: TasktypeDropDown
        function TasktypeDropDownValueChanged(app, event)
            value = app.TasktypeDropDown.Value;
            switch value
                case 'Analyze'
                    set(app.AnalysisoutputsTextArea,'visible',true);
                    set(app.AnalysisoutputsLabel,'visible',true)
                    set(app.ThicknessmEditField,'visible',false);
                    set(app.ThicknessmEditFieldLabel,'visible',false);
                    set(app.ScopeDropDown,'visible',false);
                    set(app.ScopeDropDownLabel,'visible',false)
                    set(app.Reagent1primaryDropDown,'visible',false);
                    set(app.Reagent1primaryDropDownLabel,'visible',false);
                    set(app.Reagent2secondaryDropDown,'visible',false);
                    set(app.Reagent2secondaryDropDownLabel,'visible',false);
                    set(app.ColorchannelDropDown,'visible',false);
                    set(app.ColorchannelDropDownLabel,'visible',false);
                    set(app.BrainareaDropDown,'visible',false);
                    set(app.BrainareaDropDownLabel,'visible',false);
                    set(app.CellnumberEditField,'visible',false);
                    set(app.CellnumberEditFieldLabel,'visible',false);
                    set(app.ClearingprotocolEditField,'visible',false);
                    set(app.ClearingprotocolLabel,'visible',false);
                    set(app.HostspeciesLabel,'visible',false);
                    set(app.TargetspeciesLabel,'visible',false);
                    set(app.ColorLabel,'visible',false);
                    set(app.ColorLabel_2,'visible',false);
                    set(app.HostEditField,'visible',false);
                    set(app.TargetEditField,'visible',false);
                    set(app.Color1_EditField,'visible',false);
                    set(app.Color2_EditField,'visible',false);
                    set(app.TargetLabel, 'visible', false);
                    set(app.AntibodyTargetEditField,'visible',false);

                case 'ImageAxonTerminals'
                    set(app.AnalysisoutputsTextArea,'visible',false);
                    set(app.AnalysisoutputsLabel,'visible',false)
                    set(app.ThicknessmEditField,'visible',false);
                    set(app.ThicknessmEditFieldLabel,'visible',false);
                    set(app.ScopeDropDown,'visible',true);
                    set(app.ScopeDropDownLabel,'visible',true)
                    set(app.Reagent1primaryDropDown,'visible',false);
                    set(app.Reagent1primaryDropDownLabel,'visible',false);
                    set(app.Reagent2secondaryDropDown,'visible',false);
                    set(app.Reagent2secondaryDropDownLabel,'visible',false);
                    set(app.ColorchannelDropDown,'visible',true);
                    set(app.ColorchannelDropDownLabel,'visible',true);
                    set(app.BrainareaDropDown,'visible',true)
                    set(app.BrainareaDropDownLabel,'visible',true)
                    set(app.CellnumberEditField,'visible',false)
                    set(app.CellnumberEditFieldLabel,'visible',false)
                    set(app.ClearingprotocolEditField,'visible',false);
                    set(app.ClearingprotocolLabel,'visible',false);
                    set(app.HostspeciesLabel,'visible',false);
                    set(app.TargetspeciesLabel,'visible',false);
                    set(app.ColorLabel,'visible',false);
                    set(app.ColorLabel_2,'visible',false);
                    set(app.ColorLabel_2,'visible',false);
                    set(app.HostEditField,'visible',false);
                    set(app.TargetEditField,'visible',false);
                    set(app.Color1_EditField,'visible',false);
                    set(app.Color2_EditField,'visible',false);
                    set(app.TargetLabel, 'visible', false);
                    set(app.AntibodyTargetEditField,'visible',false);

                case 'ImageCellMorphology'
                    set(app.AnalysisoutputsTextArea,'visible',false);
                    set(app.AnalysisoutputsLabel,'visible',false)
                    set(app.ThicknessmEditField,'visible',false);
                    set(app.ThicknessmEditFieldLabel,'visible',false);
                    set(app.ScopeDropDown,'visible',true);
                    set(app.ScopeDropDownLabel,'visible',true)
                    set(app.Reagent1primaryDropDown,'visible',false);
                    set(app.Reagent1primaryDropDownLabel,'visible',false);
                    set(app.Reagent2secondaryDropDown,'visible',false);
                    set(app.Reagent2secondaryDropDownLabel,'visible',false);
                    set(app.ColorchannelDropDown,'visible',true);
                    set(app.ColorchannelDropDownLabel,'visible',true);
                    set(app.BrainareaDropDown,'visible',false);
                    set(app.BrainareaDropDownLabel,'visible',false);
                    set(app.CellnumberEditField,'visible',true);
                    set(app.CellnumberEditFieldLabel,'visible',true);
                    set(app.ClearingprotocolEditField,'visible',false);
                    set(app.ClearingprotocolLabel,'visible',false);
                    set(app.HostspeciesLabel,'visible',false);
                    set(app.TargetspeciesLabel,'visible',false);
                    set(app.ColorLabel,'visible',false);
                    set(app.ColorLabel_2,'visible',false);
                    set(app.ColorLabel_2,'visible',false);
                    set(app.HostEditField,'visible',false);
                    set(app.TargetEditField,'visible',false);
                    set(app.Color1_EditField,'visible',false);
                    set(app.Color2_EditField,'visible',false);
                    set(app.TargetLabel, 'visible', false);
                    set(app.AntibodyTargetEditField,'visible',false);

                case 'ImageRetinaInColorChannel'
                    set(app.AnalysisoutputsTextArea,'visible',false);
                    set(app.AnalysisoutputsLabel,'visible',false)
                    set(app.ThicknessmEditField,'visible',false);
                    set(app.ThicknessmEditFieldLabel,'visible',false);
                    set(app.ScopeDropDown,'visible',true);
                    set(app.ScopeDropDownLabel,'visible',true)
                    set(app.Reagent1primaryDropDown,'visible',false);
                    set(app.Reagent1primaryDropDownLabel,'visible',false);
                    set(app.Reagent2secondaryDropDown,'visible',false);
                    set(app.Reagent2secondaryDropDownLabel,'visible',false);
                    set(app.ColorchannelDropDown,'visible',true);
                    set(app.ColorchannelDropDownLabel,'visible',true);
                    set(app.BrainareaDropDown,'visible',false);
                    set(app.BrainareaDropDownLabel,'visible',false);
                    set(app.CellnumberEditField,'visible',false);
                    set(app.CellnumberEditFieldLabel,'visible',false);
                    set(app.ClearingprotocolEditField,'visible',false);
                    set(app.ClearingprotocolLabel,'visible',false);
                    set(app.HostspeciesLabel,'visible',false);
                    set(app.TargetspeciesLabel,'visible',false);
                    set(app.ColorLabel,'visible',false);
                    set(app.ColorLabel_2,'visible',false);
                    set(app.ColorLabel_2,'visible',false);
                    set(app.HostEditField,'visible',false);
                    set(app.TargetEditField,'visible',false);
                    set(app.Color1_EditField,'visible',false);
                    set(app.Color2_EditField,'visible',false);
                    set(app.TargetLabel, 'visible', false);
                    set(app.AntibodyTargetEditField,'visible',false);

                case 'Slice'
                    set(app.AnalysisoutputsTextArea,'visible',false);
                    set(app.AnalysisoutputsLabel,'visible',false)
                    set(app.ThicknessmEditField,'visible',true);
                    set(app.ThicknessmEditFieldLabel,'visible',true);
                    set(app.ScopeDropDown,'visible',false);
                    set(app.ScopeDropDownLabel,'visible',false)
                    set(app.Reagent1primaryDropDown,'visible',false);
                    set(app.Reagent1primaryDropDownLabel,'visible',false);
                    set(app.Reagent2secondaryDropDown,'visible',false);
                    set(app.Reagent2secondaryDropDownLabel,'visible',false);
                    set(app.ColorchannelDropDown,'visible',false);
                    set(app.ColorchannelDropDownLabel,'visible',false);
                    set(app.BrainareaDropDown,'visible',true);
                    set(app.BrainareaDropDownLabel,'visible',true);
                    set(app.CellnumberEditField,'visible',false);
                    set(app.CellnumberEditFieldLabel,'visible',false);
                    set(app.ClearingprotocolEditField,'visible',false);
                    set(app.ClearingprotocolLabel,'visible',false);
                    set(app.HostspeciesLabel,'visible',false);
                    set(app.TargetspeciesLabel,'visible',false);
                    set(app.ColorLabel,'visible',false);
                    set(app.ColorLabel_2,'visible',false);
                    set(app.ColorLabel_2,'visible',false);
                    set(app.HostEditField,'visible',false);
                    set(app.TargetEditField,'visible',false);
                    set(app.Color1_EditField,'visible',false);
                    set(app.Color2_EditField,'visible',false);
                    set(app.TargetLabel, 'visible', false);
                    set(app.AntibodyTargetEditField,'visible',false);

                case 'Stain'
                    set(app.AnalysisoutputsTextArea,'visible',false);
                    set(app.AnalysisoutputsLabel,'visible',false)
                    set(app.ThicknessmEditField,'visible',false);
                    set(app.ThicknessmEditFieldLabel,'visible',false);
                    set(app.ScopeDropDown,'visible',false);
                    set(app.ScopeDropDownLabel,'visible',false)
                    set(app.Reagent1primaryDropDown,'visible',true);
                    set(app.Reagent1primaryDropDownLabel,'visible',true);
                    set(app.Reagent2secondaryDropDown,'visible',true);
                    set(app.Reagent2secondaryDropDownLabel,'visible',true);
                    set(app.ColorchannelDropDown,'visible',false);
                    set(app.ColorchannelDropDownLabel,'visible',false);
                    set(app.BrainareaDropDown,'visible',false);
                    set(app.BrainareaDropDownLabel,'visible',false);
                    set(app.CellnumberEditField,'visible',false);
                    set(app.CellnumberEditFieldLabel,'visible',false);
                    set(app.ClearingprotocolEditField,'visible',false);
                    set(app.ClearingprotocolLabel,'visible',false);
                    app.load_reagents();
                    set(app.HostspeciesLabel,'visible',true);
                    set(app.TargetspeciesLabel,'visible',true);
                    set(app.ColorLabel,'visible',true);
                    set(app.ColorLabel_2,'visible',true);
                    set(app.HostEditField,'visible',true);
                    set(app.TargetEditField,'visible',true);
                    set(app.Color1_EditField,'visible',true);
                    set(app.Color2_EditField,'visible',true);
                    app.Reagent1primaryDropDownValueChanged([]);
                    app.Reagent2secondaryDropDownValueChanged([]);

                case 'TraceCell'
                    set(app.AnalysisoutputsTextArea,'visible',false);
                    set(app.AnalysisoutputsLabel,'visible',false)
                    set(app.ThicknessmEditField,'visible',false);
                    set(app.ThicknessmEditFieldLabel,'visible',false);
                    set(app.ScopeDropDown,'visible',false);
                    set(app.ScopeDropDownLabel,'visible',false)
                    set(app.Reagent1primaryDropDown,'visible',false);
                    set(app.Reagent1primaryDropDownLabel,'visible',false);
                    set(app.Reagent2secondaryDropDown,'visible',false);
                    set(app.Reagent2secondaryDropDownLabel,'visible',false);
                    set(app.ColorchannelDropDown,'visible',false);
                    set(app.ColorchannelDropDownLabel,'visible',false);
                    set(app.BrainareaDropDown,'visible',false);
                    set(app.BrainareaDropDownLabel,'visible',false);
                    set(app.CellnumberEditField,'visible',true);
                    set(app.CellnumberEditFieldLabel,'visible',true);
                    set(app.ClearingprotocolEditField,'visible',false);
                    set(app.ClearingprotocolLabel,'visible',false);
                    set(app.HostspeciesLabel,'visible',false);
                    set(app.TargetspeciesLabel,'visible',false);
                    set(app.ColorLabel,'visible',false);
                    set(app.ColorLabel_2,'visible',false);
                    set(app.ColorLabel_2,'visible',false);
                    set(app.HostEditField,'visible',false);
                    set(app.TargetEditField,'visible',false);
                    set(app.Color1_EditField,'visible',false);
                    set(app.Color2_EditField,'visible',false);
                    set(app.TargetLabel, 'visible', false);
                    set(app.AntibodyTargetEditField,'visible',false);

                case 'Clear'
                    set(app.AnalysisoutputsTextArea,'visible',false);
                    set(app.AnalysisoutputsLabel,'visible',false)
                    set(app.ThicknessmEditField,'visible',false);
                    set(app.ThicknessmEditFieldLabel,'visible',false);
                    set(app.ScopeDropDown,'visible',false);
                    set(app.ScopeDropDownLabel,'visible',false)
                    set(app.Reagent1primaryDropDown,'visible',false);
                    set(app.Reagent1primaryDropDownLabel,'visible',false);
                    set(app.Reagent2secondaryDropDown,'visible',false);
                    set(app.Reagent2secondaryDropDownLabel,'visible',false);
                    set(app.ColorchannelDropDown,'visible',false);
                    set(app.ColorchannelDropDownLabel,'visible',false);
                    set(app.BrainareaDropDown,'visible',false);
                    set(app.BrainareaDropDownLabel,'visible',false);
                    set(app.CellnumberEditField,'visible',false);
                    set(app.CellnumberEditFieldLabel,'visible',false);
                    set(app.ClearingprotocolEditField,'visible',true);
                    set(app.ClearingprotocolLabel,'visible',true);
                    set(app.HostspeciesLabel,'visible',false);
                    set(app.TargetspeciesLabel,'visible',false);
                    set(app.ColorLabel,'visible',false);
                    set(app.ColorLabel_2,'visible',false);
                    set(app.ColorLabel_2,'visible',false);
                    set(app.HostEditField,'visible',false);
                    set(app.TargetEditField,'visible',false);
                    set(app.Color1_EditField,'visible',false);
                    set(app.Color2_EditField,'visible',false);
                    set(app.TargetLabel, 'visible', false);
                    set(app.AntibodyTargetEditField,'visible',false);

                case 'TraceTerminals'
                    set(app.AnalysisoutputsTextArea,'visible',false);
                    set(app.AnalysisoutputsLabel,'visible',false)
                    set(app.ThicknessmEditField,'visible',false);
                    set(app.ThicknessmEditFieldLabel,'visible',false);
                    set(app.ScopeDropDown,'visible',false);
                    set(app.ScopeDropDownLabel,'visible',false)
                    set(app.Reagent1primaryDropDown,'visible',false);
                    set(app.Reagent1primaryDropDownLabel,'visible',false);
                    set(app.Reagent2secondaryDropDown,'visible',false);
                    set(app.Reagent2secondaryDropDownLabel,'visible',false);
                    set(app.ColorchannelDropDown,'visible',false);
                    set(app.ColorchannelDropDownLabel,'visible',false);
                    set(app.BrainareaDropDown,'visible',true);
                    set(app.BrainareaDropDownLabel,'visible',true);
                    set(app.CellnumberEditField,'visible',false);
                    set(app.CellnumberEditFieldLabel,'visible',false);
                    set(app.ClearingprotocolEditField,'visible',false);
                    set(app.ClearingprotocolLabel,'visible',false);
                    set(app.HostspeciesLabel,'visible',false);
                    set(app.TargetspeciesLabel,'visible',false);
                    set(app.ColorLabel,'visible',false);
                    set(app.ColorLabel_2,'visible',false);
                    set(app.ColorLabel_2,'visible',false);
                    set(app.HostEditField,'visible',false);
                    set(app.TargetEditField,'visible',false);
                    set(app.Color1_EditField,'visible',false);
                    set(app.Color2_EditField,'visible',false);
                    set(app.TargetLabel, 'visible', false);
                    set(app.AntibodyTargetEditField,'visible',false);
            end
        end

        % Button pushed function: CreateButton_task
        function CreateButton_taskPushed(app, event)
            key = struct;
            key.task_name = get(app.TasknameEditField,'value');
            key.task_type = get(app.TasktypeDropDown,'value');
            try
                switch key.task_type
                    case 'Analyze'
                        outputs = strjoin(get(app.AnalysisoutputsTextArea, 'value'),', ');
                        key.outputs = outputs;

                    case 'ImageAxonTerminals'
                        key.target = get(app.BrainareaDropDown,'value');
                        key.color_channel_name = get(app.ColorchannelDropDown,'value');
                        key.scope_name = get(app.ScopeDropDown,'value');

                    case 'ImageCellMorphology'
                        key.color_channel_name = get(app.ColorchannelDropDown,'value');
                        key.scope_name = get(app.ScopeDropDown,'value');
                        key.cell_number = get(app.CellnumberEditField,'value');

                    case 'ImageRetinaInColorChannel'
                        key.color_channel_name = get(app.ColorchannelDropDown,'value');
                        key.scope_name = get(app.ScopeDropDown,'value');

                    case 'Slice'
                        key.target = get(app.BrainareaDropDown,'value');
                        key.thickness = get(app.ThicknessmEditField,'value');

                    case 'Stain'
                        key.reagent_1 = fetch1(sln_tissue.StainReagent & sprintf('reagent_name="%s"',get(app.Reagent1primaryDropDown,'value')), 'reagent_id');
                        reagent_2 = get(app.Reagent2secondaryDropDown,'value');
                        if ~strcmp(reagent_2,'None')
                            key.reagent_2 = fetch1(sln_tissue.StainReagent & sprintf('reagent_name="%s"',reagent_2), 'reagent_id');

                            %check for matching hosts
                            primary_host = get(app.HostEditField,'value');
                            secondary_target = get(app.TargetEditField,'value');
                            if ~strcmp(primary_host, secondary_target)
                                error('Host species must match');
                            end
                        end

                    case 'Clear'
                        key.clearing_protocol = get(app.ClearingprotocolEditField,'value');

                    case 'TraceCell'
                        key.cell_number = get(app.CellnumberEditField,'value');

                    case 'TraceTerminals'
                        key.target = get(app.BrainareaDropDown,'value');

                end
            catch key_set_error
                set(app.StatusEdit_task,'value',sprintf('Task insert failed: %s',key_set_error.message));
                set(app.StatusLamp_task,'color','red');
                return
            end
            try
                sln_tissue.add_task(key,key.task_type);
                app.load_tasks();
                set(app.StatusLamp_task,'color','green');
                set(app.StatusEdit_task,'value',sprintf('Task insert success.'));
                pause(2);
                set(app.StatusLamp_task,'color','white');
                set(app.StatusEdit_task,'value','');
            catch ME
                set(app.StatusEdit_task,'value',sprintf('Task insert failed: %s',ME.message));
                set(app.StatusLamp_task,'color','red');
            end

        end

        % Button pushed function: MoveupButton
        function MoveupButtonPushed(app, event)
            selection = get(app.TaskTree,'SelectedNodes');
            if isempty(selection)
                return;
            end
            selectedNode = selection(1);
            siblings = app.get_siblings(selectedNode);
            for i=1:length(siblings)
                set(siblings(i),'parent',selectedNode);
            end
            
        end

        % Button pushed function: MovedownButton
        function MovedownButtonPushed(app, event)
            selection = get(app.TaskTree,'SelectedNodes');
            if isempty(selection)
                return;
            end
            selectedNode = selection(1);
            children = get(selectedNode,'children');
            parentNode = get(selectedNode,'parent');
            for i=1:length(children)
                set(children(i),'parent',parentNode);
            end
        end

        % Button pushed function: CompletetaskButton
        function CompletetaskButtonPushed(app, event)
            key.tissue_id = app.get_selected_tissue_id();
            selected_task = get(app.TaskTree_noEdit,'SelectedNodes');
            key.task_name = get(selected_task,'Text');
            key.user_name = app.current_user;
            notes = get(app.NotesTextArea_CompleteOrAbort,'value');
            key.task_completion_notes = strcat(cell2mat(notes'));
            key
            try
                insert(sln_tissue.TaskCompleted,key);
                disp('Task complete success');
                %recolor task tree
                app.CurrentTissueMacrosListBoxValueChanged([]);
                is_finished = app.check_for_unfinished_tasks(key.tissue_id)
                if is_finished
                    thisTissue = sln_tissue.Tissue & sprintf('tissue_id=%d',key.tissue_id);
                    thisTissue.deactivate();
                    app.load_tissue_table();
                end
            catch ME
                fprintf('Task complete failed: %s\n', ME.message);
            end
        end

        % Button pushed function: AborttaskButton
        function AborttaskButtonPushed(app, event)
            key.tissue_id = app.get_selected_tissue_id();
            selected_task = get(app.TaskTree_noEdit,'SelectedNodes');
            key.task_name = get(selected_task,'Text');
            key.user_name = app.current_user;
            notes = get(app.NotesTextArea_CompleteOrAbort,'value');
            reason = strcat(cell2mat(notes'));
            %also abort children of this node
            key
            try
            app.abort_task_and_children(selected_task,reason);
            disp('Task abort success');
            app.CurrentTissueMacrosListBoxValueChanged([]);
            is_finished = app.check_for_unfinished_tasks(key.tissue_id)
            if is_finished
                thisTissue = sln_tissue.Tissue & sprintf('tissue_id=%d',key.tissue_id);
                thisTissue.deactivate();
                app.load_tissue_table();
            end
            catch ME
                fprintf('Task abort failed: %s\n', ME.message);
            end
        end

        % Value changed function: RetinaBrainSwitch
        function RetinaBrainSwitchValueChanged(app, event)
            app.clear_task_tree();
        end

        % Button pushed function: TaskToMacroButton
        function TaskToMacroButtonPushed(app, event)
            selected_items = get(app.TaskListBox,'value');
            for i=1:length(selected_items)
                uitreenode(app.TaskTree,'Text',selected_items{i});
            end
        end

        % Button pushed function: DropTaskButton
        function DropTaskButtonPushed(app, event)
            selection = get(app.TaskTree,'SelectedNodes');
            if isempty(selection)
                return;
            end
            selectedNode = selection(1);
            selectedNode.delete();
        end

        % Button pushed function: CreateButton_macro
        function CreateButton_macroPushed(app, event)
            T = app.export_full_task_tree();
            macro_name = get(app.MacronameEditField,'value');
            key.macro_name = macro_name;
            key.tissue_type = get(app.RetinaBrainSwitch,'value');
            key.task_tree = getByteStreamFromArray(T);
            insert(sln_tissue.Macro,key);
            app.load_macros('All');
        end

        % Button pushed function: AddMacroToTissueButton
        function AddMacroToTissueButtonPushed(app, event)
            tissue_id = app.get_selected_tissue_id;
            if ~isempty(tissue_id)
                key.tissue_id = tissue_id;
                key.macro_name = get(app.MacrolistListBox,'value');
                project_name = get(app.AssigntoprojectDropDown,'value');
                if ~strcmp(project_name,'None')
                    key.project_name = project_name;
                end
                insert(sln_tissue.Assignment,key);
                assigned_macros = sln_tissue.Assignment & sprintf('tissue_id=%d',tissue_id);
                set(app.CurrentTissueMacrosListBox,'items',fetchn(assigned_macros,'macro_name'));
                app.load_tissue_table();
                app.TaskTableSelectionChanged([]);
            end
        end

        % Value changed function: MacrolistListBox
        function MacrolistListBoxValueChanged(app, event)
            value = app.MacrolistListBox.Value;
            
        end

        % Button pushed function: ShowtasktreeButton
        function ShowtasktreeButtonPushed(app, event)
            selected_macro = app.MacrolistListBox.Value;
            q = sln_tissue.Macro & sprintf('macro_name="%s"', selected_macro);
            T = q.getTaskTree;
            app.set_uitasktree_from_tree(T,false);
            style = uistyle('FontColor','b');
            addStyle(app.TaskTree_noEdit,style);
        end

        % Value changed function: CurrentTissueMacrosListBox
        function CurrentTissueMacrosListBoxValueChanged(app, event)
            selected_macro = app.CurrentTissueMacrosListBox.Value;
            q = sln_tissue.Macro & sprintf('macro_name="%s"', selected_macro);
            T = q.getTaskTree;
            style = uistyle('FontColor','k');
            addStyle(app.TaskTree_noEdit,style);
            app.set_uitasktree_from_tree(T,true);
        end

        % Selection changed function: TaskTable
        function TaskTableSelectionChanged(app, event)
             if ~isempty(event)
                indices = event.Selection;
                if ~isempty(indices)
                    selectedRow = indices(1,1);
                     app.load_macros_for_selected_tissue(selectedRow)
                end
             end
        end

        % Selection changed function: DisplayFilterButtonGroup
        function DisplayFilterButtonGroupSelectionChanged(app, event)
            app.load_tissue_table();
        end

        % Button pushed function: DeactivatetissueswithnotasksButton
        function DeactivatetissueswithnotasksButtonPushed(app, event)
            tissue_ids = app.get_tissues_passing_filter();
            N_tissues = length(tissue_ids);

            for i=1:N_tissues
                is_finished = app.check_for_unfinished_tasks(tissue_ids(i));
                if is_finished
                    thisTissue = sln_tissue.Tissue & sprintf('tissue_id=%d',tissue_ids(i));
                    thisTissue.deactivate();
                end
            end
            app.load_tissue_table();
        end

        % Button pushed function: AddnoteforthistissueButton
        function AddnoteforthistissueButtonPushed(app, event)
            notes = get(app.NoteTextArea,'value');
            key.note_text = strcat(cell2mat(notes'));
            key.user_name = app.current_user;
            key.tissue_id = app.get_selected_tissue_id();
            key
            if ~isempty(key.tissue_id)
                insert(sln_tissue.Note,key);
                set(app.NoteTextArea,'value','');
            end
        end

        % Selection changed function: TissueTypeButtonGroup
        function TissueTypeButtonGroupSelectionChanged(app, event)
            app.load_tissue_table();            
        end

        % Value changed function: Reagent1primaryDropDown
        function Reagent1primaryDropDownValueChanged(app, event)
            reagent_name = app.Reagent1primaryDropDown.Value;
            thisReagent = sln_tissue.StainReagent & sprintf('reagent_name="%s"',reagent_name);
            this_fluorescent_reagent = sln_tissue.FluorescentReagent & thisReagent;
            if this_fluorescent_reagent.exists
                reagent_struct = fetch(this_fluorescent_reagent, '*');
                set(app.TargetLabel, 'visible', false);
                set(app.AntibodyTargetEditField,'visible',false);
                set(app.Color1_EditField, 'visible', true);
                set(app.ColorLabel,'visible',true);                
                set(app.Color1_EditField, 'value', reagent_struct.color_channel_name);
                if ~isempty(reagent_struct.host_species)
                    set(app.HostspeciesLabel,'visible',true);
                    set(app.HostEditField,'visible',true);
                    set(app.HostEditField,'value',reagent_struct.host_species);
                else
                    set(app.HostspeciesLabel,'visible',false);
                    set(app.HostEditField,'visible',false);
                end
                return;
            end

            this_antibody = sln_tissue.Antibody & thisReagent;
            if this_antibody.exists
                reagent_struct = fetch(this_antibody, '*');
                set(app.Color1_EditField, 'visible', false);
                set(app.ColorLabel,'visible',false);
                set(app.TargetLabel, 'visible', true);
                set(app.AntibodyTargetEditField,'visible',true);
                set(app.AntibodyTargetEditField,'value',reagent_struct.antibody_target);

                set(app.HostspeciesLabel,'visible',true);
                set(app.HostEditField,'visible',true);
                set(app.HostEditField,'value',reagent_struct.host_species);
                return;
            end

        end

        % Value changed function: Reagent2secondaryDropDown
        function Reagent2secondaryDropDownValueChanged(app, event)
            reagent_name = app.Reagent2secondaryDropDown.Value;
            set(app.TargetspeciesLabel,'visible',false);
            set(app.TargetEditField,'visible',false);
            set(app.ColorLabel_2,'visible',false);
            set(app.Color2_EditField,'visible',false);

            if strcmp(reagent_name,'None')
                return;
            end

            thisReagent = sln_tissue.StainReagent & sprintf('reagent_name="%s"',reagent_name);
            this_fluorescent_reagent = sln_tissue.FluorescentReagent & thisReagent;
            if this_fluorescent_reagent.exists
                reagent_struct = fetch(this_fluorescent_reagent, '*');
                set(app.Color2_EditField, 'visible', true);
                set(app.ColorLabel_2,'visible',true);                
                set(app.Color2_EditField, 'value', reagent_struct.color_channel_name);
                if ~isempty(reagent_struct.target_species)
                    set(app.TargetspeciesLabel,'visible',true);
                    set(app.TargetEditField,'visible',true);
                    set(app.TargetEditField,'value',reagent_struct.target_species);
                else
                    set(app.HostspeciesLabel,'visible',false);
                    set(app.HostEditField,'visible',false);
                end
                return;
            end
        end

        % Value changed function: ByownerDropDown
        function ByownerDropDownValueChanged(app, event)
            app.load_tissue_table();
        end

        % Value changed function: ByprojectDropDown
        function ByprojectDropDownValueChanged(app, event)
            app.load_tissue_table();
        end

        % Value changing function: PasswordEditField
        function PasswordEditFieldValueChanging(app, event)
            val = event.Value;
            current_pass_length=length(val); %get the number of characters present in password edit field
            app.prev_password_length=length(app.user_password);

            %if password edit field is empty and is in focus
            if isempty(val)
                app.user_password='';   %set the password property to empty string
                app.prev_password_length=0;  %set the previous password length to zero
                app.PasswordEditField.Value='';  %set the value in edit field to empty string

                %if backspace is clicked by user
            elseif(current_pass_length<app.prev_password_length)
                app.user_password = app.user_password(1:current_pass_length);
                app.PasswordEditField.Value = repelem('*', current_pass_length);
            else
                %remove the previous available '*' from the password value obtained from the app
                add_string=erase(val, repelem('*', length(app.user_password)));

                %append the chacter to user_passowrd property
                app.user_password=append(app.user_password,add_string);

                %mask the password in edit field with the total length of characters stored in user_password
                app.PasswordEditField.Value = repelem('*', length(app.user_password));
            end
        end

        % Cell selection callback: TaskTable
        function TaskTableCellSelection(app, event)
            if ~isempty(event)
                indices = event.Indices;
                if ~isempty(indices)
                    selectedRow = indices(1,1);
                    app.load_macros_for_selected_tissue(selectedRow);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TissueDBUIFigure = uifigure('Visible', 'off');
            app.TissueDBUIFigure.Name = 'TissueDB';
            app.TissueDBUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @TissueDBUIFigureWindowButtonDown, true);
            app.TissueDBUIFigure.KeyPressFcn = createCallbackFcn(app, @TissueDBUIFigureKeyPress, true);
            app.TaskTable = uitable(app.TissueDBUIFigure);
            app.TaskTable.ColumnName = {'Entry date'; 'Type'; 'Side'; 'Animal DJ ID'; 'Owner'; 'Projects'; 'Macros'; 'Notes'};
            app.TaskTable.RowName = {};
            app.TaskTable.ColumnSortable = [true true true true true true true false];
            app.TaskTable.CellSelectionCallback = createCallbackFcn(app, @TaskTableCellSelection, true);
            app.TaskTable.SelectionChangedFcn = createCallbackFcn(app, @TaskTableSelectionChanged, true);
            app.CreatenewtaskPanel = uipanel(app.TissueDBUIFigure);
            app.CreatenewtaskPanel.Title = 'Create new task';
            app.TasknameEditFieldLabel = uilabel(app.CreatenewtaskPanel);
            app.TasknameEditFieldLabel.Text = 'Task name';
            app.TasknameEditField = uieditfield(app.CreatenewtaskPanel, 'text');
            app.TasktypeDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.TasktypeDropDownLabel.Text = 'Task type';
            app.TasktypeDropDown = uidropdown(app.CreatenewtaskPanel);
            app.TasktypeDropDown.Items = {'Analyze', 'Clear', 'ImageAxonTerminals', 'ImageCellMorphology', 'ImageRetinaInColorChannel', 'Slice', 'Stain', 'TraceCell', 'TraceTerminals'};
            app.TasktypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TasktypeDropDownValueChanged, true);
            app.TasktypeDropDown.Value = 'Analyze';
            app.Reagent2secondaryDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.Reagent2secondaryDropDownLabel.Text = 'Reagent 2 / secondary';
            app.Reagent2secondaryDropDown = uidropdown(app.CreatenewtaskPanel);
            app.Reagent2secondaryDropDown.ValueChangedFcn = createCallbackFcn(app, @Reagent2secondaryDropDownValueChanged, true);
            app.ScopeDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.ScopeDropDownLabel.Text = 'Scope';
            app.ScopeDropDown = uidropdown(app.CreatenewtaskPanel);
            app.CreateButton_task = uibutton(app.CreatenewtaskPanel, 'push');
            app.CreateButton_task.ButtonPushedFcn = createCallbackFcn(app, @CreateButton_taskPushed, true);
            app.CreateButton_task.Text = 'Create';
            app.AnalysisoutputsLabel = uilabel(app.CreatenewtaskPanel);
            app.AnalysisoutputsLabel.Text = 'Analysis outputs:';
            app.AnalysisoutputsTextArea = uitextarea(app.CreatenewtaskPanel);
            app.ColorchannelDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.ColorchannelDropDownLabel.Text = 'Color channel';
            app.ColorchannelDropDown = uidropdown(app.CreatenewtaskPanel);
            app.ThicknessmEditFieldLabel = uilabel(app.CreatenewtaskPanel);
            app.ThicknessmEditFieldLabel.Text = 'Thickness (μm)';
            app.ThicknessmEditField = uieditfield(app.CreatenewtaskPanel, 'numeric');
            app.ThicknessmEditField.Limits = [0 1000];
            app.Reagent1primaryDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.Reagent1primaryDropDownLabel.Text = 'Reagent 1 / primary';
            app.Reagent1primaryDropDown = uidropdown(app.CreatenewtaskPanel);
            app.Reagent1primaryDropDown.ValueChangedFcn = createCallbackFcn(app, @Reagent1primaryDropDownValueChanged, true);
            app.BrainareaDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.BrainareaDropDownLabel.Text = 'Brain area';
            app.BrainareaDropDown = uidropdown(app.CreatenewtaskPanel);
            app.CellnumberEditFieldLabel = uilabel(app.CreatenewtaskPanel);
            app.CellnumberEditFieldLabel.Text = 'Cell number';
            app.CellnumberEditField = uieditfield(app.CreatenewtaskPanel, 'numeric');
            app.CellnumberEditField.Limits = [0 Inf];
            app.StatusLamp_task = uilamp(app.CreatenewtaskPanel);
            app.StatusEdit_task = uieditfield(app.CreatenewtaskPanel, 'text');
            app.StatusEdit_task.Editable = 'off';
            app.ClearingprotocolEditField = uieditfield(app.CreatenewtaskPanel, 'text');
            app.ClearingprotocolEditField.Visible = 'off';
            app.ClearingprotocolLabel = uilabel(app.CreatenewtaskPanel);
            app.ClearingprotocolLabel.Visible = 'off';
            app.ClearingprotocolLabel.Text = 'Clearing protocol';
            app.HostspeciesLabel = uilabel(app.CreatenewtaskPanel);
            app.HostspeciesLabel.Text = 'Host species';
            app.TargetspeciesLabel = uilabel(app.CreatenewtaskPanel);
            app.TargetspeciesLabel.Visible = 'off';
            app.TargetspeciesLabel.Text = 'Target species';
            app.ColorLabel = uilabel(app.CreatenewtaskPanel);
            app.ColorLabel.Text = 'Color';
            app.ColorLabel_2 = uilabel(app.CreatenewtaskPanel);
            app.ColorLabel_2.Visible = 'off';
            app.ColorLabel_2.Text = 'Color';
            app.HostEditField = uieditfield(app.CreatenewtaskPanel, 'text');
            app.HostEditField.Editable = 'off';
            app.HostEditField.Visible = 'off';
            app.TargetEditField = uieditfield(app.CreatenewtaskPanel, 'text');
            app.TargetEditField.Editable = 'off';
            app.TargetEditField.Visible = 'off';
            app.Color1_EditField = uieditfield(app.CreatenewtaskPanel, 'text');
            app.Color1_EditField.Editable = 'off';
            app.Color1_EditField.Visible = 'off';
            app.Color2_EditField = uieditfield(app.CreatenewtaskPanel, 'text');
            app.Color2_EditField.Editable = 'off';
            app.Color2_EditField.Visible = 'off';
            app.TargetLabel = uilabel(app.CreatenewtaskPanel);
            app.TargetLabel.Text = 'Target';
            app.AntibodyTargetEditField = uieditfield(app.CreatenewtaskPanel, 'text');
            app.AntibodyTargetEditField.Visible = 'off';
            app.CompletetaskButton = uibutton(app.TissueDBUIFigure, 'push');
            app.CompletetaskButton.ButtonPushedFcn = createCallbackFcn(app, @CompletetaskButtonPushed, true);
            app.CompletetaskButton.Text = 'Complete task';
            app.NotesTextAreaLabel = uilabel(app.TissueDBUIFigure);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea_CompleteOrAbort = uitextarea(app.TissueDBUIFigure);
            app.ChangeusertoDropDownLabel = uilabel(app.TissueDBUIFigure);
            app.ChangeusertoDropDownLabel.Text = 'Change user to';
            app.ChangeusertoDropDown = uidropdown(app.TissueDBUIFigure);
            app.UserEditFieldLabel = uilabel(app.TissueDBUIFigure);
            app.UserEditFieldLabel.Text = 'User';
            app.UserEditField = uieditfield(app.TissueDBUIFigure, 'text');
            app.UserEditField.Editable = 'off';
            app.PasswordEditFieldLabel = uilabel(app.TissueDBUIFigure);
            app.PasswordEditFieldLabel.Text = 'Password:';
            app.PasswordEditField = uieditfield(app.TissueDBUIFigure, 'text');
            app.PasswordEditField.ValueChangingFcn = createCallbackFcn(app, @PasswordEditFieldValueChanging, true);
            app.LoginButton = uibutton(app.TissueDBUIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.LogoutButton = uibutton(app.TissueDBUIFigure, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            app.AddtissueButton = uibutton(app.TissueDBUIFigure, 'push');
            app.AddtissueButton.ButtonPushedFcn = createCallbackFcn(app, @AddtissueButtonPushed, true);
            app.AddtissueButton.Text = 'Add tissue';
            app.AnimalDropDownLabel = uilabel(app.TissueDBUIFigure);
            app.AnimalDropDownLabel.Text = 'Animal';
            app.AnimalDropDown = uidropdown(app.TissueDBUIFigure);
            app.AnimalDropDown.ValueChangedFcn = createCallbackFcn(app, @AnimalDropDownValueChanged, true);
            app.OtherAnimalEditField = uieditfield(app.TissueDBUIFigure, 'numeric');
            app.ReloadButton = uibutton(app.TissueDBUIFigure, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.AborttaskButton = uibutton(app.TissueDBUIFigure, 'push');
            app.AborttaskButton.ButtonPushedFcn = createCallbackFcn(app, @AborttaskButtonPushed, true);
            app.AborttaskButton.Text = 'Abort task';
            app.OwnerDropDownLabel = uilabel(app.TissueDBUIFigure);
            app.OwnerDropDownLabel.Text = 'Owner';
            app.OwnerDropDown = uidropdown(app.TissueDBUIFigure);
            app.CreatenewmacroPanel = uipanel(app.TissueDBUIFigure);
            app.CreatenewmacroPanel.Title = 'Create new macro';
            app.MacronameEditFieldLabel = uilabel(app.CreatenewmacroPanel);
            app.MacronameEditFieldLabel.Text = 'Macro name';
            app.MacronameEditField = uieditfield(app.CreatenewmacroPanel, 'text');
            app.CreateButton_macro = uibutton(app.CreatenewmacroPanel, 'push');
            app.CreateButton_macro.ButtonPushedFcn = createCallbackFcn(app, @CreateButton_macroPushed, true);
            app.CreateButton_macro.Text = 'Create';
            app.StatusLamp_macro = uilamp(app.CreatenewmacroPanel);
            app.StatusEdit_macro = uieditfield(app.CreatenewmacroPanel, 'text');
            app.StatusEdit_macro.Editable = 'off';
            app.TaskListBox = uilistbox(app.CreatenewmacroPanel);
            app.TaskListBox.Multiselect = 'on';
            app.TaskListBox.Value = {'Item 1'};
            app.TasklistLabel = uilabel(app.CreatenewmacroPanel);
            app.TasklistLabel.Text = 'Task list';
            app.TaskTree = uitree(app.CreatenewmacroPanel);
            app.MoveupButton = uibutton(app.CreatenewmacroPanel, 'push');
            app.MoveupButton.ButtonPushedFcn = createCallbackFcn(app, @MoveupButtonPushed, true);
            app.MoveupButton.Text = 'Move up';
            app.MovedownButton = uibutton(app.CreatenewmacroPanel, 'push');
            app.MovedownButton.ButtonPushedFcn = createCallbackFcn(app, @MovedownButtonPushed, true);
            app.MovedownButton.Text = 'Move down';
            app.TaskToMacroButton = uibutton(app.CreatenewmacroPanel, 'push');
            app.TaskToMacroButton.ButtonPushedFcn = createCallbackFcn(app, @TaskToMacroButtonPushed, true);
            app.TaskToMacroButton.Text = '<-';
            app.DropTaskButton = uibutton(app.CreatenewmacroPanel, 'push');
            app.DropTaskButton.ButtonPushedFcn = createCallbackFcn(app, @DropTaskButtonPushed, true);
            app.DropTaskButton.Text = 'Drop';
            app.RetinaBrainSwitch = uiswitch(app.CreatenewmacroPanel, 'slider');
            app.RetinaBrainSwitch.Items = {'Brain', 'Retina'};
            app.RetinaBrainSwitch.ValueChangedFcn = createCallbackFcn(app, @RetinaBrainSwitchValueChanged, true);
            app.RetinaBrainSwitch.Value = 'Brain';
            app.TasktreeforthismacroLabel = uilabel(app.CreatenewmacroPanel);
            app.TasktreeforthismacroLabel.Text = 'Task tree for this macro';
            app.CurrentTissueMacrosListBox = uilistbox(app.TissueDBUIFigure);
            app.CurrentTissueMacrosListBox.Items = {};
            app.CurrentTissueMacrosListBox.ValueChangedFcn = createCallbackFcn(app, @CurrentTissueMacrosListBoxValueChanged, true);
            app.CurrentTissueMacrosListBox.Value = {};
            app.TaskTree_noEdit = uitree(app.TissueDBUIFigure);
            app.MacrosforthistissueLabel = uilabel(app.TissueDBUIFigure);
            app.MacrosforthistissueLabel.Text = 'Macros for this tissue';
            app.TasktreeLabel = uilabel(app.TissueDBUIFigure);
            app.TasktreeLabel.Text = 'Task tree';
            app.MacrolistListBoxLabel = uilabel(app.TissueDBUIFigure);
            app.MacrolistListBoxLabel.Text = 'Macro list';
            app.MacrolistListBox = uilistbox(app.TissueDBUIFigure);
            app.MacrolistListBox.Items = {};
            app.MacrolistListBox.ValueChangedFcn = createCallbackFcn(app, @MacrolistListBoxValueChanged, true);
            app.MacrolistListBox.Value = {};
            app.AddMacroToTissueButton = uibutton(app.TissueDBUIFigure, 'push');
            app.AddMacroToTissueButton.ButtonPushedFcn = createCallbackFcn(app, @AddMacroToTissueButtonPushed, true);
            app.AddMacroToTissueButton.Text = 'Add macro to tissue';
            app.ShowtasktreeButton = uibutton(app.TissueDBUIFigure, 'push');
            app.ShowtasktreeButton.ButtonPushedFcn = createCallbackFcn(app, @ShowtasktreeButtonPushed, true);
            app.ShowtasktreeButton.Text = 'Show task tree';
            app.AssigntoprojectDropDownLabel = uilabel(app.TissueDBUIFigure);
            app.AssigntoprojectDropDownLabel.Text = 'Assign to project';
            app.AssigntoprojectDropDown = uidropdown(app.TissueDBUIFigure);
            app.DisplayFilterButtonGroup = uibuttongroup(app.TissueDBUIFigure);
            app.DisplayFilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayFilterButtonGroupSelectionChanged, true);
            app.ActiveButton = uiradiobutton(app.DisplayFilterButtonGroup);
            app.ActiveButton.Text = 'Active';
            app.ActiveButton.Value = true;
            app.AllButton = uiradiobutton(app.DisplayFilterButtonGroup);
            app.AllButton.Text = 'All';
            app.LastButton = uiradiobutton(app.DisplayFilterButtonGroup);
            app.LastButton.Text = 'Last';
            app.monthsLabel = uilabel(app.DisplayFilterButtonGroup);
            app.monthsLabel.Text = 'months';
            app.MonthsEditField = uieditfield(app.DisplayFilterButtonGroup, 'numeric');
            app.ShowLabel = uilabel(app.TissueDBUIFigure);
            app.ShowLabel.Text = 'Show';
            app.DeactivatetissueswithnotasksButton = uibutton(app.TissueDBUIFigure, 'push');
            app.DeactivatetissueswithnotasksButton.ButtonPushedFcn = createCallbackFcn(app, @DeactivatetissueswithnotasksButtonPushed, true);
            app.DeactivatetissueswithnotasksButton.Text = 'Deactivate tissues with no tasks';
            app.NoteTextArea = uitextarea(app.TissueDBUIFigure);
            app.AddnoteforthistissueButton = uibutton(app.TissueDBUIFigure, 'push');
            app.AddnoteforthistissueButton.ButtonPushedFcn = createCallbackFcn(app, @AddnoteforthistissueButtonPushed, true);
            app.AddnoteforthistissueButton.Text = 'Add note for this tissue';
            app.TissueTypeButtonGroup = uibuttongroup(app.TissueDBUIFigure);
            app.TissueTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TissueTypeButtonGroupSelectionChanged, true);
            app.AllButton_2 = uiradiobutton(app.TissueTypeButtonGroup);
            app.AllButton_2.Text = 'All';
            app.AllButton_2.Value = true;
            app.BrainButton = uiradiobutton(app.TissueTypeButtonGroup);
            app.BrainButton.Text = 'Brain';
            app.RetinaButton = uiradiobutton(app.TissueTypeButtonGroup);
            app.RetinaButton.Text = 'Retina';
            app.ByownerDropDownLabel = uilabel(app.TissueDBUIFigure);
            app.ByownerDropDownLabel.Text = 'By owner';
            app.ByownerDropDown = uidropdown(app.TissueDBUIFigure);
            app.ByownerDropDown.ValueChangedFcn = createCallbackFcn(app, @ByownerDropDownValueChanged, true);
            app.ByprojectDropDownLabel = uilabel(app.TissueDBUIFigure);
            app.ByprojectDropDownLabel.Text = 'By project';
            app.ByprojectDropDown = uidropdown(app.TissueDBUIFigure);
            app.ByprojectDropDown.ValueChangedFcn = createCallbackFcn(app, @ByprojectDropDownValueChanged, true);
            app.TissueTypeToAddButtonGroup = uibuttongroup(app.TissueDBUIFigure);
            app.LretinaButton = uiradiobutton(app.TissueTypeToAddButtonGroup);
            app.LretinaButton.Text = 'L retina';
            app.LretinaButton.Value = true;
            app.RretinaButton = uiradiobutton(app.TissueTypeToAddButtonGroup);
            app.RretinaButton.Text = 'R retina';
            app.BrainButton_2 = uiradiobutton(app.TissueTypeToAddButtonGroup);
            app.BrainButton_2.Text = 'Brain';
            % Show the figure after all components are created
            app.TissueDBUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TissueDB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TissueDBUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TissueDBUIFigure)
        end
    end
end