classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SchwartzLabDJLoginUIFigure  matlab.ui.Figure
        UserDropDownLabel           matlab.ui.control.Label
        UserDropDown                matlab.ui.control.DropDown
        PasswordEditFieldLabel      matlab.ui.control.Label
        PasswordEditField           matlab.ui.control.EditField
        LoginButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
         user_password=''; %property that will store the password entered by user
         prev_password_length=0; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %datajoint setup
            setenv('DJ_USER', 'sl_user')
            setenv('DJ_HOST', 'vfsmdatajoint01.fsm.northwestern.edu')
            setenv('DJ_PASS', 'r0zsNB0xk6wc5BgA!')
            dj.conn();

            %load user names
            users = fetchn(sl.User,'user_name'); %eventually sl.UserActive
            set(app.UserDropDown,'items',users);            
        end

        % Value changing function: PasswordEditField
        function PasswordEditFieldValueChanging(app, event)
            val = event.Value;
            current_pass_length=length(val); %get the number of characters present in password edit field
            app.prev_password_length=length(app.user_password);
            
            %if password edit field is empty and is in focus
            if isempty(val)
                app.user_password='';   %set the password property to empty string
                app.prev_password_length=0;  %set the previous password length to zero
                app.PasswordEditField.Value='';  %set the value in edit field to empty string
                
            %if backspace is clicked by user  
            elseif(current_pass_length<app.prev_password_length)
                app.user_password = app.user_password(1:current_pass_length);
                app.PasswordEditField.Value = repelem('*', current_pass_length); 
            else
                %remove the previous available '*' from the password value obtained from the app
                add_string=erase(val, repelem('*', length(app.user_password)));
                
                %append the chacter to user_passowrd property
                app.user_password=append(app.user_password,add_string);
                
                %mask the password in edit field with the total length of characters stored in user_password
                app.PasswordEditField.Value = repelem('*', length(app.user_password));
            end
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            %disp(['password = ' app.user_password]);
            currentUser = get(app.UserDropDown, 'value');
            match_pwd = fetch1(sl.User & ['user_name=' '"' currentUser '"'], 'password');
            if isempty(match_pwd)
                disp(['User ' currentUser ' does not have login access']);            
            elseif strcmp(app.user_password, match_pwd)
                disp('login successful');
                AnimalLogger(currentUser);
                delete(app);
            else
                disp('login failed');
            end
        end

        % Close request function: SchwartzLabDJLoginUIFigure
        function SchwartzLabDJLoginUIFigureCloseRequest(app, event)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SchwartzLabDJLoginUIFigure = uifigure('Visible', 'off');
            app.SchwartzLabDJLoginUIFigure.Name = 'SchwartzLab DJ Login';
            app.SchwartzLabDJLoginUIFigure.CloseRequestFcn = createCallbackFcn(app, @SchwartzLabDJLoginUIFigureCloseRequest, true);
            app.UserDropDownLabel = uilabel(app.SchwartzLabDJLoginUIFigure);
            app.UserDropDownLabel.Text = 'User';
            app.UserDropDown = uidropdown(app.SchwartzLabDJLoginUIFigure);
            app.PasswordEditFieldLabel = uilabel(app.SchwartzLabDJLoginUIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.SchwartzLabDJLoginUIFigure, 'text');
            app.PasswordEditField.ValueChangingFcn = createCallbackFcn(app, @PasswordEditFieldValueChanging, true);
            app.LoginButton = uibutton(app.SchwartzLabDJLoginUIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            % Show the figure after all components are created
            app.SchwartzLabDJLoginUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SchwartzLabDJLoginUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SchwartzLabDJLoginUIFigure)
        end
    end
end