classdef MP_quick_browse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FilterbyyearButton  matlab.ui.control.Button
        YearEditField       matlab.ui.control.NumericEditField
        YearEditFieldLabel  matlab.ui.control.Label
        SafemodeCheckBox    matlab.ui.control.CheckBox
        ViewButton          matlab.ui.control.Button
        SaveButton          matlab.ui.control.Button
        UITable             matlab.ui.control.Table
        UIAxes              matlab.ui.control.UIAxes
    end


    properties (Access = private)
        data_table % Description
    end

    methods (Access = private)
        function fetch_table(app , yr)
            a = fetch(sln_symphony.UserParamCellCcQuality, '*');
            if nargin < 2
                b = fetch(sln_symphony.Dataset * sln_cell.CellName & (sln_symphony.DatasetEpoch * sln_symphony.ExperimentEpochBlock & 'protocol_name = "multi_pulse"'), '*');
            else
                disp('here')
                yr_str = extractAfter(string(yr), 2);
                q_str = sprintf('sln_symphony.Dataset * sln_cell.CellName & (sln_symphony.DatasetEpoch * sln_symphony.ExperimentEpochBlock & ''protocol_name = "multi_pulse" AND file_name LIKE "____%s_"'')', yr_str);
                q = eval(q_str);
                b = fetch(q, '*');
            end

            a_T = struct2table(a);
            b_T = struct2table(b);

            c_T = outerjoin(b_T, a_T, 'Keys', {'file_name', 'source_id'}, 'Type', 'left', 'RightVariables', 'cc_quality');

            d = fetch(sln_symphony.UserParamDatasetExtraMp, '*');
            d_T = struct2table(d);

            app.data_table = outerjoin(c_T, d_T, 'Keys', {'file_name', 'source_id', 'dataset_name'}, 'Type', 'left', 'RightVariables', 'extra_mp');
            app.data_table.cc_quality = categorical(app.data_table.cc_quality);
            app.data_table.cc_quality = fillmissing(app.data_table.cc_quality, 'constant', 'unassigned');
            app.data_table.extra_mp = categorical(app.data_table.extra_mp);
            app.data_table.extra_mp = fillmissing(app.data_table.extra_mp, 'constant', 'unassigned');

        end

        function plot_current_dataset(app, selected_row)
            if nargin < 2
                selected_row = app.UITable.DisplaySelection(1);
            end

            s.file_name = app.UITable.DisplayData{selected_row, 'file_name'}{:};
            s.source_id = app.UITable.DisplayData.source_id(selected_row);
            s.dataset_name = app.UITable.DisplayData{selected_row, 'dataset_name'}{:};

            app.UIFigure.set('Name', 'running...');

            dataset_epoch = sln_symphony.ExperimentEpochChannel * sln_symphony.DatasetEpoch * sln_symphony.ExperimentElectrode ...
                * sln_symphony.ExperimentChannel & s & 'channel_name = "Amp1"';

            dataset_epoch_data = fetch(dataset_epoch, '*');

            raw_data = vertcat(dataset_epoch_data.raw_data);
            sample_rate = dataset_epoch_data(1).sample_rate;
            time_axis = [0: size(raw_data, 2) - 1] ./ sample_rate / 1000;
            title_text = sprintf('%s source id = %s %s', s.file_name, string(s.source_id), s.dataset_name);

            plot(app.UIAxes ,time_axis, raw_data');
            title(app.UIAxes, title_text, 'Interpreter', 'none');
            xlabel(app.UIAxes,'Time (s)');
            ylabel(app.UIAxes, 'V_m (mV)');

            app.UIFigure.set('Name', 'Done');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fetch_table();
            app.UITable.Data = app.data_table;
            app.UITable.ColumnName = {'File name', 'Source ID', 'Cell name', 'Dataset name', 'CCQuality', 'ExtraMP?'};
            app.UITable.ColumnEditable = [false false false false true true];
            app.UITable.SelectionType = "row";

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            changed_data = setdiff(app.UITable.DisplayData, app.data_table);
            for i = 1:height(changed_data)
                cc_qual_struct.file_name = changed_data{i, 'file_name'}{:};
                cc_qual_struct.source_id = changed_data.source_id(i);
                cc_qual_struct.cc_quality = string(changed_data.cc_quality(i));

                extra_MP_struct.file_name = changed_data{i, 'file_name'}{:};
                extra_MP_struct.source_id = changed_data.source_id(i);
                extra_MP_struct.dataset_name = changed_data{i, 'dataset_name'}{:};
                if changed_data.extra_mp(i) == 'unassigned'
                    extra_MP_struct.extra_mp = 'F';
                else
                    extra_MP_struct.extra_mp = string(changed_data.extra_mp(i));
                end

                s = sprintf('Inserting %s %s %s %s', changed_data{i, 'cell_name'}{:}, cc_qual_struct.cc_quality, extra_MP_struct.dataset_name, extra_MP_struct.extra_mp);

                if app.SafemodeCheckBox.Value
                    answer = questdlg(s, 'Insert?', 'Continue', 'Skip', 'Cancel', 'Continue');
                    switch answer
                        case 'Continue'
                            try
                                insert(sln_symphony.UserParamCellCcQuality, cc_qual_struct);
          
                            catch ME
                                disp(ME);
                                
                            end

                             try
                                
                                insert(sln_symphony.UserParamDatasetExtraMp, extra_MP_struct, 'replace');
                            catch ME
                                disp(ME);
                                continue
                            end
                        case 'Continue'
                            continue
                        case 'Cancel'
                            break
                    end
                else
                    try
                        insert(sln_symphony.UserParamCellCcQuality, cc_qual_struct);
                        
                    catch ME
                        disp(ME);
                        
                    end
                    
                     try
                        
                        insert(sln_symphony.UserParamDatasetExtraMp, extra_MP_struct, 'replace');
                    catch ME
                        disp(ME);
                        continue
                    end

                end
            end
            %delete(app.UIFigure);
            %app1();
            app.startupFcn();
            app.UITable.removeStyle();
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            %newDisplayData = app.UITable.DisplayData;
            %[~, idx] = setdiff(newDisplayData, app.data_table);
            %addStyle(app.UITable, uistyle("BackgroundColor","#fa9fb5"), 'row', idx);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            addStyle(app.UITable, uistyle("BackgroundColor","#fa9fb5"), 'cell', indices);
        end

        % Double-clicked callback: UITable
        function UITableDoubleClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            app.plot_current_dataset(displayRow);


        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            selected_row = app.UITable.DisplaySelection(1);
            app.plot_current_dataset(selected_row);
        end

        % Button pushed function: FilterbyyearButton
        function FilterbyyearButtonPushed(app, event)
            yr = app.YearEditField.Value
            app.fetch_table(yr);
            app.UITable.Data = app.data_table;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @UITableDoubleClicked, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ViewButton = uibutton(app.UIFigure, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.SafemodeCheckBox = uicheckbox(app.UIFigure);
            app.SafemodeCheckBox.Text = 'Safe mode?';
            app.SafemodeCheckBox.Value = true;
            app.YearEditFieldLabel = uilabel(app.UIFigure);
            app.YearEditFieldLabel.Text = 'Year';
            app.YearEditField = uieditfield(app.UIFigure, 'numeric');
            app.YearEditField.Value = 2026;
            app.FilterbyyearButton = uibutton(app.UIFigure, 'push');
            app.FilterbyyearButton.ButtonPushedFcn = createCallbackFcn(app, @FilterbyyearButtonPushed, true);
            app.FilterbyyearButton.Text = 'Filter by year';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MP_quick_browse

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end