classdef CellImageViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CellImageViewerUIFigure       matlab.ui.Figure
        AssignButton                  matlab.ui.control.Button
        CelltypeDropDown              matlab.ui.control.DropDown
        CelltypeDropDownLabel         matlab.ui.control.Label
        CellclassDropDown             matlab.ui.control.DropDown
        CellclassDropDownLabel        matlab.ui.control.Label
        SkeletononlyCheckBox          matlab.ui.control.CheckBox
        LoadButton                    matlab.ui.control.Button
        NewCellEditField              matlab.ui.control.EditField
        LoadnewcellbynameorunidLabel  matlab.ui.control.Label
        CurrentcellEditField          matlab.ui.control.EditField
        CurrentcellEditFieldLabel     matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        SkeletonTab                   matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        ax_strat                      matlab.ui.control.UIAxes
        ax_skel_side                  matlab.ui.control.UIAxes
        ax_skel_enface                matlab.ui.control.UIAxes
        ImagesTab                     matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        Panel_chat                    matlab.ui.container.Panel
        Panel_genetic                 matlab.ui.container.Panel
        Panel_cell_fill               matlab.ui.container.Panel
        ax_chat_side                  matlab.ui.control.UIAxes
        ax_genetic_side               matlab.ui.control.UIAxes
        ax_cell_fill_side             matlab.ui.control.UIAxes
        ax_chat_enface                matlab.ui.control.UIAxes
        ax_genetic_enface             matlab.ui.control.UIAxes
        ax_cell_fill_enface           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        loaded_cell_unid = nan; % loaded cell's unid
    end
    
    methods (Access = private)
        
        function loadCell(app, cell_identifier, morph_only)
            if nargin<3
                morph_only = false;
            end
            if isnumeric(cell_identifier)
                match = sln_cell.Cell & sprintf('cell_unid=%d',cell_identifier);
            else
                match = sln_cell.Cell * sln_cell.CellName & sprintf('cell_name="%s"',cell_identifier);
            end
           
            if match.count ~= 1
                error("Error loading cell");
            end

            cell_unid = fetch1(match,'cell_unid');
            app.loaded_cell_unid = cell_unid;
            cell_type_query = sln_cell.AssignType.current & sprintf('cell_unid=%d',cell_unid);
            if ~cell_type_query.exists
                cell_type = 'unclassified';
                cell_class = 'other';
            else
                cell_type = fetch1(cell_type_query,'cell_type');
                cell_class = fetch1(cell_type_query,'cell_class');                
            end

            app.CellclassDropDown.Value = cell_class;
            app.CelltypeDropDown.Value = cell_type;

            morph_data = sln_image.RetinalCellMorphology & sprintf('cell_unid=%d',cell_unid);
            if morph_data.count == 1
                morph_data_struct = fetch(morph_data,'nodes_flattened','strat_x','strat_y_norm','lower_surface_z','upper_surface_z');
                morph_data_found = true;
            else
                fprintf('No morphology data found for cell %d\n',cell_unid);
                morph_data_found = false;
            end

            image_data_found = false;
            if ~morph_only
                image_match = sln_image.Image * sln_image.RetinalCellImage & sprintf('cell_unid=%d',cell_unid);
                if image_match.count > 1
                    fprintf('Multiple matching images found for cell %d\n',cell_unid);
                    fprintf('Image chooser needs to be implemented.\n');
                elseif image_match.count == 1
                    image_data_found = true;
                    disp('Fetching image data...');
                    image_data = fetch(image_match,'*');
                    disp('Done.');
                    cell_fill_id = fetch1(sln_image.ChannelType & 'channel_content="cell fill"', 'channel_type_id');
                    genetic_or_viral_id = fetch1(sln_image.ChannelType & 'channel_content="genetic or viral label"', 'channel_type_id');
                    chat_id = fetch1(sln_image.ChannelType & 'channel_content="ChAT"', 'channel_type_id');
                    cell_fill_side = [];
                    genetic_or_viral_side = [];
                    chat_side = [];
                    for i=1:image_data.n_channels
                        ch_field = sprintf('ch%d_type',i);
                        switch image_data.(ch_field) %TODO could have multiple cells filled
                            case cell_fill_id
                                cell_fill_full = flipud(image_data.raw_image(:,:,:,i));
                                cell_fill_enface = flipud(max(image_data.raw_image(:,:,:,i), [], 3));
                                cell_fill_side = flipud(squeeze(max(image_data.raw_image(:,:,:,i), [], 1))');
                            case genetic_or_viral_id
                                genetic_or_viral_full = flipud(image_data.raw_image(:,:,:,i));
                                genetic_or_viral_enface = flipud(max(image_data.raw_image(:,:,:,i), [], 3));
                                genetic_or_viral_side = flipud(squeeze(max(image_data.raw_image(:,:,:,i), [], 1))');
                            case chat_id
                                chat_full = flipud(image_data.raw_image(:,:,:,i));
                                chat_enface = flipud(max(image_data.raw_image(:,:,:,i), [], 3));
                                chat_side = flipud(squeeze(max(image_data.raw_image(:,:,:,i), [], 1))');
                        end

                    end
                else
                    fprintf('No matching image found for cell %d\n',cell_unid);
                end
            end
            if morph_data_found
                scatter(app.ax_skel_enface, ...
                    morph_data_struct.nodes_flattened(:,1),...
                    morph_data_struct.nodes_flattened(:,2),...
                    'ko','filled');
                axis(app.ax_skel_enface,'image');

                hold(app.ax_skel_side,'on');
                scatter(app.ax_skel_side,...
                    morph_data_struct.nodes_flattened(:,1),...
                    morph_data_struct.nodes_flattened(:,3),...
                    'ko','filled')
                line(app.ax_skel_side,[0, max(morph_data_struct.nodes_flattened(:,1))],...
                    [morph_data_struct.lower_surface_z morph_data_struct.lower_surface_z],...
                    'Color','r','linestyle','--')
                line(app.ax_skel_side,[0, max(morph_data_struct.nodes_flattened(:,1))],...
                    [morph_data_struct.upper_surface_z morph_data_struct.upper_surface_z],...
                    'Color','r','linestyle','--')
                ylim(app.ax_skel_side,[0, morph_data_struct.upper_surface_z * 1.5])
                hold(app.ax_skel_side,'off');

                hold(app.ax_strat, 'on');
                plot(app.ax_strat,morph_data_struct.strat_x,...
                    morph_data_struct.strat_y_norm,...
                    'k-','LineWidth',2);
                line(app.ax_strat,[0 0], [0 1], 'Color','r','linestyle','--');
                line(app.ax_strat, [1 1], [0 1], 'Color','r','linestyle','--');
                xlim(app.ax_strat,[-2 2]);                
                hold(app.ax_strat,'off');
            end
            
            if image_data_found && ~morph_only
                if ~isempty(cell_fill_side)
                    axis(app.ax_cell_fill_enface,'image');
                    imagesc(app.ax_cell_fill_enface,cell_fill_enface);

                    axis(app.ax_cell_fill_side,'image');
                    imagesc(app.ax_cell_fill_side,cell_fill_side);

                    sliceViewer(cell_fill_full,'Parent',app.Panel_cell_fill);
                end

                if ~isempty(genetic_or_viral_side)
                    axis(app.ax_genetic_enface,'image');
                    imagesc(app.ax_genetic_enface,genetic_or_viral_enface);

                    axis(app.ax_genetic_side,'image');
                    imagesc(app.ax_genetic_side,genetic_or_viral_side);

                    sliceViewer(genetic_or_viral_full,'Parent',app.Panel_genetic);
                end

                if ~isempty(chat_side)
                    axis(app.ax_cell_fill_enface,'image');
                    imagesc(app.ax_chat_enface,chat_enface);

                    axis(app.ax_chat_side,'image');
                    imagesc(app.ax_chat_side,chat_side);

                    sliceViewer(chat_full,'Parent',app.Panel_chat);
                end

            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, cell_identifier, morph_only)
            if nargin<3
                morph_only = true;
            end
            %load class and type
            class_names = unique(fetchn(sln_cell.CellType,'cell_class'));
            type_names = unique(fetchn(sln_cell.CellType,'cell_type'));
            app.CellclassDropDown.Items = class_names;
            app.CelltypeDropDown.Items = type_names;

            if nargin>1
                app.SkeletononlyCheckBox.Value = morph_only;
                if ~isnan(str2double(cell_identifier))                    
                    cell_identifier = str2double(cell_identifier);
                    cell_match = sln_cell.CellName * sln_cell.Cell & ...
                        sprintf('cell_unid=%d',cell_identifier);
                    if cell_match.exists
                        app.CurrentcellEditField.Value = fetch1(cell_match,'cell_name');
                    else
                        app.CurrentcellEditField.Value = num2str(cell_identifier);
                    end
                else
                    app.CurrentcellEditField.Value = num2str(cell_identifier);
                end
                app.loadCell(cell_identifier,app.SkeletononlyCheckBox.Value);                                
            end            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            new_cell_id = app.NewCellEditField.Value;
            if ~isnan(str2double(new_cell_id))
                new_cell_id = str2double(new_cell_id);
                cell_match = sln_cell.CellName * sln_cell.Cell & ...
                    sprintf('cell_unid=%d',new_cell_id);
                if cell_match.exists
                    app.CurrentcellEditField.Value = fetch1(cell_match,'cell_name');
                else
                    app.CurrentcellEditField.Value = num2str(new_cell_id);
                end
            else
                app.CurrentcellEditField.Value = num2str(new_cell_id);
            end
            app.loadCell(new_cell_id,app.SkeletononlyCheckBox.Value);
        end

        % Value changed function: SkeletononlyCheckBox
        function SkeletononlyCheckBoxValueChanged(app, event)
            value = app.SkeletononlyCheckBox.Value;
            cur_cell = app.CurrentcellEditField.Value;
            if ~isempty(str2double(cur_cell))
                app.loadCell(str2double(cur_cell),value);
            else
                app.loadCell(cur_cell,value);
            end
        end

        % Button pushed function: AssignButton
        function AssignButtonPushed(app, event)
            key.cell_class = app.CellclassDropDown.Value;
            key.cell_type = app.CelltypeDropDown.Value;
            C = dj.conn;
            key.user_name = C.user;
            key.cell_unid = app.loaded_cell_unid;
            key
            sln_cell.add_event(key,'AssignType');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CellImageViewerUIFigure = uifigure('Visible', 'off');
            app.CellImageViewerUIFigure.Name = 'CellImageViewer';
            app.TabGroup = uitabgroup(app.CellImageViewerUIFigure);
            app.SkeletonTab = uitab(app.TabGroup);
            app.SkeletonTab.Title = 'Skeleton';
            app.GridLayout2 = uigridlayout(app.SkeletonTab);
            app.ax_skel_enface = uiaxes(app.GridLayout2);
            title(app.ax_skel_enface, 'Skeleton en face')
            xlabel(app.ax_skel_enface, 'X (µm)')
            ylabel(app.ax_skel_enface, 'Y (µm)')
            app.ax_skel_enface.XLimitMethod = 'tight';
            app.ax_skel_enface.YLimitMethod = 'tight';
            app.ax_skel_side = uiaxes(app.GridLayout2);
            title(app.ax_skel_side, 'Side view')
            xlabel(app.ax_skel_side, 'X (µm)')
            ylabel(app.ax_skel_side, 'IPL depth')
            app.ax_skel_side.XLimitMethod = 'tight';
            app.ax_skel_side.YLimitMethod = 'tight';
            app.ax_strat = uiaxes(app.GridLayout2);
            title(app.ax_strat, 'Stratification')
            xlabel(app.ax_strat, 'IPL depth (norm.)')
            ylabel(app.ax_strat, 'Strat. (norm.)')
            app.ImagesTab = uitab(app.TabGroup);
            app.ImagesTab.Title = 'Images';
            app.GridLayout = uigridlayout(app.ImagesTab);
            app.ax_cell_fill_enface = uiaxes(app.GridLayout);
            title(app.ax_cell_fill_enface, 'Cell fill')
            app.ax_genetic_enface = uiaxes(app.GridLayout);
            title(app.ax_genetic_enface, 'Genetic / viral label')
            app.ax_chat_enface = uiaxes(app.GridLayout);
            title(app.ax_chat_enface, 'ChAT')
            app.ax_cell_fill_side = uiaxes(app.GridLayout);
            app.ax_genetic_side = uiaxes(app.GridLayout);
            app.ax_chat_side = uiaxes(app.GridLayout);
            app.Panel_cell_fill = uipanel(app.GridLayout);
            app.Panel_genetic = uipanel(app.GridLayout);
            app.Panel_chat = uipanel(app.GridLayout);
            app.CurrentcellEditFieldLabel = uilabel(app.CellImageViewerUIFigure);
            app.CurrentcellEditFieldLabel.Text = 'Current cell:';
            app.CurrentcellEditField = uieditfield(app.CellImageViewerUIFigure, 'text');
            app.CurrentcellEditField.Editable = 'off';
            app.LoadnewcellbynameorunidLabel = uilabel(app.CellImageViewerUIFigure);
            app.LoadnewcellbynameorunidLabel.Text = 'Load new cell (by name or unid):';
            app.NewCellEditField = uieditfield(app.CellImageViewerUIFigure, 'text');
            app.LoadButton = uibutton(app.CellImageViewerUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SkeletononlyCheckBox = uicheckbox(app.CellImageViewerUIFigure);
            app.SkeletononlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @SkeletononlyCheckBoxValueChanged, true);
            app.SkeletononlyCheckBox.Text = 'Skeleton only';
            app.CellclassDropDownLabel = uilabel(app.CellImageViewerUIFigure);
            app.CellclassDropDownLabel.Text = 'Cell class';
            app.CellclassDropDown = uidropdown(app.CellImageViewerUIFigure);
            app.CelltypeDropDownLabel = uilabel(app.CellImageViewerUIFigure);
            app.CelltypeDropDownLabel.Text = 'Cell type';
            app.CelltypeDropDown = uidropdown(app.CellImageViewerUIFigure);
            app.AssignButton = uibutton(app.CellImageViewerUIFigure, 'push');
            app.AssignButton.ButtonPushedFcn = createCallbackFcn(app, @AssignButtonPushed, true);
            app.AssignButton.Text = 'Assign';
            % Show the figure after all components are created
            app.CellImageViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellImageViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CellImageViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CellImageViewerUIFigure)
        end
    end
end