classdef ImageLoader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageLoaderUIFigure           matlab.ui.Figure
        TissueButtonGroup             matlab.ui.container.ButtonGroup
        BrainButton                   matlab.ui.control.RadioButton
        ReyeButton                    matlab.ui.control.RadioButton
        LeyeButton                    matlab.ui.control.RadioButton
        DJIDEditField                 matlab.ui.control.NumericEditField
        DJIDEditFieldLabel            matlab.ui.control.Label
        DimensionsButtonGroup         matlab.ui.container.ButtonGroup
        DButton_2                     matlab.ui.control.RadioButton
        DButton                       matlab.ui.control.RadioButton
        LoadButton                    matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        ScopeDropDown                 matlab.ui.control.DropDown
        ScopeDropDownLabel            matlab.ui.control.Label
        UserDropDown                  matlab.ui.control.DropDown
        UserDropDownLabel             matlab.ui.control.Label
        z_scaleEdit                   matlab.ui.control.EditField
        ForcezscalempixelleaveemptytoreaditfromeachfileEditFieldLabel  matlab.ui.control.Label
        Channel4contentDropDown       matlab.ui.control.DropDown
        Channel4contentDropDownLabel  matlab.ui.control.Label
        Channel3contentDropDown       matlab.ui.control.DropDown
        Channel3contentDropDownLabel  matlab.ui.control.Label
        Channel2contentDropDown       matlab.ui.control.DropDown
        Channel2contentDropDownLabel  matlab.ui.control.Label
        Channel1contentDropDown       matlab.ui.control.DropDown
        Channel1contentDropDownLabel  matlab.ui.control.Label
        ImagetypeButtonGroup          matlab.ui.container.ButtonGroup
        bothButton                    matlab.ui.control.RadioButton
        tifButton                     matlab.ui.control.RadioButton
        nd2Button                     matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            channel_types = fetchn(sln_image.ChannelType,'channel_content');
            users = fetchn(sln_lab.ActiveUser,'user_name');
            scopes = fetchn(sln_lab.Scope,'scope_name');
            app.Channel1contentDropDown.Items = [' '; channel_types];
            app.Channel1contentDropDown.Value = ' ';
            app.Channel2contentDropDown.Items = [' '; channel_types];
            app.Channel2contentDropDown.Value = ' ';
            app.Channel3contentDropDown.Items = [' '; channel_types];
            app.Channel3contentDropDown.Value = ' ';
            app.Channel4contentDropDown.Items = [' '; channel_types];
            app.Channel4contentDropDown.Value = ' ';

            app.UserDropDown.Items = users;
            C = dj.conn;
            if any(strcmp(users, C.user))
                app.UserDropDown.Value = C.user;
            end
            app.ScopeDropDown.Items = [' '; scopes];            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            loaderPrefs = struct;
            assignin("base","loaderPrefs",loaderPrefs);
            app.ImageLoaderUIFigureCloseRequest();
        end

        % Close request function: ImageLoaderUIFigure
        function ImageLoaderUIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            loaderPrefs = struct;
            loaderPrefs.ch1 = app.Channel1contentDropDown.Value;
            loaderPrefs.ch2 = app.Channel2contentDropDown.Value;
            loaderPrefs.ch3 = app.Channel3contentDropDown.Value;
            loaderPrefs.ch4 = app.Channel4contentDropDown.Value;
            loaderPrefs.user = app.UserDropDown.Value;
            loaderPrefs.scope = app.ScopeDropDown.Value;
            loaderPrefs.animal_id = app.DJIDEditField.Value;   
            if isempty(app.z_scaleEdit.Value)                
                loaderPrefs.z_scale = [];
            else
                loaderPrefs.z_scale = str2num(app.z_scaleEdit.Value);
            end
            obj = app.ImagetypeButtonGroup.SelectedObject;
            loaderPrefs.image_type = obj.Text;

            obj = app.DimensionsButtonGroup.SelectedObject;
            dims = obj.Text;

            obj = app.TissueButtonGroup.SelectedObject;
            loaderPrefs.tissue_type = obj.Text;

            %checks
            err = false;
            if strcmp(loaderPrefs.ch1, ' ')
                err = true;
                errordlg('Channel 1 must have a type');
            end

            if strcmp(loaderPrefs.scope, ' ')
                err = true;
                errordlg('You must select a scope');
            end

            if strcmp(loaderPrefs.user, ' ')
                err = true;
                errordlg('You must select a user');
            end

            if (strcmp(loaderPrefs.image_type, '.nd2') || strcmp(loaderPrefs.image_type, 'both')) && ...
                    isempty(loaderPrefs.z_scale) && strcmp(dims, '3D')
                err = true;
                errordlg('For .nd2 stacks you must enter a z scale');
            end

            if err
                loaderPrefs = struct;
                assignin("base","loaderPrefs",loaderPrefs);
            else
                assignin("base","loaderPrefs",loaderPrefs);
                app.ImageLoaderUIFigureCloseRequest();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageLoaderUIFigure = uifigure('Visible', 'off');
            app.ImageLoaderUIFigure.Name = 'ImageLoader';
            app.ImageLoaderUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImageLoaderUIFigureCloseRequest, true);
            app.ImagetypeButtonGroup = uibuttongroup(app.ImageLoaderUIFigure);
            app.ImagetypeButtonGroup.Title = 'Image type';
            app.nd2Button = uiradiobutton(app.ImagetypeButtonGroup);
            app.nd2Button.Text = '.nd2';
            app.nd2Button.Value = true;
            app.tifButton = uiradiobutton(app.ImagetypeButtonGroup);
            app.tifButton.Text = '.tif';
            app.bothButton = uiradiobutton(app.ImagetypeButtonGroup);
            app.bothButton.Text = 'both';
            app.Channel1contentDropDownLabel = uilabel(app.ImageLoaderUIFigure);
            app.Channel1contentDropDownLabel.Text = 'Channel 1 content';
            app.Channel1contentDropDown = uidropdown(app.ImageLoaderUIFigure);
            app.Channel2contentDropDownLabel = uilabel(app.ImageLoaderUIFigure);
            app.Channel2contentDropDownLabel.Text = 'Channel 2 content';
            app.Channel2contentDropDown = uidropdown(app.ImageLoaderUIFigure);
            app.Channel3contentDropDownLabel = uilabel(app.ImageLoaderUIFigure);
            app.Channel3contentDropDownLabel.Text = 'Channel 3 content';
            app.Channel3contentDropDown = uidropdown(app.ImageLoaderUIFigure);
            app.Channel4contentDropDownLabel = uilabel(app.ImageLoaderUIFigure);
            app.Channel4contentDropDownLabel.Text = 'Channel 4 content';
            app.Channel4contentDropDown = uidropdown(app.ImageLoaderUIFigure);
            app.ForcezscalempixelleaveemptytoreaditfromeachfileEditFieldLabel = uilabel(app.ImageLoaderUIFigure);
            app.ForcezscalempixelleaveemptytoreaditfromeachfileEditFieldLabel.Text = 'Force z scale (µm / pixel): leave empty to read it from each file (works for ScanImage .tifs)';
            app.z_scaleEdit = uieditfield(app.ImageLoaderUIFigure, 'text');
            app.UserDropDownLabel = uilabel(app.ImageLoaderUIFigure);
            app.UserDropDownLabel.Text = 'User';
            app.UserDropDown = uidropdown(app.ImageLoaderUIFigure);
            app.ScopeDropDownLabel = uilabel(app.ImageLoaderUIFigure);
            app.ScopeDropDownLabel.Text = 'Scope';
            app.ScopeDropDown = uidropdown(app.ImageLoaderUIFigure);
            app.CancelButton = uibutton(app.ImageLoaderUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.LoadButton = uibutton(app.ImageLoaderUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.DimensionsButtonGroup = uibuttongroup(app.ImageLoaderUIFigure);
            app.DimensionsButtonGroup.Title = 'Dimensions';
            app.DButton = uiradiobutton(app.DimensionsButtonGroup);
            app.DButton.Text = '3D';
            app.DButton.Value = true;
            app.DButton_2 = uiradiobutton(app.DimensionsButtonGroup);
            app.DButton_2.Text = '2D';
            app.DJIDEditFieldLabel = uilabel(app.ImageLoaderUIFigure);
            app.DJIDEditFieldLabel.Text = 'DJID';
            app.DJIDEditField = uieditfield(app.ImageLoaderUIFigure, 'numeric');
            app.TissueButtonGroup = uibuttongroup(app.ImageLoaderUIFigure);
            app.TissueButtonGroup.Title = 'Tissue';
            app.LeyeButton = uiradiobutton(app.TissueButtonGroup);
            app.LeyeButton.Text = 'L eye';
            app.LeyeButton.Value = true;
            app.ReyeButton = uiradiobutton(app.TissueButtonGroup);
            app.ReyeButton.Text = 'R eye';
            app.BrainButton = uiradiobutton(app.TissueButtonGroup);
            app.BrainButton.Text = 'Brain';
            % Show the figure after all components are created
            app.ImageLoaderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageLoader

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageLoaderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageLoaderUIFigure)
        end
    end
end