classdef ImageLoader_contentOnly < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageLoaderUIFigure           matlab.ui.Figure
        LoadButton                    matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        Channel4contentDropDown       matlab.ui.control.DropDown
        Channel4contentDropDownLabel  matlab.ui.control.Label
        Channel3contentDropDown       matlab.ui.control.DropDown
        Channel3contentDropDownLabel  matlab.ui.control.Label
        Channel2contentDropDown       matlab.ui.control.DropDown
        Channel2contentDropDownLabel  matlab.ui.control.Label
        Channel1contentDropDown       matlab.ui.control.DropDown
        Channel1contentDropDownLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            channel_types = fetchn(sln_image.ChannelType,'channel_content');
            app.Channel1contentDropDown.Items = [' '; channel_types];
            app.Channel1contentDropDown.Value = ' ';
            app.Channel2contentDropDown.Items = [' '; channel_types];
            app.Channel2contentDropDown.Value = ' ';
            app.Channel3contentDropDown.Items = [' '; channel_types];
            app.Channel3contentDropDown.Value = ' ';
            app.Channel4contentDropDown.Items = [' '; channel_types];
            app.Channel4contentDropDown.Value = ' ';        
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            loaderPrefs = struct;
            assignin("base","loaderPrefs",loaderPrefs);
            app.ImageLoaderUIFigureCloseRequest();
        end

        % Close request function: ImageLoaderUIFigure
        function ImageLoaderUIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            loaderPrefs = struct;
            loaderPrefs.ch1 = app.Channel1contentDropDown.Value;
            loaderPrefs.ch2 = app.Channel2contentDropDown.Value;
            loaderPrefs.ch3 = app.Channel3contentDropDown.Value;
            loaderPrefs.ch4 = app.Channel4contentDropDown.Value;

            %checks
            err = false;
            if strcmp(loaderPrefs.ch1, ' ')
                err = true;
                errordlg('Channel 1 must have a type');
            end

            if err
                loaderPrefs = struct;
                assignin("base","loaderPrefs",loaderPrefs);
            else
                assignin("base","loaderPrefs",loaderPrefs);
                app.ImageLoaderUIFigureCloseRequest();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageLoaderUIFigure = uifigure('Visible', 'off');
            app.ImageLoaderUIFigure.Name = 'ImageLoader';
            app.ImageLoaderUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImageLoaderUIFigureCloseRequest, true);
            app.Channel1contentDropDownLabel = uilabel(app.ImageLoaderUIFigure);
            app.Channel1contentDropDownLabel.Text = 'Channel 1 content';
            app.Channel1contentDropDown = uidropdown(app.ImageLoaderUIFigure);
            app.Channel2contentDropDownLabel = uilabel(app.ImageLoaderUIFigure);
            app.Channel2contentDropDownLabel.Text = 'Channel 2 content';
            app.Channel2contentDropDown = uidropdown(app.ImageLoaderUIFigure);
            app.Channel3contentDropDownLabel = uilabel(app.ImageLoaderUIFigure);
            app.Channel3contentDropDownLabel.Text = 'Channel 3 content';
            app.Channel3contentDropDown = uidropdown(app.ImageLoaderUIFigure);
            app.Channel4contentDropDownLabel = uilabel(app.ImageLoaderUIFigure);
            app.Channel4contentDropDownLabel.Text = 'Channel 4 content';
            app.Channel4contentDropDown = uidropdown(app.ImageLoaderUIFigure);
            app.CancelButton = uibutton(app.ImageLoaderUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.LoadButton = uibutton(app.ImageLoaderUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.ImageLoaderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageLoader_contentOnly

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageLoaderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageLoaderUIFigure)
        end
    end
end